classdef ExportSettings < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        NanoLoczExportUIFigure       matlab.ui.Figure
        PixelResolutiondpiEditField  matlab.ui.control.NumericEditField
        PixelResolutiondpiEditFieldLabel  matlab.ui.control.Label
        BackgroundColorSwitch        matlab.ui.control.Switch
        BackgroundColorSwitchLabel   matlab.ui.control.Label
        ExportSettingsLabel          matlab.ui.control.Label
    end

    
    properties (Access = private)
     mainApp
     
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            movegui(app.NanoLoczExportUIFigure,'center');
            allfigs = findall(0,'Type', 'figure');
            % isolate the NanoLocz handle
             app2Handle = findall(allfigs, 'Name', 'NanoLocz');
             app.mainApp = app2Handle(1).RunningAppInstance;
            app.PixelResolutiondpiEditField.Value = app.mainApp.Settings.ExportSettings.resolution;
            app.BackgroundColorSwitch.Value = app.mainApp.Settings.ExportSettings.color;
          
        end

        % Value changed function: BackgroundColorSwitch
        function BackgroundColorSwitchValueChanged(app, event)
            app.mainApp.Settings.ExportSettings.color = app.BackgroundColorSwitch.Value;
            
        end

        % Value changed function: PixelResolutiondpiEditField
        function PixelResolutiondpiEditFieldValueChanged(app, event)
            app.mainApp.Settings.ExportSettings.resolution = app.PixelResolutiondpiEditField.Value;
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.NanoLoczExportUIFigure = uifigure('Visible', 'off');
            app.NanoLoczExportUIFigure.Name = 'NanoLocz Export';
            app.ExportSettingsLabel = uilabel(app.NanoLoczExportUIFigure);
            app.ExportSettingsLabel.Text = 'Export Settings';
            app.BackgroundColorSwitchLabel = uilabel(app.NanoLoczExportUIFigure);
            app.BackgroundColorSwitchLabel.Text = 'Background Color';
            app.BackgroundColorSwitch = uiswitch(app.NanoLoczExportUIFigure, 'slider');
            app.BackgroundColorSwitch.Items = {'black', 'white'};
            app.BackgroundColorSwitch.ValueChangedFcn = createCallbackFcn(app, @BackgroundColorSwitchValueChanged, true);
            app.BackgroundColorSwitch.Value = 'black';
            app.PixelResolutiondpiEditFieldLabel = uilabel(app.NanoLoczExportUIFigure);
            app.PixelResolutiondpiEditFieldLabel.Text = 'Pixel Resolution (dpi)';
            app.PixelResolutiondpiEditField = uieditfield(app.NanoLoczExportUIFigure, 'numeric');
            app.PixelResolutiondpiEditField.Limits = [0.1 Inf];
            app.PixelResolutiondpiEditField.ValueChangedFcn = createCallbackFcn(app, @PixelResolutiondpiEditFieldValueChanged, true);
            app.PixelResolutiondpiEditField.Value = 300;
            % Show the figure after all components are created
            app.NanoLoczExportUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = ExportSettings

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.NanoLoczExportUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.NanoLoczExportUIFigure)
        end
    end
end