classdef ImageTools < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        ImageToolsUIFigure          matlab.ui.Figure
        ApplyButton                 matlab.ui.control.Button
        pixelscale                  matlab.ui.control.NumericEditField
        ScalexLabel_5               matlab.ui.control.Label
        RotateImg                   matlab.ui.control.NumericEditField
        ScalexLabel_4               matlab.ui.control.Label
        pixelsy                     matlab.ui.control.NumericEditField
        ScalexLabel_3               matlab.ui.control.Label
        pixelsx                     matlab.ui.control.NumericEditField
        ScalexLabel_2               matlab.ui.control.Label
        PixelinterpolationDropDown  matlab.ui.control.DropDown
        PixelinterpolationDropDownLabel  matlab.ui.control.Label
        Scaleh                      matlab.ui.control.NumericEditField
        ScaleheightLabel            matlab.ui.control.Label
        Scaley                      matlab.ui.control.NumericEditField
        ScaleyLabel                 matlab.ui.control.Label
        Scalex                      matlab.ui.control.NumericEditField
        ScalexLabel                 matlab.ui.control.Label
        ImagesizetoolsLabel         matlab.ui.control.Label
    end

    
    properties (Access = private)
     mainApp
     sz
     settings
    end

    
    methods (Access = public)
        
        function updateImagesettings(app)

            app.settings.pixelscale = app.pixelscale.Value;
            app.settings.pixelsy =  app.pixelsy.Value;
            app.settings.pixelsx =  app.pixelsx.Value;
            app.settings.method =   app.PixelinterpolationDropDown.Value;
             app.settings.rotate =  app.RotateImg.Value;
             app.settings.Scaleh = app.Scaleh.Value;
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            movegui(app.ImageToolsUIFigure,'center');
            allfigs = findall(0,'Type', 'figure');
            % isolate the NanoLocz handle
            app2Handle = findall(allfigs, 'Name', 'NanoLocz');
            app.mainApp = app2Handle(1).RunningAppInstance;

            app.sz = size(app.mainApp.ImageTarget);
            
            app.pixelscale.Value = app.mainApp.ImageInfo.PixelPerNm(1);
            app.pixelsy.Value = app.sz(1);
            app.pixelsx.Value = app.sz(2);
            updateImagesettings(app)
        end

        % Value changed function: Scalex
        function ScalexValueChanged(app, event)
        app.pixelsx.Value = round(app.sz(2)*app.Scalex.Value);
        updateImagesettings(app)
        end

        % Value changed function: Scaley
        function ScaleyValueChanged(app, event)
            app.pixelsy.Value = round(app.sz(1).*app.Scaley.Value);
            updateImagesettings(app)
        end

        % Value changed function: pixelsx
        function pixelsxValueChanged(app, event)
           app.Scalex.Value = app.pixelsx.Value./app.sz(2);
            updateImagesettings(app)
        end

        % Value changed function: pixelsy
        function pixelsyValueChanged(app, event)
          app.Scaley.Value = app.pixelsy.Value./app.sz(1);
            updateImagesettings(app)
        end

        % Value changed function: PixelinterpolationDropDown
        function PixelinterpolationDropDownValueChanged(app, event)
        updateImagesettings(app)
            
        end

        % Value changed function: pixelscale
        function pixelscaleValueChanged(app, event)
            app.mainApp.ImageInfo.PixelPerNm =  app.pixelscale.Value;
        end

        % Value changed function: RotateImg
        function RotateImgValueChanged(app, event)

            updateImagesettings(app)
        end

        % Value changed function: Scaleh
        function ScalehValueChanged(app, event)
            updateImagesettings(app)
        end

        % Button pushed function: ApplyButton
        function ApplyButtonPushed(app, event)
            app.mainApp.ReshapeImage(app.settings);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.ImageToolsUIFigure = uifigure('Visible', 'off');
            app.ImageToolsUIFigure.Name = 'ImageTools';
            app.ImagesizetoolsLabel = uilabel(app.ImageToolsUIFigure);
            app.ImagesizetoolsLabel.Text = 'Image size tools';
            app.ScalexLabel = uilabel(app.ImageToolsUIFigure);
            app.ScalexLabel.Text = 'Scale x';
            app.Scalex = uieditfield(app.ImageToolsUIFigure, 'numeric');
            app.Scalex.Limits = [0 Inf];
            app.Scalex.ValueChangedFcn = createCallbackFcn(app, @ScalexValueChanged, true);
            app.Scalex.Value = 1;
            app.ScaleyLabel = uilabel(app.ImageToolsUIFigure);
            app.ScaleyLabel.Text = 'Scale y';
            app.Scaley = uieditfield(app.ImageToolsUIFigure, 'numeric');
            app.Scaley.Limits = [0 Inf];
            app.Scaley.ValueChangedFcn = createCallbackFcn(app, @ScaleyValueChanged, true);
            app.Scaley.Value = 1;
            app.ScaleheightLabel = uilabel(app.ImageToolsUIFigure);
            app.ScaleheightLabel.Text = 'Scale height';
            app.Scaleh = uieditfield(app.ImageToolsUIFigure, 'numeric');
            app.Scaleh.Limits = [0 Inf];
            app.Scaleh.ValueChangedFcn = createCallbackFcn(app, @ScalehValueChanged, true);
            app.Scaleh.Value = 1;
            app.PixelinterpolationDropDownLabel = uilabel(app.ImageToolsUIFigure);
            app.PixelinterpolationDropDownLabel.Text = 'Pixel interpolation';
            app.PixelinterpolationDropDown = uidropdown(app.ImageToolsUIFigure);
            app.PixelinterpolationDropDown.Items = {'nearest', 'bilinear', 'bicubic'};
            app.PixelinterpolationDropDown.ValueChangedFcn = createCallbackFcn(app, @PixelinterpolationDropDownValueChanged, true);
            app.PixelinterpolationDropDown.Value = 'nearest';
            app.ScalexLabel_2 = uilabel(app.ImageToolsUIFigure);
            app.ScalexLabel_2.Text = 'x pixels ';
            app.pixelsx = uieditfield(app.ImageToolsUIFigure, 'numeric');
            app.pixelsx.Limits = [0 Inf];
            app.pixelsx.ValueChangedFcn = createCallbackFcn(app, @pixelsxValueChanged, true);
            app.pixelsx.Value = 1;
            app.ScalexLabel_3 = uilabel(app.ImageToolsUIFigure);
            app.ScalexLabel_3.Text = 'y pixels ';
            app.pixelsy = uieditfield(app.ImageToolsUIFigure, 'numeric');
            app.pixelsy.Limits = [0 Inf];
            app.pixelsy.ValueChangedFcn = createCallbackFcn(app, @pixelsyValueChanged, true);
            app.pixelsy.Value = 1;
            app.ScalexLabel_4 = uilabel(app.ImageToolsUIFigure);
            app.ScalexLabel_4.Text = 'Rotate ';
            app.RotateImg = uieditfield(app.ImageToolsUIFigure, 'numeric');
            app.RotateImg.Limits = [-360 360];
            app.RotateImg.ValueChangedFcn = createCallbackFcn(app, @RotateImgValueChanged, true);
            app.ScalexLabel_5 = uilabel(app.ImageToolsUIFigure);
            app.ScalexLabel_5.Text = 'pixel scale (pixel/nm)';
            app.pixelscale = uieditfield(app.ImageToolsUIFigure, 'numeric');
            app.pixelscale.Limits = [0 Inf];
            app.pixelscale.ValueChangedFcn = createCallbackFcn(app, @pixelscaleValueChanged, true);
            app.pixelscale.Value = 1;
            app.ApplyButton = uibutton(app.ImageToolsUIFigure, 'push');
            app.ApplyButton.ButtonPushedFcn = createCallbackFcn(app, @ApplyButtonPushed, true);
            app.ApplyButton.Text = 'Apply';
            % Show the figure after all components are created
            app.ImageToolsUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = ImageTools

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.ImageToolsUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.ImageToolsUIFigure)
        end
    end
end