classdef Lighting < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                   matlab.ui.Figure
        FacelightingDropDown       matlab.ui.control.DropDown
        FacelightingDropDownLabel  matlab.ui.control.Label
        ElavationSlider            matlab.ui.control.Slider
        ElavationSliderLabel       matlab.ui.control.Label
        AngleSlider                matlab.ui.control.Slider
        AngleSliderLabel           matlab.ui.control.Label
    end

    
    properties (Access = public)
        mainApp 
        settings
    end
    
    methods (Access = public)
        
     function updateLight(app)
         az = app.AngleSlider.Value;
         el = app.ElavationSlider.Value;
      %   [x, y, z] = sph2cart(deg2rad(az), deg2rad(el), 1)
         app.mainApp.Settings.lightAng = [az, el];
         app.mainApp.Settings.lightmethod = app.FacelightingDropDown.Value;
     end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
             allfigs = findall(0,'Type', 'figure'); 
             % isolate the NanoLocz handle
             app2Handle = findall(allfigs, 'Name', 'NanoLocz');
             app.mainApp = app2Handle(1).RunningAppInstance;
             updateLight(app)    

        end

        % Value changing function: AngleSlider
        function AngleSliderValueChanging(app, event)

            app.mainApp.Settings.lightAng = [event.Value, app.ElavationSlider.Value];
            app.mainApp.updatePlot('normal')
        end

        % Value changing function: ElavationSlider
        function ElavationSliderValueChanging(app, event)

            app.mainApp.Settings.lightAng = [app.AngleSlider.Value, event.Value];
            app.mainApp.updatePlot('normal')

        end

        % Value changed function: FacelightingDropDown
        function FacelightingDropDownValueChanged(app, event)
             app.mainApp.Settings.lightmethod = app.FacelightingDropDown.Value;
            app.mainApp.updatePlot('normal')
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.AngleSliderLabel = uilabel(app.UIFigure);
            app.AngleSliderLabel.Text = 'Angle';
            app.AngleSlider = uislider(app.UIFigure);
            app.AngleSlider.Limits = [-180 180];
            app.AngleSlider.ValueChangingFcn = createCallbackFcn(app, @AngleSliderValueChanging, true);
            app.AngleSlider.Value = 45;
            app.ElavationSliderLabel = uilabel(app.UIFigure);
            app.ElavationSliderLabel.Text = 'Elavation';
            app.ElavationSlider = uislider(app.UIFigure);
            app.ElavationSlider.Limits = [-180 180];
            app.ElavationSlider.ValueChangingFcn = createCallbackFcn(app, @ElavationSliderValueChanging, true);
            app.ElavationSlider.Value = 45;
            app.FacelightingDropDownLabel = uilabel(app.UIFigure);
            app.FacelightingDropDownLabel.Text = 'Face lighting';
            app.FacelightingDropDown = uidropdown(app.UIFigure);
            app.FacelightingDropDown.Items = {'none', 'flat', 'gouraud'};
            app.FacelightingDropDown.ValueChangedFcn = createCallbackFcn(app, @FacelightingDropDownValueChanged, true);
            app.FacelightingDropDown.Value = 'none';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Lighting

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end