classdef MaskTools < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        NanoLoczMaskToolsUIFigure  matlab.ui.Figure
        MaskAppearancePanel        matlab.ui.container.Panel
        ColourDropDown             matlab.ui.control.DropDown
        ColourDropDownLabel        matlab.ui.control.Label
        OpacitySlider              matlab.ui.control.Slider
        OpacitySliderLabel         matlab.ui.control.Label
        AnalyzeMaskPanel           matlab.ui.container.Panel
        RMS                        matlab.ui.control.CheckBox
        Or                         matlab.ui.control.CheckBox
        Ci                         matlab.ui.control.CheckBox
        Minh                       matlab.ui.control.CheckBox
        Maxh                       matlab.ui.control.CheckBox
        Meanh                      matlab.ui.control.CheckBox
        Perim                      matlab.ui.control.CheckBox
        Cent                       matlab.ui.control.CheckBox
        Area                       matlab.ui.control.CheckBox
        AnalyzeMaskButton          matlab.ui.control.Button
        EditMaskPanel              matlab.ui.container.Panel
        ZeroallButton              matlab.ui.control.Button
        CloseEditField             matlab.ui.control.NumericEditField
        CloseEditFieldLabel        matlab.ui.control.Label
        OpenEditField              matlab.ui.control.NumericEditField
        OpenEditFieldLabel         matlab.ui.control.Label
        DilateEditField            matlab.ui.control.NumericEditField
        DilatedisksizeLabel        matlab.ui.control.Label
        ErodeEditField             matlab.ui.control.NumericEditField
        ErodeLabel                 matlab.ui.control.Label
        MinimunsizeEditField       matlab.ui.control.NumericEditField
        MinimunsizeEditFieldLabel  matlab.ui.control.Label
        FillholesCheckBox          matlab.ui.control.CheckBox
        UpdateMaskButton           matlab.ui.control.Button
    end

    
    properties (Access = private)
        mainApp 
        settings
    end
    
    methods (Access = private)

        function props = ListType(app)
            props = [];
            if app.Area.Value
                props = [props,{'Area'}];
            end

            if app.Cent.Value
                props = [props, {'Centroid'}];
            end

            if app.Or.Value
                props = [props, {'Orientation'}];
            end

            if app.Ci.Value
                props = [props, {'Circularity'}];
            end

            if app.Perim.Value
                props = [props, {'Perimeter'}];
            end

            if app.Minh.Value
                props = [props, {'MinIntensity'}];
            end
            if app.Maxh.Value
                props = [props, {'MaxIntensity'}];
            end
            if app.Meanh.Value
                props = [props, {'MeanIntensity'}];
            end
            if app.RMS.Value
                props = [props, {'PixelValues'}];
            end

        end
            
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            allfigs = findall(0,'Type', 'figure'); 
            % isolate the NanoLocz handle

            app2Handle = findall(allfigs, 'Name', 'NanoLocz');
            app.mainApp = app2Handle(1).RunningAppInstance;
            app.settings.mingrain = app.MinimunsizeEditField.Value;
            app.settings.Erode = app.ErodeEditField.Value;
            app.settings.Dilate = app.DilateEditField.Value;
            app.settings.Open = app.OpenEditField.Value;
            app.settings.Close = app.CloseEditField.Value;
            app.OpacitySlider.Value = app.mainApp.Settings.MaskAlpha*100;
            app.ColourDropDown.Value = app.mainApp.Settings.MaskColor;
            app.settings.Fill = app.FillholesCheckBox.Value;
            app.settings.Analyze = ListType(app);
        end

        % Button pushed function: UpdateMaskButton
        function UpdateMaskButtonPushed(app, event)
            app.mainApp.cleanMask(app.settings)
        end

        % Value changed function: MinimunsizeEditField
        function MinimunsizeEditFieldValueChanged(app, event)
         app.settings.mingrain = app.MinimunsizeEditField.Value;       
        end

        % Value changed function: ErodeEditField
        function ErodeEditFieldValueChanged(app, event)
            app.settings.Erode = app.ErodeEditField.Value;
        end

        % Value changed function: DilateEditField
        function DilateEditFieldValueChanged(app, event)
            app.settings.Dilate = app.DilateEditField.Value;
        end

        % Value changed function: OpenEditField
        function OpenEditFieldValueChanged(app, event)
             app.settings.Open  = app.OpenEditField.Value;
            
        end

        % Value changed function: CloseEditField
        function CloseEditFieldValueChanged(app, event)
            app.settings.Close = app.CloseEditField.Value;
            
        end

        % Value changed function: ColourDropDown
        function ColourDropDownValueChanged(app, event)
            app.mainApp.Settings.MaskColor = event.Value;
            app.mainApp.updatePlot('normal')
        end

        % Value changing function: OpacitySlider
        function OpacitySliderValueChanging(app, event)

            app.mainApp.Settings.MaskAlpha = event.Value/100;
            app.mainApp.updatePlot('normal')
        end

        % Value changed function: FillholesCheckBox
        function FillholesCheckBoxValueChanged(app, event)
            app.settings.Fill = app.FillholesCheckBox.Value;
            
        end

        % Value changed function: Area, Cent, Ci, Maxh, Meanh, Minh, Or, 
        % ...and 1 other component
        function AreaValueChanged(app, event)
   app.settings.Analyze = ListType(app);
            
        end

        % Button pushed function: AnalyzeMaskButton
        function AnalyzeMaskButtonPushed(app, event)
            app.mainApp.AnalyzeMask(app.settings);
        end

        % Button pushed function: ZeroallButton
        function ZeroallButtonPushed(app, event)
           app.MinimunsizeEditField.Value = 0;
           app.ErodeEditField.Value = 0;
           app.DilateEditField.Value = 0;
           app.OpenEditField.Value = 0;
           app.CloseEditField.Value = 0;

            app.settings.mingrain = app.MinimunsizeEditField.Value;
            app.settings.Erode = app.ErodeEditField.Value;
            app.settings.Dilate = app.DilateEditField.Value;
            app.settings.Open = app.OpenEditField.Value;
            app.settings.Close = app.CloseEditField.Value;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.NanoLoczMaskToolsUIFigure = uifigure('Visible', 'off');
            app.NanoLoczMaskToolsUIFigure.Name = 'NanoLocz MaskTools';
            app.EditMaskPanel = uipanel(app.NanoLoczMaskToolsUIFigure);
            app.EditMaskPanel.Title = 'Edit Mask';
            app.UpdateMaskButton = uibutton(app.EditMaskPanel, 'push');
            app.UpdateMaskButton.ButtonPushedFcn = createCallbackFcn(app, @UpdateMaskButtonPushed, true);
            app.UpdateMaskButton.Text = 'Update Mask';
            app.FillholesCheckBox = uicheckbox(app.EditMaskPanel);
            app.FillholesCheckBox.ValueChangedFcn = createCallbackFcn(app, @FillholesCheckBoxValueChanged, true);
            app.FillholesCheckBox.Text = 'Fill holes';
            app.MinimunsizeEditFieldLabel = uilabel(app.EditMaskPanel);
            app.MinimunsizeEditFieldLabel.Text = 'Minimun size';
            app.MinimunsizeEditField = uieditfield(app.EditMaskPanel, 'numeric');
            app.MinimunsizeEditField.Limits = [0 Inf];
            app.MinimunsizeEditField.ValueChangedFcn = createCallbackFcn(app, @MinimunsizeEditFieldValueChanged, true);
            app.ErodeLabel = uilabel(app.EditMaskPanel);
            app.ErodeLabel.Text = 'Erode ';
            app.ErodeEditField = uieditfield(app.EditMaskPanel, 'numeric');
            app.ErodeEditField.Limits = [0 Inf];
            app.ErodeEditField.ValueChangedFcn = createCallbackFcn(app, @ErodeEditFieldValueChanged, true);
            app.DilatedisksizeLabel = uilabel(app.EditMaskPanel);
            app.DilatedisksizeLabel.Text = 'Dilate ';
            app.DilateEditField = uieditfield(app.EditMaskPanel, 'numeric');
            app.DilateEditField.Limits = [0 Inf];
            app.DilateEditField.ValueChangedFcn = createCallbackFcn(app, @DilateEditFieldValueChanged, true);
            app.OpenEditFieldLabel = uilabel(app.EditMaskPanel);
            app.OpenEditFieldLabel.Text = 'Open';
            app.OpenEditField = uieditfield(app.EditMaskPanel, 'numeric');
            app.OpenEditField.Limits = [0 Inf];
            app.OpenEditField.ValueChangedFcn = createCallbackFcn(app, @OpenEditFieldValueChanged, true);
            app.CloseEditFieldLabel = uilabel(app.EditMaskPanel);
            app.CloseEditFieldLabel.Text = 'Close';
            app.CloseEditField = uieditfield(app.EditMaskPanel, 'numeric');
            app.CloseEditField.Limits = [0 Inf];
            app.CloseEditField.ValueChangedFcn = createCallbackFcn(app, @CloseEditFieldValueChanged, true);
            app.ZeroallButton = uibutton(app.EditMaskPanel, 'push');
            app.ZeroallButton.ButtonPushedFcn = createCallbackFcn(app, @ZeroallButtonPushed, true);
            app.ZeroallButton.Text = 'Zero all';
            app.AnalyzeMaskPanel = uipanel(app.NanoLoczMaskToolsUIFigure);
            app.AnalyzeMaskPanel.Title = 'Analyze Mask';
            app.AnalyzeMaskButton = uibutton(app.AnalyzeMaskPanel, 'push');
            app.AnalyzeMaskButton.ButtonPushedFcn = createCallbackFcn(app, @AnalyzeMaskButtonPushed, true);
            app.AnalyzeMaskButton.Text = 'Analyze Mask';
            app.Area = uicheckbox(app.AnalyzeMaskPanel);
            app.Area.ValueChangedFcn = createCallbackFcn(app, @AreaValueChanged, true);
            app.Area.Text = 'Area';
            app.Area.Value = true;
            app.Cent = uicheckbox(app.AnalyzeMaskPanel);
            app.Cent.ValueChangedFcn = createCallbackFcn(app, @AreaValueChanged, true);
            app.Cent.Text = 'Center';
            app.Cent.Value = true;
            app.Perim = uicheckbox(app.AnalyzeMaskPanel);
            app.Perim.ValueChangedFcn = createCallbackFcn(app, @AreaValueChanged, true);
            app.Perim.Text = 'Perimeter';
            app.Perim.Value = true;
            app.Meanh = uicheckbox(app.AnalyzeMaskPanel);
            app.Meanh.ValueChangedFcn = createCallbackFcn(app, @AreaValueChanged, true);
            app.Meanh.Text = 'Mean height';
            app.Meanh.Value = true;
            app.Maxh = uicheckbox(app.AnalyzeMaskPanel);
            app.Maxh.ValueChangedFcn = createCallbackFcn(app, @AreaValueChanged, true);
            app.Maxh.Text = 'Max height';
            app.Maxh.Value = true;
            app.Minh = uicheckbox(app.AnalyzeMaskPanel);
            app.Minh.ValueChangedFcn = createCallbackFcn(app, @AreaValueChanged, true);
            app.Minh.Text = 'Min height';
            app.Minh.Value = true;
            app.Ci = uicheckbox(app.AnalyzeMaskPanel);
            app.Ci.ValueChangedFcn = createCallbackFcn(app, @AreaValueChanged, true);
            app.Ci.Text = 'Circularity';
            app.Ci.Value = true;
            app.Or = uicheckbox(app.AnalyzeMaskPanel);
            app.Or.ValueChangedFcn = createCallbackFcn(app, @AreaValueChanged, true);
            app.Or.Text = 'Orientation';
            app.Or.Value = true;
            app.RMS = uicheckbox(app.AnalyzeMaskPanel);
            app.RMS.Text = 'RMS roughness';
            app.RMS.Value = true;
            app.MaskAppearancePanel = uipanel(app.NanoLoczMaskToolsUIFigure);
            app.MaskAppearancePanel.Title = 'Mask Appearance';
            app.OpacitySliderLabel = uilabel(app.MaskAppearancePanel);
            app.OpacitySliderLabel.Text = 'Opacity';
            app.OpacitySlider = uislider(app.MaskAppearancePanel);
            app.OpacitySlider.MajorTicks = [0 25 50 75 100];
            app.OpacitySlider.MajorTickLabels = {'0', '25', '50', '75', '100'};
            app.OpacitySlider.ValueChangingFcn = createCallbackFcn(app, @OpacitySliderValueChanging, true);
            app.OpacitySlider.MinorTicks = [0 5 10 15 20 25 30 35 40 45 50 55 60 65 70 75 80 85 90 95 100];
            app.ColourDropDownLabel = uilabel(app.MaskAppearancePanel);
            app.ColourDropDownLabel.Text = 'Colour';
            app.ColourDropDown = uidropdown(app.MaskAppearancePanel);
            app.ColourDropDown.Items = {'green', 'red', 'blue', 'black', 'white'};
            app.ColourDropDown.ValueChangedFcn = createCallbackFcn(app, @ColourDropDownValueChanged, true);
            app.ColourDropDown.Value = 'green';
            % Show the figure after all components are created
            app.NanoLoczMaskToolsUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = MaskTools

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.NanoLoczMaskToolsUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.NanoLoczMaskToolsUIFigure)
        end
    end
end