classdef VideoAlign < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        NanoLoczVideoAlignUIFigure  matlab.ui.Figure
        SmoothDrift                 matlab.ui.control.NumericEditField
        SmoothdriftLabel            matlab.ui.control.Label
        CroppingSwitch              matlab.ui.control.Switch
        CroppingSwitchLabel         matlab.ui.control.Label
        Maxdrift                    matlab.ui.control.NumericEditField
        MaxdriftEditFieldLabel      matlab.ui.control.Label
        FilterImg                   matlab.ui.control.NumericEditField
        FilterImgEditFieldLabel     matlab.ui.control.Label
        FilterCCR                   matlab.ui.control.NumericEditField
        FilterCCREditFieldLabel     matlab.ui.control.Label
        VideoAlignSettingsLabel     matlab.ui.control.Label
        SubPixelLabel               matlab.ui.control.Label
        SubPixCheckBox              matlab.ui.control.CheckBox
    end

    
    properties (Access = private)
     mainApp
     
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            movegui(app.NanoLoczVideoAlignUIFigure,'center');
            allfigs = findall(0,'Type', 'figure');
            % isolate the NanoLocz handle
             app2Handle = findall(allfigs, 'Name', 'NanoLocz');
             app.mainApp = app2Handle(1).RunningAppInstance;
            app.FilterCCR.Value = app.mainApp.Settings.Align.FilterCCR;
            app.FilterImg.Value = app.mainApp.Settings.Align.FilterImg;
            app.Maxdrift.Value =  app.mainApp.Settings.Align.Maxdrift;
            app.CroppingSwitch.Value = app.mainApp.Settings.Align.CroppingSwitch;
            app.SubPixCheckBox.Value = app.mainApp.Settings.Align.SubPix;
            app.SmoothDrift.Value = app.mainApp.Settings.Align.SmoothDrift;
        end

        % Value changed function: FilterCCR
        function FilterCCRValueChanged(app, event)
        app.mainApp.Settings.Align.FilterCCR = app.FilterCCR.Value;
        end

        % Value changed function: FilterImg
        function FilterImgValueChanged(app, event)
         app.mainApp.Settings.Align.FilterImg = app.FilterImg.Value;
            
        end

        % Value changed function: Maxdrift
        function MaxdriftValueChanged(app, event)
               app.mainApp.Settings.Align.Maxdrift =  app.Maxdrift.Value;
            
        end

        % Value changed function: SubPixCheckBox
        function SubPixCheckBoxValueChanged(app, event)
            app.mainApp.Settings.Align.SubPix = app.SubPixCheckBox.Value;
            
        end

        % Value changed function: CroppingSwitch
        function CroppingSwitchValueChanged(app, event)
             app.mainApp.Settings.Align.CroppingSwitch = app.CroppingSwitch.Value;
            
        end

        % Value changed function: SmoothDrift
        function SmoothDriftValueChanged(app, event)
            app.mainApp.Settings.Align.SmoothDrift = app.SmoothDrift.Value;
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.NanoLoczVideoAlignUIFigure = uifigure('Visible', 'off');
            app.NanoLoczVideoAlignUIFigure.Name = 'NanoLocz VideoAlign';
            app.SubPixCheckBox = uicheckbox(app.NanoLoczVideoAlignUIFigure);
            app.SubPixCheckBox.ValueChangedFcn = createCallbackFcn(app, @SubPixCheckBoxValueChanged, true);
            app.SubPixCheckBox.Text = '';
            app.SubPixelLabel = uilabel(app.NanoLoczVideoAlignUIFigure);
            app.SubPixelLabel.Text = 'Sub-Pixel?';
            app.VideoAlignSettingsLabel = uilabel(app.NanoLoczVideoAlignUIFigure);
            app.VideoAlignSettingsLabel.Text = 'Video Align Settings';
            app.FilterCCREditFieldLabel = uilabel(app.NanoLoczVideoAlignUIFigure);
            app.FilterCCREditFieldLabel.Text = 'Filter CCR';
            app.FilterCCR = uieditfield(app.NanoLoczVideoAlignUIFigure, 'numeric');
            app.FilterCCR.Limits = [0 Inf];
            app.FilterCCR.ValueChangedFcn = createCallbackFcn(app, @FilterCCRValueChanged, true);
            app.FilterCCR.Value = 1;
            app.FilterImgEditFieldLabel = uilabel(app.NanoLoczVideoAlignUIFigure);
            app.FilterImgEditFieldLabel.Text = 'Filter Img';
            app.FilterImg = uieditfield(app.NanoLoczVideoAlignUIFigure, 'numeric');
            app.FilterImg.Limits = [0 Inf];
            app.FilterImg.ValueChangedFcn = createCallbackFcn(app, @FilterImgValueChanged, true);
            app.FilterImg.Value = 1;
            app.MaxdriftEditFieldLabel = uilabel(app.NanoLoczVideoAlignUIFigure);
            app.MaxdriftEditFieldLabel.Text = 'Max drift';
            app.Maxdrift = uieditfield(app.NanoLoczVideoAlignUIFigure, 'numeric');
            app.Maxdrift.Limits = [0 Inf];
            app.Maxdrift.ValueChangedFcn = createCallbackFcn(app, @MaxdriftValueChanged, true);
            app.CroppingSwitchLabel = uilabel(app.NanoLoczVideoAlignUIFigure);
            app.CroppingSwitchLabel.Text = 'Cropping';
            app.CroppingSwitch = uiswitch(app.NanoLoczVideoAlignUIFigure, 'slider');
            app.CroppingSwitch.Items = {'Crop', 'Expand'};
            app.CroppingSwitch.ValueChangedFcn = createCallbackFcn(app, @CroppingSwitchValueChanged, true);
            app.CroppingSwitch.Value = 'Expand';
            app.SmoothdriftLabel = uilabel(app.NanoLoczVideoAlignUIFigure);
            app.SmoothdriftLabel.Text = 'Smooth drift';
            app.SmoothDrift = uieditfield(app.NanoLoczVideoAlignUIFigure, 'numeric');
            app.SmoothDrift.Limits = [0 Inf];
            app.SmoothDrift.RoundFractionalValues = 'on';
            app.SmoothDrift.ValueChangedFcn = createCallbackFcn(app, @SmoothDriftValueChanged, true);
            app.SmoothDrift.Value = 4;
            % Show the figure after all components are created
            app.NanoLoczVideoAlignUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = VideoAlign

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.NanoLoczVideoAlignUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.NanoLoczVideoAlignUIFigure)
        end
    end
end