classdef plotter < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        NanoLoczPlotterUIFigure   matlab.ui.Figure
        CombinePlotsCheckBox      matlab.ui.control.CheckBox
        RefreshdataButton         matlab.ui.control.Button
        BinSpinner                matlab.ui.control.Spinner
        BinSpinnerLabel           matlab.ui.control.Label
        MaxSpinner                matlab.ui.control.Spinner
        MaxSpinnerLabel           matlab.ui.control.Label
        MinSpinner                matlab.ui.control.Spinner
        MinSpinnerLabel           matlab.ui.control.Label
        ExportdataButton          matlab.ui.control.Button
        ExportFileFormat          matlab.ui.control.DropDown
        ColorbytrackCheckBox      matlab.ui.control.CheckBox
        TrackDetectionsPanel      matlab.ui.container.Panel
        MinlengthEditField        matlab.ui.control.NumericEditField
        MinlengthEditFieldLabel   matlab.ui.control.Label
        MaxstepEditField          matlab.ui.control.NumericEditField
        MaxstepEditFieldLabel     matlab.ui.control.Label
        MaxmissingEditField       matlab.ui.control.NumericEditField
        MaxmissingEditFieldLabel  matlab.ui.control.Label
        RunTrackingButton         matlab.ui.control.Button
        RangeEditField_2          matlab.ui.control.EditField
        RangeEditField_2Label     matlab.ui.control.Label
        RangeEditField            matlab.ui.control.EditField
        RangeEditFieldLabel       matlab.ui.control.Label
        TracksLabel               matlab.ui.control.Label
        FramesLabel               matlab.ui.control.Label
        DataLabel                 matlab.ui.control.Label
        PlotTypeLabel             matlab.ui.control.Label
        YLabel                    matlab.ui.control.Label
        XLabel                    matlab.ui.control.Label
        LineCheckBox              matlab.ui.control.CheckBox
        MarkersCheckBox           matlab.ui.control.CheckBox
        TrackList                 matlab.ui.control.ListBox
        FrameList                 matlab.ui.control.ListBox
        YList                     matlab.ui.control.ListBox
        XList                     matlab.ui.control.ListBox
        PlotterTable              matlab.ui.control.Table
        DataTypeListBox           matlab.ui.control.ListBox
        PlotTypeListBox           matlab.ui.control.ListBox
        PlotterAxes               matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
       mainApp
       Data
       Style
       pos
       Frames
       Tracks
       HoldOn
    end

    methods (Access = public)

        function updateType(app)
            clear app.DataTypeListBox.Items
            switch app.PlotTypeListBox.Value
                case 'Plot'
                    app.ExportdataButton.Visible = 'off';
                    app.ExportFileFormat.Visible = 'off';
                    app.PlotterTable.Visible = "off";
                    app.PlotterAxes.Visible = "on";
                    app.XList.Visible = "on";
                    app.YList.Visible = "on";
                    app.XLabel.Visible = "on";
                    app.YLabel.Visible = "on";
                    app.LineCheckBox.Visible = 'on';
                    app.MarkersCheckBox.Visible = 'on';

                    app.PlotterAxes.Color = [1 1 1];           % white background
                    app.PlotterAxes.XColor = [0 0 0];         % black x-axis font
                    app.PlotterAxes.YColor = [0 0 0];         % black y-axis font
                    app.PlotterAxes.ZColor = [0 0 0];         % black z-axis font (if 3D)
                case 'Histogram'
                    app.ExportdataButton.Visible = 'off';
                    app.ExportFileFormat.Visible = 'off';
                    app.PlotterTable.Visible = "off";
                    app.PlotterAxes.Visible = "on";
                    app.XList.Visible = "on";
                    app.XLabel.Visible = "off";
                    app.YLabel.Visible = "off";
                    app.YList.Visible = "off";
                    app.LineCheckBox.Visible = 'off';
                    app.MarkersCheckBox.Visible = 'off';
                    app.BinSpinner.Visible = 'on';
                    app.BinSpinner.Visible = 'on';
                    app.PlotterAxes.Color = [1 1 1];           % white background
                    app.PlotterAxes.XColor = [0 0 0];         % black x-axis font
                    app.PlotterAxes.YColor = [0 0 0];         % black y-axis font
                    app.PlotterAxes.ZColor = [0 0 0];         % black z-axis font (if 3D)

                case 'Table'
                    app.ExportdataButton.Visible = 'on';
                     app.ExportFileFormat.Visible = 'on';
                    app.PlotterTable.Visible = "on";
                    app.PlotterAxes.Visible = "off";
                    app.XList.Visible = "off";
                    app.YList.Visible = "off";
                    app.XLabel.Visible = "off";
                    app.YLabel.Visible = "off";
                    app.LineCheckBox.Visible = 'off';
                    app.MarkersCheckBox.Visible = 'off';
                    app.BinSpinner.Visible = 'off';
            end

        end

        function CheckAppData(app)
            clear app.DataTypeListBox.Items
             
            if isfield(app.mainApp.Mask, 'Analysis')
                app.Data.Areas = 1;
                app.DataTypeListBox.Items = [app.DataTypeListBox.Items, {'Mask Particle Analysis','Mask Frame Analysis'}];
            else
                app.Data.Areas = 0;
            end

            if isfield(app.mainApp.Part, 'Locs')
                app.Data.PartLocs = 1;
                app.DataTypeListBox.Items = [app.DataTypeListBox.Items, {'Particle Analysis'}];
            else
                app.Data.PartLocs = 0;
            end

            if isfield(app.mainApp.LAFM, 'Locs')
                app.Data.LAFMLocs = 1;
                app.DataTypeListBox.Items = [app.DataTypeListBox.Items, {'Localizations'}];
            else
                app.Data.LAFMLocs = 0;
            end

            if isfield(app.mainApp.ROI, 'AreaAverage')
                app.Data.AreaAverage = 1;
                app.DataTypeListBox.Items = [app.DataTypeListBox.Items, {'Area Average'}];
            else
                app.Data.AreaAverage = 0;
            end

            if isfield(app.mainApp.ROI, 'Line') && isstruct(app.mainApp.ROI.Line)
                if isfield(app.mainApp.ROI.Line, 'Profile')
                    numLines = numel(app.mainApp.ROI.Line); % Count the number of Line entries
                    if numLines > 0
                        app.Data.LineProfile = 1; % Flag to indicate Line Profiles exist
                        lineProfileItems = arrayfun(@(i) sprintf('Line Profile %d', i), 1:numLines, 'UniformOutput', false);
                        app.DataTypeListBox.Items = unique([app.DataTypeListBox.Items, lineProfileItems]);
                    else
                        app.Data.LineProfile = 0;
                    end
                else
                    app.Data.LineProfile = 0;
                end
            else
                app.Data.LineProfile = 0;
            end

            if numel(app.mainApp.ImageTarget(1,1,:))>1
                app.Data.time = 1;
            else
                app.Data.time = 0;
            end

        end


        function updateTable(app)
            switch app.DataTypeListBox.Value
                case 'Mask Particle Analysis' 
                    app.PlotterTable.Data = (app.mainApp.Mask.Analysis);
                    app.PlotterTable.ColumnName =app.mainApp.Mask.Analysis.Properties.VariableNames;
                     app.Frames ='all';
                    app.pos = [];

                case  'Mask Frame Analysis'
                    app.PlotterTable.Data = (app.mainApp.Mask.Frame_Analysis);
                    app.PlotterTable.ColumnName =app.mainApp.Mask.Frame_Analysis.Properties.VariableNames;
                    app.pos = [];

                case 'Particle Analysis'
                    if size(app.mainApp.Part.Locs,2)>9
                    T = array2table(app.mainApp.Part.Locs(app.mainApp.Part.IncludeImages,:),...
                        'VariableNames',{'x','y','Height','ccr','Frame','id','Track id','Angle','FWHM min','FWHM max','FWHM mean'});
                    else
                        T = array2table(app.mainApp.Part.Locs(app.mainApp.Part.IncludeImages,:),...
                            'VariableNames',{'x','y','Height','ccr','Frame','id','Track id','Angle'});
                    end

                    app.PlotterTable.Data = T;
                    app.PlotterTable.ColumnName = T.Properties.VariableNames;
                    app.Frames ='all';
                    app.pos = [];

                case 'Localizations'
                    baseVarNames = {'x','y','z','Peak Prominence','Particle','Frame','Time','ccr'};
                    extraVarNames = {'PtCloudRMSE','Error','ID'};
                    nCols = size(app.mainApp.LAFM.Locs, 2);
                    allVarNames = [baseVarNames, extraVarNames];
                    varNames = allVarNames(1:nCols);  % Trim to match data

                    % Create table
                    T = array2table(app.mainApp.LAFM.Locs, 'VariableNames', varNames);
                    app.PlotterTable.Data = T;
                    app.PlotterTable.ColumnName = T.Properties.VariableNames;
                     app.Frames ='all';
                     app.pos = [];
                case 'Area Average'
                    [numRows, numColumns] = size(app.mainApp.ROI.AreaAverage);
                    frameNumbers = (1:numRows)';
                    dataWithFrame = [frameNumbers, app.mainApp.ROI.AreaAverage];
                    variableNames = [{'Frame'}, arrayfun(@(x) sprintf('Area %d', x), 1:numColumns, 'UniformOutput', false)];
                    T = array2table(dataWithFrame, 'VariableNames', variableNames);
                    app.PlotterTable.Data = T;
                    app.PlotterTable.ColumnName = T.Properties.VariableNames;
                    app.Frames ='all';
                    app.pos = [];

                otherwise
                    if startsWith(app.DataTypeListBox.Value, 'Line Profile')
                        profileIndex = sscanf(app.DataTypeListBox.Value, 'Line Profile %d');
                        if ~isempty(profileIndex) && profileIndex <= numel(app.mainApp.ROI.Line)
                            profileData = app.mainApp.ROI.Line(profileIndex).Profile;
                            T = array2table(profileData, ...
                                'VariableNames', {'distance (nm)', 'z (nm)'});
                            app.PlotterTable.Data = T;
                            app.PlotterTable.ColumnName = T.Properties.VariableNames;
                        else
                            app.PlotterTable.Data = table(); % Empty table
                            app.PlotterTable.ColumnName = {};
                        end
                        app.Frames ='na';
                        app.pos = [];
                    else
                        app.PlotterTable.Data = table(); % Empty table
                        app.PlotterTable.ColumnName = {};
                    end
            end
        end


        function updateXList(app)
            clear app.XList.Items
            app.XList.Items = app.PlotterTable.ColumnName;
        end

        function updateYList(app)
            clear app.YList.Items
            app.YList.Items = app.PlotterTable.ColumnName;
        end


        function updateGraph(app)
            try
                if app.CombinePlotsCheckBox.Value ==1
                    hold(app.PlotterAxes,'on')
                else
                    hold(app.PlotterAxes,'off')
                    cla(app.PlotterAxes);
                end
                if app.Data.time == 0
                    app.Frames ='all';
          end
                switch app.PlotTypeListBox.Value
                    case 'Plot'
                        Xname = app.XList.Value;
                        Yname = app.YList.Value;
                        X = double(app.PlotterTable.Data.(Xname));
                        Y = double(app.PlotterTable.Data.(Yname));
                        app.Style = '-';

                        switch app.Frames
                            case 'all'
                                try
                                app.pos = logical(app.PlotterTable.Data.('Frame'));
                                catch
                                    app.pos = logical(X);
                                end
                            case  'frame mean'
                                for i = 1:max(app.PlotterTable.Data.('Frame'))
                                    app.pos = app.PlotterTable.Data.('Frame') ==i;
                                    X2(i) = mean(X(app.pos),'omitnan');
                                    Y2(i) = mean(Y(app.pos),'omitnan');
                                end
                                X=X2;
                                Y=Y2;
                                X(isnan(X)) = [];
                                Y(isnan(Y)) = [];
                                app.pos = logical(X);
                            otherwise
                                try
                                    app.pos = app.PlotterTable.Data.('Frame') ==app.Frames ;
                                catch
                                    app.pos = logical(X);
                                end
                        end

                        if app.MarkersCheckBox.Value==1
                            app.Style = 'o';
                        end

                        if app.MarkersCheckBox.Value==1 && app.LineCheckBox.Value==1
                            app.Style = 'o-';
                        end

                        if app.ColorbytrackCheckBox.Value
                            hold(app.PlotterAxes,'on')
                            n = max(app.PlotterTable.Data.('Track id'));
                            for i = 1:n
                                app.pos = app.PlotterTable.Data.('Track id') ==i;
                                plot(app.PlotterAxes,X(app.pos),Y(app.pos), app.Style)
                            end
                              hold(app.PlotterAxes,'off')
                        else
                        plot(app.PlotterAxes,X(app.pos),Y(app.pos), app.Style)
                        end

                        xlabel(app.PlotterAxes,Xname)
                        ylabel(app.PlotterAxes,Yname)
                        axis(app.PlotterAxes, 'tight')
                        xlim(app.PlotterAxes,[app.MinSpinner.Value, app.MaxSpinner.Value])
                    case 'Histogram'
                        if app.BinSpinner.Value ==0
                            app.BinSpinner.Value=0.1;
                        end
                        Xname = app.XList.Value;
                        X = double(app.PlotterTable.Data.(Xname));
                        switch app.Frames
                            case 'all'
                                try
                                    app.pos = logical(app.PlotterTable.Data.('Frame'));
                                catch
                                   app.pos = logical(X);
                                end
                            case  'frame mean'
                                for i = 1:max(app.PlotterTable.Data.('Frame'))
                                    app.pos = app.PlotterTable.Data.('Frame') ==i;
                                    X2(i) = mean(X(app.pos));
                                end
                                X=X2;
                                app.pos = logical(X);
                            otherwise
                                try
                                app.pos = app.PlotterTable.Data.('Frame') ==app.Frames;
                                catch
                                    app.pos = logical(X);
                                end

                        end
                  try
                        histogram(app.PlotterAxes,double(X(app.pos)),app.MinSpinner.Value:app.BinSpinner.Value:app.MaxSpinner.Value)
                  catch
                       histogram(app.PlotterAxes,double(X(app.pos)))
                  end
                        xlabel(app.PlotterAxes,Xname)
                        ylabel(app.PlotterAxes,'Count')
                          axis(app.PlotterAxes, 'tight')
                         xlim(app.PlotterAxes,[app.MinSpinner.Value, app.MaxSpinner.Value])
  
                end
           
           catch
           end
            
        end

        function updateFrameList(app)
            try
            n = max(app.PlotterTable.Data.('Frame'));
            values = cellstr(num2str((1:n)', '%d'));
            app.FrameList.Items = [{'all'}; {'frame mean'}; values];
            catch 
            end
        end
                

    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
             allfigs = findall(0,'Type', 'figure'); 
            app2Handle = findall(allfigs, 'Name', 'NanoLocz');
            app.mainApp = app2Handle(1).RunningAppInstance;
            % Set KeyPress and KeyRelease callbacks

            CheckAppData(app)
            updateType(app)
            app.Frames = 'all';
            app.Tracks = 'all';
        end

        % Value changed function: PlotTypeListBox
        function PlotTypeListBoxValueChanged(app, event)
           updateType(app)
        end

        % Value changed function: DataTypeListBox
        function DataTypeListBoxValueChanged(app, event)
            updateTable(app)
            updateXList(app)
            updateYList(app)

            if  app.Data.time == 1 && ~strcmp(app.PlotTypeListBox.Value,'Table')
                updateFrameList(app)
                app.FrameList.Value ='all';
                app.FrameList.Visible = "on";
                app.FramesLabel.Visible = "on";
            else
                app.FrameList.Visible = "off";
                app.FramesLabel.Visible = "off";
            end

            if startsWith(app.DataTypeListBox.Value, 'Line Profile') && ~strcmp(app.PlotTypeListBox.Value,'Table')
                app.XList.Value = 'distance (nm)';
                app.YList.Value = 'z (nm)';

                Xname = app.XList.Value;
                X = app.PlotterTable.Data.(Xname);
                app.MaxSpinner.Value = double(max(X)) + 0.1*double(max(X));
                app.MinSpinner.Value = double(min(X))- 0.1*double(min(X));
                app.BinSpinner.Value = (app.MaxSpinner.Value-app.MinSpinner.Value)/20;

                app.MinSpinner.Step = app.BinSpinner.Value;
                app.MaxSpinner.Step = app.BinSpinner.Value;
                app.BinSpinner.Step = 0.2*app.BinSpinner.Value;

                updateGraph(app)
            end

        end

        % Value changed function: LineCheckBox, MarkersCheckBox, XList
        function XListValueChanged(app, event)
            Xname = app.XList.Value;
            X = app.PlotterTable.Data.(Xname);
            app.MaxSpinner.Value = double(max(X)) + 0.1*double(max(X));
            app.MinSpinner.Value = double(min(X))- 0.1*double(min(X));
            app.BinSpinner.Value = (app.MaxSpinner.Value-app.MinSpinner.Value)/20;
             
            app.MinSpinner.Step = app.BinSpinner.Value;
            app.MaxSpinner.Step = app.BinSpinner.Value;
            app.BinSpinner.Step = 0.2*app.BinSpinner.Value;
           
            updateGraph(app)
        end

        % Value changed function: YList
        function YListValueChanged(app, event)
          updateGraph(app)            
        end

        % Value changed function: FrameList
        function FrameListValueChanged(app, event)

            app.Frames = app.FrameList.Value;
            if app.Data.time == 1
                switch app.Frames
                    case {'all', 'frame mean'}
                        app.pos = logical(app.PlotterTable.Data.('Frame'));
                    otherwise
                        app.Frames = str2double(app.Frames);
                        app.pos = app.Frames == app.PlotterTable.Data.('Frame');
                end
            end
            updateGraph(app) 
            
        end

        % Button pushed function: RunTrackingButton
        function RunTrackingButtonPushed(app, event)
            switch app.DataTypeListBox.Value
                case 'Mask Particle Analysis'
                    xy = [app.PlotterTable.Data.x, app.PlotterTable.Data.y];
                    frames = app.PlotterTable.Data.Frame;
                case  'Mask Frame Analysis'
                    msgbox('No particles to track')
                    xy = [];
                case 'Particle Analysis'
                    xy = app.mainApp.Part.Locs(app.mainApp.Part.IncludeImages,1:2);
                    frames = app.mainApp.Part.Locs(app.mainApp.Part.IncludeImages,5);
                case 'Localizations'
                    xy = [app.PlotterTable.Data.X, app.PlotterTable.Data.Y];
                    frames = app.PlotterTable.Data.Frame;
            end

            if any(xy)
                tracking = track_particles(xy, frames, app.MaxstepEditField.Value, app.MaxmissingEditField.Value);
                switch app.DataTypeListBox.Value
                    case 'Particle Analysis'
                        counter=0;
                        for i = 1:numel(xy(:,1))
                            if ~isnan(app.mainApp.Part.Locs(i,6))
                                counter=counter+1;
                                app.mainApp.Part.Locs(i,7)= tracking(counter);
                            else
                                app.mainApp.Part.Locs(i,7)  = NaN;
                            end
                        end
                    case  'Mask Particle Analysis'
                        app.mainApp.Mask.Analysis.('Track id') = tracking';  
                    case 'Localizations'
                end

                for i=1:max(tracking)
                    chec = tracking==i;
                    if sum(chec)<app.MinlengthEditField.Value
                        tracking(chec) = NaN;
                    end
                end
                app.PlotterTable.Data.('Track id') = tracking';

            end


        end

        % Value changed function: BinSpinner, MaxSpinner, MinSpinner
        function MinEditFieldValueChanged(app, event)
      updateGraph(app) 
      xlim(app.PlotterAxes,[app.MinSpinner.Value, app.MaxSpinner.Value])
        end

        % Value changed function: ColorbytrackCheckBox
        function ColorbytrackCheckBoxValueChanged(app, event)
          cla(app.PlotterAxes);
            updateGraph(app) 
            
        end

        % Button pushed function: ExportdataButton
        function ExportdataButtonPushed(app, event)
            namesplit = split(app.mainApp.ImageName,'.');
            namesuggest = join(namesplit,"_");
            [outputFileName,folder] = uiputfile([namesuggest{1},app.DataTypeListBox.Value,app.ExportFileFormat.Value;]);
            fullFileName = fullfile(folder, outputFileName);
            Out{1} = app.PlotterTable.Data;
            writetable(Out{1},fullFileName);
                     
        end

        % Button pushed function: RefreshdataButton
        function RefreshdataButtonPushed(app, event)
            updateTable(app)
              updateGraph(app)   
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.NanoLoczPlotterUIFigure = uifigure('Visible', 'off');
            app.NanoLoczPlotterUIFigure.Name = 'NanoLocz Plotter';
            app.PlotterAxes = uiaxes(app.NanoLoczPlotterUIFigure);
            xlabel(app.PlotterAxes, 'X')
            ylabel(app.PlotterAxes, 'Y')
            zlabel(app.PlotterAxes, 'Z')
            app.PlotTypeListBox = uilistbox(app.NanoLoczPlotterUIFigure);
            app.PlotTypeListBox.Items = {'Table', 'Histogram', 'Plot'};
            app.PlotTypeListBox.ValueChangedFcn = createCallbackFcn(app, @PlotTypeListBoxValueChanged, true);
            app.PlotTypeListBox.Value = 'Table';
            app.DataTypeListBox = uilistbox(app.NanoLoczPlotterUIFigure);
            app.DataTypeListBox.Items = {};
            app.DataTypeListBox.ValueChangedFcn = createCallbackFcn(app, @DataTypeListBoxValueChanged, true);
            app.DataTypeListBox.Value = {};
            app.PlotterTable = uitable(app.NanoLoczPlotterUIFigure);
            app.PlotterTable.ColumnName = {'Column 1'; 'Column 2'; 'Column 3'; 'Column 4'};
            app.PlotterTable.RowName = {};
            app.XList = uilistbox(app.NanoLoczPlotterUIFigure);
            app.XList.Items = {};
            app.XList.ValueChangedFcn = createCallbackFcn(app, @XListValueChanged, true);
            app.XList.Visible = 'off';
            app.XList.Value = {};
            app.YList = uilistbox(app.NanoLoczPlotterUIFigure);
            app.YList.Items = {};
            app.YList.ValueChangedFcn = createCallbackFcn(app, @YListValueChanged, true);
            app.YList.Visible = 'off';
            app.YList.Value = {};
            app.FrameList = uilistbox(app.NanoLoczPlotterUIFigure);
            app.FrameList.Items = {};
            app.FrameList.ValueChangedFcn = createCallbackFcn(app, @FrameListValueChanged, true);
            app.FrameList.Visible = 'off';
            app.FrameList.Value = {};
            app.TrackList = uilistbox(app.NanoLoczPlotterUIFigure);
            app.TrackList.Items = {};
            app.TrackList.Visible = 'off';
            app.TrackList.Value = {};
            app.MarkersCheckBox = uicheckbox(app.NanoLoczPlotterUIFigure);
            app.MarkersCheckBox.ValueChangedFcn = createCallbackFcn(app, @XListValueChanged, true);
            app.MarkersCheckBox.Text = 'Markers';
            app.MarkersCheckBox.Value = true;
            app.LineCheckBox = uicheckbox(app.NanoLoczPlotterUIFigure);
            app.LineCheckBox.ValueChangedFcn = createCallbackFcn(app, @XListValueChanged, true);
            app.LineCheckBox.Text = 'Line';
            app.LineCheckBox.Value = true;
            app.XLabel = uilabel(app.NanoLoczPlotterUIFigure);
            app.XLabel.Text = 'x';
            app.YLabel = uilabel(app.NanoLoczPlotterUIFigure);
            app.YLabel.Text = 'y';
            app.PlotTypeLabel = uilabel(app.NanoLoczPlotterUIFigure);
            app.PlotTypeLabel.Text = 'Plot Type';
            app.DataLabel = uilabel(app.NanoLoczPlotterUIFigure);
            app.DataLabel.Text = 'Data';
            app.FramesLabel = uilabel(app.NanoLoczPlotterUIFigure);
            app.FramesLabel.Visible = 'off';
            app.FramesLabel.Text = 'Frame(s)';
            app.TracksLabel = uilabel(app.NanoLoczPlotterUIFigure);
            app.TracksLabel.Visible = 'off';
            app.TracksLabel.Text = 'Track(s)';
            app.RangeEditFieldLabel = uilabel(app.NanoLoczPlotterUIFigure);
            app.RangeEditFieldLabel.Visible = 'off';
            app.RangeEditFieldLabel.Text = 'Range';
            app.RangeEditField = uieditfield(app.NanoLoczPlotterUIFigure, 'text');
            app.RangeEditField.Visible = 'off';
            app.RangeEditField_2Label = uilabel(app.NanoLoczPlotterUIFigure);
            app.RangeEditField_2Label.Visible = 'off';
            app.RangeEditField_2Label.Text = 'Range';
            app.RangeEditField_2 = uieditfield(app.NanoLoczPlotterUIFigure, 'text');
            app.RangeEditField_2.Visible = 'off';
            app.TrackDetectionsPanel = uipanel(app.NanoLoczPlotterUIFigure);
            app.RunTrackingButton = uibutton(app.TrackDetectionsPanel, 'push');
            app.RunTrackingButton.ButtonPushedFcn = createCallbackFcn(app, @RunTrackingButtonPushed, true);
            app.RunTrackingButton.Text = 'Run Tracking';
            app.MaxmissingEditFieldLabel = uilabel(app.TrackDetectionsPanel);
            app.MaxmissingEditFieldLabel.Text = 'Max missing ';
            app.MaxmissingEditField = uieditfield(app.TrackDetectionsPanel, 'numeric');
            app.MaxmissingEditField.Value = 3;
            app.MaxstepEditFieldLabel = uilabel(app.TrackDetectionsPanel);
            app.MaxstepEditFieldLabel.Text = 'Max step';
            app.MaxstepEditField = uieditfield(app.TrackDetectionsPanel, 'numeric');
            app.MaxstepEditField.Value = 10;
            app.MinlengthEditFieldLabel = uilabel(app.TrackDetectionsPanel);
            app.MinlengthEditFieldLabel.Text = 'Min length ';
            app.MinlengthEditField = uieditfield(app.TrackDetectionsPanel, 'numeric');
            app.MinlengthEditField.Value = 3;
            app.ColorbytrackCheckBox = uicheckbox(app.NanoLoczPlotterUIFigure);
            app.ColorbytrackCheckBox.ValueChangedFcn = createCallbackFcn(app, @ColorbytrackCheckBoxValueChanged, true);
            app.ColorbytrackCheckBox.Text = 'Color by track';
            app.ExportFileFormat = uidropdown(app.NanoLoczPlotterUIFigure);
            app.ExportFileFormat.Items = {'.txt', '.csv', '.xls'};
            app.ExportFileFormat.Value = '.txt';
            app.ExportdataButton = uibutton(app.NanoLoczPlotterUIFigure, 'push');
            app.ExportdataButton.ButtonPushedFcn = createCallbackFcn(app, @ExportdataButtonPushed, true);
            app.ExportdataButton.Tooltip = {''};
            app.ExportdataButton.Text = 'Export data';
            app.MinSpinnerLabel = uilabel(app.NanoLoczPlotterUIFigure);
            app.MinSpinnerLabel.Text = 'Min';
            app.MinSpinner = uispinner(app.NanoLoczPlotterUIFigure);
            app.MinSpinner.ValueChangedFcn = createCallbackFcn(app, @MinEditFieldValueChanged, true);
            app.MaxSpinnerLabel = uilabel(app.NanoLoczPlotterUIFigure);
            app.MaxSpinnerLabel.Text = 'Max';
            app.MaxSpinner = uispinner(app.NanoLoczPlotterUIFigure);
            app.MaxSpinner.ValueChangedFcn = createCallbackFcn(app, @MinEditFieldValueChanged, true);
            app.BinSpinnerLabel = uilabel(app.NanoLoczPlotterUIFigure);
            app.BinSpinnerLabel.Text = 'Bin';
            app.BinSpinner = uispinner(app.NanoLoczPlotterUIFigure);
            app.BinSpinner.Limits = [0 Inf];
            app.BinSpinner.ValueChangedFcn = createCallbackFcn(app, @MinEditFieldValueChanged, true);
            app.RefreshdataButton = uibutton(app.NanoLoczPlotterUIFigure, 'push');
            app.RefreshdataButton.ButtonPushedFcn = createCallbackFcn(app, @RefreshdataButtonPushed, true);
            app.RefreshdataButton.Text = 'Refresh data';
            app.CombinePlotsCheckBox = uicheckbox(app.NanoLoczPlotterUIFigure);
            app.CombinePlotsCheckBox.Text = 'Combine Plots';
            % Show the figure after all components are created
            app.NanoLoczPlotterUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = plotter

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.NanoLoczPlotterUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.NanoLoczPlotterUIFigure)
        end
    end
end