classdef Mat_SimAFM_v1_6 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        GridLayout                    matlab.ui.container.GridLayout
        LeftPanel                     matlab.ui.container.Panel
        ParachutinggradientEditField  matlab.ui.control.Spinner
        ParachutinggradientSpinnerLabel  matlab.ui.control.Label
        ParachutingDropDown           matlab.ui.control.DropDown
        ParachutingDropDownLabel      matlab.ui.control.Label
        ExporttoNanoLoczButton        matlab.ui.control.Button
        PlotButton                    matlab.ui.control.Button
        DrawLineProfileButton         matlab.ui.control.StateButton
        xEditField                    matlab.ui.control.NumericEditField
        yEditField                    matlab.ui.control.NumericEditField
        zEditField                    matlab.ui.control.NumericEditField
        LoadtxtcsvButton              matlab.ui.control.Button
        ImagefilteringEditField       matlab.ui.control.NumericEditField
        ImagefilteringEditFieldLabel  matlab.ui.control.Label
        oLabel                        matlab.ui.control.Label
        PDBIDEditField                matlab.ui.control.EditField
        PDBIDEditFieldLabel           matlab.ui.control.Label
        LoadpdbIDButton               matlab.ui.control.Button
        ExportTiffButton              matlab.ui.control.Button
        ExporttoMatlabButton          matlab.ui.control.Button
        SidewallangleEditField        matlab.ui.control.NumericEditField
        SidewallangleEditFieldLabel   matlab.ui.control.Label
        RotateaboutzaxisSlider        matlab.ui.control.Slider
        RotateaboutzaxisSliderLabel   matlab.ui.control.Label
        RotateaboutyaxisSlider        matlab.ui.control.Slider
        RotateaboutyaxisSliderLabel   matlab.ui.control.Label
        nmLabel                       matlab.ui.control.Label
        pixnmLabel                    matlab.ui.control.Label
        ZLimitSlider                  matlab.ui.control.Slider
        ZLimitLabel                   matlab.ui.control.Label
        RotateaboutxaxisSlider        matlab.ui.control.Slider
        RotateaboutxaxisSliderLabel   matlab.ui.control.Label
        NoiseEditField                matlab.ui.control.NumericEditField
        NoiseEditFieldLabel           matlab.ui.control.Label
        PixelSamplingEditField        matlab.ui.control.NumericEditField
        PixelSamplingEditFieldLabel   matlab.ui.control.Label
        TipradiusEditField            matlab.ui.control.NumericEditField
        TipradiusEditFieldLabel       matlab.ui.control.Label
        Label                         matlab.ui.control.Label
        LoadlocalpdbfileButton        matlab.ui.control.Button
        SimulateDynamicsPanel         matlab.ui.container.Panel
        RunButton                     matlab.ui.control.Button
        zfluctuationsEditField        matlab.ui.control.NumericEditField
        zfluctuationsEditFieldLabel   matlab.ui.control.Label
        xyfluctuationsEditField       matlab.ui.control.NumericEditField
        xyfluctuationsEditFieldLabel  matlab.ui.control.Label
        FramesEditField               matlab.ui.control.NumericEditField
        FramesEditFieldLabel          matlab.ui.control.Label
        SpinPanel                     matlab.ui.container.Panel
        SpinRange                     matlab.ui.control.EditField
        RangeEditFieldLabel           matlab.ui.control.Label
        RunSpin                       matlab.ui.control.Button
        AxisDropDown                  matlab.ui.control.DropDown
        AxisDropDownLabel             matlab.ui.control.Label
        RightPanel                    matlab.ui.container.Panel
        VideoPanel                    matlab.ui.container.Panel
        StartImage                    matlab.ui.control.Image
        EndImage                      matlab.ui.control.Image
        PauseImage                    matlab.ui.control.Image
        PlayImage                     matlab.ui.control.Image
        fps                           matlab.ui.control.NumericEditField
        fpsEditFieldLabel             matlab.ui.control.Label
        Spinner                       matlab.ui.control.Spinner
        FrameSlider                   matlab.ui.control.Slider
        ImageTypeDropDown             matlab.ui.control.DropDown
        ImageTypeDropDownLabel        matlab.ui.control.Label
        ColourMapDropDown             matlab.ui.control.DropDown
        ColourMapDropDownLabel        matlab.ui.control.Label
        ZScaleLabel                   matlab.ui.control.Label
        ZScaleDropBox                 matlab.ui.control.DropDown
        ContrastMinSpinner            matlab.ui.control.Spinner
        minSpinnerLabel               matlab.ui.control.Label
        ContrastMaxSpinner            matlab.ui.control.Spinner
        maxSpinnerLabel               matlab.ui.control.Label
        ColorBarCheckBox              matlab.ui.control.CheckBox
        ScalebarCheckBox              matlab.ui.control.CheckBox
        UIAxes                        matlab.ui.control.UIAxes
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
    end


    properties (Access = private)
        coords
        img
        frames
        roi
        c
        xt 
        label
        cmap
        viewangle
        stop
        mainApp
        
    end

    methods (Access = private)

        function Load_coords(app)
            clearvars app.coods
            [file,path] = uigetfile('*.pdb');
            PDBStruct = pdbread([path,file]);
            for i = 1:numel(PDBStruct.Model)
                if i ==1
                    icoords = [PDBStruct.Model(i).Atom.X ; PDBStruct.Model(i).Atom.Y; PDBStruct.Model(i).Atom.Z];
                else
                    icoords = [icoords, [PDBStruct.Model(i).Atom.X ; PDBStruct.Model(i).Atom.Y; PDBStruct.Model(i).Atom.Z]];
                end
            end
            icoords = icoords';
            
            app.coords = icoords;
        end

        function Load_coords_web(app)
            clearvars app.icoods
            namee = app.PDBIDEditField.Value;
            PDBStruct = pdbread(['http://www.rcsb.org/pdb/files/',namee,'.pdb']);
            for i = 1:numel(PDBStruct.Model)
                if i ==1
                    icoords = [PDBStruct.Model(i).Atom.X ; PDBStruct.Model(i).Atom.Y; PDBStruct.Model(i).Atom.Z];
                else
                    icoords = [icoords, [PDBStruct.Model(i).Atom.X ; PDBStruct.Model(i).Atom.Y; PDBStruct.Model(i).Atom.Z]];
                end
            end
            icoords = icoords';
            app.coords = icoords;
        end

        function updatePlot(app)
            if app.frames>1
                f = round(app.Spinner.Value);
                prev = app.img(:,:,f);
            else
                prev = app.img;
            end

            prev =  prev + app.NoiseEditField.Value*randn(size(prev));

switch app.ParachutingDropDown.Value
    case {'trace','retrace','bi-directional'}
        prev = add_para(prev, app.ParachutinggradientEditField.Value, app.ParachutingDropDown.Value);
end

            if app.ImagefilteringEditField.Value>0
                prev = imgaussfilt(prev,app.ImagefilteringEditField.Value);
            end

            switch app.ZScaleDropBox.Value
                case 'Auto'
                    minC = round(min(prev (:)),3,"significant");
                    maxC = round(max(prev(:)),3,"significant");
                    if minC<maxC
                        app.ContrastMinSpinner.Value = double(minC)/10;
                        app.ContrastMaxSpinner.Value = double(maxC)/10;
                    else
                        app.ContrastMinSpinner.Value = 0;
                        app.ContrastMaxSpinner.Value = 1;
                    end
                case 'Manual'
            end

            zcont = [app.ContrastMinSpinner.Value*10 app.ContrastMaxSpinner.Value*10];


            if strcmp(app.ImageTypeDropDown.Value,'2D surface')
                imagesc(app.UIAxes,prev,zcont)
                view(app.UIAxes,2)

                if app.ColorBarCheckBox.Value==1
                    a = colorbar(app.UIAxes,'Color','w','Box','off','TickDirection','out');
                    ylabel(a,'Height (nm)','Rotation',270);
                    a.Label.Position(1) = 3;
                    a.TickLabels = a.Ticks/10;
                else
                    colorbar(app.UIAxes,'off')
                end

                app.label.ScaleBar.position = 'Bottom Right';
                app.label.ScaleBar.color = 'w';

                if app.ScalebarCheckBox.Value ==1
                    try
                        [r, cs, ~] = size(prev);
                        sb = cs./app.PixelSamplingEditField.Value;
                        scale_bar = round(sb/5,1,"significant");
                        scale_bar_pix = scale_bar.*app.PixelSamplingEditField.Value;

                        switch app.label.ScaleBar.position
                            case 'Top Right'
                                xpos = [round(cs.*0.95)-scale_bar_pix, round(cs.*0.95)];
                                ypos = round([r*.05, r*.05]);
                            case 'Top Left'
                                xpos = [round(cs.*0.05), round(cs.*0.05)+scale_bar_pix];
                                ypos = round([r*.05, r*.05]);
                            case 'Bottom Left'
                                xpos = [round(cs.*0.05), round(cs.*0.05)+scale_bar_pix];
                                ypos = round([r*.95, r*.95]);
                            case 'Bottom Right'
                                xpos = [round(cs.*0.95)-scale_bar_pix, round(cs.*0.95)];
                                ypos = round([r*.95, r*.95]);
                        end

                        line(app.UIAxes,xpos,ypos,'LineWidth',2,'Color',app.label.ScaleBar.color);
                        text(app.UIAxes, xpos(1) + scale_bar_pix/2, (ypos(1) - cs*.03), [num2str(scale_bar) 'nm'],...
                            'FontWeight','bold','FontSize', 15,'Color', app.label.ScaleBar.color,'HorizontalAlignment','center');
                    catch
                    end
                end


            elseif strcmp(app.ImageTypeDropDown.Value,'3D surface')
                surf(app.UIAxes,prev)
                shading(app.UIAxes,'interp')

                if app.ColorBarCheckBox.Value==1
                    a = colorbar(app.UIAxes,'Color','w','Box','off','TickDirection','out');
                    ylabel(a,'Height (Å)','Rotation',270);
                    a.Label.Position(1) = 3;
                    a.TickLabels = a.Ticks/10;
                else
                    colorbar(app.UIAxes,'off')
                end

            elseif strcmp(app.ImageTypeDropDown.Value,'Coordinates')
                plot3(app.UIAxes,app.coords(:,1),app.coords(:,2),app.coords(:,3),'r.')
            end

            axis(app.UIAxes, 'tight','equal');
            switch app.ColourMapDropDown.Value
                case 'AFM brown'
                    colormap(app.UIAxes,app.cmap.AFM_brown)
                case 'AFM dark gold'
                    colormap(app.UIAxes,app.cmap.AFM_Dark_Gold)
                case 'AFM gold'
                    colormap(app.UIAxes,app.cmap.AFM_Gold)
                case 'Orange hot'
                    colormap(app.UIAxes,app.cmap.AFM_orange)
                case'AFM_fire'
                    colormap(app.UIAxes,app.cmap.AFM_fire)
                case {'gray', 'hot','jet','parula'}
                    colormap(app.UIAxes,app.ColourMapDropDown.Value)
            end

        end


        function updateVideoTools(app)

            if app.frames==1
                app.VideoPanel.Visible = 0;
            else
                app.Spinner.Limits = [1 app.frames];
                app.FrameSlider.Limits = [1 app.frames];
                if app.Spinner.Limits(2)>=10
                    inter = (app.Spinner.Limits(2)/10);
                else
                    inter = 1;
                end
                app.FrameSlider.MajorTicks = round(1:inter:app.frames);
                app.FrameSlider.MajorTickLabels = string(round(1:inter:app.frames));
                app.VideoPanel.Visible = 1;
                app.PlayImage.Visible=1;

            end

        end


        function result = generateSimAFM(app,x,y,z)

            ppa = app.PixelSamplingEditField.Value/10;
            ri = app.TipradiusEditField.Value*10;
            angle = app.SidewallangleEditField.Value;
            icoords = app.coords;
            z_thresh = app.ZLimitSlider.Value/100;
            theta_x = x;
            theta_y = y;
            theta_z = z;


            if theta_x >0
                icoords_rx(:,2) = icoords(:,2)*cos(theta_x*pi/180) - icoords(:,3)*sin(theta_x*pi/180);
                icoords_rx(:,3) = icoords(:,2)*sin(theta_x*pi/180) + icoords(:,3)*cos(theta_x*pi/180);
                icoords(:,2:3) = icoords_rx(:,2:3);
                clear icoords_rx
            elseif theta_x < 0
                icoords_rx(:,2) = icoords(:,2) * cos(abs(theta_x)*pi/180) + icoords(:,3) * sin(abs(theta_x)*pi/180);
                icoords_rx(:,3) = icoords(:,3) * cos(abs(theta_x)*pi/180) - icoords(:,2) * sin(abs(theta_x)*pi/180);
                icoords(:,2:3) = icoords_rx(:,2:3);
                clear icoords_rx
            end

            if theta_y >0
                icoords_ry(:,1) = icoords(:,1)*cos(theta_y*pi/180) + icoords(:,3)*sin(theta_y*pi/180);
                icoords_ry(:,3) = icoords(:,3)*cos(theta_y*pi/180) - icoords(:,1)*sin(theta_y*pi/180);
                icoords(:,1) = icoords_ry(:,1);icoords(:,3) = icoords_ry(:,3);
                clear icoords_ry
            elseif theta_y < 0
                icoords_ry(:,1) = icoords(:,1) * cos(abs(theta_y)*pi/180) - icoords(:,3) * sin(abs(theta_y)*pi/180);
                icoords_ry(:,3) = icoords(:,3) * cos(abs(theta_y)*pi/180) + icoords(:,1) * sin(abs(theta_y)*pi/180);
                icoords(:,1) = icoords_ry(:,1);
                icoords(:,3) = icoords_ry(:,3);
                clear icoords_ry    
            end

            if theta_z >0
                v=[icoords(:,1)';icoords(:,2)'];
                R = [cos(theta_z*pi/180) sin(theta_z*pi/180); -sin(theta_z*pi/180) cos(theta_z*pi/180)];
                so = R*v;
                icoords(:,1) = so(1,:);
                icoords(:,2) = so(2,:);
                clear so R v
            elseif theta_z < 0
                v = [icoords(:,1)'; icoords(:,2)'];
                R = [cos(abs(theta_z)*pi/180) -sin(abs(theta_z)*pi/180); sin(abs(theta_z)*pi/180) cos(abs(theta_z)*pi/180)]; 
                so = R * v;
                icoords(:,1) = so(1,:);
                icoords(:,2) = so(2,:);
                clear so R v
            end

            icoords(:,3) = icoords(:,3)-min(icoords(:,3));
            if z_thresh > 0
                z_thresh = z_thresh.*(max(icoords(:,3))-min(icoords(:,3))) + min(icoords(:,3));
                pos = icoords(:,3)>=(z_thresh);
                icoords = icoords(pos,1:3)-z_thresh;
            end
            result = Mat_SimAFM(icoords,ri,angle,ppa);
        end


        function result = generateSimAFM_dyn(app,x,y,z)

            ppa = app.PixelSamplingEditField.Value/10;
            ri = app.TipradiusEditField.Value*10;
            angle = app.SidewallangleEditField.Value;
            icoords = app.coords;
            z_thresh = app.ZLimitSlider.Value/100;
            theta_x = x;
            theta_y = y;
            theta_z = z;


            if theta_x >0
                icoords_rx(:,2) = icoords(:,2)*cos(theta_x*pi/180) - icoords(:,3)*sin(theta_x*pi/180);
                icoords_rx(:,3) = icoords(:,2)*sin(theta_x*pi/180) + icoords(:,3)*cos(theta_x*pi/180);
                icoords(:,2:3) = icoords_rx(:,2:3);
                clear icoords_rx
            else
                icoords_rx(:,2) = icoords(:,2) * cos(abs(theta_x)*pi/180) + icoords(:,3) * sin(abs(theta_x)*pi/180);
                icoords_rx(:,3) = icoords(:,3) * cos(abs(theta_x)*pi/180) - icoords(:,2) * sin(abs(theta_x)*pi/180);
                icoords(:,2:3) = icoords_rx(:,2:3);
                clear icoords_rx
            end

            if theta_y >0
                icoords_ry(:,1) = icoords(:,1)*cos(theta_y*pi/180) + icoords(:,3)*sin(theta_y*pi/180);
                icoords_ry(:,3) = icoords(:,3)*cos(theta_y*pi/180) - icoords(:,1)*sin(theta_y*pi/180);
                icoords(:,1) = icoords_ry(:,1);icoords(:,3) = icoords_ry(:,3);
                clear icoords_ry
            else
                icoords_ry(:,1) = icoords(:,1) * cos(abs(theta_y)*pi/180) - icoords(:,3) * sin(abs(theta_y)*pi/180);
                icoords_ry(:,3) = icoords(:,3) * cos(abs(theta_y)*pi/180) + icoords(:,1) * sin(abs(theta_y)*pi/180);
                icoords(:,1) = icoords_ry(:,1);
                icoords(:,3) = icoords_ry(:,3);
                clear icoords_ry    
            end

            if theta_z >0
                v=[icoords(:,1)';icoords(:,2)'];
                R = [cos(theta_z*pi/180) sin(theta_z*pi/180); -sin(theta_z*pi/180) cos(theta_z*pi/180)];
                so = R*v;
                icoords(:,1) = so(1,:);
                icoords(:,2) = so(2,:);
                clear so R v
            end

            icoords(:,3) = icoords(:,3)-min(icoords(:,3));
            if z_thresh > 0
                z_thresh = z_thresh.*(max(icoords(:,3))-min(icoords(:,3))) + min(icoords(:,3));
                pos = icoords(:,3)>=(z_thresh);
                icoords = icoords(pos,1:3)-z_thresh;
            end

            result = Mat_SimAFM_dyn(icoords,ri,angle,ppa,app.zfluctuationsEditField.Value, ...
                app.xyfluctuationsEditField.Value, app.FramesEditField.Value);
        end


        function result = generateSimAFM_spin(app,x,y,z)

            ppa = app.PixelSamplingEditField.Value/10;
            ri = app.TipradiusEditField.Value*10;
            angle = app.SidewallangleEditField.Value;
            icoords = app.coords;
            z_thresh = app.ZLimitSlider.Value/100;
            theta_x = x;
            theta_y = y;
            theta_z = z;


            if theta_x >0
                icoords_rx(:,2) = icoords(:,2)*cos(theta_x*pi/180) - icoords(:,3)*sin(theta_x*pi/180);
                icoords_rx(:,3) = icoords(:,2)*sin(theta_x*pi/180) + icoords(:,3)*cos(theta_x*pi/180);
                icoords(:,2:3) = icoords_rx(:,2:3);
                clear icoords_rx
            else
                theta_x = abs(theta_x);

                icoords_rx(:,2) = icoords(:,2)*cos(theta_x*pi/180) + icoords(:,3)*sin(theta_x*pi/180);
                icoords_rx(:,3) = -icoords(:,2)*sin(theta_x*pi/180) + icoords(:,3)*cos(theta_x*pi/180);
                icoords(:,2:3) = icoords_rx(:,2:3);
                clear icoords_rx;
            end

            if theta_y >0
                icoords_ry(:,1) = icoords(:,1)*cos(theta_y*pi/180) + icoords(:,3)*sin(theta_y*pi/180);
                icoords_ry(:,3) = icoords(:,3)*cos(theta_y*pi/180) - icoords(:,1)*sin(theta_y*pi/180);
                icoords(:,1) = icoords_ry(:,1);icoords(:,3) = icoords_ry(:,3);
                clear icoords_ry
            else
                theta_y = abs(theta_y);

                icoords_ry(:,1) = icoords(:,1)*cos(theta_y*pi/180) - icoords(:,3)*sin(theta_y*pi/180);
                icoords_ry(:,3) = icoords(:,1)*sin(theta_y*pi/180) + icoords(:,3)*cos(theta_y*pi/180);
                icoords(:,1) = icoords_ry(:,1);
                icoords(:,3) = icoords_ry(:,3);
                clear icoords_ry;
            end

            if theta_z >0
                v=[icoords(:,1)';icoords(:,2)'];
                R = [cos(theta_z*pi/180) sin(theta_z*pi/180); -sin(theta_z*pi/180) cos(theta_z*pi/180)];
                so = R*v;
                icoords(:,1) = so(1,:);
                icoords(:,2) = so(2,:);
                clear so R v
            end

            icoords(:,3) = icoords(:,3)-min(icoords(:,3));

            spin = str2num(app.SpinRange.Value);
            result = Mat_SimAFM_spin(icoords,ri,angle,ppa, spin, app.AxisDropDown.Value, z_thresh);
        end

    end
    
    methods (Access = public)


    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
          %  addpath('./icons');
            app.cmap = load('AFM_luts_full');
            ld = load('AFM_co.mat');
            app.coords = ld.AFM_co;
            app.frames = 1;
            updateVideoTools(app)
            app.img = generateSimAFM(app, app.RotateaboutxaxisSlider.Value,app.RotateaboutyaxisSlider.Value,app.RotateaboutzaxisSlider.Value);
            updatePlot(app)
            set(app.UIAxes,'visible','off')
            axis(app.UIAxes,'tight','equal');
            app.UIAxes.XTick = [];
            app.UIAxes.XTickLabelRotation = 0;
            app.UIAxes.XTickLabel = '';
            app.UIAxes.YTick = [];
            app.UIAxes.YTickLabelRotation = 0;
            app.UIAxes.YTickLabel = '';
            grid(app.UIAxes,'off')
            app.UIAxes.XLabel = [];
            app.UIAxes.YLabel = [];
            
            allfigs = findall(0,'Type', 'figure'); 
            app2Handle = findall(allfigs, 'Name', 'NanoLocz');
            
            if isempty(app2Handle(1)) || ~isvalid(app2Handle(1))
                app.ExporttoNanoLoczButton.Enable = 'off';
                app.ExporttoNanoLoczButton.Visible = 'off';
            else
                app.ExporttoNanoLoczButton.Enable = 'on';
                app.ExporttoNanoLoczButton.Visible = 'on';

                app.mainApp = app2Handle(1).RunningAppInstance;
                try
                app.PixelSamplingEditField.Value = app.mainApp.ImageInfo.PixelPerNm(1);
                catch
                    app.PixelSamplingEditField.Value = 10;
                end

            end    
            
        end

        % Button pushed function: LoadlocalpdbfileButton
        function LoadlocalpdbfileButtonPushed(app, event)

            Load_coords(app)
            app.img = generateSimAFM(app, app.RotateaboutxaxisSlider.Value,app.RotateaboutyaxisSlider.Value,app.RotateaboutzaxisSlider.Value);
            app.frames = 1;
            updateVideoTools(app)
            updatePlot(app)
  app.stop = 1;
        end

        % Value changed function: TipradiusEditField
        function TipradiusEditFieldValueChanged(app, event)
            app.img = generateSimAFM(app, app.RotateaboutxaxisSlider.Value,app.RotateaboutyaxisSlider.Value,app.RotateaboutzaxisSlider.Value);
            app.frames = 1;
            updateVideoTools(app)
            updatePlot(app)
            app.stop = 1;
        end

        % Callback function: NoiseEditField, ParachutingDropDown, 
        % ...and 2 other components
        function NoiseEditFieldValueChanged(app, event)
            updatePlot(app)
        end

        % Value changed function: PixelSamplingEditField
        function PixelSamplingEditFieldValueChanged(app, event)
            app.img = generateSimAFM(app, app.RotateaboutxaxisSlider.Value,app.RotateaboutyaxisSlider.Value,app.RotateaboutzaxisSlider.Value);
            app.frames = 1;
            updateVideoTools(app)
            updatePlot(app)
            app.DrawLineProfileButton.Value = 0;
            delete(app.roi)
            app.stop = 1;
        end

        % Value changed function: ZLimitSlider
        function ZLimitSliderValueChanged(app, event)
            app.img = generateSimAFM(app, app.RotateaboutxaxisSlider.Value,app.RotateaboutyaxisSlider.Value,app.RotateaboutzaxisSlider.Value);
            app.frames = 1;
            updateVideoTools(app)
            updatePlot(app)
              app.stop = 1;
        end

        % Value changed function: RotateaboutxaxisSlider
        function RotateaboutxaxisSliderValueChanged(app, event)
            app.img = generateSimAFM(app, app.RotateaboutxaxisSlider.Value,app.RotateaboutyaxisSlider.Value,app.RotateaboutzaxisSlider.Value);
            app.frames = 1;
            updateVideoTools(app)
            updatePlot(app)
            app.stop = 1;
        end

        % Value changed function: RotateaboutyaxisSlider
        function RotateaboutyaxisSliderValueChanged(app, event)
            app.img = generateSimAFM(app, app.RotateaboutxaxisSlider.Value,app.RotateaboutyaxisSlider.Value,app.RotateaboutzaxisSlider.Value);
            app.frames = 1;
            updateVideoTools(app)
            updatePlot(app)
            app.stop = 1;
        end

        % Value changed function: RotateaboutzaxisSlider
        function RotateaboutzaxisSliderValueChanged(app, event)
            app.img = generateSimAFM(app, app.RotateaboutxaxisSlider.Value,app.RotateaboutyaxisSlider.Value,app.RotateaboutzaxisSlider.Value);
            app.frames = 1;
            updateVideoTools(app)
            updatePlot(app)
            app.stop = 1;
        end

        % Value changed function: ColourMapDropDown
        function ColourMapDropDownValueChanged(app, event)

            updatePlot(app)
        end

        % Value changed function: SidewallangleEditField
        function SidewallangleEditFieldValueChanged(app, event)
            app.img = generateSimAFM(app, app.RotateaboutxaxisSlider.Value,app.RotateaboutyaxisSlider.Value,app.RotateaboutzaxisSlider.Value);
            app.frames = 1;
            updateVideoTools(app)
            updatePlot(app)
  app.stop = 1;
        end

        % Button pushed function: ExporttoMatlabButton
        function ExporttoMatlabButtonPushed(app, event)
         
            img_out =  app.img + app.NoiseEditField.Value*randn(size(app.img));

            for i = 1:size(img_out,3)
            switch app.ParachutingDropDown.Value
                case {'trace','retrace','bi-directional'}
                    img_out(:,:,i) = add_para(img_out(:,:,i), app.ParachutinggradientEditField.Value, app.ParachutingDropDown.Value);
            end
            end
            if app.ImagefilteringEditField.Value>0
                img_out= imgaussfilt(img_out,app.ImagefilteringEditField.Value);
            end

            assignin('base', 'AFMsurface', img_out)
            assignin('base', 'Profile_z', app.c)
            assignin('base', 'Profile_x', app.xt)
            assignin('base', 'Pixel_sampling', app.PixelSamplingEditField.Value)
            assignin('base', 'tip_radius', app.TipradiusEditField.Value)
            assignin('base', 'noise', app.NoiseEditField.Value)
            assignin('base', 'coords', app.coords)
        end

        % Value changed function: ImageTypeDropDown
        function ImageTypeDropDownValueChanged(app, event)
            updatePlot(app)
            app.stop = 1;
        end

        % Button pushed function: ExportTiffButton
        function ExportTiffButtonPushed(app, event)
            img_out =  app.img + app.NoiseEditField.Value*randn(size(app.img));
            for i = 1:size(img_out,3)
            switch app.ParachutingDropDown.Value
                case {'trace','retrace','bi-directional'}
                    img_out(:,:,i) = add_para(img_out(:,:,i), app.ParachutinggradientEditField.Value, app.ParachutingDropDown.Value);
            end
            end

            if app.ImagefilteringEditField.Value>0
                img_out= imgaussfilt(img_out,app.ImagefilteringEditField.Value);
            end
            [~,~] = export_tiffs(img_out);
        end

        % Button pushed function: LoadpdbIDButton
        function LoadpdbIDButtonPushed(app, event)
            Load_coords_web(app)
            app.img = generateSimAFM(app, app.RotateaboutxaxisSlider.Value,app.RotateaboutyaxisSlider.Value,app.RotateaboutzaxisSlider.Value);
            app.frames = 1;
            updateVideoTools(app)
            updatePlot(app)
            app.stop = 1;
        end

        % Value changed function: DrawLineProfileButton
        function DrawLineProfileButtonValueChanged(app, event)
            delete(app.roi)
            if app.DrawLineProfileButton.Value == 0
            else
                app.roi = drawline(app.UIAxes);
            end
        end

        % Button pushed function: PlotButton
        function PlotButtonPushed(app, event)

            img_out =  app.img + app.NoiseEditField.Value*randn(size(app.img));

            switch app.ParachutingDropDown.Value
                case {'trace','retrace','bi-directional'}
                    img_out = add_para(img_out, app.ParachutinggradientEditField.Value, app.ParachutingDropDown.Value);
            end

            if app.ImagefilteringEditField.Value>0
                img_out= imgaussfilt(img_out,app.ImagefilteringEditField.Value);
            end
            try
                app.DrawLineProfileButton.Value = 0;
                if app.frames>1
                    f = round(app.Spinner.Value);
                    prev = img_out(:,:,f);
                else
                    prev = img_out;
                end
                [cx,cy,app.c] = improfile(prev,app.roi.Position(:,1),app.roi.Position(:,2));
                dist = 1/app.PixelSamplingEditField.Value*((cx(1)-cx(end))^2 + (cy(1)-cy(end))^2)^0.5;
                app.xt = linspace(0,dist,numel(app.c));
                plot(app.xt,app.c/10,'ro-')
                xlabel('Distance (nm)')
                ylabel('Height (nm)')
                set(gca,'fontname','Ariel','fontsize', 14,'TickDir','out')
                grid on
                axis('tight');
            catch
            end
        end

        % Value changed function: ImagefilteringEditField
        function ImagefilteringEditFieldValueChanged(app, event)
            updatePlot(app)
        end

        % Button pushed function: LoadtxtcsvButton
        function LoadtxtcsvButtonPushed(app, event)

            clearvars app.coods
            [file, path] = uigetfile('*');
            if any(file)
                PDBStruct = fullfile(path, file);
                T = readtable(PDBStruct,'NumHeaderLines',0);
                app.coords = table2array(T);
                app.img = generateSimAFM(app, app.RotateaboutxaxisSlider.Value,app.RotateaboutyaxisSlider.Value,app.RotateaboutzaxisSlider.Value);
                app.frames = 1;
                updateVideoTools(app)
                updatePlot(app)
            end
              app.stop = 1;
        end

        % Value changing function: RotateaboutxaxisSlider
        function RotateaboutxaxisSliderValueChanging(app, event)
            app.xEditField.Value = event.Value;
            app.img = generateSimAFM(app, event.Value,app.RotateaboutyaxisSlider.Value,app.RotateaboutzaxisSlider.Value);
            app.frames = 1;
            updateVideoTools(app)
            updatePlot(app)
              app.stop = 1;
        end

        % Value changing function: RotateaboutyaxisSlider
        function RotateaboutyaxisSliderValueChanging(app, event)
            app.yEditField.Value = event.Value;
            app.img = generateSimAFM(app,app.RotateaboutxaxisSlider.Value,event.Value,app.RotateaboutzaxisSlider.Value);
            app.frames = 1;
            updateVideoTools(app)
            updatePlot(app)
              app.stop = 1;
        end

        % Value changing function: RotateaboutzaxisSlider
        function RotateaboutzaxisSliderValueChanging(app, event)
            app.zEditField.Value = event.Value;
            app.img = generateSimAFM(app,app.RotateaboutxaxisSlider.Value,app.RotateaboutyaxisSlider.Value,event.Value);
            app.frames = 1;
            updateVideoTools(app)
            updatePlot(app)
              app.stop = 1;

        end

        % Value changed function: ScalebarCheckBox
        function ScalebarCheckBoxValueChanged(app, event)
            updatePlot(app)
        end

        % Value changed function: ColorBarCheckBox
        function ColorBarCheckBoxValueChanged(app, event)
            updatePlot(app)
        end

        % Value changed function: ContrastMaxSpinner
        function ContrastMaxSpinnerValueChanged(app, event)
            app.ZScaleDropBox.Value = 'Manual';
            updatePlot(app)
        end

        % Value changed function: ContrastMinSpinner
        function ContrastMinSpinnerValueChanged(app, event)
            app.ZScaleDropBox.Value = 'Manual';
            updatePlot(app)
        end

        % Value changed function: ZScaleDropBox
        function ZScaleDropBoxValueChanged(app, event)
            updatePlot(app)
        end

        % Value changed function: xEditField
        function xEditFieldValueChanged(app, event)
            app.RotateaboutxaxisSlider.Value =  app.xEditField.Value;
            app.img = generateSimAFM(app, app.RotateaboutxaxisSlider.Value,app.RotateaboutyaxisSlider.Value,app.RotateaboutzaxisSlider.Value);
            app.frames = 1;
            updateVideoTools(app)
            updatePlot(app)
              app.stop = 1;
        end

        % Value changed function: yEditField
        function yEditFieldValueChanged(app, event)
            app.RotateaboutyaxisSlider.Value =  app.yEditField.Value;
            app.img = generateSimAFM(app, app.RotateaboutxaxisSlider.Value,app.RotateaboutyaxisSlider.Value,app.RotateaboutzaxisSlider.Value);
            app.frames = 1;
            updateVideoTools(app)
            updatePlot(app)
              app.stop = 1;
        end

        % Value changed function: zEditField
        function zEditFieldValueChanged(app, event)
            app.RotateaboutzaxisSlider.Value =  app.zEditField.Value;
            app.img = generateSimAFM(app, app.RotateaboutxaxisSlider.Value,app.RotateaboutyaxisSlider.Value,app.RotateaboutzaxisSlider.Value);
            app.frames = 1;
            updateVideoTools(app)
            updatePlot(app)
              app.stop = 1;
        end

        % Image clicked function: PlayImage
        function PlayImageClicked(app, event)
            app.PlayImage.Visible=0;
            app.PauseImage.Visible=1;
            app.stop=0;
            currentFrame = round(app.FrameSlider.Value);

            if strcmp(app.ImageTypeDropDown.Value,'3D surface') || strcmp(app.ImageTypeDropDown.Value,'Coordinates')
                [app.viewangle(1), app.viewangle(2)]= view(app.UIAxes);
            end

            while true
                tic;
                if app.stop == 1
                    return
                end

                if currentFrame == app.FrameSlider.Limits(2) + 1
                    currentFrame = 1;
                end

                app.FrameSlider.Value = currentFrame;
                app.Spinner.Value = currentFrame;

                updatePlot(app)

                if strcmp(app.ImageTypeDropDown.Value,'3D surface') || strcmp(app.ImageTypeDropDown.Value,'Coordinates')
                    view(app.UIAxes,app.viewangle(1), app.viewangle(2));
                end

                playtime = toc;
                pause(1/app.fps.Value-playtime)
                drawnow
                currentFrame = currentFrame + 1;
            end

        end

        % Image clicked function: PauseImage
        function PauseImageClicked(app, event)
            app.PlayImage.Visible=1;
            app.PauseImage.Visible=0;
            app.stop = 1;
        end

        % Image clicked function: StartImage
        function StartImageClicked(app, event)
            app.FrameSlider.Value = 1;
            app.Spinner.Value = app.FrameSlider.Value;
            updatePlot(app)
        end

        % Image clicked function: EndImage
        function EndImageClicked(app, event)
            app.FrameSlider.Value = app.FrameSlider.Limits(2);
            app.Spinner.Value = app.FrameSlider.Value;
            updatePlot(app)
        end

        % Button pushed function: RunButton
        function RunButtonPushed(app, event)
            app.img = generateSimAFM_dyn(app, app.xEditField.Value,app.yEditField.Value,app.zEditField.Value);
            app.frames =  numel(app.img(1,1,:));
            updateVideoTools(app)
            updatePlot(app)
PlayImageClicked(app, event)
        end

        % Button pushed function: RunSpin
        function RunSpinPushed(app, event)
            app.img = generateSimAFM_spin(app, app.xEditField.Value,app.yEditField.Value,app.zEditField.Value);
            app.frames =  numel(app.img(1,1,:));
            updateVideoTools(app)
            updatePlot(app)
PlayImageClicked(app, event)
        end

        % Value changing function: FrameSlider
        function FrameSliderValueChanging(app, event)
    
           app.Spinner.Value = round(event.Value);
            updatePlot(app)
        end

        % Value changed function: Spinner
        function SpinnerValueChanged(app, event)

           app.FrameSlider.Value = round(app.Spinner.Value);
            updatePlot(app)
        end

        % Button pushed function: ExporttoNanoLoczButton
        function ExporttoNanoLoczButtonPushed(app, event)
            simImg = app.img+app.NoiseEditField.Value*randn(size(app.img));
            for i = 1:size(simImg,3)
                switch app.ParachutingDropDown.Value
                    case {'trace','retrace','bi-directional'}
                        simImg(:,:,i) = add_para(simImg(:,:,i), app.ParachutinggradientEditField.Value, app.ParachutingDropDown.Value);
                end
            end
              
            PixelPerNm = app.PixelSamplingEditField.Value;
            if size(simImg,3)>1
                app.mainApp.ImageName = inputdlg('Simulation name:', 'Import AFM Simulation', [1 50]);
            end
            app.mainApp.ImageName
            app.mainApp.importSimulation(simImg,PixelPerNm)


        end

        % Callback function: UIAxes, UIFigure
        function UIAxesButtonDown(app, event)
            if strcmp(app.ImageTypeDropDown.Value,'2D surface') && app.DrawLineProfileButton.Value == 0
                img2 = hittest(app.UIFigure);
                
                ax = app.UIAxes;
                fig = ancestor(img2, 'figure');
                props.WindowButtonMotionFcn = get(fig, 'WindowButtonMotionFcn');
                props.WindowButtonUpFcn = get(fig, 'WindowButtonUpFcn');
                setappdata(fig, 'TestGuiCallbacks', props);
                sp =[ app.xEditField.Value, app.yEditField.Value];
                                
                set(fig, 'WindowButtonMotionFcn', @(src, evt) dragging_sq(src, evt, ax,sp));
                set(fig, 'WindowButtonUpFcn', @(src, evt) mouseup_sq(src, evt, ax));
             
                ax.UserData.startPos = ax.CurrentPoint(1, 1:2);
                ax.UserData.points = [];
                
            end 
            
                function dragging_sq(~, ~, ax, sp)
                    ax.XLim = ax.XLim;   ax.YLim = ax.YLim ;
                    xRange = ax.XLim(2) - ax.XLim(1);
                    yRange = ax.YLim(2) - ax.YLim(1);
                    startPos = ax.UserData.startPos;
                    currentPos = ax.CurrentPoint(1, 1:2);
                    
                    if numel(startPos) == 2 && numel(currentPos) == 2
                        dx = (startPos(1) - currentPos(1)) * 180/ xRange + sp(1);
                        dy = (currentPos(2) - startPos(2)) * 180 / yRange + sp(2);
                        
                        if currentPos(1) >= ax.XLim(1) && currentPos(1) <= ax.XLim(2) && ...
                            currentPos(2) >= ax.YLim(1) && currentPos(2) <= ax.YLim(2)
                            
                            if dx >= 0 && dy >= 0
                                app.xEditField.Value = (abs(dx));
                                app.yEditField.Value = (abs(dy));
                                app.RotateaboutxaxisSlider.Value = app.xEditField.Value;
                                app.RotateaboutyaxisSlider.Value = app.yEditField.Value;
                                app.img = generateSimAFM(app, app.RotateaboutxaxisSlider.Value,app.RotateaboutyaxisSlider.Value,app.RotateaboutzaxisSlider.Value);
                            else
                                app.xEditField.Value = dx;
                                 app.RotateaboutxaxisSlider.Value =  dx;
                                app.yEditField.Value = dy;
                                  app.RotateaboutyaxisSlider.Value =  dy;
                                app.img = generateSimAFM(app, dx, dy, app.RotateaboutzaxisSlider.Value);
                            end
                            
                            app.frames = 1;
                            updateVideoTools(app)
                            updatePlot(app)
                            app.stop = 1;
                        end
                    end
                end
                
                function mouseup_sq(~, ~, ax)
                    fig = ancestor(ax, 'figure');
                    props = getappdata(fig, 'TestGuiCallbacks');
                    set(fig, props);
                    setappdata(fig, 'TestGuiCallbacks', []);
                    ax.UserData.startPos = [];
                    
                    set(fig, 'WindowButtonMotionFcn', '');
                    set(fig, 'WindowButtonUpFcn', '');
                end    
            
        end

        % Callback function
        function ExportButtonPushed(app, event)

            img_out =  app.img + app.NoiseEditField.Value*randn(size(app.img));

            for i = size(img_out,3)
                switch app.ParachutingDropDown.Value
                    case {'trace','retrace','bi-directional'}
                        img_out(:,:,i) = add_para(img_out(:,:,i), app.ParachutinggradientEditField.Value, app.ParachutingDropDown.Value);
                end
            end
            
            if app.ImagefilteringEditField.Value>0
                img_out= imgaussfilt(img_out,app.ImagefilteringEditField.Value);
            end
            [filename,folder] = export_tiffs(img_out);

            allfigs = findall(0,'Type', 'figure'); 
            app2Handle = findall(allfigs, 'Name', 'NanoLocz');
            if isempty(app2Handle(1)) || ~isvalid(app2Handle(1))
                app.mainApp = NanoLocz_1_00;
            end

            app.mainApp.import_tiff(filename,folder)
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.UIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 2x1 grid
                app.GridLayout.RowHeight = {481, 481};
                app.GridLayout.ColumnWidth = {'1x'};
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 1;
            else
                % Change to a 1x2 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {378, '1x'};
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 2;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.UIFigure.WindowButtonDownFcn = createCallbackFcn(app, @UIAxesButtonDown, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.SpinPanel = uipanel(app.LeftPanel);
            app.SpinPanel.Title = 'Spin';
            app.AxisDropDownLabel = uilabel(app.SpinPanel);
            app.AxisDropDownLabel.Text = 'Axis';
            app.AxisDropDown = uidropdown(app.SpinPanel);
            app.AxisDropDown.Items = {'X', 'Y', 'Z'};
            app.AxisDropDown.Value = 'X';
            app.RunSpin = uibutton(app.SpinPanel, 'push');
            app.RunSpin.ButtonPushedFcn = createCallbackFcn(app, @RunSpinPushed, true);
            app.RunSpin.Text = 'Run';
            app.RangeEditFieldLabel = uilabel(app.SpinPanel);
            app.RangeEditFieldLabel.Text = 'Range';
            app.SpinRange = uieditfield(app.SpinPanel, 'text');
            app.SpinRange.Value = '0:5:360';
            app.SimulateDynamicsPanel = uipanel(app.LeftPanel);
            app.SimulateDynamicsPanel.Title = 'Simulate Dynamics';
            app.FramesEditFieldLabel = uilabel(app.SimulateDynamicsPanel);
            app.FramesEditFieldLabel.Text = 'Frames';
            app.FramesEditField = uieditfield(app.SimulateDynamicsPanel, 'numeric');
            app.FramesEditField.Limits = [1 Inf];
            app.FramesEditField.Value = 10;
            app.xyfluctuationsEditFieldLabel = uilabel(app.SimulateDynamicsPanel);
            app.xyfluctuationsEditFieldLabel.Text = 'x-y fluctuations';
            app.xyfluctuationsEditField = uieditfield(app.SimulateDynamicsPanel, 'numeric');
            app.xyfluctuationsEditField.Limits = [0 Inf];
            app.xyfluctuationsEditField.Value = 1;
            app.zfluctuationsEditFieldLabel = uilabel(app.SimulateDynamicsPanel);
            app.zfluctuationsEditFieldLabel.Text = 'z fluctuations';
            app.zfluctuationsEditField = uieditfield(app.SimulateDynamicsPanel, 'numeric');
            app.zfluctuationsEditField.Limits = [0 Inf];
            app.zfluctuationsEditField.Value = 1;
            app.RunButton = uibutton(app.SimulateDynamicsPanel, 'push');
            app.RunButton.ButtonPushedFcn = createCallbackFcn(app, @RunButtonPushed, true);
            app.RunButton.Text = 'Run';
            app.LoadlocalpdbfileButton = uibutton(app.LeftPanel, 'push');
            app.LoadlocalpdbfileButton.ButtonPushedFcn = createCallbackFcn(app, @LoadlocalpdbfileButtonPushed, true);
            app.LoadlocalpdbfileButton.Tooltip = {'Load .PDB file from local storage instead of online'};
            app.LoadlocalpdbfileButton.Text = 'Load local .pdb file';
            app.Label = uilabel(app.LeftPanel);
            app.Label.Text = ' Å';
            app.TipradiusEditFieldLabel = uilabel(app.LeftPanel);
            app.TipradiusEditFieldLabel.Text = 'Tip radius';
            app.TipradiusEditField = uieditfield(app.LeftPanel, 'numeric');
            app.TipradiusEditField.Limits = [0.1 50];
            app.TipradiusEditField.ValueChangedFcn = createCallbackFcn(app, @TipradiusEditFieldValueChanged, true);
            app.TipradiusEditField.Tooltip = {'AFM tip apex radius'};
            app.TipradiusEditField.Value = 0.5;
            app.PixelSamplingEditFieldLabel = uilabel(app.LeftPanel);
            app.PixelSamplingEditFieldLabel.Text = 'Pixel Sampling';
            app.PixelSamplingEditField = uieditfield(app.LeftPanel, 'numeric');
            app.PixelSamplingEditField.Limits = [0.01 20];
            app.PixelSamplingEditField.ValueChangedFcn = createCallbackFcn(app, @PixelSamplingEditFieldValueChanged, true);
            app.PixelSamplingEditField.Value = 5;
            app.NoiseEditFieldLabel = uilabel(app.LeftPanel);
            app.NoiseEditFieldLabel.Text = 'Noise';
            app.NoiseEditField = uieditfield(app.LeftPanel, 'numeric');
            app.NoiseEditField.Limits = [0 Inf];
            app.NoiseEditField.ValueChangedFcn = createCallbackFcn(app, @NoiseEditFieldValueChanged, true);
            app.NoiseEditField.Tooltip = {'Add background noise'};
            app.NoiseEditField.Value = 1;
            app.RotateaboutxaxisSliderLabel = uilabel(app.LeftPanel);
            app.RotateaboutxaxisSliderLabel.Text = 'Rotate about x axis';
            app.RotateaboutxaxisSlider = uislider(app.LeftPanel);
            app.RotateaboutxaxisSlider.Limits = [-180 180];
            app.RotateaboutxaxisSlider.MajorTicks = [-180 -90 0 90 180];
            app.RotateaboutxaxisSlider.MajorTickLabels = {'-180', '-90', '0', '90', '180'};
            app.RotateaboutxaxisSlider.ValueChangedFcn = createCallbackFcn(app, @RotateaboutxaxisSliderValueChanged, true);
            app.RotateaboutxaxisSlider.ValueChangingFcn = createCallbackFcn(app, @RotateaboutxaxisSliderValueChanging, true);
            app.ZLimitLabel = uilabel(app.LeftPanel);
            app.ZLimitLabel.Text = {'Z Limit '; '(%)'};
            app.ZLimitSlider = uislider(app.LeftPanel);
            app.ZLimitSlider.Limits = [0 99];
            app.ZLimitSlider.MajorTicks = [0 25 50 75 99];
            app.ZLimitSlider.ValueChangedFcn = createCallbackFcn(app, @ZLimitSliderValueChanged, true);
            app.ZLimitSlider.Tooltip = {'Adjust zero level - can be used to mimic position of membrane'};
            app.pixnmLabel = uilabel(app.LeftPanel);
            app.pixnmLabel.Text = ' pix/nm';
            app.nmLabel = uilabel(app.LeftPanel);
            app.nmLabel.Text = ' nm';
            app.RotateaboutyaxisSliderLabel = uilabel(app.LeftPanel);
            app.RotateaboutyaxisSliderLabel.Text = 'Rotate about y axis';
            app.RotateaboutyaxisSlider = uislider(app.LeftPanel);
            app.RotateaboutyaxisSlider.Limits = [-180 180];
            app.RotateaboutyaxisSlider.MajorTicks = [-180 -90 0 90 180];
            app.RotateaboutyaxisSlider.ValueChangedFcn = createCallbackFcn(app, @RotateaboutyaxisSliderValueChanged, true);
            app.RotateaboutyaxisSlider.ValueChangingFcn = createCallbackFcn(app, @RotateaboutyaxisSliderValueChanging, true);
            app.RotateaboutzaxisSliderLabel = uilabel(app.LeftPanel);
            app.RotateaboutzaxisSliderLabel.Text = 'Rotate about z axis';
            app.RotateaboutzaxisSlider = uislider(app.LeftPanel);
            app.RotateaboutzaxisSlider.Limits = [-180 180];
            app.RotateaboutzaxisSlider.MajorTicks = [-180 -90 0 90 180];
            app.RotateaboutzaxisSlider.ValueChangedFcn = createCallbackFcn(app, @RotateaboutzaxisSliderValueChanged, true);
            app.RotateaboutzaxisSlider.ValueChangingFcn = createCallbackFcn(app, @RotateaboutzaxisSliderValueChanging, true);
            app.SidewallangleEditFieldLabel = uilabel(app.LeftPanel);
            app.SidewallangleEditFieldLabel.Text = 'Sidewall angle';
            app.SidewallangleEditField = uieditfield(app.LeftPanel, 'numeric');
            app.SidewallangleEditField.Limits = [0.1 50];
            app.SidewallangleEditField.ValueChangedFcn = createCallbackFcn(app, @SidewallangleEditFieldValueChanged, true);
            app.SidewallangleEditField.Tooltip = {'sidewall angle of AFM tip'};
            app.SidewallangleEditField.Value = 8;
            app.ExporttoMatlabButton = uibutton(app.LeftPanel, 'push');
            app.ExporttoMatlabButton.ButtonPushedFcn = createCallbackFcn(app, @ExporttoMatlabButtonPushed, true);
            app.ExporttoMatlabButton.Text = 'Export to Matlab';
            app.ExportTiffButton = uibutton(app.LeftPanel, 'push');
            app.ExportTiffButton.ButtonPushedFcn = createCallbackFcn(app, @ExportTiffButtonPushed, true);
            app.ExportTiffButton.Text = 'Export Tiff';
            app.LoadpdbIDButton = uibutton(app.LeftPanel, 'push');
            app.LoadpdbIDButton.ButtonPushedFcn = createCallbackFcn(app, @LoadpdbIDButtonPushed, true);
            app.LoadpdbIDButton.Tooltip = {'''Load pdb ID'' from online data bank (requires internet connection)'};
            app.LoadpdbIDButton.Text = 'Load pdb ID';
            app.PDBIDEditFieldLabel = uilabel(app.LeftPanel);
            app.PDBIDEditFieldLabel.Text = 'PDB ID';
            app.PDBIDEditField = uieditfield(app.LeftPanel, 'text');
            app.PDBIDEditField.Tooltip = {'Enter PDB ID and press ''Load pdb ID'' to load from online data bank'};
            app.PDBIDEditField.Value = '2abm';
            app.oLabel = uilabel(app.LeftPanel);
            app.oLabel.Text = 'o';
            app.ImagefilteringEditFieldLabel = uilabel(app.LeftPanel);
            app.ImagefilteringEditFieldLabel.Text = 'Image filtering';
            app.ImagefilteringEditField = uieditfield(app.LeftPanel, 'numeric');
            app.ImagefilteringEditField.ValueChangedFcn = createCallbackFcn(app, @ImagefilteringEditFieldValueChanged, true);
            app.LoadtxtcsvButton = uibutton(app.LeftPanel, 'push');
            app.LoadtxtcsvButton.ButtonPushedFcn = createCallbackFcn(app, @LoadtxtcsvButtonPushed, true);
            app.LoadtxtcsvButton.Text = 'Load txt,csv...';
            app.zEditField = uieditfield(app.LeftPanel, 'numeric');
            app.zEditField.Limits = [-360 360];
            app.zEditField.ValueChangedFcn = createCallbackFcn(app, @zEditFieldValueChanged, true);
            app.yEditField = uieditfield(app.LeftPanel, 'numeric');
            app.yEditField.Limits = [-360 360];
            app.yEditField.ValueChangedFcn = createCallbackFcn(app, @yEditFieldValueChanged, true);
            app.xEditField = uieditfield(app.LeftPanel, 'numeric');
            app.xEditField.Limits = [-360 360];
            app.xEditField.ValueChangedFcn = createCallbackFcn(app, @xEditFieldValueChanged, true);
            app.DrawLineProfileButton = uibutton(app.LeftPanel, 'state');
            app.DrawLineProfileButton.ValueChangedFcn = createCallbackFcn(app, @DrawLineProfileButtonValueChanged, true);
            app.DrawLineProfileButton.Text = 'Draw Line Profile';
            app.PlotButton = uibutton(app.LeftPanel, 'push');
            app.PlotButton.ButtonPushedFcn = createCallbackFcn(app, @PlotButtonPushed, true);
            app.PlotButton.Text = 'Plot';
            app.ExporttoNanoLoczButton = uibutton(app.LeftPanel, 'push');
            app.ExporttoNanoLoczButton.ButtonPushedFcn = createCallbackFcn(app, @ExporttoNanoLoczButtonPushed, true);
            app.ExporttoNanoLoczButton.Tooltip = {'Export Simulated Image to NanoLocz'};
            app.ExporttoNanoLoczButton.Text = 'Export to NanoLocz';
            app.ParachutingDropDownLabel = uilabel(app.LeftPanel);
            app.ParachutingDropDownLabel.Text = 'Parachuting';
            app.ParachutingDropDown = uidropdown(app.LeftPanel);
            app.ParachutingDropDown.Items = {'none', 'trace', 'retrace', 'bi-directional'};
            app.ParachutingDropDown.ValueChangedFcn = createCallbackFcn(app, @NoiseEditFieldValueChanged, true);
            app.ParachutingDropDown.Value = 'none';
            app.ParachutinggradientSpinnerLabel = uilabel(app.LeftPanel);
            app.ParachutinggradientSpinnerLabel.Text = 'Parachuting gradient';
            app.ParachutinggradientEditField = uispinner(app.LeftPanel);
            app.ParachutinggradientEditField.Step = 0.5;
            app.ParachutinggradientEditField.ValueChangingFcn = createCallbackFcn(app, @NoiseEditFieldValueChanged, true);
            app.ParachutinggradientEditField.ValueChangedFcn = createCallbackFcn(app, @NoiseEditFieldValueChanged, true);
            app.ParachutinggradientEditField.Value = 1;
            app.RightPanel = uipanel(app.GridLayout);
            app.UIAxes = uiaxes(app.RightPanel);
            app.UIAxes.ButtonDownFcn = createCallbackFcn(app, @UIAxesButtonDown, true);
            app.ScalebarCheckBox = uicheckbox(app.RightPanel);
            app.ScalebarCheckBox.ValueChangedFcn = createCallbackFcn(app, @ScalebarCheckBoxValueChanged, true);
            app.ScalebarCheckBox.Text = 'Scale bar';
            app.ScalebarCheckBox.Value = true;
            app.ColorBarCheckBox = uicheckbox(app.RightPanel);
            app.ColorBarCheckBox.ValueChangedFcn = createCallbackFcn(app, @ColorBarCheckBoxValueChanged, true);
            app.ColorBarCheckBox.Text = 'Colour bar';
            app.maxSpinnerLabel = uilabel(app.RightPanel);
            app.maxSpinnerLabel.Text = 'max';
            app.ContrastMaxSpinner = uispinner(app.RightPanel);
            app.ContrastMaxSpinner.Step = 0.25;
            app.ContrastMaxSpinner.ValueChangedFcn = createCallbackFcn(app, @ContrastMaxSpinnerValueChanged, true);
            app.minSpinnerLabel = uilabel(app.RightPanel);
            app.minSpinnerLabel.Text = 'min';
            app.ContrastMinSpinner = uispinner(app.RightPanel);
            app.ContrastMinSpinner.Step = 0.25;
            app.ContrastMinSpinner.ValueChangedFcn = createCallbackFcn(app, @ContrastMinSpinnerValueChanged, true);
            app.ZScaleDropBox = uidropdown(app.RightPanel);
            app.ZScaleDropBox.Items = {'Auto', 'Manual'};
            app.ZScaleDropBox.ValueChangedFcn = createCallbackFcn(app, @ZScaleDropBoxValueChanged, true);
            app.ZScaleDropBox.Value = 'Auto';
            app.ZScaleLabel = uilabel(app.RightPanel);
            app.ZScaleLabel.Text = 'Z Scale';
            app.ColourMapDropDownLabel = uilabel(app.RightPanel);
            app.ColourMapDropDownLabel.Text = 'Colour Map';
            app.ColourMapDropDown = uidropdown(app.RightPanel);
            app.ColourMapDropDown.Items = {'AFM brown', 'AFM dark gold', 'AFM gold', 'AFM_fire', 'Orange hot', 'gray', 'hot', 'jet', 'parula'};
            app.ColourMapDropDown.ValueChangedFcn = createCallbackFcn(app, @ColourMapDropDownValueChanged, true);
            app.ColourMapDropDown.Value = 'AFM brown';
            app.ImageTypeDropDownLabel = uilabel(app.RightPanel);
            app.ImageTypeDropDownLabel.Text = 'Image Type';
            app.ImageTypeDropDown = uidropdown(app.RightPanel);
            app.ImageTypeDropDown.Items = {'2D surface', '3D surface', 'Coordinates'};
            app.ImageTypeDropDown.ValueChangedFcn = createCallbackFcn(app, @ImageTypeDropDownValueChanged, true);
            app.ImageTypeDropDown.Value = '2D surface';
            app.VideoPanel = uipanel(app.RightPanel);
            app.FrameSlider = uislider(app.VideoPanel);
            app.FrameSlider.Limits = [1 10];
            app.FrameSlider.MajorTicks = [1 10];
            app.FrameSlider.MajorTickLabels = {'1', '10'};
            app.FrameSlider.ValueChangingFcn = createCallbackFcn(app, @FrameSliderValueChanging, true);
            app.FrameSlider.Value = 1;
            app.Spinner = uispinner(app.VideoPanel);
            app.Spinner.Limits = [1 Inf];
            app.Spinner.ValueChangedFcn = createCallbackFcn(app, @SpinnerValueChanged, true);
            app.Spinner.Value = 1;
            app.fpsEditFieldLabel = uilabel(app.VideoPanel);
            app.fpsEditFieldLabel.Text = 'fps';
            app.fps = uieditfield(app.VideoPanel, 'numeric');
            app.fps.Limits = [0.1 50];
            app.fps.Value = 10;
            app.PlayImage = uiimage(app.VideoPanel);
            app.PlayImage.ImageClickedFcn = createCallbackFcn(app, @PlayImageClicked, true);
            app.PlayImage.ImageSource = fullfile(pathToMLAPP, 'play.png');
            app.PauseImage = uiimage(app.VideoPanel);
            app.PauseImage.ImageClickedFcn = createCallbackFcn(app, @PauseImageClicked, true);
            app.PauseImage.ImageSource = fullfile(pathToMLAPP, 'pause.png');
            app.EndImage = uiimage(app.VideoPanel);
            app.EndImage.ImageClickedFcn = createCallbackFcn(app, @EndImageClicked, true);
            app.EndImage.ImageSource = fullfile(pathToMLAPP, 'end.png');
            app.StartImage = uiimage(app.VideoPanel);
            app.StartImage.ImageClickedFcn = createCallbackFcn(app, @StartImageClicked, true);
            app.StartImage.ImageSource = fullfile(pathToMLAPP, 'start.png');
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Mat_SimAFM_v1_6

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end