classdef NanoLocz_v1_10 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        NanoLocz                        matlab.ui.Figure
        FileMenu                        matlab.ui.container.Menu
        OpenMenu                        matlab.ui.container.Menu
        FolderMenu                      matlab.ui.container.Menu
        OpenSessionMenu                 matlab.ui.container.Menu
        SaveMenu                        matlab.ui.container.Menu
        QuitMenu                        matlab.ui.container.Menu
        SettingsMenu                    matlab.ui.container.Menu
        LabelSettingsMenu               matlab.ui.container.Menu
        ExportSettingsMenu              matlab.ui.container.Menu
        VideoAlignSettingsMenu          matlab.ui.container.Menu
        ImageMenu                       matlab.ui.container.Menu
        ResizerotateMenu                matlab.ui.container.Menu
        ToolsMenu                       matlab.ui.container.Menu
        SimulationAFMMenu               matlab.ui.container.Menu
        MaskAnalysisMenu                matlab.ui.container.Menu
        PlotterMenu_2                   matlab.ui.container.Menu
        BatchConvertMenu                matlab.ui.container.Menu
        CenterPanel                     matlab.ui.container.Panel
        Export_settings                 matlab.ui.control.Image
        LAFMPanel                       matlab.ui.container.Panel
        LAFM_ZScaleDropBox              matlab.ui.control.DropDown
        LAFMTimescaleCheckBox           matlab.ui.control.CheckBox
        LAFMSpinner                     matlab.ui.control.Spinner
        MaxLabel_5                      matlab.ui.control.Label
        LAFMStartImage                  matlab.ui.control.Image
        LAFMEndImage                    matlab.ui.control.Image
        LAFMPlayImage                   matlab.ui.control.Image
        LAFMPauseImage                  matlab.ui.control.Image
        LAFMFrameSlider                 matlab.ui.control.Slider
        LAFMMaxSlider                   matlab.ui.control.Slider
        LAFMColorBarCheckBox            matlab.ui.control.CheckBox
        ColorLabel                      matlab.ui.control.Label
        LAFMView                        matlab.ui.control.DropDown
        TypeLabel                       matlab.ui.control.Label
        LAFM_LUT                        matlab.ui.control.DropDown
        LAFMColorMin                    matlab.ui.control.Spinner
        ColorminLabel                   matlab.ui.control.Label
        LAFMColorMax                    matlab.ui.control.Spinner
        ColormaxLabel                   matlab.ui.control.Label
        LAFMScalebarCheckBox            matlab.ui.control.CheckBox
        ViewSymmetrizedButton           matlab.ui.control.StateButton
        AutoCenterLAFM                  matlab.ui.control.CheckBox
        LAFM_sym                        matlab.ui.control.Spinner
        LAFMMinSlider                   matlab.ui.control.Slider
        MinLabel_5                      matlab.ui.control.Label
        ReferenceTools                  matlab.ui.container.Panel
        FindParticlesButton_2           matlab.ui.control.Button
        RotationalFreedom               matlab.ui.control.StateButton
        anglesEditField                 matlab.ui.control.EditField
        anglesLabel                     matlab.ui.control.Label
        SymmetryLabel                   matlab.ui.control.Label
        CenterReferenceButton           matlab.ui.control.Button
        symmetry                        matlab.ui.control.Spinner
        RotationalAvgButton             matlab.ui.control.Button
        AverageReferenceButton          matlab.ui.control.Button
        ImportSimulationButton          matlab.ui.control.Button
        RefListBox                      matlab.ui.control.ListBox
        ResettrimButton                 matlab.ui.control.Button
        RefCropButton                   matlab.ui.control.Button
        Plotter                         matlab.ui.control.StateButton
        LineProfilePanel                matlab.ui.container.Panel
        FWHMCheckBox                    matlab.ui.control.CheckBox
        AlignLineType                   matlab.ui.control.DropDown
        Live                            matlab.ui.control.StateButton
        AlignButton                     matlab.ui.control.Button
        UITable2                        matlab.ui.control.Table
        ReferenceButtonGroup            matlab.ui.container.ButtonGroup
        PopAx2                          matlab.ui.control.Button
        RightPlotList                   matlab.ui.control.DropDown
        VideoPanel                      matlab.ui.container.Panel
        VideoAlignSettings              matlab.ui.control.Image
        VideoAlignButton                matlab.ui.control.Button
        DeleterangeLabel                matlab.ui.control.Label
        RemoveframesEditField           matlab.ui.control.EditField
        BinRangeImage                   matlab.ui.control.Image
        BinImage                        matlab.ui.control.Image
        StartImage                      matlab.ui.control.Image
        EndImage                        matlab.ui.control.Image
        PauseImage                      matlab.ui.control.Image
        PlayImage                       matlab.ui.control.Image
        fps                             matlab.ui.control.NumericEditField
        fpsEditFieldLabel               matlab.ui.control.Label
        Spinner                         matlab.ui.control.Spinner
        FrameSlider                     matlab.ui.control.Slider
        ExportButton                    matlab.ui.control.Button
        ExportSelect                    matlab.ui.control.DropDown
        ExportFileFormat                matlab.ui.control.DropDown
        ColourmapDropDown               matlab.ui.control.DropDown
        PlotTypeDropDown                matlab.ui.control.DropDown
        OverlayDropDown                 matlab.ui.control.DropDown
        ScalebarCheckBox                matlab.ui.control.CheckBox
        TimescaleCheckBox               matlab.ui.control.CheckBox
        AspectRatioSwitch               matlab.ui.control.Switch
        LeftPlotList                    matlab.ui.control.DropDown
        ViewpixelsDropDown              matlab.ui.control.DropDown
        AcceptButtonImage               matlab.ui.control.Image
        ColorBarCheckBox                matlab.ui.control.CheckBox
        ProcessingImage                 matlab.ui.control.Image
        Forceto11Button                 matlab.ui.control.Button
        PopAx1                          matlab.ui.control.Button
        Image                           matlab.ui.control.Image
        ZPanel                          matlab.ui.container.Panel
        HoldButton                      matlab.ui.control.Button
        AutoButton                      matlab.ui.control.Button
        ContrastMinSpinner              matlab.ui.control.Spinner
        minSpinnerLabel                 matlab.ui.control.Label
        ContrastMaxSpinner              matlab.ui.control.Spinner
        maxSpinnerLabel                 matlab.ui.control.Label
        ZScaleDropBox                   matlab.ui.control.DropDown
        MontageButton                   matlab.ui.control.Button
        kymo                            matlab.ui.control.Button
        ZoomIn                          matlab.ui.control.StateButton
        CropButton                      matlab.ui.control.Button
        Point_roi_Button                matlab.ui.control.StateButton
        Line_roi_Button                 matlab.ui.control.StateButton
        Sq_roi_Button                   matlab.ui.control.StateButton
        Circ_roi_Button                 matlab.ui.control.StateButton
        ResetButton                     matlab.ui.control.Button
        UIAxes_2                        matlab.ui.control.UIAxes
        UIAxes                          matlab.ui.control.UIAxes
        LeftPanel                       matlab.ui.container.Panel
        Stacker                         matlab.ui.control.DropDown
        ErrorButton                     matlab.ui.control.StateButton
        ChannelDropDown                 matlab.ui.control.DropDown
        CancelButton                    matlab.ui.control.Button
        SaveType                        matlab.ui.control.DropDown
        FilesDropDown                   matlab.ui.control.DropDown
        SaveSession                     matlab.ui.control.Image
        OpenFolderButton                matlab.ui.control.Image
        View1st                         matlab.ui.control.NumericEditField
        View1stCheckBox                 matlab.ui.control.CheckBox
        AutoplayCheckBox                matlab.ui.control.CheckBox
        BidirectionaldataCheckBox       matlab.ui.control.CheckBox
        FramePartSwitch                 matlab.ui.control.Switch
        PreviewonoffButton              matlab.ui.control.StateButton
        TabGroup                        matlab.ui.container.TabGroup
        LevelTab                        matlab.ui.container.Tab
        FixShiftValue                   matlab.ui.control.NumericEditField
        FixShiftMethod                  matlab.ui.control.DropDown
        FixLineShift                    matlab.ui.control.CheckBox
        RestoreButton                   matlab.ui.control.Button
        ZeroallButton                   matlab.ui.control.Button
        Panel                           matlab.ui.container.Panel
        MaskwithzscaleCheckBox          matlab.ui.control.CheckBox
        ThresholdMethod                 matlab.ui.control.DropDown
        MethodLabel                     matlab.ui.control.Label
        AnalyzeMaskButton               matlab.ui.control.Button
        ViewMask                        matlab.ui.control.StateButton
        LogYHist                        matlab.ui.control.StateButton
        Hist_excludeOut                 matlab.ui.control.StateButton
        InvertMask                      matlab.ui.control.StateButton
        FitButton                       matlab.ui.control.Button
        ROIThresholdButton              matlab.ui.control.StateButton
        ThresholdContrastSwitch         matlab.ui.control.Switch
        SliderMax                       matlab.ui.control.Slider
        SliderMin                       matlab.ui.control.Slider
        upperlimitLabel                 matlab.ui.control.Label
        lowerlimitLabel                 matlab.ui.control.Label
        ClearROIButton                  matlab.ui.control.Button
        Hist_Axes                       matlab.ui.control.UIAxes
        LevelingPanel                   matlab.ui.container.Panel
        AutoflattenDropDown             matlab.ui.control.DropDown
        YSpinnerLabel_4                 matlab.ui.control.Label
        YSpinnerLabel_3                 matlab.ui.control.Label
        YSpinnerLabel_2                 matlab.ui.control.Label
        MeanLabel_2                     matlab.ui.control.Label
        MeanPlaneCheckBox               matlab.ui.control.CheckBox
        MedLabel                        matlab.ui.control.Label
        MedianCheckBox                  matlab.ui.control.CheckBox
        YPlaneSpinner                   matlab.ui.control.Spinner
        YSpinnerLabel                   matlab.ui.control.Label
        XPlaneSpinner                   matlab.ui.control.Spinner
        XSpinnerLabel                   matlab.ui.control.Label
        YLineSpinner                    matlab.ui.control.Spinner
        XLineSpinner                    matlab.ui.control.Spinner
        LineLabel                       matlab.ui.control.Label
        PlaneLabel                      matlab.ui.control.Label
        FilteringPanel                  matlab.ui.container.Panel
        ImgFilterLabel_3                matlab.ui.control.EditField
        ImgFilterStrength_3             matlab.ui.control.Spinner
        ImgFilterType                   matlab.ui.control.DropDown
        SubtractmodeCheckBox            matlab.ui.control.CheckBox
        ImgFilterLabel_2                matlab.ui.control.EditField
        ImgFilterLabel                  matlab.ui.control.EditField
        ImgFilterStrength_2             matlab.ui.control.Spinner
        FilterLabel                     matlab.ui.control.Label
        ImgFilterStrength               matlab.ui.control.Spinner
        DetectTab                       matlab.ui.container.Tab
        LinelengthLabel                 matlab.ui.control.Label
        MaxradiusEditField              matlab.ui.control.NumericEditField
        FWHMreferenceDropDown           matlab.ui.control.DropDown
        FWHMreferenceDropDownLabel      matlab.ui.control.Label
        OverlaylinesCheckBox            matlab.ui.control.CheckBox
        LineDiag2                       matlab.ui.control.StateButton
        LineDiag1                       matlab.ui.control.StateButton
        LineVert                        matlab.ui.control.StateButton
        LineHor                         matlab.ui.control.StateButton
        AutoProfileButton               matlab.ui.control.StateButton
        Sq_roi_Button_2                 matlab.ui.control.StateButton
        MinseparationLabel              matlab.ui.control.Label
        MinheightLabel                  matlab.ui.control.Label
        Peaks_minSep                    matlab.ui.control.Spinner
        Peaks_minHeight                 matlab.ui.control.Spinner
        PreviewpeaksLabel               matlab.ui.control.Label
        PreviewPeaks                    matlab.ui.control.CheckBox
        FilterdetectionsLabel           matlab.ui.control.Label
        ExcludeedgesLabel               matlab.ui.control.Label
        ExcludeEdgeCheckBox             matlab.ui.control.CheckBox
        FilterxcorimageLabel            matlab.ui.control.Label
        SettingsLabel_Detect_2          matlab.ui.control.Label
        BoxSizeEditField                matlab.ui.control.NumericEditField
        BoxSizeEditFieldLabel           matlab.ui.control.Label
        PeaksRegionsSwitch              matlab.ui.control.Switch
        SelectMethodLabel_2             matlab.ui.control.Label
        SettingsLabel_Detect            matlab.ui.control.Label
        FindParticlesButton             matlab.ui.control.Button
        FilterImgEditField_Detect       matlab.ui.control.NumericEditField
        FilterImageLabel                matlab.ui.control.Label
        TrackDetectionsPanel            matlab.ui.container.Panel
        MaxstepEditField                matlab.ui.control.NumericEditField
        MaxstepEditFieldLabel           matlab.ui.control.Label
        MaxmissingEditField             matlab.ui.control.NumericEditField
        MaxmissingEditFieldLabel        matlab.ui.control.Label
        RunTrackingButton               matlab.ui.control.Button
        Overlay                         matlab.ui.container.Panel
        ValuesLabel                     matlab.ui.control.Label
        OverlayValues                   matlab.ui.control.DropDown
        TrackLengthEditField            matlab.ui.control.NumericEditField
        TrackLengthEditFieldLabel       matlab.ui.control.Label
        BoxesCheckBox                   matlab.ui.control.CheckBox
        MarkersCheckBox                 matlab.ui.control.CheckBox
        Filter_detections_panel         matlab.ui.container.Panel
        Height_corr_switch              matlab.ui.control.Switch
        CCRSliderMax                    matlab.ui.control.Slider
        CCRSliderMin                    matlab.ui.control.Slider
        upperlimitLabel_2               matlab.ui.control.Label
        lowerlimitLabel_2               matlab.ui.control.Label
        CorrelationCheckBox             matlab.ui.control.CheckBox
        HeightCheckBox                  matlab.ui.control.CheckBox
        Min_CCR_Edit                    matlab.ui.control.NumericEditField
        Max_CCR_Edit                    matlab.ui.control.NumericEditField
        MaxLabel_2                      matlab.ui.control.Label
        MinLabel_2                      matlab.ui.control.Label
        MinLabel                        matlab.ui.control.Label
        MaxLabel                        matlab.ui.control.Label
        Min_h_Edit                      matlab.ui.control.NumericEditField
        Max_h_Edit                      matlab.ui.control.NumericEditField
        HeightCCRHist                   matlab.ui.control.UIAxes
        FilterCCREditField_Detect       matlab.ui.control.NumericEditField
        FineAlignTab                    matlab.ui.container.Tab
        SettingsLabel_Detect_3          matlab.ui.control.Label
        TrackDetectionsPanel_2          matlab.ui.container.Panel
        MaxstepEditField_2              matlab.ui.control.NumericEditField
        MaxstepEditField_2Label         matlab.ui.control.Label
        MaxmissingEditField_2           matlab.ui.control.NumericEditField
        MaxmissingEditField_2Label      matlab.ui.control.Label
        RunTrackingButton_2             matlab.ui.control.Button
        SelectTrackButton               matlab.ui.control.StateButton
        TrackList                       matlab.ui.control.ListBox
        ApplyButton                     matlab.ui.control.Button
        RoiareaEditField                matlab.ui.control.NumericEditField
        RoiareaEditFieldLabel           matlab.ui.control.Label
        Panel_3                         matlab.ui.container.Panel
        ThresholdwithminheightCheckBox  matlab.ui.control.CheckBox
        RunFineAlignButton              matlab.ui.control.Button
        AutoupdatereferenceimageCheckBox  matlab.ui.control.CheckBox
        MaxdriftEditField_2             matlab.ui.control.NumericEditField
        MaxdriftEditField_2Label        matlab.ui.control.Label
        Tran_IterationsEditField        matlab.ui.control.NumericEditField
        IterationsEditField_3Label      matlab.ui.control.Label
        TranslationalLabel              matlab.ui.control.Label
        Rot_IterationsEditField         matlab.ui.control.NumericEditField
        IterationsEditField_2Label      matlab.ui.control.Label
        RotationalLabel                 matlab.ui.control.Label
        searchangleEditField            matlab.ui.control.NumericEditField
        searchangleEditFieldLabel       matlab.ui.control.Label
        LocalizeTab                     matlab.ui.container.Tab
        BoostsubpixCheckBox             matlab.ui.control.CheckBox
        SlidewindowEditField            matlab.ui.control.NumericEditField
        SlidewindowEditFieldLabel       matlab.ui.control.Label
        ParticlespermapEditField        matlab.ui.control.NumericEditField
        ParticlespermapEditFieldLabel   matlab.ui.control.Label
        RenderLAFMMovieButton           matlab.ui.control.Button
        LAFM_exp                        matlab.ui.control.NumericEditField
        imageexpansionLabel             matlab.ui.control.Label
        LAFM_gus                        matlab.ui.control.NumericEditField
        RenderpointsLabel               matlab.ui.control.Label
        RenderLAFMButton                matlab.ui.control.Button
        Localize_ThreshPanel            matlab.ui.container.Panel
        LoczProminenceSpinner           matlab.ui.control.Spinner
        ProminenceSpinnerLabel          matlab.ui.control.Label
        ThresholdLabel_3                matlab.ui.control.Label
        LoczHeightSpinner               matlab.ui.control.Spinner
        ThresholdLabel_2                matlab.ui.control.Label
        LoczMinSep                      matlab.ui.control.Spinner
        ThresholdLabel                  matlab.ui.control.Label
        LoczImagepeaksCheckBox          matlab.ui.control.CheckBox
        FindallpeaksButton              matlab.ui.control.Button
        Localize_FilterPanel            matlab.ui.container.Panel
        LoczShowfilterCheckBox          matlab.ui.control.CheckBox
        LocFilterLabel                  matlab.ui.control.Label
        LoczHighpass                    matlab.ui.control.Spinner
        LoczLowpass                     matlab.ui.control.Spinner
        LoczLowpassDropDown             matlab.ui.control.DropDown
        LowpassDropDownLabel            matlab.ui.control.Label
        LoczHighpassDropDown            matlab.ui.control.DropDown
        HighpassDropDownLabel           matlab.ui.control.Label
        FolderDown                      matlab.ui.control.Image
        FolderUp                        matlab.ui.control.Image
        UITable                         matlab.ui.control.Table
        FilesListBox                    matlab.ui.control.ListBox
        loadbar                         matlab.ui.control.UIAxes
    end


    %  DESCRIPTION:   NanoLocz: Localization Atomic Force Microscopy Analysis Platform
    %  COPYRIGHT:     George Heath, 2023
    %  LICENSE:       GPLv3
    %  AUTHOR:        George Heath, University of Leeds, g.r.heath@leeds.ac.uk
    %  Contributors:  Tabitha Storer, Emily Micklethwaite

    %  If using the Localization Atomic Force Microscopy method please cite:
    %  Heath, G.R., Kots, E., Robertson, J.L. et al. Localization atomic force microscopy.
    %  Nature 594, 385–390 (2021). https://doi.org/10.1038/s41586-021-03551-x

    properties (Access = public)
        OpenFolder
        ImageName
        ImageTarget
        ImagePreview
        Part      %Particle locations: 1=x, 2=y, 3=z, 4=ccr, 5=frame, 6 = id; 7=particle track, 8 = particle angle
        ImageInfo
        cmap
        filter_full
        filter_already
        ref
        rect
        img_locs
        LAFM
        roi
        patches
        PointROI
        LineROI
        SquareROI
        CircleROI
        stop
        Settings
        Mask
        stored
        RadialDist
        PopupApp
        ThumbnailApp
        ROIs
        RefNo
    end

    methods (Access = public)

        function reset_defaults(app)
            app.img_locs = []; app.ref = []; app.rect = []; app.ImageInfo=[]; app.Part=[];  app.LAFM = []; app.LineROI=[];app.SquareROI=[]; app.PointROI=[];
            app.LineROI = [];  app.SquareROI = []; app.Mask = []; app.RefListBox.Items = {};
            app.TrackList.Visible =0;
            app.RightPlotList.Items = {'Off'};
            app.RightPlotList.Value = 'Off';
            PauseImageClicked(app)
            app.filter_already =0;
            app.FramePartSwitch.Value = 'Frames';
            app.OverlayDropDown.Value = 'Mask';
            cla(app.UIAxes);   cla(app.UIAxes_2); cla(app.HeightCCRHist); cla(app.loadbar);
            app.LoczImagepeaksCheckBox.Value =0;
            app.Max_CCR_Edit.Value =1;
            app.Min_CCR_Edit.Value =0.5;
            app.PreviewPeaks.Value =0;
            app.LAFMPanel.Visible = 0;
            app.TrackLengthEditField.Value = 0;
            app.OverlayValues.Value = 'None';
            app.SelectTrackButton.Value=0;
            app.LAFM_ZScaleDropBox.Value = 'Exc outliers';
            TurnOffButtons(app,'all')
        end

        function updateFolder(app)
            %Show file from the chosen directory in the listbox
            if app.OpenFolder ~= 0
                imageFiles = dir([app.OpenFolder]);
                imageFiles = imageFiles(~ismember({imageFiles(:).name},{'.','..','.DS_Store'}));
                app.FilesListBox.Items = {imageFiles.name};
            end
        end

        function updateVideoTools(app)

            try
            if app.View1stCheckBox.Value ==1 && app.View1st.Value < app.ImageInfo.n
                loadn = app.View1st.Value;
            else
                loadn = app.ImageInfo.n;
            end

            ty = app.FramePartSwitch.Value;
            switch ty
                case 'Frames'
                    if loadn <2
                        app.TimescaleCheckBox.Visible = 0;
                        app.TimescaleCheckBox.Value = 0;
                        app.VideoPanel.Visible = 0;
                    else
                        app.Spinner.Limits = [1 loadn];
                        app.FrameSlider.Limits = [1 loadn];
                        if app.Spinner.Limits(2)>=10
                            inter = (app.Spinner.Limits(2)/10);
                        else
                            inter = 1;
                        end
                        app.FrameSlider.MajorTicks = round(1:inter:loadn);
                        app.FrameSlider.MajorTickLabels = string(round(1:inter:loadn));
                        app.VideoPanel.Visible = 1;
                        app.TimescaleCheckBox.Visible = 1;
                    end

                case 'Particles'
                    app.TimescaleCheckBox.Visible = 0;
                    app.TimescaleCheckBox.Value = 0;
                    if    numel(app.Part.Image(1,1,app.Part.IncludeImages ))<2
                        app.VideoPanel.Visible = 0;

                    else
                        app.Spinner.Limits = [1 numel(app.Part.Image(1,1,app.Part.IncludeImages ))];
                        app.FrameSlider.Limits = app.Spinner.Limits;
                        if app.Spinner.Limits(2)>=10
                            inter = (app.Spinner.Limits(2)/10);
                        else
                            inter = 1;
                        end
                        app.FrameSlider.MajorTicks = round(1:inter:app.Spinner.Limits(2));
                        app.FrameSlider.MajorTickLabels = string(round(1:inter:app.Spinner.Limits(2)));
                        app.VideoPanel.Visible = 1;
                    end
            end
            catch 
            end
        end


        function filter_Partlocs(app)

            app.Part.IncludeImages = ones(numel(app.Part.Locs(:,3)),1);
            if app.CorrelationCheckBox.Value==1
                app.Part.IncludeImages = app.Part.IncludeImages.*(app.Part.Locs(:,4)>=app.Min_CCR_Edit.Value);
                app.Part.IncludeImages = app.Part.IncludeImages.*(app.Part.Locs(:,4)<=app.Max_CCR_Edit.Value);
            end

            if app.HeightCheckBox.Value==1
                app.Part.IncludeImages = app.Part.IncludeImages.*(app.Part.Locs(:,3)>=app.Min_h_Edit.Value);
                app.Part.IncludeImages = app.Part.IncludeImages.*(app.Part.Locs(:,3)<=app.Max_h_Edit.Value);
            end
try
            if app.SelectTrackButton.Value==1
                Track = str2double(app.TrackList.Value);
                app.Part.IncludeImages = app.Part.IncludeImages.*(app.Part.Locs(:,7)==Track);
            end
catch
end
            app.Part.IncludeImages = app.Part.IncludeImages >0;
            app.Part.Locs(:,6) = NaN;
            app.Part.Locs(app.Part.IncludeImages,6) =  1:numel(app.Part.Locs(app.Part.IncludeImages,3));

        end


        %Update Plot 1
        function updatePlot(app,varargin)
            switch  app.FramePartSwitch.Value
                case 'Frames'
                    switch  app.LeftPlotList.Value
                        case 'Target'
                            img = app.ImageTarget;
                            app.AcceptButtonImage.Visible = 0;
                            app.PreviewonoffButton.Value = 0;
                        case 'Preview'
                            img = app.ImagePreview;
                            app.AcceptButtonImage.Visible = 1;
                            app.PreviewonoffButton.Value = 1;
                    end
                case 'Particles'
                    switch  app.LeftPlotList.Value
                        case 'Target'
                            img = app.Part.Image(:,:,app.Part.IncludeImages);
                            app.AcceptButtonImage.Visible = 0;
                            app.PreviewonoffButton.Value = 0;
                        case 'Preview'
                            img = app.ImagePreview(:,:,app.Part.IncludeImages);
                            app.AcceptButtonImage.Visible = 1;
                            app.PreviewonoffButton.Value = 1;
                    end
                     
            end
            previewOnOff(app)

            switch varargin{1}
                case 'popout'
                    frame = round(app.Spinner.Value);
                    figure()
                    ax = gca;
                case 'export frame'
                    frame = round(app.Spinner.Value);
                    figure()
                    ax = gca;
                case 'export video'
                    frame = 1:numel(img(1,1,:));
                    figure(1)
                    ax = gca;

                case 'normal'
                    ax = app.UIAxes;
                    frame = round(app.Spinner.Value);
                    cla(ax);
            end


            for iii = frame
                if numel(frame) < 2
                    iii=1;
                end
                if frame(iii) <= app.Spinner.Limits(2)
                    prev = img(:,:,frame(iii));
                else
                    prev = img(:,:,1);
                end

                if numel(app.ImageInfo.ScanSize)>1
                    updateTable(app)
                end


                if app.LoczShowfilterCheckBox.Value==1

                    if app.BoostsubpixCheckBox.Value
                        prev = imresize(prev,3,'bicubic');
                        ex=3;
                    else
                        ex=1;
                    end
                    if strcmp(app.LoczHighpassDropDown.Value,'-Average')
                        d_av = mean(img,3);
                        prev = filter_movie(prev, app.LoczLowpassDropDown.Value, app.LoczLowpass.Value*ex, app.LoczHighpassDropDown.Value,  app.LoczHighpass.Value,d_av);
                    else
                        prev = filter_movie(prev, app.LoczLowpassDropDown.Value, app.LoczLowpass.Value*ex, app.LoczHighpassDropDown.Value,  app.LoczHighpass.Value);
                    end

                    if app.BoostsubpixCheckBox.Value
                        prev = imresize(prev,1/3,'bicubic');
                    end
                end


                switch app.ZScaleDropBox.Value
                    case 'Max Min'
                        minC = round(min(prev(:)),3,"significant");
                        maxC = round(max(prev(:)),3,"significant");
                        if minC<maxC
                            app.ContrastMinSpinner.Value = double(minC);
                            app.ContrastMaxSpinner.Value = double(maxC);
                        else
                            app.ContrastMinSpinner.Value = 0;
                            app.ContrastMaxSpinner.Value = 1;
                        end

                    case 'Exc outliers'
                        B = rmoutliers(prev(:),"mean");
                        minC = round(min(B(:)),3,"significant");
                        maxC = round(max(B(:)),3,"significant");
                        if minC<maxC
                            app.ContrastMinSpinner.Value = double(minC);
                            app.ContrastMaxSpinner.Value = double(maxC);
                        else
                            app.ContrastMinSpinner.Value = 0;
                            app.ContrastMaxSpinner.Value = 1;
                        end
                    case 'Histogram'
                        app.ContrastMinSpinner.Value = app.SliderMin.Value;
                        app.ContrastMaxSpinner.Value = app.SliderMax.Value;
                    case 'Manual'
                end


                if app.ContrastMinSpinner.Value<app.ContrastMaxSpinner.Value
                    zcont = [app.ContrastMinSpinner.Value app.ContrastMaxSpinner.Value];
                else
                    zcont =[0 1];
                end

                if strcmp(app.PlotTypeDropDown.Value,'2D surface')
                    hold(ax,'on')
                    im = imagesc(ax,single(prev),zcont);
                    view(ax,2)
                    if strcmp(app.ViewpixelsDropDown.Value,'Original')
                        im.Interpolation= 'nearest';
                    else
                        im.Interpolation= 'bilinear';
                    end


                elseif strcmp(app.PlotTypeDropDown.Value,'3D surface')
                    set(ax,'visible','on')
                    surf(ax,prev)
                    zlim(ax,zcont)
                    shading(ax,'interp')
                    axis(ax,'normal');

                elseif strcmp(app.PlotTypeDropDown.Value,'Coordinates')
                    set(ax,'visible','on')
                    sd=size(prev);
                    [x, y]=find(prev(1:sd(1),1:sd(2)));
                    for i = 1:numel(x)
                        z(i) = prev(y(i),x(i));
                    end
                    scatter3(ax,x,y,z,10,z,'filled')
                    axis(ax,'normal');
                end

                %annotations
                if app.ScalebarCheckBox.Value ==1
                    [r, cs, ~] = size(img(:,:,1));

                    if numel(app.ImageInfo.PixelPerNm)>1
                        fr = frame(iii);
                        switch  app.FramePartSwitch.Value
                            case 'Particles'
                                fr = app.Part.Locs((app.Part.Locs(:,6)==fr),5);
                        end
                    else
                        fr=1;
                    end

                    sb = cs./app.ImageInfo.PixelPerNm(fr);
                    try
                        scale_bar = round(sb/5,1,"significant");
                        scale_bar_pix = scale_bar.*app.ImageInfo.PixelPerNm(fr);

                        switch app.Settings.label.ScaleBar.position
                            case 'Top Right'
                                xpos = [round(cs.*0.95)-scale_bar_pix, round(cs.*0.95)];
                                ypos = round([r*.05, r*.05]);
                            case 'Top Left'
                                xpos = [round(cs.*0.05), round(cs.*0.05)+scale_bar_pix];
                                ypos = round([r*.05, r*.05]);
                            case 'Bottom Left'
                                xpos = [round(cs.*0.05), round(cs.*0.05)+scale_bar_pix];
                                ypos = round([r*.95, r*.95]);
                            case 'Bottom Right'
                                xpos = [round(cs.*0.95)-scale_bar_pix, round(cs.*0.95)];
                                ypos = round([r*.95, r*.95]);
                        end

                        line(ax,xpos,ypos,'LineWidth',2,'Color',app.Settings.label.ScaleBar.color);
                        if scale_bar >1000

                            text(ax, xpos(1)+scale_bar_pix/2, round(ypos(1) - r*.03), [num2str(scale_bar/1000) char(181),'m'],...
                                'FontWeight','bold','FontSize', 15,'Color', app.Settings.label.ScaleBar.color,'HorizontalAlignment','center');
                        else
                            text(ax, xpos(1)+scale_bar_pix/2, round(ypos(1) - r*.03), [num2str(scale_bar) 'nm'],...
                                'FontWeight','bold','FontSize', 15,'Color', app.Settings.label.ScaleBar.color,'HorizontalAlignment','center');
                        end
                    catch
                    end
                end


                if app.TimescaleCheckBox.Value==1
                    [r, cs, ~] = size(img(:,:,1));
                    try
                        % try
                            time = app.ImageInfo.time(frame(iii));
                        % catch
                        % 
                        %     time = round((frame(iii)-1)/app.ImageInfo.ScanSpeed,4,"significant");
                        % end
                        switch app.Settings.label.TimeStamp.position
                            case 'Top Right'
                                xpos = round(cs*.90);
                                ypos = round(r*.05);
                            case 'Top Left'
                                xpos = round(cs*.05);
                                ypos = round(r*.05);
                            case 'Bottom Left'
                                xpos = round(cs*.05);
                                ypos = round(r*.95);
                            case 'Bottom Right'
                                xpos = round(cs*.90);
                                ypos = round(r*.95);
                        end
                        text(ax,xpos(1),ypos(1),[num2str(time) 's'],'FontWeight','bold','FontSize', 17,'Color',app.Settings.label.TimeStamp.color);
                    catch
                    end
                end

                if app.PreviewPeaks.Value ==1
                    hold(ax,'on')
                    if app.FilterImgEditField_Detect.Value >0
                        prev = imgaussfilt(prev,app.FilterImgEditField_Detect.Value);
                    end
                    locs = peaks2D(prev, app.Min_h_Edit.Value, app.Peaks_minSep.Value);
                    s = any(locs);
                    if s(1)==0
                    else
                        plot(ax,locs(:,1),locs(:,2), 'cx','MarkerSize',7,'LineWidth',1)
                    end
                    hold(ax,'off')
                end

                switch app.OverlayDropDown.Value
                    case 'None'
                        hold(ax,'off')
                        app.ViewMask.Value = 0;
                        %ViewMaskValueChanged(app)
                    case 'Detections'
                        app.ViewMask.Value = 0;
                        %ViewMaskValueChanged(app)
                        try
                            hold(ax,'on')

                            pos = app.Part.Locs(:,5) == frame(iii);
                            pos = (pos.*app.Part.IncludeImages)>0;

                            if app.MarkersCheckBox.Value == 1
                                % if app.MultiReferenceCheckBox.Value
                                %     for i = 1:app.RefNo
                                %         colourmapReferences = hsv(app.RefNo);
                                %         colour = colourmapReferences(i, :);
                                %         plot(ax,app.Part.Locs(pos,1),app.Part.Locs(pos,2),...
                                %             'go','MarkerSize',8,'LineWidth',2,...
                                %             'MarkerFaceColor',colour)
                                %     end
                                % else
                                    plot(ax,app.Part.Locs(pos,1),app.Part.Locs(pos,2),...
                                        'go','MarkerSize',8,'LineWidth',2,...
                                        'MarkerFaceColor','r')
                                % end
                            end


                            if app.BoxesCheckBox.Value == 1
                                % if app.MultiReferenceCheckBox.Value
                                %     for i = 1:app.RefNo
                                %         % Get the position array for the current reference
                                %         positionArray = app.ref.positions{i};
                                % 
                                %         % Extract the frame width and height from the position array
                                %         current_frame_width = positionArray(3);
                                %         current_frame_height = positionArray(4);
                                % 
                                %         % Calculate the vertices for the current reference
                                %         vertices = [current_frame_width/2, current_frame_height/2;    % top right
                                %             -current_frame_width/2, current_frame_height/2;   % top left
                                %             -current_frame_width/2, -current_frame_height/2;  % bottom left
                                %             current_frame_width/2, -current_frame_height/2];  % bottom right
                                % 
                                %         % Store the vertices for the current reference in the cell array
                                %         verticesCellArray{i} = vertices;
                                %     end
                                % 
                                %     for pointIdx = 1:app.RefNo
                                %         x = app.Part.Locs(pointIdx, 1);
                                %         y = app.Part.Locs(pointIdx, 2);
                                % 
                                %         % Find the reference index with the maximum correlation for each point
                                %         [~, maxCorrRefs] = max(app.Part.Locs, [], 2);
                                %         % Get the frame width and height for the current reference
                                %         refIndex = maxCorrRefs(pointIdx);
                                % 
                                %         angle_rad = app.Part.Locs(pointIdx, 8) * pi / 180;
                                % 
                                %         vertices = verticesCellArray{refIndex};
                                % 
                                %         R = [cos(angle_rad), -sin(angle_rad); sin(angle_rad), cos(angle_rad)];
                                %         vertices_rot = (R * vertices')';
                                %         vertices_trans = vertices_rot + [x, y];
                                % 
                                %         colourIndex = refIndex;
                                %         colourmapReferences = hsv(app.RefNo);
                                %         colour = colourmapReferences(colourIndex, :);
                                % 
                                %         % Has an issue when trying to change the limits of the correlation
                                %         % Also need to find a way the user can identify which reference is which colour
                                % 
                                %         patch(ax, 'vertices', vertices_trans, 'faces', [1, 2, 3, 4], 'EdgeColor', colour, 'FaceColor', 'w', 'FaceAlpha', 0.15);
                                %     end
                               % else
                                    x = app.Part.Locs(pos,1);
                                    y = app.Part.Locs(pos,2);
                                    frame_width = app.ref.position(3);
                                    frame_height = app.ref.position(4);
                                    angle_rad = app.Part.Locs(pos,8) * pi / 180;
                                    vertices = [frame_width/2, frame_height/2;  % top right
                                        -frame_width/2, frame_height/2; % top left
                                        -frame_width/2, -frame_height/2; % bottom left
                                        frame_width/2, -frame_height/2]; % bottom right

                                    for i = 1:numel(x)
                                        cent = [x(i), y(i)];
                                        R = [cos(angle_rad(i)), -sin(angle_rad(i)); sin(angle_rad(i)), cos(angle_rad(i))];
                                        vertices_rot = (R * vertices')';
                                        vertices_trans = vertices_rot + cent;
                                        patch(ax,'vertices',vertices_trans,'faces',[1,2,3,4],'EdgeColor','g','FaceColor','w','FaceAlpha',0.15 )
                                    end
                              %  end
                            end


                            switch app.OverlayValues.Value
                                case 'None'
                                case 'Height'
                                    p = round(app.Part.Locs(pos,3),1);
                                case 'CCR'
                                    p = round(app.Part.Locs(pos,4),2);
                                case 'Angle'
                                    p = round(app.Part.Locs(pos,8),1);
                                case 'Track Number'
                                    p = app.Part.Locs(pos,7);
                                case 'FWHM'
                                    try
                                        p = round(app.Part.Locs(pos,11),1);
                                    catch
                                    end
                            end

                            switch app.OverlayValues.Value
                                case 'None'
                                otherwise
                                    x = app.Part.Locs(pos,1);
                                    y = app.Part.Locs(pos,2);
                                    for i = 1:numel(x)
                                        text(ax,x(i)+4,y(i),num2str(p(i)),'Color','r','FontSize',12)
                                    end
                            end

                            if app.TrackLengthEditField.Value>0 && numel(app.Part.Locs(1,:))>6

                                x = app.Part.Locs(pos,1);
                                p = app.Part.Locs(pos,7);

                                if frame(iii)<app.TrackLengthEditField.Value+1
                                    for j = 1:numel(x)
                                        pos2 = app.Part.Locs(:,7)==p(j);
                                        xtemp = app.Part.Locs(pos2,1);
                                        ytemp = app.Part.Locs(pos2,2);
                                        pos3 = app.Part.Locs(pos2,5)<=frame(iii);
                                        plot(ax,xtemp(pos3),ytemp(pos3),'o-')
                                    end
                                else
                                    for j = 1:numel(x)
                                        pos2 = app.Part.Locs(:,7)==p(j);
                                        xtemp = app.Part.Locs(pos2,1);
                                        ytemp = app.Part.Locs(pos2,2);
                                        pos3 = app.Part.Locs(pos2,5)<=frame(iii) & app.Part.Locs(pos2,5)>=frame(iii)-app.TrackLengthEditField.Value;
                                        plot(ax,xtemp(pos3),ytemp(pos3),'o-')
                                    end
                                end
                            end
                                hold(ax,'off')
                        catch
                        end
                    case 'Line Profile'
                        app.ViewMask.Value = 0;
                        %ViewMaskValueChanged(app)
                        C = hsv(8);
                        C = [C ;   jet(8)];
                        C = [C ;   parula(8)];
                        hold(ax,'on')
                        n = numel(app.LineROI);
                        if n>500
                            n=500;
                        end
                        for jj=1:n
                            xpos = app.LineROI(jj).Position(:,1);
                            ypos = app.LineROI(jj).Position(:,2);
                            pos3 = (xpos>0) & (xpos<size(prev,2)) & (ypos>0) & (ypos<size(prev,1));
                            try
                               app.LineROI(jj).plt = plot(ax,xpos(pos3),ypos(pos3),'LineWidth',1,'Color',C(jj,:));
                            catch
                              app.LineROI(jj).plt =  plot(ax,xpos(pos3),ypos(pos3),'LineWidth',1);
                            end

                        end
                        hold(ax,'off')

                    case 'Mask'
                        try
                        app.ViewMask.Value = 1;
                        %ViewMaskValueChanged(app)

                        high_thresh = app.SliderMax.Value;%/100.*(max_all-min_all)+min_all;
                        low_thresh = app.SliderMin.Value;%/100.*(max_all-min_all)+min_all;
                        prev = app.ImageTarget(:,:,frame(iii));
                        try
                            imgt = app.Mask.Mask(:,:,frame(iii));
                        catch
                            imgt = thresholder(prev,app.ThresholdMethod.Value,[low_thresh,high_thresh],app.InvertMask.Value);
                            if isfield(app.Mask,'ROI')
                                s = any(app.Mask.ROI);
                            else
                                s(1)=0;
                            end
                            if app.ROIThresholdButton.Value == 1 && s(1)==1
                                imgt = thresholder(app.Mask.ROI.*imgt,'selection',[low_thresh,high_thresh],app.InvertMask.Value);
                            end
                        end
                        hold(ax,'on')
                        switch app.Settings.MaskColor
                            case 'green'
                                overlay_c = cat(3, zeros(size(prev)), ones(size(prev)), zeros(size(prev)));
                            case 'red'
                                overlay_c = cat(3, ones(size(prev)), zeros(size(prev)), zeros(size(prev)));
                            case 'blue'
                                overlay_c = cat(3, zeros(size(prev)), zeros(size(prev)), ones(size(prev)));
                            case 'black'
                                overlay_c = cat(3, zeros(size(prev)), zeros(size(prev)), zeros(size(prev)));
                            case 'white'
                                overlay_c = cat(3, ones(size(prev)), ones(size(prev)), ones(size(prev)));
                        end
                        over = imagesc(ax, overlay_c,zcont);
                        hold(ax,'off')
                        set(over, 'AlphaData', app.Settings.MaskAlpha*isnan(imgt))
                        catch
                        end
                    case 'Localizations'
                        app.ViewMask.Value = 0;
                        %ViewMaskValueChanged(app)
                        hold(ax,'on')

                        if app.LoczImagepeaksCheckBox.Value==1
                            if frame(iii) <= app.Spinner.Limits(2)
                                prev = img(:,:,frame(iii));
                            else
                                prev = img(:,:,1);
                            end

                            if app.BoostsubpixCheckBox.Value
                            prev = imresize(prev,3,'bicubic');
                            ex=3;
                            else
                                ex=1;
                            end
                            if strcmp(app.LoczHighpassDropDown.Value,'-Average')
                                d_av = mean(img,3);
                                prev = filter_movie(prev, app.LoczLowpassDropDown.Value, app.LoczLowpass.Value*ex, app.LoczHighpassDropDown.Value,  app.LoczHighpass.Value,d_av);
                            else
                                prev = filter_movie(prev, app.LoczLowpassDropDown.Value, app.LoczLowpass.Value*ex, app.LoczHighpassDropDown.Value,  app.LoczHighpass.Value);
                            end

                            locs = peaks2D(prev,app.LoczHeightSpinner.Value,app.LoczMinSep.Value,app.LoczProminenceSpinner.Value);
                            s = any(locs);

                            if app.BoostsubpixCheckBox.Value
                                prev = imresize(prev,1/3,'bicubic');
                            end

                            if s(1)==0
                            else
                                plot(ax,locs(:,1)/ex,locs(:,2)/ex, 'cx','MarkerSize',7,'LineWidth',1)
                            end
                            hold(ax,'off')
                        end

                end


                switch app.ColourmapDropDown.Value
                    case 'AFM brown'
                        colormap(ax,app.cmap.AFM_brown)
                    case 'AFM dark gold'
                        colormap(ax,app.cmap.AFM_Dark_Gold)
                    case 'AFM gold'
                        colormap(ax,app.cmap.AFM_Gold)
                    case 'Orange hot'
                        colormap(ax,app.cmap.AFM_orange)
                    case'fire'
                        colormap(ax,app.cmap.AFM_fire)
                    case'Rainbow'
                        colormap(ax,app.cmap.Rainbow)
                    otherwise
                        colormap(ax,app.ColourmapDropDown.Value)
                end

                switch  app.LeftPlotList.Value
                    case 'Preview'
                        [r, cs, ~] = size(img(:,:,1));
                        xpos(1) = round(cs*.05);
                        ypos(1) = round(r*.95);
                        text(ax,xpos(1),ypos(1),'PREVIEW','FontWeight','bold','FontSize', 24,'Color',[0.5 0.5 0.5]);
                end

                set(ax,'visible','off','YDir','reverse')
                switch varargin{1}
                    case {'export video','export frame'}
                        hold(ax,'on')
                        if iii == 1
                            namesplit = split(app.ImageName,'.');
                            namesuggest = join(namesplit,"_");
                            filetype = app.ExportFileFormat.Value;
                            [outputFileName,folder] = uiputfile([namesuggest{1},filetype]);
                            fullFileName = fullfile(folder, outputFileName);
                            set(figure(1),'color',app.Settings.ExportSettings.color)
                            axis(gca,'equal');
                            if app.ColorBarCheckBox.Value==1
                                if strcmp(app.Settings.ExportSettings.color,'white')
                                    colorbar('Color','k','Box','off','TickDirection','out')
                                else
                                    colorbar('Color','w','Box','off','TickDirection','out')
                                end
                            end

                        end

                    if app.ColorBarCheckBox.Value==1
                        im = getframe(figure(1));
                    else
                        im = getframe(gca);
                    end

                        switch varargin{2}
                            case '.avi'
                                if iii == 1
                                    v = VideoWriter(fullFileName,'Motion JPEG AVI');
                                    v.FrameRate  = app.fps.Value;
                                end
                                open(v);
                                writeVideo(v,im);
                            case '.gif'
                                [imind,cm] = rgb2ind(im.cdata,256);
                                if iii == 1
                                    imwrite(imind,cm,fullFileName,'gif', 'DelayTime',1/app.fps.Value,'Loopcount',inf);
                                else
                                    imwrite(imind,cm,fullFileName,'gif','WriteMode','append', 'DelayTime',1/app.fps.Value);
                                end
                            case {'.png','.jpeg','.pdf','.svg'}
                                exportgraphics(gcf,fullFileName,'BackgroundColor','current', 'Resolution',app.Settings.ExportSettings.resolution);
                        end
                        cla(ax)
                end


            end % end of for loop for export movie
            if numel(varargin)>1
                switch varargin{2}
                    case '.avi'
                        close(v)
                    otherwise
                        close
                end
            end

        end

        %end of updatePlot 1 -------------------------------------

        function updateRightPlot(app)
            switch app.RightPlotList.Value
                case 'Off'
                    updatePlot2_type(app,'image')
                case 'Line Profile'
                    updatePlot2_type(app,'plot')
                case 'Ref Image'
                    updatePlot2_type(app,'image')
                case 'Localizations'
                    updatePlot2_type(app,'image')
                case {'LAFM','LAFM sym'}
                    updatePlot2_type(app,'image')
            end

            updatePlot2(app,'normal')
        end

        function updatePlot2_type(app,type)
            switch type
                case 'image'
                    set(app.UIAxes_2,'visible','off')
                    axis(app.UIAxes_2,'tight','equal');
                    set(app.UIAxes_2,'Color','k')
                    app.UIAxes_2.XTick = [];
                    app.UIAxes_2.XTickLabelRotation = 0;
                    app.UIAxes_2.XTickLabel = '';
                    app.UIAxes_2.YTick = [];
                    app.UIAxes_2.YTickLabelRotation = 0;
                    app.UIAxes_2.YTickLabel = '';
                    grid(app.UIAxes_2,'off')
  
                    %app.UIAxes_2.Position =  [app.UIAxes_2.Position(1),  app.UIAxes.Position(2), app.UIAxes.Position(3),app.UIAxes.Position(3)];


                case 'plot'
                    axis(app.UIAxes_2, 'normal');
                    set(app.UIAxes_2,'YDir','normal');
                    set(app.UIAxes_2,'Color','k')
                    colorbar(app.UIAxes_2,'off')
                    set(app.UIAxes_2,'visible','on')
                    set(app.UIAxes_2,'fontname','Ariel','fontsize', 14,'TickDir','out')
                    app.UIAxes_2.YColor = 'w';
                    app.UIAxes_2.XColor = 'w';
                    app.UIAxes_2.XTickMode = 'auto';
                    app.UIAxes_2.XTickLabelMode = 'auto';
                    app.UIAxes_2.YTickMode = 'auto';
                    app.UIAxes_2.YTickLabelMode = 'auto';
                    grid(app.UIAxes_2,'on')
                    grid(app.UIAxes_2,'minor')
                    app.UIAxes_2.MinorGridLineStyle = '-';
                    app.UIAxes_2.MinorGridAlpha = 0.05;
                    app.UIAxes_2.GridAlpha = 0.2;
                    app.UIAxes_2.GridColor = 'k';

            end

        end

        % Update Plot 2 -------------------------------------

        function updatePlot2(app,varargin)
            switch app.RightPlotList.Value
                case 'Line Profile'
                    switch  app.FramePartSwitch.Value
                        case 'Frames'
                            switch  app.LeftPlotList.Value
                                case 'Target'
                                    img = app.ImageTarget;
                                case 'Preview'
                                    img = app.ImagePreview;
                            end
                        case 'Particles'
                            img = app.Part.Image(:,:,app.Part.IncludeImages);
                    end
                case 'LAFM sym Movie'
                    img =  app.LAFM.Movie_sym;
                case 'LAFM Movie'
                    img =  app.LAFM.Movie;
                case 'Off'
                    return
            end

           frame =  round(app.LAFMSpinner.Value);
            switch varargin{1}
                case {'popout','export frame'}
                    switch app.RightPlotList.Value
                        case {'LAFM sym Movie','LAFM Movie'}
                            frame =  round(app.LAFMSpinner.Value);
                        otherwise
                            frame = round(app.Spinner.Value);
                    end
                            figure()
                            ax = gca;
                    
                case 'export video'
                     switch app.RightPlotList.Value
                          case {'LAFM sym Movie','LAFM Movie'}
                              frame = 1:numel(img);
                         otherwise
                             frame = 1:numel(img(1,1,:));
                     end
                    
                    figure(1)
                    ax = gca;

                case {'normal','live'}
                    switch app.RightPlotList.Value
                        case {'LAFM sym Movie','LAFM Movie'}
                            frame =  round(app.LAFMSpinner.Value);
                        otherwise
                            frame = round(app.Spinner.Value);
                    end
                    ax = app.UIAxes_2;
                    cla(ax);
            end

            for iii = frame
                if numel(frame) < 2
                    iii=1;
                end

                switch app.RightPlotList.Value
                    case 'Off'
                        colorbar(ax,'off')
                     
                        app.LAFMPanel.Visible = 0;
                        app.UITable2.Visible = 0;
                        app.LineProfilePanel.Visible = 0;
                        app.ReferenceTools.Visible =0;
                    case 'Line Profile'
                 
                        app.LAFMPanel.Visible = 0;
                        app.UITable2.Visible = 1;
                        app.LineProfilePanel.Visible = 1;
                        prev = img(:,:,frame(iii));
                        app.ReferenceTools.Visible =0;

                        if numel(app.ImageInfo.ScanSize)>1
                            fr = round(app.FrameSlider.Value);
                            switch  app.FramePartSwitch.Value
                                case 'Particles'
                                    fr = app.Part.Locs((app.Part.Locs(:,6)==fr),5);
                            end
                        else
                            fr=1;
                        end

                          try

                        C = hsv(8);
                        C = [C ;   jet(8)];
                        C = [C ;   parula(8)];
                        currentmin = Inf;
                        currentmax = -Inf;
                        for jj=1:numel(app.LineROI)

                            [cx,cy,ch] = improfile(prev,double(app.LineROI(jj).Position(:,1)),double(app.LineROI(jj).Position(:,2)));
                            try
                                if app.ImageInfo.PixelPerNm(fr)>0
                                    dist = (((cx(1)-cx(end))^2 + (cy(1)-cy(end))^2)^0.5)./app.ImageInfo.PixelPerNm(fr);
                                else
                                    dist = ((cx(1)-cx(end))^2 + (cy(1)-cy(end))^2)^0.5;
                                end

                            catch
                                dist = ((cx(1)-cx(end))^2 + (cy(1)-cy(end))^2)^0.5;
                            end

                            if  app.Live.Value==1
                                app.LineROI(jj).Profile =[];
                                app.LineROI(jj).Profile(:,1)= linspace(0,dist,numel(ch))';
                                app.LineROI(jj).Profile(:,2) = ch;

                            elseif ~isfield(app.LineROI(jj),'Profile') ||  strcmp(varargin{1},'live')
                                app.LineROI(jj).Profile =[];
                                app.LineROI(jj).Profile(:,1)= linspace(0,dist,numel(ch))';
                                app.LineROI(jj).Profile(:,2) = ch;
                            end
                            try
                                plot(ax,app.LineROI(jj).Profile(:,1),app.LineROI(jj).Profile(:,2),'-','LineWidth',1.5,'Color',C(jj,:))
                            catch
                               plot(ax,app.LineROI(jj).Profile(:,1),app.LineROI(jj).Profile(:,2),'-','LineWidth',1.5)
                            end
                            hold(ax,'on')
                            currentmin = min([min(ch); currentmin]);
                            currentmax = max([max(ch); currentmax]);
                        end
                        hold(ax,'off')
                        increase = 0.05 * range([currentmin, currentmax]);
                        newLimits = [currentmin - increase, currentmax + increase];
                        if  strcmp(varargin{1},'live') || app.Live.Value==1
                            ylim(ax, newLimits);
                        end
                        xlabel(ax,'Distance (nm)')
                        ylabel(ax,'Height (nm)')
                        catch
                        %
                         end


                    case 'Ref Image'
                     
                        app.LAFMPanel.Visible = 0;
                        app.UITable2.Visible = 0;
                        app.LineProfilePanel.Visible = 0;
                        app.ReferenceTools.Visible = 1;
                        view(ax,2)
                       %  if app.MultiReferenceCheckBox.Value == 1
                        %     app.ReferenceButtonGroup.Visible = 1;
                        %     num = app.ReferenceButtonGroup.SelectedObject.Text;
                        %     index = str2double(num);
                        %     if isfield(app.ref, 'crop') == 1
                        %         try
                        %             app.ref.image = app.ref.croppedimg{index};
                        %             climit = [min(app.ref.image(:)),max(app.ref.image(:))];
                        %             imagesc(ax,single(app.ref.image),climit)
                        %         catch
                        %             app.ref.image = app.ref.images(index);
                        %             climit = [min(app.ref.image{1}(:)),max(app.ref.image{1}(:))];
                        %             imagesc(ax,single(app.ref.image{1}),climit)
                        %         end
                        %     else
                        %         app.ref.image = app.ref.images(index);
                        %         climit = [min(app.ref.image{1}(:)),max(app.ref.image{1}(:))];
                        %         imagesc(ax,single(app.ref.image{1}),climit)
                        %     end
                        % 
                        % else
                            app.ReferenceButtonGroup.Visible = 0;
                            if any(cell2mat(app.RefListBox.Value))
                                switch cell2mat(app.RefListBox.Value)
                                    case 'ROI Reference'

                                        if ~isempty(app.ref.ROIimage)
                                            app.ref.image = app.ref.ROIimage;
                                            climit = [app.ContrastMinSpinner.Value, app.ContrastMaxSpinner.Value];
                                        end
                                    case 'Simulated Reference'
                                        if ~isempty(app.ref.simImage) || isvalid(app.ref.simImage)
                                            app.ref.image = app.ref.simImage;
                                            climit = [app.ContrastMinSpinner.Value, app.ContrastMaxSpinner.Value];
                                        end
                                    case 'LAFM Reference'
                                        if ~isempty(app.ref.LAFM) || isvalid(app.ref.LAFM)
                                            app.ref.image = app.ref.LAFM;
                                        end 
                                        climit = [min(app.ref.image(:)),max(app.ref.image(:))];
                                    case 'Averaged Reference'
                                        app.ref.image = app.ref.av;
                                        climit = [app.ContrastMinSpinner.Value, app.ContrastMaxSpinner.Value];
                                end

                                
                                try
                                    imagesc(ax,single(app.ref.image),climit)
                                catch
                                    imagesc(ax,single(app.ref.image),[0,1])
                                end
                           % end
                        end

                        switch app.ColourmapDropDown.Value
                            case 'AFM brown'
                                colormap(ax,app.cmap.AFM_brown)
                            case 'AFM dark gold'
                                colormap(ax,app.cmap.AFM_Dark_Gold)
                            case 'AFM gold'
                                colormap(ax,app.cmap.AFM_Gold)
                            case 'Orange hot'
                                colormap(ax,app.cmap.AFM_orange)
                            case'fire'
                                colormap(ax,app.cmap.AFM_fire)
                            case'Rainbow'
                                colormap(ax,app.cmap.Rainbow)
                            otherwise
                                colormap(ax,app.ColourmapDropDown.Value)
                        end
            
                        set(ax,'visible','off')
                        axis(ax,'tight','equal');

                    case 'Localizations'
                        app.LAFMPanel.Visible = 0;
                        hold(ax,'on')
                        s = any(app.LAFM.Locs);
                        if s(1)==0
                        else
                            %  h=  (app.LAFM.Locs(:,3)-min(app.LAFM.Locs(:,3)))/(max(app.LAFM.Locs(:,3))-min(app.LAFM.Locs(:,3)));
                            scatter3(ax,app.LAFM.Locs(:,1),app.LAFM.Locs(:,2),app.LAFM.Locs(:,3),6,app.LAFM.Locs(:,3),'filled','MarkerFaceAlpha',0.5)
                            set(ax, 'YDir', 'reverse');
                            % ylim(ax,[1 size(img,1)])
                            % xlim(ax,[1 size(img,2)])
                        end
                        hold(ax,'off')

                    case {'LAFM','LAFM sym','LAFM Movie','LAFM sym Movie'}
                        view(ax,2)
                        clear t scaledImage
                        app.LAFMPanel.Visible = 1;
                        app.UITable2.Visible = 0;
                        app.LineProfilePanel.Visible = 0;
                        switch app.RightPlotList.Value
                            case 'LAFM'
                                t = app.LAFM.Image;
                            case 'LAFM sym'
                                t = app.LAFM.Image_sym;
                            case 'LAFM Movie'
                                t = app.LAFM.Movie{frame(iii)};
                                
                            case 'LAFM sym Movie'
                               t = app.LAFM.Movie_sym{frame(iii)};
                        end

                        switch app.LAFM_LUT.Value
                            case 'LAFM color'
                                color =  app.cmap.LAFMcolor;
                            case 'Rainbow'
                                color = app.cmap.Rainbow;
                            otherwise
                                color = eval(app.LAFM_LUT.Value);
                        end

                        colormap(ax,color)
                        switch app.LAFMView.Value
                            case {'LAFM map','Height'}
                                scaledImage = (t-app.LAFMMinSlider.Value)/(app.LAFMMaxSlider.Value - app.LAFMMinSlider.Value);
                                imagesc(ax, scaledImage)
                                switch app.LAFMView.Value
                                    case {'LAFM map','Height'}
                                        try 
                                        clim(ax,[app.LAFM.lims(1), app.LAFM.lims(2)]);
                                        catch
                                         caxis(ax,[app.LAFM.lims(1), app.LAFM.lims(2)]);
                                        end
                                end

                            case 'Probability'
                                if app.LAFMMinSlider.Value > app.LAFMMaxSlider.Value
                                    app.LAFMMinSlider.Value = 0;
                                    app.LAFMMaxSlider.Value = 1;
                                end
                                climit = [app.LAFMMinSlider.Value, app.LAFMMaxSlider.Value];
                                imagesc(ax, t,climit)

                        end

                        set(ax,'visible','off')
                        axis(ax,'tight','equal');

                    case 'x & y Align'
                        app.LAFMPanel.Visible = 0;
                        updatePlot2_type(app,'plot')
                        if any(app.img_locs)
                            plot(ax,app.img_locs(:,1),'x-')
                            hold(ax,'on')
                            plot(ax,app.img_locs(:,2),'v-')
                            hold(ax,'off')
                        end

                    case 'Time Profile'
                        switch  app.FramePartSwitch.Value
                            case 'Frames'
                                switch  app.LeftPlotList.Value
                                    case 'Target'
                                        img = app.ImageTarget;
                                    case 'Preview'
                                        img = app.ImagePreview;
                                end
                            case 'Particles'
                                img = app.Part.Image(:,:,app.Part.IncludeImages);
                        end

                        sd = size(img);
                        [x, y] = meshgrid(1:sd(2), 1:sd(1));
                        distances = sqrt((x - app.CircleROI.Position(2)).^2 + (y - app.CircleROI.Position(3)).^2);
                        inside_circle = distances <= app.CircleROI.Position(1);


                        for k = 1:sd(3)
                            temp = img(:,:,k);
                            roi_pixels = temp(inside_circle);
                            app.CircleROI.Profile(k) = mean(roi_pixels);
                        end
                        plot(ax, app.CircleROI.Profile)

                    case 'Kymograph'
                        app.LAFMPanel.Visible = 0;
                        app.UITable2.Visible = 0;
                        app.LineProfilePanel.Visible = 0;

                        imagesc(ax, app.LineROI(1).kymo, [app.ContrastMinSpinner.Value app.ContrastMaxSpinner.Value])
                        switch app.ColourmapDropDown.Value
                            case 'AFM brown'
                                colormap(ax, app.cmap.AFM_brown)
                            case 'AFM dark gold'
                                colormap(ax, app.cmap.AFM_Dark_Gold)
                            case 'AFM gold'
                                colormap(ax, app.cmap.AFM_Gold)
                            case 'Orange hot'
                                colormap(ax, app.cmap.AFM_orange)
                            case'fire'
                                colormap(ax, app.cmap.AFM_fire)
                            case'Rainbow'
                                colormap(ax, app.cmap.Rainbow)
                            otherwise
                                colormap(ax, app.ColourmapDropDown.Value)
                        end
                        axis(ax,'normal');
                end
              
                if app.LAFMScalebarCheckBox.Value ==1
                    switch app.RightPlotList.Value
                        case {'LAFM sym', 'LAFM','LAFM Movie','LAFM sym Movie'}

                            switch app.LAFMView.Value
                                case {'LAFM map','Height'}
                                    tp = rgb2gray(t);
                                otherwise
                                    tp = t;
                            end
                            if numel(app.ImageInfo.PixelPerNm)>1
                                fr = round(app.FrameSlider.Value);
                                switch  app.FramePartSwitch.Value
                                    case 'Particles'
                                        fr = app.Part.Locs((app.Part.Locs(:,6)==fr),5);
                                end
                            else
                                fr=1;
                            end

                            [r, cs] = size(tp);
                            sb = cs./app.ImageInfo.PixelPerNm(fr)/app.LAFM_exp.Value;
                            try
                                scale_bar = round(sb/5,1,"significant");
                                scale_bar_pix = scale_bar.*app.ImageInfo.PixelPerNm(fr)*app.LAFM_exp.Value;

                                switch app.Settings.label.LAFMScaleBar.position
                                    case 'Top Right'
                                        xpos = [round(cs.*0.95)-scale_bar_pix, round(cs.*0.95)];
                                        ypos = round([r*.05, r*.05]);
                                    case 'Top Left'
                                        xpos = [round(cs.*0.05), round(cs.*0.05)+scale_bar_pix];
                                        ypos = round([r*.05, r*.05]);
                                    case 'Bottom Left'
                                        xpos = [round(cs.*0.05), round(cs.*0.05)+scale_bar_pix];
                                        ypos = round([r*.95, r*.95]);
                                    case 'Bottom Right'
                                        xpos = [round(cs.*0.95)-scale_bar_pix, round(cs.*0.95)];
                                        ypos = round([r*.95, r*.95]);
                                end

                                line(ax,xpos,ypos,'LineWidth',2,'Color',app.Settings.label.LAFMScaleBar.color);
                                if scale_bar >1000

                                    text(ax, xpos(1)+scale_bar_pix/2, round(ypos(1) - r*.03), [num2str(scale_bar/1000) char(181),'m'],...
                                        'FontWeight','bold','FontSize', 15,'Color', app.Settings.label.LAFMScaleBar.color,'HorizontalAlignment','center');
                                else
                                    text(ax, xpos(1)+scale_bar_pix/2, round(ypos(1) - r*.03), [num2str(scale_bar) 'nm'],...
                                        'FontWeight','bold','FontSize', 15,'Color', app.Settings.label.LAFMScaleBar.color,'HorizontalAlignment','center');
                                end
                            catch
                            end
                    end
                end

                if app.LAFMTimescaleCheckBox.Value ==1
                    switch app.RightPlotList.Value
                        case {'LAFM Movie','LAFM sym Movie'}
                            switch app.LAFMView.Value
                                case {'LAFM map','Height'}
                                    tp = rgb2gray(t);
                                otherwise
                                    tp = t;
                            end
                            [r, cs, ~] = size(tp);
                            %  try
                            time = round(app.LAFM.time(frame(iii)),4,"significant");

                            switch app.Settings.label.TimeStamp.position
                                case 'Top Right'
                                    xpos = round(cs*.90);
                                    ypos = round(r*.05);
                                case 'Top Left'
                                    xpos = round(cs*.05);
                                    ypos = round(r*.05);
                                case 'Bottom Left'
                                    xpos = round(cs*.05);
                                    ypos = round(r*.95);
                                case 'Bottom Right'
                                    xpos = round(cs*.90);
                                    ypos = round(r*.95);
                            end
                            text(ax,xpos(1),ypos(1),[num2str(time) 's'],'FontWeight','bold','FontSize', 17,'Color',app.Settings.label.TimeStamp.color);
                            % catch
                            % end
                    end
                end
          

                switch varargin{1}
                    case {'export video','export frame'}
                        hold(ax,'on')
                        if iii == 1
                            namesplit = split(app.ImageName,'.');
                            namesuggest = join(namesplit,"_");
                            filetype = app.ExportFileFormat.Value;
                            [outputFileName,folder] = uiputfile([namesuggest{1},filetype]);
                            fullFileName = fullfile(folder, outputFileName);
                            set(figure(1),'color',app.Settings.ExportSettings.color)
                            if app.LAFMColorBarCheckBox.Value==1     
                                    switch app.LAFM_LUT.Value
                                        case 'LAFM color'
                                            color =  app.cmap.LAFMcolor;
                                        case 'Rainbow'
                                            color = app.cmap.Rainbow;
                                        otherwise
                                            color = eval(app.LAFM_LUT.Value);
                                    end
                                     colormap(ax,color)
                                     if strcmp(app.Settings.ExportSettings.color,'white')
                                         colorbar(ax,'Color','k','Box','off','TickDirection','out')
                                     else
                                         colorbar(ax,'Color','w','Box','off','TickDirection','out')
                                     end
                                     switch app.LAFMView.Value
                                         case {'LAFM map','Height'}
                                             try
                                                 clim(ax,[app.LAFM.lims(1), app.LAFM.lims(2)]);
                                             catch
                                                 caxis(ax,[app.LAFM.lims(1), app.LAFM.lims(2)]);
                                             end
                                     end
                            end
                        end

                        switch varargin{3}
                            case 'plot'
                                axis tight
                                if strcmp(app.Settings.ExportSettings.color,'white')
                                    set(figure(1),'color','w')
                                    set(gca,'Tickdir','out','fontsize',12,'YDir','normal','XColor','k','YColor','k')
                                else
                                    set(figure(1),'color','k')
                                    set(gca,'Tickdir','out','fontsize',12,'YDir','normal','XColor','w','YColor','w')
                                end
                                im = getframe(figure(1));

                            case 'image'
                                set(ax,'visible','off')
                                if app.ColorBarCheckBox.Value==1
                                    im = getframe(figure(1));
                                else
                                    im = getframe(gca);
                                end
                        end


                        switch varargin{2}
                            case '.avi'
                                if iii == 1
                                    v = VideoWriter(fullFileName,'Motion JPEG AVI');
                                    v.FrameRate  = app.fps.Value;
                                end
                                open(v);
                                writeVideo(v,im);
                            case '.gif'
                                [imind,cm] = rgb2ind(im.cdata,256);
                                if iii == 1
                                    imwrite(imind,cm,fullFileName,'gif', 'DelayTime',1/app.fps.Value,'Loopcount',inf);
                                else
                                    imwrite(imind,cm,fullFileName,'gif','WriteMode','append', 'DelayTime',1/app.fps.Value);
                                end
                            case {'.png','.jpeg','.pdf','.svg'}
                                exportgraphics(gcf,fullFileName,'BackgroundColor','current', 'Resolution',app.Settings.ExportSettings.resolution);
                        end
                end
            end % end of for loop for export movie
            if numel(varargin)>1
                switch varargin{2}
                    case '.avi'
                        close(v)
                end
            end

        end

        %
        function updateHist(app)

            if app.Hist_excludeOut.Value ==1
                T = reshape(app.ImageTarget(:,:,:),1,[]);
                T = rmoutliers(T,"percentiles",[5 95]);
            else
                T = app.ImageTarget(:,:,:);
            end
            histogram(app.Hist_Axes,T,100,'EdgeColor','None','FaceColor',[0.5 0.5 0.5]);
            if app.LogYHist.Value==1
                set(app.Hist_Axes, 'YScale', 'log')
            else
                set(app.Hist_Axes, 'YScale', 'linear')
            end
            app.patches.histup = patch(app.Hist_Axes,[app.SliderMax.Limits(2) app.SliderMax.Value app.SliderMax.Value app.SliderMax.Limits(2)], [app.Hist_Axes.YLim(1), app.Hist_Axes.YLim(1),  app.Hist_Axes.YLim(2)  app.Hist_Axes.YLim(2)],...
                'r', 'FaceAlpha', 0.5,'EdgeColor','none');
            app.patches.histdown = patch(app.Hist_Axes,[app.SliderMin.Value app.SliderMin.Limits(1) app.SliderMin.Limits(1) app.SliderMin.Value], [app.Hist_Axes.YLim(1), app.Hist_Axes.YLim(1),  app.Hist_Axes.YLim(2)  app.Hist_Axes.YLim(2)],...
                'r', 'FaceAlpha', 0.5,'EdgeColor','none');
        end

        function updateCCRHist(app)

            switch app.Height_corr_switch.Value
                case 'Height'
                    histogram(app.HeightCCRHist, app.Part.Locs(:,3) ,50,'EdgeColor','None','FaceColor',[0.5 0.5 0.5]);
                    app.CCRSliderMax.Limits = double([min(app.Part.Locs(:,3)), max(app.Part.Locs(:,3))]);
                    app.CCRSliderMin.Limits = double([min(app.Part.Locs(:,3)), max(app.Part.Locs(:,3))]);
                    app.CCRSliderMin.Value = [app.Min_h_Edit.Value];
                    app.CCRSliderMax.Value = [app.Max_h_Edit.Value];
                    app.CCRSliderMax.Value = [app.CCRSliderMax.Limits(2)];
                    xlim(app.HeightCCRHist,app.CCRSliderMin.Limits)
                case 'Correlation'
                    histogram(app.HeightCCRHist, app.Part.Locs(:,4)  ,50,'EdgeColor','None','FaceColor',[0.5 0.5 0.5]);
                    if any(app.Part.Locs(1,4))
                        app.CCRSliderMax.Limits = [app.Min_CCR_Edit.Value, app.Max_CCR_Edit.Value];
                        app.CCRSliderMin.Limits = [app.Min_CCR_Edit.Value, app.Max_CCR_Edit.Value];
                    end
                    app.CCRSliderMin.Value = [app.Min_CCR_Edit.Value];
                    app.CCRSliderMax.Value = [app.Max_CCR_Edit.Value];
                    xlim(app.HeightCCRHist,app.CCRSliderMin.Limits)
            end


            app.patches.ccrhistup = patch(app.HeightCCRHist,[app.CCRSliderMax.Limits(2) app.CCRSliderMax.Value app.CCRSliderMax.Value app.CCRSliderMax.Limits(2)], [app.HeightCCRHist.YLim(1), app.HeightCCRHist.YLim(1),  app.HeightCCRHist.YLim(2)  app.HeightCCRHist.YLim(2)],...
                'r', 'FaceAlpha', 0.5,'EdgeColor','none');
            app.patches.ccrhistdown = patch(app.HeightCCRHist,[app.CCRSliderMin.Value app.CCRSliderMin.Limits(1) app.CCRSliderMin.Limits(1) app.CCRSliderMin.Value], [app.HeightCCRHist.YLim(1), app.HeightCCRHist.YLim(1),  app.HeightCCRHist.YLim(2)  app.HeightCCRHist.YLim(2)],...
                'r', 'FaceAlpha', 0.5,'EdgeColor','none');


        end

        function LoadstartupImg(app)
            imageName = 'LoadImg.tif';
            info = imfinfo(imageName);   app.ImageInfo.n = numel(info);
            if any(info(1).XResolution)
                app.ImageInfo.PixelPerNm =  info(1).XResolution;
            else
                app.ImageInfo.PixelPerNm =  1;
            end
            app.ImageInfo.xPixels = info(1).Width;
            app.ImageInfo.yPixels  = info(1).Height;

            if app.ImageInfo.PixelPerNm >0
                app.ImageInfo.ScanSize = app.ImageInfo.xPixels/app.ImageInfo.PixelPerNm;
            else
                app.ImageInfo.ScanSize = 0;
            end
            app.ImageInfo.LineSpeed = 0;
            app.ImageInfo.ScanSpeed = 0;
            im = zeros(app.ImageInfo.yPixels ,app.ImageInfo.xPixels, app.ImageInfo.n);
            for ii = 1:app.ImageInfo.n
                im(:,:,ii) = imread(imageName,ii);
            end
            app.ImageTarget = double(im);
            app.ImageInfo.Channel = 'Tiff';
            app.ImageInfo.Channels = {app.ImageInfo.Channel};
            app.ImageInfo.time = 0;
            updateTable(app)
            app.ImageName =  imageName;
            app.ImagePreview =  app.ImageTarget;
        end

        function im = Open_files(app)

            %check if edited image stored
            app.ChannelDropDown.Visible = 0;
            app.CancelButton.Visible = 1;
            app.stop = 0;
            imageName = [app.OpenFolder '/' app.FilesListBox.Value];
            app.ErrorButton.Visible = 0;

            if isfolder(imageName)  %if folder try open as Video
                [Format, fileNames] = checkfolder(imageName);
                n = length(fileNames);
                if n == 0
                    im = 0;
                     finished(app)
                      app.CancelButton.Visible = 0;
                   
                      rectangle(app.loadbar, 'Position', [0, 0, 1, 1], 'FaceColor', 'red');
                       text(app.loadbar, 0.5, 0.5, 'No images loaded', 'HorizontalAlignment', 'center', 'VerticalAlignment', 'middle');
                    return
                else
                    app.ImageInfo.n = n;
                end
                updateInterval = 5;
                cla(app.loadbar)
                rectangle(app.loadbar, 'Position', [0, 0, 1, 1], 'FaceColor', 'none', 'EdgeColor', 'black');
                progressBar = rectangle(app.loadbar, 'Position', [0, 0, 0, 1], 'FaceColor', 'green');
                progressText = text(app.loadbar, 0.5, 0.5, '0%', 'HorizontalAlignment', 'center', 'VerticalAlignment', 'middle');

                if app.View1stCheckBox.Value ==1 && app.View1st.Value < app.ImageInfo.n
                    loadn = app.View1st.Value;
                else
                    loadn = app.ImageInfo.n;
                end
                stepsPerUpdate = ceil(loadn * (updateInterval / 100));
                updateVideoTools(app)
              
                switch Format
                    case '.jpk'
                        app.ChannelDropDown.Visible = 1;
                        for ii = 1:loadn
                            if app.stop == 1
                                return
                            end
                            if ii ==1
                                [info, Channels] = open_JPK_info(fileNames{ii});
                                app.ImageInfo.Channels = strcat({Channels.Channel_name}', ' - ', {Channels.Trace_type}');
                                app.ChannelDropDown.Items = app.ImageInfo.Channels;

                                searchCh = app.ChannelDropDown.Value;
                                app.ImageInfo.Channel = searchCh;
                                rowNumber = strcmp(app.ChannelDropDown.Items, searchCh);
                                temp = 1:numel(rowNumber);
                                ch = temp(rowNumber);

                                im = zeros(info.y_scan_pixels,info.x_scan_pixels,loadn);
                                im(:,:,ii) = 10*flip(-open_JPK_image(fileNames{ii},ch+1));
         
                                app.ImageInfo.LineSpeed = info.Scan_Rate;
                                app.ImageInfo.ScanSize = round(info.x_scan_length*1e9);
                                app.ImageInfo.yPixels = info.y_scan_pixels;
                                app.ImageInfo.xPixels = info.x_scan_pixels;
                                app.ImageInfo.PixelPerNm = app.ImageInfo.xPixels./app.ImageInfo.ScanSize;
                                app.ImageInfo.ScanSpeed = app.ImageInfo.LineSpeed./app.ImageInfo.yPixels.*(1.*(app.BidirectionaldataCheckBox.Value)+1);
                                updateTable(app)
                                updatePlot(app,'normal')
                                pause(0.001)
                                app.ImageInfo.time(ii) =  0;
                            else
                                if mod(ii, stepsPerUpdate) == 0 || ii == loadn
                                    progressBar.Position(3) = ii / loadn;
                                    progressText.String = sprintf('%d of %d images loaded', ii , loadn);
                                    drawnow;
                                end
                                    im(:,:,ii) = 10*flip(-open_JPK_image(fileNames{ii},ch+1));
                                    app.ImageInfo.time(ii) =  round((ii-1)/app.ImageInfo.ScanSpeed,4,"significant");
                                    app.ImageInfo.PixelPerNm(ii) = app.ImageInfo.PixelPerNm(1);
                                    app.ImageInfo.ScanSize(ii) = app.ImageInfo.ScanSize(1);
                            end
                            
                        end
                   
                    
                    case 'NanoScope'       %open nanoSCOPE
                        app.ErrorButton.Visible = 1;
                            app.ChannelDropDown.Visible = 1;
                             count = 0;
                             for ii = 1:loadn
                                 if app.stop == 1
                                     return
                                 end
                                 if ii ==1

                                     try
                                         [im(:,:,ii), info] = open_nanoscope(fileNames{ii},app.ChannelDropDown.Value,app.ErrorButton.Value);
                                     catch
                                         try
 
                                             [im(:,:,ii), info] = open_nanoscope(fileNames{ii},'Height',app.ErrorButton.Value);

                                         catch

                                             [im(:,:,ii), info] = open_nanoscope(fileNames{ii},'Height Sensor',app.ErrorButton.Value);
                                         end
                                     end

                                     app.ImageInfo.ScanSize(ii) = info.ScanSize;
                                     app.ImageInfo.yPixels = info.line_no;
                                     app.ImageInfo.xPixels = size(im,2);
                                     app.ImageInfo.PixelPerNm(ii) = app.ImageInfo.xPixels./app.ImageInfo.ScanSize(ii);
                                     app.ImageInfo.ScanSpeed = 1/(info.line_no.*info.Scan_Rate);
                                     app.ImageInfo.LineSpeed = 1/info.Scan_Rate;
                                     Timetemp{ii} = info.Time;
                                     app.ImageInfo.Channels = info.channels;
                                     app.ChannelDropDown.Items = app.ImageInfo.Channels;
                                     app.ImageInfo.Channel = app.ChannelDropDown.Value;
                                     updateTable(app)
                                     updatePlot(app,'normal')
                                     pause(0.001)
                                 else
                                     try
                                         [im(:,:,ii-count), info]  = open_nanoscope(fileNames{ii},app.ChannelDropDown.Value,app.ErrorButton.Value);
                                         app.ImageInfo.ScanSize(ii-count) = info.ScanSize;
                                         app.ImageInfo.PixelPerNm(ii-count) = app.ImageInfo.xPixels./app.ImageInfo.ScanSize(ii-count);
                                         app.ImageInfo.Channel = app.ChannelDropDown.Value;
                                         Timetemp{ii-count} = info.Time;
                                     catch
                                         count = count +1;
                                         app.ImageInfo.n = app.ImageInfo.n-1;
                                     end
                                     if mod(ii, stepsPerUpdate) == 0 || ii == loadn
                                         progressBar.Position(3) = (ii-count) / (loadn-count);
                                         progressText.String = sprintf('%d of %d images loaded', ii-count , loadn-count);
                                         drawnow;
                                     end
                                 end
                             end
                             app.ImageInfo.time = time_elapsed(Timetemp);
                    case '.ibw'
                        count = 0;
                        for ii = 1:loadn
                            if app.stop == 1
                                return
                            end
                            if ii==1
                            [im(:,:,ii), info] = open_IBW(fileNames{ii});
                            app.ImageInfo.ScanSize(ii) = info.ScanSize./1e-9;
                            app.ImageInfo.yPixels = info.yPixel;
                            app.ImageInfo.xPixels = info.xPixel;
                            app.ImageInfo.PixelPerNm(ii) = app.ImageInfo.xPixels./app.ImageInfo.ScanSize(ii);
                            app.ImageInfo.ScanSpeed = 1/info.frameAcqTime;
                            app.ImageInfo.LineSpeed = info.yPixel.*app.ImageInfo.ScanSpeed;
                            app.ImageInfo.Channel = 'Height';
                            app.ImageInfo.Channels = {app.ImageInfo.Channel};
                            updateTable(app)
                            updatePlot(app,'normal')
                            pause(0.001)
                             app.ImageInfo.time(ii) =  0;
                        else

                            try
                                [im(:,:,ii-count), info] = open_IBW(fileNames{ii});
                                app.ImageInfo.ScanSize(ii-count) = info.ScanSize./1e-9;
                                app.ImageInfo.PixelPerNm(ii-count) = app.ImageInfo.yPixels./app.ImageInfo.ScanSize(ii-count);
                                app.ImageInfo.time(ii-count) =  round((ii-1-count)/app.ImageInfo.ScanSpeed,4,"significant");
                            catch
                                count = count +1;
                                app.ImageInfo.n = app.ImageInfo.n-1;
                            end
                            if mod(ii, stepsPerUpdate) == 0 || ii == loadn
                                progressBar.Position(3) = (ii-count) / (loadn-count);
                                progressText.String = sprintf('%d of %d images loaded', ii-count , loadn-count);
                                drawnow;
                            end
                            end
                        end
                        im = im.*1e9;
                        im = flip(im);

                    case '.nhf'
                        app.ChannelDropDown.Visible = 1;
                        count = 0;

                        for ii = 1:loadn
                            if app.stop == 1
                                return
                            end
                            if ii ==1
                                try
                                    [im(:,:,ii), info] = open_NHF(fileNames{ii},app.ChannelDropDown.Value);
                                catch
                                    [im(:,:,ii), info] = open_NHF(fileNames{ii},'Topography');
                                end
                                app.ImageInfo.ScanSize(ii)  = double(info.ScanSize)./1e-9;
                                app.ImageInfo.yPixels = double(info.yPixel);
                                app.ImageInfo.xPixels = double(info.xPixel);
                                app.ImageInfo.PixelPerNm = app.ImageInfo.xPixels./app.ImageInfo.ScanSize(ii);
                                app.ImageInfo.ScanSpeed = 1/double(info.frameAcqTime);
                                app.ImageInfo.LineSpeed = double(info.yPixel).*app.ImageInfo.ScanSpeed;
                                app.ImageInfo.Channel = info.channel;
                                app.ImageInfo.Channels = info.channels;
                                app.ChannelDropDown.Items = app.ImageInfo.Channels;
                                updateTable(app)
                                updatePlot(app,'normal')
                                  app.ImageInfo.time(ii-count) =  0;
                                pause(0.001)
                            else
                                try
                                    try
                                        [im(:,:,ii-count), info] = open_NHF(fileNames{ii},app.ChannelDropDown.Value);
                                    catch
                                        [im(:,:,ii-count), info] = open_NHF(fileNames{ii},'Topography');
                                    end
                                    app.ImageInfo.ScanSize(ii-count) = double(info.ScanSize)./1e-9;
                                    app.ImageInfo.PixelPerNm(ii-count) = app.ImageInfo.xPixels./app.ImageInfo.ScanSize(ii-count);
                                    app.ImageInfo.time(ii-count) =  round((ii-1-count)/app.ImageInfo.ScanSpeed,4,"significant");
                                catch
                                    count = count +1;
                                    app.ImageInfo.n = app.ImageInfo.n-1;
                                end
                                if mod(ii, stepsPerUpdate) == 0 || ii == loadn
                                    progressBar.Position(3) = (ii-count) / (loadn-count);
                                    progressText.String = sprintf('%d of %d images loaded', ii-count , loadn-count);
                                    drawnow;
                                end
                            end
                        end
                        im = im.*1e9;

                    case '.gwy'
                        app.ChannelDropDown.Visible = 1;
                        count = 0;
                        for ii = 1:loadn
                            if app.stop == 1
                                return
                            end
                            if ii ==1
                                [img, info] = open_gwychannel(fileNames{ii});
                                app.ImageInfo.ScanSize(ii) = double(info.xreal)./1e-9;
                                app.ImageInfo.yPixels = double(info.yres);
                                app.ImageInfo.xPixels = double(info.xres);
                                app.ImageInfo.PixelPerNm(ii) = app.ImageInfo.xPixels./app.ImageInfo.ScanSize(ii);
                                app.ImageInfo.ScanSpeed = 0;
                                app.ImageInfo.LineSpeed = 0;
                                app.ImageInfo.Channels = (info.channels);
                                app.ChannelDropDown.Items = app.ImageInfo.Channels;
                                searchCh = app.ChannelDropDown.Value;
                                app.ImageInfo.Channel = searchCh;
                                rowNumber = strcmp(app.ChannelDropDown.Items, searchCh);
                                im(:,:,ii) = img{rowNumber};
                                updateTable(app)
                                updatePlot(app,'normal')
                                app.ImageInfo.time(ii-count) =  0;
                                pause(0.001)
                            else
                                try
                                    [img, info] = open_gwychannel(fileNames{ii});
                                    app.ImageInfo.ScanSize(ii-count) = double(info.xreal)./1e-9;
                                    app.ImageInfo.PixelPerNm(ii-count) = app.ImageInfo.xPixels./app.ImageInfo.ScanSize(ii-count);
                                    app.ImageInfo.Channels = (info.channels);
                                    app.ChannelDropDown.Items = app.ImageInfo.Channels;
                                    searchCh = app.ChannelDropDown.Value;
                                    app.ImageInfo.Channel = searchCh;
                                    rowNumber = strcmp(app.ChannelDropDown.Items, searchCh);
                                    im(:,:,ii-count) = img{rowNumber};
                                    app.ImageInfo.time(ii-count) =  round((ii-1-count)/app.ImageInfo.ScanSpeed,4,"significant");
                                catch
                                    count = count +1;
                                    app.ImageInfo.n = app.ImageInfo.n-1;
                                end
                                if mod(ii, stepsPerUpdate) == 0 || ii == loadn
                                    progressBar.Position(3) = (ii-count) / (loadn-count);
                                    progressText.String = sprintf('%d of %d images loaded', ii-count , loadn-count);
                                    drawnow;
                                end
                            end
                        end
                        im = im.*1e9;
                    otherwise
                         Format = 'Unknown';
                end

            else %it is a file
                namesplit = split(imageName,'.');
                filetype = namesplit{end};

                if strcmp(filetype,'tif') || strcmp(filetype,'tiff')
                    Format = 'Tiff';
                    info = imfinfo(imageName);   app.ImageInfo.n = numel(info);
                    if any(info(1).XResolution)
                        app.ImageInfo.PixelPerNm =  info(1).XResolution;
                    else
                        app.ImageInfo.PixelPerNm =  1;
                    end
                    app.ImageInfo.xPixels = info(1).Width;
                    app.ImageInfo.yPixels  = info(1).Height;
                    app.ImageInfo.LineSpeed = 0;
                    app.ImageInfo.ScanSpeed = 0;

                    if app.View1stCheckBox.Value ==1 && app.View1st.Value < app.ImageInfo.n
                        loadn = app.View1st.Value;
                    else
                        loadn = app.ImageInfo.n;
                    end
                    app.ImageInfo.PixelPerNm(1:loadn) = app.ImageInfo.PixelPerNm(1);
                     if app.ImageInfo.PixelPerNm(1) >0
                        app.ImageInfo.ScanSize(1:loadn) = app.ImageInfo.xPixels./app.ImageInfo.PixelPerNm;
                    else
                        app.ImageInfo.ScanSize(1:loadn) = 0;
                    end
                    im = zeros(app.ImageInfo.yPixels ,app.ImageInfo.xPixels, loadn);

                    for ii = 1:loadn
                        im(:,:,ii) = imread(imageName,ii);
                    end
                    im = double(im);
                    app.ImageInfo.Channel = 'Tiff';
                    app.ImageInfo.Channels = {app.ImageInfo.Channel};
                     app.ImageInfo.time = 0;
                    updateTable(app)
                elseif strcmp(filetype,'mat')
                    Format = 'mat';
                    loadedData = load(imageName);
                    if numel(loadedData.Images) ==1
                    im = loadedData.Images.Imagedata;
                    app.ImageInfo = loadedData.Images.ImageInfo;
                    
                    updateTable(app)

                    if isfield(loadedData.Images.ref,'position')
                        app.ref = loadedData.Images.ref;

                        if isfield(app.ref,'ROIimage')
                            app.RefListBox.Items = [app.RefListBox.Items , {'ROI Reference'}];
                        end
                        if isfield(app.ref,'simImage')
                            app.RefListBox.Items = [app.RefListBox.Items , {'Simulated Reference'}];
                        end
                        if isfield(app.ref,'LAFM')
                            app.RefListBox.Items = [app.RefListBox.Items , {'LAFM Reference'}];
                        end
                        if isfield(app.ref,'av')
                            app.RefListBox.Items = [app.RefListBox.Items , {'Averaged Reference'}];
                        end

                        if sum(strcmp(app.RightPlotList.Items,'Ref Image')) <1
                            app.RightPlotList.Items = [app.RightPlotList.Items , {'Ref Image'}];
                        end
                    end

                    if isfield(loadedData.Images.ImageLocs,'Locs')
                        app.Part = loadedData.Images.ImageLocs;
                        filter_Partlocs(app)
                        updateCCRHist(app)
                        app.Part.Image  = ConstructParticleStack2(app, app.ImageTarget, app.ref, app.Part);
                    end

                    if isfield(loadedData.Images.LAFMLocs,'Locs')
                        app.LAFM = loadedData.Images.LAFMLocs;
                        if sum(strcmp(app.RightPlotList.Items,'Localizations')) <1
                            app.RightPlotList.Items = [app.RightPlotList.Items , {'Localizations'}];
                        end
                        if isfield(loadedData.Images.LAFMLocs,'Image')
                            if sum(strcmp(app.RightPlotList.Items,'LAFM')) <1
                                app.RightPlotList.Items = [app.RightPlotList.Items , {'LAFM'}];
                            end
                        end
                    end
               
                    else
                        choice = questdlg('Open New Session?', 'Confirmation', 'Yes', 'No', 'No');

                        if strcmp(choice, 'Yes')
                                filepath = fullfile(imageName);
                                loadedData = load(filepath);
                                app.stored = loadedData.Images;
                                app.OpenFolder = loadedData.WorkingFolder;
                                app.FilesDropDown.Value = 'Processed';
                            try
                                app.FilesListBox.Items = {app.stored.ImageName};
                            catch
                                app.FilesListBox.Items = {'no files'};
                            end
                            app.stop =1;
                            app.CancelButton.Visible = 0;
                            return
                        elseif strcmp(choice, 'No')
                            app.stop = 1;
                            app.CancelButton.Visible = 0;
                            im=[];
                            return
                        end     

                    end

                elseif strcmp(filetype,'asd')
                    Format = 'RIBM';
                    [im, info] = open_asd(imageName);
                    app.ImageInfo.ScanSize = info.xScanRange;
                    app.ImageInfo.yPixels = info.yPixel;
                    app.ImageInfo.xPixels = info.xPixel;
                    app.ImageInfo.PixelPerNm = app.ImageInfo.xPixels./app.ImageInfo.ScanSize;
                    app.ImageInfo.ScanSpeed = 1000/info.frameAcqTime;
                    app.ImageInfo.LineSpeed = info.yPixel.*app.ImageInfo.ScanSpeed;
                    app.ImageInfo.n = info.numberFramesCurrent;
                    app.ImageInfo.Channel = 'Height';
                    app.ImageInfo.Channels = {app.ImageInfo.Channel};
                    updateTable(app)
                    app.ImageInfo.time = double(0:1/app.ImageInfo.ScanSpeed:(app.ImageInfo.n-1)/app.ImageInfo.ScanSpeed);
                    app.ImageInfo.PixelPerNm(1:app.ImageInfo.n) = app.ImageInfo.PixelPerNm(1);
                elseif strcmp(filetype,'ARIS')
                    Format = 'ARIS';
                    app.ChannelDropDown.Visible = 1;

                    [im, info] = open_ARIS(imageName,app.ChannelDropDown.Value);
                    app.ImageInfo.ScanSize = info.scale./1e-9;
                    app.ImageInfo.yPixels = info.yPixel;
                    app.ImageInfo.xPixels = info.xPixel;
                    app.ImageInfo.PixelPerNm = app.ImageInfo.xPixels./app.ImageInfo.ScanSize;
                    app.ImageInfo.ScanSpeed = 1/info.frameAcqTime;
                    app.ImageInfo.LineSpeed = info.yPixel.*app.ImageInfo.ScanSpeed;
                    app.ImageInfo.n = info.numberofFrames;
                    app.ImageInfo.Channel = info.channel;
                    app.ImageInfo.Channels = info.channels;
                    app.ChannelDropDown.Items = app.ImageInfo.Channels;
                    app.ChannelDropDown.Value = info.channel;
                    updateTable(app)
                    im = im.*1e9;
                    app.ImageInfo.time = 0:info.frameAcqTime:(info.frameAcqTime*(app.ImageInfo.n-1));
                elseif strcmp(filetype,'ibw')
                    Format = 'ibw';
                    app.ImageInfo.n = 1;
                    updateVideoTools(app)

                    [im, info] = open_IBW(imageName);
                    app.ImageInfo.ScanSize = info.ScanSize./1e-9;
                    app.ImageInfo.yPixels = info.yPixel;
                    app.ImageInfo.xPixels = info.xPixel;
                    app.ImageInfo.PixelPerNm = app.ImageInfo.xPixels./app.ImageInfo.ScanSize;
                    app.ImageInfo.ScanSpeed = 1/info.frameAcqTime;
                    app.ImageInfo.LineSpeed = info.yPixel.*app.ImageInfo.ScanSpeed;
                    app.ImageInfo.Channel = 'Height';
                    app.ImageInfo.Channels = {app.ImageInfo.Channel};
                    updateTable(app)
                    im = im.*1e9;
                    im = flip(im);
                    app.ImageInfo.time =0;

                elseif strcmp(filetype,'nhf')
                    app.ChannelDropDown.Visible = 1;
                    Format = 'nhf';
                    app.ImageInfo.n = 1;
                    updateVideoTools(app)
                    try
                        [im, info] = open_NHF(imageName,app.ChannelDropDown.Value);
                    catch
                        [im, info] = open_NHF(imageName,'Topography');
                    end

                    app.ImageInfo.ScanSize = double(info.ScanSize)./1e-9;
                    app.ImageInfo.yPixels = double(info.yPixel);
                    app.ImageInfo.xPixels = double(info.xPixel);
                    app.ImageInfo.PixelPerNm = app.ImageInfo.xPixels./app.ImageInfo.ScanSize;
                    app.ImageInfo.ScanSpeed = 1/double(info.frameAcqTime);
                    app.ImageInfo.LineSpeed = double(info.yPixel).*app.ImageInfo.ScanSpeed;
                    app.ImageInfo.Channel = info.channel;
                    app.ImageInfo.Channels = info.channels;
                    app.ChannelDropDown.Items = app.ImageInfo.Channels;
                    updateTable(app)
                    im = im.*1e9;
                    app.ImageInfo.time =0;
                elseif strcmp(filetype,'gwy')
                    app.ChannelDropDown.Visible = 1;
                    Format = 'gwy';
                    app.ImageInfo.n = 1;
                    updateVideoTools(app)

                    [img, info] = open_gwychannel(imageName);
                    app.ImageInfo.ScanSize = double(info.xreal)./1e-9;
                    app.ImageInfo.yPixels = double(info.yres);
                    app.ImageInfo.xPixels = double(info.xres);
                    app.ImageInfo.PixelPerNm = app.ImageInfo.xPixels./app.ImageInfo.ScanSize;
                    app.ImageInfo.ScanSpeed = 0;
                    app.ImageInfo.LineSpeed = 0;

                    app.ImageInfo.Channels = (info.channels);
                    app.ChannelDropDown.Items = app.ImageInfo.Channels;
                    searchCh = app.ChannelDropDown.Value;
                    app.ImageInfo.Channel = searchCh;
                    rowNumber = strcmp(app.ChannelDropDown.Items, searchCh);
                    im = img{rowNumber};
                    updateTable(app)
                    im = im.*1e9;
                    app.ImageInfo.time =0;
                elseif strcmp(filetype,'png') || strcmp(filetype,'jpeg')
                    Format = 'RGB';
                    app.ImageInfo.n = 1;
                    updateVideoTools(app)
                    im = imread(imageName);
                    im = double(rgb2gray(im));
                    info = imfinfo(imageName);
                    app.ImageInfo.xPixels = info(1).Width;
                    app.ImageInfo.yPixels  = info(1).Height;
                    app.ImageInfo.LineSpeed = 0;
                    app.ImageInfo.ScanSpeed = 0;
                    app.ImageInfo.ScanSize = 0;
                    app.ImageInfo.PixelPerNm = 0;
                    app.ImageInfo.Channel = 'RGB';
                    updateTable(app)
                    app.ImageInfo.time =0;
                elseif strcmp(filetype,'jpk')
                    Format = 'jpk';
                    app.ImageInfo.n = 1;
                    updateVideoTools(app)
                    app.ChannelDropDown.Visible = 1;
                    [info, Channels] = open_JPK_info(imageName);
                    app.ImageInfo.Channels = strcat({Channels.Channel_name}', ' - ', {Channels.Trace_type}');
                    app.ChannelDropDown.Items = app.ImageInfo.Channels;

                    searchCh = app.ChannelDropDown.Value;
                    app.ImageInfo.Channel = searchCh;
                    rowNumber = strcmp(app.ChannelDropDown.Items, searchCh);
                    temp = 1:numel(rowNumber);
                    ch = temp(rowNumber);

                    im(:,:,:) = 10*flip(-open_JPK_image(imageName,ch+1));
                    app.ImageInfo.LineSpeed = info.Scan_Rate;
                    app.ImageInfo.ScanSize = round(info.x_scan_length*1e9);
                    app.ImageInfo.yPixels = info.y_scan_pixels;
                    app.ImageInfo.xPixels = info.x_scan_pixels;
                    app.ImageInfo.PixelPerNm = app.ImageInfo.xPixels./app.ImageInfo.ScanSize;
                    app.ImageInfo.ScanSpeed = app.ImageInfo.LineSpeed./app.ImageInfo.yPixels.*(1.*(app.BidirectionaldataCheckBox.Value)+1);
                    updateTable(app)
                    app.ImageInfo.time =0;
                else
                    fid = fopen(imageName,'r');
                    line = fgets(fid);
                    fclose(fid);
                    if      length(strfind(line,'\*File list'))>=1
                        Format = 'NanoScope';
                        app.ErrorButton.Visible = 1;
                        app.ChannelDropDown.Visible = 1;
                        try
                            [im, info] = open_nanoscope(imageName,app.ChannelDropDown.Value,app.ErrorButton.Value);
                        catch
                            try
                                [im, info] = open_nanoscope(imageName,'Height',app.ErrorButton.Value);
                            catch
                                [im, info] = open_nanoscope(imageName,'Height Sensor',app.ErrorButton.Value);
                            end
                        end
                        app.ImageInfo.ScanSize = info.ScanSize;
                        app.ImageInfo.xPixels = size(im,2);
                        app.ImageInfo.yPixels = info.line_no;
                        app.ImageInfo.PixelPerNm = app.ImageInfo.xPixels./app.ImageInfo.ScanSize;
                        app.ImageInfo.ScanSpeed = 1/(info.line_no.*info.Scan_Rate);
                        app.ImageInfo.LineSpeed = 1/info.Scan_Rate;
                        app.ImageInfo.n = 1;
                        app.ImageInfo.Channels = info.channels;
                        app.ChannelDropDown.Items = app.ImageInfo.Channels;
                        app.ImageInfo.Channel = app.ChannelDropDown.Value;
                        updateTable(app)
                        app.ImageInfo.time =0;
                    else
                        Format = 'Unknown';
                    end
       
                end

            end

            if strcmp(Format,'Unknown')
                im = [];
                app.ImageInfo.n = 0;
                rectangle(app.loadbar, 'Position', [0, 0, 1, 1], 'FaceColor', 'red');
                text(app.loadbar, 0.5, 0.5, 'No images loaded', 'HorizontalAlignment', 'center', 'VerticalAlignment', 'middle');
            else
                updateVideoTools(app)
                app.ImageName = app.FilesListBox.Value;
                im = single(im - mean(im(:),'omitnan'));
            end

            app.stop =1;
            app.CancelButton.Visible = 0;
        end

        function run_filter(app)
            app.filter_full=0;
            app.ImagePreview = filter(app);
            updatePlot(app,'normal')
            updatePlot2(app,'normal')
        end

       
        function result = filter(app)
            if app.filter_already == 0
                frame = round(app.Spinner.Value);
                switch  app.FramePartSwitch.Value
                    case 'Frames'
                        img = app.ImageTarget;
                    case 'Particles'
                        img = app.Part.Image;
                end
                result = img(:,:,frame);
                if app.filter_full==1
                    loadn = numel(img(1,1,:));
                    filter_frames = 1:loadn;
                    app.filter_already =1;
                else
                    filter_frames = frame;
                end

                sw = app.AutoflattenDropDown.Value;
                switch sw
                    case '11plane-line0'
                        for i = filter_frames
                            prev = img(:,:,i);
                            prev = filter_img_v2(prev,1,1,'plane');
                            prev = filter_img_v2(prev,0,0,'med_line');
                            result(:,:,i) = prev;
                        end

                    case '(plane11-line0) x2 (fit)'
                        for i = filter_frames
                            prev = img(:,:,i);
                            prev= filter_img_v2(prev,1,1,'plane');
                            prev= filter_img_v2(prev,0,0,'med_line');
                            result(:,:,i) = prev;
                        end
                        fo = fitoptions('method','NonlinearLeastSquares');
                        t = result(:,:,:);
                        [hy, x]=  hist(double(t(:)),100);
                        gfit  = fit(double(x'),hy','gauss1',fo);
                        max = gfit.b1 +1.5*gfit.c1;
                        min = gfit.b1 -1.5*gfit.c1;

                        for i = filter_frames
                            prev = result(:,:,i);
                            imgt = thresholder(prev,'histogram',[min, max],app.InvertMask.Value);
                            prev = filter_img_v2(prev ,1,1,'plane',imgt);
                            prev = filter_img_v2(prev ,0,0,'med_line',imgt);
                             result(:,:,i) = prev;
                        end


                    case 'Plane11 + Otsu Plane22'
                        for i = filter_frames
                            prev = img(:,:,i);
                            prev = filter_img_v2(prev,1,1,'plane');
                            imgt = thresholder(prev,'otsu',[],app.InvertMask.Value);
                            prev = filter_img_v2(prev,2,2,'plane',imgt);
                            result(:,:,i) = prev;
                        end
                    case 'Line1 + Otsu Line2'
                        for i = filter_frames
                            prev = img(:,:,i);
                            prev = filter_img_v2(prev,1,0,'line');
                            imgt = thresholder(prev,'otsu',[],app.InvertMask.Value);
                            prev = filter_img_v2(prev,2,0,'line',imgt);
                            result(:,:,i) = prev;
                        end
                    case {'iterative holes', 'iterative peaks'}

                        switch sw
                            case 'iterative holes'
                                h = 0;
                                p = Inf;
                            case 'iterative peaks'
                                h = -Inf;
                                p = 0;
                        end

                        for i = filter_frames
                            prev = img(:,:,i);
                            prev= filter_img_v2(prev,2,2,'plane');
                            prev= filter_img_v2(prev,0,0,'med_line');
                            result(:,:,i) = prev;
                        end
                        fo = fitoptions('method','NonlinearLeastSquares');
                        t = result(:,:,:);
                        [hy, x]=  hist(double(t(:)),100);
                        gfit  = fit(double(x'),hy','gauss1',fo);
                        max = (gfit.b1 +1.5*gfit.c1)+p;
                        min = (gfit.b1 -1.5*gfit.c1)+h;

                        for i = filter_frames
                            prev = result(:,:,i);
                            imgt = thresholder(prev,'histogram',[min, max],app.InvertMask.Value);
                            prev = filter_img_v2(prev ,2,2,'plane',imgt);
                            prev = filter_img_v2(prev ,0,0,'med_line',imgt);
                             result(:,:,i) = prev;
                        end

                        fo = fitoptions('method','NonlinearLeastSquares');
                        t = result(:,:,:);
                        [hy, x]=  hist(double(t(:)),100);
                        gfit  = fit(double(x'),hy','gauss1',fo);
                        max = (gfit.b1 +1.5*gfit.c1)+p;
                        min = (gfit.b1 -1.5*gfit.c1)+h;

                        for i = filter_frames
                            prev = result(:,:,i);
                            imgt = thresholder(prev,'histogram',[min, max],app.InvertMask.Value);
                            prev = filter_img_v2(prev ,2,2,'plane',imgt);
                            prev = filter_img_v2(prev,1,0,'line',imgt);
                             result(:,:,i) = prev;
                        end

                    case 'Off'
                        for i = filter_frames
                            prev = img(:,:,i);
                            result(:,:,i) = prev;
                        end
                end
                polyx = app.XPlaneSpinner.Value;
                polyy = app.YPlaneSpinner.Value;
                xline = app.XLineSpinner.Value;
                yline = app.YLineSpinner.Value;

                switch app.ThresholdContrastSwitch.Value
                    case 'Z-scale'
                        high_thresh = app.SliderMax.Limits(2);
                        low_thresh = app.SliderMin.Limits(1);
                    case 'Mask'
                        if app.MaskwithzscaleCheckBox.Value
                            high_thresh =  app.ContrastMaxSpinner.Value;
                            low_thresh =  app.ContrastMinSpinner.Value;
                        else
                            high_thresh = app.SliderMax.Value;%/100.*(max_all-min_all)+min_all;
                            low_thresh = app.SliderMin.Value;%/100.*(max_all-min_all)+min_all;
                        end
                end

                for i = filter_frames
                    prev = result(:,:,i);
                    imgt = thresholder(prev,app.ThresholdMethod.Value,[low_thresh,high_thresh],app.InvertMask.Value);
                    if isfield(app.Mask,'ROI')
                        s = any(app.Mask.ROI);
                    else
                        s(1)=0;
                    end
                    if app.ROIThresholdButton.Value == 1 && s(1)==1
                        imgt = thresholder(app.Mask.ROI.*imgt,'selection',[low_thresh,high_thresh],app.InvertMask.Value);
                    end

                    try
                    app.Mask.Mask(:,:,i) = imgt;
                    catch 
                    end
                    
                    if  polyx > 0 || polyy > 0
                        prev = filter_img_v2(prev,polyx,polyy,'plane',imgt);
                    end

                    if  xline > 0  || yline > 0   %x y line noise removal
                        prev = filter_img_v2(prev,xline,yline,'line', imgt);
                    end

                    if app.MedianCheckBox.Value==1
                        prev = filter_img_v2(prev,0,0,'med_line',imgt);
                    end

                    if app.MeanPlaneCheckBox.Value ==1
                        prev = filter_img_v2(prev,0,0,'mean_plane',imgt);
                    end

                    if  app.FixLineShift.Value > 0
                        switch app.FixShiftMethod.Value
                            case 'Line peaks'
                                [app.FixShiftValue.Value, prev] = LineShift(prev,'peaks');
                            case 'Line correlation'
                                [app.FixShiftValue.Value, prev] = LineShift(prev,'ccr');
                            case 'Manual Shift'
                                [app.FixShiftValue.Value, prev] = LineShift(prev,app.FixShiftValue.Value);
                        end
                    end

                    switch app.ImgFilterType.Value
                        case 'Gaussian'
                            if app.ImgFilterStrength.Value >0
                                prev = imgaussfilt(prev,app.ImgFilterStrength.Value);
                            end
                        case 'Median'
                            if  app.ImgFilterStrength.Value>2 || app.ImgFilterStrength_2.Value>2
                                prev = medfilt2(prev, [round(app.ImgFilterStrength_2.Value), round(app.ImgFilterStrength.Value)]);
                                %prev = colfilt(prev, [app.ImgFilterStrength_2.Value, app.ImgFilterStrength.Value;], 'sliding', @(x) median(x(:)));
                            end
                        case 'Mean'
                            if  app.ImgFilterStrength.Value>2 || app.ImgFilterStrength_2.Value>2
                                kernel_size_x = round(app.ImgFilterStrength.Value);
                                kernel_size_y = round(app.ImgFilterStrength_2.Value);
                                mean_kernel = ones(kernel_size_y, kernel_size_x) / (kernel_size_x * kernel_size_y);
                                prev = imfilter(prev, mean_kernel);
                            end
                        case 'Non-local mean'
                            if app.ImgFilterStrength.Value >0
                                prev =  imnlmfilt(prev,'DegreeOfSmoothing',app.ImgFilterStrength.Value);
                            end
                            % case 'Deconvolution'
                            %     if  app.ImgFilterStrength.Value>1 || app.ImgFilterStrength_2.Value>0
                            %         if numel(app.ImageInfo.ScanSize)>1
                            %             fr = round(app.FrameSlider.Value);
                            %             switch  app.FramePartSwitch.Value
                            %                 case 'Particles'
                            %                     fr = app.Part.Locs((app.Part.Locs(:,6)==fr),5);
                            %             end
                            %         else
                            %             fr=1;
                            %         end
                            %
                            %         tip_radius = app.ImgFilterStrength.Value.*app.ImageInfo.PixelPerNm(fr);
                            %         matrix_size = round(2*tip_radius);
                            %         [x, y] = meshgrid(-(matrix_size-1)/2:(matrix_size-1)/2, -(matrix_size-1)/2:(matrix_size-1)/2);
                            %         radius = sqrt(x.^2 + y.^2);
                            %         psf = tip_radius * (1 - (radius / tip_radius).^2);
                            %         psf(psf < 0) = 0;
                            %         prev = deconvwnr(prev, psf, 1/app.ImgFilterStrength_2.Value);
                            %     end

                        case 'Sphere Deconvolution'
                            if  app.ImgFilterStrength.Value>0
                                P = app.ImgFilterStrength.Value+1;
                                offs = round(P)- P;
                                [hx,hy] = ndgrid(-(P):(P));
                                hx = hx-offs;
                                hy = hy-offs;
                                H = real(sqrt((-(hx.^2)-(hy.^2)+P^2)));
                                H=H./(sum(H(:)));
                                if offs>0
                                    H = H(2:end , 2:end);
                                end
                                prev = deconvlucy(prev,H,app.ImgFilterStrength_2.Value);
                            end
                        case 'Top Hat'
                            if app.ImgFilterStrength.Value >1
                                se = strel('disk',round(app.ImgFilterStrength.Value));
                                prev = imtophat(prev,se);
                            end
                        case 'High-pass'
                            if app.ImgFilterStrength.Value >0
                                [mx, ny] =size(prev);
                                ft = fft2(prev);
                                f_shift = fftshift(ft);
                                ps = mx/2;
                                qs = ny/2;
                                for ii=1:mx
                                    for jj=1:ny
                                        distance = sqrt((ii-ps)^2+(jj-qs)^2);
                                        low_filter(ii,jj) = 1-exp(-(distance)^2/(2*(app.ImgFilterStrength.Value.^2)));
                                    end
                                end
                                filter_apply = f_shift.*low_filter;
                                image_orignal = ifftshift(filter_apply);
                                prev = abs(ifft2(image_orignal));
                            end
                        case 'Fill Mask'
                            M = isnan(imgt);
                            prev = regionfill(prev,M);
                        case 'Scar Fill'
                            prev = scar_fill(prev,app.ImgFilterStrength.Value, app.ImgFilterStrength_2.Value, app.ImgFilterStrength_3.Value);

                        otherwise
                    end
                    if app.SubtractmodeCheckBox.Value
                         result(:,:,i) =  result(:,:,i) -prev;
                    else
                        result(:,:,i) = prev;
                    end
                end

            else
                result = app.ImagePreview;
            end
            result(isnan(result)) = 0;
        end

        function cleanMask(app,settings)
            M = isnan(app.Mask.Mask);

            if settings.mingrain>0
                M = bwareaopen(M, settings.mingrain);
            end

            if settings.Dilate>0
                se = strel('disk',settings.Dilate);
                M = imdilate(M, se);
            end

            if settings.Erode>0
                se = strel('disk',settings.Erode);
                M = imerode(M, se);
            end

            if settings.Close>0
                se = strel('disk',settings.Close);
                M = imclose(M, se);
            end

            if settings.Open>0
                se = strel('disk',settings.Open);
                M = imopen(M, se);
            end

            if settings.Fill
                imfill((M),"holes");
            end

            app.Mask.Mask = M.*1;
            app.Mask.Mask(app.Mask.Mask  == 1) = NaN;
            app.Mask.Mask(app.Mask.Mask  == 0) = 1;
            updatePlot(app,'normal')

        end


        function AnalyzeMask(app,settings)
            M = isnan(app.Mask.Mask);
            clear app.Mask.Analysis app.Mask.Frame_Analysis

            [app.Mask.Analysis,  app.Mask.Frame_Analysis] = AnalyzeAreas(M, app.ImageTarget, settings.Analyze, 1./app.ImageInfo.PixelPerNm, app.ImageInfo.time,app.ImageInfo.ScanSpeed);
            app.PopupApp.Plot = plotter();
        end

        function ReshapeImage(app,settings)
            sz = size(app.ImageTarget);
            if settings.pixelsy ~= sz(1) || settings.pixelsx ~= sz(2)
                app.ImageTarget = imresize(app.ImageTarget, [settings.pixelsy, settings.pixelsx], settings.method);
                app.ImageInfo.PixelPerNm = app.ImageInfo.PixelPerNm.*settings.pixelsx/sz(2);
            end
            if settings.rotate >0
                app.ImageTarget = imrotate(app.ImageTarget,settings.rotate, settings.method,'crop');
                 app.Mask.Mask = [];
            end

           if settings.Scaleh ~=1
                app.ImageTarget = settings.Scaleh.*app.ImageTarget;
                 app.Mask.Mask = [];
           end
           app.ViewMask.Value = 0;
ViewMaskValueChanged(app)
            updatePlot(app,'normal')
            updatePlot2(app,'normal')
             T = app.ImageTarget;
            min_all = double(min(T,[],'all'));
            max_all = double(max(T,[],'all'));
            app.SliderMax.Limits = [min_all(1), max_all(1)];
            app.SliderMin.Limits = [min_all(1), max_all(1)];
            app.SliderMax.Value = max_all;
            app.SliderMin.Value = min_all;
            updateHist(app)
            storedata(app)

        end


        function updateTable(app)
            try
                if numel(app.ImageInfo.PixelPerNm)>1
                    fr = round(app.FrameSlider.Value);
                    switch  app.FramePartSwitch.Value
                        case 'Particles'
                            fr = app.Part.Locs((app.Part.Locs(:,6)==fr),5);
                    end
                end
                if numel(app.ImageInfo.PixelPerNm)>1
                    app.UITable.Data(7,2) = num2cell(app.ImageInfo.PixelPerNm(fr));
                else
                    app.UITable.Data(7,2) = num2cell(app.ImageInfo.PixelPerNm);
                end

                if numel(app.ImageInfo.ScanSize)>1
                    app.UITable.Data(2,2) = num2cell(app.ImageInfo.ScanSize(fr));
                else
                    app.UITable.Data(2,2) = num2cell(app.ImageInfo.ScanSize);
                end

                app.UITable.Data(1,2) = num2cell(app.ImageInfo.n);

                app.UITable.Data(3,2) = num2cell(app.ImageInfo.ScanSpeed);
                app.UITable.Data(4,2) = num2cell(app.ImageInfo.LineSpeed);
                app.UITable.Data(5,2) = num2cell(round(app.ImageInfo.yPixels));
                app.UITable.Data(6,2) = num2cell(round(app.ImageInfo.xPixels));
                app.UITable.Data(8,2) = cellstr(app.ImageInfo.Channel);
            catch
            end
            
        end


        function updateResultsTable(app,type)
            switch type
                case 'Line Profile'
                    Parameter =  {'Length','PeakHeight','FWHM','Max z','Min z','diff z','RMS'};
                    Value = [num2cell([0, 0, 0, 0, 0 ,0,0])];
                    app.UITable2.Data = [Parameter; Value];
                    app.UITable2.ColumnWidth = 'auto';

                    for jj=1:numel(app.LineROI)
                        x = single(app.LineROI(jj).Profile(:,1));
                        z = single(app.LineROI(jj).Profile(:,2));
                        app.UITable2.Data(jj+1,1) = num2cell(round(app.LineROI(jj).Profile(end,1),2));
                        if numel(z)>4 && app.FWHMCheckBox.Value ==1
                            [~, ~, wds, p] = findpeaks(z,x,'MinPeakProminence',0.1);
                            if any(p)
                                [p,ID] = max(p);
                                w = wds(ID);
                                app.UITable2.Data(jj+1,2) = num2cell(round(p,2));
                                app.UITable2.Data(jj+1,3) = num2cell(round(w,2));
                            else
                                app.UITable2.Data(jj+1,2) = num2cell(0);
                                app.UITable2.Data(jj+1,3) = num2cell(0);
                            end
                        end
                        if numel(z)>4
                            app.UITable2.Data(jj+1,4) = num2cell(round(max(z),2));
                            app.UITable2.Data(jj+1,5) = num2cell(round(min(z),2));
                            app.UITable2.Data(jj+1,6) = num2cell(round(max(z)-min(z),2));
                            app.UITable2.Data(jj+1,7) = num2cell(round(std(z),5));
                        end
                    end
            end
        end


        function TurnOffButtons(app,type)
            buttons = {'app.Point_roi_Button', 'app.Line_roi_Button', 'app.Sq_roi_Button', 'app.Sq_roi_Button_2','app.Circ_roi_Button',  'app.ROIThresholdButton','app.ZoomIn'};
            numButtons = numel(buttons);
            for i = 1:numButtons
                if strcmp(type,buttons{i})
                    f = eval(buttons{i});
                     if f.Value==1
                      f.BackgroundColor = [0.15 0.15 0.15];
                     else
                         f.BackgroundColor = [1 1 1];
                     end
                else
                    f = eval(buttons{i});
                    f.Value=0;
                    f.BackgroundColor = [1 1 1];
                end
            end

            if app.Line_roi_Button.Value ==1
                app.UITable2.Visible = 1;
                app.LineProfilePanel.Visible = 1;
            else
                app.UITable2.Visible = 0;
                app.LineProfilePanel.Visible = 0;
            end

if app.ROIThresholdButton.Value
app.ROIThresholdButton.BackgroundColor = [0.15 0.15 0.15];
app.ROIThresholdButton.FontColor = [0 1 0];
else
app.ROIThresholdButton.BackgroundColor = [1 1 1];
app.ROIThresholdButton.FontColor = [1 0 0];
end


            if app.ZoomIn.Value ==1
                app.Settings.Zoom = zoom(app.UIAxes);
app.Settings.Zoom.Enable = 'on';
            else
                app.Settings.Zoom = zoom(app.UIAxes);
                app.Settings.Zoom.Enable = 'off';
                % zoom(app.UIAxes,'off');
                % app.UIAxes.Toolbar.Visible = 'off';
                % pan(app.UIAxes,'on')
                % pan(app.UIAxes,'off')
            end
        end

        function loading(app)
            app.stop = 0;
            app.ProcessingImage.Visible = 1;
            %   app.NanoLocz1_02Pre.Pointer = 'watch';
        end

        function finished(app)
            app.stop = 1;
            app.ProcessingImage.Visible = 0;
            % app.NanoLocz1_02Pre.Pointer = 'arrow';
        end


        function storedata(app)

            ch = contains({app.stored.ImageName},app.FilesListBox.Value);
            field1 = 'ImageName';  value1 = app.ImageName;
            field2 = 'Channel';  value2 = app.ImageInfo.Channel;
            field3 = 'Imagedata';  value3 = app.ImageTarget;
            field4 = 'ImageInfo';  value4 = app.ImageInfo;
            if isfield(app.Part,'Locs')
                field5 = 'ImageLocs';  value5 = app.Part;
            else
                field5 = 'ImageLocs';  value5 = [];
            end
            if isfield(app.ref,'position')
                field6 = 'ref';  value6 = app.ref;
            else
                field6 = 'ref';  value6 = [];
            end

            if    isfield(app.LAFM,'Locs')
                field7 = 'LAFMLocs';  value7 = app.LAFM;
            else
                field7 = 'LAFMLocs';
                value7 = [];
            end
            if sum(ch)==1
                app.stored(ch) = struct(field1,value1,field2,value2,field3, value3, field4, value4, field5, value5, field6, value6,field7,value7);
            else
                pos = numel(app.stored)+1;
                app.stored(pos) = struct(field1,value1,field2,value2,field3, value3, field4, value4, field5, value5, field6, value6,field7,value7);
            end
        end

        function importSimulation(app,image,pixpernm)
            sz = size(image);
            if size(image,3)>1
                reset_defaults(app)
                app.Spinner.Value = 1;
                app.FrameSlider.Value = 1;
                app.ImageTarget = image/10;
                app.ImageInfo.PixelPerNm = pixpernm;
                app.ImageInfo.ScanSize = sz(2)./app.ImageInfo.PixelPerNm;
                app.ImageInfo.xPixels = sz(2);
                app.ImageInfo.yPixels = sz(1);
                app.ImageInfo.Channel = 'Simulation';
                app.ImageInfo.Channels = {app.ImageInfo.Channel};
                app.ImageInfo.time = 0;
                app.ImageInfo.n = sz(3);
                updateTable(app)
                updateVideoTools(app)
                updatePlot(app,'normal')

                app.FilesListBox.Items = [app.stored.ImageName, app.ImageName];

                app.FilesListBox.Value = app.ImageName;
                storedata(app)
                app.FilesDropDown.Value = 'Processed';
                try
                    app.FilesListBox.Items = {app.stored.ImageName};
                catch
                    app.FilesListBox.Items = {'no files'};
                end
                
            else
            app.ref.simImage = image/10;
            app.ref.position = [0 0 size(app.ref.simImage,2) size(app.ref.simImage,1)];
            app.ref.type = 'Simulated';
            if sum(strcmp(app.RightPlotList.Items,'Ref Image')) <1
                app.RightPlotList.Items = [app.RightPlotList.Items , {'Ref Image'}];
            end
            app.RightPlotList.Value = 'Ref Image';
            if sum(strcmp(app.RefListBox.Items,'Simulated Reference')) <1
                app.RefListBox.Items = [app.RefListBox.Items , {'Simulated Reference'}];
            end
            app.RefListBox.Value = 'Simulated Reference';

            updateRightPlot(app);
            end

            drawnow;
        end


        function import_tiff(app,filename,folder)
            app.OpenFolder = folder;
            imageFiles = dir([app.OpenFolder]);
            imageFiles = imageFiles(~ismember({imageFiles(:).name},{'.','..','.DS_Store'}));
            app.FilesListBox.Items = {imageFiles.name};
            app.FilesListBox.Value = filename;
            updateFolder(app);
            im = Open_files(app);


            updatePlot2_type(app,'image')
            pause(0.000001)
            if any(im)
                app.Hist_excludeOut.Value=0;
                app.FrameSlider.Value = 1;
                app.Spinner.Value = 1;
                app.ImageTarget = single(im);
                min_all = double(min(app.ImageTarget,[],'all'));
                max_all = double(max(app.ImageTarget,[],'all'));
                app.SliderMax.Limits = [min_all, max_all];
                app.SliderMin.Limits = [min_all, max_all];
                app.SliderMax.Value = max_all;
                app.SliderMin.Value = min_all;
                app.filter_full=0;
                app.ImagePreview = filter(app);

                updatePlot(app,'normal')
                updateHist(app)
                updatePlot2(app,'normal')

                if app.View1stCheckBox.Value ==1 && app.View1st.Value < app.ImageInfo.n
                    loadn = app.View1st.Value;
                else
                    loadn = app.ImageInfo.n;
                end

                app.Max_h_Edit.Value =50;
                app.Min_h_Edit.Value =2;
                app.Peaks_minHeight.Value = app.Min_h_Edit.Value;

            end
            finished(app)
            set(app.UIAxes,'xlimMode', 'auto','ylimMode', 'auto')

            if app.AutoplayCheckBox.Value==1 && loadn>1
                PlayImageClicked(app, event)
            end

        end


        function LabelPosition(app,position,label)
            switch label
                case 'scalebar'
                    app.Settings.label.ScaleBar.position = position;
                case 'timestamp'
                    app.Settings.label.TimeStamp.position = position;
            end
            updatePlot(app,'normal')
        end

        function LabelColor(app,color,label)
            switch label
                case 'scalebar'
                    app.Settings.label.ScaleBar.color  = color ;
                case 'timestamp'
                    app.Settings.label.TimeStamp.color  = color ;
            end
            updatePlot(app,'normal')
        end


        function updateTrackList(app)
            n = max(app.Part.Locs(:,7));
            values = cellstr(num2str((1:n)', '%d'));
            app.TrackList.Items = values;

        end
    
    
        function UpdateLAFM_MovieTools(app)
            x = strcmp(app.RightPlotList.Value,'LAFM sym Movie') | strcmp(app.RightPlotList.Value,'LAFM Movie');
            app.LAFMEndImage.Visible = x;
            app.LAFMStartImage.Visible = x;
            app.LAFMPlayImage.Visible = x;
            app.LAFMPauseImage.Visible = x;
            app.LAFMFrameSlider.Visible = x;
            app.LAFMSpinner.Visible = x;
            app.LAFMTimescaleCheckBox.Visible = x;
            switch app.RightPlotList.Value
                case 'LAFM Movie'
                    n = numel(app.LAFM.Movie);
                    app.LAFMFrameSlider.Limits = [1 n];
                    app.LAFMSpinner.Limits =  app.LAFMFrameSlider.Limits;
                    app.LAFMFrameSlider.Value = 1;
                case 'LAFM sym Movie'
                    n = numel(app.LAFM.Movie_sym);
                    app.LAFMFrameSlider.Limits = [1 n];
                    app.LAFMSpinner.Limits =  app.LAFMFrameSlider.Limits;
                    app.LAFMFrameSlider.Value = 1;
            end
        end

        function previewOnOff(app)
            if    app.PreviewonoffButton.Value ==1
                app.ImagePreview = filter(app);
                app.LeftPlotList.Value = 'Preview';
                app.PreviewonoffButton.BackgroundColor = [0, 0, 0];
                app.PreviewonoffButton.FontColor = [0, 1, 0];
            else
                app.LeftPlotList.Value = 'Target';
                app.PreviewonoffButton.BackgroundColor = [1, 1, 1];
                app.PreviewonoffButton.FontColor = [1, 0, 0];
            end
        end
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            %Sets table values
           % movegui('center');
          %  screenSize = get(0, 'ScreenSize'); % Get screen size
            newPosition = [20, 20, 1311,702];
            set(app.NanoLocz, 'Position', newPosition);
              % addpath('./scripts');
              % addpath('./icons');
              % addpath('./Mat_SimAFM')
              % addpath('./GUI')
            app.ImageInfo=[];  app.Part.Image =[]; app.rect = []; app.ref = [];  app.img_locs = [];
            app.LAFM= []; app.PointROI=[]; app.LineROI=[];app.SquareROI=[];  app.patches.histup =[]; app.patches.histdown =[];

            app.Settings.MaskColor = 'green';
            app.Settings.MaskAlpha = 0.4;

            app.Settings.Align.SmoothDrift = 0 ;
            app.Settings.Align.SubPix = 0 ;
            app.Settings.Align.FilterCCR = 1;
            app.Settings.Align.FilterImg = 1;
            app.Settings.Align.Maxdrift = 0;
            app.Settings.Align.CroppingSwitch = 'Crop';

            Parameter = {'Num Imgs';'X-Range (nm)'; 'Speed (fps)';'Line/s (Hz)'; 'y pixels'; 'x pixels';'Pixel/nm';'Channel'};
            Value = [num2cell([0; 0; 0; 0; 0; 0; 0])];
            Value = [Value ; 'unknown'];
            app.UITable.Data = [Parameter Value];
            app.cmap = load('AFM_luts_full.mat');
            axis(app.UIAxes_2, 'tight','equal');
            axis(app.UIAxes, 'tight','equal');
            set(app.UIAxes_2,'visible','off')
            set(app.UIAxes,'visible','off')
            set(app.loadbar,'visible','off')
            set(app.UIAxes_2,'Color','k')
            set(app.UIAxes,'Color','k')
            app.HeightCCRHist.Toolbar.Visible = 'off';
            app.Hist_Axes.Toolbar.Visible = 'off';
            app.loadbar.Toolbar.Visible = 'off';

            xlim(app.loadbar,[0 1])

            app.UIAxes_2.Toolbar.Visible = 'on';
            app.UIAxes.Toolbar.Visible = 'off';
            colorbar(app.UIAxes_2,'off')
            colorbar(app.UIAxes,'off')
 
 
LoadstartupImg(app)


            min_all = double(min(app.ImageTarget,[],'all'));
            max_all = double(max(app.ImageTarget,[],'all'));
            app.SliderMax.Limits = [min_all, max_all];
            app.SliderMin.Limits = [min_all, max_all];
            app.SliderMax.Value = max_all;
            app.SliderMin.Value = min_all;
            updateHist(app)

            app.Spinner.Limits = [1 app.ImageInfo.n];
            app.FrameSlider.Limits = [1 app.ImageInfo.n];
            updatePlot(app,'normal')
            app.OpenFolder =pwd;
            updateFolder(app)
            app.PauseImage.Visible = 0;
            app.stop = 0;
            app.RightPlotList.Items = {'Off'};

            app.Settings.label.ScaleBar.color = 'w';
            app.Settings.label.ScaleBar.position = 'Bottom Right';
            app.Settings.label.LAFMScaleBar.color = 'w';
            app.Settings.label.LAFMScaleBar.position = 'Bottom Right';
            app.Settings.label.TimeStamp.color = 'w';
            app.Settings.label.TimeStamp.position = 'Top Left';
            app.LAFMPanel.Visible = 0;
            app.Settings.ExportSettings.color = 'black';
            app.Settings.ExportSettings.resolution = 300;
            app.FixLineShift.Visible = "off";
            app.FixShiftMethod.Visible = "off";
            app.FixShiftValue.Visible = "off";


            field1 = 'ImageName';  value1 = 'not a file';
            field2 = 'Channel';    value2 = 'not a file';
            field3 = 'Imagedata';  value3 = 0;
            field4 = 'ImageInfo';  value4 = app.ImageInfo;
            field5 = 'ImageLocs';  value5 = [];
            field6 = 'ref';        value6 = [];
            field7 = 'LAFMLocs';   value7 = [];

            app.stored = struct(field1,value1,field2,value2,field3, value3, field4, value4, field5, value5, field6, value6, field7, value7);

        end

        % Key press function: NanoLocz
        function NanoLoczKeyPress(app, event)

            switch  event.Key
                case 'return'
                    AcceptButtonImageClicked(app, event)
                case {'delete', 'backspace'}
                    BinImageClicked(app, event)
                case 'rightarrow'
                    if app.VideoPanel.Visible ==1
                        f = app.FrameSlider.Value+1;
                        if f >app.FrameSlider.Limits(2)
                            app.FrameSlider.Value = 1;
                        else
                            app.FrameSlider.Value = app.FrameSlider.Value+1;
                        end
                        app.Spinner.Value = round(app.FrameSlider.Value);
                        app.ImagePreview = filter(app);
                        updatePlot(app,'normal')
                        updatePlot2(app,'normal')
                    end
                case'leftarrow'
                    if app.VideoPanel.Visible ==1
                        f = app.FrameSlider.Value-1;
                        if f <1
                            app.FrameSlider.Value = app.FrameSlider.Limits(2);
                        else
                            app.FrameSlider.Value = app.FrameSlider.Value-1;
                        end
                        app.Spinner.Value = round(app.FrameSlider.Value);
                        app.ImagePreview = filter(app);
                        updatePlot(app,'normal')
                        updatePlot2(app,'normal')
                    end
                case'downarrow'
                    app.ZScaleDropBox.Value = 'Manual';
                    range = app.ContrastMaxSpinner.Value - app.ContrastMinSpinner.Value;
                    if strcmp(event.Key, 'downarrow') && ismember('shift', event.Modifier)
                        app.ContrastMaxSpinner.Value = app.ContrastMaxSpinner.Value+0.05*range;
                        app.ContrastMinSpinner.Value = app.ContrastMinSpinner.Value-0.05*range;
                    else
                        app.ContrastMinSpinner.Value = app.ContrastMinSpinner.Value-0.05*range;
                        app.ContrastMaxSpinner.Value = app.ContrastMaxSpinner.Value-0.05*range;
                    end
                    updatePlot(app,'normal')
                    updatePlot2(app,'normal')

                case'uparrow'
                    app.ZScaleDropBox.Value = 'Manual';
                    range = app.ContrastMaxSpinner.Value - app.ContrastMinSpinner.Value;
                    if strcmp(event.Key, 'uparrow') && ismember('shift', event.Modifier)
                        app.ContrastMaxSpinner.Value = app.ContrastMaxSpinner.Value-0.05*range;
                        app.ContrastMinSpinner.Value = app.ContrastMinSpinner.Value+0.05*range;
                    else
                        app.ContrastMinSpinner.Value = app.ContrastMinSpinner.Value+0.05*range;
                        app.ContrastMaxSpinner.Value = app.ContrastMaxSpinner.Value+0.05*range;
                    end
                    updatePlot(app,'normal')
                    updatePlot2(app,'normal')

                case'x'
                    if strcmp(event.Key, 'x') && ismember('shift', event.Modifier)
                        CropImageClicked(app, event)
                    end

            end

        end

        % Callback function: RestoreButton, View1st, View1stCheckBox
        function Reload(app, event)
           app.Stacker.Value = 'Stack off';
            ch = contains({app.stored.ImageName},app.FilesListBox.Value);
            if sum(ch)==1
                app.stored(ch) = [];
            end

            if strcmp(app.ImageName, 'LoadImg.tif')
                LoadstartupImg(app)
            else
                FilesListBoxValueChanged(app, event)
            end
            updatePlot(app,'normal')
            app.filter_already =0;
        end

        % Callback function: AutoflattenDropDown, FixLineShift, 
        % ...and 16 other components
        function Refresh_Filter(app, event)
            
            switch app.ImgFilterType.Value
                case 'Sliding Mean Frames'
                    if app.ImgFilterStrength.Value >1
                        app.ImagePreview = movmean(app.ImageTarget ,app.ImgFilterStrength.Value,3);
                        updatePlot(app,'normal')
                         app.filter_already =1;
                    end
                    
                otherwise
            app.filter_already =0;
            app.PreviewonoffButton.Value =1;
            previewOnOff(app)
            run_filter(app)
            end
        end

        % Value changed function: Spinner
        function SpinnerValueChanged(app, event)
            app.FrameSlider.Value = round(app.Spinner.Value);
            app.filter_full=0;
            switch app.LeftPlotList.Value
                case 'Preview'
                    app.ImagePreview = filter(app);
            end
            updatePlot(app,'normal')
            updatePlot2(app,'normal')
        end

        % Button pushed function: Forceto11Button
        function Forceto11ButtonPushed(app, event)

            sz = size(app.ImageTarget);
            newSz = max(sz(1:2));
            app.ImageTarget = imresize(app.ImageTarget, [newSz, newSz], 'bicubic');
            app.Mask.Mask = [];
            updatePlot(app,'normal')
            updatePlot2(app,'normal')
        end

        % Value changed function: ChannelDropDown, ErrorButton, 
        % ...and 1 other component
        function FilesListBoxValueChanged(app, event)
           try
            loading(app)
            app.Spinner.Value = 1;
            app.FrameSlider.Value = 1;

            switch app.Stacker.Value
                case 'Stack off'
                    reset_defaults(app)
                case {'Stack','Intercalate'}
                    tempinfo = app.ImageInfo;
            end

            ch = contains({app.stored.ImageName},app.FilesListBox.Value);
            if sum(ch)==1
                if numel(app.stored(ch).ImageInfo.Channels)>1
                    app.ChannelDropDown.Visible=1;
                    ch2 = contains({app.stored(ch).Channel},app.ChannelDropDown.Value);
                else
                    ch2 = 1;
                end
            else
                ch2=0;
            end

            if sum(ch)+ sum(ch2) ==2
            switch app.Stacker.Value
                case 'Stack off'
                    im = app.stored(ch).Imagedata;
                    app.ImageTarget = single(im);

                case {'Stack','Intercalate'}
                   im = app.stored(ch).Imagedata;
            end
               
                app.ImageName = app.stored(ch).ImageName;
                app.ImageInfo = app.stored(ch).ImageInfo;

                if isfield(app.stored(ch).ref,'position')
                    app.ref = app.stored(ch).ref;

                    if isfield(app.stored(ch).ref,'ROIimage')
                        app.RefListBox.Items = [app.RefListBox.Items , {'ROI Reference'}];
                    end
                    if isfield(app.stored(ch).ref,'simImage')
                        app.RefListBox.Items = [app.RefListBox.Items , {'Simulated Reference'}];
                    end
                    if isfield(app.stored(ch).ref,'LAFM')
                        app.RefListBox.Items = [app.RefListBox.Items , {'LAFM Reference'}];
                    end
                    if isfield(app.stored(ch).ref,'av')
                        app.RefListBox.Items = [app.RefListBox.Items , {'Averaged Reference'}];
                    end

                    if sum(strcmp(app.RightPlotList.Items,'Ref Image')) <1
                        app.RightPlotList.Items = [app.RightPlotList.Items , {'Ref Image'}];
                    end
                end

                if isfield(app.stored(ch).ImageLocs,'Locs')
                    app.Part = app.stored(ch).ImageLocs;
                    filter_Partlocs(app)
                    updateCCRHist(app)
                    app.Part.Image  = ConstructParticleStack2(app, app.ImageTarget, app.ref, app.Part);
                end

                if isfield(app.stored(ch).LAFMLocs,'Locs')
                    app.LAFM = app.stored(ch).LAFMLocs;
                    if sum(strcmp(app.RightPlotList.Items,'Localizations')) <1
                        app.RightPlotList.Items = [app.RightPlotList.Items , {'Localizations'}];
                    end
                    if isfield(app.stored(ch).LAFMLocs,'Image')
                        if sum(strcmp(app.RightPlotList.Items,'LAFM')) <1
                            app.RightPlotList.Items = [app.RightPlotList.Items , {'LAFM'}];
                        end
                    end      
                end
                

            else
                
                im = Open_files(app);
                                
            end

            updatePlot2_type(app,'image')
            pause(0.000001)
            
                app.Hist_excludeOut.Value=0;
                app.FrameSlider.Value = 1;
                app.Spinner.Value = 1;
                switch app.Stacker.Value
                    case 'Stack off'
                        app.ImageTarget = single(im);
                       
                    case 'Stack'
                        app.Mask = [];
                        try
                            app.ImageTarget = cat(3,app.ImageTarget,single(im));
                        catch
                            A = app.ImageTarget; B = single(im);
                            app.ImageTarget = pad_stacker(A,B);
                        end

                        if numel(tempinfo.PixelPerNm) ~= tempinfo.n
                            tempinfo.PixelPerNm(1:tempinfo.n) = tempinfo.PixelPerNm(1);
                        end
                        if numel(app.ImageInfo.PixelPerNm) ~= app.ImageInfo.n
                            app.ImageInfo.PixelPerNm(1:app.ImageInfo.n) = app.ImageInfo.PixelPerNm(1);
                        end

                        app.ImageInfo.n = size(app.ImageTarget,3);
                        app.ImageInfo.time = [tempinfo.time, app.ImageInfo.time];
                        app.ImageInfo.ScanSize = [tempinfo.ScanSize, app.ImageInfo.ScanSize];
      
                         app.ImageInfo.PixelPerNm = [tempinfo.PixelPerNm, app.ImageInfo.PixelPerNm];

                    case 'Intercalate'
                        app.Mask = [];
                        try
                            num_slices = size(app.ImageTarget,3);
                            for i = 1:num_slices
                                temp(:, :, i*2 - 1) = app.ImageTarget(:, :, i);
                                temp(:, :, i*2) = im(:, :, i);

                                tempinfo_2.time(i*2 - 1) = tempinfo.time(i);
                                tempinfo_2.time(i*2) = app.ImageInfo.time(i);
                                if numel(tempinfo.ScanSize)>1
                                    tempinfo_2.ScanSize(i*2 - 1) = tempinfo.ScanSize(i);
                                    tempinfo_2.ScanSize(i*2) = app.ImageInfo.ScanSize(i);
                                    tempinfo_2.PixelPerNm(i*2 - 1) = tempinfo.PixelPerNm(i);
                                    tempinfo_2.PixelPerNm(i*2) = app.ImageInfo.PixelPerNm(i);
                                end
                            end
                            app.ImageTarget = temp;
                            app.ImageInfo.n = size(app.ImageTarget,3);
                            app.ImageInfo.time = tempinfo_2.time;

                            if numel(tempinfo.ScanSize)>1
                                app.ImageInfo.ScanSize = tempinfo_2.ScanSize;
                                app.ImageInfo.PixelPerNm = tempinfo_2.PixelPerNm;
                            end
                        catch
                            errordlg('Images different pixel size', 'Error');
                            return
                        end
                end

                min_all = double(min(app.ImageTarget,[],'all'));
                max_all = double(max(app.ImageTarget,[],'all'));
                app.SliderMax.Limits = [min_all, max_all];
                app.SliderMin.Limits = [min_all, max_all];
                app.SliderMax.Value = max_all;
                app.SliderMin.Value = min_all;
                app.filter_full=0;
                app.ImagePreview = filter(app);

                updatePlot(app,'normal')
                updateHist(app)
                updatePlot2(app,'normal')

                if app.View1stCheckBox.Value ==1 && app.View1st.Value < app.ImageInfo.n
                    loadn = app.View1st.Value;
                else
                    loadn = app.ImageInfo.n;
                end

                app.Max_h_Edit.Value =50;
                app.Min_h_Edit.Value =2;
                app.Peaks_minHeight.Value = app.Min_h_Edit.Value;


            finished(app)
            updateVideoTools(app)
            updateTable(app)
            set(app.UIAxes,'xlimMode', 'auto','ylimMode', 'auto')

            if app.AutoplayCheckBox.Value==1 && loadn>1
                PlayImageClicked(app, event)
            end
           catch
           end
        end

        % Button pushed function: ZeroallButton
        function ZeroallButtonPushed(app, event)

            app.filter_already =0;
            app.FixLineShift.Value = 0;
            app.ImgFilterStrength.Value = app.ImgFilterStrength.Limits(1);
            app.ImgFilterStrength_2.Value = app.ImgFilterStrength_2.Limits(1);
            app.XLineSpinner.Value = 0;
            app.YLineSpinner.Value = 0;
            app.ThresholdMethod.Value = 'histogram';
            app.MedianCheckBox.Value = 0;
            app.XPlaneSpinner.Value = 0;
            app.YPlaneSpinner.Value = 0;
            app.SliderMax.Value = app.SliderMax.Limits(2);
            app.SliderMin.Value = app.SliderMax.Limits(1);

            app.filter_full=0;
            app.ImagePreview = filter(app);

            updatePlot(app,'normal')
            updatePlot2(app,'normal')
        end

        % Value changed function: ColourmapDropDown, 
        % ...and 16 other components
        function TrackLengthEditFieldValueChanged(app, event)
            updatePlot(app,'normal')
            updatePlot2(app,'normal')
        end

        % Value changed function: ViewMask
        function ViewMaskValueChanged(app, event)
            if app.ViewMask.Value ==1
                app.OverlayDropDown.Value = 'Mask';
                app.ViewMask.FontColor = [0 1 0];
                app.ViewMask.BackgroundColor = [0 0 0];
            else
                app.OverlayDropDown.Value = 'None';
                app.ViewMask.FontColor = [1 0 0];
                app.ViewMask.BackgroundColor = [1 1 1];
            end
            updatePlot(app,'normal')
        end

        % Button pushed function: AverageReferenceButton
        function AverageReferenceButtonPushed(app, event)
            try
                app.ref.av  = mean(app.Part.Image(:,:,app.Part.IncludeImages),3);
                app.RightPlotList.Value = 'Ref Image';
                if sum(strcmp(app.RefListBox.Items,'Averaged Reference')) <1
                    app.RefListBox.Items = [app.RefListBox.Items , {'Averaged Reference'}];
                end
                app.RefListBox.Value = 'Averaged Reference';
                updateRightPlot(app);

                storedata(app)
                updatePlot2(app,'normal')
            catch
            end
        end

        % Button pushed function: RotationalAvgButton
        function RotationalAvgButtonPushed(app, event)

            app.ref.av = rotation_sym(app.ref.image, app.symmetry.Value);
            storedata(app)
            app.RightPlotList.Value = 'Ref Image';
            if sum(strcmp(app.RefListBox.Items,'Averaged Reference')) <1
                app.RefListBox.Items = [app.RefListBox.Items , {'Averaged Reference'}];
            end
            app.RefListBox.Value = 'Averaged Reference';
            updatePlot2(app,'normal')

        end

        % Value changed function: LogYHist
        function LogYHistValueChanged(app, event)
            updateHist(app)
        end

        % Value changing function: SliderMax
        function SliderMaxValueChanging(app, event)
  
            delete(app.patches.histup)
            app.SliderMax.Value = event.Value;
            app.filter_already =0;
            run_filter(app)
            app.patches.histup = patch(app.Hist_Axes,[app.SliderMax.Limits(2) app.SliderMax.Value app.SliderMax.Value app.SliderMax.Limits(2)], [app.Hist_Axes.YLim(1), app.Hist_Axes.YLim(1),  app.Hist_Axes.YLim(2)  app.Hist_Axes.YLim(2)],...
                'r', 'FaceAlpha', 0.5,'EdgeColor','none');
            switch app.ThresholdContrastSwitch.Value
                case 'Z-scale'
                    app.ZScaleDropBox.Value = 'Histogram';
                case 'Mask'
                    high_thresh = app.SliderMax.Value;%/100.*(max_all-min_all)+min_all;
                    low_thresh = app.SliderMin.Value;%/100.*(max_all-min_all)+min_all;
                    imgt = thresholder(app.ImageTarget,app.ThresholdMethod.Value,[low_thresh,high_thresh],app.InvertMask.Value);
                    if isfield(app.Mask,'ROI')
                        s = any(app.Mask.ROI);
                    else
                        s(1)=0;
                    end
                    if app.ROIThresholdButton.Value == 1 && s(1)==1
                        imgt = thresholder(app.Mask.ROI.*imgt,'selection',[low_thresh,high_thresh],app.InvertMask.Value);
                    end
                    app.Mask.Mask = imgt;
            end

        end

        % Value changing function: SliderMin
        function SliderMinValueChanging(app, event)
            delete(app.patches.histdown)
            app.SliderMin.Value = event.Value;
            app.filter_already =0;
            run_filter(app)
            app.patches.histdown = patch(app.Hist_Axes,[app.SliderMin.Value app.SliderMin.Limits(1) app.SliderMin.Limits(1) app.SliderMin.Value], [app.Hist_Axes.YLim(1), app.Hist_Axes.YLim(1),  app.Hist_Axes.YLim(2)  app.Hist_Axes.YLim(2)],...
                'r', 'FaceAlpha', 0.5,'EdgeColor','none');

            switch app.ThresholdContrastSwitch.Value
                case 'Z-scale'
                    app.ZScaleDropBox.Value = 'Histogram';
                case 'Mask'
                    high_thresh = app.SliderMax.Value;%/100.*(max_all-min_all)+min_all;
                    low_thresh = app.SliderMin.Value;%/100.*(max_all-min_all)+min_all;
                    imgt = thresholder(app.ImageTarget,app.ThresholdMethod.Value,[low_thresh,high_thresh],app.InvertMask.Value);
                    if isfield(app.Mask,'ROI')
                        s = any(app.Mask.ROI);
                    else
                        s(1)=0;
                    end
                    if app.ROIThresholdButton.Value == 1 && s(1)==1
                        imgt = thresholder(app.Mask.ROI.*imgt,'selection',[low_thresh,high_thresh],app.InvertMask.Value);
                    end
                    app.Mask.Mask = imgt;
            end
        end

        % Image clicked function: PlayImage
        function PlayImageClicked(app, event)
            app.PlayImage.Visible=0;
            app.PauseImage.Visible=1;
            app.stop=0;
            currentFrame = round(app.FrameSlider.Value);

            if strcmp(app.PlotTypeDropDown.Value,'3D surface') || strcmp(app.PlotTypeDropDown.Value,'Coordinates')
                [app.Settings.viewangle(1), app.Settings.viewangle(2)]= view(app.UIAxes);
            end

            while true
                tic;
                if app.stop == 1
                    return
                end

                if currentFrame == app.FrameSlider.Limits(2) + 1
                    currentFrame = 1;
                end

                app.FrameSlider.Value = currentFrame;
                app.Spinner.Value = currentFrame;
                app.filter_full=0;

                switch app.LeftPlotList.Value
                    case 'Preview'
                        app.ImagePreview = filter(app);
                end

                updatePlot(app,'normal')

                if strcmp(app.PlotTypeDropDown.Value,'3D surface') || strcmp(app.PlotTypeDropDown.Value,'Coordinates')
                    view(app.UIAxes,app.Settings.viewangle(1), app.Settings.viewangle(2));
                end

           switch app.RightPlotList.Value
                case {'Off','Ref Image','Localizations','LAFM', 'LAFM_sym','Kymograph'}
                case 'Line Profile'
                   updatePlot2(app,'normal')
            end

                playtime = toc;
                pause(1/app.fps.Value-playtime)
                drawnow

                currentFrame = currentFrame + 1;
            end

            app.PauseImage.Visible=0;
            app.PlayImage.Visible=1;
        end

        % Image clicked function: PauseImage
        function PauseImageClicked(app, event)
            app.PlayImage.Visible=1;
            app.PauseImage.Visible=0;
            app.stop = 1;
        end

        % Image clicked function: StartImage
        function StartImageClicked(app, event)
            app.FrameSlider.Value = 1;
            app.Spinner.Value = app.FrameSlider.Value;
            app.filter_full=0;
            switch app.LeftPlotList.Value
                case 'Preview'
                    app.ImagePreview = filter(app);
            end
            updatePlot(app,'normal')
            updatePlot2(app,'normal')
        end

        % Image clicked function: EndImage
        function EndImageClicked(app, event)
            app.FrameSlider.Value = app.FrameSlider.Limits(2);
            app.Spinner.Value = app.FrameSlider.Value;
            app.filter_full=0;
            switch app.LeftPlotList.Value
                case 'Preview'
                    app.ImagePreview = filter(app);
            end
            updatePlot(app,'normal')
            updatePlot2(app,'normal')
        end

        % Image clicked function: BinRangeImage
        function BinRangeImageClicked(app, event)
            RemoveFrames = app.RemoveframesEditField.Value;
            RemoveFrames = strrep(RemoveFrames,'-',':');
            RemoveFrames = str2num(RemoveFrames);

            switch  app.FramePartSwitch.Value
                case 'Frames'
                    app.ImageTarget(:,:,RemoveFrames) = [];
                    app.ImageInfo.n = app.ImageInfo.n - size(RemoveFrames,2);
                     try
                    if numel(app.ImageInfo.ScanSize)>1
                        app.ImageInfo.ScanSize(RemoveFrames) =[];
                        app.ImageInfo.PixelPerNm(RemoveFrames) =[];
                    end
                        app.ImageInfo.time(RemoveFrames) =[];
                    catch
                    end
                case 'Particles'
                    pos = ismember(app.Part.Locs(:,6), RemoveFrames);
                    app.Part.Locs(pos,3:4) = nan;
                    filter_Partlocs(app)
            end

            updateVideoTools(app)

            updatePlot(app,'normal')
            updatePlot2(app,'normal')
            T = app.ImageTarget;
            min_all = double(min(T,[],'all'));
            max_all = double(max(T,[],'all'));
            app.SliderMax.Limits = [min_all(1), max_all(1)];
            app.SliderMin.Limits = [min_all(1), max_all(1)];
            app.SliderMax.Value = max_all;
            app.SliderMin.Value = min_all;
            updateHist(app)
            storedata(app)
        end

        % Image clicked function: BinImage
        function BinImageClicked(app, event)

            RemoveFrames = round(app.FrameSlider.Value);
            switch  app.FramePartSwitch.Value
                case 'Frames'
                    app.ImageTarget(:,:,RemoveFrames) = [];
                    app.ImageInfo.n = app.ImageInfo.n - 1;
                    try
                        if numel(app.ImageInfo.ScanSize)>1
                            app.ImageInfo.ScanSize(RemoveFrames) =[];
                            app.ImageInfo.PixelPerNm(RemoveFrames) =[];
                        end

                        app.ImageInfo.time(RemoveFrames) =[];
                    catch
                    end
                case 'Particles'
                    pos = app.Part.Locs(:,6)==RemoveFrames;
                    app.Part.Locs(pos,3:4) = nan;
                    filter_Partlocs(app)
            end
            updateVideoTools(app)
            app.Spinner.Value = round(app.FrameSlider.Value);
            updatePlot(app,'normal')
            updatePlot2(app,'normal')

            T = app.ImageTarget;
            min_all = double(min(T,[],'all'));
            max_all = double(max(T,[],'all'));
            app.SliderMax.Limits = [min_all(1), max_all(1)];
            app.SliderMin.Limits = [min_all(1), max_all(1)];
            app.SliderMax.Value = max_all;
            app.SliderMin.Value = min_all;
            updateHist(app)
            storedata(app)
        end

        % Value changing function: FrameSlider
        function FrameSliderValueChanging(app, event)

            app.Spinner.Value = round(event.Value);
            app.filter_full=0;
            switch app.LeftPlotList.Value
                case 'Preview'
                    app.ImagePreview = filter(app);
            end
            updatePlot(app,'normal')
            updatePlot2(app,'normal')
        end

        % Image clicked function: AcceptButtonImage
        function AcceptButtonImageClicked(app, event)
            app.filter_full=1;
            loading(app)
            pause(0.000001)
            app.ImagePreview = filter(app);
            switch  app.FramePartSwitch.Value
                case 'Frames'
                    app.ImageTarget = app.ImagePreview;
                case 'Particles'
                    app.Part.Image = app.ImagePreview;
            end

            app.LeftPlotList.Value = 'Target';
            updatePlot(app,'normal')
            app.filter_already =0;
            app.ImgFilterType.Value = 'Off';
            app.ImgFilterStrength.Value = app.ImgFilterStrength.Limits(1);
            app.ImgFilterStrength_2.Value = app.ImgFilterStrength_2.Limits(1);
            app.XLineSpinner.Value = 0;
            app.YLineSpinner.Value = 0;
            app.ThresholdMethod.Value = 'histogram';
            app.AutoflattenDropDown.Value = 'Off';
            app.MedianCheckBox.Value = 0;
            app.FixLineShift.Value = 0;
            app.XPlaneSpinner.Value = 0;
            app.YPlaneSpinner.Value = 0;
            if app.Hist_excludeOut.Value==1
                T = reshape(app.ImageTarget(:,:,:),1,[]);
                T = rmoutliers(T,"percentiles",[5 95]);
            else
                T = app.ImageTarget;
            end
            min_all = double(min(T,[],'all'));
            max_all = double(max(T,[],'all'));
            app.SliderMax.Limits = [min_all(1), max_all(1)];
            app.SliderMin.Limits = [min_all(1), max_all(1)];
            app.SliderMax.Value = max_all;
            app.SliderMin.Value = min_all;
            updateHist(app)
            finished(app)
            storedata(app)

        end

        % Value changed function: PreviewonoffButton
        function PreviewonoffButtonValueChanged(app, event)
            previewOnOff(app)
            updatePlot(app,'normal')
        end

        % Image clicked function: FolderUp
        function FolderUpImageClicked(app, event)

            app.OpenFolder = fileparts(app.OpenFolder);
            imageFiles = dir([app.OpenFolder]);
            imageFiles = imageFiles(~ismember({imageFiles(:).name},{'.','..','.DS_Store'}));
            app.FilesListBox.Items = {imageFiles.name};
        end

        % Image clicked function: FolderDown
        function FolderDownImageClicked(app, event)
            app.OpenFolder = [app.OpenFolder '/' app.FilesListBox.Value];
            imageFiles = dir([app.OpenFolder]);
            imageFiles = imageFiles(~ismember({imageFiles(:).name},{'.','..','.DS_Store'}));
            app.FilesListBox.Items = {imageFiles.name};
        end

        % Callback function
        function FilesListBoxDoubleClicked(app, event)
            app.OpenFolder = [app.OpenFolder '/' app.FilesListBox.Value];
            imageFiles = dir([app.OpenFolder]);
            imageFiles = imageFiles(~ismember({imageFiles(:).name},{'.','..','.DS_Store'}));
            app.FilesListBox.Items = {imageFiles.name};

        end

        % Value changed function: ColorBarCheckBox
        function ColorBarCheckBoxValueChanged(app, event)

            if app.ColorBarCheckBox.Value==1
                colorbar(app.UIAxes,'Color','w','Box','off','TickDirection','out')
            else
                colorbar(app.UIAxes,'off')
            end
        end

        % Button pushed function: AutoButton
        function AutoButtonPushed(app, event)
            switch app.ZScaleDropBox.Value
                case 'Max Min'
                    app.ZScaleDropBox.Value = 'Exc outliers';
                otherwise
                    app.ZScaleDropBox.Value = 'Max Min';
            end
            updatePlot(app,'normal')
            updatePlot2(app,'normal')
        end

        % Callback function: ContrastMaxSpinner, ContrastMinSpinner, 
        % ...and 1 other component
        function HoldButtonPushed(app, event)
            app.ZScaleDropBox.Value = 'Manual';

            if app.MaskwithzscaleCheckBox.Value
                delete(app.patches.histdown)
                delete(app.patches.histup)
                app.filter_already =0;
                run_filter(app)
                try
                app.patches.histdown = patch(app.Hist_Axes,[app.ContrastMinSpinner.Value app.SliderMin.Limits(1) app.SliderMin.Limits(1) app.ContrastMinSpinner.Value], [app.Hist_Axes.YLim(1), app.Hist_Axes.YLim(1),  app.Hist_Axes.YLim(2)  app.Hist_Axes.YLim(2)],...
                    'r', 'FaceAlpha', 0.5,'EdgeColor','none');
                app.SliderMin.Value = app.ContrastMinSpinner.Value;
                catch
                end
                try
                    app.patches.histup = patch(app.Hist_Axes,[app.SliderMax.Limits(2) app.ContrastMaxSpinner.Value app.ContrastMaxSpinner.Value app.SliderMax.Limits(2)], [app.Hist_Axes.YLim(1), app.Hist_Axes.YLim(1),  app.Hist_Axes.YLim(2)  app.Hist_Axes.YLim(2)],...
                        'r', 'FaceAlpha', 0.5,'EdgeColor','none');
                    app.SliderMax.Value = app.ContrastMaxSpinner.Value;
                catch
                end

                switch app.ThresholdContrastSwitch.Value
                    case 'Z-scale'
                        app.ZScaleDropBox.Value = 'Histogram';
                    case 'Mask'
                        high_thresh = app.ContrastMaxSpinner.Value;%/100.*(max_all-min_all)+min_all;
                        low_thresh = app.ContrastMinSpinner.Value;%/100.*(max_all-min_all)+min_all;
                        imgt = thresholder(app.ImageTarget,app.ThresholdMethod.Value,[low_thresh,high_thresh],app.InvertMask.Value);
                        if isfield(app.Mask,'ROI')
                            s = any(app.Mask.ROI);
                        else
                            s(1)=0;
                        end
                        if app.ROIThresholdButton.Value == 1 && s(1)==1
                            imgt = thresholder(app.Mask.ROI.*imgt,'selection',[low_thresh,high_thresh],app.InvertMask.Value);
                        end
                        app.Mask.Mask = imgt;
                end
            end

            updatePlot(app,'normal')
            updatePlot2(app,'normal')
        end

        % Button pushed function: ResetButton
        function ResetButtonPushed(app, event)

            app.LineROI = findobj(app.UIAxes, 'Tag', 'MyDrawPolygon'); %Find the Polygon currntly drawn.
            app.SquareROI= findobj(app.UIAxes, 'Tag', 'MyDrawPolygon'); %Find the Polygon currntly drawn.
            if ~isempty(app.PointROI)
                app.PointROI = [];
            end

            if ~isempty(app.LineROI)
                app.LineROI.delete();
            end

            if ~isempty(app.SquareROI)
                app.SquareROI.delete();
            end
            app.RightPlotList.Value='Off';
            RightPlotListValueChanged(app, event)
            if strcmp(app.AspectRatioSwitch.Value, '1:1')
                axis(app.UIAxes,'normal');
                   axis(app.UIAxes,'tight','equal');
                pbaspect(app.UIAxes,[1 1 1])
            else
                axis(app.UIAxes,'normal');
                axis(app.UIAxes,'tight','equal');
            end
            app.LineROI = [];
            updatePlot2(app,'normal')
            updatePlot(app,'normal')
        end

        % Value changed function: RightPlotList
        function RightPlotListValueChanged(app, event)

            switch app.RightPlotList.Value
                case {'Off','Ref Image','Localizations','LAFM', 'LAFM_sym','Kymograph'}
                    updatePlot2_type(app,'image')
                case 'Line Profile'
                    updatePlot2_type(app,'plot')
            end

            switch app.RightPlotList.Value
                case {'LAFM'}
                    app.LAFMMinSlider.Limits = [0, max(app.LAFM.Image(:))];
                    app.LAFMMaxSlider.Limits =  app.LAFMMinSlider.Limits;
            end

            switch app.RightPlotList.Value
                case 'Ref Image'
                    app.ReferenceTools.Visible = 1;

                    % if app.MultiReferenceCheckBox.Value == 1
                    %     app.ReferenceButtonGroup.Visible = 1;
                    % else
                        app.ReferenceButtonGroup.Visible = 0;
                    % end
                otherwise
                    app.ReferenceTools.Visible = 0;
            end
            updatePlot2(app,'normal')
        end

        % Button pushed function: VideoAlignButton
        function RunAlignButtonPushed(app, event)
            loading(app)
            app.img_locs = [];
            if app.View1stCheckBox.Value ==1 && app.View1st.Value < app.ImageInfo.n
                loadn = app.View1st.Value;
            else
                loadn = app.ImageInfo.n;
            end

            if isfield(app.ref,'position')
            else
                app.ref.position = [1, 1, size(app.ImageTarget,2),size(app.ImageTarget,1)];
                p =app.ref.position;
                app.ref.image =  imcrop(app.ImageTarget(:,:,round(app.FrameSlider.Value)), [p(1), p(2), p(3)-1, p(4)-1]);
            end
            refimg = app.ref;
            full_image = 0;

            if app.Settings.Align.FilterImg >0
                T = imgaussfilt(app.ImageTarget,app.Settings.Align.FilterImg);
            else
                T = app.ImageTarget;
            end

            [x, y] = Align_Frames(app, T, refimg, app.Settings.Align.Maxdrift, full_image,  app.Settings.Align.SubPix, app.Settings.Align.FilterCCR );

            if app.Settings.Align.SmoothDrift > 1
                x = smooth(x,app.Settings.Align.SmoothDrift);
                y = smooth(y,app.Settings.Align.SmoothDrift);
            end

            if app.Settings.Align.SubPix ==0
                x = round(x);
                y = round(y);
            end

            app.img_locs(:,1) = x;
            app.img_locs(:,2) = y;

            figure(10)
            plot(x,'x-')
            hold on
            plot(y,'v-')
            hold off

            sz = size(app.ImageTarget);

            switch app.Settings.Align.CroppingSwitch
                case 'Expand'
                    if min(y)<0
                        miny = round(min(y));
                    else
                        miny = 0;
                    end

                    if min(x)<0
                        minx = round(min(x));
                    else
                        minx = 0;
                    end

                    if max(y)>0
                        maxy = round(max(y));
                    else
                        maxy = 0;
                    end

                    if max(x)>0
                        maxx = round(max(x));
                    else
                        maxx = 0;
                    end
                    app.ImagePreview = zeros(sz(1)+maxy-miny,sz(2)+ maxx-minx,sz(3));
                    D = padarray(app.ImageTarget,[maxy maxx],0,'pre');
                    D = padarray(D,[-miny -minx],0,'post');
                    for i = 1:loadn
                        app.ImagePreview(:,:,i) = imtranslate(D(:,:,i), [-x(i), -y(i)] );
                    end

                case 'Crop'
                    app.ImagePreview = zeros(sz(1),sz(2), sz(3));
                    for i = 1:loadn
                        app.ImagePreview(:,:,i) = imtranslate(app.ImageTarget(:,:,i), [-x(i), -y(i)] );
                    end

            end
            app.img_locs(:,1) = x;
            app.img_locs(:,2) = y;
            app.filter_already = 1;
            app.PreviewonoffButton.Value =1;
             previewOnOff(app)
            app.Mask.Mask = [];
            updatePlot(app,'normal')
            finished(app)


        end

        % Value changed function: PeaksRegionsSwitch
        function PeaksRegionsSwitchValueChanged(app, event)

            switch app.PeaksRegionsSwitch.Value
                case 'ROI'
                    app.Peaks_minSep.Visible = "off";     app.MinseparationLabel.Visible = 'off';
                    app.Peaks_minHeight.Visible = "off";  app.MinheightLabel.Visible = 'off'; app.AutoProfileButton.Visible = 'off';
app.Min_CCR_Edit.Value = 0.5;
                    app.ReferenceTools.Visible = 1;
                    app.PreviewpeaksLabel.Visible=0;
                    app.PreviewPeaks.Visible=0;
                    app.FilterCCREditField_Detect.Visible = 1;
                    app.FilterxcorimageLabel.Visible =1;
                    app.BoxSizeEditField.Visible = 0;
                    app.BoxSizeEditFieldLabel.Visible = 0;

                    app.Height_corr_switch.Value = 'Correlation';
                    app.HeightCheckBox.Value=0;
                    app.CorrelationCheckBox.Value=1;
                    app.PreviewPeaks.Value=0;

                case 'Peaks'
                    app.Peaks_minSep.Visible = "on";  app.MinseparationLabel.Visible = 'on';
                    app.Peaks_minHeight.Visible = "on";  app.MinheightLabel.Visible = 'on'; app.AutoProfileButton.Visible = 'on';
app.Min_CCR_Edit.Value = -1;
                    app.Peaks_minHeight.Visible = 1;
                    app.ReferenceTools.Visible = 0;
                    app.PreviewpeaksLabel.Visible=1;
                    app.PreviewPeaks.Visible=1;
                    app.PreviewPeaks.Value=1;
                    app.FilterCCREditField_Detect.Visible = 0;
                    app.FilterxcorimageLabel.Visible =0;
                    app.BoxSizeEditField.Visible = 1;
                    app.BoxSizeEditFieldLabel.Visible = 1;
                    app.Height_corr_switch.Value = 'Height';
                    app.HeightCheckBox.Value=1;
                    app.CorrelationCheckBox.Visible=0;
                    updatePlot(app,'normal')

            end
        end

        % Button pushed function: RunTrackingButton, RunTrackingButton_2
        function RunTrackingButtonPushed(app, event)
            loading(app)
            tracking = track_particles(app.Part.Locs(app.Part.IncludeImages,1:2), app.Part.Locs(app.Part.IncludeImages,5),app.MaxstepEditField.Value, app.MaxmissingEditField.Value);
            counter=0;

            for i = 1:numel(app.Part.Locs(:,1))
                if ~isnan(app.Part.Locs(i,6))
                    counter=counter+1;
                    app.Part.Locs(i,7)= tracking(counter);
                else
                    app.Part.Locs(i,7)  = NaN;
                end
            end
            updatePlot(app,'normal')
            app.TrackLengthEditField.Value = 20;
            app.OverlayValues.Value = 'Track Number';
            finished(app)
            updateTrackList(app)
            storedata(app)
        end

        % Value changed function: Height_corr_switch
        function Height_corr_switchValueChanged(app, event)
          updateCCRHist(app)
            switch app.Height_corr_switch.Value
                case 'Height'
                    app.HeightCheckBox.Value=1;
                    app.CorrelationCheckBox.Value=0;
                    app.CorrelationCheckBox.Visible=0;
                case 'Correlation'
                    app.HeightCheckBox.Value=0;
                    app.CorrelationCheckBox.Value=1;
                    app.CorrelationCheckBox.Visible=1;
            end

        end

        % Value changing function: CCRSliderMax
        function CCRSliderMaxValueChanging(app, event)
            delete(app.patches.ccrhistup)
            app.CCRSliderMax.Value = event.Value;

            switch app.Height_corr_switch.Value
                case 'Height'
                    app.Max_h_Edit.Value = event.Value;
                case 'Correlation'
                    app.Max_CCR_Edit.Value = event.Value;
            end
            filter_Partlocs(app)
            updateVideoTools(app)
            updatePlot(app,'normal')
            updatePlot2(app,'normal')
            app.patches.ccrhistup = patch(app.HeightCCRHist,[app.CCRSliderMax.Limits(2) app.CCRSliderMax.Value app.CCRSliderMax.Value app.CCRSliderMax.Limits(2)], [app.HeightCCRHist.YLim(1), app.HeightCCRHist.YLim(1),  app.HeightCCRHist.YLim(2)  app.HeightCCRHist.YLim(2)],...
                'r', 'FaceAlpha', 0.5,'EdgeColor','none');

        end

        % Value changing function: CCRSliderMin
        function CCRSliderMinValueChanging(app, event)

            delete(app.patches.ccrhistdown )
            app.CCRSliderMin.Value = event.Value;
            switch app.Height_corr_switch.Value
                case 'Height'
                    app.Min_h_Edit.Value = event.Value;
                    app.Peaks_minHeight.Value = app.Min_h_Edit.Value;
                case 'Correlation'
                    app.Min_CCR_Edit.Value = event.Value;
            end
            filter_Partlocs(app)
            updateVideoTools(app)
            updatePlot(app,'normal')
            updatePlot2(app,'normal')
            app.patches.ccrhistdown = patch(app.HeightCCRHist,[app.CCRSliderMin.Limits(1) app.CCRSliderMin.Value app.CCRSliderMin.Value app.CCRSliderMin.Limits(1)], [app.HeightCCRHist.YLim(1), app.HeightCCRHist.YLim(1),  app.HeightCCRHist.YLim(2)  app.HeightCCRHist.YLim(2)],...
                'r', 'FaceAlpha', 0.5,'EdgeColor','none');

        end

        % Button pushed function: CenterReferenceButton
        function CenterReferenceButtonPushed(app, event)

            center_translation = FindCenterPositions(app.symmetry.Value,  app.ref.image, 10);
            app.ref.image = imtranslate(app.ref.image, -center_translation);

            switch cell2mat(app.RefListBox.Value)
                case 'ROI Reference'
                    app.ref.ROIimage =  app.ref.image;
                case 'Simulated Reference'
                    app.ref.simImage =  app.ref.image;
                case 'LAFM Reference'
                    app.ref.image = app.ref.LAFM;
                case 'Averaged Reference'
                    app.ref.av =  app.ref.image;
            end

            updatePlot2_type(app, 'image')
            updateRightPlot(app);

        end

        % Button pushed function: RunFineAlignButton
        function RunFineAlignButtonPushed(app, event)
            app.CancelButton.Visible = 1;
            loading(app)
            app.Part.FineAlign = [];
            app.ImagePreview=[];
            tran_count = 0;
            rot_count = 0;
            total_count = 0;

            img = app.Part.Image(:,:,app.Part.IncludeImages);
            cla(app.loadbar);
            n = numel(img(1,1,:));
            updateInterval = 5;
            %iterative loop starts
            while tran_count < app.Tran_IterationsEditField.Value || rot_count < app.Rot_IterationsEditField.Value

                if app.ThresholdwithminheightCheckBox.Value ==1
                    img = img.*(img>app.ContrastMinSpinner.Value);
                    rf = app.ref.image.*(app.ref.image>app.ContrastMinSpinner.Value);
                else
                    rf = app.ref.image;
                end


                if tran_count < app.Tran_IterationsEditField.Value
                    total_count = total_count +1;
                    tran_count = tran_count + 1;
                    [app.Part.FineAlign.x, app.Part.FineAlign.y] = FineAlign_Frames(app, img,rf, app.MaxdriftEditField_2.Value,1,1);
                    for i = 1:n
                        try
                        img(:,:,i) = imtranslate(img(:,:,i), [-app.Part.FineAlign.x(i), -app.Part.FineAlign.y(i)] );
                        catch
                        end
                    end
                    app.Part.Locs(app.Part.IncludeImages,1) = app.Part.Locs(app.Part.IncludeImages,1) + app.Part.FineAlign.x;
                    app.Part.Locs(app.Part.IncludeImages,2) = app.Part.Locs(app.Part.IncludeImages,2) + app.Part.FineAlign.y;
                end


                if rot_count < app.Rot_IterationsEditField.Value
                    total_count = total_count +1;
                    rot_count = rot_count + 1;
                    cla(app.loadbar);
                    stepsPerUpdate = ceil(n * (updateInterval / 100));
                    rectangle(app.loadbar, 'Position', [0, 0, 1, 1], 'FaceColor', 'none', 'EdgeColor', 'black');
                    progressBar = rectangle(app.loadbar, 'Position', [0, 0, 0, 1], 'FaceColor', 'green');
                    progressText = text(app.loadbar, 0.5, 0.5, '0%', 'HorizontalAlignment', 'center', 'VerticalAlignment', 'middle');

                    for i = 1:numel(img(1,1,:))
                        if app.stop == 1
                            return
                        end
                        app.Part.FineAlign.rot_ang(i,:) = ccr_rot_align(rf, img(:,:,i), [-app.searchangleEditField.Value, app.searchangleEditField.Value] ,1);
                        img(:,:,i)  = imrotate(img(:,:,i), app.Part.FineAlign.rot_ang(i), 'crop');

                        if mod(i, stepsPerUpdate) == 0 || i == n
                            progressBar.Position(3) = i / n;
                            progressText.String = sprintf([num2str(i),' frames rotated']);
                            drawnow;
                        end
                    end

                    app.Part.Locs(app.Part.IncludeImages,8) = app.Part.Locs(app.Part.IncludeImages,8) + app.Part.FineAlign.rot_ang;

                end

                app.Part.Image =  ConstructParticleStack2(app, app.ImageTarget, app.ref, app.Part);
                img = app.Part.Image(:,:,app.Part.IncludeImages);

                if app.AutoupdatereferenceimageCheckBox.Value ==1
                    app.ref.av = mean(img,3);   %update ref image
                    if sum(strcmp(app.RefListBox.Items,'Averaged Reference')) <1
                        app.RefListBox.Items = [app.RefListBox.Items , {'Averaged Reference'}];
                    end
                    app.RefListBox.Value = 'Averaged Reference';
                    updatePlot2(app,'normal')
                end
            end
            %iterative loop ends
            updatePlot(app,'normal')

            finished(app)
            storedata(app)
            app.CancelButton.Visible = 0;
        end

        % Button down function: FineAlignTab
        function FineAlignTabButtonDown(app, event)
            app.ViewMask.Value=0;
            app.OverlayDropDown.Value = 'None';

            set(app.UIAxes,'xlimMode', 'auto','ylimMode', 'auto')
            ViewMaskValueChanged(app)

            app.PreviewonoffButton.Value=0;
            previewOnOff(app)
            app.LoczImagepeaksCheckBox.Value =0;
            app.MarkersCheckBox.Value = 0;
            app.BoxesCheckBox.Value = 0;
            app.OverlayValues.Value = 'None';
            app.TrackLengthEditField.Value = 0;
            if isfield(app.Part,'IncludeImages')
            else
                sz = size(app.ImageTarget);
                if numel(sz)>2
                    app.Part.Locs(1:sz(3),1) = sz(2)/2+1;
                    app.Part.Locs(1:sz(3),2) = sz(1)/2+1;
                    app.Part.Locs(:,4) = ones(numel(app.Part.Locs(:,1)),1);
                    app.Part.Locs(1:sz(3),5) = 1:sz(3);
                    app.Part.Locs(1:sz(3),8) = 0;

                    app.ref.position(3) = sz(2);
                    app.ref.position(4) = sz(1);

                    app.Part.IncludeImages = app.Part.Locs(:,4) >0;
                    app.Part.Image  =  app.ImageTarget;
                end
            end


            if isfield(app.ref,'image')
            else
                if isfield(app.Part,'IncludeImages')
                    app.ref.image = app.Part.Image(:,:,round(app.FrameSlider.Value));
                    app.ref.frame = app.FrameSlider.Value;
                end
            end
            if isfield(app.Part,'IncludeImages')
                app.FramePartSwitch.Value = 'Particles';

                if sum(strcmp(app.RightPlotList.Items,'Ref Image')) <1
                    app.RightPlotList.Items = [app.RightPlotList.Items , {'Ref Image'}];
                end
                app.RightPlotList.Value='Ref Image';
                app.ReferenceTools.Visible = 1;
 
            end
            updateVideoTools(app)

               updatePlot2(app,'normal')
                updatePlot(app,'normal')
        end

        % Button pushed function: FindallpeaksButton
        function FindallpeaksButtonPushed(app, event)
            app.LAFM.Locs = [];
            app.CancelButton.Visible = 1;
            loading(app)
            pause(0.000001)

            switch  app.FramePartSwitch.Value
                case 'Frames'
                    img = app.ImageTarget;
                    frames = 1:numel(img(1,1,:));
                case 'Particles'
                    img = app.Part.Image(:,:,app.Part.IncludeImages);
                    frames = app.Part.Locs(app.Part.IncludeImages,5);
            end
            n = numel(img(1,1,:));
            if app.BoostsubpixCheckBox.Value
                img =  imresize(img, 3, 'bicubic');
                ex=3;
            else
                ex=1;
            end
            if strcmp(app.LoczHighpassDropDown.Value,'-Average')
                d_av = mean(img,3);
                for i = 1:n
                    prev(:,:,i) = filter_movie(img(:,:,i) , app.LoczLowpassDropDown.Value, app.LoczLowpass.Value*ex, app.LoczHighpassDropDown.Value,  app.LoczHighpass.Value,d_av);
                end
            else
                for i = 1:n
                    prev(:,:,i) = filter_movie(img(:,:,i) , app.LoczLowpassDropDown.Value, app.LoczLowpass.Value*ex, app.LoczHighpassDropDown.Value,  app.LoczHighpass.Value);
                end
            end

            updateInterval = 5;
            cla(app.loadbar)
            stepsPerUpdate = ceil(n * (updateInterval / 100));
            rectangle(app.loadbar, 'Position', [0, 0, 1, 1], 'FaceColor', 'none', 'EdgeColor', 'black');
            progressBar = rectangle(app.loadbar, 'Position', [0, 0, 0, 1], 'FaceColor', 'green');
            progressText = text(app.loadbar, 0.5, 0.5, '0%', 'HorizontalAlignment', 'center', 'VerticalAlignment', 'middle');
            for i = 1:numel(img(1,1,:))
                if app.stop == 1
                    return
                end
                locs = peaks2D( prev(:,:,i),app.LoczHeightSpinner.Value,app.LoczMinSep.Value,app.LoczProminenceSpinner.Value);
                s = any(locs);
                if s(1)==0
                else
                    try
                    locs_full = [locs,i*ones(numel(locs(:,1)),1),frames(i)*ones(numel(locs(:,1)),1),app.ImageInfo.time(frames(i))*ones(numel(locs(:,1)),1)];
                    catch
                        locs_full = [locs,i*ones(numel(locs(:,1)),1),frames(i)*ones(numel(locs(:,1)),1),(frames(i))*ones(numel(locs(:,1)),1)];
                    end
                    app.LAFM.Locs = [app.LAFM.Locs; locs_full];
                end

                if mod(i, stepsPerUpdate) == 0 || i == n
                    progressBar.Position(3) = i / n;
                    s = any(app.LAFM.Locs);
                    if s(1)==0
                        progressText.String = sprintf([0,' peaks detected']);
                    else
                        progressText.String = sprintf([num2str(numel(app.LAFM.Locs(:,1))),' peaks detected']);
                    end
                    drawnow;
                end
            end
            finished(app)
            loading(app)
            pause(0.000001)

            app.LAFM.Locs  = localize(app, prev, app.LAFM.Locs);
            if app.ImageInfo.PixelPerNm(1) ==0
                app.ImageInfo.PixelPerNm(1) =1;
            end

if app.BoostsubpixCheckBox.Value
    app.LAFM.Locs(:,1:2) =  app.LAFM.Locs(:,1:2)/3;
end

            if sum(strcmp(app.RightPlotList.Items,'Localizations')) <1
                app.RightPlotList.Items = [app.RightPlotList.Items , {'Localizations'}];
            end
            app.RightPlotList.Value = 'Localizations';
            RightPlotListValueChanged(app, event)
            finished(app)
            app.CancelButton.Visible = 0;
        end

        % Value changed function: LoczImagepeaksCheckBox
        function LoczImagepeaksCheckBoxValueChanged(app, event)
            app.OverlayDropDown.Value = 'Localizations';
            updatePlot(app,'normal')
        end

        % Button pushed function: ExportButton
        function ExportButtonPushed(app, event)

            switch app.ExportSelect.Value
                case 'data'
                    switch app.ExportFileFormat.Value
                        case 'Matlab workspace'
                            assignin('base','ImageTarget', app.ImageTarget);
                            assignin('base','ImagePreview', app.ImagePreview);
                            assignin('base','ImageInfo', app.ImageInfo);
                            assignin('base','ref', app.ref);
                            assignin('base','img_locs', app.img_locs);
                            assignin('base','Part', app.Part);
                            assignin('base','LineROI',app.LineROI);
                            assignin('base','LAFM',app.LAFM);
                            assignin('base','Mask',app.Mask);
                    end
                case 'Plot 1'
                    switch app.ExportFileFormat.Value
                        case '.tiff'
                            switch app.FramePartSwitch.Value
                                case 'Frames'
                                    Out{1} = app.ImageTarget;
                                case 'Particles'
                                    Out{1} = app.Part.Image(:,:,app.Part.IncludeImages);
                            end
                            namesplit = split(app.ImageName,'.');
                            namesuggest = join(namesplit,"_");
                            tiff_exporter(Out{1},namesuggest{1})
                        case {'.gif','.avi'}
                            updatePlot(app,'export video',app.ExportFileFormat.Value)
                        case {'.png','.jpeg','.pdf','.svg'}
                            updatePlot(app,'export frame',app.ExportFileFormat.Value)
                        case {'.txt', '.xls', '.csv'}
                            namesplit = split(app.ImageName,'.');
                            namesuggest = join(namesplit,"_");
                            [outputFileName,folder] = uiputfile([namesuggest{1},app.ExportFileFormat.Value;]);
                            fullFileName = fullfile(folder, outputFileName);
                            switch app.FramePartSwitch.Value
                                case 'Frames'
                                    Out{1} = app.ImageTarget;
                                case 'Particles'
                                    Out{1} = app.Part.Image(:,:,app.Part.IncludeImages);
                            end
                            N = numel(Out{1}(1,1,:));
                            img = Out{1};
                            Out = cell(1,N);
                            for k = 1:N
                                Out{k}=img(:,:,k);
                            end

                            switch app.ExportFileFormat.Value
                                case {'.txt','.csv'}
                                    for jj=1:numel(Out)
                                        if jj==1
                                            writematrix(Out{1},fullFileName);
                                        else
                                            writematrix(Out{jj},fullFileName,'WriteMode','append');
                                        end
                                    end

                                case '.xls'

                                    for jj=1:numel(Out)
                                        if jj==1
                                            writematrix(Out{1},fullFileName);
                                        else
                                            writematrix(Out{jj},fullFileName,'Sheet',jj,'WriteMode','append');
                                        end
                                    end
                            end
                    end

                case 'Plot 2'

                    switch app.RightPlotList.Value
                        case 'Line Profile'
                            imagetype = 'plot';
                            for jj = 1:numel(app.LineROI)
                                Out{jj} = [app.LineROI(jj).Profile(:,1),app.LineROI(jj).Profile(:,2)];
                            end
                        case 'Ref Image'
                            imagetype = 'image';
                            Out{1} = app.ref.image;
                        case 'Localizations'
                            imagetype = 'plot';
                            Out{1} = app.LAFM.Locs;
                        case 'LAFM'
                            imagetype = 'image';
                            Out{1} = app.LAFM.Image;
                        case 'LAFM sym'
                            imagetype = 'image';
                            Out{1} = app.LAFM.Image_sym;
                        case 'LAFM Movie'
                            imagetype = 'image';
                            if strcmp(app.LAFMView.Value,'Probability')
                                for ii = 1:numel(app.LAFM.Movie)
                                    Out{1}(:,:,ii) = (app.LAFM.Movie{ii});
                                end
                            else
                                for ii = 1:numel(app.LAFM.Movie)
                                    Out{1}(:,:,ii) = rgb2gray(app.LAFM.Movie{ii});
                                end
                            end
                        case 'LAFM sym Movie'
                            imagetype = 'image';
                            if strcmp(app.LAFMView.Value,'Probability')
                                for ii = 1:numel(app.LAFM.Movie_sym)
                                    Out{1}(:,:,ii) = (app.LAFM.Movie_sym{ii});
                                end
                            else
                                for ii = 1:numel(app.LAFM.Movie_sym)
                                    Out{1}(:,:,ii) = rgb2gray(app.LAFM.Movie_sym{ii});
                                end
                            end
                        case 'x & y Align'
                            imagetype = 'plot';
                            Out{1} = app.img_locs;
                        case 'Time Profile'
                            imagetype = 'plot';
                            Out{1} = app.CircleROI.Profile;
                        case 'Hist'
                            imagetype = 'plot';
                        case 'Kymograph'
                            imagetype = 'image';
                            Out{1} = app.LineROI(1).kymo;
                    end


                    switch app.ExportFileFormat.Value
                        case '.tiff'
                            namesplit = split(app.ImageName,'.');
                            namesuggest = join(namesplit,"_");
                            tiff_exporter(Out{1},namesuggest{1})
                        case {'.gif','.avi'}
                            updatePlot2(app,'export video',app.ExportFileFormat.Value,imagetype)
                        case {'.png','.jpeg','.pdf','.svg'}
                            updatePlot2(app,'export frame',app.ExportFileFormat.Value,imagetype)
                        case {'.txt', '.xls', '.csv'}
                            namesplit = split(app.ImageName,'.');
                            namesuggest = join(namesplit,"_");
                            [outputFileName,folder] = uiputfile([namesuggest{1},app.ExportFileFormat.Value;]);
                            fullFileName = fullfile(folder, outputFileName);
                            switch app.ExportFileFormat.Value
                                case {'.txt','.csv'}
                                    for jj=1:numel(Out)
                                        if jj==1
                                            writematrix(Out{1},fullFileName);
                                        else
                                            writematrix(Out{jj},fullFileName,'WriteMode','append');
                                        end
                                    end

                                case '.xls'

                                    for jj=1:numel(Out)
                                        if jj==1
                                            writematrix(Out{1},fullFileName);
                                        else
                                            writematrix(Out{jj},fullFileName,'Sheet',jj,'WriteMode','append');
                                        end
                                    end
                            end
                    end
            end


        end

        % Button pushed function: FindParticlesButton, 
        % ...and 1 other component
        function FindParticlesButtonPushed(app, event)
            app.CancelButton.Visible = 1;
            loading(app)
            app.Part.Image = [];
            app.Part.Locs = nan;
            filt_img = app.FilterImgEditField_Detect.Value;
            filt_ccr = app.FilterCCREditField_Detect.Value;

            switch app.PeaksRegionsSwitch.Value
                case 'ROI'
                    method = 'ccr';
                    if isfield(app.ref,'image')

                       % if ~app.MultiReferenceCheckBox.Value
                            refimg = app.ref.image;
                       % else
                       %     refimg = app.ref.images;
                       % end
                        angles = -str2num(app.anglesEditField.Value);
                        [app.Part.Locs] = Detector(app, app.ImageTarget, method, refimg, filt_img, filt_ccr, app.Min_CCR_Edit.Value, app.ExcludeEdgeCheckBox.Value,...
                            app.RotationalFreedom.Value,angles);

                        % if ~app.MultiReferenceCheckBox.Value
                            if isnan(app.Part.Locs)
                            else
                                app.Max_h_Edit.Value = double(max(app.Part.Locs(:,3)));
                                app.Min_h_Edit.Value = double(min(app.Part.Locs(:,3)));
                                app.Peaks_minHeight.Value = app.Min_h_Edit.Value;
                            end
                            if isnan(app.Part.Locs)
                                progressText.String = sprintf('0 particles detected');
                            else

                                %construct Particle image stack:
                                app.Part.IncludeImages = ones(numel(app.Part.Locs(:,3)),1);
                                app.Part.Image  = ConstructParticleStack2(app, app.ImageTarget, app.ref, app.Part);
                                app.BoxesCheckBox.Value=1;
                                app.OverlayDropDown.Value = 'Detections';
                                updatePlot(app,'normal')
                                updatePlot2(app,'normal')
                                switch app.Height_corr_switch.Value
                                    case 'Height'
                                        app.CCRSliderMax.Limits = [app.Min_h_Edit.Value, app.Max_h_Edit.Value];
                                        app.CCRSliderMin.Limits =  app.CCRSliderMax.Limits;
                                    case 'Correlation'
                                        app.CCRSliderMax.Limits = [min(app.Part.Locs(:,4)), 1];
                                        app.CCRSliderMin.Limits = [min(app.Part.Locs(:,4)), 1];
                                end

                                updateCCRHist(app)
                            end
                            filter_Partlocs(app)
                            finished(app)
                            storedata(app)
                            app.CancelButton.Visible = 0;

                        % else
                        %     numPoints = size(app.Part.Locs{1}, 1);
                        %     maxCorrRefs = zeros(numPoints, 1);
                        %     maxCorrData = cell(numPoints, 1);
                        % 
                        %     for i = 1:numPoints % only currently works for same size locs arrays
                        %         maxCorrValue = -Inf;
                        %         maxCorrRefData = [];
                        % 
                        %         for j = 1:app.RefNo
                        %             corrValue = app.Part.Locs{j}(i, 4);
                        % 
                        %             if corrValue > maxCorrValue
                        %                 maxCorrValue = corrValue;
                        %                 maxCorrRefData = app.Part.Locs{j}(i, :);  % Store the data for the reference with the maximum correlation
                        %             end
                        %         end
                        % 
                        %         % Store the data for the reference with the maximum correlation value for the current point
                        %         maxCorrData{i} = maxCorrRefData;
                        %     end
                        %     locs = cell2mat(maxCorrData);
                        %     app.Part.Locs = locs;
                        % 
                        %     if isnan(locs)
                        %         progressText.String = sprintf('0 particles detected');
                        %     else
                        %         for i = 1:app.RefNo
                        % 
                        %             %construct Particle image stack:
                        %             app.Part.IncludeImages = ones(numel(locs(:,3)),1);
                        %             app.Part.Image  = ConstructParticleStack2(app, app.ImageTarget, {app.ref.images{i}; app.ref.positions{i}}, locs);
                        %             app.BoxesCheckBox.Value=1;
                        %             app.OverlayDropDown.Value = 'Detections';
                        %             updatePlot(app,'normal')
                        %             updatePlot2(app,'normal')
                        %             switch app.Height_corr_switch.Value
                        %                 case 'Height'
                        %                     app.CCRSliderMax.Limits = [app.Min_h_Edit.Value, app.Max_h_Edit.Value];
                        %                     app.CCRSliderMin.Limits =  app.CCRSliderMax.Limits;
                        %                 case 'Correlation'
                        %                     app.CCRSliderMax.Limits = [min(locs(:,4)), 1];
                        %                     app.CCRSliderMin.Limits = [min(locs(:,4)), 1];
                        %             end
                        % 
                        %             updateCCRHist(app)
                        %         end
                        %         filter_Partlocs(app)
                        %         finished(app)
                        %         storedata(app)
                        %         app.CancelButton.Visible = 0;
                        %     end
                        % end

                    else

                        msgbox('Select an image reference by drawing an ROI');
                        return;

                    end
                case 'Peaks' % same method as above for multiref needs to be implimented
                    app.PreviewPeaks.Value =0;
                    app.ref.position(3) = app.BoxSizeEditField.Value;
                    app.ref.position(4) = app.ref.position(3);
                    refimg = [];
                    method = 'Peak picker';
                    [app.Part.Locs] = Detector(app, app.ImageTarget, method, app.Peaks_minSep.Value , filt_img, filt_ccr, app.Min_h_Edit.Value, app.ExcludeEdgeCheckBox.Value);

                    directions = [app.LineHor.Value, app.LineVert.Value, app.LineDiag1.Value, app.LineDiag2.Value];
                    if app.AutoProfileButton.Value

                        app.Part.Locs(:,9:11) = zeros(numel(app.Part.Locs(:,1)),3);
                        sz = size(app.ImageTarget);
                        if numel(sz)>2

                            for i = 1:sz(3)
                                if numel(app.ImageInfo.ScanSize)>1
                                    fr= i;
                                else
                                    fr=1;
                                end
                                pos =  app.Part.Locs(:,5)==i;
                                r = app.MaxradiusEditField.Value.*app.ImageInfo.PixelPerNm(fr);
                                [app.Part.Locs(pos,9), app.Part.Locs(pos,10), app.Part.Locs(pos,11), p] = Lineprofiler(app.ImageTarget(:,:,i), round(app.Part.Locs(pos,1:2)), r/2, directions, app.FWHMreferenceDropDown.Value);
                                app.Part.Locs(pos,9:11) = app.Part.Locs(pos,9:11)./app.ImageInfo.PixelPerNm(fr);
                            end

                        else
                            r = app.MaxradiusEditField.Value.*app.ImageInfo.PixelPerNm;
                            [app.Part.Locs(:,9), app.Part.Locs(:,10), app.Part.Locs(:,11), p] = Lineprofiler(app.ImageTarget, round(app.Part.Locs(:,1:2)), r/2,directions,app.FWHMreferenceDropDown.Value);
                            app.Part.Locs(:,9:11) = app.Part.Locs(:,9:11)./app.ImageInfo.PixelPerNm;
                        end


                        if directions(1)
                            app.LineROI=[];
                            for jj=1:length(p)
                                app.LineROI(jj).Position(:,1) = p{jj,1}(:,1);
                                app.LineROI(jj).Position(:,2) =  ones(numel(p{jj,1}(:,1)),1)*app.Part.Locs(jj,2);
                            end
                        end

                    end
                    if isnan(app.Part.Locs)
                    else
                        app.Max_h_Edit.Value = double(max(app.Part.Locs(:,3)));
                        app.Min_h_Edit.Value = double(min(app.Part.Locs(:,3)));
                        app.Peaks_minHeight.Value = app.Min_h_Edit.Value;
                        % app.Peaks_minHeight.Value = app.Min_h_Edit.Value;
                    end
                    if isnan(app.Part.Locs)
                        progressText.String = sprintf('0 particles detected');
                    else

                        %construct Particle image stack:
                        app.Part.IncludeImages = ones(numel(app.Part.Locs(:,3)),1);
                        app.Part.Image  = ConstructParticleStack2(app, app.ImageTarget, app.ref, app.Part);
                        switch app.PeaksRegionsSwitch.Value
                            case 'Peaks'
                                app.ref.av  = mean(app.Part.Image,3);
                                for i = 1:size(app.Part.Image,3)
                                    app.Part.Locs(i,4) = corr2(app.ref.av,app.Part.Image(:,:,i));
                                end
                        end
                        app.BoxesCheckBox.Value=1;
                        app.OverlayDropDown.Value = 'Detections';
                        if app.AutoProfileButton.Value
                                 app.BoxesCheckBox.Value=0;
                            if app.OverlaylinesCheckBox.Value
                                app.OverlayDropDown.Value ='Line Profile';
                            else
                            end
                        end
                        updatePlot(app,'normal')
                        updatePlot2(app,'normal')

                                app.CCRSliderMax.Limits = [app.Min_h_Edit.Value, app.Max_h_Edit.Value];
                                app.CCRSliderMin.Limits =  app.CCRSliderMax.Limits;

                                app.CCRSliderMax.Limits = [double(min(app.Part.Locs(:,4))), 1];
                                app.CCRSliderMin.Limits = [double(min(app.Part.Locs(:,4))), 1];

                        updateCCRHist(app)
                    end
                    filter_Partlocs(app)
                    finished(app)
                    storedata(app)
                    app.CancelButton.Visible = 0;
            end

        end

        % Value changed function: FramePartSwitch
        function FramePartSwitchValueChanged(app, event)
            if isfield(app.Part,'IncludeImages')
              
                app.Spinner.Value=1;
                app.FrameSlider.Value =1;
                updateVideoTools(app)
                app.MarkersCheckBox.Value = 0;
                app.BoxesCheckBox.Value = 0;
                app.OverlayValues.Value = 'None';
                app.TrackLengthEditField.Value = 0;
                if strcmp(app.FramePartSwitch.Value,'Particles')
                    filter_Partlocs(app)
                      app.ViewMask.Value=0;
                else
                end
                app.ViewMask.Value=0;
                 app.OverlayDropDown.Value = 'None';
                updatePlot(app,'normal')
                set(app.UIAxes,'xlimMode', 'auto','ylimMode', 'auto')
                ViewMaskValueChanged(app)
            else
                app.FramePartSwitch.Value = 'Frames';
                updateVideoTools(app)
            end

        end

        % Value changed function: Peaks_minHeight
        function Peaks_minHeightValueChanged(app, event)
            app.Min_h_Edit.Value= app.Peaks_minHeight.Value;
            updatePlot(app,'normal')
        end

        % Value changed function: Min_h_Edit
        function Min_h_EditValueChanged(app, event)
            app.Peaks_minHeight.Value = app.Min_h_Edit.Value;
            updatePlot(app,'normal')
        end

        % Window button down function: NanoLocz
        function NanoLoczWindowButtonDown(app, event)
            img = hittest(app.NanoLocz);
            ax = app.UIAxes;
            fig = ancestor(img, 'figure');
            props.WindowButtonMotionFcn = get(fig, 'WindowButtonMotionFcn');
            props.WindowButtonUpFcn = get(fig, 'WindowButtonUpFcn');

            if isa(img, 'matlab.graphics.primitive.Rectangle')
                type = 'rectHandle';
                setappdata(fig, 'TestGuiCallbacks', props);
                set(fig, 'WindowButtonMotionFcn', @(src, evt) dragging_sq(src, evt, ax,type));
                set(fig, 'WindowButtonUpFcn', @(src, evt) mouseup_sq(src, evt, ax));

                ax.UserData.startPos = [ax.UserData.rectHandle.Position(1),ax.UserData.rectHandle.Position(2)];
                ax.UserData.points = [];
            end
            k=0;
            for i = 1:numel(app.LineROI)
                if ismember(img, app.LineROI(i).plt)
                    k=i;
                    type = 'lineHandle';
                    C = hsv(8);
                    C = [C ;   jet(8)];
                    C = [C ;   parula(8)];
                else
                end
            end

            if k>0
                app.LineROI(k) =[];
                setappdata(fig, 'TestGuiCallbacks', props);
                set(fig, 'WindowButtonMotionFcn', @(src, evt) dragging_sq(src, evt, ax,type));
                set(fig, 'WindowButtonUpFcn', @(src, evt) mouseup_sq(src, evt, ax));
                ax.UserData.startPos = [img.XData(1),img.YData(1)];
                ax.UserData.points = [];
                updatePlot(app,'normal')
                updatePlot2(app,'live')
            end


            if isa(img, 'matlab.graphics.primitive.Image') && (app.ROIThresholdButton.Value==1  || app.Sq_roi_Button.Value==1 || app.Line_roi_Button.Value==1 || ...
                    app.Point_roi_Button.Value==1 || app.Circ_roi_Button.Value==1)
             try
              
                 if app.Sq_roi_Button.Value==1
                     type = 'rectHandle';
                     app.SquareROI = [];
                 elseif app.Line_roi_Button.Value==1
                     type = 'lineHandle';
                     set(app.UIAxes_2,'fontname','Ariel','fontsize', 14,'TickDir','out')
                     k = numel(app.LineROI)+1;
                     C = hsv(8);
                     C = [C ;   jet(8)];
                     C = [C ;   parula(8)];
                 elseif app.Point_roi_Button.Value==1
                     type = 'markerHandle';
                     k = numel(app.PointROI)+1;
                     C = hsv(8);
                     C = [C ;   jet(8)];
                     C = [C ;   parula(8)];
                 elseif app.Circ_roi_Button.Value==1
                     type = 'circleHandle';
                 elseif app.ROIThresholdButton.Value==1
                     type = 'scribble';
                 end

                setappdata(fig, 'TestGuiCallbacks', props);
                set(fig, 'WindowButtonMotionFcn', @(src, evt) dragging_sq(src, evt, ax,type));
                set(fig, 'WindowButtonUpFcn', @(src, evt) mouseup_sq(src, evt, ax));

                if isfield(ax.UserData, type)
                   % if app.MultiReferenceCheckBox.Value == 0
                        delete(ax.UserData.(type));
                        ax.UserData = rmfield(ax.UserData, type);
                        % Remove any previously drawn rectangle
                  %  else
                  %      app.ROIs{end+1} = ax.UserData.(type);
                  %  end

                end

                ax.UserData.startPos = ax.CurrentPoint(1, 1:2);
                ax.UserData.points = [];
            catch
            end
            end


            function dragging_sq(~, ~, ax, type)
                ax.XLim = ax.XLim;   ax.YLim = ax.YLim ;
                startPos = ax.UserData.startPos;
                currentPosD = ax.CurrentPoint(1, 1:2);

                % Remove any previously drawn
                if isfield(ax.UserData, type)
                    delete(ax.UserData.(type));
                    ax.UserData = rmfield(ax.UserData, type);
                end

                switch type
                    case 'rectHandle'
                      %  if ~app.MultiReferenceCheckBox.Value
                            % Draw the Rectangle
                            WidthHeight = abs(currentPosD - startPos);
                            rect4 = [min(startPos(1), currentPosD(1)), min(startPos(2), currentPosD(2)), WidthHeight];
                            ax.UserData.rectHandle = rectangle('Parent', ax, 'Position', rect4, 'EdgeColor', 'w', 'LineWidth', 1);
                            p = round(ax.UserData.rectHandle.Position);
                            switch  app.FramePartSwitch.Value
                                case 'Frames'
                                    img = app.ImageTarget;
                                case 'Particles'
                                    img = app.Part.Image(:,:,app.Part.IncludeImages);
                            end
                            app.ref.ROIimage =  imcrop(img(:,:,round(app.FrameSlider.Value)), [p(1), p(2), p(3)-1, p(4)-1]);
                            app.ref.position = p;
                            app.SquareROI.Position = p;
                    %   end


                        if sum(strcmp(app.RightPlotList.Items,'Ref Image')) <1
                            app.RightPlotList.Items = [app.RightPlotList.Items , {'Ref Image'}];
                        end
                        app.RightPlotList.Value = 'Ref Image';
                        app.ReferenceTools.Visible = 1;
                        if sum(strcmp(app.RefListBox.Items,'ROI Reference')) <1
                            app.RefListBox.Items = [app.RefListBox.Items , {'ROI Reference'}];
                        end
                        app.RefListBox.Value = 'ROI Reference';
                        if p(3)>1 && p(4)>1
                            updateRightPlot(app)
                        end
                        drawnow;


                    case 'lineHandle'
                        if isfield(ax.UserData, 'textHandle')
                            prevTextHandle = ax.UserData.textHandle;
                            delete(prevTextHandle);  % Delete the previous text
                        end

                        %plot lineprofile
                        app.LineROI(k).Position(:,1) = [startPos(1), currentPosD(1)];
                        app.LineROI(k).Position(:,2) = [startPos(2), currentPosD(2)];
                        if abs(startPos(1)-currentPosD(1)) > 2 || abs(startPos(2)-currentPosD(2)) > 2
                            if sum(strcmp(app.RightPlotList.Items,'Line Profile')) <1
                                app.RightPlotList.Items = [app.RightPlotList.Items , {'Line Profile'}];
                            end
                            app.RightPlotList.Value = 'Line Profile';
                            updatePlot2_type(app,'plot')
                            app.OverlayDropDown.Value = 'Line Profile';
                            updatePlot2(app,'live')
                            updateResultsTable(app,'Line Profile')
                        
                        % Draw the Line
                        try
                         ax.UserData.lineHandle = line(ax, [startPos(1), currentPosD(1)], [startPos(2), currentPosD(2)], ...
                            'Color', C(k,:), 'LineWidth', 2);
                        catch
                            ax.UserData.lineHandle = line(ax, [startPos(1), currentPosD(1)], [startPos(2), currentPosD(2)], ...
                            'LineWidth', 2);
                        end
                        dx = startPos(1)- currentPosD(1);
                        dy = startPos(2)- currentPosD(2);
                        angle_rad = atan2(dx, dy); angle_deg = -rad2deg(angle_rad);
                        if abs(angle_deg) <20  || abs(angle_deg) >160
                            py = app.UIAxes.XLim(2)/50; px = 0;
                        elseif abs(angle_deg)>70  && abs(angle_deg)<110
                            px = app.UIAxes.YLim(2)/50; py = -app.UIAxes.XLim(2)/50;
                        else
                            px = 0; py = 0;
                        end

                        if numel(app.ImageInfo.ScanSize)>1
                            fr = round(app.FrameSlider.Value);
                            switch  app.FramePartSwitch.Value
                                case 'Particles'
                                    fr = app.Part.Locs((app.Part.Locs(:,6)==fr),5);
                            end
                        else
                            fr=1;
                        end

                        dist = round( ((dx^2 + dy^2)^0.5)./app.ImageInfo.PixelPerNm(fr), 1);
                        try
                            textHandle = text(app.UIAxes, mean([startPos(1), currentPosD(1)])+py, mean([startPos(2), currentPosD(2)])+px, [num2str(dist) 'nm'],'Color',C(k,:),'FontSize',12);
                        catch
                            textHandle = text(app.UIAxes, mean([startPos(1), currentPosD(1)])+py, mean([startPos(2), currentPosD(2)])+px, [num2str(dist) 'nm'],'FontSize',12);
                        end
                        ax.UserData.textHandle = textHandle;
                        drawnow;
                        end
                    case 'circleHandle'
                        radius = norm(currentPosD - startPos) / 2;
                        center = (currentPosD + startPos) / 2;
                        angle = atan2(currentPosD(2) - center(2), currentPosD(1) - center(1));
                        startAngle = angle;
                        % Draw the circle
                        theta = linspace(startAngle, startAngle + 2 * pi, 100);
                        x = center(1) + radius * cos(theta);
                        y = center(2) + radius * sin(theta);
                        ax.UserData.circleHandle = line(ax, x, y, 'Color', 'w', 'LineWidth', 1);
                        drawnow;
                        app.CircleROI.Position = [radius,center(1), center(2)];

                        if sum(strcmp(app.RightPlotList.Items,'Time Profile')) <1
                            app.RightPlotList.Items = [app.RightPlotList.Items , {'Time Profile'}];
                        end
                        app.RightPlotList.Value = 'Time Profile';
                        updatePlot2_type(app,'plot')
                        updatePlot2(app,'normal')

                    case 'markerHandle'
                        %
                        if isfield(ax.UserData, 'textHandle')
                            prevTextHandle = ax.UserData.textHandle;
                            delete(prevTextHandle);  % Delete the previous text
                        end
                        hold(app.UIAxes,'on')
                        ax.UserData.markerHandle = plot(ax, currentPosD(1), currentPosD(2), ...
                            'Marker', 'o', 'MarkerSize', 4, 'MarkerFaceColor', C(k,:));

                        frame = round(app.Spinner.Value);

                        switch  app.FramePartSwitch.Value
                            case 'Frames'
                                switch  app.LeftPlotList.Value
                                    case 'Target'
                                        img = app.ImageTarget;
                                    case 'Preview'
                                        img = app.ImagePreview;
                                end
                            case 'Particles'
                                switch  app.LeftPlotList.Value
                                    case 'Target'
                                        img = app.Part.Image(:,:,app.Part.IncludeImages);
                                    case 'Preview'
                                        img = app.ImagePreview(:,:,app.Part.IncludeImages);
                                end
                        end
                        if frame <= app.Spinner.Limits(2)
                            prev = img(:,:,frame);
                        else
                            prev = img(:,:,1);
                        end
                        app.PointROI(k).Position = [currentPosD(1), currentPosD(2)];

                        app.PointROI(k).h = round(prev(round( app.PointROI(k).Position(2)),round( app.PointROI(k).Position(1))),2);
                        textHandle = text(app.UIAxes, app.PointROI(k).Position(1)+5, app.PointROI(k).Position(2),num2str( app.PointROI(k).h ),'Color',C(k,:),'FontSize',16,'FontWeight','bold');
                        ax.UserData.textHandle = textHandle;
                        drawnow;

                    case 'scribble'
                        ax.UserData.points = [ax.UserData.points; ax.CurrentPoint(1, 1:2)];
                        % Draw the line connecting the current and previous points
                        line(app.UIAxes,ax.UserData.points(:, 1), ax.UserData.points(:, 2), 'Color', 'r', 'LineWidth', 2);
                        drawnow;
                end
            end

            function mouseup_sq(~, ~, ax)
                fig = ancestor(ax, 'figure');
                % Restore the original callbacks
                props = getappdata(fig, 'TestGuiCallbacks');
                set(fig, props);
                setappdata(fig, 'TestGuiCallbacks', []);
                ax.UserData.startPos = [];

                switch type
                    case 'lineHandle'
                        try
                            try
                                app.LineROI(k).plt = line(ax, app.LineROI(k).Position(:,1), app.LineROI(k).Position(:,2), ...
                                    'Color', C(k,:), 'LineWidth', 2);
                            catch

                                app.LineROI(k).plt = line(ax, app.LineROI(k).Position(:,1), app.LineROI(k).Position(:,2), ...
                                    'LineWidth', 2);
                            end
                            drawnow;
                        catch
                        end
                    case 'markerHandle'
                        hold(app.UIAxes,'on')
                        plot(ax, app.PointROI(k).Position(1), app.PointROI(k).Position(2), 'Marker', 'o', 'MarkerSize', 4, 'MarkerFaceColor', C(k,:));
                        text(ax, app.PointROI(k).Position(1)+5, app.PointROI(k).Position(2),num2str(app.PointROI(k).h),'Color',C(k,:),'FontSize',16,'FontWeight','bold');
                        drawnow;
                        hold(app.UIAxes,'off')
                    case 'scribble'
                        points = ax.UserData.points;
                        if size(points, 1) >= 2
                            % Connect the start point to the end point
                            line(app.UIAxes, [points(1, 1), points(end, 1)], [points(1, 2), points(end, 2)], ...
                                'Color', 'r', 'LineWidth', 2);
                            x = points(:, 1);
                            y = points(:, 2);
                            mask = freehandROItoLogicMatrix(x, y, size(ax.Children(end).CData));

                            if isfield(app.Mask,'ROI')
                                s = any(app.Mask.ROI);
                            else
                                s(1)=0;
                            end

                            if s(1) ==1
                                app.Mask.ROI = app.Mask.ROI.*(mask< 1);
                            else
                                app.Mask.ROI =  mask< 1;
                            end
                        end
                        app.filter_already =0;
                        app.PreviewonoffButton.Value =1;
                        previewOnOff(app)
                        run_filter(app)
                        updatePlot2(app,'normal')
                        ax.UserData.points = [];
                end


            end

            function mask = freehandROItoLogicMatrix(x, y, imageSize)
                [X, Y] = meshgrid(1:imageSize(2), 1:imageSize(1));
                warning('off');
                polygon = polyshape(x, y);
                warning('on');
                mask = inpolygon(X(:), Y(:), polygon.Vertices(:, 1), polygon.Vertices(:, 2));
                mask = reshape(mask, imageSize);
            end
        end

        % Value changed function: Point_roi_Button
        function Point_roi_ButtonValueChanged(app, event)
            TurnOffButtons(app,'app.Point_roi_Button')
        end

        % Value changed function: Sq_roi_Button
        function Sq_roi_ButtonValueChanged(app, event)
            TurnOffButtons(app,'app.Sq_roi_Button')
        end

        % Value changed function: Circ_roi_Button
        function Circ_roi_ButtonValueChanged(app, event)
            TurnOffButtons(app,'app.Circ_roi_Button')
        end

        % Value changed function: Line_roi_Button
        function Line_roi_ButtonValueChanged(app, event)
            TurnOffButtons(app,'app.Line_roi_Button')
        end

        % Callback function: AutoCenterLAFM, LAFMView, LAFM_LUT, 
        % ...and 4 other components
        function RenderLAFMButtonPushed(app, event)
            loading(app)
            pause(0.000001)

            switch  app.FramePartSwitch.Value
                case 'Frames'
                    img = app.ImageTarget;
                case 'Particles'
                    img = app.Part.Image(:,:,app.Part.IncludeImages);
            end

            switch app.LAFM_LUT.Value
                case 'LAFM color'
                    color =  app.cmap.LAFMcolor;
                case 'Rainbow'
                    color = app.cmap.Rainbow;
                otherwise
                    color = eval(app.LAFM_LUT.Value);
            end

            if strcmp(app.LAFMView.Value,'Probability')
                P = 1;
            else
                P=0;
            end

            if app.ViewSymmetrizedButton.Value && app.LAFM_sym.Value>1
                app.LAFM.Locs_sym = app.LAFM.Locs;
                if app.AutoCenterLAFM.Value
                    center_translation = FindCenter_ptCloud(app.LAFM_sym.Value, img, app.LAFM.Locs);
                    app.LAFM.Locs_sym (:,1) = app.LAFM.Locs(:,1)-center_translation(1);
                    app.LAFM.Locs_sym (:,2) = app.LAFM.Locs(:,2)-center_translation(2);
                end
                app.LAFM.Locs_sym = sym_ptCloud(app.LAFM_sym.Value, img, app.LAFM.Locs_sym);
                [app.LAFM.Image_sym, app.LAFM.lims]= LAFM_renderer(app.LAFM.Locs_sym, app.LAFM_gus.Value,  app.LAFM_exp.Value, color, P, [app.LAFMColorMin.Value, app.LAFMColorMax.Value],app.LAFM_ZScaleDropBox.Value);

                if sum(strcmp(app.RightPlotList.Items,'LAFM sym')) <1
                    app.RightPlotList.Items = [app.RightPlotList.Items , {'LAFM sym'}];
                end
                app.RightPlotList.Value = 'LAFM sym';
                if strcmp(app.LAFMView.Value,'Height')
                    app.LAFM.Image_sym = app.LAFM.Image_sym./(mean(app.LAFM.Image_sym,3)+0.8);
                end
                app.LAFM.Image_sym = app.LAFM.Image_sym./max(app.LAFM.Image_sym(:));
            else

                try
                    [app.LAFM.Image, app.LAFM.lims]= LAFM_renderer(app.LAFM.Locs, app.LAFM_gus.Value,  app.LAFM_exp.Value, color, P, [app.LAFMColorMin.Value, app.LAFMColorMax.Value],app.LAFM_ZScaleDropBox.Value);
                catch
                    try
                        app.FramePartSwitch.Value = 'Particles';
                        img = app.Part.Image(:,:,app.Part.IncludeImages);
                        [app.LAFM.Image, app.LAFM.lims] = LAFM_renderer(app.LAFM.Locs, app.LAFM_gus.Value,  app.LAFM_exp.Value, color, P, [app.LAFMColorMin.Value, app.LAFMColorMax.Value],app.LAFM_ZScaleDropBox.Value);
                    catch
                        errordlg('Run "Find all peaks" 1st', 'Error');
                        app.FramePartSwitch.Value = 'Frames';
                        return
                    end
                end

                if sum(strcmp(app.RightPlotList.Items,'LAFM')) <1
                    app.RightPlotList.Items = [app.RightPlotList.Items , {'LAFM'}];
                end
                app.RightPlotList.Value = 'LAFM';
                if strcmp(app.LAFMView.Value,'Height')
                    app.LAFM.Image = app.LAFM.Image./(mean(app.LAFM.Image,3)+0.8);
                end
                app.LAFM.Image = app.LAFM.Image./max(app.LAFM.Image(:));

            end

            RightPlotListValueChanged(app, event)
            finished(app)
            storedata(app)
            if app.ViewSymmetrizedButton.Value && app.LAFM_sym.Value >1
                ref_img = app.LAFM.Image_sym;
            else
                ref_img = app.LAFM.Image;
            end
            app.ref.LAFM = imresize(ref_img,[size(img,1),size(img,2)]);
            try app.ref.LAFM = rgb2gray(app.ref.LAFM);
            catch
            end

            if sum(strcmp(app.RefListBox.Items,'LAFM Reference')) <1
                app.RefListBox.Items = [app.RefListBox.Items , {'LAFM Reference'}];
            end

            app.LAFMColorMax.Value =  app.LAFM.lims(2);
            app.LAFMColorMin.Value =  app.LAFM.lims(1);

        end

        % Button pushed function: CropButton
        function CropButtonPushed(app, event)
            if isfield(app.SquareROI,'Position')
                switch  app.FramePartSwitch.Value
                    case 'Frames'
                        img = app.ImageTarget;
                    case 'Particles'
                        img = app.Part.Image;
                end

                for i = 1:numel(img(1,1,:))
                    img_c(:,:,i) = imcrop(img(:,:,i),app.SquareROI.Position);
                end

                switch  app.FramePartSwitch.Value
                    case 'Frames'
                        app.ImageTarget = img_c;
                    case 'Particles'
                        app.Part.Image = img_c;
                end
            end
            app.Mask.Mask = [];
            set(app.UIAxes,'xlimMode', 'auto','ylimMode', 'auto')
            updatePlot(app,'normal')
            updatePlot2(app,'normal')
            storedata(app)
        end

        % Value changed function: Sq_roi_Button_2
        function Sq_roi_Button_2ValueChanged(app, event)
            if app.Sq_roi_Button_2.Value ==1
                app.Sq_roi_Button.Value =1;
                TurnOffButtons(app,'app.Sq_roi_Button')
                app.Sq_roi_Button_2.Value =1;
            else
                app.Sq_roi_Button.Value =0;
            end

        end

        % Value changed function: ROIThresholdButton
        function ROIThresholdButtonValueChanged(app, event)
            TurnOffButtons(app,'app.ROIThresholdButton')
        end

        % Button pushed function: ClearROIButton
        function ClearROIButtonPushed(app, event)
            app.Mask.ROI = [];
            app.filter_already =0;
            app.PreviewonoffButton.Value =1;
            previewOnOff(app)
            run_filter(app)
            updatePlot(app,'normal')
            updatePlot2(app,'normal')

        end

        % Callback function: FolderMenu, OpenFolderButton
        function OpenFolderButtonClicked(app, event)

            selection = questdlg('Select a folder or a session file', 'Open', 'Folder', 'Session File', 'Cancel', 'Folder');

            if strcmp(selection, 'Folder')
                app.OpenFolder = uigetdir;
                updateFolder(app)
                app.FilesDropDown.Value = 'All files';
            elseif strcmp(selection, 'Session File')
                [filename, pathname] = uigetfile('.mat', 'Select session file');
                if isequal(filename, 0) || isequal(pathname, 0)
                else
                    filepath = fullfile(pathname, filename);
                    loadedData = load(filepath);
                    app.stored = loadedData.Images;
                    app.OpenFolder = loadedData.WorkingFolder;
                end
                 app.FilesDropDown.Value = 'Processed';
                 try
                     app.FilesListBox.Items = {app.stored.ImageName};
                 catch
                     app.FilesListBox.Items = {'no files'};
                 end

            else
            end


        end

        % Callback function: SaveMenu, SaveSession
        function SaveSessionImageClicked(app, event)
            switch app.SaveType.Value
                case 'Current File'
                    ch = contains({app.stored.ImageName},app.FilesListBox.Value);
                    Images = app.stored(ch);
                    namesplit = split(app.stored(ch).ImageName,'.');
                    namesuggest = join(namesplit,"_") + "_edited."+"mat";
                    WorkingFolder = app.OpenFolder;
                case 'All Processed'
                    Images = app.stored;
                    [~, namesplit, ~] = fileparts(app.OpenFolder);
                    namesuggest = join(namesplit,"_") + "_session."+"mat";
                    WorkingFolder = app.OpenFolder;
            end

            [filename, pathname] = uiputfile(namesuggest, 'Save App Data');
            if isequal(filename, 0) || isequal(pathname, 0)
            else
                filepath = fullfile(pathname, filename);
                save(filepath, 'Images','WorkingFolder');
            end
        end

        % Menu selected function: OpenSessionMenu
        function OpenSessionMenuSelected(app, event)
            [filename, pathname] = uigetfile('.mat', 'Load App Data');
            if isequal(filename, 0) || isequal(pathname, 0)
            else
                filepath = fullfile(pathname, filename);
                loadedData = load(filepath);
                app.stored = loadedData.Images;
                app.OpenFolder = loadedData.WorkingFolder;
            end
            updateFolder(app)
        end

        % Button down function: DetectTab
        function DetectTabButtonDown(app, event)
            app.LoczImagepeaksCheckBox.Value =0;
            app.FramePartSwitch.Value = 'Frames';
            updateVideoTools(app)
            updatePlot(app,'normal')
        end

        % Button pushed function: PopAx2
        function PopAx2Pushed(app, event)
            updatePlot2(app,'popout')
        end

        % Button pushed function: PopAx1
        function PopAx1ButtonPushed(app, event)
            updatePlot(app,'popout')
        end

        % Value changed function: PlotTypeDropDown
        function PlotTypeDropDownValueChanged(app, event)

            switch app.PlotTypeDropDown.Value
                case '2D surface'
                    app.UIAxes.Toolbar.Visible = 'off';
                    pan(app.UIAxes,'on')
                    pan(app.UIAxes,'off')
                case '3D surface'
                    app.UIAxes.Toolbar.Visible = 'on';
                case 'Coordinates'
                    app.UIAxes.Toolbar.Visible = 'on';
            end
            updatePlot(app,'normal')
        end

        % Value changed function: ZoomIn
        function ZoomInValueChanged(app, event)
            if app.ZoomIn.Value ==1
                % zoom(app.UIAxes,'on')
                % app.UIAxes.Toolbar.Visible = 'on';
                          app.Settings.Zoom = zoom(app.UIAxes);
app.Settings.Zoom.Enable = 'on';
            else
                % zoom(app.UIAxes,'off')
                % app.UIAxes.Toolbar.Visible = 'off';
                          app.Settings.Zoom = zoom(app.UIAxes);
app.Settings.Zoom.Enable = 'off';
            end
            TurnOffButtons(app,'app.ZoomIn')
            
        end

        % Button pushed function: AlignButton
        function AlignButtonPushed(app, event)
            [app.LineROI] = LineAlign(app.LineROI,app.AlignLineType.Value);
            updatePlot2(app,'normal')
        end

        % Value changed function: BidirectionaldataCheckBox
        function BidirectionaldataCheckBoxValueChanged(app, event)
            if app.BidirectionaldataCheckBox.Value == 1
                app.FixShiftValue.Visible = 1;
                app.FixShiftMethod.Visible = 1;
                app.FixLineShift.Visible = 1;
            else
                app.FixShiftValue.Visible = 0;
                app.FixShiftMethod.Visible = 0;
                app.FixLineShift.Visible = 0;
            end
        end

        % Button pushed function: FitButton
        function FitButtonPushed(app, event)

            fo = fitoptions('method','NonlinearLeastSquares');
            t = app.ImageTarget(:,:,:);
            [hy, x]=  hist(t(:),100);
            gfit  = fit(double(x'),hy','gauss1',fo);
            if gfit.b1 +1.5*gfit.c1 <app.SliderMax.Value
                app.SliderMax.Value = gfit.b1 +1.5*gfit.c1;
            end
            if  gfit.b1 -1.5*gfit.c1 > app.SliderMin.Value
                app.SliderMin.Value = gfit.b1 -1.5*gfit.c1;
            end

            delete(app.patches.histup)
            delete(app.patches.histdown)
            app.filter_already =0;
            run_filter(app)
            app.patches.histup = patch(app.Hist_Axes,[app.SliderMax.Limits(2) app.SliderMax.Value app.SliderMax.Value app.SliderMax.Limits(2)], [app.Hist_Axes.YLim(1), app.Hist_Axes.YLim(1),  app.Hist_Axes.YLim(2)  app.Hist_Axes.YLim(2)],...
                'r', 'FaceAlpha', 0.5,'EdgeColor','none');
            app.patches.histdown = patch(app.Hist_Axes,[app.SliderMin.Value app.SliderMin.Limits(1) app.SliderMin.Limits(1) app.SliderMin.Value], [app.Hist_Axes.YLim(1), app.Hist_Axes.YLim(1),  app.Hist_Axes.YLim(2)  app.Hist_Axes.YLim(2)],...
                'r', 'FaceAlpha', 0.5,'EdgeColor','none');
            switch app.ThresholdContrastSwitch.Value
                case 'Z-scale'
                    app.ZScaleDropBox.Value = 'Histogram';
                case 'Mask'
            end

        end

        % Value changing function: LAFMMaxSlider
        function LAFMMaxSliderValueChanging(app, event)
            app.LAFMMaxSlider.Value = event.Value;
            updatePlot2(app,'normal')
        end

        % Value changing function: LAFMMinSlider
        function LAFMMinSliderValueChanging(app, event)

            app.LAFMMinSlider.Value = event.Value;
            updatePlot2(app,'normal')
        end

        % Button pushed function: ApplyButton
        function ApplyButtonPushed(app, event)
            s = app.RoiareaEditField.Value;
            app.ref.position(3) =  app.ref.position(3) + 2*s;
            app.ref.position(4) =  app.ref.position(4) + 2*s;

            xdiff=  app.ref.position(3)-size(app.Part.Image,2);
            x1= ceil(xdiff/2); x2= floor(xdiff/2);
            if xdiff>0
                app.Part.Image =  padarray( app.Part.Image ,[0, x1],0,'post');
                app.Part.Image =  padarray( app.Part.Image ,[0, x2],0,'pre');
            elseif xdiff<0
                app.Part.Image = app.Part.Image(:, 1-x1:end+x2,:);
            end

            ydiff=  app.ref.position(4)-size(app.Part.Image,1);
            y1= ceil(ydiff/2); y2= floor(ydiff/2);
            if ydiff>0
                app.Part.Image =  padarray( app.Part.Image ,[y1, 0],0,'post');
                app.Part.Image =  padarray( app.Part.Image ,[y2, 0],0,'pre');
            elseif ydiff<0
                app.Part.Image = app.Part.Image(1-y1:end+y2,:,:);
            end


             %  app.ref.ROIimage =  imcrop(img(:,:,round(app.FrameSlider.Value)), [p(1), p(2), p(3)-1, p(4)-1]);
            app.Part.Image  = ConstructParticleStack2(app, app.ImageTarget, app.ref, app.Part);
            if s >0
                app.ref.image =  padarray(app.ref.image ,[s, s],0,'both');
            else

                app.ref.image =  app.ref.image(1-s:end+s, 1-s:end+s);
            end
            try
            switch cell2mat(app.RefListBox.Value)
                case 'ROI Reference'
                    app.ref.ROIimage =  app.ref.image;
                case 'Simulated Reference'
                    app.ref.simImage =  app.ref.image;
                case 'LAFM Reference'
                    app.ref.image = app.ref.LAFM;
                case 'Averaged Reference'
                    app.ref.av =  app.ref.image;
            end
            catch
                app.RefListBox.Value = 'ROI Reference';
                app.ref.ROIimage =  app.ref.image;
            end

            updatePlot(app,'normal')
            updatePlot2(app,'normal')

        end

        % Button pushed function: kymo
        function kymoButtonPushed(app, event)

            if numel(app.LineROI) >0  && ~isfield(app.LineROI,'kymo')
                switch  app.FramePartSwitch.Value
                    case 'Frames'
                        img = app.ImageTarget;
                    case 'Particles'
                        img = app.Part.Image(:,:,app.Part.IncludeImages);
                end

                for i = 1:numel(img(1,1,:))
                    [~,~,ch] = improfile(img(:,:,i),app.LineROI(1).Position(:,1),app.LineROI(1).Position(:,2));
                    app.LineROI(1).kymo(:,i) = ch;
                end
            end

            if isfield(app.LineROI,'kymo')
                if sum(strcmp(app.RightPlotList.Items,'Kymograph')) <1
                    app.RightPlotList.Items = [app.RightPlotList.Items , {'Kymograph'}];
                end
                app.RightPlotList.Value='Kymograph';
            end

            RightPlotListValueChanged(app, event)
            updatePlot2(app,'normal')

        end

        % Button pushed function: CancelButton
        function CancelButtonPushed(app, event)
            app.stop = 1;
            app.CancelButton.Visible = 0;
            finished(app)
        end

        % Value changed function: LAFMScalebarCheckBox, 
        % ...and 1 other component
        function LAFMScalebarCheckBoxValueChanged(app, event)
              updatePlot(app,'normal')
            updatePlot2(app,'normal')
        end

        % Button down function: LevelTab
        function LevelTabButtonDown(app, event)
            app.FramePartSwitch.Value = 'Frames';
            updateVideoTools(app)
            ViewMaskValueChanged(app)
        end

        % Value changed function: FilesDropDown
        function FilesDropDownValueChanged(app, event)
            switch app.FilesDropDown.Value
                case 'All files'
                    updateFolder(app)
                case 'Processed'
                    try
                    app.FilesListBox.Items = {app.stored.ImageName};
                    catch 
                         app.FilesListBox.Items = {'no files'};
                    end
            end
        end

        % Button pushed function: MontageButton
        function MontageButtonPushed(app, event)

            switch  app.FramePartSwitch.Value
                case 'Frames'
                    img = app.ImageTarget;
                case 'Particles'
                    img = app.Part.Image(:,:,app.Part.IncludeImages);
            end
            img = padarray(img,[2,2],1000,'both');
            aspectRatio =  16/9;
            numFrames = size(img, 3);
            cols = ceil(sqrt(numFrames * aspectRatio));
            rows = ceil(numFrames / cols);
            imageSize = size(img(:, :, 1));
            montageWidth = cols * imageSize(2);
            montageHeight = rows * imageSize(1);
            montageMatrix = zeros(montageHeight, montageWidth);
            currentRow = 1;
            currentCol = 1;
          %  [r, cs, ~] = size(img(:,:,1));
            for i = 1:numFrames
                rowIndices = currentRow : currentRow + imageSize(1) - 1;
                colIndices = currentCol : currentCol + imageSize(2) - 1;

                montageMatrix(rowIndices, colIndices) = img(:, :, i);
              %  time = app.ImageInfo.time(i);

               %  switch app.Settings.label.TimeStamp.position
               %      case 'Top Right'
               %          xpos = round(cs*.90);
               %          ypos = round(r*.05);
               %      case 'Top Left'
               %          xpos = round(cs*.05);
               %          ypos = round(r*.05);
               %      case 'Bottom Left'
               %          xpos = round(cs*.05);
               %          ypos = round(r*.95);
               %      case 'Bottom Right'
               %          xpos = round(cs*.90);
               %          ypos = round(r*.95);
               %  end
               % montageMatrix =  insertText(montageMatrix,[xpos(1)+currentCol,ypos(1)+currentRow],[num2str(time) 's'],'TextColor', app.Settings.label.TimeStamp.color, 'FontSize', 10);

                currentCol = currentCol + imageSize(2);
                
                if currentCol > montageWidth
                    currentCol = 1;
                    currentRow = currentRow + imageSize(1);
                end
            end

            figure('Position', [100, 100, 1.3*800, 1.3*450]);

            try
                imagesc(montageMatrix,clim(app.UIAxes));
            catch
                imagesc(montageMatrix,caxis(app.UIAxes));
            end

            switch app.ColourmapDropDown.Value
                case 'AFM brown'
                    colormap(app.cmap.AFM_brown)
                case 'AFM dark gold'
                    colormap(app.cmap.AFM_Dark_Gold)
                case 'AFM gold'
                    colormap(app.cmap.AFM_Gold)
                case 'Orange hot'
                    colormap(app.cmap.AFM_orange)
                case'fire'
                    colormap(app.cmap.AFM_fire)
                case 'Rainbow'
                    colormap(app.cmap.Rainbow)
                otherwise
                    colormap(app.ColourmapDropDown.Value)
            end

            axis equal tight off; % Set equal axes and hide axis ticks

        end

        % Value changed function: Hist_excludeOut
        function Hist_excludeOutValueChanged(app, event)
            if app.Hist_excludeOut.Value==1
                T = reshape(app.ImageTarget(:,:,:),1,[]);
                T = rmoutliers(T,"percentiles",[5 95]);
            else
                T = app.ImageTarget;
            end
            min_all = double(min(T,[],'all'));
            max_all = double(max(T,[],'all'));
            app.SliderMax.Limits = [min_all(1), max_all(1)];
            app.SliderMin.Limits = [min_all(1), max_all(1)];
            app.SliderMax.Value = max_all;
            app.SliderMin.Value = min_all;
            updateHist(app)
        end

        % Callback function: ImportSimulationButton, SimulationAFMMenu
        function OpenMat_SimAFMMenuSelected(app, event)
            app.PopupApp.Sim = Mat_SimAFM_v1_6();
        end

        % Button pushed function: RefCropButton
        function RefCropButtonPushed(app, event)
            %       img = hittest(app.NanoLocz);
            ax = app.UIAxes_2;
            app.ref.crop = 1;
            Freehandroi = drawfreehand(ax,'Color','r');
            ax.UserData.roi = Freehandroi;

            if ~isempty(ax.UserData.roi)
                roiPoints = ax.UserData.roi.Position;
                if ~isempty(roiPoints)
                    minX = min(roiPoints(:, 1));
                    maxX = max(roiPoints(:, 1));
                    minY = min(roiPoints(:, 2));
                    maxY = max(roiPoints(:, 2));
                    width = maxX - minX;
                    height = maxY - minY;

                    app.ref.orgImage = app.ref.image;
                    image = app.ref.image;

                    % if ~app.MultiReferenceCheckBox.Value
                        mask = poly2mask(roiPoints(:, 1), roiPoints(:, 2), size(image, 1), size(image, 2));
                        maskImage = bsxfun(@times, image, cast(mask, 'like', image));
                        croppedImage = imcrop(maskImage, [minX, minY, width, height]);
                        switch cell2mat(app.RefListBox.Value)
                            case 'ROI Reference'
                                app.ref.ROIimage = croppedImage;
                            case 'Simulated Reference'
                                app.ref.simImage = croppedImage;
                            case 'LAFM Reference'
                                app.ref.LAFM = croppedImage;
                            case 'Averaged Reference'
                                app.ref.av = croppedImage;
                        end
                    % else
                    %     mask = poly2mask(roiPoints(:, 1), roiPoints(:, 2), size(image{1}, 1), size(image{1}, 2));
                    %     maskImage = bsxfun(@times, image{1}, cast(mask, 'like', image{1}));
                    %     croppedImage = imcrop(maskImage, [minX, minY, width, height]);
                    %     num = app.ReferenceButtonGroup.SelectedObject.Text;
                    %     app.ref.croppedimg{str2double(num)} = croppedImage;
                    % end

                    app.ref.image = croppedImage;
                    app.ref.position = [minX, minY, size(croppedImage,2), size(croppedImage,1)];
                    updatePlot2_type(app, 'image')
                    updateRightPlot(app);

                    if isfield(app.Part,'Image')
                        app.Part.Image  = ConstructParticleStack2(app, app.ImageTarget, app.ref, app.Part);
                    end
                    updatePlot(app,'normal')

                end
            end
            %app.ref = rmfield(app.ref, 'crop');
        end

        % Callback function
        function MultiReferenceCheckBoxValueChanged(app, event)
            % if app.MultiReferenceCheckBox.Value == 1
            %     app.RefNo = str2double(inputdlg('How many references would you like to use?'));
            %     ax = app.UIAxes;
            %     %button_text = 'Reference ';
            %     for i = 1:app.RefNo
            %         app.ROIs{i} = drawrectangle(ax);
            %         p = app.ROIs{i}.Position;
            %         switch  app.FramePartSwitch.Value
            %             case 'Frames'
            %                 img = app.ImageTarget;
            %             case 'Particles'
            %                 img = app.Part.Image(:,:,app.Part.IncludeImages);
            %         end
            %         app.ref.ROIimage =  imcrop(img(:,:,round(app.FrameSlider.Value)), [p(1), p(2), p(3)-1, p(4)-1]);
            %         if sum(strcmp(app.RightPlotList.Items,'Ref Image')) < 1
            %             app.RightPlotList.Items = [app.RightPlotList.Items , {'Ref Image'}];
            %         end
            %         app.RightPlotList.Value = 'Ref Image';
            %         %text3 = [button_text, num2str(i)];
            % 
            %         buttonWidth = 150;
            %         buttonHeight = 30;
            %         totalHeight = app.RefNo * buttonHeight + (app.RefNo - 1) * 10;
            %         startY = app.ReferenceButtonGroup.Position(4) - totalHeight - 10;
            %         y = startY + (i - 1) * (buttonHeight + 10);
            %         uiradiobutton(app.ReferenceButtonGroup, 'Text', num2str(i), 'Position', [10, y, buttonWidth, buttonHeight]);
            % 
            %         app.ref.images{i} = app.ref.ROIimage;
            %         app.ref.positions{i} = p;
            % 
            %     end
            % 
            %     % want to remove the drawn rectangles from the axes
            %     app.ReferenceButtonGroup.Visible = 1;
            %     app.ReferenceTools.Visible = 1;
            %     updatePlot2_type(app, 'image')
            %     updateRightPlot(app);
            %     if isprop(app.ref, 'crop')
            %         app.ref.images{i} = app.ref.ROIimage;
            %     end
            % else
            %     app.ReferenceButtonGroup.Visible = 0;
            %     buttons = app.ReferenceButtonGroup.Children;
            %     delete(buttons);
            %     if isprop(app.ref, 'crop')
            %         app.ref.images{i} = app.ref.ROIimage;
            %     end
            %     if sum(strcmp(app.RightPlotList.Items,'Ref Image')) == 1
            %         indexToDelete = find(strcmp(app.RightPlotList.Items, 'Ref Image'));
            %         app.RightPlotList.Items(indexToDelete) = [];
            %     end
            % end
        end

        % Button pushed function: ResettrimButton
        function ResettrimButtonPushed(app, event)
            % if ~app.MultiReferenceCheckBox.Value
                switch cell2mat(app.RefListBox.Value)
                    case 'ROI Reference'
                        app.ref.ROIimage = app.ref.orgImage;
                    case 'Simulated Reference'
                        app.ref.simImage = app.ref.orgImage;
                    case 'LAFM Reference'
                        app.ref.LAFM = app.ref.orgImage;
                    case 'Averaged Reference'
                        app.ref.av = app.ref.orgImage;
                end
            % else
                % app.ref.image = app.ref.orgImage;
            % end
            
            updatePlot2_type(app, 'image')
            updateRightPlot(app);
        end

        % Selection changed function: ReferenceButtonGroup
        function ReferenceButtonGroupSelectionChanged(app, event)
            updatePlot2_type(app, 'image')
            updateRightPlot(app);
        end

        % Callback function: Image, LabelSettingsMenu
        function OpenLabelSettings(app, event)
            LabelSettings();
        end

        % Value changed function: RefListBox
        function RefListBoxValueChanged(app, event)
            % if app.MultiReferenceCheckBox.Value == 0
                switch cell2mat(app.RefListBox.Value)
                    case 'ROI Reference'
                        app.ref.image = app.ref.ROIimage;
                        updatePlot2_type(app, 'image')
                        updateRightPlot(app);
                    case 'Simulated Reference'
                        app.ref.image = app.ref.simImage;
                        updatePlot2_type(app, 'image')
                        updateRightPlot(app);
                    case 'LAFM Reference'
                        app.ref.image = app.ref.LAFM;
                        updatePlot2_type(app, 'image')
                        updateRightPlot(app);
                    case 'Averaged Reference'
                        app.ref.image = app.ref.av;
                        updatePlot2_type(app, 'image')
                        updateRightPlot(app);
                end
                app.ref.position(3) = size(app.ref.image,2);
                app.ref.position(4) = size(app.ref.image,1);
            % end

            xdiff=  app.ref.position(3)-size(app.Part.Image,2);
            x1= ceil(xdiff/2); x2= floor(xdiff/2);
            if xdiff>0
                app.Part.Image =  padarray( app.Part.Image ,[0, x1],0,'post');
                app.Part.Image =  padarray( app.Part.Image ,[0, x2],0,'pre');
            elseif xdiff<0
                app.Part.Image = app.Part.Image(:, 1-x1:end+x2,:);
            end

            ydiff=  app.ref.position(4)-size(app.Part.Image,1);
            y1= ceil(ydiff/2); y2= floor(ydiff/2);
            if ydiff>0
                app.Part.Image =  padarray( app.Part.Image ,[y1, 0],0,'post');
                app.Part.Image =  padarray( app.Part.Image ,[y2, 0],0,'pre');
            elseif ydiff<0
                app.Part.Image = app.Part.Image(1-y1:end+y2,:,:);
            end


        end

        % Value changed function: symmetry
        function symmetryValueChanged(app, event)
            app.symmetry.Value = event.Value;
            app.LAFM_sym.Value = event.Value;
        end

        % Value changed function: RotationalFreedom
        function RotationalFreedomValueChanged(app, event)

            if app.RotationalFreedom.Value
                app.anglesEditField.Visible = 1;
                app.anglesLabel.Visible = 1;
                if app.symmetry.Value>1
                    r = 180./app.symmetry.Value;
                    app.anglesEditField.Value = [num2str(-r), ':10:', num2str(r)];
                end
            else
                app.anglesEditField.Visible = 0;
                app.anglesLabel.Visible = 0;
            end

        end

        % Callback function: AnalyzeMaskButton, MaskAnalysisMenu
        function MaskAreasMenuSelected(app, event)
            app.PopupApp.Mask = MaskTools();
        end

        % Callback function: Plotter, PlotterMenu_2
        function PlotterValueChanged(app, event)
            app.PopupApp.Plot = plotter();
        end

        % Cell edit callback: UITable
        function UITableCellEdit(app, event)
            indices = event.Indices;

            if indices(1) == 2
                app.ImageInfo.ScanSize = event.NewData;
            end

            if indices(1) == 3
                app.ImageInfo.ScanSpeed = event.NewData;
                 app.ImageInfo.time = double(0:1/app.ImageInfo.ScanSpeed:(app.ImageInfo.n-1)/app.ImageInfo.ScanSpeed);
                  updatePlot(app,'normal')
            end

            if indices(1) == 4
                app.ImageInfo.LineSpeed= event.NewData;
                 updatePlot(app,'normal')
            end

            if indices(1) == 5
                sz = size(app.ImageTarget);
                app.ImageInfo.yPixels = round(event.NewData);
                settings.pixelsy = app.ImageInfo.yPixels;
                settings.pixelsx = sz(2);  settings.rotate = 0;  settings.Scaleh = 1;
                settings.method = 'bicubic';
                ReshapeImage(app,settings)
            end

            if indices(1) == 6     
                sz = size(app.ImageTarget);
                app.ImageInfo.xPixels = round(event.NewData);
                settings.pixelsx = app.ImageInfo.xPixels;
                settings.pixelsy = sz(1);  settings.rotate = 0;  settings.Scaleh = 1;
                settings.method = 'bicubic';
                ReshapeImage(app,settings)
            end

            if indices(1) ==7
                app.ImageInfo.PixelPerNm = event.NewData;
                updatePlot(app,'normal')
            end

            if indices(1) ==8
                app.ImageInfo.Channel  = event.NewData;
            end

        end

        % Value changed function: BoxesCheckBox, MarkersCheckBox, 
        % ...and 2 other components
        function MarkersCheckBoxValueChanged(app, event)
            app.OverlayDropDown.Value = 'Detections';
            updatePlot(app,'normal')
        end

        % Value changed function: TrackList
        function TrackListValueChanged(app, event)

            value = str2double(app.TrackList.Value);
            app.Spinner.Value = 1;
            app.FrameSlider.Value = 1;
            if value > 0
                filter_Partlocs(app)
                updateVideoTools(app)
                updatePlot(app,'normal')
                updatePlot2(app,'normal')
            end
        end

        % Value changed function: SelectTrackButton
        function SelectTrackButtonValueChanged(app, event)
          % try
            if sum(app.Part.Locs(:,7))>1 && app.SelectTrackButton.Value == 1
            app.SelectTrackButton.Value = 1;
            updateTrackList(app)
            app.TrackList.Visible = app.SelectTrackButton.Value;
            filter_Partlocs(app)
            updateVideoTools(app)
            updatePlot(app,'normal')
            updatePlot2(app,'normal')
           
            else
                app.SelectTrackButton.Value=0;
                 updateTrackList(app)
            app.TrackList.Visible = app.SelectTrackButton.Value;
            filter_Partlocs(app)
            updateVideoTools(app)
            updatePlot(app,'normal')
            updatePlot2(app,'normal')
            end
         %  catch 
         %  end
        end

        % Callback function: VideoAlignSettings, VideoAlignSettingsMenu
        function VideoAlignSettingsImageClicked(app, event)
            app.PopupApp.Align = VideoAlign();
        end

        % Value changed function: ImgFilterType
        function ImgFilterTypeValueChanged(app, event)
            switch app.ImgFilterType.Value
                case {'Gaussian' ,'High-pass','Non-local mean'}
                    app.ImgFilterStrength.Limits =  [0,Inf];
                    app.ImgFilterStrength.Step =0.1;
                    app.ImgFilterStrength_2.Visible=0;
                    app.ImgFilterLabel.Value = 'Power';
                    app.ImgFilterLabel_2.Visible = 0;
                    app.ImgFilterLabel_3.Visible = 0;
                    app.ImgFilterStrength_3.Visible = 0;
                case {'Median','Mean'}
                    app.ImgFilterStrength.Limits =  [1,Inf];
                    app.ImgFilterStrength.Step =1;
                    app.ImgFilterStrength.Value=1;
                    app.ImgFilterLabel.Value = 'x';

                    app.ImgFilterStrength_2.Limits =  [1,Inf];
                    app.ImgFilterStrength_2.Visible=1;
                    app.ImgFilterStrength_2.Value=3;
                    app.ImgFilterStrength_2.Step =1;

                    app.ImgFilterLabel_2.Visible = 1;
                    app.ImgFilterLabel_2.Value = 'y';
                    app.ImgFilterLabel_3.Visible = 0;
                    app.ImgFilterStrength_3.Visible = 0;
                case 'Top Hat'
                    app.ImgFilterStrength.Limits =  [1,Inf];
                    app.ImgFilterStrength.Step =1;
                    app.ImgFilterStrength_2.Visible=0;
                    app.ImgFilterLabel.Value = 'disk size';
                    app.ImgFilterLabel_2.Visible = 0;
                    app.ImgFilterLabel_3.Visible = 0;
                    app.ImgFilterStrength_3.Visible = 0;
                case 'Sliding Mean Frames'
                    app.ImgFilterStrength.Limits =  [1,Inf];
                    app.ImgFilterStrength.Step =1;
                    app.ImgFilterStrength.Value=3;
                    app.ImgFilterLabel.Value = 'slide window';
                    app.ImgFilterStrength_2.Visible=0;
                    app.ImgFilterLabel_2.Visible = 0;
                case 'Sphere Deconvolution'
                    app.ImgFilterStrength.Limits =  [0,Inf];
                    app.ImgFilterStrength.Step =0.1;
                    app.ImgFilterStrength.Value=0.5;
                    app.ImgFilterLabel.Value = 'Radius';

                    app.ImgFilterStrength_2.Limits =  [1,200];
                    app.ImgFilterStrength_2.Visible=1;
                    app.ImgFilterStrength_2.Value=1;
                    app.ImgFilterStrength_2.Step =1;

                    app.ImgFilterLabel_2.Visible = 1;
                    app.ImgFilterLabel_2.Value = 'Iterations';

                case 'Scar Fill'
                    app.ImgFilterStrength.Limits =  [0,Inf];
                    app.ImgFilterStrength.Step =0.1;
                    app.ImgFilterStrength.Value=0.5;
                    app.ImgFilterLabel.Value = 'Sensitivity';

                    app.ImgFilterStrength_2.Limits =  [-Inf,Inf];
                    app.ImgFilterStrength_2.Visible=1;
                    app.ImgFilterStrength_2.Value=1;
                    app.ImgFilterStrength_2.Step =0.25;

                    app.ImgFilterLabel_2.Visible = 1;
                    app.ImgFilterLabel_2.Value = 'z threshold';

                    app.ImgFilterStrength_3.Limits = [1, Inf];
                    app.ImgFilterStrength_3.Visible=1;
                    app.ImgFilterStrength_3.Value=10;
                    app.ImgFilterStrength_3.Step =2;

                    app.ImgFilterLabel_3.Visible = 1;
                    app.ImgFilterLabel_3.Value = 'Length threshold';

                    % case 'Deconvolution'
                    %     app.ImgFilterLabel.Value = 'tip radius';
                    %     app.ImgFilterStrength.Limits =  [0.1,Inf];
                    %     app.ImgFilterStrength.Value = 3;
                    %      app.ImgFilterStrength.Step =0.5;
                    %
                    %     app.ImgFilterLabel_2.Value = 'Signal-Noise Ratio';
                    %      app.ImgFilterStrength_2.Limits =  [0,Inf];
                    %     app.ImgFilterStrength_2.Value = 5;
                    %      app.ImgFilterStrength_2.Step =0.1;
                    %
                    %
                    %     app.ImgFilterLabel_2.Visible = 1;
                    %     app.ImgFilterStrength_2.Visible=1;
            end

            switch app.ImgFilterType.Value
                case {'Mean All','Median all'}
                    app.PreviewonoffButton.Value =0;
                    previewOnOff(app)
                    app.ImageInfo.n = app.ImageInfo.n+1;

                    if numel(app.ImageInfo.ScanSize)>1
                        app.ImageInfo.ScanSize(app.ImageInfo.n) =app.ImageInfo.ScanSize(app.ImageInfo.n-1);
                        app.ImageInfo.PixelPerNm(app.ImageInfo.n) =app.ImageInfo.PixelPerNm(app.ImageInfo.n-1);
                    end

                    switch app.ImgFilterType.Value
                        case 'Mean All'
                    app.ImageTarget(:,:,app.ImageInfo.n) = mean(app.ImageTarget,3);
                        case 'Median all'
                    app.ImageTarget(:,:,app.ImageInfo.n) = median(app.ImageTarget,3);
                    end
                    updateVideoTools(app)
                    app.FrameSlider.Value = app.ImageInfo.n;
                    app.Spinner.Value = app.FrameSlider.Value;
                    try
                    app.ImageInfo.time(app.ImageInfo.n) = mean(app.ImageInfo.time);
                    catch
                    end
                    updatePlot(app,'normal')
                    app.ImgFilterType.Value = 'Off';

                    case 'Sliding Mean Frames'
                        if app.ImgFilterStrength.Value >1
                            app.ImagePreview = movmean(app.ImageTarget ,app.ImgFilterStrength.Value,3);
                            app.PreviewonoffButton.Value =1;
                            previewOnOff(app)
                            updatePlot(app,'normal')
                        end
                otherwise
                    app.filter_already =0;
                    app.PreviewonoffButton.Value =1;
                    previewOnOff(app)
                    run_filter(app)
            end
        end

        % Value changed function: AutoProfileButton
        function AutoProfileButtonValueChanged(app, event)
            S = app.AutoProfileButton.Value;
            app.OverlaylinesCheckBox.Visible=S;
            app.LineDiag2.Visible=S;
            app.LineDiag1.Visible=S;
            app.LineVert.Visible=S;
            app.LineHor.Visible=S;
            app.MaxradiusEditField.Visible=S;
            app.LinelengthLabel.Visible=S;
            app.FWHMreferenceDropDown.Visible=S;

        end

        % Value changed function: OverlaylinesCheckBox
        function OverlaylinesCheckBoxValueChanged(app, event)
            if app.OverlaylinesCheckBox.Value
                app.OverlayDropDown.Value = 'Line Profile';
            else
                app.OverlayDropDown.Value = 'Detections';
            end
            updatePlot(app,'normal')
        end

        % Value changed function: LAFMColorBarCheckBox
        function LAFMColorBarCheckBoxValueChanged(app, event)

            if app.LAFMColorBarCheckBox.Value
                switch app.LAFM_LUT.Value
                    case 'LAFM color'
                        color =  app.cmap.LAFMcolor;
                    case 'Rainbow'
                        color = app.cmap.Rainbow;
                    otherwise
                        color = eval(app.LAFM_LUT.Value);
                end
                colormap(app.UIAxes_2,color)
                colorbar(app.UIAxes_2,'Color','w','Box','off','TickDirection','out')

                switch app.LAFMView.Value
                    case {'LAFM map','Height'}
                        try
                            clim(app.UIAxes_2,[app.LAFM.lims(1), app.LAFM.lims(2)]);
                        catch
                            caxis(app.UIAxes_2,[app.LAFM.lims(1), app.LAFM.lims(2)]);
                        end
                end
                
            else
                colorbar(app.UIAxes_2,'off')
            end
        end

        % Callback function: ExportSettingsMenu, Export_settings
        function Export_settingsImageClicked(app, event)
             app.PopupApp.Export = ExportSettings();
        end

        % Button pushed function: RenderLAFMMovieButton
        function RenderLAFMMovieButtonPushed(app, event)
            loading(app)
            pause(0.000001)
      
            total_parts = max(app.LAFM.Locs(:,5));
            window = app.ParticlespermapEditField.Value;
            slide = app.SlidewindowEditField.Value;
            if slide > 0
                n = round((total_parts - window)/slide)+1;
            else
                n = round(total_parts/window);
            end

            switch  app.FramePartSwitch.Value
                case 'Frames'
                    img = app.ImageTarget;
                case 'Particles'
                    img = app.Part.Image(:,:,app.Part.IncludeImages);
            end

            switch app.LAFM_LUT.Value
                case 'LAFM color'
                    color =  app.cmap.LAFMcolor;
                case 'Rainbow'
                    color = app.cmap.Rainbow;
                otherwise
                    color = eval(app.LAFM_LUT.Value);
            end

            if strcmp(app.LAFMView.Value,'Probability')
                P = 1;
            else
                P=0;
            end

            if app.ViewSymmetrizedButton.Value && app.LAFM_sym.Value>1
                app.LAFM.Locs_sym = app.LAFM.Locs;
                if app.AutoCenterLAFM.Value
                    center_translation = FindCenter_ptCloud(app.LAFM_sym.Value, img, app.LAFM.Locs);
                    app.LAFM.Locs_sym (:,1) = app.LAFM.Locs(:,1)-center_translation(1);
                    app.LAFM.Locs_sym (:,2) = app.LAFM.Locs(:,2)-center_translation(2);
                end
                app.LAFM.Locs_sym = sym_ptCloud(app.LAFM_sym.Value, img, app.LAFM.Locs_sym);

                [app.LAFM.Movie_sym, app.LAFM.lims, app.LAFM.time]= LAFM_Movie_renderer(app.LAFM.Locs_sym, app.LAFM_gus.Value,  app.LAFM_exp.Value, color, P, [app.LAFMColorMin.Value, app.LAFMColorMax.Value],app.LAFM_ZScaleDropBox.Value, window, slide);

                for i = 1:n
                if strcmp(app.LAFMView.Value,'Height')
                    app.LAFM.Movie_sym{i} = app.LAFM.Movie_sym{i}./(mean(app.LAFM.Movie_sym{i},3)+0.8);
                end
                app.LAFM.Movie_sym{i} =  app.LAFM.Movie_sym{i}./max( app.LAFM.Movie_sym{i}(:));
                end

                if sum(strcmp(app.RightPlotList.Items,'LAFM sym Movie')) <1
                    app.RightPlotList.Items = [app.RightPlotList.Items , {'LAFM sym Movie'}];
                end
                app.RightPlotList.Value = 'LAFM sym Movie';

            else
              
                try
                    [app.LAFM.Movie, app.LAFM.lims, app.LAFM.time]= LAFM_Movie_renderer(app.LAFM.Locs, app.LAFM_gus.Value,  app.LAFM_exp.Value, color,P, [app.LAFMColorMin.Value, app.LAFMColorMax.Value],app.LAFM_ZScaleDropBox.Value, window, slide);
                catch
                    app.FramePartSwitch.Value = 'Particles';
                    [app.LAFM.Movie, app.LAFM.lims, app.LAFM.time] = LAFM_Movie_renderer(app.LAFM.Locs, app.LAFM_gus.Value,  app.LAFM_exp.Value, color, P, [app.LAFMColorMin.Value, app.LAFMColorMax.Value],app.LAFM_ZScaleDropBox.Value, window, slide);
                end
                for i = 1:n
                    if strcmp(app.LAFMView.Value,'Height')
                        app.LAFM.Movie{i} = app.LAFM.Movie{i}./(mean(app.LAFM.Movie{i},3)+0.8);
                    end
                    app.LAFM.Movie{i} = app.LAFM.Movie{i}./max(app.LAFM.Movie{i}(:));
                end

                if sum(strcmp(app.RightPlotList.Items,'LAFM Movie')) <1
                    app.RightPlotList.Items = [app.RightPlotList.Items , {'LAFM Movie'}];
                end
                app.RightPlotList.Value = 'LAFM Movie';

            end

            UpdateLAFM_MovieTools(app)
            RightPlotListValueChanged(app, event)
            finished(app)
            storedata(app)


        end

        % Callback function: LAFMFrameSlider, LAFMFrameSlider, LAFMSpinner,
        % 
        % ...and 1 other component
        function LAFMFrameSliderValueChanging(app, event)
            app.LAFMSpinner.Value = round(event.Value);
            app.LAFMFrameSlider.Value = round(event.Value);
            updatePlot2(app,'normal')
        end

        % Image clicked function: LAFMPlayImage
        function LAFMPlayImageClicked(app, event)
              app.LAFMPlayImage.Visible=0;
            app.LAFMPauseImage.Visible=1;
            app.stop=0;
            currentFrame = round(app.LAFMFrameSlider.Value);

            while true
                tic;
                if app.stop == 1
                    return
                end

                if currentFrame == app.LAFMFrameSlider.Limits(2) + 1
                    currentFrame = 1;
                end
                app.LAFMFrameSlider.Value = currentFrame;
                app.LAFMSpinner.Value = currentFrame;
                updatePlot2(app,'normal')
                playtime = toc;
                pause(1/app.fps.Value-playtime)
                drawnow
                currentFrame = currentFrame + 1;
            end

            app.PauseImage.Visible=0;
            app.PlayImage.Visible=1;
        end

        % Image clicked function: LAFMPauseImage
        function LAFMPauseImageClicked(app, event)
            app.LAFMPlayImage.Visible=1;
            app.LAFMPauseImage.Visible=0;
            app.stop = 1;
        end

        % Image clicked function: LAFMStartImage
        function LAFMStartImageClicked(app, event)
            app.LAFMFrameSlider.Value = 1;
            app.LAFMSpinner.Value = app.LAFMFrameSlider.Value;
            updatePlot2(app,'normal')
        end

        % Image clicked function: LAFMEndImage
        function LAFMEndImageClicked(app, event)
            app.LAFMFrameSlider.Value = app.LAFMFrameSlider.Limits(2);
            app.LAFMSpinner.Value = app.LAFMFrameSlider.Value;
            updatePlot2(app,'normal')
        end

        % Value changed function: InvertMask
        function InvertMaskValueChanged(app, event)
        if app.InvertMask.Value
                app.InvertMask.FontColor = [0 1 0];
                app.InvertMask.BackgroundColor = [0 0 0];
        else
                app.InvertMask.FontColor = [1 0 0];
                app.InvertMask.BackgroundColor = [1 1 1];
        end
                  app.filter_already =0;
            app.PreviewonoffButton.Value =1;
            previewOnOff(app)
            run_filter(app)
        end

        % Value changed function: MaskwithzscaleCheckBox
        function MaskwithzscaleCheckBoxValueChanged(app, event)
            HoldButtonPushed(app);
        end

        % Menu selected function: QuitMenu
        function QuitMenuSelected(app, event)
            delete(app.NanoLocz)
        end

        % Menu selected function: ResizerotateMenu
        function ResizerotateMenuSelected(app, event)
              app.PopupApp.ImageTools = ImageTools();
        end

        % Value changed function: MaxstepEditField_2
        function MaxstepEditField_2ValueChanged(app, event)
            app.MaxstepEditField.Value = app.MaxstepEditField_2.Value;
            
        end

        % Value changed function: MaxmissingEditField_2
        function MaxmissingEditField_2ValueChanged(app, event)
            app.MaxmissingEditField.Value = app.MaxmissingEditField_2.Value;
            
        end

        % Value changed function: Min_CCR_Edit
        function Min_CCR_EditValueChanged(app, event)
updateCCRHist(app)
            
        end

        % Value changed function: AspectRatioSwitch
        function AspectRatioSwitchValueChanged(app, event)
           
            if strcmp(app.AspectRatioSwitch.Value, '1:1')
                axis(app.UIAxes,'normal');
                pbaspect(app.UIAxes,[1 1 1])
            else
                axis(app.UIAxes,'tight','equal');
            end
            updatePlot(app,'normal')
        end

        % Value changed function: LAFMColorMax, LAFMColorMin
        function LAFMColorMaxValueChanged(app, event)
            app.LAFM_ZScaleDropBox.Value = 'Manual';
            if app.LAFMColorMax.Value > app.LAFMColorMin.Value
                RenderLAFMButtonPushed(app, event)
            else
                app.LAFMColorMax.Value = app.LAFMColorMin.Value+0.01;
            end
        end

        % Menu selected function: BatchConvertMenu
        function BatchLoadButtonPushed(app, event)
            %select output folder:
            outputFolder = uigetdir('', 'Select Output Folder');
            WorkingFolder = app.OpenFolder;
            if outputFolder == 0
            else
                for i = 1:numel(app.FilesListBox.Items)
                    clear Images
                    app.FilesListBox.Value = app.FilesListBox.Items{i};

                    im = Open_files(app);
                    Images.Imagedata = single(im);
                    Images.ImageInfo = app.ImageInfo;
                    Images.ImageName = app.ImageName;
                    Images.Channel = app.ImageInfo.Channel;
                    namesplit = split(app.ImageName,'.');
                    Images.ImageLocs=[];
                    Images.ref=[];
                    Images.LAFMLocs=[];

                    filename = join(namesplit,"_")+".mat";
                    filepath = fullfile(outputFolder, filename);
                    save(filepath, 'Images','WorkingFolder');
                end
                app.OpenFolder =outputFolder;
                updateFolder(app)
                app.FilesDropDown.Value = 'All files';
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.NanoLocz = uifigure('Visible', 'off');
            app.NanoLocz.Name = 'NanoLocz';
            app.NanoLocz.WindowButtonDownFcn = createCallbackFcn(app, @NanoLoczWindowButtonDown, true);
            app.NanoLocz.KeyPressFcn = createCallbackFcn(app, @NanoLoczKeyPress, true);
            app.FileMenu = uimenu(app.NanoLocz);
            app.FileMenu.Text = 'File';
            app.OpenMenu = uimenu(app.FileMenu);
            app.OpenMenu.Text = 'Open';
            app.FolderMenu = uimenu(app.OpenMenu);
            app.FolderMenu.MenuSelectedFcn = createCallbackFcn(app, @OpenFolderButtonClicked, true);
            app.FolderMenu.Text = 'Folder';
            app.OpenSessionMenu = uimenu(app.OpenMenu);
            app.OpenSessionMenu.MenuSelectedFcn = createCallbackFcn(app, @OpenSessionMenuSelected, true);
            app.OpenSessionMenu.Text = 'Session';
            app.SaveMenu = uimenu(app.FileMenu);
            app.SaveMenu.MenuSelectedFcn = createCallbackFcn(app, @SaveSessionImageClicked, true);
            app.SaveMenu.Text = 'Save';
            app.QuitMenu = uimenu(app.FileMenu);
            app.QuitMenu.MenuSelectedFcn = createCallbackFcn(app, @QuitMenuSelected, true);
            app.QuitMenu.Text = 'Quit';
            app.SettingsMenu = uimenu(app.NanoLocz);
            app.SettingsMenu.Text = 'Settings';
            app.LabelSettingsMenu = uimenu(app.SettingsMenu);
            app.LabelSettingsMenu.MenuSelectedFcn = createCallbackFcn(app, @OpenLabelSettings, true);
            app.LabelSettingsMenu.Text = 'Label Settings';
            app.ExportSettingsMenu = uimenu(app.SettingsMenu);
            app.ExportSettingsMenu.MenuSelectedFcn = createCallbackFcn(app, @Export_settingsImageClicked, true);
            app.ExportSettingsMenu.Text = 'Export Settings';
            app.VideoAlignSettingsMenu = uimenu(app.SettingsMenu);
            app.VideoAlignSettingsMenu.MenuSelectedFcn = createCallbackFcn(app, @VideoAlignSettingsImageClicked, true);
            app.VideoAlignSettingsMenu.Text = 'Video Align Settings';
            app.ImageMenu = uimenu(app.NanoLocz);
            app.ImageMenu.Text = 'Image';
            app.ResizerotateMenu = uimenu(app.ImageMenu);
            app.ResizerotateMenu.MenuSelectedFcn = createCallbackFcn(app, @ResizerotateMenuSelected, true);
            app.ResizerotateMenu.Text = 'Resize/rotate';
            app.ToolsMenu = uimenu(app.NanoLocz);
            app.ToolsMenu.Text = 'Tools';
            app.SimulationAFMMenu = uimenu(app.ToolsMenu);
            app.SimulationAFMMenu.MenuSelectedFcn = createCallbackFcn(app, @OpenMat_SimAFMMenuSelected, true);
            app.SimulationAFMMenu.Text = 'Simulation AFM';
            app.MaskAnalysisMenu = uimenu(app.ToolsMenu);
            app.MaskAnalysisMenu.MenuSelectedFcn = createCallbackFcn(app, @MaskAreasMenuSelected, true);
            app.MaskAnalysisMenu.Text = 'Mask Analysis';
            app.PlotterMenu_2 = uimenu(app.ToolsMenu);
            app.PlotterMenu_2.MenuSelectedFcn = createCallbackFcn(app, @PlotterValueChanged, true);
            app.PlotterMenu_2.Text = 'Plotter';
            app.BatchConvertMenu = uimenu(app.ToolsMenu);
            app.BatchConvertMenu.MenuSelectedFcn = createCallbackFcn(app, @BatchLoadButtonPushed, true);
            app.BatchConvertMenu.Text = 'Batch Convert';
            app.LeftPanel = uipanel(app.NanoLocz);
            app.loadbar = uiaxes(app.LeftPanel);
            title(app.loadbar, 'Title')
            xlabel(app.loadbar, 'X')
            ylabel(app.loadbar, 'Y')
            zlabel(app.loadbar, 'Z')
            app.FilesListBox = uilistbox(app.LeftPanel);
            app.FilesListBox.Items = {};
            app.FilesListBox.ValueChangedFcn = createCallbackFcn(app, @FilesListBoxValueChanged, true);
            app.FilesListBox.Value = {};
            app.UITable = uitable(app.LeftPanel);
            app.UITable.ColumnName = {'Parameter'; 'Value'};
            app.UITable.RowName = {};
            app.UITable.ColumnEditable = [false true];
            app.UITable.CellEditCallback = createCallbackFcn(app, @UITableCellEdit, true);
            app.FolderUp = uiimage(app.LeftPanel);
            app.FolderUp.ImageClickedFcn = createCallbackFcn(app, @FolderUpImageClicked, true);
            app.FolderUp.Tooltip = {'Move one folder above current directory'};
            app.FolderUp.ImageSource = fullfile(pathToMLAPP, 'icons', 'up.png');
            app.FolderDown = uiimage(app.LeftPanel);
            app.FolderDown.ImageClickedFcn = createCallbackFcn(app, @FolderDownImageClicked, true);
            app.FolderDown.Tooltip = {'Open folder in file list'};
            app.FolderDown.ImageSource = fullfile(pathToMLAPP, 'icons', 'down.png');
            app.TabGroup = uitabgroup(app.LeftPanel);
            app.LevelTab = uitab(app.TabGroup);
            app.LevelTab.Title = 'Level';
            app.LevelTab.ButtonDownFcn = createCallbackFcn(app, @LevelTabButtonDown, true);
            app.FilteringPanel = uipanel(app.LevelTab);
            app.FilteringPanel.Title = 'Filtering';
            app.ImgFilterStrength = uispinner(app.FilteringPanel);
            app.ImgFilterStrength.Step = 0.1;
            app.ImgFilterStrength.ValueChangingFcn = createCallbackFcn(app, @Refresh_Filter, true);
            app.ImgFilterStrength.Limits = [0 Inf];
            app.ImgFilterStrength.ValueChangedFcn = createCallbackFcn(app, @Refresh_Filter, true);
            app.FilterLabel = uilabel(app.FilteringPanel);
            app.FilterLabel.Text = 'Filter:      ';
            app.ImgFilterStrength_2 = uispinner(app.FilteringPanel);
            app.ImgFilterStrength_2.Step = 0.1;
            app.ImgFilterStrength_2.ValueChangingFcn = createCallbackFcn(app, @Refresh_Filter, true);
            app.ImgFilterStrength_2.Limits = [0 Inf];
            app.ImgFilterStrength_2.ValueChangedFcn = createCallbackFcn(app, @Refresh_Filter, true);
            app.ImgFilterLabel = uieditfield(app.FilteringPanel, 'text');
            app.ImgFilterLabel_2 = uieditfield(app.FilteringPanel, 'text');
            app.SubtractmodeCheckBox = uicheckbox(app.FilteringPanel);
            app.SubtractmodeCheckBox.ValueChangedFcn = createCallbackFcn(app, @Refresh_Filter, true);
            app.SubtractmodeCheckBox.Text = 'Subtract mode';
            app.ImgFilterType = uidropdown(app.FilteringPanel);
            app.ImgFilterType.Items = {'Off', 'Gaussian', 'Median', 'Mean', 'Non-local mean', 'High-pass', 'Top Hat', 'Sliding Mean Frames', 'Sphere Deconvolution', 'Mean All', 'Median all', 'Fill Mask', 'Scar Fill'};
            app.ImgFilterType.ValueChangedFcn = createCallbackFcn(app, @ImgFilterTypeValueChanged, true);
            app.ImgFilterType.Value = 'Off';
            app.ImgFilterStrength_3 = uispinner(app.FilteringPanel);
            app.ImgFilterStrength_3.Step = 0.1;
            app.ImgFilterStrength_3.ValueChangingFcn = createCallbackFcn(app, @Refresh_Filter, true);
            app.ImgFilterStrength_3.Limits = [0 Inf];
            app.ImgFilterStrength_3.ValueChangedFcn = createCallbackFcn(app, @Refresh_Filter, true);
            app.ImgFilterLabel_3 = uieditfield(app.FilteringPanel, 'text');
            app.LevelingPanel = uipanel(app.LevelTab);
            app.LevelingPanel.Title = 'Leveling';
            app.PlaneLabel = uilabel(app.LevelingPanel);
            app.PlaneLabel.Text = 'Plane';
            app.LineLabel = uilabel(app.LevelingPanel);
            app.LineLabel.Text = 'Line';
            app.XLineSpinner = uispinner(app.LevelingPanel);
            app.XLineSpinner.Limits = [0 Inf];
            app.XLineSpinner.ValueChangedFcn = createCallbackFcn(app, @Refresh_Filter, true);
            app.YLineSpinner = uispinner(app.LevelingPanel);
            app.YLineSpinner.Limits = [0 Inf];
            app.YLineSpinner.ValueChangedFcn = createCallbackFcn(app, @Refresh_Filter, true);
            app.XSpinnerLabel = uilabel(app.LevelingPanel);
            app.XSpinnerLabel.Text = 'X';
            app.XPlaneSpinner = uispinner(app.LevelingPanel);
            app.XPlaneSpinner.Limits = [0 Inf];
            app.XPlaneSpinner.ValueChangedFcn = createCallbackFcn(app, @Refresh_Filter, true);
            app.YSpinnerLabel = uilabel(app.LevelingPanel);
            app.YSpinnerLabel.Text = 'Y';
            app.YPlaneSpinner = uispinner(app.LevelingPanel);
            app.YPlaneSpinner.Limits = [0 Inf];
            app.YPlaneSpinner.ValueChangedFcn = createCallbackFcn(app, @Refresh_Filter, true);
            app.MedianCheckBox = uicheckbox(app.LevelingPanel);
            app.MedianCheckBox.ValueChangedFcn = createCallbackFcn(app, @Refresh_Filter, true);
            app.MedianCheckBox.Text = '';
            app.MedLabel = uilabel(app.LevelingPanel);
            app.MedLabel.Text = '-Med';
            app.MeanPlaneCheckBox = uicheckbox(app.LevelingPanel);
            app.MeanPlaneCheckBox.ValueChangedFcn = createCallbackFcn(app, @Refresh_Filter, true);
            app.MeanPlaneCheckBox.Text = '';
            app.MeanLabel_2 = uilabel(app.LevelingPanel);
            app.MeanLabel_2.Text = '-Mean';
            app.YSpinnerLabel_2 = uilabel(app.LevelingPanel);
            app.YSpinnerLabel_2.Text = 'Y';
            app.YSpinnerLabel_3 = uilabel(app.LevelingPanel);
            app.YSpinnerLabel_3.Text = 'X';
            app.YSpinnerLabel_4 = uilabel(app.LevelingPanel);
            app.YSpinnerLabel_4.Text = 'Auto:';
            app.AutoflattenDropDown = uidropdown(app.LevelingPanel);
            app.AutoflattenDropDown.Items = {'Off', '11plane-line0', '(plane11-line0) x2 (fit)', 'iterative holes', 'iterative peaks', 'Plane11 + Otsu Plane22', 'Plane', 'Line1 + Otsu Line2'};
            app.AutoflattenDropDown.ValueChangedFcn = createCallbackFcn(app, @Refresh_Filter, true);
            app.AutoflattenDropDown.Value = 'Off';
            app.Panel = uipanel(app.LevelTab);
            app.Hist_Axes = uiaxes(app.Panel);
            zlabel(app.Hist_Axes, 'Z')
            app.Hist_Axes.YTick = [];
            app.ClearROIButton = uibutton(app.Panel, 'push');
            app.ClearROIButton.ButtonPushedFcn = createCallbackFcn(app, @ClearROIButtonPushed, true);
            app.ClearROIButton.Text = 'Clear';
            app.lowerlimitLabel = uilabel(app.Panel);
            app.lowerlimitLabel.Text = 'lower limit';
            app.upperlimitLabel = uilabel(app.Panel);
            app.upperlimitLabel.Text = 'upper limit';
            app.SliderMin = uislider(app.Panel);
            app.SliderMin.MajorTicks = [];
            app.SliderMin.MajorTickLabels = {''};
            app.SliderMin.ValueChangingFcn = createCallbackFcn(app, @SliderMinValueChanging, true);
            app.SliderMin.MinorTicks = [];
            app.SliderMax = uislider(app.Panel);
            app.SliderMax.MajorTicks = [];
            app.SliderMax.MajorTickLabels = {''};
            app.SliderMax.ValueChangingFcn = createCallbackFcn(app, @SliderMaxValueChanging, true);
            app.SliderMax.MinorTicks = [];
            app.SliderMax.Tag = 'slidermax';
            app.SliderMax.Value = 100;
            app.ThresholdContrastSwitch = uiswitch(app.Panel, 'slider');
            app.ThresholdContrastSwitch.Items = {'Mask', 'Z-scale'};
            app.ThresholdContrastSwitch.Value = 'Mask';
            app.ROIThresholdButton = uibutton(app.Panel, 'state');
            app.ROIThresholdButton.ValueChangedFcn = createCallbackFcn(app, @ROIThresholdButtonValueChanged, true);
            app.ROIThresholdButton.Tooltip = {'Draw ROIs to exclude'};
            app.ROIThresholdButton.Text = 'Draw Mask';
            app.FitButton = uibutton(app.Panel, 'push');
            app.FitButton.ButtonPushedFcn = createCallbackFcn(app, @FitButtonPushed, true);
            app.FitButton.Text = 'Fit';
            app.InvertMask = uibutton(app.Panel, 'state');
            app.InvertMask.ValueChangedFcn = createCallbackFcn(app, @InvertMaskValueChanged, true);
            app.InvertMask.Text = 'Invert';
            app.Hist_excludeOut = uibutton(app.Panel, 'state');
            app.Hist_excludeOut.ValueChangedFcn = createCallbackFcn(app, @Hist_excludeOutValueChanged, true);
            app.Hist_excludeOut.Text = 'Zoom';
            app.LogYHist = uibutton(app.Panel, 'state');
            app.LogYHist.ValueChangedFcn = createCallbackFcn(app, @LogYHistValueChanged, true);
            app.LogYHist.Text = 'Log y';
            app.ViewMask = uibutton(app.Panel, 'state');
            app.ViewMask.ValueChangedFcn = createCallbackFcn(app, @ViewMaskValueChanged, true);
            app.ViewMask.Text = 'View Mask';
            app.ViewMask.Value = true;
            app.AnalyzeMaskButton = uibutton(app.Panel, 'push');
            app.AnalyzeMaskButton.ButtonPushedFcn = createCallbackFcn(app, @MaskAreasMenuSelected, true);
            app.AnalyzeMaskButton.Text = 'Analyze Mask';
            app.MethodLabel = uilabel(app.Panel);
            app.MethodLabel.Text = 'Method:  ';
            app.ThresholdMethod = uidropdown(app.Panel);
            app.ThresholdMethod.Items = {'histogram', 'otsu', '2 level otsu', 'step detection', 'adaptive'};
            app.ThresholdMethod.ValueChangedFcn = createCallbackFcn(app, @Refresh_Filter, true);
            app.ThresholdMethod.Value = 'histogram';
            app.MaskwithzscaleCheckBox = uicheckbox(app.Panel);
            app.MaskwithzscaleCheckBox.ValueChangedFcn = createCallbackFcn(app, @MaskwithzscaleCheckBoxValueChanged, true);
            app.MaskwithzscaleCheckBox.Text = 'Mask with z-scale';
            app.ZeroallButton = uibutton(app.LevelTab, 'push');
            app.ZeroallButton.ButtonPushedFcn = createCallbackFcn(app, @ZeroallButtonPushed, true);
            app.ZeroallButton.Text = 'Zero all';
            app.RestoreButton = uibutton(app.LevelTab, 'push');
            app.RestoreButton.ButtonPushedFcn = createCallbackFcn(app, @Reload, true);
            app.RestoreButton.Text = 'Restore';
            app.FixLineShift = uicheckbox(app.LevelTab);
            app.FixLineShift.ValueChangedFcn = createCallbackFcn(app, @Refresh_Filter, true);
            app.FixLineShift.Text = 'Bi-directional line shift';
            app.FixShiftMethod = uidropdown(app.LevelTab);
            app.FixShiftMethod.Items = {'Line peaks', 'Line correlation', 'Manual Shift'};
            app.FixShiftMethod.ValueChangedFcn = createCallbackFcn(app, @Refresh_Filter, true);
            app.FixShiftMethod.Value = 'Line peaks';
            app.FixShiftValue = uieditfield(app.LevelTab, 'numeric');
            app.FixShiftValue.ValueChangedFcn = createCallbackFcn(app, @Refresh_Filter, true);
            app.DetectTab = uitab(app.TabGroup);
            app.DetectTab.Title = 'Detect';
            app.DetectTab.ButtonDownFcn = createCallbackFcn(app, @DetectTabButtonDown, true);
            app.FilterCCREditField_Detect = uieditfield(app.DetectTab, 'numeric');
            app.FilterCCREditField_Detect.Limits = [0 Inf];
            app.FilterCCREditField_Detect.Value = 1;
            app.Filter_detections_panel = uipanel(app.DetectTab);
            app.HeightCCRHist = uiaxes(app.Filter_detections_panel);
            zlabel(app.HeightCCRHist, 'Z')
            app.HeightCCRHist.YTick = [];
            app.Max_h_Edit = uieditfield(app.Filter_detections_panel, 'numeric');
            app.Max_h_Edit.Value = 100;
            app.Min_h_Edit = uieditfield(app.Filter_detections_panel, 'numeric');
            app.Min_h_Edit.ValueChangedFcn = createCallbackFcn(app, @Min_h_EditValueChanged, true);
            app.Min_h_Edit.Value = 2;
            app.MaxLabel = uilabel(app.Filter_detections_panel);
            app.MaxLabel.Text = 'Max';
            app.MinLabel = uilabel(app.Filter_detections_panel);
            app.MinLabel.Text = 'Min';
            app.MinLabel_2 = uilabel(app.Filter_detections_panel);
            app.MinLabel_2.Text = 'Min';
            app.MaxLabel_2 = uilabel(app.Filter_detections_panel);
            app.MaxLabel_2.Text = 'Max';
            app.Max_CCR_Edit = uieditfield(app.Filter_detections_panel, 'numeric');
            app.Max_CCR_Edit.Limits = [-1 1];
            app.Max_CCR_Edit.Value = 1;
            app.Min_CCR_Edit = uieditfield(app.Filter_detections_panel, 'numeric');
            app.Min_CCR_Edit.Limits = [-1 1];
            app.Min_CCR_Edit.ValueChangedFcn = createCallbackFcn(app, @Min_CCR_EditValueChanged, true);
            app.Min_CCR_Edit.Value = 0.5;
            app.HeightCheckBox = uicheckbox(app.Filter_detections_panel);
            app.HeightCheckBox.Text = 'Height';
            app.CorrelationCheckBox = uicheckbox(app.Filter_detections_panel);
            app.CorrelationCheckBox.Text = 'Correlation';
            app.CorrelationCheckBox.Value = true;
            app.lowerlimitLabel_2 = uilabel(app.Filter_detections_panel);
            app.lowerlimitLabel_2.Text = 'lower limit';
            app.upperlimitLabel_2 = uilabel(app.Filter_detections_panel);
            app.upperlimitLabel_2.Text = 'upper limit';
            app.CCRSliderMin = uislider(app.Filter_detections_panel);
            app.CCRSliderMin.MajorTicks = [];
            app.CCRSliderMin.MajorTickLabels = {''};
            app.CCRSliderMin.ValueChangingFcn = createCallbackFcn(app, @CCRSliderMinValueChanging, true);
            app.CCRSliderMin.MinorTicks = [];
            app.CCRSliderMax = uislider(app.Filter_detections_panel);
            app.CCRSliderMax.MajorTicks = [];
            app.CCRSliderMax.MajorTickLabels = {''};
            app.CCRSliderMax.ValueChangingFcn = createCallbackFcn(app, @CCRSliderMaxValueChanging, true);
            app.CCRSliderMax.MinorTicks = [];
            app.CCRSliderMax.Value = 100;
            app.Height_corr_switch = uiswitch(app.Filter_detections_panel, 'slider');
            app.Height_corr_switch.Items = {'Correlation', 'Height'};
            app.Height_corr_switch.ValueChangedFcn = createCallbackFcn(app, @Height_corr_switchValueChanged, true);
            app.Height_corr_switch.Value = 'Correlation';
            app.Overlay = uipanel(app.DetectTab);
            app.MarkersCheckBox = uicheckbox(app.Overlay);
            app.MarkersCheckBox.ValueChangedFcn = createCallbackFcn(app, @MarkersCheckBoxValueChanged, true);
            app.MarkersCheckBox.Text = 'Markers';
            app.BoxesCheckBox = uicheckbox(app.Overlay);
            app.BoxesCheckBox.ValueChangedFcn = createCallbackFcn(app, @MarkersCheckBoxValueChanged, true);
            app.BoxesCheckBox.Text = 'Boxes';
            app.BoxesCheckBox.Value = true;
            app.TrackLengthEditFieldLabel = uilabel(app.Overlay);
            app.TrackLengthEditFieldLabel.Text = 'Track Length';
            app.TrackLengthEditField = uieditfield(app.Overlay, 'numeric');
            app.TrackLengthEditField.ValueChangedFcn = createCallbackFcn(app, @MarkersCheckBoxValueChanged, true);
            app.OverlayValues = uidropdown(app.Overlay);
            app.OverlayValues.Items = {'None', 'Height', 'CCR', 'Angle', 'Track Number', 'FWHM'};
            app.OverlayValues.ValueChangedFcn = createCallbackFcn(app, @MarkersCheckBoxValueChanged, true);
            app.OverlayValues.Value = 'None';
            app.ValuesLabel = uilabel(app.Overlay);
            app.ValuesLabel.Text = 'Values:';
            app.TrackDetectionsPanel = uipanel(app.DetectTab);
            app.RunTrackingButton = uibutton(app.TrackDetectionsPanel, 'push');
            app.RunTrackingButton.ButtonPushedFcn = createCallbackFcn(app, @RunTrackingButtonPushed, true);
            app.RunTrackingButton.Text = 'Run Tracking';
            app.MaxmissingEditFieldLabel = uilabel(app.TrackDetectionsPanel);
            app.MaxmissingEditFieldLabel.Text = 'Max missing ';
            app.MaxmissingEditField = uieditfield(app.TrackDetectionsPanel, 'numeric');
            app.MaxmissingEditField.Value = 3;
            app.MaxstepEditFieldLabel = uilabel(app.TrackDetectionsPanel);
            app.MaxstepEditFieldLabel.Text = 'Max step';
            app.MaxstepEditField = uieditfield(app.TrackDetectionsPanel, 'numeric');
            app.MaxstepEditField.Value = 10;
            app.FilterImageLabel = uilabel(app.DetectTab);
            app.FilterImageLabel.Text = 'Filter image';
            app.FilterImgEditField_Detect = uieditfield(app.DetectTab, 'numeric');
            app.FilterImgEditField_Detect.Limits = [0 Inf];
            app.FilterImgEditField_Detect.ValueChangedFcn = createCallbackFcn(app, @TrackLengthEditFieldValueChanged, true);
            app.FilterImgEditField_Detect.Value = 1;
            app.FindParticlesButton = uibutton(app.DetectTab, 'push');
            app.FindParticlesButton.ButtonPushedFcn = createCallbackFcn(app, @FindParticlesButtonPushed, true);
            app.FindParticlesButton.Text = 'Find Particles';
            app.SettingsLabel_Detect = uilabel(app.DetectTab);
            app.SettingsLabel_Detect.Text = 'Overlay Plot';
            app.SelectMethodLabel_2 = uilabel(app.DetectTab);
            app.SelectMethodLabel_2.Text = 'Select Method:';
            app.PeaksRegionsSwitch = uiswitch(app.DetectTab, 'slider');
            app.PeaksRegionsSwitch.Items = {'Peaks', 'ROI'};
            app.PeaksRegionsSwitch.ValueChangedFcn = createCallbackFcn(app, @PeaksRegionsSwitchValueChanged, true);
            app.PeaksRegionsSwitch.Value = 'ROI';
            app.BoxSizeEditFieldLabel = uilabel(app.DetectTab);
            app.BoxSizeEditFieldLabel.Visible = 'off';
            app.BoxSizeEditFieldLabel.Text = 'Box Size';
            app.BoxSizeEditField = uieditfield(app.DetectTab, 'numeric');
            app.BoxSizeEditField.Limits = [2 1000];
            app.BoxSizeEditField.Visible = 'off';
            app.BoxSizeEditField.Value = 30;
            app.SettingsLabel_Detect_2 = uilabel(app.DetectTab);
            app.SettingsLabel_Detect_2.Text = 'Tracking:';
            app.FilterxcorimageLabel = uilabel(app.DetectTab);
            app.FilterxcorimageLabel.Text = 'Filter xcor image';
            app.ExcludeEdgeCheckBox = uicheckbox(app.DetectTab);
            app.ExcludeEdgeCheckBox.Text = '';
            app.ExcludeEdgeCheckBox.Value = true;
            app.ExcludeedgesLabel = uilabel(app.DetectTab);
            app.ExcludeedgesLabel.Text = 'Exclude edges';
            app.FilterdetectionsLabel = uilabel(app.DetectTab);
            app.FilterdetectionsLabel.Text = 'Filter detections';
            app.PreviewPeaks = uicheckbox(app.DetectTab);
            app.PreviewPeaks.ValueChangedFcn = createCallbackFcn(app, @TrackLengthEditFieldValueChanged, true);
            app.PreviewPeaks.Visible = 'off';
            app.PreviewPeaks.Text = '';
            app.PreviewpeaksLabel = uilabel(app.DetectTab);
            app.PreviewpeaksLabel.Visible = 'off';
            app.PreviewpeaksLabel.Text = 'Preview peaks';
            app.Peaks_minHeight = uispinner(app.DetectTab);
            app.Peaks_minHeight.ValueChangedFcn = createCallbackFcn(app, @Peaks_minHeightValueChanged, true);
            app.Peaks_minHeight.Visible = 'off';
            app.Peaks_minHeight.Value = 2;
            app.Peaks_minSep = uispinner(app.DetectTab);
            app.Peaks_minSep.Limits = [1 50];
            app.Peaks_minSep.ValueChangedFcn = createCallbackFcn(app, @TrackLengthEditFieldValueChanged, true);
            app.Peaks_minSep.Visible = 'off';
            app.Peaks_minSep.Value = 2;
            app.MinheightLabel = uilabel(app.DetectTab);
            app.MinheightLabel.Visible = 'off';
            app.MinheightLabel.Text = 'Min height';
            app.MinseparationLabel = uilabel(app.DetectTab);
            app.MinseparationLabel.Visible = 'off';
            app.MinseparationLabel.Text = 'Min separation';
            app.Sq_roi_Button_2 = uibutton(app.DetectTab, 'state');
            app.Sq_roi_Button_2.ValueChangedFcn = createCallbackFcn(app, @Sq_roi_Button_2ValueChanged, true);
            app.Sq_roi_Button_2.Icon = fullfile(pathToMLAPP, 'icons', 'roi_sq.png');
            app.Sq_roi_Button_2.Text = '';
            app.AutoProfileButton = uibutton(app.DetectTab, 'state');
            app.AutoProfileButton.ValueChangedFcn = createCallbackFcn(app, @AutoProfileButtonValueChanged, true);
            app.AutoProfileButton.Visible = 'off';
            app.AutoProfileButton.Text = {'Auto'; 'Profile'};
            app.LineHor = uibutton(app.DetectTab, 'state');
            app.LineHor.Visible = 'off';
            app.LineHor.Text = '---';
            app.LineHor.Value = true;
            app.LineVert = uibutton(app.DetectTab, 'state');
            app.LineVert.Visible = 'off';
            app.LineVert.Text = '|';
            app.LineVert.Value = true;
            app.LineDiag1 = uibutton(app.DetectTab, 'state');
            app.LineDiag1.Visible = 'off';
            app.LineDiag1.Text = '/';
            app.LineDiag1.Value = true;
            app.LineDiag2 = uibutton(app.DetectTab, 'state');
            app.LineDiag2.Visible = 'off';
            app.LineDiag2.Text = '\';
            app.LineDiag2.Value = true;
            app.OverlaylinesCheckBox = uicheckbox(app.DetectTab);
            app.OverlaylinesCheckBox.ValueChangedFcn = createCallbackFcn(app, @OverlaylinesCheckBoxValueChanged, true);
            app.OverlaylinesCheckBox.Visible = 'off';
            app.OverlaylinesCheckBox.Text = 'Overlay lines';
            app.FWHMreferenceDropDownLabel = uilabel(app.DetectTab);
            app.FWHMreferenceDropDownLabel.Visible = 'off';
            app.FWHMreferenceDropDownLabel.Text = 'FWHM reference';
            app.FWHMreferenceDropDown = uidropdown(app.DetectTab);
            app.FWHMreferenceDropDown.Items = {'z = 0', 'local height'};
            app.FWHMreferenceDropDown.Visible = 'off';
            app.FWHMreferenceDropDown.Value = 'z = 0';
            app.MaxradiusEditField = uieditfield(app.DetectTab, 'numeric');
            app.MaxradiusEditField.Limits = [2 Inf];
            app.MaxradiusEditField.RoundFractionalValues = 'on';
            app.MaxradiusEditField.Visible = 'off';
            app.MaxradiusEditField.Value = 10;
            app.LinelengthLabel = uilabel(app.DetectTab);
            app.LinelengthLabel.Visible = 'off';
            app.LinelengthLabel.Text = 'Line length';
            app.FineAlignTab = uitab(app.TabGroup);
            app.FineAlignTab.Title = 'FineAlign';
            app.FineAlignTab.ButtonDownFcn = createCallbackFcn(app, @FineAlignTabButtonDown, true);
            app.Panel_3 = uipanel(app.FineAlignTab);
            app.searchangleEditFieldLabel = uilabel(app.Panel_3);
            app.searchangleEditFieldLabel.Text = 'search angle +/- ';
            app.searchangleEditField = uieditfield(app.Panel_3, 'numeric');
            app.searchangleEditField.Limits = [0 Inf];
            app.searchangleEditField.Value = 90;
            app.RotationalLabel = uilabel(app.Panel_3);
            app.RotationalLabel.Text = 'Rotational';
            app.IterationsEditField_2Label = uilabel(app.Panel_3);
            app.IterationsEditField_2Label.Text = 'Iterations';
            app.Rot_IterationsEditField = uieditfield(app.Panel_3, 'numeric');
            app.Rot_IterationsEditField.Limits = [0 Inf];
            app.TranslationalLabel = uilabel(app.Panel_3);
            app.TranslationalLabel.Text = 'Translational';
            app.IterationsEditField_3Label = uilabel(app.Panel_3);
            app.IterationsEditField_3Label.Text = 'Iterations';
            app.Tran_IterationsEditField = uieditfield(app.Panel_3, 'numeric');
            app.Tran_IterationsEditField.Limits = [0 Inf];
            app.Tran_IterationsEditField.Value = 1;
            app.MaxdriftEditField_2Label = uilabel(app.Panel_3);
            app.MaxdriftEditField_2Label.Text = 'Max drift';
            app.MaxdriftEditField_2 = uieditfield(app.Panel_3, 'numeric');
            app.MaxdriftEditField_2.Limits = [0 Inf];
            app.MaxdriftEditField_2.Value = 5;
            app.AutoupdatereferenceimageCheckBox = uicheckbox(app.Panel_3);
            app.AutoupdatereferenceimageCheckBox.Text = 'Auto update reference image';
            app.RunFineAlignButton = uibutton(app.Panel_3, 'push');
            app.RunFineAlignButton.ButtonPushedFcn = createCallbackFcn(app, @RunFineAlignButtonPushed, true);
            app.RunFineAlignButton.Text = 'Run Align';
            app.ThresholdwithminheightCheckBox = uicheckbox(app.Panel_3);
            app.ThresholdwithminheightCheckBox.Text = 'Threshold with min height';
            app.RoiareaEditFieldLabel = uilabel(app.FineAlignTab);
            app.RoiareaEditFieldLabel.Text = '+/- Roi area';
            app.RoiareaEditField = uieditfield(app.FineAlignTab, 'numeric');
            app.ApplyButton = uibutton(app.FineAlignTab, 'push');
            app.ApplyButton.ButtonPushedFcn = createCallbackFcn(app, @ApplyButtonPushed, true);
            app.ApplyButton.Text = 'Apply';
            app.TrackList = uilistbox(app.FineAlignTab);
            app.TrackList.Items = {};
            app.TrackList.ValueChangedFcn = createCallbackFcn(app, @TrackListValueChanged, true);
            app.TrackList.Visible = 'off';
            app.TrackList.Value = {};
            app.SelectTrackButton = uibutton(app.FineAlignTab, 'state');
            app.SelectTrackButton.ValueChangedFcn = createCallbackFcn(app, @SelectTrackButtonValueChanged, true);
            app.SelectTrackButton.Text = {'Select'; 'Track'};
            app.TrackDetectionsPanel_2 = uipanel(app.FineAlignTab);
            app.RunTrackingButton_2 = uibutton(app.TrackDetectionsPanel_2, 'push');
            app.RunTrackingButton_2.ButtonPushedFcn = createCallbackFcn(app, @RunTrackingButtonPushed, true);
            app.RunTrackingButton_2.Text = 'Run Tracking';
            app.MaxmissingEditField_2Label = uilabel(app.TrackDetectionsPanel_2);
            app.MaxmissingEditField_2Label.Text = 'Max missing ';
            app.MaxmissingEditField_2 = uieditfield(app.TrackDetectionsPanel_2, 'numeric');
            app.MaxmissingEditField_2.ValueChangedFcn = createCallbackFcn(app, @MaxmissingEditField_2ValueChanged, true);
            app.MaxmissingEditField_2.Value = 3;
            app.MaxstepEditField_2Label = uilabel(app.TrackDetectionsPanel_2);
            app.MaxstepEditField_2Label.Text = 'Max step';
            app.MaxstepEditField_2 = uieditfield(app.TrackDetectionsPanel_2, 'numeric');
            app.MaxstepEditField_2.ValueChangedFcn = createCallbackFcn(app, @MaxstepEditField_2ValueChanged, true);
            app.MaxstepEditField_2.Value = 10;
            app.SettingsLabel_Detect_3 = uilabel(app.FineAlignTab);
            app.SettingsLabel_Detect_3.Text = 'Tracking:';
            app.LocalizeTab = uitab(app.TabGroup);
            app.LocalizeTab.Title = 'Localize';
            app.Localize_FilterPanel = uipanel(app.LocalizeTab);
            app.HighpassDropDownLabel = uilabel(app.Localize_FilterPanel);
            app.HighpassDropDownLabel.Text = 'High-pass';
            app.LoczHighpassDropDown = uidropdown(app.Localize_FilterPanel);
            app.LoczHighpassDropDown.Items = {'Off', 'High-pass', '-Average'};
            app.LoczHighpassDropDown.ValueChangedFcn = createCallbackFcn(app, @TrackLengthEditFieldValueChanged, true);
            app.LoczHighpassDropDown.Value = 'Off';
            app.LowpassDropDownLabel = uilabel(app.Localize_FilterPanel);
            app.LowpassDropDownLabel.Text = 'Low-pass';
            app.LoczLowpassDropDown = uidropdown(app.Localize_FilterPanel);
            app.LoczLowpassDropDown.Items = {'Off', 'Gaussian'};
            app.LoczLowpassDropDown.ValueChangedFcn = createCallbackFcn(app, @TrackLengthEditFieldValueChanged, true);
            app.LoczLowpassDropDown.Value = 'Gaussian';
            app.LoczLowpass = uispinner(app.Localize_FilterPanel);
            app.LoczLowpass.Step = 0.1;
            app.LoczLowpass.Limits = [0 Inf];
            app.LoczLowpass.ValueChangedFcn = createCallbackFcn(app, @TrackLengthEditFieldValueChanged, true);
            app.LoczHighpass = uispinner(app.Localize_FilterPanel);
            app.LoczHighpass.Step = 0.1;
            app.LoczHighpass.Limits = [0 Inf];
            app.LoczHighpass.ValueChangedFcn = createCallbackFcn(app, @TrackLengthEditFieldValueChanged, true);
            app.LocFilterLabel = uilabel(app.Localize_FilterPanel);
            app.LocFilterLabel.Text = 'LocFilter';
            app.LoczShowfilterCheckBox = uicheckbox(app.Localize_FilterPanel);
            app.LoczShowfilterCheckBox.ValueChangedFcn = createCallbackFcn(app, @TrackLengthEditFieldValueChanged, true);
            app.LoczShowfilterCheckBox.Text = 'See filter';
            app.FindallpeaksButton = uibutton(app.LocalizeTab, 'push');
            app.FindallpeaksButton.ButtonPushedFcn = createCallbackFcn(app, @FindallpeaksButtonPushed, true);
            app.FindallpeaksButton.Text = 'Find all peaks';
            app.LoczImagepeaksCheckBox = uicheckbox(app.LocalizeTab);
            app.LoczImagepeaksCheckBox.ValueChangedFcn = createCallbackFcn(app, @LoczImagepeaksCheckBoxValueChanged, true);
            app.LoczImagepeaksCheckBox.Text = 'Preview peaks';
            app.ThresholdLabel = uilabel(app.LocalizeTab);
            app.ThresholdLabel.Text = 'Min separation';
            app.LoczMinSep = uispinner(app.LocalizeTab);
            app.LoczMinSep.Limits = [1 50];
            app.LoczMinSep.ValueChangedFcn = createCallbackFcn(app, @TrackLengthEditFieldValueChanged, true);
            app.LoczMinSep.Value = 1;
            app.Localize_ThreshPanel = uipanel(app.LocalizeTab);
            app.ThresholdLabel_2 = uilabel(app.Localize_ThreshPanel);
            app.ThresholdLabel_2.Text = 'Height';
            app.LoczHeightSpinner = uispinner(app.Localize_ThreshPanel);
            app.LoczHeightSpinner.Step = 0.1;
            app.LoczHeightSpinner.ValueChangedFcn = createCallbackFcn(app, @TrackLengthEditFieldValueChanged, true);
            app.ThresholdLabel_3 = uilabel(app.Localize_ThreshPanel);
            app.ThresholdLabel_3.Text = 'Threshold';
            app.ProminenceSpinnerLabel = uilabel(app.Localize_ThreshPanel);
            app.ProminenceSpinnerLabel.Text = 'Prominence ';
            app.LoczProminenceSpinner = uispinner(app.Localize_ThreshPanel);
            app.LoczProminenceSpinner.Step = 0.1;
            app.LoczProminenceSpinner.ValueChangedFcn = createCallbackFcn(app, @TrackLengthEditFieldValueChanged, true);
            app.RenderLAFMButton = uibutton(app.LocalizeTab, 'push');
            app.RenderLAFMButton.ButtonPushedFcn = createCallbackFcn(app, @RenderLAFMButtonPushed, true);
            app.RenderLAFMButton.Text = 'Render LAFM';
            app.RenderpointsLabel = uilabel(app.LocalizeTab);
            app.RenderpointsLabel.Text = 'Render points';
            app.LAFM_gus = uieditfield(app.LocalizeTab, 'numeric');
            app.LAFM_gus.Limits = [0.1 50];
            app.LAFM_gus.Value = 1;
            app.imageexpansionLabel = uilabel(app.LocalizeTab);
            app.imageexpansionLabel.Text = 'Expansion';
            app.LAFM_exp = uieditfield(app.LocalizeTab, 'numeric');
            app.LAFM_exp.Limits = [1 50];
            app.LAFM_exp.Value = 5;
            app.RenderLAFMMovieButton = uibutton(app.LocalizeTab, 'push');
            app.RenderLAFMMovieButton.ButtonPushedFcn = createCallbackFcn(app, @RenderLAFMMovieButtonPushed, true);
            app.RenderLAFMMovieButton.Text = 'Render LAFM Movie';
            app.ParticlespermapEditFieldLabel = uilabel(app.LocalizeTab);
            app.ParticlespermapEditFieldLabel.Text = 'Particles per map';
            app.ParticlespermapEditField = uieditfield(app.LocalizeTab, 'numeric');
            app.ParticlespermapEditField.Value = 50;
            app.SlidewindowEditFieldLabel = uilabel(app.LocalizeTab);
            app.SlidewindowEditFieldLabel.Text = 'Slide window ';
            app.SlidewindowEditField = uieditfield(app.LocalizeTab, 'numeric');
            app.SlidewindowEditField.Value = 50;
            app.BoostsubpixCheckBox = uicheckbox(app.LocalizeTab);
            app.BoostsubpixCheckBox.Text = 'Boost subpix';
            app.PreviewonoffButton = uibutton(app.LeftPanel, 'state');
            app.PreviewonoffButton.ValueChangedFcn = createCallbackFcn(app, @PreviewonoffButtonValueChanged, true);
            app.PreviewonoffButton.Text = 'Preview on/off';
            app.FramePartSwitch = uiswitch(app.LeftPanel, 'slider');
            app.FramePartSwitch.Items = {'Frames', 'Particles'};
            app.FramePartSwitch.ValueChangedFcn = createCallbackFcn(app, @FramePartSwitchValueChanged, true);
            app.FramePartSwitch.Value = 'Frames';
            app.BidirectionaldataCheckBox = uicheckbox(app.LeftPanel);
            app.BidirectionaldataCheckBox.ValueChangedFcn = createCallbackFcn(app, @BidirectionaldataCheckBoxValueChanged, true);
            app.BidirectionaldataCheckBox.Tooltip = {'Bidirectional scan - How to check: Trace will appear as smoothed ReTrace'};
            app.BidirectionaldataCheckBox.Text = 'Bi-directional data';
            app.AutoplayCheckBox = uicheckbox(app.LeftPanel);
            app.AutoplayCheckBox.Text = 'Auto play';
            app.View1stCheckBox = uicheckbox(app.LeftPanel);
            app.View1stCheckBox.ValueChangedFcn = createCallbackFcn(app, @Reload, true);
            app.View1stCheckBox.Text = 'View 1st         frames';
            app.View1st = uieditfield(app.LeftPanel, 'numeric');
            app.View1st.Limits = [1 Inf];
            app.View1st.ValueChangedFcn = createCallbackFcn(app, @Reload, true);
            app.View1st.Value = 10;
            app.OpenFolderButton = uiimage(app.LeftPanel);
            app.OpenFolderButton.ImageClickedFcn = createCallbackFcn(app, @OpenFolderButtonClicked, true);
            app.OpenFolderButton.Tooltip = {'Open working folder or session'};
            app.OpenFolderButton.ImageSource = fullfile(pathToMLAPP, 'icons', 'open.png');
            app.SaveSession = uiimage(app.LeftPanel);
            app.SaveSession.ImageClickedFcn = createCallbackFcn(app, @SaveSessionImageClicked, true);
            app.SaveSession.Tooltip = {'Save'};
            app.SaveSession.ImageSource = fullfile(pathToMLAPP, 'icons', 'save.png');
            app.FilesDropDown = uidropdown(app.LeftPanel);
            app.FilesDropDown.Items = {'All files', 'Processed'};
            app.FilesDropDown.ValueChangedFcn = createCallbackFcn(app, @FilesDropDownValueChanged, true);
            app.FilesDropDown.Value = 'All files';
            app.SaveType = uidropdown(app.LeftPanel);
            app.SaveType.Items = {'All Processed', 'Current File'};
            app.SaveType.Value = 'All Processed';
            app.CancelButton = uibutton(app.LeftPanel, 'push');
            app.CancelButton.ButtonPushedFcn = createCallbackFcn(app, @CancelButtonPushed, true);
            app.CancelButton.Visible = 'off';
            app.CancelButton.Text = 'Cancel';
            app.ChannelDropDown = uidropdown(app.LeftPanel);
            app.ChannelDropDown.Items = {'Height'};
            app.ChannelDropDown.ValueChangedFcn = createCallbackFcn(app, @FilesListBoxValueChanged, true);
            app.ChannelDropDown.Value = 'Height';
            app.ErrorButton = uibutton(app.LeftPanel, 'state');
            app.ErrorButton.ValueChangedFcn = createCallbackFcn(app, @FilesListBoxValueChanged, true);
            app.ErrorButton.Visible = 'off';
            app.ErrorButton.Text = 'Error';
            app.Stacker = uidropdown(app.LeftPanel);
            app.Stacker.Items = {'Stack off', 'Stack', 'Intercalate'};
            app.Stacker.Value = 'Stack off';
            app.CenterPanel = uipanel(app.NanoLocz);
            app.UIAxes = uiaxes(app.CenterPanel);
            app.UIAxes.XTick = [];
            app.UIAxes.XTickLabel = '';
            app.UIAxes.YTick = [];
            app.UIAxes.YTickLabel = '';
            app.UIAxes.ZTick = [0 0.5 1];
            app.UIAxes.ZTickLabel = '';
            colormap(app.UIAxes, 'hot')
            app.UIAxes_2 = uiaxes(app.CenterPanel);
            app.UIAxes_2.XTick = [];
            app.UIAxes_2.XTickLabel = '';
            app.UIAxes_2.YTick = [];
            app.UIAxes_2.YTickLabel = '';
            colormap(app.UIAxes_2, 'hot')
            app.ResetButton = uibutton(app.CenterPanel, 'push');
            app.ResetButton.ButtonPushedFcn = createCallbackFcn(app, @ResetButtonPushed, true);
            app.ResetButton.Text = 'Reset';
            app.Circ_roi_Button = uibutton(app.CenterPanel, 'state');
            app.Circ_roi_Button.ValueChangedFcn = createCallbackFcn(app, @Circ_roi_ButtonValueChanged, true);
            app.Circ_roi_Button.Tooltip = {'Height profile over time'};
            app.Circ_roi_Button.Icon = fullfile(pathToMLAPP, 'icons', 'ZProfile.png');
            app.Circ_roi_Button.Text = '';
            app.Sq_roi_Button = uibutton(app.CenterPanel, 'state');
            app.Sq_roi_Button.ValueChangedFcn = createCallbackFcn(app, @Sq_roi_ButtonValueChanged, true);
            app.Sq_roi_Button.Tooltip = {'Draw reference area'};
            app.Sq_roi_Button.Icon = fullfile(pathToMLAPP, 'icons', 'roi_sq.png');
            app.Sq_roi_Button.Text = '';
            app.Line_roi_Button = uibutton(app.CenterPanel, 'state');
            app.Line_roi_Button.ValueChangedFcn = createCallbackFcn(app, @Line_roi_ButtonValueChanged, true);
            app.Line_roi_Button.Tooltip = {'Line Profile'};
            app.Line_roi_Button.Icon = fullfile(pathToMLAPP, 'icons', 'line.png');
            app.Line_roi_Button.Text = '';
            app.Point_roi_Button = uibutton(app.CenterPanel, 'state');
            app.Point_roi_Button.ValueChangedFcn = createCallbackFcn(app, @Point_roi_ButtonValueChanged, true);
            app.Point_roi_Button.Tooltip = {'Data tips'};
            app.Point_roi_Button.Icon = fullfile(pathToMLAPP, 'icons', 'multi_point.png');
            app.Point_roi_Button.Text = '';
            app.CropButton = uibutton(app.CenterPanel, 'push');
            app.CropButton.ButtonPushedFcn = createCallbackFcn(app, @CropButtonPushed, true);
            app.CropButton.Icon = fullfile(pathToMLAPP, 'icons', 'crop.png');
            app.CropButton.Tooltip = {'Crop'};
            app.CropButton.Text = '';
            app.ZoomIn = uibutton(app.CenterPanel, 'state');
            app.ZoomIn.ValueChangedFcn = createCallbackFcn(app, @ZoomInValueChanged, true);
            app.ZoomIn.Tooltip = {'Zoom'};
            app.ZoomIn.Icon = fullfile(pathToMLAPP, 'icons', 'zoomin.png');
            app.ZoomIn.Text = '';
            app.kymo = uibutton(app.CenterPanel, 'push');
            app.kymo.ButtonPushedFcn = createCallbackFcn(app, @kymoButtonPushed, true);
            app.kymo.Icon = fullfile(pathToMLAPP, 'icons', 'kymo.png');
            app.kymo.Tooltip = {'Kymograph from line profile'};
            app.kymo.Text = '';
            app.MontageButton = uibutton(app.CenterPanel, 'push');
            app.MontageButton.ButtonPushedFcn = createCallbackFcn(app, @MontageButtonPushed, true);
            app.MontageButton.Icon = fullfile(pathToMLAPP, 'icons', 'montage.png');
            app.MontageButton.Tooltip = {'Image montage'};
            app.MontageButton.Text = '';
            app.ZPanel = uipanel(app.CenterPanel);
            app.ZScaleDropBox = uidropdown(app.ZPanel);
            app.ZScaleDropBox.Items = {'Max Min', 'Exc outliers', 'Histogram', 'Manual'};
            app.ZScaleDropBox.ValueChangedFcn = createCallbackFcn(app, @TrackLengthEditFieldValueChanged, true);
            app.ZScaleDropBox.Value = 'Max Min';
            app.maxSpinnerLabel = uilabel(app.ZPanel);
            app.maxSpinnerLabel.Text = 'max';
            app.ContrastMaxSpinner = uispinner(app.ZPanel);
            app.ContrastMaxSpinner.Step = 0.25;
            app.ContrastMaxSpinner.ValueChangedFcn = createCallbackFcn(app, @HoldButtonPushed, true);
            app.minSpinnerLabel = uilabel(app.ZPanel);
            app.minSpinnerLabel.Text = 'min';
            app.ContrastMinSpinner = uispinner(app.ZPanel);
            app.ContrastMinSpinner.Step = 0.25;
            app.ContrastMinSpinner.ValueChangedFcn = createCallbackFcn(app, @HoldButtonPushed, true);
            app.AutoButton = uibutton(app.ZPanel, 'push');
            app.AutoButton.ButtonPushedFcn = createCallbackFcn(app, @AutoButtonPushed, true);
            app.AutoButton.Text = 'Auto';
            app.HoldButton = uibutton(app.ZPanel, 'push');
            app.HoldButton.ButtonPushedFcn = createCallbackFcn(app, @HoldButtonPushed, true);
            app.HoldButton.Text = 'Hold';
            app.Image = uiimage(app.CenterPanel);
            app.Image.ImageClickedFcn = createCallbackFcn(app, @OpenLabelSettings, true);
            app.Image.ImageSource = fullfile(pathToMLAPP, 'icons', 'settings.png');
            app.PopAx1 = uibutton(app.CenterPanel, 'push');
            app.PopAx1.ButtonPushedFcn = createCallbackFcn(app, @PopAx1ButtonPushed, true);
            app.PopAx1.Icon = fullfile(pathToMLAPP, 'icons', 'pop.png');
            app.PopAx1.Text = '';
            app.Forceto11Button = uibutton(app.CenterPanel, 'push');
            app.Forceto11Button.ButtonPushedFcn = createCallbackFcn(app, @Forceto11ButtonPushed, true);
            app.Forceto11Button.Text = 'Force to 1:1';
            app.ProcessingImage = uiimage(app.CenterPanel);
            app.ProcessingImage.Visible = 'off';
            app.ProcessingImage.ImageSource = fullfile(pathToMLAPP, 'icons', 'processing.png');
            app.ColorBarCheckBox = uicheckbox(app.CenterPanel);
            app.ColorBarCheckBox.ValueChangedFcn = createCallbackFcn(app, @ColorBarCheckBoxValueChanged, true);
            app.ColorBarCheckBox.Text = 'z-scale (nm)';
            app.AcceptButtonImage = uiimage(app.CenterPanel);
            app.AcceptButtonImage.ImageClickedFcn = createCallbackFcn(app, @AcceptButtonImageClicked, true);
            app.AcceptButtonImage.Visible = 'off';
            app.AcceptButtonImage.ImageSource = fullfile(pathToMLAPP, 'icons', 'accept.png');
            app.ViewpixelsDropDown = uidropdown(app.CenterPanel);
            app.ViewpixelsDropDown.Items = {'Original', 'Interpolated'};
            app.ViewpixelsDropDown.ValueChangedFcn = createCallbackFcn(app, @TrackLengthEditFieldValueChanged, true);
            app.ViewpixelsDropDown.Value = 'Original';
            app.LeftPlotList = uidropdown(app.CenterPanel);
            app.LeftPlotList.Items = {'Target', 'Preview'};
            app.LeftPlotList.Value = 'Target';
            app.AspectRatioSwitch = uiswitch(app.CenterPanel, 'slider');
            app.AspectRatioSwitch.Items = {'Original', '1:1'};
            app.AspectRatioSwitch.ValueChangedFcn = createCallbackFcn(app, @AspectRatioSwitchValueChanged, true);
            app.AspectRatioSwitch.Value = 'Original';
            app.TimescaleCheckBox = uicheckbox(app.CenterPanel);
            app.TimescaleCheckBox.ValueChangedFcn = createCallbackFcn(app, @TrackLengthEditFieldValueChanged, true);
            app.TimescaleCheckBox.Tooltip = {''};
            app.TimescaleCheckBox.Text = 'Timescale';
            app.ScalebarCheckBox = uicheckbox(app.CenterPanel);
            app.ScalebarCheckBox.ValueChangedFcn = createCallbackFcn(app, @TrackLengthEditFieldValueChanged, true);
            app.ScalebarCheckBox.Tooltip = {''};
            app.ScalebarCheckBox.Text = 'Scale bar';
            app.OverlayDropDown = uidropdown(app.CenterPanel);
            app.OverlayDropDown.Items = {'None', 'Mask', 'Line Profile', 'Detections', 'Localizations'};
            app.OverlayDropDown.ValueChangedFcn = createCallbackFcn(app, @TrackLengthEditFieldValueChanged, true);
            app.OverlayDropDown.Value = 'Mask';
            app.PlotTypeDropDown = uidropdown(app.CenterPanel);
            app.PlotTypeDropDown.Items = {'2D surface', '3D surface', 'Coordinates'};
            app.PlotTypeDropDown.ValueChangedFcn = createCallbackFcn(app, @PlotTypeDropDownValueChanged, true);
            app.PlotTypeDropDown.Value = '2D surface';
            app.ColourmapDropDown = uidropdown(app.CenterPanel);
            app.ColourmapDropDown.Items = {'AFM brown', 'AFM dark gold', 'AFM gold', 'fire', 'Rainbow', 'gray', 'hot', 'cool', 'Orange hot', 'jet', 'turbo', 'parula', 'hsv', 'winter', 'spring', 'summer', 'autumn'};
            app.ColourmapDropDown.ValueChangedFcn = createCallbackFcn(app, @TrackLengthEditFieldValueChanged, true);
            app.ColourmapDropDown.Value = 'AFM brown';
            app.ExportFileFormat = uidropdown(app.CenterPanel);
            app.ExportFileFormat.Items = {'.tiff', '.gif', '.avi', '.png', '.jpeg', '.pdf', '.txt', '.csv', '.xls', 'Matlab workspace'};
            app.ExportFileFormat.Value = '.tiff';
            app.ExportSelect = uidropdown(app.CenterPanel);
            app.ExportSelect.Items = {'Plot 1', 'Plot 2', 'data'};
            app.ExportSelect.Value = 'Plot 1';
            app.ExportButton = uibutton(app.CenterPanel, 'push');
            app.ExportButton.ButtonPushedFcn = createCallbackFcn(app, @ExportButtonPushed, true);
            app.ExportButton.Tooltip = {''};
            app.ExportButton.Text = 'Export';
            app.VideoPanel = uipanel(app.CenterPanel);
            app.FrameSlider = uislider(app.VideoPanel);
            app.FrameSlider.Limits = [1 10];
            app.FrameSlider.MajorTicks = [1 10];
            app.FrameSlider.MajorTickLabels = {'1', '10'};
            app.FrameSlider.ValueChangingFcn = createCallbackFcn(app, @FrameSliderValueChanging, true);
            app.FrameSlider.Value = 1;
            app.Spinner = uispinner(app.VideoPanel);
            app.Spinner.Limits = [1 Inf];
            app.Spinner.ValueChangedFcn = createCallbackFcn(app, @SpinnerValueChanged, true);
            app.Spinner.Value = 1;
            app.fpsEditFieldLabel = uilabel(app.VideoPanel);
            app.fpsEditFieldLabel.Text = 'fps';
            app.fps = uieditfield(app.VideoPanel, 'numeric');
            app.fps.Limits = [0.1 50];
            app.fps.Value = 10;
            app.PlayImage = uiimage(app.VideoPanel);
            app.PlayImage.ImageClickedFcn = createCallbackFcn(app, @PlayImageClicked, true);
            app.PlayImage.ImageSource = fullfile(pathToMLAPP, 'icons', 'play.png');
            app.PauseImage = uiimage(app.VideoPanel);
            app.PauseImage.ImageClickedFcn = createCallbackFcn(app, @PauseImageClicked, true);
            app.PauseImage.ImageSource = fullfile(pathToMLAPP, 'icons', 'pause.png');
            app.EndImage = uiimage(app.VideoPanel);
            app.EndImage.ImageClickedFcn = createCallbackFcn(app, @EndImageClicked, true);
            app.EndImage.ImageSource = fullfile(pathToMLAPP, 'icons', 'end.png');
            app.StartImage = uiimage(app.VideoPanel);
            app.StartImage.ImageClickedFcn = createCallbackFcn(app, @StartImageClicked, true);
            app.StartImage.ImageSource = fullfile(pathToMLAPP, 'icons', 'start.png');
            app.BinImage = uiimage(app.VideoPanel);
            app.BinImage.ImageClickedFcn = createCallbackFcn(app, @BinImageClicked, true);
            app.BinImage.ImageSource = fullfile(pathToMLAPP, 'icons', 'bin.png');
            app.BinRangeImage = uiimage(app.VideoPanel);
            app.BinRangeImage.ImageClickedFcn = createCallbackFcn(app, @BinRangeImageClicked, true);
            app.BinRangeImage.ImageSource = fullfile(pathToMLAPP, 'icons', 'bin.png');
            app.RemoveframesEditField = uieditfield(app.VideoPanel, 'text');
            app.DeleterangeLabel = uilabel(app.VideoPanel);
            app.DeleterangeLabel.Text = 'Delete range:';
            app.VideoAlignButton = uibutton(app.VideoPanel, 'push');
            app.VideoAlignButton.ButtonPushedFcn = createCallbackFcn(app, @RunAlignButtonPushed, true);
            app.VideoAlignButton.Text = 'Video Align';
            app.VideoAlignSettings = uiimage(app.VideoPanel);
            app.VideoAlignSettings.ImageClickedFcn = createCallbackFcn(app, @VideoAlignSettingsImageClicked, true);
            app.VideoAlignSettings.ImageSource = fullfile(pathToMLAPP, 'icons', 'settings.png');
            app.RightPlotList = uidropdown(app.CenterPanel);
            app.RightPlotList.Items = {'Off'};
            app.RightPlotList.ValueChangedFcn = createCallbackFcn(app, @RightPlotListValueChanged, true);
            app.RightPlotList.Value = 'Off';
            app.PopAx2 = uibutton(app.CenterPanel, 'push');
            app.PopAx2.ButtonPushedFcn = createCallbackFcn(app, @PopAx2Pushed, true);
            app.PopAx2.Icon = fullfile(pathToMLAPP, 'icons', 'pop.png');
            app.PopAx2.Text = '';
            app.ReferenceButtonGroup = uibuttongroup(app.CenterPanel);
            app.ReferenceButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @ReferenceButtonGroupSelectionChanged, true);
            app.ReferenceButtonGroup.Title = 'Reference:';
            app.ReferenceButtonGroup.Visible = 'off';
            app.UITable2 = uitable(app.CenterPanel);
            app.UITable2.ColumnName = '';
            app.UITable2.RowName = {};
            app.UITable2.Visible = 'off';
            app.LineProfilePanel = uipanel(app.CenterPanel);
            app.LineProfilePanel.Visible = 'off';
            app.AlignButton = uibutton(app.LineProfilePanel, 'push');
            app.AlignButton.ButtonPushedFcn = createCallbackFcn(app, @AlignButtonPushed, true);
            app.AlignButton.Text = 'Align';
            app.Live = uibutton(app.LineProfilePanel, 'state');
            app.Live.Text = 'Live';
            app.Live.Value = true;
            app.AlignLineType = uidropdown(app.LineProfilePanel);
            app.AlignLineType.Items = {'Correlation', 'Max'};
            app.AlignLineType.Value = 'Max';
            app.FWHMCheckBox = uicheckbox(app.LineProfilePanel);
            app.FWHMCheckBox.Text = 'FWHM';
            app.Plotter = uibutton(app.CenterPanel, 'state');
            app.Plotter.ValueChangedFcn = createCallbackFcn(app, @PlotterValueChanged, true);
            app.Plotter.Tooltip = {'View data'};
            app.Plotter.Icon = fullfile(pathToMLAPP, 'icons', 'Plotter.png');
            app.Plotter.Text = '';
            app.ReferenceTools = uipanel(app.CenterPanel);
            app.ReferenceTools.Visible = 'off';
            app.RefCropButton = uibutton(app.ReferenceTools, 'push');
            app.RefCropButton.ButtonPushedFcn = createCallbackFcn(app, @RefCropButtonPushed, true);
            app.RefCropButton.Icon = fullfile(pathToMLAPP, 'icons', 'crop.png');
            app.RefCropButton.Tooltip = {'Crop Reference'};
            app.RefCropButton.Text = '';
            app.ResettrimButton = uibutton(app.ReferenceTools, 'push');
            app.ResettrimButton.ButtonPushedFcn = createCallbackFcn(app, @ResettrimButtonPushed, true);
            app.ResettrimButton.Text = {'Reset'; 'trim'};
            app.RefListBox = uilistbox(app.ReferenceTools);
            app.RefListBox.Items = {};
            app.RefListBox.Multiselect = 'on';
            app.RefListBox.ValueChangedFcn = createCallbackFcn(app, @RefListBoxValueChanged, true);
            app.RefListBox.Value = {};
            app.ImportSimulationButton = uibutton(app.ReferenceTools, 'push');
            app.ImportSimulationButton.ButtonPushedFcn = createCallbackFcn(app, @OpenMat_SimAFMMenuSelected, true);
            app.ImportSimulationButton.Text = 'Import Simulation';
            app.AverageReferenceButton = uibutton(app.ReferenceTools, 'push');
            app.AverageReferenceButton.ButtonPushedFcn = createCallbackFcn(app, @AverageReferenceButtonPushed, true);
            app.AverageReferenceButton.Text = 'Average Reference';
            app.RotationalAvgButton = uibutton(app.ReferenceTools, 'push');
            app.RotationalAvgButton.ButtonPushedFcn = createCallbackFcn(app, @RotationalAvgButtonPushed, true);
            app.RotationalAvgButton.Text = 'Rotational Avg';
            app.symmetry = uispinner(app.ReferenceTools);
            app.symmetry.Limits = [1 2000];
            app.symmetry.ValueChangedFcn = createCallbackFcn(app, @symmetryValueChanged, true);
            app.symmetry.Value = 1;
            app.CenterReferenceButton = uibutton(app.ReferenceTools, 'push');
            app.CenterReferenceButton.ButtonPushedFcn = createCallbackFcn(app, @CenterReferenceButtonPushed, true);
            app.CenterReferenceButton.Text = 'Center Reference';
            app.SymmetryLabel = uilabel(app.ReferenceTools);
            app.SymmetryLabel.Text = 'Symmetry:';
            app.anglesLabel = uilabel(app.ReferenceTools);
            app.anglesLabel.Visible = 'off';
            app.anglesLabel.Text = 'angles: ';
            app.anglesEditField = uieditfield(app.ReferenceTools, 'text');
            app.anglesEditField.Visible = 'off';
            app.anglesEditField.Value = '-40:10:40';
            app.RotationalFreedom = uibutton(app.ReferenceTools, 'state');
            app.RotationalFreedom.ValueChangedFcn = createCallbackFcn(app, @RotationalFreedomValueChanged, true);
            app.RotationalFreedom.Text = 'Allow Rotation';
            app.FindParticlesButton_2 = uibutton(app.ReferenceTools, 'push');
            app.FindParticlesButton_2.ButtonPushedFcn = createCallbackFcn(app, @FindParticlesButtonPushed, true);
            app.FindParticlesButton_2.Text = 'Find Particles';
            app.LAFMPanel = uipanel(app.CenterPanel);
            app.MinLabel_5 = uilabel(app.LAFMPanel);
            app.MinLabel_5.Text = 'Min';
            app.LAFMMinSlider = uislider(app.LAFMPanel);
            app.LAFMMinSlider.Limits = [0 1];
            app.LAFMMinSlider.MajorTicks = [];
            app.LAFMMinSlider.ValueChangingFcn = createCallbackFcn(app, @LAFMMinSliderValueChanging, true);
            app.LAFMMinSlider.MinorTicks = [];
            app.LAFM_sym = uispinner(app.LAFMPanel);
            app.LAFM_sym.Limits = [1 50];
            app.LAFM_sym.ValueChangedFcn = createCallbackFcn(app, @RenderLAFMButtonPushed, true);
            app.LAFM_sym.Value = 1;
            app.AutoCenterLAFM = uicheckbox(app.LAFMPanel);
            app.AutoCenterLAFM.ValueChangedFcn = createCallbackFcn(app, @RenderLAFMButtonPushed, true);
            app.AutoCenterLAFM.Text = 'Auto Center';
            app.AutoCenterLAFM.Value = true;
            app.ViewSymmetrizedButton = uibutton(app.LAFMPanel, 'state');
            app.ViewSymmetrizedButton.ValueChangedFcn = createCallbackFcn(app, @RenderLAFMButtonPushed, true);
            app.ViewSymmetrizedButton.Text = 'View Symmetrized';
            app.LAFMScalebarCheckBox = uicheckbox(app.LAFMPanel);
            app.LAFMScalebarCheckBox.ValueChangedFcn = createCallbackFcn(app, @LAFMScalebarCheckBoxValueChanged, true);
            app.LAFMScalebarCheckBox.Tooltip = {'Right click for more settings'};
            app.LAFMScalebarCheckBox.Text = 'Scale bar';
            app.ColormaxLabel = uilabel(app.LAFMPanel);
            app.ColormaxLabel.Text = 'Color max (nm)';
            app.LAFMColorMax = uispinner(app.LAFMPanel);
            app.LAFMColorMax.Step = 0.25;
            app.LAFMColorMax.ValueChangedFcn = createCallbackFcn(app, @LAFMColorMaxValueChanged, true);
            app.ColorminLabel = uilabel(app.LAFMPanel);
            app.ColorminLabel.Text = 'Color min (nm)';
            app.LAFMColorMin = uispinner(app.LAFMPanel);
            app.LAFMColorMin.Step = 0.25;
            app.LAFMColorMin.ValueChangedFcn = createCallbackFcn(app, @LAFMColorMaxValueChanged, true);
            app.LAFM_LUT = uidropdown(app.LAFMPanel);
            app.LAFM_LUT.Items = {'LAFM color', 'gray', 'Rainbow', 'hot', 'jet'};
            app.LAFM_LUT.ValueChangedFcn = createCallbackFcn(app, @RenderLAFMButtonPushed, true);
            app.LAFM_LUT.Value = 'LAFM color';
            app.TypeLabel = uilabel(app.LAFMPanel);
            app.TypeLabel.Text = 'Type';
            app.LAFMView = uidropdown(app.LAFMPanel);
            app.LAFMView.Items = {'LAFM map', 'Probability', 'Height'};
            app.LAFMView.ValueChangedFcn = createCallbackFcn(app, @RenderLAFMButtonPushed, true);
            app.LAFMView.Value = 'LAFM map';
            app.ColorLabel = uilabel(app.LAFMPanel);
            app.ColorLabel.Text = 'Color';
            app.LAFMColorBarCheckBox = uicheckbox(app.LAFMPanel);
            app.LAFMColorBarCheckBox.ValueChangedFcn = createCallbackFcn(app, @LAFMColorBarCheckBoxValueChanged, true);
            app.LAFMColorBarCheckBox.Text = 'z-scale (nm)';
            app.LAFMMaxSlider = uislider(app.LAFMPanel);
            app.LAFMMaxSlider.Limits = [0 1];
            app.LAFMMaxSlider.MajorTicks = [];
            app.LAFMMaxSlider.ValueChangingFcn = createCallbackFcn(app, @LAFMMaxSliderValueChanging, true);
            app.LAFMMaxSlider.MinorTicks = [];
            app.LAFMMaxSlider.Value = 1;
            app.LAFMFrameSlider = uislider(app.LAFMPanel);
            app.LAFMFrameSlider.Limits = [1 10];
            app.LAFMFrameSlider.MajorTicks = [];
            app.LAFMFrameSlider.MajorTickLabels = {''};
            app.LAFMFrameSlider.ValueChangedFcn = createCallbackFcn(app, @LAFMFrameSliderValueChanging, true);
            app.LAFMFrameSlider.ValueChangingFcn = createCallbackFcn(app, @LAFMFrameSliderValueChanging, true);
            app.LAFMFrameSlider.MinorTicks = [];
            app.LAFMFrameSlider.Visible = 'off';
            app.LAFMFrameSlider.Value = 1;
            app.LAFMPauseImage = uiimage(app.LAFMPanel);
            app.LAFMPauseImage.ImageClickedFcn = createCallbackFcn(app, @LAFMPauseImageClicked, true);
            app.LAFMPauseImage.Visible = 'off';
            app.LAFMPauseImage.ImageSource = fullfile(pathToMLAPP, 'icons', 'pause.png');
            app.LAFMPlayImage = uiimage(app.LAFMPanel);
            app.LAFMPlayImage.ImageClickedFcn = createCallbackFcn(app, @LAFMPlayImageClicked, true);
            app.LAFMPlayImage.Visible = 'off';
            app.LAFMPlayImage.ImageSource = fullfile(pathToMLAPP, 'icons', 'play.png');
            app.LAFMEndImage = uiimage(app.LAFMPanel);
            app.LAFMEndImage.ImageClickedFcn = createCallbackFcn(app, @LAFMEndImageClicked, true);
            app.LAFMEndImage.Visible = 'off';
            app.LAFMEndImage.ImageSource = fullfile(pathToMLAPP, 'icons', 'end.png');
            app.LAFMStartImage = uiimage(app.LAFMPanel);
            app.LAFMStartImage.ImageClickedFcn = createCallbackFcn(app, @LAFMStartImageClicked, true);
            app.LAFMStartImage.Visible = 'off';
            app.LAFMStartImage.ImageSource = fullfile(pathToMLAPP, 'icons', 'start.png');
            app.MaxLabel_5 = uilabel(app.LAFMPanel);
            app.MaxLabel_5.Text = 'Max';
            app.LAFMSpinner = uispinner(app.LAFMPanel);
            app.LAFMSpinner.ValueChangingFcn = createCallbackFcn(app, @LAFMFrameSliderValueChanging, true);
            app.LAFMSpinner.Limits = [1 Inf];
            app.LAFMSpinner.ValueChangedFcn = createCallbackFcn(app, @LAFMFrameSliderValueChanging, true);
            app.LAFMSpinner.Visible = 'off';
            app.LAFMSpinner.Value = 1;
            app.LAFMTimescaleCheckBox = uicheckbox(app.LAFMPanel);
            app.LAFMTimescaleCheckBox.ValueChangedFcn = createCallbackFcn(app, @LAFMScalebarCheckBoxValueChanged, true);
            app.LAFMTimescaleCheckBox.Visible = 'off';
            app.LAFMTimescaleCheckBox.Tooltip = {'Right click for more settings'};
            app.LAFMTimescaleCheckBox.Text = 'Timescale';
            app.LAFM_ZScaleDropBox = uidropdown(app.LAFMPanel);
            app.LAFM_ZScaleDropBox.Items = {'Max Min', 'Exc outliers', 'Manual'};
            app.LAFM_ZScaleDropBox.ValueChangedFcn = createCallbackFcn(app, @RenderLAFMButtonPushed, true);
            app.LAFM_ZScaleDropBox.Value = 'Max Min';
            app.Export_settings = uiimage(app.CenterPanel);
            app.Export_settings.ImageClickedFcn = createCallbackFcn(app, @Export_settingsImageClicked, true);
            app.Export_settings.ImageSource = fullfile(pathToMLAPP, 'icons', 'settings.png');
            % Show the figure after all components are created
            app.NanoLocz.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = NanoLocz_v1_10

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.NanoLocz)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.NanoLocz)
        end
    end
end