classdef NanoLocz_v1_41 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        NanoLocz                        matlab.ui.Figure
        FileMenu                        matlab.ui.container.Menu
        OpenMenu                        matlab.ui.container.Menu
        FolderMenu                      matlab.ui.container.Menu
        OpenSessionMenu                 matlab.ui.container.Menu
        ImportMenu                      matlab.ui.container.Menu
        LocalizationsMenu               matlab.ui.container.Menu
        ParticleLocationsMenu           matlab.ui.container.Menu
        SaveMenu                        matlab.ui.container.Menu
        QuitMenu                        matlab.ui.container.Menu
        SettingsMenu                    matlab.ui.container.Menu
        SettingsMenu_2                  matlab.ui.container.Menu
        LabelsettingsMenu               matlab.ui.container.Menu
        ImageMenu                       matlab.ui.container.Menu
        ResizerotateMenu                matlab.ui.container.Menu
        DSurfaceLightingMenu            matlab.ui.container.Menu
        ToolsMenu                       matlab.ui.container.Menu
        SimulationAFMMenu               matlab.ui.container.Menu
        MaskAnalysisMenu                matlab.ui.container.Menu
        PlotterMenu_2                   matlab.ui.container.Menu
        TransferParticlesMenu           matlab.ui.container.Menu
        LAFMMenu                        matlab.ui.container.Menu
        FRCResolutionMenu               matlab.ui.container.Menu
        ImportLocalizationsMenu         matlab.ui.container.Menu
        AffineAlignMenu                 matlab.ui.container.Menu
        ConvertParticlestoframesMenu    matlab.ui.container.Menu
        LogMenu                         matlab.ui.container.Menu
        OpenLogwindowMenu               matlab.ui.container.Menu
        ViewImagehistoryMenu            matlab.ui.container.Menu
        ExportLogastxtMenu              matlab.ui.container.Menu
        FullLogMenu                     matlab.ui.container.Menu
        CurrentImageLogMenu             matlab.ui.container.Menu
        CenterPanel                     matlab.ui.container.Panel
        Lightmode                       matlab.ui.control.StateButton
        FramePartSwitch                 matlab.ui.control.Switch
        AcceptButtonImage               matlab.ui.control.Image
        ProcessingImage                 matlab.ui.control.Image
        DrawTool                        matlab.ui.control.DropDown
        RemoveMask_tool_Button          matlab.ui.control.StateButton
        AddMask_tool_Button             matlab.ui.control.StateButton
        Profile_tool_Button             matlab.ui.control.StateButton
        Average_tool_Button             matlab.ui.control.StateButton
        ROI_Button                      matlab.ui.control.StateButton
        Circ_roi_Button                 matlab.ui.control.StateButton
        Freehand_roi_Button             matlab.ui.control.StateButton
        Plotter                         matlab.ui.control.Button
        ToolLabel                       matlab.ui.control.Label
        DrawLabel                       matlab.ui.control.Label
        Draw                            matlab.ui.control.DropDown
        PreferencesIcon                 matlab.ui.control.Button
        TimescaleCheckBox               matlab.ui.control.CheckBox
        ResetButton                     matlab.ui.control.Button
        ExportButton                    matlab.ui.control.Button
        ExportSelect                    matlab.ui.control.DropDown
        ExportFileFormat                matlab.ui.control.DropDown
        TabGroup2                       matlab.ui.container.TabGroup
        LineTab                         matlab.ui.container.Tab
        UITable2                        matlab.ui.control.Table
        ReferenceTab                    matlab.ui.container.Tab
        FindParticlesButton_2           matlab.ui.control.Button
        anglesEditField                 matlab.ui.control.EditField
        anglesLabel                     matlab.ui.control.Label
        RotationalFreedom               matlab.ui.control.StateButton
        SymmetryLabel                   matlab.ui.control.Label
        CenterReferenceButton           matlab.ui.control.Button
        symmetry                        matlab.ui.control.Spinner
        RotationalAvgButton             matlab.ui.control.Button
        AverageReferenceButton          matlab.ui.control.Button
        ImportSimulationButton          matlab.ui.control.Button
        RefListBox                      matlab.ui.control.ListBox
        ResettrimButton                 matlab.ui.control.Button
        RefCropButton                   matlab.ui.control.Button
        LAFMTab                         matlab.ui.container.Tab
        LAFMTimescaleCheckBox           matlab.ui.control.CheckBox
        LAFMStartImage                  matlab.ui.control.Image
        LAFMEndImage                    matlab.ui.control.Image
        LAFMPlayImage                   matlab.ui.control.Image
        LAFMPauseImage                  matlab.ui.control.Image
        LAFMFrameSlider                 matlab.ui.control.Slider
        LAFMView                        matlab.ui.control.DropDown
        TypeLabel                       matlab.ui.control.Label
        LAFMColorMin                    matlab.ui.control.Spinner
        ColorminLabel                   matlab.ui.control.Label
        LAFMColorMax                    matlab.ui.control.Spinner
        ColormaxLabel                   matlab.ui.control.Label
        LAFMMinSlider                   matlab.ui.control.Slider
        MinLabel_5                      matlab.ui.control.Label
        MaxLabel_5                      matlab.ui.control.Label
        LAFMMaxSlider                   matlab.ui.control.Slider
        LAFM_sym                        matlab.ui.control.Spinner
        LAFM_ZScaleDropBox              matlab.ui.control.DropDown
        LAFMSpinner                     matlab.ui.control.Spinner
        LAFMColorBarCheckBox            matlab.ui.control.CheckBox
        ColorLabel                      matlab.ui.control.Label
        LAFM_LUT                        matlab.ui.control.DropDown
        LAFMScalebarCheckBox            matlab.ui.control.CheckBox
        ViewSymmetrizedButton           matlab.ui.control.StateButton
        AutoCenterLAFM                  matlab.ui.control.CheckBox
        LineProfilePanel                matlab.ui.container.Panel
        LineFFTButton                   matlab.ui.control.Button
        LineThickness                   matlab.ui.control.Spinner
        LineThicknessSpinnerLabel       matlab.ui.control.Label
        FWHMCheckBox                    matlab.ui.control.CheckBox
        AlignLineType                   matlab.ui.control.DropDown
        Live                            matlab.ui.control.StateButton
        AlignButton                     matlab.ui.control.Button
        PopAx2                          matlab.ui.control.Button
        RightPlotList                   matlab.ui.control.DropDown
        VideoPanel                      matlab.ui.container.Panel
        VideoAlignButton                matlab.ui.control.Button
        DeleterangeLabel                matlab.ui.control.Label
        RemoveframesEditField           matlab.ui.control.EditField
        BinRangeImage                   matlab.ui.control.Image
        BinImage                        matlab.ui.control.Image
        StartImage                      matlab.ui.control.Image
        EndImage                        matlab.ui.control.Image
        PauseImage                      matlab.ui.control.Image
        PlayImage                       matlab.ui.control.Image
        fps                             matlab.ui.control.NumericEditField
        fpsEditFieldLabel               matlab.ui.control.Label
        Spinner                         matlab.ui.control.Spinner
        FrameSlider                     matlab.ui.control.Slider
        ColourmapDropDown               matlab.ui.control.DropDown
        PlotTypeDropDown                matlab.ui.control.DropDown
        OverlayDropDown                 matlab.ui.control.DropDown
        ScalebarCheckBox                matlab.ui.control.CheckBox
        AspectRatioSwitch               matlab.ui.control.Switch
        LeftPlotList                    matlab.ui.control.DropDown
        ViewpixelsDropDown              matlab.ui.control.DropDown
        ColorBarCheckBox                matlab.ui.control.CheckBox
        Forceto11Button                 matlab.ui.control.Button
        PopAx1                          matlab.ui.control.Button
        ZPanel                          matlab.ui.container.Panel
        HoldButton                      matlab.ui.control.Button
        AutoButton                      matlab.ui.control.Button
        ContrastMinSpinner              matlab.ui.control.Spinner
        minSpinnerLabel                 matlab.ui.control.Label
        ContrastMaxSpinner              matlab.ui.control.Spinner
        maxSpinnerLabel                 matlab.ui.control.Label
        ZScaleDropBox                   matlab.ui.control.DropDown
        MontageButton                   matlab.ui.control.Button
        kymo                            matlab.ui.control.Button
        ZoomIn                          matlab.ui.control.StateButton
        CropButton                      matlab.ui.control.Button
        Point_roi_Button                matlab.ui.control.StateButton
        Line_roi_Button                 matlab.ui.control.StateButton
        Sq_roi_Button                   matlab.ui.control.StateButton
        UIAxes_2                        matlab.ui.control.UIAxes
        UIAxes                          matlab.ui.control.UIAxes
        LeftPanel                       matlab.ui.container.Panel
        PopHist                         matlab.ui.control.Button
        LogYHist                        matlab.ui.control.StateButton
        Hist_excludeOut                 matlab.ui.control.StateButton
        FitButton                       matlab.ui.control.Button
        ResetButton_2                   matlab.ui.control.Button
        HistogramDropDown               matlab.ui.control.DropDown
        PreviewonoffButton              matlab.ui.control.StateButton
        AutoplayCheckBox                matlab.ui.control.CheckBox
        Lamp                            matlab.ui.control.Lamp
        Image_2                         matlab.ui.control.Image
        OpenFolderImage                 matlab.ui.control.Image
        FolderDown                      matlab.ui.control.Image
        FolderUp                        matlab.ui.control.Image
        Stacker                         matlab.ui.control.DropDown
        ErrorButton                     matlab.ui.control.StateButton
        ChannelDropDown                 matlab.ui.control.DropDown
        CancelButton                    matlab.ui.control.Button
        FilesDropDown                   matlab.ui.control.DropDown
        View1st                         matlab.ui.control.NumericEditField
        View1stCheckBox                 matlab.ui.control.CheckBox
        TabGroup                        matlab.ui.container.TabGroup
        HomeTab                         matlab.ui.container.Tab
        OpenFolderImage_2               matlab.ui.control.Image
        CurrentDirButton                matlab.ui.control.Button
        OpenFolder                      matlab.ui.control.EditField
        OutputFolderButton              matlab.ui.control.Button
        OutputFolder                    matlab.ui.control.EditField
        VideoAlignSettings_2            matlab.ui.control.Image
        Hyperlink                       matlab.ui.control.Hyperlink
        PreferencesButton               matlab.ui.control.Button
        LoaddemodataButton              matlab.ui.control.Button
        LevelTab                        matlab.ui.container.Tab
        Apply                           matlab.ui.control.Button
        UndoFilter                      matlab.ui.control.Button
        FilteringPanel                  matlab.ui.container.Panel
        ImgFilterLabel_3                matlab.ui.control.EditField
        ImgFilterStrength_3             matlab.ui.control.Spinner
        ImgFilterType                   matlab.ui.control.DropDown
        SubtractmodeCheckBox            matlab.ui.control.CheckBox
        ImgFilterLabel_2                matlab.ui.control.EditField
        ImgFilterLabel                  matlab.ui.control.EditField
        ImgFilterStrength_2             matlab.ui.control.Spinner
        FilterLabel                     matlab.ui.control.Label
        ImgFilterStrength               matlab.ui.control.Spinner
        MaskingPanel                    matlab.ui.container.Panel
        ChannelDropDown_2               matlab.ui.control.DropDown
        ImgFilterLabel_5                matlab.ui.control.EditField
        ImgFilterLabel_4                matlab.ui.control.EditField
        ImgFilterStrength_5             matlab.ui.control.Spinner
        ImgFilterStrength_4             matlab.ui.control.Spinner
        ViewMask                        matlab.ui.control.StateButton
        AnalyzeMaskButton               matlab.ui.control.Button
        InvertMask                      matlab.ui.control.StateButton
        ThresholdMethod                 matlab.ui.control.DropDown
        FixShiftValue                   matlab.ui.control.NumericEditField
        FixShiftMethod                  matlab.ui.control.DropDown
        FixLineShift                    matlab.ui.control.CheckBox
        RestoreButton                   matlab.ui.control.Button
        ZeroallButton                   matlab.ui.control.Button
        LevelingPanel                   matlab.ui.container.Panel
        MedLabel                        matlab.ui.control.Label
        MedianCheckBox_y                matlab.ui.control.CheckBox
        MultiplanefitLabel              matlab.ui.control.Label
        MultiPlane                      matlab.ui.control.CheckBox
        sMedLabel                       matlab.ui.control.Label
        sMedianCheckBox                 matlab.ui.control.CheckBox
        AutoflattenDropDown             matlab.ui.control.DropDown
        YSpinnerLabel_4                 matlab.ui.control.Label
        MeanLabel_2                     matlab.ui.control.Label
        MeanPlaneCheckBox               matlab.ui.control.CheckBox
        MedianCheckBox                  matlab.ui.control.CheckBox
        YPlaneSpinner                   matlab.ui.control.Spinner
        YSpinnerLabel                   matlab.ui.control.Label
        XPlaneSpinner                   matlab.ui.control.Spinner
        XSpinnerLabel                   matlab.ui.control.Label
        YLineSpinner                    matlab.ui.control.Spinner
        XLineSpinner                    matlab.ui.control.Spinner
        LineLabel                       matlab.ui.control.Label
        PlaneLabel                      matlab.ui.control.Label
        DetectTab                       matlab.ui.container.Tab
        MaxstepEditFieldLabel_2         matlab.ui.control.Label
        Min_CCR_Edit                    matlab.ui.control.NumericEditField
        Max_CCR_Edit                    matlab.ui.control.NumericEditField
        MaxLabel_2                      matlab.ui.control.Label
        MinLabel_2                      matlab.ui.control.Label
        MaxstepEditFieldLabel_3         matlab.ui.control.Label
        BinRangeImage_2                 matlab.ui.control.Image
        FastFindCheckBox                matlab.ui.control.CheckBox
        LinelengthLabel                 matlab.ui.control.Label
        MaxradiusEditField              matlab.ui.control.NumericEditField
        FWHMreferenceDropDown           matlab.ui.control.DropDown
        FWHMreferenceDropDownLabel      matlab.ui.control.Label
        OverlaylinesCheckBox            matlab.ui.control.CheckBox
        LineDiag2                       matlab.ui.control.StateButton
        LineDiag1                       matlab.ui.control.StateButton
        LineVert                        matlab.ui.control.StateButton
        LineHor                         matlab.ui.control.StateButton
        AutoProfileButton               matlab.ui.control.StateButton
        Sq_roi_Button_2                 matlab.ui.control.StateButton
        MinseparationLabel              matlab.ui.control.Label
        MinheightLabel                  matlab.ui.control.Label
        Peaks_minSep                    matlab.ui.control.Spinner
        Peaks_minHeight                 matlab.ui.control.Spinner
        PreviewpeaksLabel               matlab.ui.control.Label
        PreviewPeaks                    matlab.ui.control.CheckBox
        ExcludeedgesLabel               matlab.ui.control.Label
        ExcludeEdgeCheckBox             matlab.ui.control.CheckBox
        FilterxcorimageLabel            matlab.ui.control.Label
        BoxSizeEditField                matlab.ui.control.NumericEditField
        BoxSizeEditFieldLabel           matlab.ui.control.Label
        PeaksRegionsSwitch              matlab.ui.control.Switch
        SelectMethodLabel_2             matlab.ui.control.Label
        FindParticlesButton             matlab.ui.control.Button
        FilterImgEditField_Detect       matlab.ui.control.NumericEditField
        FilterImageLabel                matlab.ui.control.Label
        TrackDetectionsPanel            matlab.ui.container.Panel
        MaxstepEditField                matlab.ui.control.NumericEditField
        MaxstepEditFieldLabel           matlab.ui.control.Label
        MaxmissingEditField             matlab.ui.control.NumericEditField
        MaxmissingEditFieldLabel        matlab.ui.control.Label
        RunTrackingButton               matlab.ui.control.Button
        Overlay                         matlab.ui.container.Panel
        SettingsLabel_Detect            matlab.ui.control.Label
        OverlayValues                   matlab.ui.control.DropDown
        TrackLengthEditField            matlab.ui.control.NumericEditField
        TrackLengthEditFieldLabel       matlab.ui.control.Label
        BoxesCheckBox                   matlab.ui.control.CheckBox
        MarkersCheckBox                 matlab.ui.control.CheckBox
        FilterCCREditField_Detect       matlab.ui.control.NumericEditField
        FineAlignTab                    matlab.ui.container.Tab
        RecalculatecorrelationButton    matlab.ui.control.Button
        SelectTrackButton               matlab.ui.control.StateButton
        TrackList                       matlab.ui.control.ListBox
        ApplyButton                     matlab.ui.control.Button
        RoiareaEditField                matlab.ui.control.NumericEditField
        RoiareaEditFieldLabel           matlab.ui.control.Label
        Panel_3                         matlab.ui.container.Panel
        Undo_FineAlignButton            matlab.ui.control.Button
        Rota_Method                     matlab.ui.control.DropDown
        Translat_Method                 matlab.ui.control.DropDown
        ThresholdwithminheightCheckBox  matlab.ui.control.CheckBox
        RunFineAlignButton              matlab.ui.control.Button
        AutoupdatereferenceimageCheckBox  matlab.ui.control.CheckBox
        MaxdriftEditField_2             matlab.ui.control.NumericEditField
        MaxdriftEditField_2Label        matlab.ui.control.Label
        Tran_IterationsEditField        matlab.ui.control.NumericEditField
        IterationsEditField_3Label      matlab.ui.control.Label
        TranslationalLabel              matlab.ui.control.Label
        Rot_IterationsEditField         matlab.ui.control.NumericEditField
        IterationsEditField_2Label      matlab.ui.control.Label
        RotationalLabel                 matlab.ui.control.Label
        searchangleEditField            matlab.ui.control.NumericEditField
        searchangleEditFieldLabel       matlab.ui.control.Label
        LocalizeTab                     matlab.ui.container.Tab
        BinRangeImage_3                 matlab.ui.control.Image
        DeleteoutliersEditField         matlab.ui.control.NumericEditField
        DeleteoutliersEditFieldLabel    matlab.ui.control.Label
        AutoButton_3                    matlab.ui.control.Button
        FRCresolutionButton             matlab.ui.control.Button
        SlidewindowEditField            matlab.ui.control.NumericEditField
        SlidewindowEditFieldLabel       matlab.ui.control.Label
        ParticlespermapEditField        matlab.ui.control.NumericEditField
        ParticlespermapEditFieldLabel   matlab.ui.control.Label
        LocalizationMethodDropDown      matlab.ui.control.DropDown
        LocalizationMethodLabel         matlab.ui.control.Label
        PointCloudAlignButton           matlab.ui.control.Button
        RenderLAFMMovieButton           matlab.ui.control.Button
        LAFM_exp                        matlab.ui.control.NumericEditField
        imageexpansionLabel             matlab.ui.control.Label
        LAFM_gus                        matlab.ui.control.NumericEditField
        RenderpointsLabel               matlab.ui.control.Label
        RenderLAFMButton                matlab.ui.control.Button
        FindallpeaksButton              matlab.ui.control.Button
        Localize_FilterPanel            matlab.ui.container.Panel
        BoostsubpixCheckBox             matlab.ui.control.CheckBox
        AutoButton_2                    matlab.ui.control.Button
        LoczImagepeaksCheckBox          matlab.ui.control.CheckBox
        LoczProminenceSpinner           matlab.ui.control.Spinner
        ProminenceSpinnerLabel          matlab.ui.control.Label
        LoczHeightSpinner               matlab.ui.control.Spinner
        ThresholdLabel_2                matlab.ui.control.Label
        ThresholdLabel_3                matlab.ui.control.Label
        LoczMinSep                      matlab.ui.control.Spinner
        ThresholdLabel                  matlab.ui.control.Label
        LoczShowfilterCheckBox          matlab.ui.control.CheckBox
        LocFilterLabel                  matlab.ui.control.Label
        LoczHighpass                    matlab.ui.control.Spinner
        LoczLowpass                     matlab.ui.control.Spinner
        LoczLowpassDropDown             matlab.ui.control.DropDown
        LowpassDropDownLabel            matlab.ui.control.Label
        LoczHighpassDropDown            matlab.ui.control.DropDown
        HighpassDropDownLabel           matlab.ui.control.Label
        UITable                         matlab.ui.control.Table
        FilesListBox                    matlab.ui.control.ListBox
        AutoSaveButton                  matlab.ui.control.StateButton
        Hist_Axes                       matlab.ui.control.UIAxes
        loadbar                         matlab.ui.control.UIAxes
    end


    %  DESCRIPTION:   NanoLocz: Localization Atomic Force Microscopy Analysis Platform
    %  COPYRIGHT:     George Heath, 2025
    %  LICENSE:       GPLv3
    %  AUTHOR:        George Heath, University of Leeds, g.r.heath@leeds.ac.uk
    %  Contributors:  Tabitha Storer, Emily Micklethwaite

    %  If using the Localization Atomic Force Microscopy method please cite:
    %  Heath, G.R., Kots, E., Robertson, J.L. et al. Localization atomic force microscopy.
    %  Nature 594, 385–390 (2021). https://doi.org/10.1038/s41586-021-03551-x

    % v1.4 using NanoLocz Library in MATALB 2025b
    % New features:
%     - Convert particles to frames
%     - Mask with other channels
%     - Core functions are now managed externally (as .m files) for better stability and maintainability

    properties (Access = public)
        AppName = "NanoLocz v1.41";
        ImageName
        ImageSource
        ImageUndo
        ImageTarget
        ImagePreview
        ImageExtraChannel
        Part      %Particle locations: 1=x, 2=y, 3=z, 4=ccr, 5=frame, 6 = id; 7=particle track, 8 = particle angle,
        ImageInfo
        filter_full
        filter_already
        ref
        img_locs
        LAFM %Localizations: 1=x, 2=y, 3=z, 4 = peak prom or , 5 = particle, 6 = frame; 7=time, 8 = ccr, 9 = pt cloud rmse,  10 = err 11 = id
        patches
        SquareROI
        ROI
        stop
        Settings
        Mask
        stored
        RadialDist
        PopupApp
        RefNo
        FRC
        ActiveSlider
        LogBuffer string = string.empty;
    end

    properties (Access = private)
        LogFig        % Handle to external log figure
        LogListBox    % ListBox inside external log figure
        ImageLogFig
        ImageLogListBox
    end


    methods (Access = public)

        function reset_defaults(app)
            app.img_locs = []; app.ref = []; app.ImageInfo=[]; app.Part=[];  app.LAFM = []; app.ROI.Line =[]; app.ROI.AreaAverage =[]; app.ROI.Point =[];
            app.SquareROI = []; app.Mask = []; app.RefListBox.Items = {}; app.ROI.AreaAverage =[]; app.ImageExtraChannel =[];
            app.Mask.auto_thick = 5; app.Mask.auto_thresh = 1.5;
            app.TrackList.Visible =0;
            app.RightPlotList.Items = {'Off'};
            app.RightPlotList.Value = 'Off';
            app.HistogramDropDown.Items = {'Mask z (all)','Mask z (frame)'};
            app.HistogramDropDown.Value ='Mask z (all)';
            PauseImageClicked(app)
            app.filter_already =0;
            app.FramePartSwitch.Value = 'Frames';
            app.OverlayDropDown.Value = 'None';
            cla(app.UIAxes);   cla(app.UIAxes_2); cla(app.loadbar);
            app.LoczImagepeaksCheckBox.Value =0;
            app.Max_CCR_Edit.Value =1;
            app.Min_CCR_Edit.Value =0.5;
            app.PreviewPeaks.Value =0;
            app.TrackLengthEditField.Value = 0;
            app.OverlayValues.Value = 'None';
            app.SelectTrackButton.Value=0;
            app.LAFM_ZScaleDropBox.Value = 'Exc outliers';
            app.Mask.low_thresh = -Inf;
            app.Mask.high_thresh = Inf;

            ViewMaskChanged(app)
            % TurnOffButtons(app,'all')
            writeLog(app, "******** File Changed ********");
        end

  
        function createLogWindow(app)
            if isempty(app.LogFig) || ~isvalid(app.LogFig)
                screenSize = get(0,'ScreenSize');
                width = 800; height = 120; x = 100; y = screenSize(4)-height-30;

                app.LogFig = figure(...
                    'Name', 'NanoLocz Log', ...
                    'NumberTitle', 'off', ...
                    'MenuBar', 'none', ...
                    'ToolBar', 'none', ...
                    'Color', [0 0 0], ...       % background black
                    'Position', [x y width height], ...
                    'Resize', 'on', ...
                    'HandleVisibility','callback', ...
                    'Visible','on', ...
                    'SizeChangedFcn', @(src,ev) resizeLogListBox(app));  % dynamic resize

                % ListBox inside the figure
                padding = 10;
                app.LogListBox = uilistbox(app.LogFig, ... 
                    'Position', [padding padding width-2*padding height-2*padding], ... 
                    'BackgroundColor', app.LogFig.Color, ... 
                    'FontColor', [1 1 1], ... 
                    'FontName', 'Consolas', ... 
                    'FontSize', 12, ... 
                    'Items', string.empty, ... 
                    'Enable', 'on');

                if ~isempty(app.LogBuffer)
                    app.LogListBox.Items = app.LogBuffer;
                    drawnow;
                    scroll(app.LogListBox, 'bottom');
                end
            end
        end

        function resizeLogListBox(app)
            if isempty(app.LogListBox) || ~isvalid(app.LogListBox)
                return
            end

            % Keep some padding from figure edges
            padding = 10;
            figPos = app.LogFig.Position;
            app.LogListBox.Position = [padding padding figPos(3)-2*padding figPos(4)-2*padding];
        end

        function writeLog(app, msg, scope)

    if nargin < 3 || isempty(scope)
        scope = "global";
    end
    scope = string(scope);

    % Timestamped message
    timestamp = string(datetime('now','Format','HH:mm:ss'));
    newLine = "[" + timestamp + "] " + msg;

    % --- 1. Global session log (vertical) ---
    app.LogBuffer(end+1,1) = newLine;

    % --- 2. Image-specific log (vertical) ---
    if scope == "image" && isstruct(app.ImageInfo)
        if ~isfield(app.ImageInfo,'Log') || isempty(app.ImageInfo.Log)
            app.ImageInfo.Log = strings(0,1);
        end
        app.ImageInfo.Log(end+1,1) = newLine;
    end

    % --- 3. UI ListBox (must be ROW) ---
    if ~isempty(app.LogListBox) && isvalid(app.LogListBox)
        items = string(app.LogListBox.Items);
        items = items(:).';        % FORCE 1×N row
        items(end+1) = newLine;

        app.LogListBox.Items = items;

        drawnow;
        scroll(app.LogListBox,'bottom');
    end
end


        function updateFolder(app)
            %Show file from the chosen directory in the listbox
            if app.Settings.Folder.Open ~= 0
                imageFiles = dir([app.Settings.Folder.Open]);
                imageFiles = imageFiles(~ismember({imageFiles(:).name},{'.','..','.DS_Store'}));
                app.FilesListBox.Items = {imageFiles.name};
                % fileList = imageFiles;
                %
                % numFiles = length(imageFiles);
                % tableData = cell(numFiles, 3);
                %
                % for i = 1:numFiles
                %     tableData{i,1} = fileList(i).name;
                %     tableData{i,2} = round(fileList(i).bytes / 1024, 2);  % Size in KB
                %     tableData{i,3} = datestr(fileList(i).datenum, 'yyyy-mm-dd HH:MM:SS');
                % end
                % app.FileTable.Data = tableData;

            end

        end

        function updateVideoTools(app)

            if app.View1stCheckBox.Value ==1 && app.View1st.Value < app.ImageInfo.n
                loadn = app.View1st.Value;
            else
                loadn = app.ImageInfo.n;
            end

            ty = app.FramePartSwitch.Value;
            switch ty
                case 'Frames'
                    if loadn <2
                        app.TimescaleCheckBox.Visible = 0;
                        app.TimescaleCheckBox.Value = 0;
                        app.VideoPanel.Visible = 0;
                    else
                        app.Spinner.Limits = [1 loadn];
                        app.FrameSlider.Limits = [1 loadn];
                        if app.Spinner.Limits(2)>=10
                            inter = (app.Spinner.Limits(2)/10);
                        else
                            inter = 1;
                        end
                        app.FrameSlider.MajorTicks = round(1:inter:loadn);
                        app.FrameSlider.MajorTickLabels = string(round(1:inter:loadn));
                        app.VideoPanel.Visible = 1;
                        app.TimescaleCheckBox.Visible = 1;
                    end

                case 'Particles'
                    app.TimescaleCheckBox.Visible = 0;
                    app.TimescaleCheckBox.Value = 0;
                    if    numel(app.Part.Image(1,1,app.Part.IncludeImages))<2
                        app.VideoPanel.Visible = 0;

                        else
                            app.Spinner.Limits = [1 numel(app.Part.Image(1,1,app.Part.IncludeImages ))];
                            app.FrameSlider.Limits = app.Spinner.Limits;
                            if app.Spinner.Limits(2)>=10
                                inter = (app.Spinner.Limits(2)/10);
                            else
                                inter = 1;
                            end
                            app.FrameSlider.MajorTicks = round(1:inter:app.Spinner.Limits(2));
                            app.FrameSlider.MajorTickLabels = string(round(1:inter:app.Spinner.Limits(2)));
                            app.VideoPanel.Visible = 1;
                        end
                end
            
            
        end


        function filter_Partlocs(app)

            app.Part.IncludeImages = ones(numel(app.Part.Locs(:,3)),1);
            try
                app.Part.IncludeImages = app.Part.IncludeImages.*(app.Part.Locs(:,4)>=app.Part.ccr_low_thresh);
                app.Part.IncludeImages = app.Part.IncludeImages.*(app.Part.Locs(:,4)<=app.Part.ccr_high_thresh);
                app.Part.IncludeImages = app.Part.IncludeImages.*(app.Part.Locs(:,3)>= app.Part.z_low_thresh);
                app.Part.IncludeImages = app.Part.IncludeImages.*(app.Part.Locs(:,3)<= app.Part.z_high_thresh);
            catch
                app.Part.ccr_low_thresh = 0.5;
                app.Part.ccr_high_thresh = 1;
                app.Part.z_low_thresh = -Inf;
                app.Part.z_high_thresh = Inf;
            end
            try
                if app.SelectTrackButton.Value==1
                    Track = str2double(app.TrackList.Value);
                    app.Part.IncludeImages = app.Part.IncludeImages.*(app.Part.Locs(:,7)==Track);
                end
            catch
            end
            app.Part.IncludeImages = app.Part.IncludeImages >0;
            app.Part.Locs(:,6) = NaN;
            app.Part.Locs(app.Part.IncludeImages,6) =  1:numel(app.Part.Locs(app.Part.IncludeImages,3));

        end


        function filter_LAFMlocs(app)

            app.LAFM.IncludeLocs = ones(numel(app.LAFM.Locs(:,3)),1);
            try
                app.LAFM.IncludeLocs = app.LAFM.IncludeLocs.*(app.LAFM.Locs(:,3)>= app.LAFM.z_low_thresh);
                app.LAFM.IncludeLocs = app.LAFM.IncludeLocs.*(app.LAFM.Locs(:,3)<= app.LAFM.z_high_thresh);
            catch
                app.LAFM.z_low_thresh = -Inf;
                app.LAFM.z_high_thresh = Inf;
            end

            try
                app.LAFM.IncludeLocs = app.LAFM.IncludeLocs.*(app.LAFM.Locs(:,8)>=app.LAFM.ccr_low_thresh);
                app.LAFM.IncludeLocs = app.LAFM.IncludeLocs.*(app.LAFM.Locs(:,8)<=app.LAFM.ccr_high_thresh);
            catch
                app.LAFM.ccr_low_thresh = -0.5;
                app.LAFM.ccr_high_thresh = 1;

            end

            try
                if app.LAFM.pp_low_thresh < app.LAFM.pp_high_thresh
                    app.LAFM.IncludeLocs = app.LAFM.IncludeLocs .* ...
                        (app.LAFM.Locs(:,4) >= app.LAFM.pp_low_thresh)  .* ...
                        (app.LAFM.Locs(:,4) <= app.LAFM.pp_high_thresh);
                end
            catch
                app.LAFM.pp_low_thresh = -Inf;
                app.LAFM.pp_high_thresh = Inf;
            end

            try
                if size(app.LAFM.Locs, 2) >= 10 && ~isempty(app.LAFM.Locs(:,10))
                    if app.LAFM.err_low_thresh < app.LAFM.err_high_thresh
                        app.LAFM.IncludeLocs = app.LAFM.IncludeLocs .* ...
                            (app.LAFM.Locs(:,10) >= app.LAFM.err_low_thresh) .* ...
                            (app.LAFM.Locs(:,10) <= app.LAFM.err_high_thresh);
                    end
                end
            catch
                app.LAFM.err_low_thresh = -Inf;
                app.LAFM.err_high_thresh = Inf;
            end
            
            app.LAFM.IncludeLocs = app.LAFM.IncludeLocs >0;
           % app.LAFM.Locs(:,11) = NaN;
            %app.LAFM.Locs(app.LAFM.Locs,11) =  1:numel(app.LAFM.Locs(app.LAFM.IncludeLocs,3));

        end


        %Update Plot 1
       function updatePlot(app,varargin)
            % ... (PHASE 1: INITIAL DATA SETUP & CONTROL VISIBILITY - UNCHANGED) ...
            if isfield(app.ImageInfo,'Channel')
                
                % Determine source image (img) and update UI visibility flags
                switch app.FramePartSwitch.Value
                    case 'Frames'
                        if strcmp(app.LeftPlotList.Value, 'Target')
                            img = app.ImageTarget;
                            app.AcceptButtonImage.Visible = 0;
                            app.PreviewonoffButton.Value = 0;
                        else % 'Preview'
                            img = app.ImagePreview;
                            app.AcceptButtonImage.Visible = 1;
                            app.PreviewonoffButton.Value = 1;
                        end
                    case 'Particles'
                        includeFrames = app.Part.IncludeImages;
                        if strcmp(app.LeftPlotList.Value, 'Target')
                            img = app.Part.Image(:,:,includeFrames);
                            app.AcceptButtonImage.Visible = 0;
                            app.PreviewonoffButton.Value = 0;
                        else % 'Preview'
                            img = app.ImagePreview(:,:,includeFrames);
                            app.AcceptButtonImage.Visible = 1;
                            app.PreviewonoffButton.Value = 1;
                        end
                end
                
                previewOnOff(app)

                % --- PHASE 2: EXPORT SETUP ---
                
                ftmp = [];
                ax_tmp = [];
                isExport = numel(varargin) > 0 && (strcmp(varargin{1}, "export video") || strcmp(varargin{1}, "export frame"));

                if strcmp(varargin{1}, "export video")
                    % Setup persistent hidden figure for video export
                    bgColor = [1 1 1]; 
                    if isfield(app.Settings.ExportSettings,'color')
                        switch lower(app.Settings.ExportSettings.color)
                            case 'black', bgColor = [0 0 0];
                        end
                    end
                    ftmp = figure('Visible','off','Color',bgColor,'Tag','ExportFigure');
                    if size(img,1) >250
                        set(ftmp,'Units','pixels','Position',[100 100 size(img,2) size(img,1)]);
                    else
                        set(ftmp,'Units','pixels','Position',[100 100 256 256]);
                    end
                    
                    ax_tmp = axes(ftmp,'Units','normalized','Position',[0 0 1 1]); 
                    axis(ax_tmp,'tight','image');
                    ax_tmp.YDir = 'reverse'; 
                end

                % Determine the target axes (ax) and frame range (frame)
                switch varargin{1}
                    case 'popout'
                        frame = round(app.Spinner.Value);
                        figure();
                        ax = gca;
                    case 'export frame'
                        frame = round(app.Spinner.Value);
                        figure();
                        ax = gca;
                    case 'export video'
                        frame = 1:numel(img(1,1,:));
                        figure(1); % Placeholder figure for drawing, content moved to ftmp
                        ax = gca;
                    case 'normal'
                        ax = app.UIAxes;
                        frame = round(app.Spinner.Value);
                        cla(ax);
                end
                
                % --- PHASE 3: MAIN DRAWING LOOP ---
                
                fullFileName = '';
                
                for iii = frame
                    if numel(frame) < 2
                        iii=1;
                    end
                    currentFrameIdx = frame(iii);
                    
                    if currentFrameIdx <= app.Spinner.Limits(2)
                        prev = img(:,:,currentFrameIdx);
                    else
                        prev = img(:,:,1);
                    end

                    if numel(app.ImageInfo.ScanSize)>1
                        updateTable(app)
                    end
                    
                    % --- Image Filtering (Kept complex filter logic inline for now) ---
                    if app.LoczShowfilterCheckBox.Value==1
                        if app.BoostsubpixCheckBox.Value
                            prev = imresize(prev,3,'bicubic');
                            ex=3;
                            if ismember(app.LoczHighpassDropDown.Value, {'Deconv', '-Gaussian', 'Peak sharp', 'Laplacian'})
                                ex2=3;
                            else
                                ex2 = 1;
                            end
                        else
                            ex = 1;
                            ex2 = 1;
                        end
                        if strcmp(app.LoczHighpassDropDown.Value,'-Average')
                            temp = filter_movie(img, app.LoczLowpassDropDown.Value, app.LoczLowpass.Value*ex, app.LoczHighpassDropDown.Value,  app.LoczHighpass.Value*ex2);
                            prev  = temp(:,:,currentFrameIdx);
                        else
                            prev = filter_movie(prev, app.LoczLowpassDropDown.Value, app.LoczLowpass.Value*ex, app.LoczHighpassDropDown.Value,  app.LoczHighpass.Value*ex2);
                            if app.BoostsubpixCheckBox.Value
                                prev = imresize(prev,1/3,'bicubic');
                            end
                        end
                    end
                    
                    % --- Contrast Limits ---
                    switch app.ZScaleDropBox.Value
                        case 'Max Min'
                            minC = round(min(prev(:)),3,"significant");
                            maxC = round(max(prev(:)),3,"significant");
                            if minC>=maxC, minC=0; maxC=1; end
                        case 'Exc outliers'
                            B = rmoutliers(prev(:),"mean");
                            minC = round(min(B(:)),3,"significant");
                            maxC = round(max(B(:)),3,"significant");
                            if minC>=maxC, minC=0; maxC=1; end
                        case 'Histogram'
                            minC = app.Mask.low_thresh;
                            maxC = app.Mask.high_thresh;
                        case 'Manual'
                            minC = app.ContrastMinSpinner.Value;
                            maxC = app.ContrastMaxSpinner.Value;
                    end
                    app.ContrastMinSpinner.Value = double(minC);
                    app.ContrastMaxSpinner.Value = double(maxC);
                    
                    if minC < maxC
                        zcont = [minC maxC];
                    else
                        zcont =[0 1];
                    end

                    % --- Plotting Type ---
                    if strcmp(app.PlotTypeDropDown.Value,'2D surface')
                        hold(ax,'on')
                        im = imagesc(ax,single(prev),zcont);
                        view(ax,2)
                        im.Interpolation = iff(strcmp(app.ViewpixelsDropDown.Value,'Original'), 'nearest', 'bilinear');
                    elseif strcmp(app.PlotTypeDropDown.Value,'3D surface')
                        if ~ismember(varargin{1}, {'popout','export frame','export video'})
                            [app.Settings.viewangle(1), app.Settings.viewangle(2)]= view(ax);
                        end
                        set(ax,'visible','on')
                        h = surf(ax,prev);
                        zlim(ax,zcont)
                        shading(ax,'interp')
                        axis(ax,'normal');
                        clim(ax, zcont);
                        
                        if ismember(app.Settings.lightmethod, {'flat','gouraud'})
                            delete(findall(ax, 'Type', 'light'));
                            h.FaceLighting = app.Settings.lightmethod;
                            lightangle(ax,app.Settings.lightAng(1),app.Settings.lightAng(2))
                            h.BackFaceLighting = 'reverselit';               
                            h.SpecularExponent = 50;                         
                            h.SpecularStrength = 1.0;                        
                            h.AmbientStrength = 0.5;                         
                            h.DiffuseStrength = 0.9;
                            light(ax,'Style', 'infinite');
                        end
                        try
                            view(ax,app.Settings.viewangle(1), app.Settings.viewangle(2));
                        catch
                            view(ax, 45, 45)
                        end
                    elseif strcmp(app.PlotTypeDropDown.Value,'Coordinates')
                        set(ax,'visible','on')
                        % --- PERFORMANCE IMPROVEMENT: Use array indexing instead of loop ---
                        [y, x] = find(prev); % find returns column indices first (y, x)
                        idx = sub2ind(size(prev), y, x); 
                        z = prev(idx);
                        scatter3(ax,x,y,z,10,z,'filled')
                        axis(ax,'normal');
                    end
       
                    % Annotations (Scalebar, Timescale)
                    drawLabels(ax, img, currentFrameIdx, app.ImageInfo, app.ScalebarCheckBox.Value, app.TimescaleCheckBox.Value, app.Settings)
          
                    % --- Peak Preview (Kept inline) ---
                    if app.PreviewPeaks.Value ==1
                        hold(ax,'on')
                        if app.FilterImgEditField_Detect.Value >0
                            prev = imgaussfilt(prev,app.FilterImgEditField_Detect.Value);
                        end
                        locs = Fast_peaks2D(prev, app.Peaks_minHeight.Value, app.Peaks_minSep.Value);
                        if any(locs(:,1))
                            plot(ax,locs(:,1),locs(:,2), 'cx','MarkerSize',7,'LineWidth',1)
                        end
                        hold(ax,'off')
                    end

                    % --- Overlays ---
                    switch app.OverlayDropDown.Value
                        case 'None'
                            hold(ax,'off')
                            app.ViewMask.Value = 0;
                            
                        case 'Detections'
                            app.ViewMask.Value = 0;
                            try
                                hold(ax,'on')
                                pos = app.Part.Locs(:,5) == currentFrameIdx;
                                pos = (pos.*app.Part.IncludeImages)>0;
                                
                                % Markers
                                if app.MarkersCheckBox.Value == 1
                                    plot(ax,app.Part.Locs(pos,1),app.Part.Locs(pos,2),...
                                        'go','MarkerSize',8,'LineWidth',2,...
                                        'MarkerFaceColor','r')
                                end
                                
                                % Boxes
                                if app.BoxesCheckBox.Value == 1
                                    x = app.Part.Locs(pos,1);
                                    y = app.Part.Locs(pos,2);
                                    frame_width = app.ref.position(3);
                                    frame_height = app.ref.position(4);
                                    angle_rad = app.Part.Locs(pos,8) * pi / 180;
                                    vertices = [frame_width/2, frame_height/2; -frame_width/2, frame_height/2; -frame_width/2, -frame_height/2; frame_width/2, -frame_height/2]; 
                                    for i = 1:numel(x)
                                        cent = [x(i), y(i)];
                                        R = [cos(angle_rad(i)), -sin(angle_rad(i)); sin(angle_rad(i)), cos(angle_rad(i))];
                                        vertices_rot = (R * vertices')';
                                        vertices_trans = vertices_rot + cent;
                                        patch(ax,'vertices',vertices_trans,'faces',[1,2,3,4],'EdgeColor','g','FaceColor','w','FaceAlpha',0.15 )
                                    end
                                end
                                
                                % Overlay Values
                                switch app.OverlayValues.Value
                                    case 'Height', p = round(app.Part.Locs(pos,3),1);
                                    case 'CCR', p = round(app.Part.Locs(pos,4),2);
                                    case 'Angle', p = round(app.Part.Locs(pos,8),1);
                                    case 'Track Number', p = app.Part.Locs(pos,7);
                                    case 'FWHM', try, p = round(app.Part.Locs(pos,11),1); catch, p = []; end
                                    otherwise, p = [];
                                end
                                
                                if ~strcmp(app.OverlayValues.Value, 'None')
                                    x = app.Part.Locs(pos,1);
                                    y = app.Part.Locs(pos,2);
                                    for i = 1:numel(x)
                                        text(ax,x(i)+4,y(i),num2str(p(i)),'Color','r','FontSize',12)
                                    end
                                end

                                % Tracks
                                if app.TrackLengthEditField.Value>0 && numel(app.Part.Locs(1,:))>6
                                    x = app.Part.Locs(pos,1);
                                    p = app.Part.Locs(pos,7);
                                    trackLimit = max(1, currentFrameIdx - app.TrackLengthEditField.Value);
                                    for j = 1:numel(x)
                                        pos2 = app.Part.Locs(:,7)==p(j);
                                        xtemp = app.Part.Locs(pos2,1);
                                        ytemp = app.Part.Locs(pos2,2);
                                        pos3 = app.Part.Locs(pos2,5)<=currentFrameIdx & app.Part.Locs(pos2,5)>=trackLimit;
                                        plot(ax,xtemp(pos3),ytemp(pos3),'o-')
                                    end
                                end
                                hold(ax,'off')
                            catch
                            end
                            
                        case 'Line Profile'
                            app.ViewMask.Value = 0;
                            ViewMaskChanged(app)
                            C = [hsv(8); jet(8); parula(8); parula(16)];
                            hold(ax,'on')
                            n = min(numel(app.ROI.Line), 500);
                            for jj=1:n
                                xpos = app.ROI.Line(jj).Position(:,1);
                                ypos = app.ROI.Line(jj).Position(:,2);
                                pos3 = (xpos>0) & (xpos<size(prev,2)) & (ypos>0) & (ypos<size(prev,1));
                                try
                                    app.ROI.Line(jj).plt = plot(ax,xpos(pos3),ypos(pos3),'LineWidth',1,'Color',C(jj,:));
                                    plot(ax,[xpos(1),xpos(end)],[ypos(1),ypos(end)],'o','LineWidth',1,'Color',C(jj,:));
                                catch
                                    app.ROI.Line(jj).plt =  plot(ax,xpos(pos3),ypos(pos3),'LineWidth',1);
                                    plot(ax,[xpos(1),xpos(end)],[ypos(1),ypos(end)],'o','LineWidth',1);
                                end
                            end
                            hold(ax,'off')
                            
                        case 'Mask'
                            app.ViewMask.Value = 1;
                            if isfield(app.Mask,'Mask')
                                try
                                    imgt = app.Mask.Mask(:,:,currentFrameIdx);
                                catch
                                    imgt = app.Mask.Mask;
                                end
                                s = isfield(app.Mask,'ROI') && any(app.Mask.ROI(:));

                                if (strcmp(app.DrawTool.Value,'- Mask') || strcmp(app.DrawTool.Value,'+ Mask')) && s
                                    imgt = thresholder(app.Mask.ROI.*imgt,'selection',[ app.Mask.low_thresh, app.Mask.high_thresh],app.InvertMask.Value);
                                end
                                hold(ax,'on')
                                
                                % Set overlay color
                                switch app.Settings.MaskColor
                                    case 'green', overlay_c = cat(3, zeros(size(prev)), ones(size(prev)), zeros(size(prev)));
                                    case 'red', overlay_c = cat(3, ones(size(prev)), zeros(size(prev)), zeros(size(prev)));
                                    case 'blue', overlay_c = cat(3, zeros(size(prev)), zeros(size(prev)), ones(size(prev)));
                                    case 'black', overlay_c = cat(3, zeros(size(prev)), zeros(size(prev)), zeros(size(prev)));
                                    case 'white', overlay_c = cat(3, ones(size(prev)), ones(size(prev)), ones(size(prev)));
                                    otherwise, overlay_c = cat(3, zeros(size(prev)), ones(size(prev)), zeros(size(prev))); % Default to green
                                end
                                
                                over = imagesc(ax, overlay_c,zcont);
                                hold(ax,'off')
                                set(over, 'AlphaData', app.Settings.MaskAlpha*isnan(imgt))
                            end
                            
                        case 'Localizations'
                            app.ViewMask.Value = 0;
                            ViewMaskChanged(app)
                            hold(ax,'on')
                            if app.LoczImagepeaksCheckBox.Value==1
                          
                                if currentFrameIdx <= app.Spinner.Limits(2)
                                    prev_peaks = img(:,:,currentFrameIdx);
                                else
                                    prev_peaks = img(:,:,1);
                                end
                                
                                ex = iff(app.BoostsubpixCheckBox.Value, 3, 1);
                                ex2 = iff(ismember(app.LoczHighpassDropDown.Value, {'Deconv', '-Gaussian', 'Option4'}), 3, 1);
                                
                                if strcmp(app.LoczHighpassDropDown.Value,'-Average')
                                    temp = filter_movie(img, app.LoczLowpassDropDown.Value, app.LoczLowpass.Value*ex, app.LoczHighpassDropDown.Value,  app.LoczHighpass.Value*ex2);
                                    prev_peaks  = temp(:,:,currentFrameIdx);
                                else
                                    prev_peaks = filter_movie(prev_peaks, app.LoczLowpassDropDown.Value, app.LoczLowpass.Value*ex, app.LoczHighpassDropDown.Value,  app.LoczHighpass.Value*ex2);
                                end
                                
                                locs = Fast_peaks2D(prev_peaks,app.LoczHeightSpinner.Value,app.LoczMinSep.Value,app.LoczProminenceSpinner.Value);
                                
                                if any(locs(:,1))
                                    plot(ax,locs(:,1),locs(:,2), 'cx','MarkerSize',7,'LineWidth',1)
                                end
                                hold(ax,'off')
                            end
                            
                    end

                    % --- Colormap and Preview Label ---
                    c = app_color_map(app.ColourmapDropDown.Value, app);
                    colormap(ax,c);
                    
                    if strcmp(app.LeftPlotList.Value, 'Preview')
                        [r, cs, ~] = size(img(:,:,1));
                        xpos(1) = round(cs*.05);
                        ypos(1) = round(r*.95);
                        text(ax,xpos(1),ypos(1),'PREVIEW','FontWeight','bold','FontSize', 24,'Color',[0.5 0.5 0.5]);
                    end
                    
                    set(ax,'visible','off','YDir','reverse')
                    
                    % --- PHASE 4: EXPORT EXECUTION ---
                    
                    if isExport
                        
                        % --- Initialize Export Handlers & Filename ---
                        if strcmp(varargin{1}, "export video")
                            current_ftmp = ftmp; 
                            current_ax_tmp = ax_tmp;
                            if iii == 1
                                namesplit = split(app.ImageName,'.');
                                namesuggest = join(namesplit,"_");
                                filetype = varargin{2};
                                [outputFileName,folder] = uiputfile([namesuggest{1},filetype]);
                                fullFileName = fullfile(folder, outputFileName);
                            end
                        else % 'export frame'
                            bgColor = [1 1 1]; 
                            if isfield(app.Settings.ExportSettings,'color')
                                switch lower(app.Settings.ExportSettings.color)
                                    case 'black', bgColor = [0 0 0];
                                end
                            end
                            current_ftmp = figure('Visible','off','Color',bgColor,'Tag','ExportFigure');
                            if size(prev,1) >250
                                set(current_ftmp,'Units','pixels','Position',[100 100 size(prev,2) size(prev,1)]);
                            else
                                set(current_ftmp,'Units','pixels','Position',[100 100 256 256]);
                            end
                            current_ax_tmp = axes(current_ftmp,'Units','normalized','Position',[0 0 1 1]);
                            axis(current_ax_tmp,'tight','image');
                            current_ax_tmp.YDir = 'reverse'; 
                            
                            if iii == 1
                                namesplit = split(app.ImageName,'.');
                                namesuggest = join(namesplit,"_");
                                filetype = varargin{2};
                                [outputFileName,folder] = uiputfile([namesuggest{1},filetype]);
                                fullFileName = fullfile(folder, outputFileName);
                            end
                        end
                        
                        % --- Handle user cancellation ---
                        if isempty(fullFileName)
                            if strcmp(varargin{1}, "export frame")
                                close(current_ftmp);
                            end
                            break;
                        end

                        % --- Copy the content (image + labels/annotations) ---
                        copyobj(allchild(ax), current_ax_tmp);
                        
                        % --- FIX 3: SCALE FONT SIZE ---
                       
                        FONT_SCALE_FACTOR = 0.6; 
                        
                        text_objects = findobj(current_ax_tmp, 'Type', 'text');
                        
                        % FONT SCALING
                        for k = 1:length(text_objects)
                            originalSize = get(text_objects(k), 'FontSize');
                            set(text_objects(k), 'FontSize', originalSize * FONT_SCALE_FACTOR);
                        end

                        % --- FIX 1: REMOVED COORDINATE FLIPPING. Image YDir='reverse' is sufficient. ---
                        
                        % --- Adjust destination axes properties ---
                        colormap(current_ax_tmp, colormap(ax)); 
                        
                        % *** FIX 1 (Contrast): Explicitly set color limits for all exports ***
                        % This handles the static export CLim issue.
                        current_ax_tmp.CLim = ax.CLim;
                        
                        % --- FIX 2: Handle Colorbar (Copy/Reparent) ---
                        if app.ColorBarCheckBox.Value == 1
                            cb_src = findall(ax.Parent,'Type','ColorBar');
                            
                            if ~isempty(cb_src)
                                cb_tmp = copyobj(cb_src, current_ftmp); 
                                cb_tmp.Peer = current_ax_tmp;
                                
                                if strcmp(app.Settings.ExportSettings.color,'white')
                                    cb_tmp.Color = 'k';
                                else
                                    cb_tmp.Color = 'w';
                                end
                                cb_tmp.Box = 'off';
                                cb_tmp.TickDirection = 'out';
                            end
                        
                        end
                        
                        drawnow; 
                        
                        % --- Capture and Export ---

                        switch varargin{2}
                            case {'.avi', '.gif'}
                                % Use getframe path for video/gif
                                fr = getframe(current_ftmp);
                                imRGB = fr.cdata;
                                
                                if strcmp(varargin{2}, '.avi')
                                    if iii == 1
                                        v = VideoWriter(fullFileName,'Motion JPEG AVI');
                                        v.FrameRate = app.fps.Value;
                                        open(v);
                                    end
                                    writeVideo(v,imRGB);
                                else % .gif
                                    [imind,cm] = rgb2ind(imRGB,256);
                                    if iii == 1
                                        imwrite(imind,cm,fullFileName,'gif','DelayTime',1/app.fps.Value,'Loopcount',inf);
                                    else
                                        imwrite(imind,cm,fullFileName,'gif','WriteMode','append','DelayTime',1/app.fps.Value);
                                    end
                                end
                                
                            case {'.png','.jpeg','.jpg','.pdf','.eps'}
                                % Use exportgraphics for high quality static exports
                                exportgraphics(current_ftmp,fullFileName,'BackgroundColor','current', 'Resolution',app.Settings.ExportSettings.resolution);
                        end
                        
                        if strcmp(varargin{1}, "export frame")
                            close(current_ftmp); 
                        else
                            cla(current_ax_tmp); 
                        end
                        cla(ax); 
                        
                    end % end isExport check
                end % end of frame loop
                
                % --- PHASE 5: POST-LOOP CLEANUP ---
                if strcmp(varargin{1}, "export video")
                    if strcmp(varargin{2}, ".avi")
                        close(v);
                    end
                    try
                        close(ftmp);
                    catch
                    end
                end
            end
            
            % Nested helper function
            function output = iff(condition, trueVal, falseVal)
                if condition
                    output = trueVal;
                else
                    output = falseVal;
                end
            end
        end

        %end of updatePlot 1 -------------------------------------

        function updateRightPlot(app)
            switch app.RightPlotList.Value
                case 'Off'
                    updatePlot2_type(app,'image')
                case 'Line Profile'
                    updatePlot2_type(app,'plot')
                case 'Ref Image'
                    updatePlot2_type(app,'image')
                case 'Localizations'
                    updatePlot2_type(app,'image')
                case {'LAFM','LAFM sym'}
                    updatePlot2_type(app,'image')
            end

            updatePlot2(app,'normal')
        end

        function updatePlot2_type(app,type)
            switch type
                case 'image'
                    set(app.UIAxes_2,'visible','off')
                    axis(app.UIAxes_2,'tight','equal');
                    set(app.UIAxes_2,'Color','k')
                    app.UIAxes_2.XTick = [];
                    app.UIAxes_2.XTickLabelRotation = 0;
                    app.UIAxes_2.XTickLabel = '';
                    app.UIAxes_2.YTick = [];
                    app.UIAxes_2.YTickLabelRotation = 0;
                    app.UIAxes_2.YTickLabel = '';
                    grid(app.UIAxes_2,'off')

                    %app.UIAxes_2.Position =  [app.UIAxes_2.Position(1),  app.UIAxes.Position(2), app.UIAxes.Position(3),app.UIAxes.Position(3)];


                case 'plot'
                    axis(app.UIAxes_2, 'normal');
                    set(app.UIAxes_2,'YDir','normal');
                    set(app.UIAxes_2,'Color','k')
                    colorbar(app.UIAxes_2,'off')
                    set(app.UIAxes_2,'visible','on')
                    set(app.UIAxes_2,'fontname','Ariel','fontsize', 14,'TickDir','out')
                    if app.Lightmode.Value == 0
                        app.UIAxes_2.YColor = 'k';
                        app.UIAxes_2.XColor = 'k';
                        app.UIAxes_2.GridColor = 'w';
                    else
                        app.UIAxes_2.YColor = 'w';
                        app.UIAxes_2.XColor = 'w';
                        app.UIAxes_2.GridColor = 'k';
                    end
                    app.UIAxes_2.XTickMode = 'auto';
                    app.UIAxes_2.XTickLabelMode = 'auto';
                    app.UIAxes_2.YTickMode = 'auto';
                    app.UIAxes_2.YTickLabelMode = 'auto';
                    grid(app.UIAxes_2,'on')
                    grid(app.UIAxes_2,'minor')
                    app.UIAxes_2.MinorGridLineStyle = '-';
                    app.UIAxes_2.MinorGridAlpha = 0.05;
                    app.UIAxes_2.GridAlpha = 0.2;
                    

            end

        end

        % Update Plot 2 -------------------------------------

        function updatePlot2(app,varargin)
            try
            switch app.RightPlotList.Value
                case 'Line Profile'
                    switch  app.FramePartSwitch.Value
                        case 'Frames'
                            switch  app.LeftPlotList.Value
                                case 'Target'
                                    img = app.ImageTarget;
                                case 'Preview'
                                    img = app.ImagePreview;
                            end
                        case 'Particles'
                            img = app.Part.Image(:,:,app.Part.IncludeImages);
                    end
                case 'LAFM sym Movie'
                    img =  app.LAFM.Movie_sym;
                case 'LAFM Movie'
                    img =  app.LAFM.Movie;
                case 'Off'
                    return
            end

            frame =  round(app.LAFMSpinner.Value);
            switch varargin{1}
                case {'popout','export frame'}
                    switch app.RightPlotList.Value
                        case {'LAFM sym Movie','LAFM Movie'}
                            frame =  round(app.LAFMSpinner.Value);
                        otherwise
                            frame = round(app.Spinner.Value);
                    end
                    figure()
                    ax = gca;

                case 'export video'
                    switch app.RightPlotList.Value
                        case {'LAFM sym Movie','LAFM Movie'}
                            frame = 1:numel(img);
                        otherwise
                            frame = 1:numel(img(1,1,:));
                    end

                    figure(1)
                    ax = gca;

                case {'normal','live'}
                    switch app.RightPlotList.Value
                        case {'LAFM sym Movie','LAFM Movie'}
                            frame =  round(app.LAFMSpinner.Value);
                        otherwise
                            frame = round(app.Spinner.Value);
                    end
                    ax = app.UIAxes_2;
                    cla(ax);
            end

            for iii = frame
                if numel(frame) < 2
                    iii=1;
                end

                switch app.RightPlotList.Value
                    case 'Off'
                        colorbar(ax,'off')
                    case 'Line Profile'
                        app.TabGroup2.SelectedTab = app.LineTab;
                        app.LineProfilePanel.Visible = 1;
                        C = hsv(8);
                        C = [C ;   jet(8)];
                        C = [C ;   parula(8)];
                        currentmin = Inf;
                        currentmax = -Inf;

                        for jj=1:numel(app.ROI.Line)
                            if  (strcmp(varargin{1},'live') || app.Live.Value==1) && numel(img)>1
                                app = draw_calc(app,app.ROI.Line(jj).Position,0,jj);
                            end
                            try
                                plot(ax,app.ROI.Line(jj).Profile(:,1),app.ROI.Line(jj).Profile(:,2),'-','LineWidth',1.5,'Color',C(jj,:))
                            catch   
                                plot(ax,app.ROI.Line(jj).Profile(:,1),app.ROI.Line(jj).Profile(:,2),'-','LineWidth',1.5)
                            end
                            hold(ax,'on')
                            currentmin = min([min(app.ROI.Line(jj).Profile(:,2)); currentmin]);
                            currentmax = max([max(app.ROI.Line(jj).Profile(:,2)); currentmax]);
                        end
                        hold(ax,'off')
                        increase = 0.05 * range([currentmin, currentmax]);
                        newLimits = [currentmin - increase, currentmax + increase];

                        ylim(ax, newLimits);

                        xlabel(ax,'Distance (nm)')
                        ylabel(ax,'Height (nm)')


                    case 'Ref Image'

                        app.LineProfilePanel.Visible = 0;
                        app.TabGroup2.SelectedTab = app.ReferenceTab;
                        view(ax,2)

                        if any(cell2mat(app.RefListBox.Value))
                            switch cell2mat(app.RefListBox.Value)
                                case 'ROI Reference'

                                    if ~isempty(app.ref.ROIimage)
                                        app.ref.image = app.ref.ROIimage;
                                        climit = [app.ContrastMinSpinner.Value, app.ContrastMaxSpinner.Value];
                                    end
                                case 'Simulated Reference'
                                    if ~isempty(app.ref.simImage) || isvalid(app.ref.simImage)
                                        app.ref.image = app.ref.simImage;
                                        climit = [app.ContrastMinSpinner.Value, app.ContrastMaxSpinner.Value];
                                    end
                                case 'LAFM Reference'
                                    if ~isempty(app.ref.LAFM) || isvalid(app.ref.LAFM)
                                        app.ref.image = app.ref.LAFM;
                                    end
                                    climit = [min(app.ref.image(:)),max(app.ref.image(:))];
                                case 'Averaged Reference'
                                    app.ref.image = app.ref.av;
                                    climit = [app.ContrastMinSpinner.Value, app.ContrastMaxSpinner.Value];
                            end


                            try
                                imagesc(ax,single(app.ref.image),climit)
                            catch
                                imagesc(ax,single(app.ref.image),[0,1])
                            end
                            % end
                        end

                        c = app_color_map(app.ColourmapDropDown.Value, app);
                        colormap(ax,c);

                        set(ax,'visible','off')
                        axis(ax,'tight','equal');

                    case 'Localizations'

                        hold(ax,'on')
                        s = any(app.LAFM.Locs);
                        if s(1)==0
                        else
                            Locs = app.LAFM.Locs(app.LAFM.IncludeLocs,:);
                            %  h=  (app.LAFM.Locs(:,3)-min(app.LAFM.Locs(:,3)))/(max(app.LAFM.Locs(:,3))-min(app.LAFM.Locs(:,3)));
                            scatter3(ax,Locs(:,1),Locs(:,2),Locs(:,3),3,Locs(:,3),'filled','MarkerFaceAlpha',0.5)
                            set(ax, 'YDir', 'reverse');
                            % ylim(ax,[1 size(img,1)])
                            % xlim(ax,[1 size(img,2)])
                        end
                        hold(ax,'off')

                    case {'LAFM','LAFM sym','LAFM Movie','LAFM sym Movie'}
                        view(ax,2)
                        clear t scaledImage
                        app.TabGroup2.SelectedTab = app.LAFMTab;
                        app.LineProfilePanel.Visible = 0;
                        switch app.RightPlotList.Value
                            case 'LAFM'
                                t = app.LAFM.Image;
                            case 'LAFM sym'
                                t = app.LAFM.Image_sym;
                            case 'LAFM Movie'
                                t = app.LAFM.Movie{frame(iii)};

                            case 'LAFM sym Movie'
                                t = app.LAFM.Movie_sym{frame(iii)};
                        end

                        color = app_color_map(app.LAFM_LUT.Value, app);

                        colormap(ax,color)
                        switch app.LAFMView.Value
                            case {'LAFM map','Height'}
                                scaledImage = (t-app.LAFMMinSlider.Value)/(app.LAFMMaxSlider.Value - app.LAFMMinSlider.Value);
                                imagesc(ax, scaledImage)
                                switch app.LAFMView.Value
                                    case {'LAFM map','Height'}
                                        try
                                            clim(ax,[app.LAFM.lims(1), app.LAFM.lims(2)]);
                                        catch
                                            caxis(ax,[app.LAFM.lims(1), app.LAFM.lims(2)]);
                                        end
                                end

                            case 'Probability'
                                if app.LAFMMinSlider.Value > app.LAFMMaxSlider.Value
                                    app.LAFMMinSlider.Value = 0;
                                    app.LAFMMaxSlider.Value = 1;
                                end
                                climit = [app.LAFMMinSlider.Value, app.LAFMMaxSlider.Value];
                                imagesc(ax, t,climit)

                        end

                        set(ax,'visible','off')
                        axis(ax,'tight','equal');

                    case 'x & y Align'

                        updatePlot2_type(app,'plot')
                        if any(app.img_locs)
                            plot(ax,app.img_locs(:,1),'x-')
                            hold(ax,'on')
                            plot(ax,app.img_locs(:,2),'v-')
                            hold(ax,'off')
                        end

                    case 'Time Profile'
                        C = hsv(8);
                        C = [C ;   jet(8)];
                        C = [C ;   parula(8)];
                        for jj=1:numel(app.ROI.AreaAverage(1,:))
                            plot(ax, app.ROI.AreaAverage(:,jj),'-','LineWidth',1.5,'Color',C(jj,:))
                            hold(ax, 'on')
                        end
                        hold(ax, 'off')
                        xlabel(ax,'Frame')
                        ylabel(ax,'Height (nm)')

                    case 'Kymograph'
                        app.LineProfilePanel.Visible = 0;

                        imagesc(ax, app.ROI.Line(1).kymo, [app.ContrastMinSpinner.Value app.ContrastMaxSpinner.Value])
                        c = app_color_map(app.ColourmapDropDown.Value, app);
                        colormap(ax,c);

                        axis(ax,'normal');
                end

                if app.LAFMScalebarCheckBox.Value ==1
                    switch app.RightPlotList.Value
                        case {'LAFM sym', 'LAFM','LAFM Movie','LAFM sym Movie'}

                            switch app.LAFMView.Value
                                case {'LAFM map','Height'}
                                    tp = rgb2gray(t);
                                otherwise
                                    tp = t;
                            end
                            if numel(app.ImageInfo.PixelPerNm)>1
                                fr = round(app.FrameSlider.Value);
                                switch  app.FramePartSwitch.Value
                                    case 'Particles'
                                        fr = app.Part.Locs((app.Part.Locs(:,6)==fr),5);
                                end
                            else
                                fr=1;
                            end

                            [r, cs] = size(tp);
                            sb = cs./app.ImageInfo.PixelPerNm(fr)/app.LAFM_exp.Value;
                            try
                                scale_bar = round(sb/5,1,"significant");
                                scale_bar_pix = scale_bar.*app.ImageInfo.PixelPerNm(fr)*app.LAFM_exp.Value;

                                switch app.Settings.label.LAFMScaleBar.position
                                    case 'Top Right'
                                        xpos = [round(cs.*0.95)-scale_bar_pix, round(cs.*0.95)];
                                        ypos = round([r*.05, r*.05]);
                                    case 'Top Left'
                                        xpos = [round(cs.*0.05), round(cs.*0.05)+scale_bar_pix];
                                        ypos = round([r*.05, r*.05]);
                                    case 'Bottom Left'
                                        xpos = [round(cs.*0.05), round(cs.*0.05)+scale_bar_pix];
                                        ypos = round([r*.95, r*.95]);
                                    case 'Bottom Right'
                                        xpos = [round(cs.*0.95)-scale_bar_pix, round(cs.*0.95)];
                                        ypos = round([r*.95, r*.95]);
                                end

                                line(ax,xpos,ypos,'LineWidth',2,'Color',app.Settings.label.LAFMScaleBar.color);
                                if scale_bar >1000

                                    text(ax, xpos(1)+scale_bar_pix/2, round(ypos(1) - r*.03), [num2str(scale_bar/1000) char(181),'m'],...
                                        'FontWeight','bold','FontSize', 15,'Color', app.Settings.label.LAFMScaleBar.color,'HorizontalAlignment','center');
                                else
                                    text(ax, xpos(1)+scale_bar_pix/2, round(ypos(1) - r*.03), [num2str(scale_bar) 'nm'],...
                                        'FontWeight','bold','FontSize', 15,'Color', app.Settings.label.LAFMScaleBar.color,'HorizontalAlignment','center');
                                end
                            catch
                            end
                    end
                end

                if app.LAFMTimescaleCheckBox.Value ==1
                    switch app.RightPlotList.Value
                        case {'LAFM Movie','LAFM sym Movie'}
                            switch app.LAFMView.Value
                                case {'LAFM map','Height'}
                                    tp = rgb2gray(t);
                                otherwise
                                    tp = t;
                            end
                            [r, cs, ~] = size(tp);
                            %  try
                            time = round(app.LAFM.time(frame(iii)),4,"significant");

                            switch app.Settings.label.TimeStamp.position
                                case 'Top Right'
                                    xpos = round(cs*.90);
                                    ypos = round(r*.05);
                                case 'Top Left'
                                    xpos = round(cs*.05);
                                    ypos = round(r*.05);
                                case 'Bottom Left'
                                    xpos = round(cs*.05);
                                    ypos = round(r*.95);
                                case 'Bottom Right'
                                    xpos = round(cs*.90);
                                    ypos = round(r*.95);
                            end
                            text(ax,xpos(1),ypos(1),[num2str(time) 's'],'FontWeight','bold','FontSize', 17,'Color',app.Settings.label.TimeStamp.color);
                            % catch
                            % end
                    end
                end


                switch varargin{1}   % {'export video','export frame'}

                    case {'export video','export frame'}
                        hold(ax,'on');

                        % --------- FIRST FRAME: Ask for filename ----------
                        if iii == 1
                            namesplit = split(app.ImageName,'.');
                            namesuggest = join(namesplit,"_");
                            filetype = app.ExportFileFormat.Value;

                            [outputFileName,folder] = uiputfile([namesuggest{1}, filetype]);
                            fullFileName = fullfile(folder, outputFileName);
                        end

                        % --------- Determine background + tick colours ----------
                        bgColor = [1 1 1];  % default background white
                        tickColor = [0 0 0];

                        if isfield(app.Settings.ExportSettings,'color')
                            switch lower(app.Settings.ExportSettings.color)
                                case 'black'
                                    bgColor = [0 0 0];
                                    tickColor = [1 1 1];
                                case 'white'
                                    bgColor = [1 1 1];
                                    tickColor = [0 0 0];
                            end
                        end

                        % --------- CREATE CLEAN HIDDEN FIGURE ----------
                        ftmp = figure( ...
                            'Visible','off', ...
                            'Color',bgColor, ...
                            'Units','pixels', ...
                            'Position',[100 100 900 650] ...
                            );

                        % --------- COPY AXES + ASSOCIATED COLORBAR ----------
                        objsToCopy = [ax; findall(ax.Parent,'Type','ColorBar')];
                        ax_tmp = copyobj(objsToCopy, ftmp);
                        ax_tmp = ax_tmp(1);   % ensure axes handle is first element

                        % --------- FORCE FULL-FIGURE LAYOUT ----------
                        set(ax_tmp,'Units','normalized','Position',[0.10 0.10 0.85 0.85]);

                        % --------- Fix plot styling ----------
                        set(ax_tmp,'Color',bgColor);        % axes background
                        ax_tmp.XColor = tickColor;
                        ax_tmp.YColor = tickColor;
                        ax_tmp.ZColor = tickColor;

                        set(ax_tmp,'TickDir','out','LineWidth',1.2)
                        axis(ax_tmp,'tight');
       

                        % --------- 3D view preservation ----------
                        try
                            view(ax_tmp, view(ax));
                        end

                        cb_tmp = findall(ftmp,'Type','ColorBar');
                        if ~isempty(cb_tmp)
                            cb_tmp.Color = tickColor;
                            cb_tmp.TickDirection = 'out';
                            cb_tmp.Box = 'off';
                        end
                        set(ax_tmp, 'FontSize', 14);  % or any size you prefer
                        set(findall(ax_tmp, '-property', 'FontSize'), 'FontSize', 14);  % also updates labels
                        drawnow;
                        fr = getframe(ftmp);
                        imRGB = fr.cdata;

                        % --------- EXPORT ----------
                        switch varargin{2}

                            case '.avi'
                                if iii == 1
                                    v = VideoWriter(fullFileName,'Motion JPEG AVI');
                                    v.FrameRate = app.fps.Value;
                                    open(v);
                                end
                                writeVideo(v, imRGB);

                            case '.gif'
                                [imind, cm] = rgb2ind(imRGB, 256);
                                if iii == 1
                                    imwrite(imind, cm, fullFileName, 'gif', ...
                                        'DelayTime',1/app.fps.Value, 'Loopcount',inf);
                                else
                                    imwrite(imind, cm, fullFileName, 'gif', ...
                                        'WriteMode','append','DelayTime',1/app.fps.Value);
                                end

                            case {'.png','.jpeg','.jpg','.pdf','.eps'}
                                exportgraphics(ftmp, fullFileName, ...
                                    'BackgroundColor','current', ...
                                    'Resolution', app.Settings.ExportSettings.resolution);
                        end

        % --------- CLEANUP ----------
        close(ftmp);
end

            end % end of for loop for export movie
            if numel(varargin)>1
                switch varargin{2}
                    case '.avi'
                        close(v)
                end
            end
            catch 
            end
        end

        %
        function updateHist(app,varargin)

            switch varargin{1}
                case 'popout'
                    figure()
                    ax = gca;
                case 'normal'
                    ax = app.Hist_Axes;
                    cla(ax);
            end


            % Select data based on dropdown
            try
            switch app.HistogramDropDown.Value
                case 'Mask z (all)'
                    T = app.ImageTarget(:,:,:);
                    upper = app.Mask.high_thresh;
                    lower = app.Mask.low_thresh;
                case 'Mask z (frame)'
                    T = app.ImageTarget(:,:,app.Spinner.Value);
                    upper = app.Mask.high_thresh;
                    lower = app.Mask.low_thresh;
                case 'Particle z'
                    T = app.Part.Locs(:,3);
                    upper = app.Part.z_high_thresh;
                    lower = app.Part.z_low_thresh;
                case 'Particle Correlation'
                    T = app.Part.Locs(:,4);
                    upper = app.Part.ccr_high_thresh;
                    lower = app.Part.ccr_low_thresh;
                case 'LAFM z'
                    T = app.LAFM.Locs(:,3);
                    upper = app.LAFM.z_high_thresh;
                    lower = app.LAFM.z_low_thresh;

                case 'LAFM Particle Correlation'
                    T = app.LAFM.Locs(:,8);
                    lower = app.LAFM.ccr_low_thresh;
                    upper = app.LAFM.ccr_high_thresh;

                case 'LAFM peak prominance'
                    T = app.LAFM.Locs(:, 4);
                    lower = app.LAFM.pp_low_thresh;
                    upper = app.LAFM.pp_high_thresh;

                case 'LAFM Loc error'
                    T = app.LAFM.Locs(:, 10);
                    lower = app.LAFM.err_low_thresh;
                    upper = app.LAFM.err_high_thresh;

            end

            % Exclude outliers if option is enabled
            if app.Hist_excludeOut.Value == 1
                T = reshape(T,1,[]);
                 T = T(T >= lower & T <= upper);
            end

            % Plot histogram
            histogram(ax, T, 100, 'EdgeColor', 'None', 'FaceColor', [1.00,0.41,0.16]);

            % Apply log scale if needed
            if app.LogYHist.Value == 1
                set(ax, 'YScale', 'log')
            else
                set(ax, 'YScale', 'linear')
            end

            Hmin = double(min(T,[],'all'));
            Hmax = double(max(T,[],'all'));
            set(ax, 'xlim', [Hmin, Hmax])
            yLimits = ylim(app.Hist_Axes);

            colorbar(app.Hist_Axes,'off')
            switch varargin{1}
                case 'normal'
                    delete(app.patches.histup);
                    delete(app.patches.histdown);

                    if upper ~= Inf
                        app.patches.histup = patch(ax, ...
                            [Hmax, upper, upper, Hmax], ...
                            [yLimits(1), yLimits(1), yLimits(2), yLimits(2)], ...
                            [0.30,0.98,0.40], 'FaceAlpha', 0.5, 'EdgeColor', 'none');
                    end

                    % Lower threshold patch
                    if lower ~= -Inf
                        app.patches.histdown = patch(ax, ...
                            [lower, Hmin, Hmin, lower], ...
                            [yLimits(1), yLimits(1), yLimits(2), yLimits(2)], ...
                            [0.30,0.98,0.40], 'FaceAlpha', 0.5, 'EdgeColor', 'none');
                    end
            end
            catch
            end
        end

        function LoadstartupImg(app)
            imageName = 'LoadImg.tif';
            info = imfinfo(imageName);   app.ImageInfo.n = numel(info);
            if any(info(1).XResolution)
                app.ImageInfo.PixelPerNm =  info(1).XResolution;
            else
                app.ImageInfo.PixelPerNm =  1;
            end
            app.ImageInfo.xPixels = info(1).Width;
            app.ImageInfo.yPixels  = info(1).Height;

            if app.ImageInfo.PixelPerNm >0
                app.ImageInfo.ScanSize = app.ImageInfo.xPixels/app.ImageInfo.PixelPerNm;
            else
                app.ImageInfo.ScanSize = 0;
            end
            app.ImageInfo.LineSpeed = 0;
            app.ImageInfo.ScanSpeed = 0;
            im = zeros(app.ImageInfo.yPixels ,app.ImageInfo.xPixels, app.ImageInfo.n);
            for ii = 1:app.ImageInfo.n
                im(:,:,ii) = imread(imageName,ii);
            end
            app.ImageTarget = double(im);
            app.ImageInfo.Channel = 'Tiff';
            app.ImageInfo.Channels = {app.ImageInfo.Channel};
            app.ImageInfo.time = 0;
            updateTable(app)
            app.ImageName =  imageName;
            app.ImagePreview =  app.ImageTarget;
        end

        function im = Open_files(app)
            app.ChannelDropDown.Visible = 0;
            app.ChannelDropDown_2.Visible = 0;

            fileName = [app.Settings.Folder.Open '/' app.FilesListBox.Value];
            [~, ~, ext] = fileparts(fileName);
            writeLog(app, "Loading File... " + app.FilesListBox.Value +" Channel: "+ app.ChannelDropDown.Value);
            try
                if strcmpi(ext, '.mat') || strcmpi(ext, '.h5')
                    im = app_openmat(app, fileName, ext);
                    app_populate_gui(app)

                else
                    if app.View1stCheckBox.Value
                        [im, app.ImageInfo] = ReadAFMFile(fileName, app.ChannelDropDown.Value,app.View1st.Value, app);
                    else
                        [im, app.ImageInfo] = ReadAFMFile(fileName, app.ChannelDropDown.Value,'all', app);
                    end
                    app.ImageInfo.Log = strings(0,1);
                end
            catch
                writeLog(app, "Error - No files opened ");
                im = 0;
                finished(app)
                return
            end

            
            app.ImageInfo.Log(end+1,1) = app.AppName + "  MATLAB: " + version;

            writeLog(app, ...
                "Opened " + app.FilesListBox.Value + ...
                " Channel: " + app.ChannelDropDown.Value, ...
                'image');

            app.ImageName = app.FilesListBox.Value;

            try
                app.ChannelDropDown.Items = app.ImageInfo.Channels;
                app.ChannelDropDown_2.Items = app.ImageInfo.Channels;
                app.ChannelDropDown.Visible = 1;
                app.ChannelDropDown.Value = app.ImageInfo.Channel;
                app.ChannelDropDown_2.Value = app.ImageInfo.Channel;
                app.ChannelDropDown_2.Visible = 1;
            catch
            end
            if contains(lower(app.ImageInfo.Channels), 'bi-directional')
                app.FixShiftValue.Visible = 1;
                app.FixShiftMethod.Visible = 1;
                app.FixLineShift.Visible = 1;
            else
                app.FixShiftValue.Visible = 0;
                app.FixShiftMethod.Visible = 0;
                app.FixLineShift.Visible = 0;
            end

        end

        function run_filter(app)
            app.filter_full=0;
            app.ImagePreview = filter(app);
            updatePlot(app,'normal')
            updatePlot2(app,'normal')
        end

        function result = filter(app)
            if app.filter_already == 0
                frame = round(app.Spinner.Value);
                switch  app.FramePartSwitch.Value
                    case 'Frames'
                        img = app.ImageTarget;
                    case 'Particles'
                        img = app.Part.Image;
                end
                result = img(:,:,frame);
                if app.filter_full==1
                    loadn = numel(img(1,1,:));
                    filter_frames = 1:loadn;
                    app.filter_already =1;
                else
                    filter_frames = frame;
                end

                sw = app.AutoflattenDropDown.Value;
                switch sw
                    case 'Off'
                        for i = filter_frames
                            prev = img(:,:,i);
                            result(:,:,i) = prev;
                        end
                    otherwise
                        result = level_auto(img,filter_frames,sw);
                end
                polyx = app.XPlaneSpinner.Value;
                polyy = app.YPlaneSpinner.Value;
                xline = app.XLineSpinner.Value;
                yline = app.YLineSpinner.Value;


                for i = filter_frames
                    if strcmp(app.ChannelDropDown_2.Value, app.ChannelDropDown.Value) || isempty(app.ImageExtraChannel)
                        mask_img = img(:,:,i);
                    else
                        mask_img = app.ImageExtraChannel(:,:,i);
                    end
                    prev = result(:,:,i);

                    switch app.ThresholdMethod.Value
                        case {'auto edges','parachute'}
                            imgt = thresholder(mask_img,app.ThresholdMethod.Value,[ app.Mask.auto_thick, app.Mask.auto_thresh],app.InvertMask.Value);
                        otherwise
                            imgt = thresholder(mask_img,app.ThresholdMethod.Value,[ app.Mask.low_thresh, app.Mask.high_thresh],app.InvertMask.Value);
                    end


                    if isfield(app.Mask,'ROI')
                        s = any(app.Mask.ROI);
                    else
                        s(1)=0;
                    end
                    if (strcmp(app.DrawTool.Value,'- Mask') || strcmp(app.DrawTool.Value,'+ Mask')) && s(1)==1
                        imgt = thresholder(app.Mask.ROI.*imgt,'selection',[ app.Mask.low_thresh, app.Mask.high_thresh],app.InvertMask.Value);
                    end

                    try
                        app.Mask.Mask(:,:,i) = imgt;
                    catch
                    end

                    if  polyx > 0 || polyy > 0
                        if app.MultiPlane.Value
                            prev = level_weighted(prev,polyx,polyy,'plane',imgt);
                        else
                            prev = level(prev,polyx,polyy,'plane',imgt);
                        end
                    end

                    if  xline > 0  || yline > 0   %x y line noise removal
                        if app.MultiPlane.Value
                            prev = level_weighted(prev,xline,yline,'line', imgt);
                        else
                            prev = level(prev,xline,yline,'line', imgt);
                        end
                    end

                    if app.MedianCheckBox.Value==1
                        if app.MultiPlane.Value
                            prev = level_weighted(prev,0,0,'med_line',imgt);
                        else
                            prev = level(prev,0,0,'med_line',imgt);
                        end
                    end

                    if app.MedianCheckBox_y.Value==1
                        if app.MultiPlane.Value
                            prev = level_weighted(prev,0,0,'med_line_y',imgt);
                        else
                            prev = level(prev,0,0,'med_line_y',imgt);
                        end
                    end

                    if app.sMedianCheckBox.Value==1
                        if app.MultiPlane.Value
                            prev = level_weighted(prev,0,0,'smed_line',imgt);
                        else
                            prev = level(prev,0,0,'smed_line',imgt);
                        end
                    end

                    if app.MeanPlaneCheckBox.Value ==1
                        prev = level(prev,0,0,'mean_plane',imgt);
                    end

                    if  app.FixLineShift.Value > 0
                        switch app.FixShiftMethod.Value
                            case 'Line peaks'
                                [app.FixShiftValue.Value, prev] = LineShift(prev,'peaks');
                            case 'Line correlation'
                                [app.FixShiftValue.Value, prev] = LineShift(prev,'ccr');
                            case 'Manual Shift'
                                [app.FixShiftValue.Value, prev] = LineShift(prev,app.FixShiftValue.Value);
                        end
                    end

                    switch app.ImgFilterType.Value
                        case 'Gaussian'
                            if app.ImgFilterStrength.Value >0
                                prev = imgaussfilt(prev,app.ImgFilterStrength.Value);
                            end
                        case 'Median'
                            if  app.ImgFilterStrength.Value>2 || app.ImgFilterStrength_2.Value>2
                                prev = medfilt2(prev, [round(app.ImgFilterStrength_2.Value), round(app.ImgFilterStrength.Value)]);
                                %prev = colfilt(prev, [app.ImgFilterStrength_2.Value, app.ImgFilterStrength.Value;], 'sliding', @(x) median(x(:)));
                            end
                        case 'Mean'
                            if  app.ImgFilterStrength.Value>2 || app.ImgFilterStrength_2.Value>2
                                kernel_size_x = round(app.ImgFilterStrength.Value);
                                kernel_size_y = round(app.ImgFilterStrength_2.Value);
                                mean_kernel = ones(kernel_size_y, kernel_size_x) / (kernel_size_x * kernel_size_y);
                                prev = imfilter(prev, mean_kernel);
                            end
                        case 'Non-local mean'
                            if app.ImgFilterStrength.Value >0
                                prev =  imnlmfilt(prev,'DegreeOfSmoothing',app.ImgFilterStrength.Value);
                            end

                        case 'Sphere Deconvolution'
                            if  app.ImgFilterStrength.Value>0
                                P = app.ImgFilterStrength.Value+1;
                                offs = round(P)- P;
                                [hx,hy] = ndgrid(-(P):(P));
                                hx = hx-offs;
                                hy = hy-offs;
                                H = real(sqrt((-(hx.^2)-(hy.^2)+P^2)));
                                H=H./(sum(H(:)));
                                if offs>0
                                    H = H(2:end , 2:end);
                                end
                                prev = deconvlucy(prev,H,app.ImgFilterStrength_2.Value);
                            end
                        case 'Top Hat'
                            if app.ImgFilterStrength.Value >1
                                se = strel('disk',round(app.ImgFilterStrength.Value));
                                prev = imtophat(prev,se);
                            end
                        case 'High-pass'
                            if app.ImgFilterStrength.Value >0
                                [mx, ny] =size(prev);
                                ft = fft2(prev);
                                f_shift = fftshift(ft);
                                ps = mx/2;
                                qs = ny/2;
                                for ii=1:mx
                                    for jj=1:ny
                                        distance = sqrt((ii-ps)^2+(jj-qs)^2);
                                        low_filter(ii,jj) = 1-exp(-(distance)^2/(2*(app.ImgFilterStrength.Value.^2)));
                                    end
                                end
                                filter_apply = f_shift.*low_filter;
                                image_orignal = ifftshift(filter_apply);
                                prev = abs(ifft2(image_orignal));
                            end
                        case 'Fill Mask'
                            M = isnan(imgt);
                            prev = regionfill(prev,M);
                        case 'Scar Fill'
                            prev = scar_fill(prev,app.ImgFilterStrength.Value, app.ImgFilterStrength_2.Value, app.ImgFilterStrength_3.Value);

                        otherwise
                    end
                    if app.SubtractmodeCheckBox.Value
                        result(:,:,i) =  result(:,:,i) -prev;
                    else
                        result(:,:,i) = prev;
                    end
                end

            else
                result = app.ImagePreview;
            end
            result(isnan(result)) = 0;
        end

        function cleanMask(app,settings)
            M = isnan(app.Mask.Mask);

            if settings.mingrain>0
                M = bwareaopen(M, settings.mingrain);
            end

            if settings.Dilate>0
                se = strel('disk',settings.Dilate);
                M = imdilate(M, se);
            end

            if settings.Erode>0
                se = strel('disk',settings.Erode);
                M = imerode(M, se);

            end

            if settings.Close>0
                se = strel('disk',settings.Close);
                M = imclose(M, se);
            end

            if settings.Open>0
                se = strel('disk',settings.Open);
                M = imopen(M, se);
            end

            if settings.Fill
                imfill((M),"holes");
            end

            app.Mask.Mask = M.*1;
            app.Mask.Mask(app.Mask.Mask  == 1) = NaN;
            app.Mask.Mask(app.Mask.Mask  == 0) = 1;
            app.PreviewonoffButton.Value =0;
            previewOnOff(app)
            updatePlot(app,'normal')

        end


        function AnalyzeMask(app,settings)
            M = isnan(app.Mask.Mask);
            clear app.Mask.Analysis app.Mask.Frame_Analysis

            [app.Mask.Analysis,  app.Mask.Frame_Analysis] = AnalyzeAreas(M, app.ImageTarget, settings.Analyze, 1./app.ImageInfo.PixelPerNm, app.ImageInfo.time,app.ImageInfo.ScanSpeed);
            app.PopupApp.Plot = plotter();
            storedata(app)
            savedata(app, app.Mask.Analysis,'Area')
            savedata(app, app.Mask.Frame_Analysis,'Area Frame')
        end

        function ReshapeImage(app,settings)
            sz = size(app.ImageTarget);
            if settings.pixelsy ~= sz(1) || settings.pixelsx ~= sz(2)
                app.ImageTarget = imresize(app.ImageTarget, [settings.pixelsy, settings.pixelsx], settings.method);
                app.ImageInfo.PixelPerNm = app.ImageInfo.PixelPerNm.*settings.pixelsx/sz(2);
            end
            if settings.rotate >0
                app.ImageTarget = imrotate(app.ImageTarget,settings.rotate, settings.method,'crop');
                app.Mask.Mask = [];
            end

            if settings.Scaleh ~=1
                app.ImageTarget = settings.Scaleh.*app.ImageTarget;
                app.Mask.Mask = [];
            end
            app.ViewMask.Value = 0;
            ViewMaskChanged(app)
            updatePlot(app,'normal')
            updatePlot2(app,'normal')
            
            updateHist(app,'normal')
            storedata(app)
            savedata(app,app.ImageTarget,'Image')

        end


        function updateTable(app)
            try
            fr = round(app.FrameSlider.Value);
            switch app.FramePartSwitch.Value
                case 'Particles'
                    fr = app.Part.Locs((app.Part.Locs(:,6) == fr), 5);
            end

                if numel(app.ImageInfo.PixelPerNm)>1
                    app.UITable.Data(7,2) = num2cell(app.ImageInfo.PixelPerNm(fr));
                else
                    app.UITable.Data(7,2) = num2cell(app.ImageInfo.PixelPerNm);
                end

                if numel(app.ImageInfo.ScanSize)>1
                    app.UITable.Data(2,2) = num2cell(app.ImageInfo.ScanSize(fr));
                else
                    app.UITable.Data(2,2) = num2cell(app.ImageInfo.ScanSize);
                end

                app.UITable.Data(1,2) = num2cell(app.ImageInfo.n);

                app.UITable.Data(3,2) = num2cell(app.ImageInfo.ScanSpeed);
                app.UITable.Data(4,2) = num2cell(app.ImageInfo.LineSpeed);
                app.UITable.Data(5,2) = num2cell(round(app.ImageInfo.yPixels));
                app.UITable.Data(6,2) = num2cell(round(app.ImageInfo.xPixels));
                app.UITable.Data(8,2) = cellstr(app.ImageInfo.Channel);
            catch
            end
        end


        function updateResultsTable(app,type)
            switch type
                case 'Line Profile'
                    Parameter =  {'Length','PeakHeight','FWHM','Max z','Min z','diff z','RMS'};
                    Value = [num2cell([0, 0, 0, 0, 0 ,0,0])];
                    app.UITable2.Data = [Parameter; Value];
                    app.UITable2.ColumnWidth = 'auto';

                    for jj=1:numel(app.ROI.Line)
                        perimeter = app.ROI.Line(jj).Position;
                        app = draw_calc(app,perimeter,0,jj);
                        x = single(app.ROI.Line(jj).Profile(:,1));
                        z = single(app.ROI.Line(jj).Profile(:,2));
                        app.UITable2.Data(jj+1,1) = num2cell(round(app.ROI.Line(jj).Profile(end,1),2));
                        if numel(z)>4 && app.FWHMCheckBox.Value ==1
                            [~, ~, wds, p] = findpeaks(z,x,'MinPeakProminence',0.1);
                            if any(p)
                                [p,ID] = max(p);
                                w = wds(ID);
                                app.UITable2.Data(jj+1,2) = num2cell(round(p,2));
                                app.UITable2.Data(jj+1,3) = num2cell(round(w,2));
                            else
                                app.UITable2.Data(jj+1,2) = num2cell(0);
                                app.UITable2.Data(jj+1,3) = num2cell(0);
                            end
                        end
                        if numel(z)>4
                            app.UITable2.Data(jj+1,4) = num2cell(round(max(z),2));
                            app.UITable2.Data(jj+1,5) = num2cell(round(min(z),2));
                            app.UITable2.Data(jj+1,6) = num2cell(round(max(z)-min(z),2));
                            app.UITable2.Data(jj+1,7) = num2cell(round(std(z),5));
                        end
                    end
            end
        end


        function TurnOffDrawButtons(app,type)
            buttons = {'app.Point_roi_Button', 'app.Line_roi_Button', 'app.Sq_roi_Button', 'app.Sq_roi_Button_2','app.Circ_roi_Button', 'app.Freehand_roi_Button','app.ZoomIn'};
            numButtons = numel(buttons);
            for i = 1:numButtons
                if strcmp(type,buttons{i})
                    f = eval(buttons{i});
                    if f.Value==1
                        f.BackgroundColor = [0.15 0.15 0.15];
                    else
                        f.BackgroundColor = [1 1 1];
                    end
                else
                    f = eval(buttons{i});
                    f.Value=0;
                    f.BackgroundColor = [1 1 1];
                end
            end

            if app.ZoomIn.Value ==1
                app.Settings.Zoom = zoom(app.UIAxes);
                app.Settings.Zoom.Enable = 'on';
              app.UIAxes.Toolbar.Visible = 'on';
            else
                app.Settings.Zoom = zoom(app.UIAxes);
                app.Settings.Zoom.Enable = 'off';
                app.UIAxes.Toolbar.Visible = 'off';
                pan(app.UIAxes,'on')
                pan(app.UIAxes,'off')
            end
        end

        function TurnOffToolButtons(app)
            % Map DrawTool values to button handles
            drawToolMap = containers.Map( ...
                {'Profile', 'Average', 'ROI', '+ Mask', '- Mask'}, ...
                {app.Profile_tool_Button, app.Average_tool_Button, ...
                app.ROI_Button, app.AddMask_tool_Button, app.RemoveMask_tool_Button} ...
                );

            % Get the selected tool
            selectedTool = app.DrawTool.Value;

            % List all tool buttons to turn off by default
            allButtons = {app.Profile_tool_Button, app.Average_tool_Button, ...
                app.ROI_Button, app.AddMask_tool_Button, ...
                app.RemoveMask_tool_Button};

            % Loop through all buttons
            for i = 1:numel(allButtons)
                btn = allButtons{i};

                % Check if this is the selected tool's button
                if isKey(drawToolMap, selectedTool) && btn == drawToolMap(selectedTool)
                    btn.Value = 1;
                    btn.BackgroundColor = [0.5 0.5 0.5];
                else
                    btn.Value = 0;
                    btn.BackgroundColor = [1 1 1];
                end
            end

            % Special handling for ZoomIn
            if app.ZoomIn.Value == 1
                app.Settings.Zoom = zoom(app.UIAxes);
                app.Settings.Zoom.Enable = 'on';
              app.UIAxes.Toolbar.Visible = 'on';
            else
                app.Settings.Zoom = zoom(app.UIAxes);
                app.Settings.Zoom.Enable = 'off';
                app.UIAxes.Toolbar.Visible = 'off';
                pan(app.UIAxes,'on')
                pan(app.UIAxes,'off')
            end
        end


        function HideToolButtons(app)
            % Mapping between button handles and DrawTool items
            buttonMapping = {
                app.Profile_tool_Button, 'Profile';
                app.Average_tool_Button, 'Average';
                app.ROI_Button, 'ROI';
                app.AddMask_tool_Button, '+ Mask';
                app.RemoveMask_tool_Button, '- Mask';
                };
            for i = 1:size(buttonMapping, 1)
                button = buttonMapping{i, 1}; % Button handle
                itemName = buttonMapping{i, 2}; % Corresponding item name

                % Check if the item name is in the DrawTool items
                if ismember(itemName, app.DrawTool.Items)
                    % Item exists, make the button visible
                    button.Visible = 'on';
                else
                    % Item does not exist, hide the button
                    button.Visible = 'off';
                    button.Value = 0;
                end
            end
        end


        function loading(app)
            app.stop = 0;
            app.ProcessingImage.Visible = 1;
            try
               app.NanoLocz.Pointer = 'watch';
            catch
            end
        end

        function finished(app)
            app.stop = 1;
            app.ProcessingImage.Visible = 0;
            try
            app.NanoLocz.Pointer = 'arrow';
            catch
            end
        end

        function storedata(app)
            ch = contains({app.stored.ImageName},app.FilesListBox.Value);
            field1 = 'ImageName';  value1 = app.ImageName;
            field2 = 'Channel';  value2 = app.ImageInfo.Channel;
            field3 = 'Imagedata';  value3 = app.ImageTarget;
            field4 = 'ImageInfo';  value4 = app.ImageInfo;
            if isfield(app.Part,'Locs')
                field5 = 'ImageLocs';  value5 = app.Part;
            else
                field5 = 'ImageLocs';  value5 = [];
            end
            if isfield(app.ref,'position')
                field6 = 'ref';  value6 = app.ref;
            else
                field6 = 'ref';  value6 = [];
            end

            if    isfield(app.LAFM,'Locs')
                field7 = 'LAFMLocs';  value7 = app.LAFM;
            else
                field7 = 'LAFMLocs';
                value7 = [];
            end

            if    isfield(app.Mask,'Analysis')
                field8 = 'Mask';  value8 = app.Mask;
            else
                field8 = 'Mask';
                value8 = [];
            end

            if sum(ch)==1
                app.stored(ch) = struct(field1,value1,field2,value2,field3, value3, field4, value4, field5, value5, field6, value6,field7,value7,field8,value8);
            else
                ch = numel(app.stored)+1;
                app.stored(ch) = struct(field1,value1,field2,value2,field3, value3, field4, value4, field5, value5, field6, value6,field7,value7,field8,value8);
            end
        end

        function batch_export(app,settings)
            settings.Channels;
            settings.Analysis;
            settings.Video_Frame;
            settings.Video_Particle;
            settings.Video_Format;
            settings.Analysis_Format;
            settings.Folder;

            switch settings.BatchexportDropDown
                case 'All files in folder'
                    for i = 1:numel(app.FilesListBox.Items)
                        clear Images_i
                        app.FilesListBox.Value = app.FilesListBox.Items{i};

                        switch settings.Channels
                            case 'Current Channel'
                                im = Open_files(app);
                                Images_i.Imagedata = single(im);
                                Images_i.ImageInfo = app.ImageInfo;
                                Images_i.ImageName = app.ImageName;
                                Images_i.Channel = app.ImageInfo.Channel;

                                Images_i.ImageLocs=[];
                                Images_i.ref=[];
                                Images_i.LAFMLocs=[];
                                exporter(app, Images_i, settings.Video_Format, settings.Folder)

                            case 'All Channels'
                        end
                    end

                case 'All processed'
            end
        end

        function importSimulation(app,image,pixpernm)
            sz = size(image);
            if size(image,3)>1
                reset_defaults(app)
                app.Spinner.Value = 1;
                app.FrameSlider.Value = 1;
                app.ImageTarget = image/10;
                app.ImageInfo.PixelPerNm = pixpernm;
                app.ImageInfo.ScanSize = sz(2)./app.ImageInfo.PixelPerNm;
                app.ImageInfo.xPixels = sz(2);
                app.ImageInfo.yPixels = sz(1);
                app.ImageInfo.Channel = 'Simulation';
                app.ImageInfo.Channels = {app.ImageInfo.Channel};
                app.ImageInfo.time = 0;
                app.ImageInfo.n = sz(3);
                updateTable(app)
                updateVideoTools(app)
                updatePlot(app,'normal')

                app.FilesListBox.Items = [app.stored.ImageName, app.ImageName];

                app.FilesListBox.Value = app.ImageName;
                storedata(app)
                savedata(app,app.ImageTarget,'Image')
                app.FilesDropDown.Value = 'Processed';
                try
                    app.FilesListBox.Items = {app.stored.ImageName};
                catch
                    app.FilesListBox.Items = {'no files'};
                end

            else
                app.ref.simImage = image/10;
                app.ref.position = [0 0 size(app.ref.simImage,2) size(app.ref.simImage,1)];
                app.ref.type = 'Simulated';
                if sum(strcmp(app.RightPlotList.Items,'Ref Image')) <1
                    app.RightPlotList.Items = [app.RightPlotList.Items , {'Ref Image'}];
                end
                app.RightPlotList.Value = 'Ref Image';
                if sum(strcmp(app.RefListBox.Items,'Simulated Reference')) <1
                    app.RefListBox.Items = [app.RefListBox.Items , {'Simulated Reference'}];
                end
                app.RefListBox.Value = 'Simulated Reference';

                updateRightPlot(app);
            end

            drawnow;
        end

        function updateTrackList(app)
            n = max(app.Part.Locs(:,7));
            values = cellstr(num2str((1:n)', '%d'));
            app.TrackList.Items = values;

        end

        function UpdateLAFM_MovieTools(app)
            x = strcmp(app.RightPlotList.Value,'LAFM sym Movie') | strcmp(app.RightPlotList.Value,'LAFM Movie');
            app.LAFMEndImage.Visible = x;
            app.LAFMStartImage.Visible = x;
            app.LAFMPlayImage.Visible = x;
            app.LAFMPauseImage.Visible = x;
            app.LAFMFrameSlider.Visible = x;
            app.LAFMSpinner.Visible = x;
            app.LAFMTimescaleCheckBox.Visible = x;
            switch app.RightPlotList.Value
                case 'LAFM Movie'
                    n = numel(app.LAFM.Movie);
                    app.LAFMFrameSlider.Limits = [1 n];
                    app.LAFMSpinner.Limits =  app.LAFMFrameSlider.Limits;
                    app.LAFMFrameSlider.Value = 1;
                case 'LAFM sym Movie'
                    n = numel(app.LAFM.Movie_sym);
                    app.LAFMFrameSlider.Limits = [1 n];
                    app.LAFMSpinner.Limits =  app.LAFMFrameSlider.Limits;
                    app.LAFMFrameSlider.Value = 1;
            end
        end

        function previewOnOff(app)
            if    app.PreviewonoffButton.Value ==1
                app.ImagePreview = filter(app);
                app.LeftPlotList.Value = 'Preview';
                app.PreviewonoffButton.BackgroundColor = [0, 0, 0];
                app.PreviewonoffButton.FontColor = [0.22,0.98,0.26];
            else
                app.LeftPlotList.Value = 'Target';
                app.PreviewonoffButton.BackgroundColor = [1, 1, 1];
                app.PreviewonoffButton.FontColor = [1, 0, 0];
            end
        end
        
        function ViewMaskChanged(app)
         
            if app.ViewMask.Value ==1
                app.OverlayDropDown.Value = 'Mask';
                app.ViewMask.FontColor = [0.22,0.98,0.26];
                app.ViewMask.BackgroundColor = [0 0 0];
            else
                app.ViewMask.FontColor = [1 0 0];
                app.ViewMask.BackgroundColor = [1 1 1];
            end
        end
          

        function handleDrop(app, data)
            droppedItems = data.names;  % Get dropped file/folder paths

            if exist(droppedItems{1}, 'dir')  % Check if it's a folder
                try
                    app.Settings.Folder.Open = droppedItems{1};
                    app.OpenFolder.Value = droppedItems{1};
                    updateFolder(app)
                    app.FilesDropDown.Value = 'All files';
                catch
                end
            else
                try
                    namesplit = split(droppedItems{1},'/');
                    folder = join(namesplit(1:end-1),'/');
                    file = namesplit(end);
                    app.Settings.Folder.Open = folder{1};
                    app.OpenFolder.Value = folder{1};
                    updateFolder(app)
                    app.FilesDropDown.Value = 'All files';
                    app.FilesListBox.Value = file{1};
                    FilesListBoxValueChanged(app)
                catch
                end
            end
        end

    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            % movegui('center');
            %  screenSize = get(0, 'ScreenSize'); % Get screen size
            % newPosition = [40, 40, 1311,702];
            % set(app.NanoLocz, 'Position', newPosition);
      
            % 
             % addpath('./NanoLocz-lib');
             % addpath('./icons');
             % addpath('./Mat_SimAFM')
             % addpath('./GUI')
try
          DnD_uifigure(app.FilesListBox, @(src, data) handleDrop(app, data));
catch
end

            app.ImageInfo=[];  app.Part.Image =[]; app.ref = [];  app.img_locs = [];
            app.LAFM= []; app.ROI.Line =[];app.ROI.AreaAverage =[]; app.ROI.Point =[];app.SquareROI=[];  app.patches.histup =[]; app.patches.histdown =[];
            app.stop = 0;

            Parameter = {'Num Imgs';'X-Range (nm)'; 'Speed (fps)';'Line/s (Hz)'; 'y pixels'; 'x pixels';'Pixel/nm';'Channel'};
            Value = [num2cell([0; 0; 0; 0; 0; 0; 0])];
            Value = [Value ; 'unknown'];
            app.UITable.Data = [Parameter Value];

            s = load('pref.mat');
            app.Settings = s.Settings;
            app.ColourmapDropDown.Value = app.Settings.ColourmapDropDown;
            app.Settings.Folder.Open = app.Settings.Folder.Starting;
            app.OutputFolder.Value = app.Settings.Folder.Output;
            app.OpenFolder.Value = app.Settings.Folder.Open;
            app.Settings.lightmethod = 'none';
            app.Settings.lightAng = [45 45];
            app.Settings.viewangle = [45 45];

            app.Lamp.Enable = app.Settings.ExportSettings.AutoSave;
            app.AutoSaveButton.Value =  app.Settings.ExportSettings.AutoSave;

             axis(app.UIAxes_2, 'tight','equal');
             axis(app.UIAxes, 'tight','equal');
             axis(app.Hist_Axes,'tight');
             set(app.Hist_Axes,'visible','on')
             set(app.Hist_Axes,'Color','k')
            % set(app.UIAxes_2,'visible','off')
            % set(app.UIAxes,'visible','off')
             set(app.loadbar,'visible','off')
             set(app.UIAxes_2,'Color','k')
             set(app.UIAxes,'Color','k')
            
            xlim(app.loadbar,[0 1])
            updateFolder(app)

            field1 = 'ImageName';  value1 = 'Empty';
            field2 = 'Channel';    value2 = 'Empty';
            field3 = 'Imagedata';  value3 = 0;
            field4 = 'ImageInfo';  value4 = app.ImageInfo;
            field5 = 'ImageLocs';  value5 = [];
            field6 = 'ref';        value6 = [];
            field7 = 'LAFMLocs';   value7 = [];
            field8 = 'Mask';   value8 = [];

            app.stored = struct(field1,value1,field2,value2,field3, value3, field4, value4, field5, value5, field6, value6, field7, value7,field8,value8);
            app.NanoLocz.AutoResizeChildren = 'on';
            % Get main UIFigure position
            createLogWindow(app);
     
            writeLog(app,  app.AppName + "  MATLAB: " + version);  % Name shown in window title)
     
        end

        % Key press function: NanoLocz
        function NanoLoczKeyPress(app, event)

            switch  event.Key
                case 'return'
                    AcceptButtonImageClicked(app, event)
                case {'delete', 'backspace'}
                    BinImageClicked(app, event)
                case 'rightarrow'
                    if app.VideoPanel.Visible ==1
                        f = app.FrameSlider.Value+1;
                        if f >app.FrameSlider.Limits(2)
                            app.FrameSlider.Value = 1;
                        else
                            app.FrameSlider.Value = app.FrameSlider.Value+1;
                        end
                        app.Spinner.Value = round(app.FrameSlider.Value);
                        app.ImagePreview = filter(app);
                        updatePlot(app,'normal')
                        updatePlot2(app,'normal')
                    end
                case'leftarrow'
                    if app.VideoPanel.Visible ==1
                        f = app.FrameSlider.Value-1;
                        if f <1
                            app.FrameSlider.Value = app.FrameSlider.Limits(2);
                        else
                            app.FrameSlider.Value = app.FrameSlider.Value-1;
                        end
                        app.Spinner.Value = round(app.FrameSlider.Value);
                        app.ImagePreview = filter(app);
                        updatePlot(app,'normal')
                        updatePlot2(app,'normal')
                    end
                case'downarrow'
                    app.ZScaleDropBox.Value = 'Manual';
                    range = app.ContrastMaxSpinner.Value - app.ContrastMinSpinner.Value;
                    if strcmp(event.Key, 'downarrow') && ismember('shift', event.Modifier)
                        app.ContrastMaxSpinner.Value = app.ContrastMaxSpinner.Value+0.05*range;
                        app.ContrastMinSpinner.Value = app.ContrastMinSpinner.Value-0.05*range;
                    else
                        app.ContrastMinSpinner.Value = app.ContrastMinSpinner.Value-0.05*range;
                        app.ContrastMaxSpinner.Value = app.ContrastMaxSpinner.Value-0.05*range;
                    end
                    updatePlot(app,'normal')
                    updatePlot2(app,'normal')

                case'uparrow'
                    app.ZScaleDropBox.Value = 'Manual';
                    range = app.ContrastMaxSpinner.Value - app.ContrastMinSpinner.Value;
                    if strcmp(event.Key, 'uparrow') && ismember('shift', event.Modifier)
                        app.ContrastMaxSpinner.Value = app.ContrastMaxSpinner.Value-0.05*range;
                        app.ContrastMinSpinner.Value = app.ContrastMinSpinner.Value+0.05*range;
                    else
                        app.ContrastMinSpinner.Value = app.ContrastMinSpinner.Value+0.05*range;
                        app.ContrastMaxSpinner.Value = app.ContrastMaxSpinner.Value+0.05*range;
                    end
                    updatePlot(app,'normal')
                    updatePlot2(app,'normal')

                case'x'
                    if strcmp(event.Key, 'x') && ismember('shift', event.Modifier)
                        CropButtonPushed(app)
                    end
                case'k'
                    if sum(strcmp( app.DrawTool.Items,'Profile')) ==1
                        app.DrawTool.Value = 'Profile';
                        DrawToolValueChanged(app)
                    end
                case'r'
                    if sum(strcmp( app.DrawTool.Items,'ROI')) ==1
                        app.DrawTool.Value = 'ROI';
                        DrawToolValueChanged(app)
                    end
                case'a'
                    if sum(strcmp( app.DrawTool.Items,'Average')) ==1
                        app.DrawTool.Value = 'Average';
                        DrawToolValueChanged(app)
                    end
                case {'+','add','equal'}
                    if sum(strcmp( app.DrawTool.Items,'+ Mask')) ==1
                        app.DrawTool.Value = '+ Mask';
                        DrawToolValueChanged(app)
                    end

                case {'-','hyphen'}
                    if sum(strcmp( app.DrawTool.Items,'- Mask')) ==1
                        app.DrawTool.Value = '- Mask';
                        DrawToolValueChanged(app)
                    end
                case'l'
                    if sum(strcmp( app.Draw.Items,'Line')) ==1
                        app.Draw.Value = 'Line';
                        DrawValueChanged(app)
                    end
                case's'
                    if sum(strcmp( app.Draw.Items,'Square'))==1
                        app.Draw.Value = 'Square';
                        DrawValueChanged(app)
                    end
                case'c'
                    if sum(strcmp( app.Draw.Items,'Circle')) ==1
                        app.Draw.Value = 'Circle';
                        DrawValueChanged(app)
                    end
                case'f'
                    if sum(strcmp( app.Draw.Items,'Freehand')) ==1
                        app.Draw.Value = 'Freehand';
                        DrawValueChanged(app)
                    end


            end

        end

        % Callback function: RestoreButton, View1st, View1stCheckBox
        function Reload(app, event)
            app.Stacker.Value = 'Stack off';
            ch = contains({app.stored.ImageName},app.FilesListBox.Value);
            if sum(ch)==1
                app.stored(ch) = [];
            end

            if strcmp(app.ImageName, 'LoadImg.tif')
                LoadstartupImg(app)
                updatePlot(app,'normal')
            else
                FilesListBoxValueChanged(app, event)
            end
            app.filter_already =0;
        end

        % Callback function: AutoflattenDropDown, FixLineShift, 
        % ...and 18 other components
        function Refresh_Filter(app, event)
if isfield(app.ImageInfo,'Channel')
            switch app.ImgFilterType.Value
                case 'Sliding Mean Frames'
                    if app.ImgFilterStrength.Value >1
                        app.ImagePreview = movmean(app.ImageTarget ,app.ImgFilterStrength.Value,3);
                        updatePlot(app,'normal')
                        app.filter_already =1;
                    end

                otherwise
                    app.filter_already =0;
                    app.PreviewonoffButton.Value =1;
                    previewOnOff(app)
                    run_filter(app)
            end
end
        end

        % Value changed function: Spinner
        function SpinnerValueChanged(app, event)
            app.FrameSlider.Value = round(app.Spinner.Value);
            app.filter_full=0;
            switch app.LeftPlotList.Value
                case 'Preview'
                    app.ImagePreview = filter(app);
            end

                switch app.HistogramDropDown.Value
                    case 'Mask z (frame)'
                        
                        updateHist(app,'normal')
                end
            updatePlot(app,'normal')
            updatePlot2(app,'normal')
        end

        % Button pushed function: Forceto11Button
        function Forceto11ButtonPushed(app, event)

            sz = size(app.ImageTarget);
            newSz = max(sz(1:2));
            app.ImageTarget = imresize(app.ImageTarget, [newSz, newSz], 'bicubic');
            app.Mask.Mask = [];
            updatePlot(app,'normal')
            updatePlot2(app,'normal')
        end

        % Value changed function: ChannelDropDown, ErrorButton, 
        % ...and 1 other component
        function FilesListBoxValueChanged(app, event)
            
                loading(app)
                app.Spinner.Value = 1;
                app.FrameSlider.Value = 1;

                switch app.Stacker.Value
                    case 'Stack off'
                        reset_defaults(app)
                    case {'Stack','Intercalate'}
                        tempinfo = app.ImageInfo;
                        app.Mask = [];
                        app.Mask.low_thresh = -Inf;
                        app.Mask.high_thresh = Inf;
                end
                try
                    ch = contains({app.stored.ImageName},app.FilesListBox.Value);
                    if sum(ch)==1
                        if numel(app.stored(ch).ImageInfo.Channels)>1
                            app.ChannelDropDown.Visible=1;
                            ch2 = contains({app.stored(ch).Channel},app.ChannelDropDown.Value);
                        else
                            ch2 = 1;
                        end
                    else
                        ch2=0;
                    end
                catch
                    ch2=1;
                    app.stored(ch).ImageInfo.Channels = {'old session'};
                    app.stored(ch).ImageInfo.Channel = {'old session'};
                end
                if sum(ch)+ sum(ch2) ==2
                    switch app.Stacker.Value
                        case 'Stack off'
                            im = app.stored(ch).Imagedata;
                            app.ImageTarget = single(im);

                        case {'Stack','Intercalate'}
                            im = app.stored(ch).Imagedata;
                    end

                    app.ImageName = app.stored(ch).ImageName;
                    app.ImageInfo = app.stored(ch).ImageInfo;

                    if isfield(app.stored(ch).ref,'position')
                        app.ref = app.stored(ch).ref;

                        if isfield(app.stored(ch).ref,'ROIimage')
                            app.RefListBox.Items = [app.RefListBox.Items , {'ROI Reference'}];
                        end
                        if isfield(app.stored(ch).ref,'simImage')
                            app.RefListBox.Items = [app.RefListBox.Items , {'Simulated Reference'}];
                        end
                        if isfield(app.stored(ch).ref,'LAFM')
                            app.RefListBox.Items = [app.RefListBox.Items , {'LAFM Reference'}];
                        end
                        if isfield(app.stored(ch).ref,'av')
                            app.RefListBox.Items = [app.RefListBox.Items , {'Averaged Reference'}];
                        end

                        if sum(strcmp(app.RightPlotList.Items,'Ref Image')) <1
                            app.RightPlotList.Items = [app.RightPlotList.Items , {'Ref Image'}];
                        end
                    end

                    if isfield(app.stored(ch).ImageLocs,'Locs')
                        app.Part = app.stored(ch).ImageLocs;

                        if sum(strcmp(app.HistogramDropDown.Items,'Particle Correlation')) <1
                            app.HistogramDropDown.Items = [app.HistogramDropDown.Items , {'Particle Correlation'}];
                        end
                        if sum(strcmp(app.HistogramDropDown.Items,'Particle z')) <1
                            app.HistogramDropDown.Items = [app.HistogramDropDown.Items , {'Particle z'}];
                        end
                        updateHist(app,'normal')
                        if app.BoxesCheckBox.Value==1
                            app.OverlayDropDown.Value = 'Detections';
                        end
                        filter_Partlocs(app)
                        %  app.Part.Image  = ConstructParticleStack2(app, app.ImageTarget, app.ref, app.Part,0);
                    end

                    if isfield(app.stored(ch).LAFMLocs,'Locs')
                        app.LAFM = app.stored(ch).LAFMLocs;
                        if sum(strcmp(app.RightPlotList.Items,'Localizations')) <1
                            app.RightPlotList.Items = [app.RightPlotList.Items , {'Localizations'}];
                        end
                        if isfield(app.stored(ch).LAFMLocs,'Image')
                            if sum(strcmp(app.RightPlotList.Items,'LAFM')) <1
                                app.RightPlotList.Items = [app.RightPlotList.Items , {'LAFM'}];
                            end
                        end
                        if sum(strcmp(app.HistogramDropDown.Items,'Particle z')) <1
                            app.HistogramDropDown.Items = [app.HistogramDropDown.Items , {'Particle z'}];
                        end
                    end

                else

                    im = Open_files(app);
                    if im == 0
                        return
                    end

                end

                updatePlot2_type(app,'image')
                pause(0.000001)

                app.Hist_excludeOut.Value=0;
                app.FrameSlider.Value = 1;
                app.Spinner.Value = 1;
                switch app.Stacker.Value
                    case 'Stack off'
                        app.ImageTarget = single(im);

                    case 'Stack'
                     
                        try
                            app.ImageTarget = cat(3,app.ImageTarget,single(im));
                        catch
                            A = app.ImageTarget; B = single(im);
                            app.ImageTarget = pad_stacker(A,B);
                        end

                        if numel(tempinfo.PixelPerNm) ~= tempinfo.n
                            tempinfo.PixelPerNm(1:tempinfo.n) = tempinfo.PixelPerNm(1);
                        end
                        if numel(app.ImageInfo.PixelPerNm) ~= app.ImageInfo.n
                            app.ImageInfo.PixelPerNm(1:app.ImageInfo.n) = app.ImageInfo.PixelPerNm(1);
                        end

                        app.ImageInfo.n = size(app.ImageTarget,3);
                        app.ImageInfo.time = [tempinfo.time, app.ImageInfo.time];
                        app.ImageInfo.ScanSize = [tempinfo.ScanSize, app.ImageInfo.ScanSize];

                        app.ImageInfo.PixelPerNm = [tempinfo.PixelPerNm, app.ImageInfo.PixelPerNm];

                    case 'Intercalate'
                        app.Mask = [];
                               app.Mask.low_thresh = -Inf;
                        app.Mask.high_thresh = Inf;
                        try
                            num_slices = size(app.ImageTarget,3);
                            for i = 1:num_slices
                                temp(:, :, i*2 - 1) = app.ImageTarget(:, :, i);
                                temp(:, :, i*2) = im(:, :, i);

                                tempinfo_2.time(i*2 - 1) = tempinfo.time(i);
                                tempinfo_2.time(i*2) = app.ImageInfo.time(i);
                                if numel(tempinfo.ScanSize)>1
                                    tempinfo_2.ScanSize(i*2 - 1) = tempinfo.ScanSize(i);
                                    tempinfo_2.ScanSize(i*2) = app.ImageInfo.ScanSize(i);
                                    tempinfo_2.PixelPerNm(i*2 - 1) = tempinfo.PixelPerNm(i);
                                    tempinfo_2.PixelPerNm(i*2) = app.ImageInfo.PixelPerNm(i);
                                end
                            end
                            app.ImageTarget = temp;
                            app.ImageInfo.n = size(app.ImageTarget,3);
                            app.ImageInfo.time = tempinfo_2.time;

                            if numel(tempinfo.ScanSize)>1
                                app.ImageInfo.ScanSize = tempinfo_2.ScanSize;
                                app.ImageInfo.PixelPerNm = tempinfo_2.PixelPerNm;
                            end
                        catch
                            errordlg('Images different pixel size', 'Error');
                            writeLog(app, '---ERROR--- Images different pixel size')
                            return
                        end
                end

             
                app.filter_full=0;
                app.ImagePreview = filter(app);
                
                updatePlot(app,'normal')
                updateHist(app,'normal')
                updatePlot2(app,'normal')

                if app.View1stCheckBox.Value ==1 && app.View1st.Value < app.ImageInfo.n
                    loadn = app.View1st.Value;
                else
                    loadn = app.ImageInfo.n;
                end
                %app.ImageSource = app.ImageTarget;
                finished(app)
                updateVideoTools(app)
                DrawValueChanged(app)
                updateTable(app)
                set(app.UIAxes,'xlimMode', 'auto','ylimMode', 'auto')

                if app.AutoplayCheckBox.Value==1 && loadn>1
                    PlayImageClicked(app, event)
                end
                switch app.PlotTypeDropDown.Value
                    case '2D surface'
                        app.UIAxes.Toolbar.Visible = 'off';
                        pan(app.UIAxes,'on')
                        pan(app.UIAxes,'off')
                        rotate3d(app.UIAxes, 'off');
                    case '3D surface'
                        app.UIAxes.Toolbar.Visible = 'on';
                        rotate3d(app.UIAxes, 'on');
                    case 'Coordinates'
                        app.UIAxes.Toolbar.Visible = 'on';
                        rotate3d(app.UIAxes, 'on');
                end
        end

        % Button pushed function: ZeroallButton
        function ZeroallButtonPushed(app, event)

            app.filter_already =0;
            app.FixLineShift.Value = 0;
            app.ImgFilterStrength.Value = app.ImgFilterStrength.Limits(1);
            app.ImgFilterStrength_2.Value = app.ImgFilterStrength_2.Limits(1);
            app.XLineSpinner.Value = 0;
            app.YLineSpinner.Value = 0;
            app.ThresholdMethod.Value = 'histogram';
            app.MedianCheckBox.Value = 0;
            app.MedianCheckBox_y.Value = 0;
            app.sMedianCheckBox.Value = 0;
            app.XPlaneSpinner.Value = 0;
            app.YPlaneSpinner.Value = 0;


            app.filter_full=0;
            app.ImagePreview = filter(app);

            updatePlot(app,'normal')
            updatePlot2(app,'normal')
        end

        % Value changed function: ColourmapDropDown, 
        % ...and 16 other components
        function TrackLengthEditFieldValueChanged(app, event)
            
            updatePlot(app,'normal')
            updatePlot2(app,'normal')
        
        end

        % Button pushed function: AverageReferenceButton
        function AverageReferenceButtonPushed(app, event)
            try
                app.ref.av  = mean(app.Part.Image(:,:,app.Part.IncludeImages),3);
                app.RightPlotList.Value = 'Ref Image';
                if sum(strcmp(app.RefListBox.Items,'Averaged Reference')) <1
                    app.RefListBox.Items = [app.RefListBox.Items , {'Averaged Reference'}];
                end
                app.RefListBox.Value = 'Averaged Reference';
                updateRightPlot(app);

                storedata(app)
                savedata(app,app.ref.av,'Ref avg')
                updatePlot2(app,'normal')
            catch
            end
        end

        % Button pushed function: RotationalAvgButton
        function RotationalAvgButtonPushed(app, event)

            app.ref.av = rotation_sym(app.ref.image, app.symmetry.Value);
            storedata(app)
            savedata(app,app.ref.av,'Ref avg')
            app.RightPlotList.Value = 'Ref Image';
            if sum(strcmp(app.RefListBox.Items,'Averaged Reference')) <1
                app.RefListBox.Items = [app.RefListBox.Items , {'Averaged Reference'}];
            end
            app.RefListBox.Value = 'Averaged Reference';
            updatePlot2(app,'normal')

        end

        % Value changed function: LogYHist
        function LogYHistValueChanged(app, event)
            updateHist(app,'normal')
        end

        % Image clicked function: PlayImage
        function PlayImageClicked(app, event)
            app.PlayImage.Visible=0;
            app.PauseImage.Visible=1;
            app.stop=0;
            currentFrame = round(app.FrameSlider.Value);

            if strcmp(app.PlotTypeDropDown.Value,'3D surface') || strcmp(app.PlotTypeDropDown.Value,'Coordinates')
                [app.Settings.viewangle(1), app.Settings.viewangle(2)]= view(app.UIAxes);
            end

            while true
                tic;
                if app.stop == 1
                    return
                end

                if currentFrame == app.FrameSlider.Limits(2) + 1
                    currentFrame = 1;
                end

                app.FrameSlider.Value = currentFrame;
                app.Spinner.Value = currentFrame;
                app.filter_full=0;

                switch app.LeftPlotList.Value
                    case 'Preview'
                        app.ImagePreview = filter(app);
                end

                updatePlot(app,'normal')

                switch app.HistogramDropDown.Value
                    case 'Mask z (frame)'
                        updateHist(app,'normal')
                end

                if strcmp(app.PlotTypeDropDown.Value,'3D surface') || strcmp(app.PlotTypeDropDown.Value,'Coordinates')
                    view(app.UIAxes,app.Settings.viewangle(1), app.Settings.viewangle(2));
                end

                switch app.RightPlotList.Value
                    case {'Off','Ref Image','Localizations','LAFM', 'LAFM_sym','Kymograph'}
                    case 'Line Profile'
                        updatePlot2(app,'normal')
                end

                playtime = toc;
                pause(1/app.fps.Value-playtime)
                drawnow

                currentFrame = currentFrame + 1;
            end

            app.PauseImage.Visible=0;
            app.PlayImage.Visible=1;
        end

        % Image clicked function: PauseImage
        function PauseImageClicked(app, event)
            app.PlayImage.Visible=1;
            app.PauseImage.Visible=0;
            app.stop = 1;
        end

        % Image clicked function: StartImage
        function StartImageClicked(app, event)
            app.FrameSlider.Value = 1;
            app.Spinner.Value = app.FrameSlider.Value;
            app.filter_full=0;
            switch app.LeftPlotList.Value
                case 'Preview'
                    app.ImagePreview = filter(app);
            end
            switch app.HistogramDropDown.Value
                case 'Mask z (frame)'
                    updateHist(app,'normal')
            end
            updatePlot(app,'normal')
            updatePlot2(app,'normal')
        end

        % Image clicked function: EndImage
        function EndImageClicked(app, event)
            app.FrameSlider.Value = app.FrameSlider.Limits(2);
            app.Spinner.Value = app.FrameSlider.Value;
            app.filter_full=0;
            
            switch app.LeftPlotList.Value
                case 'Preview'
                    app.ImagePreview = filter(app);
            end
            switch app.HistogramDropDown.Value
                case 'Mask z (frame)'
                    updateHist(app,'normal')
            end
            updatePlot(app,'normal')
            updatePlot2(app,'normal')
        end

        % Image clicked function: BinRangeImage
        function BinRangeImageClicked(app, event)
            RemoveFrames = app.RemoveframesEditField.Value;
            RemoveFrames = strrep(RemoveFrames,'-',':');
            RemoveFrames = str2num(RemoveFrames);

            switch  app.FramePartSwitch.Value
                case 'Frames'
                    app.ImageTarget(:,:,RemoveFrames) = [];
                    app.ImageInfo.n = app.ImageInfo.n - size(RemoveFrames,2);
                 %   app.ImageSource(:,:,RemoveFrames) = [];
                    try
                        if numel(app.ImageInfo.ScanSize)>1
                            app.ImageInfo.ScanSize(RemoveFrames) =[];
                            app.ImageInfo.PixelPerNm(RemoveFrames) =[];
                        end
                        app.ImageInfo.time(RemoveFrames) =[];
                    catch
                    end
                    writeLog(app, "Frames " + app.RemoveframesEditField.Value + " removed",'image');
                case 'Particles'
                    pos = ismember(app.Part.Locs(:,6), RemoveFrames);
                    app.Part.Locs(pos,3:4) = nan;
                    filter_Partlocs(app)
                    writeLog(app, "Particles " + app.RemoveframesEditField.Value + " removed",'image');
            end

            updateVideoTools(app)

            updatePlot(app,'normal')
            updatePlot2(app,'normal')

            updateHist(app,'normal')
            storedata(app)
            savedata(app,app.ImageTarget,'Image')
        end

        % Image clicked function: BinImage
        function BinImageClicked(app, event)

            RemoveFrames = round(app.FrameSlider.Value);
            switch  app.FramePartSwitch.Value
                case 'Frames'
                    app.ImageTarget(:,:,RemoveFrames) = [];
                    app.ImageInfo.n = app.ImageInfo.n - 1;
                  %  app.ImageSource(:,:,RemoveFrames) = [];
                    try
                        if numel(app.ImageInfo.ScanSize)>1
                            app.ImageInfo.ScanSize(RemoveFrames) =[];
                            app.ImageInfo.PixelPerNm(RemoveFrames) =[];
                        end

                        app.ImageInfo.time(RemoveFrames) =[];
                    catch
                    end
                    writeLog(app, "Frame " + string(RemoveFrames) + " removed",'image');
                case 'Particles'
                    pos = app.Part.Locs(:,6)==RemoveFrames;
                    app.Part.Locs(pos,3:4) = nan;
                    filter_Partlocs(app)
                   writeLog(app, "Particle " + string(RemoveFrames) + " removed",'image');
            end
            updateVideoTools(app)
            app.Spinner.Value = round(app.FrameSlider.Value);
            updatePlot(app,'normal')
            updatePlot2(app,'normal')
      
            updateHist(app,'normal')
            storedata(app)
            savedata(app,app.ImageTarget,'Image')
        end

        % Value changing function: FrameSlider
        function FrameSliderValueChanging(app, event)

            app.Spinner.Value = round(event.Value);
            app.filter_full=0;
            switch app.LeftPlotList.Value
                case 'Preview'
                    app.ImagePreview = filter(app);
            end

            switch app.HistogramDropDown.Value
                case 'Mask z (frame)'
                    
                    updateHist(app,'normal')
            end

            updatePlot(app,'normal')
            updatePlot2(app,'normal')
        end

        % Callback function: AcceptButtonImage, Apply
        function AcceptButtonImageClicked(app, event)
            app.ImageUndo = app.ImageTarget;
            app.filter_full=1;
            loading(app)
            pause(0.000001)
            app.ImagePreview = filter(app);

            switch  app.FramePartSwitch.Value
                case 'Frames'
                    app.ImageTarget = app.ImagePreview;
                case 'Particles'
                    app.Part.Image = app.ImagePreview;
            end

            % --- Build filter details list ---
            details = strings(0,1);

            hasPlane  = app.XPlaneSpinner.Value ~= 0 || app.YPlaneSpinner.Value ~= 0;
            hasLine   = app.XLineSpinner.Value ~= 0 || app.YLineSpinner.Value ~= 0;
            hasMedian = app.MedianCheckBox.Value || app.MedianCheckBox_y.Value;

            % --- Image filter ---
            if app.ImgFilterType.Value ~= "Off"
                details(end+1) = app.ImgFilterType.Value;

                if app.ImgFilterStrength.Value ~= app.ImgFilterStrength.Limits(1)
                    details(end+1) = "Strength=" + string(app.ImgFilterStrength.Value);
                end

                if app.ImgFilterStrength_2.Value ~= app.ImgFilterStrength_2.Limits(1)
                    details(end+1) = "Strength2=" + string(app.ImgFilterStrength_2.Value);
                end
            end

            % --- Line removal ---
            if app.XLineSpinner.Value ~= 0
                details(end+1) = "X-lines=" + string(app.XLineSpinner.Value);
            end

            if app.YLineSpinner.Value ~= 0
                details(end+1) = "Y-lines=" + string(app.YLineSpinner.Value);
            end

            % --- Autoflatten ---
            if app.AutoflattenDropDown.Value ~= "Off"
                details(end+1) = "Autoflatten=" + app.AutoflattenDropDown.Value;
            end

            % --- Median filters ---
            if app.MedianCheckBox.Value
                details(end+1) = "Median X";
            end

            if app.MedianCheckBox_y.Value
                details(end+1) = "Median Y";
            end

            % --- Line shift ---
            if app.FixLineShift.Value
                details(end+1) = "LineShift";
            end

            % --- Plane subtraction ---
            if app.XPlaneSpinner.Value ~= 0
                details(end+1) = "X-plane=" + string(app.XPlaneSpinner.Value);
            end

            if app.YPlaneSpinner.Value ~= 0
                details(end+1) = "Y-plane=" + string(app.YPlaneSpinner.Value);
            end

            % --- Multi-plane (ONLY if meaningful) ---
            if app.MultiPlane.Value && (hasPlane || hasLine || hasMedian)
                details(end+1) = "Multi-plane";
            end

            % --- Thresholding ---
            thresholdUsesLimits = ismember(app.ThresholdMethod.Value, ...
                ["histogram","auto edges","hist edges","hist skel", ...
                "step detection","adaptive","parachute"]);

            if app.ThresholdMethod.Value ~= "Off"

                showThreshold = true;
                limits = "";

                if thresholdUsesLimits
                    switch app.ThresholdMethod.Value
                        case {'auto edges','parachute'}
                            low = app.Mask.auto_thick;
                            high = app.Mask.auto_thresh;
                        otherwise
                            low = app.Mask.low_thresh;
                            high = app.Mask.high_thresh;
                    end

                    % --- Suppress if limits are [-Inf, Inf] ---
                    if isinf(low) && isinf(high) && low < 0 && high > 0
                        showThreshold = false;
                    else
                        limits = "Limits=[" + low + ", " + high + "]";
                    end
                end

                if showThreshold
                    details(end+1) = "Threshold=" + app.ThresholdMethod.Value;

                    if limits ~= ""
                        details(end+1) = limits;
                    end

                    if app.InvertMask.Value
                        details(end+1) = "InvertMask";
                    end
                end
            end


            % --- Create log message ---
            if isempty(details)
                msg = "Filter applied (no parameters)";
            else
                msg = "Filter applied: " + join(details, ", ");
            end

            writeLog(app, msg,'image');

            app.LeftPlotList.Value = 'Target';
            updatePlot(app,'normal')
            app.filter_already =0;
            app.ImgFilterType.Value = 'Off';
            app.ImgFilterStrength.Value = app.ImgFilterStrength.Limits(1);
            app.ImgFilterStrength_2.Value = app.ImgFilterStrength_2.Limits(1);
            app.XLineSpinner.Value = 0;
            app.YLineSpinner.Value = 0;
            app.AutoflattenDropDown.Value = 'Off';
            app.MedianCheckBox.Value = 0;
            app.MedianCheckBox_y.Value = 0;
            app.FixLineShift.Value = 0;
            app.XPlaneSpinner.Value = 0;
            app.YPlaneSpinner.Value = 0;

            updateHist(app,'normal')
            finished(app)
            storedata(app)
            savedata(app,app.ImageTarget,'Image')
            
        end

        % Value changed function: PreviewonoffButton
        function PreviewonoffButtonValueChanged(app, event)
            previewOnOff(app)
            updatePlot(app,'normal')
        end

        % Image clicked function: FolderUp
        function FolderUpImageClicked(app, event)

            app.Settings.Folder.Open = fileparts(app.Settings.Folder.Open);
            imageFiles = dir([app.Settings.Folder.Open]);
            imageFiles = imageFiles(~ismember({imageFiles(:).name},{'.','..','.DS_Store'}));
            app.FilesListBox.Items = {imageFiles.name};

            app.OpenFolder.Value = app.Settings.Folder.Open;
        end

        % Image clicked function: FolderDown
        function FolderDownImageClicked(app, event)
            app.Settings.Folder.Open = [app.Settings.Folder.Open '/' app.FilesListBox.Value];
            imageFiles = dir([app.Settings.Folder.Open]);
            imageFiles = imageFiles(~ismember({imageFiles(:).name},{'.','..','.DS_Store'}));
            app.FilesListBox.Items = {imageFiles.name};

            app.OpenFolder.Value = app.Settings.Folder.Open;
        end

        % Double-clicked callback: FilesListBox
        function FilesListBoxDoubleClicked(app, event)
           app.stop = 1;
            app.CancelButton.Visible = 0;
            finished(app)
            app.Settings.Folder.Open = [app.Settings.Folder.Open '/' app.FilesListBox.Value];
            imageFiles = dir([app.Settings.Folder.Open]);
            imageFiles = imageFiles(~ismember({imageFiles(:).name},{'.','..','.DS_Store'}));
            app.FilesListBox.Items = {imageFiles.name};

            app.OpenFolder.Value = app.Settings.Folder.Open;
        end

        % Value changed function: ColorBarCheckBox
        function ColorBarCheckBoxValueChanged(app, event)

            if app.ColorBarCheckBox.Value==1
                colorbar(app.UIAxes,'Color','w','Box','off','TickDirection','out')
                if app.Lightmode.Value == 0
                    colorbar('Color','k','Box','off','TickDirection','out')
                else
                    colorbar('Color','w','Box','off','TickDirection','out')
                end
                            
            else
                colorbar(app.UIAxes,'off')
            end
        end

        % Button pushed function: AutoButton
        function AutoButtonPushed(app, event)
            switch app.ZScaleDropBox.Value
                case 'Max Min'
                    app.ZScaleDropBox.Value = 'Exc outliers';
                otherwise
                    app.ZScaleDropBox.Value = 'Max Min';
            end
            updatePlot(app,'normal')
            updatePlot2(app,'normal')
        end

        % Callback function: ContrastMaxSpinner, ContrastMinSpinner, 
        % ...and 1 other component
        function HoldButtonPushed(app, event)
            app.ZScaleDropBox.Value = 'Manual';

            updatePlot(app,'normal')
            updatePlot2(app,'normal')
        end

        % Button pushed function: ResetButton
        function ResetButtonPushed(app, event)

            app.ROI.Line = findobj(app.UIAxes, 'Tag', 'MyDrawPolygon'); %Find the Polygon currntly drawn.
            app.SquareROI= findobj(app.UIAxes, 'Tag', 'MyDrawPolygon'); %Find the Polygon currntly drawn.
            if ~isempty(app.ROI.Point)
                app.ROI.Point = [];
            end

            if ~isempty(app.ROI.Line)
                app.ROI.Line.delete();
            end

            if ~isempty(app.ROI.AreaAverage)
                app.ROI.AreaAverage =[];
            end

            if ~isempty(app.SquareROI)
                app.SquareROI.delete();
            end
            app.RightPlotList.Value='Off';
            RightPlotListValueChanged(app, event)
            if strcmp(app.AspectRatioSwitch.Value, '1:1')
                axis(app.UIAxes,'normal');
                axis(app.UIAxes,'tight','equal');
                pbaspect(app.UIAxes,[1 1 1])
            else
                axis(app.UIAxes,'normal');
                axis(app.UIAxes,'tight','equal');
            end
            app.ROI.Line = [];
            app.Mask.ROI = [];
            app.filter_already =0;

            run_filter(app)

            updatePlot2(app,'normal')
            updatePlot(app,'normal')
        end

        % Value changed function: RightPlotList
        function RightPlotListValueChanged(app, event)

            switch app.RightPlotList.Value
                case {'Off','Ref Image','Localizations','LAFM', 'LAFM_sym','Kymograph'}
                    updatePlot2_type(app,'image')
                case 'Line Profile'
                    updatePlot2_type(app,'plot')
            end

            switch app.RightPlotList.Value
                case {'LAFM'}
                    app.LAFMMinSlider.Limits = [0, max(app.LAFM.Image(:))];
                    app.LAFMMaxSlider.Limits =  app.LAFMMinSlider.Limits;
            end

            switch app.RightPlotList.Value
                case 'Ref Image'
                    app.TabGroup2.SelectedTab = app.ReferenceTab;
            end
            updatePlot2(app,'normal')
        end

        % Button pushed function: VideoAlignButton
        function RunAlignButtonPushed(app, event)
            loading(app)
            app.img_locs = [];
            if app.View1stCheckBox.Value ==1 && app.View1st.Value < app.ImageInfo.n
                loadn = app.View1st.Value;
            else
                loadn = app.ImageInfo.n;
            end

            if isfield(app.ref,'position')
            else
                app.ref.position = [1, 1, size(app.ImageTarget,2),size(app.ImageTarget,1)];
                p =app.ref.position;
                app.ref.image =  imcrop(app.ImageTarget(:,:,round(app.FrameSlider.Value)), [p(1), p(2), p(3)-1, p(4)-1]);
            end
            refimg = app.ref;
            full_image = 0;

            if app.Settings.Align.FilterImg >0
                T = imgaussfilt(app.ImageTarget,app.Settings.Align.FilterImg);
            else
                T = app.ImageTarget;
            end

            [x, y] = align_movie(T, refimg, app.Settings.Align.Maxdrift, full_image,  app.Settings.Align.SubPix, app.Settings.Align.FilterCCR );

            if app.Settings.Align.SmoothDrift > 1
                x = smooth(x,app.Settings.Align.SmoothDrift);
                y = smooth(y,app.Settings.Align.SmoothDrift);
            end

            if app.Settings.Align.SubPix ==0
                x = round(x);
                y = round(y);
            end

            app.img_locs(:,1) = x;
            app.img_locs(:,2) = y;

            figure(10); clf;

            plot(x, 'x-', 'LineWidth', 1.4);
            hold on;
            plot(y, 'v-', 'LineWidth', 1.4);
            hold off;

            xlabel('Frame Number');
            ylabel('Shift (pixels)');
            title('Frame-to-Frame Alignment Shifts');
            legend({'X shift','Y shift'}, 'Location','best');
            grid on;
            sz = size(app.ImageTarget);

            switch app.Settings.Align.CroppingSwitch
                case 'Expand'
                    if min(y)<0
                        miny = round(min(y));
                    else
                        miny = 0;
                    end

                    if min(x)<0
                        minx = round(min(x));
                    else
                        minx = 0;
                    end

                    if max(y)>0
                        maxy = round(max(y));
                    else
                        maxy = 0;
                    end

                    if max(x)>0
                        maxx = round(max(x));
                    else
                        maxx = 0;
                    end
                    app.ImagePreview = zeros(sz(1)+maxy-miny,sz(2)+ maxx-minx,sz(3));
                    D = padarray(app.ImageTarget,[maxy maxx],0,'pre');
                    D = padarray(D,[-miny -minx],0,'post');
                    for i = 1:loadn
                        app.ImagePreview(:,:,i) = imtranslate(D(:,:,i), [-x(i), -y(i)] );
                    end

                case 'Crop'
                    app.ImagePreview = zeros(sz(1),sz(2), sz(3));
                    for i = 1:loadn
                        app.ImagePreview(:,:,i) = imtranslate(app.ImageTarget(:,:,i), [-x(i), -y(i)] );
                    end

            end
            app.img_locs(:,1) = x;
            app.img_locs(:,2) = y;
            app.filter_already = 1;
            app.PreviewonoffButton.Value =1;
            previewOnOff(app)
            app.Mask.Mask = [];
            updatePlot(app,'normal')
            finished(app)
writeLog(app, "Video alignment is complete. Click ACCEPT PREVIEW to confirm")
        end

        % Value changed function: PeaksRegionsSwitch
        function PeaksRegionsSwitchValueChanged(app, event)

            switch app.PeaksRegionsSwitch.Value
                case 'ROI'
                    app.Peaks_minSep.Visible = "off";     app.MinseparationLabel.Visible = 'off';
                    app.Peaks_minHeight.Visible = "off";  app.MinheightLabel.Visible = 'off'; app.AutoProfileButton.Visible = 'off';
                    app.Min_CCR_Edit.Value = 0.5;
                    app.TabGroup2.SelectedTab = app.ReferenceTab;
                    app.PreviewpeaksLabel.Visible=0;
                    app.PreviewPeaks.Visible=0;
                    app.PreviewPeaks.Value=0;
                    app.FilterCCREditField_Detect.Visible = 1;
                    app.FilterxcorimageLabel.Visible =1;
                    app.BoxSizeEditField.Visible = 0;
                    app.BoxSizeEditFieldLabel.Visible = 0;
                    app.PreviewPeaks.Value=0;
                    app.Peaks_minHeight.Value = -10;

                case 'Peaks'
                    app.Peaks_minSep.Visible = "on";  app.MinseparationLabel.Visible = 'on';
                    app.Peaks_minHeight.Visible = "on";  app.MinheightLabel.Visible = 'on'; app.AutoProfileButton.Visible = 'on';
                    app.Min_CCR_Edit.Value = -1;
                    app.Peaks_minHeight.Visible = 1;
                    app.PreviewpeaksLabel.Visible=1;
                    app.PreviewPeaks.Visible=1;
                    app.PreviewPeaks.Value=1;
                    app.FilterCCREditField_Detect.Visible = 0;
                    app.FilterxcorimageLabel.Visible =0;
                    app.BoxSizeEditField.Visible = 1;
                    app.BoxSizeEditFieldLabel.Visible = 1;
                    app.Peaks_minHeight.Value = 1;
                    updatePlot(app,'normal')

            end
        end

        % Button pushed function: RunTrackingButton
        function RunTrackingButtonPushed(app, event)
            loading(app)
            tracking = track_particles(app.Part.Locs(app.Part.IncludeImages,1:2), app.Part.Locs(app.Part.IncludeImages,5),app.MaxstepEditField.Value, app.MaxmissingEditField.Value);
            counter=0;

            for i = 1:numel(app.Part.Locs(:,1))
                if ~isnan(app.Part.Locs(i,6))
                    counter=counter+1;
                    app.Part.Locs(i,7)= tracking(counter);
                else
                    app.Part.Locs(i,7)  = NaN;
                end
            end
            updatePlot(app,'normal')
            app.TrackLengthEditField.Value = 20;
            app.OverlayValues.Value = 'Track Number';
            finished(app)
            updateTrackList(app)
            storedata(app)
            savedata(app,app.Part.Locs,'Part Locs')
        end

        % Button pushed function: CenterReferenceButton
        function CenterReferenceButtonPushed(app, event)

            center_translation = FindCenterPositions(app.symmetry.Value,  app.ref.image, 10);
            app.ref.image = imtranslate(app.ref.image, -center_translation);

            switch cell2mat(app.RefListBox.Value)
                case 'ROI Reference'
                    app.ref.ROIimage =  app.ref.image;
                case 'Simulated Reference'
                    app.ref.simImage =  app.ref.image;
                case 'LAFM Reference'
                    app.ref.image = app.ref.LAFM;
                case 'Averaged Reference'
                    app.ref.av =  app.ref.image;
            end

            updatePlot2_type(app, 'image')
            updateRightPlot(app);

        end

        % Button pushed function: RunFineAlignButton
        function RunFineAlignButtonPushed(app, event)
            app.CancelButton.Visible = 1;
            loading(app)
            app.Part.FineAlign = [];
            app.ImagePreview=[];

            app.Part.Locs_temp = app.Part.Locs;
            inputPart.Image = app.Part.Image(:,:,app.Part.IncludeImages);
            inputPart.Locs = app.Part.Locs(app.Part.IncludeImages,:);
            [outPart, app.ref.Image] = align_iterate(app.ImageTarget, app.ref.image, inputPart, app.Tran_IterationsEditField.Value, app.Translat_Method.Value,...
                app.MaxdriftEditField_2.Value, app.Rot_IterationsEditField.Value, app.Rota_Method.Value,...
                app.searchangleEditField.Value, app.ThresholdwithminheightCheckBox.Value,...
                app.AutoupdatereferenceimageCheckBox.Value, app);
           
            app.Part.Image(:,:,app.Part.IncludeImages) = outPart.Image;
            app.Part.Locs(app.Part.IncludeImages,:) = outPart.Locs;


            details = strings(0,1);
            if app.Tran_IterationsEditField.Value > 0
                details(end+1) = ...
                    "Trans Iterations=" + app.Tran_IterationsEditField.Value + ...
                    " (" + app.Translat_Method.Value + ")";
                details(end+1) = ...
                    "MaxDrift=" + app.MaxdriftEditField_2.Value;
            end
            if app.Rot_IterationsEditField.Value > 0
                details(end+1) = ...
                    "Rotation Iterations=" + app.Rot_IterationsEditField.Value + ...
                    " (" + app.Rota_Method.Value + ")";
                details(end+1) = ...
                    "SearchAngle=" + app.searchangleEditField.Value;
            end
            details(end+1) = ...
                "MinHeight=" + app.ThresholdwithminheightCheckBox.Value;

            details(end+1) = ...
                "AutoRef=" + app.AutoupdatereferenceimageCheckBox.Value;

            msg = "Alignment run: " + join(details, ", ");
            writeLog(app, msg, 'image');

            updatePlot(app,'normal')
            updatePlot2(app,'normal')
            finished(app)
            storedata(app)
            savedata(app,app.Part.Locs,'Part Locs')
            app.CancelButton.Visible = 0;
        end

        % Button down function: FineAlignTab
        function FineAlignTabButtonDown(app, event)
            if isfield(app.ImageInfo,'Channel')
                
            app.ViewMask.Value=0;
            app.OverlayDropDown.Value = 'None';

            set(app.UIAxes,'xlimMode', 'auto','ylimMode', 'auto')
            ViewMaskChanged(app)

            app.PreviewonoffButton.Value=0;
            previewOnOff(app)
            app.LoczImagepeaksCheckBox.Value =0;
            app.MarkersCheckBox.Value = 0;
            app.BoxesCheckBox.Value = 0;
            app.OverlayValues.Value = 'None';
            app.TrackLengthEditField.Value = 0;
            if isfield(app.Part,'IncludeImages')
            else
                sz = size(app.ImageTarget);
                if numel(sz)>2
                    app.Part.Locs(1:sz(3),1) = sz(2)/2+1;
                    app.Part.Locs(1:sz(3),2) = sz(1)/2+1;
                    app.Part.Locs(:,4) = ones(numel(app.Part.Locs(:,1)),1);
                    app.Part.Locs(1:sz(3),5) = 1:sz(3);
                    app.Part.Locs(1:sz(3),8) = 0;

                    app.ref.position(3) = sz(2);
                    app.ref.position(4) = sz(1);

                    app.Part.IncludeImages = app.Part.Locs(:,4) >0;
                    app.Part.Image  =  app.ImageTarget;
                end
            end


            if isfield(app.ref,'image')
            else
                if isfield(app.Part,'IncludeImages')
                    app.ref.image = app.Part.Image(:,:,round(app.FrameSlider.Value));
                    app.ref.frame = app.FrameSlider.Value;
                end
            end
            if isfield(app.Part,'IncludeImages')
                app.FramePartSwitch.Value = 'Particles';

                if sum(strcmp(app.RightPlotList.Items,'Ref Image')) <1
                    app.RightPlotList.Items = [app.RightPlotList.Items , {'Ref Image'}];
                end
                app.RightPlotList.Value='Ref Image';
                app.TabGroup2.SelectedTab = app.ReferenceTab;

            end
            updateVideoTools(app)

            updatePlot2(app,'normal')
            updatePlot(app,'normal')
            end 
        end

        % Button pushed function: FindallpeaksButton
        function FindallpeaksButtonPushed(app, event)
            app.LAFM.Locs = [];
            app.CancelButton.Visible = 1;
            loading(app)
            pause(0.000001)

            switch  app.FramePartSwitch.Value
                case 'Frames'
                    img = app.ImageTarget;
                    frames = 1:numel(img(1,1,:));
                case 'Particles'
                    img = app.Part.Image(:,:,app.Part.IncludeImages);
                    frames = app.Part.Locs(app.Part.IncludeImages,5);
                    ccr = app.Part.Locs(app.Part.IncludeImages,4);
            end
            n = numel(img(1,1,:));

            writeLog(app, 'Preparing movie');
            if app.BoostsubpixCheckBox.Value
                img =  imresize(img, 3, 'bicubic');
                ex=3;
                if ismember(app.LoczHighpassDropDown.Value, {'Deconv', '-Gaussian', 'Option4'})
                    ex2 = 3;
                else
                    ex2 = 1;
                end
            else
                ex = 1;
                ex2 = 1;
            end

            prev = filter_movie(img, app.LoczLowpassDropDown.Value, app.LoczLowpass.Value*ex, app.LoczHighpassDropDown.Value,  app.LoczHighpass.Value*ex2);
writeLog(app, 'Finding peaks...');
            updateInterval = 5;
            cla(app.loadbar)
            stepsPerUpdate = ceil(n * (updateInterval / 100));
            rectangle(app.loadbar, 'Position', [0, 0, 1, 1], 'FaceColor', 'none', 'EdgeColor', 'black');
            progressBar = rectangle(app.loadbar, 'Position', [0, 0, 0, 1], 'FaceColor', [0.30,0.98,0.40]);
            progressText = text(app.loadbar, 0.5, 0.5, '0%', ...
                'HorizontalAlignment', 'center', ...
                'VerticalAlignment', 'middle', ...
                'Color', 'k');

            for i = 1:numel(img(1,1,:))
                if app.stop == 1
                    return
                end
                locs = Fast_peaks2D( prev(:,:,i),app.LoczHeightSpinner.Value,app.LoczMinSep.Value,app.LoczProminenceSpinner.Value);
                s = any(locs);
                if s(1)==0
                else
                    try
                        locs_full = [locs, i*ones(numel(locs(:,1)),1), frames(i)*ones(numel(locs(:,1)),1), app.ImageInfo.time(frames(i))*ones(numel(locs(:,1)),1), ccr(i)*ones(numel(locs(:,1)),1)];
                    catch
                        locs_full = [locs, i*ones(numel(locs(:,1)),1), frames(i)*ones(numel(locs(:,1)),1), (frames(i))*ones(numel(locs(:,1)),1), (frames(i))*ones(numel(locs(:,1)),1)];
                    end
                    app.LAFM.Locs = [app.LAFM.Locs; locs_full];
                end

                if mod(i, stepsPerUpdate) == 0 || i == n
                    progressBar.Position(3) = i / n;
                    s = any(app.LAFM.Locs);
                    if s(1)==0
                        progressText.String = sprintf([0,' peaks detected']);
                    else
                        progressText.String = sprintf([num2str(numel(app.LAFM.Locs(:,1))),' peaks detected']);
                    end
                    drawnow;
                end
            end
writeLog(app, progressText.String,'image');

            finished(app)
            loading(app)
            pause(0.000001)

            if app.ImageInfo.PixelPerNm(1) ==0
                app.ImageInfo.PixelPerNm(1) =1;
            else
                pixpernm = median(app.ImageInfo.PixelPerNm);
            end

            app.LAFM.Locs  = localize(prev, app.LAFM.Locs,app.LocalizationMethodDropDown.Value,pixpernm/10, app);
            app.LAFM.IncludeLocs = ones(numel(app.LAFM.Locs(:,3)),1);
            app.LAFM.IncludeLocs = app.LAFM.IncludeLocs >0;
            sz = size(img);
            x = round(app.LAFM.Locs(:, 1));
            y = round(app.LAFM.Locs(:, 2));
            for i = 1:numel(app.LAFM.Locs(:,1))
                if x(i)>0 && x(i)<sz(2) && y(i) >0 && y(i)<sz(1)
                    app.LAFM.Locs(i,3) = img(y(i), x(i),app.LAFM.Locs(i, 5));
                end
            end

            if app.BoostsubpixCheckBox.Value
                app.LAFM.Locs(:,1:2) =  app.LAFM.Locs(:,1:2)/3;
            end

            if sum(strcmp(app.RightPlotList.Items,'Localizations')) <1
                app.RightPlotList.Items = [app.RightPlotList.Items , {'Localizations'}];
            end

            updateLAFMHist(app)

            app.RightPlotList.Value = 'Localizations';
            RightPlotListValueChanged(app, event)
            finished(app)
            updateHist(app,'normal')
            app.CancelButton.Visible = 0;
            storedata(app)

            savedata(app,app.LAFM.Locs,'LAFM Locs')
        end

        % Value changed function: LoczImagepeaksCheckBox
        function LoczImagepeaksCheckBoxValueChanged(app, event)
            app.OverlayDropDown.Value = 'Localizations';
            updatePlot(app,'normal')
        end

        % Button pushed function: ExportButton
        function ExportButtonPushed(app, event)

            switch app.ExportSelect.Value
                case 'data'
                    switch app.ExportFileFormat.Value
                        case 'Matlab workspace'
                            assignin('base','ImageTarget', app.ImageTarget);
                            assignin('base','ImagePreview', app.ImagePreview);
                            assignin('base','ImageInfo', app.ImageInfo);
                            assignin('base','ref', app.ref);
                            assignin('base','img_locs', app.img_locs);
                            assignin('base','Part', app.Part);
                            assignin('base','ROI',app.ROI);
                            assignin('base','LAFM',app.LAFM);
                            assignin('base','Mask',app.Mask);
                    end
                case 'Plot 1'
                    switch app.ExportFileFormat.Value
                        % case '.tiffs for ML'
                        %     Out{1} = app.ImageTarget;
                        %     maskdata = app.Mask.Mask;
                        %     maskdata(isnan(maskdata)) = 0;
                        %     Out{2} = maskdata;
                        %     Out{3} = app.ImageSource;
                        %     location = app.Settings.Folder.Output;
                        % 
                        %     for i = 1:numel(Out{1}(1,1,:))
                        % 
                        %         namesuggest = {app.ImageName};
                        %         sourcefilename = sprintf('%s_%d.tiff', namesuggest{1}, i);
                        %         levelfilename = sprintf('%s_%d_levelled.tiff', namesuggest{1}, i);
                        %         maskfilename = sprintf('%s_%d_Mask.csv', namesuggest{1}, i);
                        % 
                        %         tiff_exporter(Out{3}(:,:,i),fullfile(location, sourcefilename));
                        %         tiff_exporter(Out{1}(:,:,i),fullfile(location, levelfilename));
                        %         writematrix(maskdata(:,:,i), fullfile(location, maskfilename));
                        % 
                        %     end

                        case '.tiff'
                            switch app.FramePartSwitch.Value
                                case 'Frames'
                                    Out{1} = app.ImageTarget;
                                case 'Particles'
                                    Out{1} = app.Part.Image(:,:,app.Part.IncludeImages);
                            end

                            namesplit = split(app.ImageName,'.');
                            namesuggest = join(namesplit(1:end),"_")+".tiff";
                            [outputFileName,folder] = uiputfile(namesuggest);
                            fullFileName = fullfile(folder, outputFileName);
                            tiff_exporter(Out{1},fullFileName)
                        case {'.gif','.avi'}
                            if strcmp(app.PlotTypeDropDown.Value,'3D surface')
                                [app.Settings.viewangle(1), app.Settings.viewangle(2)]= view(app.UIAxes);
                            end
                            updatePlot(app,'export video',app.ExportFileFormat.Value)
                        case {'.png','.jpeg','.pdf','.eps'}
                            if strcmp(app.PlotTypeDropDown.Value,'3D surface')
                                [app.Settings.viewangle(1), app.Settings.viewangle(2)]= view(app.UIAxes);
                            end
                            updatePlot(app,'export frame',app.ExportFileFormat.Value)
                        case {'.txt', '.xlsx', '.csv'}
                            namesplit = split(app.ImageName,'.');
                            namesuggest = join(namesplit,"_");
                            [outputFileName,folder] = uiputfile([namesuggest{1},app.ExportFileFormat.Value;]);
                            fullFileName = fullfile(folder, outputFileName);
                            switch app.FramePartSwitch.Value
                                case 'Frames'
                                    Out{1} = app.ImageTarget;
                                case 'Particles'
                                    Out{1} = app.Part.Image(:,:,app.Part.IncludeImages);
                            end
                            N = numel(Out{1}(1,1,:));
                            img = Out{1};
                            Out = cell(1,N);
                            for k = 1:N
                                Out{k}=img(:,:,k);
                            end

                            switch app.ExportFileFormat.Value
                                case {'.txt','.csv'}
                                    for jj=1:numel(Out)
                                        if jj==1
                                            writematrix(Out{1},fullFileName);
                                        else
                                            writematrix(Out{jj},fullFileName,'WriteMode','append');
                                        end
                                    end

                                case '.xlsx'

                                    for jj=1:numel(Out)
                                        if jj==1
                                            writematrix(Out{1},fullFileName);
                                        else
                                            writematrix(Out{jj},fullFileName,'Sheet',jj,'WriteMode','append');
                                        end
                                    end
                            end
                    end
                case 'Plot 2'

                    switch app.RightPlotList.Value
                        case 'Line Profile'
                            imagetype = 'plot';
                            for jj = 1:numel(app.ROI.Line)
                                Out{jj} = [app.ROI.Line(jj).Profile(:,1),app.ROI.Line(jj).Profile(:,2)];
                            end
                        case 'Ref Image'
                            imagetype = 'image';
                            Out{1} = app.ref.image;
                        case 'Localizations'
                            imagetype = 'plot';
                            Out{1} = app.LAFM.Locs;
                        case 'LAFM'
                            imagetype = 'image';
                            Out{1} = app.LAFM.Image;
                        case 'LAFM sym'
                            imagetype = 'image';
                            Out{1} = app.LAFM.Image_sym;
                        case 'LAFM Movie'
                            imagetype = 'image';
                            if strcmp(app.LAFMView.Value,'Probability')
                                for ii = 1:numel(app.LAFM.Movie)
                                    Out{1}(:,:,ii) = (app.LAFM.Movie{ii});
                                end
                            else
                                for ii = 1:numel(app.LAFM.Movie)
                                    Out{1}(:,:,ii) = rgb2gray(app.LAFM.Movie{ii});
                                end
                            end
                        case 'LAFM sym Movie'
                            imagetype = 'image';
                            if strcmp(app.LAFMView.Value,'Probability')
                                for ii = 1:numel(app.LAFM.Movie_sym)
                                    Out{1}(:,:,ii) = (app.LAFM.Movie_sym{ii});
                                end
                            else
                                for ii = 1:numel(app.LAFM.Movie_sym)
                                    Out{1}(:,:,ii) = rgb2gray(app.LAFM.Movie_sym{ii});
                                end
                            end
                        case 'x & y Align'
                            imagetype = 'plot';
                            Out{1} = app.img_locs;
                        case 'Time Profile'
                            imagetype = 'plot';
                            for jj = 1:numel(app.ROI.AreaAverage(:))
                                Out{jj} = [app.ROI.AreaAverage(1,jj),app.ROI.AreaAverage(2,jj)];
                            end
                        case 'Hist'
                            imagetype = 'plot';
                        case 'Kymograph'
                            imagetype = 'image';
                            Out{1} = app.ROI.Line(1).kymo;
                    end


                    switch app.ExportFileFormat.Value
                        case '.tiff'
                            namesplit = split(app.ImageName,'.');
                            namesuggest = join(namesplit(1:end),"_")+".tiff";
                            [outputFileName,folder] = uiputfile(namesuggest);
                            fullFileName = fullfile(folder, outputFileName);
                            tiff_exporter(Out{1},fullFileName)
                        case {'.gif','.avi'}
                            updatePlot2(app,'export video',app.ExportFileFormat.Value,imagetype)
                        case {'.png','.jpeg','.pdf','.eps'}
                            updatePlot2(app,'export frame',app.ExportFileFormat.Value,imagetype)
                        case {'.txt', '.xlsx', '.csv'}
                            namesplit = split(app.ImageName,'.');
                            namesuggest = join(namesplit,"_");
                            [outputFileName,folder] = uiputfile([namesuggest{1},app.ExportFileFormat.Value;]);
                            fullFileName = fullfile(folder, outputFileName);
                            switch app.ExportFileFormat.Value
                                case {'.txt','.csv'}
                                    for jj=1:numel(Out)
                                        if jj==1
                                            writematrix(Out{1},fullFileName);
                                        else
                                            writematrix(Out{jj},fullFileName,'WriteMode','append');
                                        end
                                    end

                                case '.xlsx'

                                    for jj=1:numel(Out)
                                        if jj==1
                                            writematrix(Out{1},fullFileName);
                                        else
                                            writematrix(Out{jj},fullFileName,'Sheet',jj,'WriteMode','append');
                                        end
                                    end
                            end
                    end
            end


        end

        % Button pushed function: FindParticlesButton, 
        % ...and 1 other component
        function FindParticlesButtonPushed(app, event)
            app.CancelButton.Visible = 1;
            loading(app)
            app.Part.Image = [];
            app.Part.Locs = nan;
            filt_img = app.FilterImgEditField_Detect.Value;
            filt_ccr = app.FilterCCREditField_Detect.Value;

            switch app.PeaksRegionsSwitch.Value
                case 'ROI'
                    method = 'ccr';
                    if isfield(app.ref,'image')
                        refimg = app.ref.image;
                        if app.RotationalFreedom.Value
                            angles = -str2num(app.anglesEditField.Value);
                        else
                            angles = 0;
                        end
                        [app.Part.Locs] = Detector(app.ImageTarget, method, refimg, filt_img, filt_ccr, app.Min_CCR_Edit.Value, app.ExcludeEdgeCheckBox.Value,...
                            app.FastFindCheckBox.Value, angles, app);

                        if isnan(app.Part.Locs)
                            progressText.String = sprintf('0 particles detected');
                            writeLog(app, progressText.String);
                        else

                            %construct Particle image stack:
                            app.Part.IncludeImages = ones(numel(app.Part.Locs(:,3)),1);
                            app.Part.Image = refimg;
                            app.Part.Image  = ConstructParticleStack(app.ImageTarget, app.Part, 1, app);
                            app.BoxesCheckBox.Value=1;
                            app.OverlayDropDown.Value = 'Detections';
                            updatePlot(app,'normal')
                            updatePlot2(app,'normal')

                            app.Part.z_high_thresh = max(app.Part.Locs(:,3));
                            app.Part.z_low_thresh = app.Peaks_minHeight.Value;
                            app.Part.ccr_high_thresh = 1;
                            app.Part.ccr_low_thresh = min(app.Part.Locs(:,4));
                            filter_Partlocs(app)
                            storedata(app)
                            savedata(app,app.Part.Locs,'Part Locs')
                        end
                        finished(app)
                        app.CancelButton.Visible = 0;

                        if sum(strcmp(app.HistogramDropDown.Items,'Particle Correlation')) <1
                            app.HistogramDropDown.Items = [app.HistogramDropDown.Items , {'Particle Correlation'}];
                        end
                        if sum(strcmp(app.HistogramDropDown.Items,'Particle z')) <1
                            app.HistogramDropDown.Items = [app.HistogramDropDown.Items , {'Particle z'}];
                        end
                        app.HistogramDropDown.Value = 'Particle Correlation';
                    else
                        msgbox('Select an image reference by drawing an ROI');
                        return;
                    end

                case 'Peaks' % same method as above for multiref needs to be implimented
                    app.PreviewPeaks.Value =0;
                    app.ref.position(3) = app.BoxSizeEditField.Value;
                    app.ref.position(4) = app.ref.position(3);
                    refimg = [];
                    method = 'Peak picker';
                    [app.Part.Locs] = Detector(app.ImageTarget, method, app.Peaks_minSep.Value , filt_img, filt_ccr, app.Peaks_minHeight.Value, app.ExcludeEdgeCheckBox.Value,0,0,app);
                                      
                    directions = [app.LineHor.Value, app.LineVert.Value, app.LineDiag1.Value, app.LineDiag2.Value];
                    
                    app.Part.z_high_thresh = max(app.Part.Locs(:,3));
                    app.Part.z_low_thresh = app.Peaks_minHeight.Value;
                    app.Part.ccr_high_thresh = 1;
                    app.Part.ccr_low_thresh = min(app.Part.Locs(:,4));

                    if app.AutoProfileButton.Value
                        app.Part.Locs(:,9:11) = zeros(numel(app.Part.Locs(:,1)),3);
                        sz = size(app.ImageTarget);
                        if numel(sz)>2

                            for i = 1:sz(3)
                                if numel(app.ImageInfo.ScanSize)>1
                                    fr= i;
                                else
                                    fr=1;
                                end
                                pos =  app.Part.Locs(:,5)==i;
                                r = app.MaxradiusEditField.Value.*app.ImageInfo.PixelPerNm(fr);
                                [app.Part.Locs(pos,9), app.Part.Locs(pos,10), app.Part.Locs(pos,11), p] = Lineprofiler(app.ImageTarget(:,:,i), round(app.Part.Locs(pos,1:2)), r/2, directions, app.FWHMreferenceDropDown.Value);
                                app.Part.Locs(pos,9:11) = app.Part.Locs(pos,9:11)./app.ImageInfo.PixelPerNm(fr);
                            end

                        else
                            r = app.MaxradiusEditField.Value.*app.ImageInfo.PixelPerNm;
                            [app.Part.Locs(:,9), app.Part.Locs(:,10), app.Part.Locs(:,11), p] = Lineprofiler(app.ImageTarget, round(app.Part.Locs(:,1:2)), r/2,directions,app.FWHMreferenceDropDown.Value);
                            app.Part.Locs(:,9:11) = app.Part.Locs(:,9:11)./app.ImageInfo.PixelPerNm;
                        end

                        if directions(1)
                            app.ROI.Line=[];
                            for jj=1:length(p)
                                app.ROI.Line(jj).Position(:,1) = p{jj,1}(:,1);
                                app.ROI.Line(jj).Position(:,2) =  ones(numel(p{jj,1}(:,1)),1)*app.Part.Locs(jj,2);
                            end
                        end

                    end
       
                    if isnan(app.Part.Locs)
                        progressText.String = sprintf('0 particles detected');
                        writeLog(app, progressText.String);
                    else
                        %construct Particle image stack:
                        app.Part.IncludeImages = ones(numel(app.Part.Locs(:,3)),1);
                        app.Part.Image = zeros(app.BoxSizeEditField.Value,app.BoxSizeEditField.Value);
                        app.Part.Image  = ConstructParticleStack(app.ImageTarget, app.Part, 1, app);
                        switch app.PeaksRegionsSwitch.Value
                            case 'Peaks'
                                app.ref.av  = mean(app.Part.Image,3);
                                for i = 1:size(app.Part.Image,3)
                                    app.Part.Locs(i,4) = corr2(app.ref.av,app.Part.Image(:,:,i));
                                end
                        end
                        app.BoxesCheckBox.Value=1;
                        app.OverlayDropDown.Value = 'Detections';
                        if app.AutoProfileButton.Value
                            app.BoxesCheckBox.Value=0;
                            if app.OverlaylinesCheckBox.Value
                                app.OverlayDropDown.Value ='Line Profile';
                            else
                            end
                        end
                       
                        if sum(strcmp(app.HistogramDropDown.Items,'Particle Correlation')) <1
                            app.HistogramDropDown.Items = [app.HistogramDropDown.Items , {'Particle Correlation'}];
                        end
                        if sum(strcmp(app.HistogramDropDown.Items,'Particle z')) <1
                            app.HistogramDropDown.Items = [app.HistogramDropDown.Items , {'Particle z'}];
                        end
                        app.HistogramDropDown.Value = 'Particle z';
                        if sum(strcmp(app.RightPlotList.Items,'Line Profile')) <1
                            app.RightPlotList.Items = [app.RightPlotList.Items , {'Line Profile'}];
                        end
                        app.RightPlotList.Value = 'Line Profile';
                        updatePlot2_type(app, 'plot');
                        updatePlot(app,'normal')
                        updatePlot2(app,'normal')
                    end
                    filter_Partlocs(app)
                    finished(app)
                    storedata(app)
                    savedata(app,app.Part.Locs,'Part Locs')
                    app.CancelButton.Visible = 0;
            end
            updateHist(app,'normal')
        end

        % Value changed function: FramePartSwitch
        function FramePartSwitchValueChanged(app, event)
            if isfield(app.Part,'IncludeImages')

                app.Spinner.Value=1;
                app.FrameSlider.Value =1;
                updateVideoTools(app)
                app.MarkersCheckBox.Value = 0;
                app.BoxesCheckBox.Value = 0;
                app.OverlayValues.Value = 'None';
                app.TrackLengthEditField.Value = 0;
                if strcmp(app.FramePartSwitch.Value,'Particles')
                    filter_Partlocs(app)
                else
                end
                app.ViewMask.Value=0;
                ViewMaskChanged(app)
                app.OverlayDropDown.Value = 'None';
                updatePlot(app,'normal')
                set(app.UIAxes,'xlimMode', 'auto','ylimMode', 'auto')
               
            else
                app.FramePartSwitch.Value = 'Frames';
                updateVideoTools(app)
            end

        end

        % Value changed function: Peaks_minHeight
        function Peaks_minHeightValueChanged(app, event)

            updatePlot(app,'normal')
        end

        % Window button down function: NanoLocz
        function NanoLoczWindowButtonDown(app, event)
            
            hit = hittest(app.NanoLocz);
            fig = ancestor(hit, 'figure');
            props.WindowButtonMotionFcn = get(fig, 'WindowButtonMotionFcn');
            props.WindowButtonUpFcn = get(fig, 'WindowButtonUpFcn');
            ax =app.UIAxes;
            k=0;

            parentAxes = ancestor(hit, 'axes');
            if parentAxes == app.UIAxes
                ax = app.UIAxes;
            elseif parentAxes == app.UIAxes_2
                ax = app.UIAxes_2;
            elseif parentAxes == app.Hist_Axes
                ax = app.Hist_Axes;

                clickPoint = event.IntersectionPoint(1);
                xLimits = xlim(app.Hist_Axes);
                clickPoint = max(min(clickPoint, xLimits(2)), xLimits(1));
                switch app.HistogramDropDown.Value
                    case {'Mask z (all)', 'Mask z (frame)'}
                        lower = app.Mask.low_thresh;
                        upper = app.Mask.high_thresh;

                    case 'Particle z'
                        lower = app.Part.z_low_thresh;
                        upper = app.Part.z_high_thresh;

                    case 'Particle Correlation'
                        lower = app.Part.ccr_low_thresh;
                        upper = app.Part.ccr_high_thresh;

                    case 'LAFM z'
                        lower = app.LAFM.z_low_thresh;
                        upper = app.LAFM.z_high_thresh;

                    case 'LAFM Particle Correlation'
                         lower = app.LAFM.ccr_low_thresh;
                        upper = app.LAFM.ccr_high_thresh;

                    case 'LAFM peak prominance'
                        lower = app.LAFM.pp_low_thresh;
                        upper = app.LAFM.pp_high_thresh;

                    case 'LAFM Loc error'
                        lower = app.LAFM.err_low_thresh;
                        upper = app.LAFM.err_high_thresh;

                    otherwise
                        warning('Unknown dropdown selection: %s', app.HistogramDropDown.Value);
                        lower = NaN;
                        upper = NaN;
                end

                % Determine which slider is closer
                distMin = abs(clickPoint - max([lower, xLimits(1)]));
                distMax = abs(clickPoint - min([upper, xLimits(2)]));

                if distMin < distMax
                    app.ActiveSlider = 'Min'; % Dragging min slider
                else
                    app.ActiveSlider = 'Max'; % Dragging max slider
                end

                % Enable mouse tracking
                fig = ancestor(app.Hist_Axes, 'figure');
                set(fig, 'WindowButtonMotionFcn', @(src, event) histogramDragCallback(app, src, event));
                set(fig, 'WindowButtonUpFcn', @(src, event) stopDragging(app, src, event));
            end
           
            if isa(hit, 'matlab.graphics.primitive.Rectangle')
                type = 'rectHandle';
                setappdata(fig, 'TestGuiCallbacks', props);
                set(fig, 'WindowButtonMotionFcn', @(src, evt) draw_dragging(app, src, evt, ax,type,hit,k));
                set(fig, 'WindowButtonUpFcn', @(src, evt) draw_finish(app, src, evt, ax,type,k));
                ax.UserData.startPos = [ax.UserData.rectHandle.Position(1),ax.UserData.rectHandle.Position(2)];
                ax.UserData.points = [];
 
            elseif ~isempty(ax)

                if isa(hit, 'matlab.graphics.primitive.Image')
                    switch app.Draw.Value
                        case 'Square'
                            type = 'rectHandle';
                            switch app.DrawTool.Value
                                case {'Profile', 'Average'}
                                    set(app.UIAxes_2,'fontname','Ariel','fontsize', 14,'TickDir','out')
                                    k = numel(app.ROI.Line)+1;
                                case {'ROI','+ Mask','- Mask'}
                                    app.SquareROI = [];
                                    k=1;
                            end
                        case 'Line'
                            type = 'lineHandle';
                            set(app.UIAxes_2,'fontname','Ariel','fontsize', 14,'TickDir','out')
                            k = numel(app.ROI.Line)+1;
                        case 'Point'
                            type = 'markerHandle';
                            k = numel(app.ROI.Point)+1;
                        case 'Circle'
                            type = 'circleHandle';
                            switch app.DrawTool.Value
                                case {'Profile', 'Average'}
                                    set(app.UIAxes_2,'fontname','Ariel','fontsize', 14,'TickDir','out')
                                    k = numel(app.ROI.Line)+1;
                                case {'ROI','+ Mask','- Mask'}
                                    app.SquareROI = [];
                                    k=1;
                            end
                        case 'Freehand'
                            type = 'scribble';
                             switch app.DrawTool.Value
                                case {'Profile', 'Average'}
                                    set(app.UIAxes_2,'fontname','Ariel','fontsize', 14,'TickDir','out')
                                    k = numel(app.ROI.Line)+1;
                                case {'+ Mask','- Mask'}
                                    k=1;
                            end
                        case 'PolyLine'
                            type = 'lineHandle';
                            set(app.UIAxes_2,'fontname','Ariel','fontsize', 14,'TickDir','out')
                            k = numel(app.ROI.Line)+1;
                        case 'Off'
                            return
                    end

                    setappdata(fig, 'TestGuiCallbacks', props);
                    set(fig, 'WindowButtonMotionFcn', @(src, evt) draw_dragging(app, src, evt, ax,type,hit,k));
                    set(fig, 'WindowButtonUpFcn', @(src, evt) draw_finish(app, src, evt, ax,type,k));

                    if isfield(ax.UserData, type)
                        delete(ax.UserData.(type));
                        ax.UserData = rmfield(ax.UserData, type);
                    end
                    ax.UserData.startPos = ax.CurrentPoint(1, 1:2);
                    ax.UserData.points = [];

                end
            end
            
        end

        % Value changed function: Point_roi_Button
        function Point_roi_ButtonValueChanged(app, event)
            TurnOffDrawButtons(app,'app.Point_roi_Button')
            app.Draw.Value = 'Point';
            DrawValueChanged(app)
        end

        % Value changed function: Sq_roi_Button
        function Sq_roi_ButtonValueChanged(app, event)
            TurnOffDrawButtons(app,'app.Sq_roi_Button')
            app.Draw.Value = 'Square';
            DrawValueChanged(app)
        end

        % Value changed function: Circ_roi_Button
        function Circ_roi_ButtonValueChanged(app, event)
            TurnOffDrawButtons(app,'app.Circ_roi_Button')
            app.Draw.Value = 'Circle';
            DrawValueChanged(app)
        end

        % Value changed function: Line_roi_Button
        function Line_roi_ButtonValueChanged(app, event)
            TurnOffDrawButtons(app,'app.Line_roi_Button')
            app.Draw.Value = 'Line';
            app.Line_roi_Button.Value =1;
            DrawValueChanged(app)
            TurnOffToolButtons(app)
        end

        % Callback function: AutoCenterLAFM, LAFMView, LAFM_LUT, 
        % ...and 4 other components
        function RenderLAFMButtonPushed(app, event)
            loading(app)
            pause(0.000001)
            Locs = app.LAFM.Locs(app.LAFM.IncludeLocs,:);
            switch  app.FramePartSwitch.Value
                case 'Frames'
                    img = app.ImageTarget;
                case 'Particles'
                    img = app.Part.Image(:,:,app.Part.IncludeImages);
            end
            color = app_color_map(app.LAFM_LUT.Value, app);

            if strcmp(app.LAFMView.Value,'Probability')
                P = 1;
            else
                P=0;
            end

            if app.ViewSymmetrizedButton.Value && app.LAFM_sym.Value>1
                app.LAFM.Locs_sym = Locs;
                if app.AutoCenterLAFM.Value
                    center_translation = FindCenter_ptCloud(app.LAFM_sym.Value, img, Locs);
                    app.LAFM.Locs_sym (:,1) = Locs(:,1)-center_translation(1);
                    app.LAFM.Locs_sym (:,2) = Locs(:,2)-center_translation(2);
                end
                app.LAFM.Locs_sym = sym_ptCloud(app.LAFM_sym.Value, img, app.LAFM.Locs_sym);
                [app.LAFM.Image_sym, app.LAFM.lims]= LAFM_renderer(app.LAFM.Locs_sym, app.LAFM_gus.Value,  app.LAFM_exp.Value, color, P, [app.LAFMColorMin.Value, app.LAFMColorMax.Value],app.LAFM_ZScaleDropBox.Value);

                if sum(strcmp(app.RightPlotList.Items,'LAFM sym')) <1
                    app.RightPlotList.Items = [app.RightPlotList.Items , {'LAFM sym'}];
                end
                app.RightPlotList.Value = 'LAFM sym';
                if strcmp(app.LAFMView.Value,'Height')
                    app.LAFM.Image_sym = app.LAFM.Image_sym./(mean(app.LAFM.Image_sym,3)+0.8);
                end
                app.LAFM.Image_sym = app.LAFM.Image_sym./max(app.LAFM.Image_sym(:));
                storedata(app)
                savedata(app,app.LAFM.Image_sym,'LAFM sym')
            else

                try
                    [app.LAFM.Image, app.LAFM.lims]= LAFM_renderer(Locs, app.LAFM_gus.Value,  app.LAFM_exp.Value, color, P, [app.LAFMColorMin.Value, app.LAFMColorMax.Value],app.LAFM_ZScaleDropBox.Value);
                catch
                    try
                        app.FramePartSwitch.Value = 'Particles';
                        img = app.Part.Image(:,:,app.Part.IncludeImages);
                        [app.LAFM.Image, app.LAFM.lims] = LAFM_renderer(Locs, app.LAFM_gus.Value,  app.LAFM_exp.Value, color, P, [app.LAFMColorMin.Value, app.LAFMColorMax.Value],app.LAFM_ZScaleDropBox.Value);
                    catch
                        errordlg('Run "Find all peaks" 1st', 'Error');
                        writeLog(app, '---ERROR--- Find all peaks" 1st')
                        app.FramePartSwitch.Value = 'Frames';
                        return
                    end
                end

                if sum(strcmp(app.RightPlotList.Items,'LAFM')) <1
                    app.RightPlotList.Items = [app.RightPlotList.Items , {'LAFM'}];
                end
                app.RightPlotList.Value = 'LAFM';
                if strcmp(app.LAFMView.Value,'Height')
                    app.LAFM.Image = app.LAFM.Image./(mean(app.LAFM.Image,3)+0.8);
                end
                app.LAFM.Image = app.LAFM.Image./max(app.LAFM.Image(:));
                storedata(app)
                savedata(app,app.LAFM.Image,'LAFM')
            end

            RightPlotListValueChanged(app)
            finished(app)

            if app.ViewSymmetrizedButton.Value && app.LAFM_sym.Value >1
                ref_img = app.LAFM.Image_sym;
            else
                ref_img = app.LAFM.Image;
            end
            app.ref.LAFM = imresize(ref_img,[size(img,1),size(img,2)]);
            try app.ref.LAFM = rgb2gray(app.ref.LAFM);
            catch
            end

            if sum(strcmp(app.RefListBox.Items,'LAFM Reference')) <1
                app.RefListBox.Items = [app.RefListBox.Items , {'LAFM Reference'}];
            end
            msg = sprintf("LAFM render values - Render Point size: %g  Expand size: x%g", ...
                app.LAFM_gus.Value, app.LAFM_exp.Value);

            writeLog(app,msg,'image');
            app.LAFMColorMax.Value =  app.LAFM.lims(2);
            app.LAFMColorMin.Value =  app.LAFM.lims(1);

        end

        % Button pushed function: CropButton
        function CropButtonPushed(app, event)
            app.PreviewonoffButton.Value =0;
            previewOnOff(app)

            if isfield(app.SquareROI,'Position')
                switch  app.FramePartSwitch.Value
                    case 'Frames'
                        img = app.ImageTarget;
                        for i = 1:numel(img(1,1,:))
                            img_c(:,:,i) = imcrop(img(:,:,i),app.SquareROI.Position);
                        end
                        app.ImageTarget = img_c;
                    case 'Particles'
                        cx = app.SquareROI.Position(1) + app.SquareROI.Position(3)/2-size(app.Part.Image,2)/2;
                        cy = app.SquareROI.Position(2) + app.SquareROI.Position(4)/2-size(app.Part.Image,1)/2;

                        app.Part.Locs(:,1) =  app.Part.Locs(:,1) +cx;
                        app.Part.Locs(:,2) =  app.Part.Locs(:,2) + cy;
                        app.ref.position(3) =   app.SquareROI.Position(3);
                        app.ref.position(4) =   app.SquareROI.Position(4);
                        app.Part.Image  = ConstructParticleStack(app.ImageTarget, app.Part,0, app);

                end
            end
            app.Mask.Mask = [];
            set(app.UIAxes,'xlimMode', 'auto','ylimMode', 'auto')
            updatePlot(app,'normal')
            updatePlot2(app,'normal')
            storedata(app)
            savedata(app,app.ImageTarget,'Image')
        end

        % Value changed function: Sq_roi_Button_2
        function Sq_roi_Button_2ValueChanged(app, event)
            if app.Sq_roi_Button_2.Value ==1
                app.Sq_roi_Button.Value =1;
                app.Draw.Value = 'Square';
                DrawValueChanged(app)
                app.DrawTool.Value = 'ROI';
                DrawToolValueChanged(app)
                TurnOffDrawButtons(app,'app.Sq_roi_Button')
                app.Sq_roi_Button_2.Value =1;
            else
                app.Sq_roi_Button.Value =0;
            end


        end

        % Callback function: CurrentDirButton, FolderMenu, OpenFolderImage
        function OpenFolderButtonClicked(app, event)
            selection = questdlg('Select a folder or a session file', 'Open', 'Folder', 'Session File', 'Cancel', 'Folder');

            if strcmp(selection, 'Folder')
                try
                    folderPath = uigetdir(app.Settings.Folder.Open);
                    if folderPath == 0  % User pressed Cancel
                        app.Settings.Folder.Open = pwd;  % Default to current directory
                    else
                        app.Settings.Folder.Open = folderPath;
                    end
                catch
                    folderPath = uigetdir();
                    if folderPath == 0  % Handle Cancel again
                        app.Settings.Folder.Open = pwd;
                    else
                        app.Settings.Folder.Open = folderPath;
                    end
                end

                updateFolder(app);
                app.FilesDropDown.Value = 'All files';
                app.OpenFolder.Value = app.Settings.Folder.Open;

            elseif strcmp(selection, 'Session File')
                [filename, pathname] = uigetfile('.mat', 'Select session file');
                if isequal(filename, 0) || isequal(pathname, 0)  % User pressed Cancel
                    return;  % Exit without making changes
                else
                    filepath = fullfile(pathname, filename);
                    loadedData = load(filepath);
                    app.stored = loadedData.Data;
                end

                app.FilesDropDown.Value = 'Processed';

                try
                    app.FilesListBox.Items = {app.stored.ImageName};
                catch
                    app.FilesListBox.Items = {'No files'};
                end

            else
                app.Settings.Folder.Open = pwd;
            end


        end

        % Callback function: Image_2, SaveMenu
        function SaveSessionImageClicked(app, event)
            app.PopupApp.Saving = Saving();
        end

        % Menu selected function: OpenSessionMenu
        function OpenSessionMenuSelected(app, event)
            [filename, pathname] = uigetfile('.mat', 'Load App Data');
            if isequal(filename, 0) || isequal(pathname, 0)
            else
                filepath = fullfile(pathname, filename);
                loadedData = load(filepath);
                app.stored = loadedData.Data;
            end
            updateFolder(app)
        end

        % Button down function: DetectTab
        function DetectTabButtonDown(app, event)

        end

        % Button pushed function: PopAx2
        function PopAx2Pushed(app, event)
            updatePlot2(app,'popout')
        end

        % Button pushed function: PopAx1
        function PopAx1ButtonPushed(app, event)
            updatePlot(app,'popout')
        end

        % Value changed function: PlotTypeDropDown
        function PlotTypeDropDownValueChanged(app, event)

            switch app.PlotTypeDropDown.Value
                case '2D surface'
                    app.UIAxes.Toolbar.Visible = 'off';
                    pan(app.UIAxes,'on')
                    pan(app.UIAxes,'off')
                    rotate3d(app.UIAxes, 'off');
                case '3D surface'
                    app.UIAxes.Toolbar.Visible = 'on';
                    rotate3d(app.UIAxes, 'on');
                case 'Coordinates'
                    app.UIAxes.Toolbar.Visible = 'on';
                    rotate3d(app.UIAxes, 'on');
            end
            updatePlot(app,'normal')
        end

        % Value changed function: ZoomIn
        function ZoomInValueChanged(app, event)
            if app.ZoomIn.Value ==1
                % zoom(app.UIAxes,'on')
                % app.UIAxes.Toolbar.Visible = 'on';

                app.Settings.Zoom = zoom(app.UIAxes);
                app.UIAxes.Toolbar.Visible = 'on';
                app.Settings.Zoom.Enable = 'on';
            else
                % zoom(app.UIAxes,'off')
                % app.UIAxes.Toolbar.Visible = 'off';
                app.Settings.Zoom = zoom(app.UIAxes);
                app.UIAxes.Toolbar.Visible = 'off';
                app.Settings.Zoom.Enable = 'off';
                pan(app.UIAxes,'on')
                pan(app.UIAxes,'off')
            end
            TurnOffDrawButtons(app,'app.ZoomIn')
            TurnOffToolButtons(app)
        end

        % Button pushed function: AlignButton
        function AlignButtonPushed(app, event)
            [app.ROI.Line] = LineAlign(app.ROI.Line,app.AlignLineType.Value);
            updatePlot2(app,'normal')
        end

        % Button pushed function: FitButton
        function FitButtonPushed(app, event)
            switch app.HistogramDropDown.Value
                case 'Mask z (all)'
                    T = app.ImageTarget(:,:,:);
                case 'Mask z (frame)'
                    T = app.ImageTarget(:,:,app.Spinner.Value);
                case 'Particle z'
                    T = app.Part.Locs(:,3);
                case 'Particle Correlation'
                    T = app.Part.Locs(:,4);
            end

            fo = fitoptions('method','NonlinearLeastSquares');
            [hy, x] =  hist(T(:),100);
            gfit  = fit(double(x'),hy','gauss1',fo);

            switch app.HistogramDropDown.Value

                case {'Mask z (all)','Mask z (frame)'}
                    app.Mask.high_thresh = gfit.b1 +1.5*gfit.c1;
                    app.Mask.low_thresh = gfit.b1 -1.5*gfit.c1;
                case  'Particle z'
                    app.Part.z_high_thresh = gfit.b1 +1.5*gfit.c1;
                    app.Part.z_low_thresh = gfit.b1 -1.5*gfit.c1;
                case'Particle Correlation'
                    app.Part.ccr_high_thresh = gfit.b1 +1.5*gfit.c1;
                    app.Part.ccr_low_thresh = gfit.b1 -1.5*gfit.c1;
            end


            delete(app.patches.histup)
            delete(app.patches.histdown)
            app.filter_already =0;
            run_filter(app)
           updateHist(app,'normal')
        end

        % Value changing function: LAFMMaxSlider
        function LAFMMaxSliderValueChanging(app, event)
            app.LAFMMaxSlider.Value = event.Value;
            updatePlot2(app,'normal')
        end

        % Value changing function: LAFMMinSlider
        function LAFMMinSliderValueChanging(app, event)

            app.LAFMMinSlider.Value = event.Value;
            updatePlot2(app,'normal')
        end

        % Button pushed function: ApplyButton
        function ApplyButtonPushed(app, event)
            s = app.RoiareaEditField.Value;
            app.ref.position(3) =  app.ref.position(3) + 2*s;
            app.ref.position(4) =  app.ref.position(4) + 2*s;

            xdiff=  app.ref.position(3)-size(app.Part.Image,2);
            x1= ceil(xdiff/2); x2= floor(xdiff/2);
            if xdiff>0
                app.Part.Image =  padarray( app.Part.Image ,[0, x1],0,'post');
                app.Part.Image =  padarray( app.Part.Image ,[0, x2],0,'pre');
            elseif xdiff<0
                app.Part.Image = app.Part.Image(:, 1-x1:end+x2,:);
            end

            ydiff=  app.ref.position(4)-size(app.Part.Image,1);
            y1= ceil(ydiff/2); y2= floor(ydiff/2);
            if ydiff>0
                app.Part.Image =  padarray( app.Part.Image ,[y1, 0],0,'post');
                app.Part.Image =  padarray( app.Part.Image ,[y2, 0],0,'pre');
            elseif ydiff<0
                app.Part.Image = app.Part.Image(1-y1:end+y2,:,:);
            end


            %  app.ref.ROIimage =  imcrop(img(:,:,round(app.FrameSlider.Value)), [p(1), p(2), p(3)-1, p(4)-1]);
            app.Part.Image  = ConstructParticleStack(app.ImageTarget, app.Part,0, app);
            if s >0
                app.ref.image =  padarray(app.ref.image ,[s, s],0,'both');
            else

                app.ref.image =  app.ref.image(1-s:end+s, 1-s:end+s);
            end
            try
                switch cell2mat(app.RefListBox.Value)
                    case 'ROI Reference'
                        app.ref.ROIimage =  app.ref.image;
                    case 'Simulated Reference'
                        app.ref.simImage =  app.ref.image;
                    case 'LAFM Reference'
                        app.ref.image = app.ref.LAFM;
                    case 'Averaged Reference'
                        app.ref.av =  app.ref.image;
                end
            catch
                app.RefListBox.Value = 'ROI Reference';
                app.ref.ROIimage =  app.ref.image;
            end

            updatePlot(app,'normal')
            updatePlot2(app,'normal')

        end

        % Button pushed function: kymo
        function kymoButtonPushed(app, event)
try
            if numel(app.ROI.Line) >0  && ~isfield(app.ROI.Line,'kymo')
                switch  app.FramePartSwitch.Value
                    case 'Frames'
                        img = app.ImageTarget;
                    case 'Particles'
                        img = app.Part.Image(:,:,app.Part.IncludeImages);
                end

                for i = 1:numel(img(1,1,:))
                    if app.LineThickness.Value>=1
                        ch  = improfile_thick(app.LineThickness.Value/2,app.ROI.Line(1).Position,img(:,:,i),app.Draw.Value);
                    else
                        [~,~,ch] = improfile(img(:,:,i),app.ROI.Line(1).Position(:,1),app.ROI.Line(1).Position(:,2));
                    end
                    app.ROI.Line(1).kymo(:,i) = ch;
                end
            end

            if isfield(app.ROI.Line,'kymo')
                if sum(strcmp(app.RightPlotList.Items,'Kymograph')) <1
                    app.RightPlotList.Items = [app.RightPlotList.Items , {'Kymograph'}];
                end
                app.RightPlotList.Value='Kymograph';
            end

            RightPlotListValueChanged(app, event)
            updatePlot2(app,'normal')
catch
end
        end

        % Button pushed function: CancelButton
        function CancelButtonPushed(app, event)
            app.stop = 1;
            app.CancelButton.Visible = 0;
            finished(app)
        end

        % Value changed function: LAFMScalebarCheckBox, 
        % ...and 1 other component
        function LAFMScalebarCheckBoxValueChanged(app, event)
            updatePlot(app,'normal')
            updatePlot2(app,'normal')
        end

        % Button down function: LevelTab
        function LevelTabButtonDown(app, event)
            try
                app.FramePartSwitch.Value = 'Frames';
                updateVideoTools(app)
                ViewMaskChanged(app)
            catch
            end
        end

        % Value changed function: FilesDropDown
        function FilesDropDownValueChanged(app, event)
            switch app.FilesDropDown.Value
                case 'All files'
                    updateFolder(app)
                case 'Processed'
                    try
                        app.FilesListBox.Items = {app.stored.ImageName};
                    catch
                        app.FilesListBox.Items = {'no files'};
                    end
            end
        end

        % Button pushed function: MontageButton
        function MontageButtonPushed(app, event)

            switch  app.FramePartSwitch.Value
                case 'Frames'
                    img = app.ImageTarget;
                case 'Particles'
                    img = app.Part.Image(:,:,app.Part.IncludeImages);
            end
            img = padarray(img,[2,2],1000,'both');
            aspectRatio =  16/9;
            numFrames = size(img, 3);
            cols = ceil(sqrt(numFrames * aspectRatio));
            rows = ceil(numFrames / cols);
            imageSize = size(img(:, :, 1));
            montageWidth = cols * imageSize(2);
            montageHeight = rows * imageSize(1);
            montageMatrix = zeros(montageHeight, montageWidth);
            currentRow = 1;
            currentCol = 1;
            %  [r, cs, ~] = size(img(:,:,1));
            for i = 1:numFrames
                rowIndices = currentRow : currentRow + imageSize(1) - 1;
                colIndices = currentCol : currentCol + imageSize(2) - 1;

                montageMatrix(rowIndices, colIndices) = img(:, :, i);
                currentCol = currentCol + imageSize(2);

                if currentCol > montageWidth
                    currentCol = 1;
                    currentRow = currentRow + imageSize(1);
                end
            end

            figure('Position', [100, 100, 1.3*800, 1.3*450]);

            try
                imagesc(montageMatrix,clim(app.UIAxes));
            catch
                imagesc(montageMatrix,caxis(app.UIAxes));
            end

            c = app_color_map(app.ColourmapDropDown.Value, app);
            colormap(c);

            axis equal tight off; % Set equal axes and hide axis ticks

        end

        % Value changed function: Hist_excludeOut
        function Hist_excludeOutValueChanged(app, event)

            updateHist(app,'normal')
        end

        % Callback function: ImportSimulationButton, SimulationAFMMenu
        function OpenMat_SimAFMMenuSelected(app, event)
            app.PopupApp.Sim = Mat_SimAFM_v1_6();
        end

        % Button pushed function: RefCropButton
        function RefCropButtonPushed(app, event)
            %       img = hittest(app.NanoLocz);
            ax = app.UIAxes_2;
            app.ref.crop = 1;
            Freehandroi = drawfreehand(ax,'Color','r');
            ax.UserData.roi = Freehandroi;

            if ~isempty(ax.UserData.roi)
                roiPoints = ax.UserData.roi.Position;
                if ~isempty(roiPoints)
                    minX = min(roiPoints(:, 1));
                    maxX = max(roiPoints(:, 1));
                    minY = min(roiPoints(:, 2));
                    maxY = max(roiPoints(:, 2));
                    width = maxX - minX;
                    height = maxY - minY;

                    app.ref.orgImage = app.ref.image;
                    image = app.ref.image;

                    % if ~app.MultiReferenceCheckBox.Value
                    mask = poly2mask(roiPoints(:, 1), roiPoints(:, 2), size(image, 1), size(image, 2));
                    maskImage = bsxfun(@times, image, cast(mask, 'like', image));
                    croppedImage = imcrop(maskImage, [minX, minY, width, height]);
                    switch cell2mat(app.RefListBox.Value)
                        case 'ROI Reference'
                            app.ref.ROIimage = croppedImage;
                        case 'Simulated Reference'
                            app.ref.simImage = croppedImage;
                        case 'LAFM Reference'
                            app.ref.LAFM = croppedImage;
                        case 'Averaged Reference'
                            app.ref.av = croppedImage;
                    end
              
                    app.ref.image = croppedImage;
                    app.ref.position = [minX, minY, size(croppedImage,2), size(croppedImage,1)];
                    updatePlot2_type(app, 'image')
                    updateRightPlot(app);

                    if isfield(app.Part,'Image')
                        app.Part.Image  = ConstructParticleStack(app.ImageTarget, app.Part,0, app);
                    end
                    updatePlot(app,'normal')

                end
            end
            %app.ref = rmfield(app.ref, 'crop');
        end

        % Button pushed function: ResettrimButton
        function ResettrimButtonPushed(app, event)
            % if ~app.MultiReferenceCheckBox.Value
            switch cell2mat(app.RefListBox.Value)
                case 'ROI Reference'
                    app.ref.ROIimage = app.ref.orgImage;
                case 'Simulated Reference'
                    app.ref.simImage = app.ref.orgImage;
                case 'LAFM Reference'
                    app.ref.LAFM = app.ref.orgImage;
                case 'Averaged Reference'
                    app.ref.av = app.ref.orgImage;
            end
            % else
            % app.ref.image = app.ref.orgImage;
            % end

            updatePlot2_type(app, 'image')
            updateRightPlot(app);
        end

        % Value changed function: RefListBox
        function RefListBoxValueChanged(app, event)
            % if app.MultiReferenceCheckBox.Value == 0
            switch cell2mat(app.RefListBox.Value)
                case 'ROI Reference'
                    app.ref.image = app.ref.ROIimage;
                    updatePlot2_type(app, 'image')
                    updateRightPlot(app);
                case 'Simulated Reference'
                    app.ref.image = app.ref.simImage;
                    updatePlot2_type(app, 'image')
                    updateRightPlot(app);
                case 'LAFM Reference'
                    app.ref.image = app.ref.LAFM;
                    updatePlot2_type(app, 'image')
                    updateRightPlot(app);
                case 'Averaged Reference'
                    app.ref.image = app.ref.av;
                    updatePlot2_type(app, 'image')
                    updateRightPlot(app);
            end
            app.ref.position(3) = size(app.ref.image,2);
            app.ref.position(4) = size(app.ref.image,1);
            % end

            xdiff=  app.ref.position(3)-size(app.Part.Image,2);
            x1= ceil(xdiff/2); x2= floor(xdiff/2);
            if xdiff>0
                app.Part.Image =  padarray( app.Part.Image ,[0, x1],0,'post');
                app.Part.Image =  padarray( app.Part.Image ,[0, x2],0,'pre');
            elseif xdiff<0
                app.Part.Image = app.Part.Image(:, 1-x1:end+x2,:);
            end

            ydiff=  app.ref.position(4)-size(app.Part.Image,1);
            y1= ceil(ydiff/2); y2= floor(ydiff/2);
            if ydiff>0
                app.Part.Image =  padarray( app.Part.Image ,[y1, 0],0,'post');
                app.Part.Image =  padarray( app.Part.Image ,[y2, 0],0,'pre');
            elseif ydiff<0
                app.Part.Image = app.Part.Image(1-y1:end+y2,:,:);
            end


        end

        % Value changed function: symmetry
        function symmetryValueChanged(app, event)
            app.symmetry.Value = event.Value;
            app.LAFM_sym.Value = event.Value;
        end

        % Value changed function: RotationalFreedom
        function RotationalFreedomValueChanged(app, event)

            if app.RotationalFreedom.Value
                app.anglesEditField.Visible = 1;
                app.anglesLabel.Visible = 1;
                if app.symmetry.Value>1
                    r = 180./app.symmetry.Value;
                    app.anglesEditField.Value = [num2str(-r), ':10:', num2str(r)];
                end
            else
                app.anglesEditField.Visible = 0;
                app.anglesLabel.Visible = 0;
            end

        end

        % Callback function: AnalyzeMaskButton, MaskAnalysisMenu
        function MaskAreasMenuSelected(app, event)
            app.PopupApp.Mask = MaskTools();
        end

        % Callback function: Plotter, PlotterMenu_2
        function PlotterValueChanged(app, event)
            app.PopupApp.Plot = plotter();
        end

        % Cell edit callback: UITable
        function UITableCellEdit(app, event)
            indices = event.Indices;

            if indices(1) == 2
                app.ImageInfo.ScanSize = event.NewData;
            end

            if indices(1) == 3
                app.ImageInfo.ScanSpeed = event.NewData;
                app.ImageInfo.time = double(0:1/app.ImageInfo.ScanSpeed:(app.ImageInfo.n-1)/app.ImageInfo.ScanSpeed);
                updatePlot(app,'normal')
            end

            if indices(1) == 4
                app.ImageInfo.LineSpeed= event.NewData;
                updatePlot(app,'normal')
            end

            if indices(1) == 5
                sz = size(app.ImageTarget);
                app.ImageInfo.yPixels = round(event.NewData);
                settings.pixelsy = app.ImageInfo.yPixels;
                settings.pixelsx = sz(2);  settings.rotate = 0;  settings.Scaleh = 1;
                settings.method = 'bicubic';
                ReshapeImage(app,settings)
            end

            if indices(1) == 6
                sz = size(app.ImageTarget);
                app.ImageInfo.xPixels = round(event.NewData);
                settings.pixelsx = app.ImageInfo.xPixels;
                settings.pixelsy = sz(1);  settings.rotate = 0;  settings.Scaleh = 1;
                settings.method = 'bicubic';
                ReshapeImage(app,settings)
            end

            if indices(1) ==7
                app.ImageInfo.PixelPerNm = event.NewData;
                updatePlot(app,'normal')
            end

            if indices(1) ==8
                app.ImageInfo.Channel  = event.NewData;
            end

        end

        % Value changed function: BoxesCheckBox, MarkersCheckBox, 
        % ...and 2 other components
        function MarkersCheckBoxValueChanged(app, event)
            app.OverlayDropDown.Value = 'Detections';
            updatePlot(app,'normal')
        end

        % Value changed function: TrackList
        function TrackListValueChanged(app, event)

            value = str2double(app.TrackList.Value);
            app.Spinner.Value = 1;
            app.FrameSlider.Value = 1;
            if value > 0
                filter_Partlocs(app)
                updateVideoTools(app)
                updatePlot(app,'normal')
                updatePlot2(app,'normal')
            end
        end

        % Value changed function: SelectTrackButton
        function SelectTrackButtonValueChanged(app, event)
            % try
            if sum(app.Part.Locs(:,7),"omitnan")>1 && app.SelectTrackButton.Value == 1
                app.SelectTrackButton.Value = 1;
                updateTrackList(app)
                app.TrackList.Visible = app.SelectTrackButton.Value;
                filter_Partlocs(app)
                updateVideoTools(app)
                updatePlot(app,'normal')
                updatePlot2(app,'normal')

            else
                app.SelectTrackButton.Value=0;
                updateTrackList(app)
                app.TrackList.Visible = app.SelectTrackButton.Value;
                filter_Partlocs(app)
                updateVideoTools(app)
                updatePlot(app,'normal')
                updatePlot2(app,'normal')
            end
            %  catch
            %  end
        end

        % Callback function: PreferencesButton, PreferencesIcon, 
        % ...and 1 other component
        function PreferencesButtonPressed(app, event)
            app.PopupApp.Preferences = Preferences();

        end

        % Value changed function: ImgFilterType
        function ImgFilterTypeValueChanged(app, event)
            switch app.ImgFilterType.Value
                case {'Gaussian' ,'High-pass','Non-local mean'}
                    app.ImgFilterStrength.Limits =  [0,Inf];
                    app.ImgFilterStrength.Step =0.1;
                    app.ImgFilterStrength_2.Visible=0;
                    app.ImgFilterLabel.Value = 'Power';
                    app.ImgFilterLabel_2.Visible = 0;
                    app.ImgFilterLabel_3.Visible = 0;
                    app.ImgFilterStrength_3.Visible = 0;
                case {'Median','Mean'}
                    app.ImgFilterStrength.Limits =  [1,Inf];
                    app.ImgFilterStrength.Step =1;
                    app.ImgFilterStrength.Value=1;
                    app.ImgFilterLabel.Value = 'x';

                    app.ImgFilterStrength_2.Limits =  [1,Inf];
                    app.ImgFilterStrength_2.Visible=1;
                    app.ImgFilterStrength_2.Value=3;
                    app.ImgFilterStrength_2.Step =1;

                    app.ImgFilterLabel_2.Visible = 1;
                    app.ImgFilterLabel_2.Value = 'y';
                    app.ImgFilterLabel_3.Visible = 0;
                    app.ImgFilterStrength_3.Visible = 0;
                case 'Top Hat'
                    app.ImgFilterStrength.Limits =  [1,Inf];
                    app.ImgFilterStrength.Step =1;
                    app.ImgFilterStrength_2.Visible=0;
                    app.ImgFilterLabel.Value = 'disk size';
                    app.ImgFilterLabel_2.Visible = 0;
                    app.ImgFilterLabel_3.Visible = 0;
                    app.ImgFilterStrength_3.Visible = 0;
                case 'Sliding Mean Frames'
                    app.ImgFilterStrength.Limits =  [1,Inf];
                    app.ImgFilterStrength.Step =1;
                    app.ImgFilterStrength.Value=3;
                    app.ImgFilterLabel.Value = 'slide window';
                    app.ImgFilterStrength_2.Visible=0;
                    app.ImgFilterLabel_2.Visible = 0;
                case 'Sphere Deconvolution'
                    app.ImgFilterStrength.Limits =  [0,Inf];
                    app.ImgFilterStrength.Step =0.1;
                    app.ImgFilterStrength.Value=0.5;
                    app.ImgFilterLabel.Value = 'Radius';

                    app.ImgFilterStrength_2.Limits =  [1,200];
                    app.ImgFilterStrength_2.Visible=1;
                    app.ImgFilterStrength_2.Value=1;
                    app.ImgFilterStrength_2.Step =1;

                    app.ImgFilterLabel_2.Visible = 1;
                    app.ImgFilterLabel_2.Value = 'Iterations';

                case 'Scar Fill'
                    app.ImgFilterStrength.Limits =  [0,Inf];
                    app.ImgFilterStrength.Step =0.1;
                    app.ImgFilterStrength.Value=0.5;
                    app.ImgFilterLabel.Value = 'Sensitivity';

                    app.ImgFilterStrength_2.Limits =  [-Inf,Inf];
                    app.ImgFilterStrength_2.Visible=1;
                    app.ImgFilterStrength_2.Value=1;
                    app.ImgFilterStrength_2.Step =0.25;

                    app.ImgFilterLabel_2.Visible = 1;
                    app.ImgFilterLabel_2.Value = 'z threshold';

                    app.ImgFilterStrength_3.Limits = [1, Inf];
                    app.ImgFilterStrength_3.Visible=1;
                    app.ImgFilterStrength_3.Value=10;
                    app.ImgFilterStrength_3.Step =2;

                    app.ImgFilterLabel_3.Visible = 1;
                    app.ImgFilterLabel_3.Value = 'Length threshold';
            end

            switch app.ImgFilterType.Value
                case {'Mean All','Median all'}
                    app.PreviewonoffButton.Value =0;
                    previewOnOff(app)
                    app.ImageInfo.n = app.ImageInfo.n+1;

                    if numel(app.ImageInfo.ScanSize)>1
                        try
                            app.ImageInfo.ScanSize(app.ImageInfo.n) =app.ImageInfo.ScanSize(app.ImageInfo.n-1);
                            app.ImageInfo.PixelPerNm(app.ImageInfo.n) =app.ImageInfo.PixelPerNm(app.ImageInfo.n-1);
                            app.ImageInfo.time(app.ImageInfo.n) = max(app.ImageInfo.time)+1;
                        catch

                        end

                    end

                    switch app.ImgFilterType.Value
                        case 'Mean All'
                            app.ImageTarget(:,:,app.ImageInfo.n) = mean(app.ImageTarget,3);
                        case 'Median all'
                            app.ImageTarget(:,:,app.ImageInfo.n) = median(app.ImageTarget,3);
                    end
                    updateVideoTools(app)
                    app.FrameSlider.Value = app.ImageInfo.n;
                    app.Spinner.Value = app.FrameSlider.Value;
                    try
                        app.ImageInfo.time(app.ImageInfo.n) = mean(app.ImageInfo.time);
                    catch
                    end
                    updatePlot(app,'normal')
                    app.ImgFilterType.Value = 'Off';

                case 'Sliding Mean Frames'
                    if app.ImgFilterStrength.Value >1
                        app.ImagePreview = movmean(app.ImageTarget ,app.ImgFilterStrength.Value,3);
                        app.PreviewonoffButton.Value =1;
                        previewOnOff(app)
                        updatePlot(app,'normal')
                    end
                otherwise
                    app.filter_already =0;
                    app.PreviewonoffButton.Value =1;
                    previewOnOff(app)
                    run_filter(app)
            end
        end

        % Value changed function: AutoProfileButton
        function AutoProfileButtonValueChanged(app, event)
            S = app.AutoProfileButton.Value;
            app.OverlaylinesCheckBox.Visible=S;
            app.LineDiag2.Visible=S;
            app.LineDiag1.Visible=S;
            app.LineVert.Visible=S;
            app.LineHor.Visible=S;
            app.MaxradiusEditField.Visible=S;
            app.LinelengthLabel.Visible=S;
            app.FWHMreferenceDropDown.Visible=S;

        end

        % Value changed function: OverlaylinesCheckBox
        function OverlaylinesCheckBoxValueChanged(app, event)
            if app.OverlaylinesCheckBox.Value
                app.OverlayDropDown.Value = 'Line Profile';
            else
                app.OverlayDropDown.Value = 'Detections';
            end
            updatePlot(app,'normal')
        end

        % Value changed function: LAFMColorBarCheckBox
        function LAFMColorBarCheckBoxValueChanged(app, event)

            if app.LAFMColorBarCheckBox.Value
                color = app_color_map(app.LAFM_LUT.Value, app);
                colormap(app.UIAxes_2,color)
                colorbar(app.UIAxes_2,'Color','w','Box','off','TickDirection','out')

                switch app.LAFMView.Value
                    case {'LAFM map','Height'}
                        try
                            clim(app.UIAxes_2,[app.LAFM.lims(1), app.LAFM.lims(2)]);
                        catch
                            caxis(app.UIAxes_2,[app.LAFM.lims(1), app.LAFM.lims(2)]);
                        end
                end

            else
                colorbar(app.UIAxes_2,'off')
            end
        end

        % Button pushed function: RenderLAFMMovieButton
        function RenderLAFMMovieButtonPushed(app, event)
            loading(app)
            pause(0.000001)
%Locs =
            total_parts = max(app.LAFM.Locs(:,5));
            window = app.ParticlespermapEditField.Value;
            slide = app.SlidewindowEditField.Value;
            if slide > 0
                n = round((total_parts - window)/slide)+1;
            else
                n = round(total_parts/window);
            end

            switch  app.FramePartSwitch.Value
                case 'Frames'
                    img = app.ImageTarget;
                case 'Particles'
                    img = app.Part.Image(:,:,app.Part.IncludeImages);
            end
            
            color = app_color_map(app.LAFM_LUT.Value, app);

            if strcmp(app.LAFMView.Value,'Probability')
                P = 1;
            else
                P=0;
            end

            if app.ViewSymmetrizedButton.Value && app.LAFM_sym.Value>1
                app.LAFM.Locs_sym = app.LAFM.Locs;
                if app.AutoCenterLAFM.Value
                    center_translation = FindCenter_ptCloud(app.LAFM_sym.Value, img, app.LAFM.Locs);
                    app.LAFM.Locs_sym (:,1) = app.LAFM.Locs(:,1)-center_translation(1);
                    app.LAFM.Locs_sym (:,2) = app.LAFM.Locs(:,2)-center_translation(2);
                end
                app.LAFM.Locs_sym = sym_ptCloud(app.LAFM_sym.Value, img, app.LAFM.Locs_sym);

                [app.LAFM.Movie_sym, app.LAFM.lims, app.LAFM.time]= LAFM_Movie_renderer(app.LAFM.Locs_sym, app.LAFM_gus.Value,  app.LAFM_exp.Value, color, P, [app.LAFMColorMin.Value, app.LAFMColorMax.Value],app.LAFM_ZScaleDropBox.Value, window, slide);

                for i = 1:n
                    if strcmp(app.LAFMView.Value,'Height')
                        app.LAFM.Movie_sym{i} = app.LAFM.Movie_sym{i}./(mean(app.LAFM.Movie_sym{i},3)+0.8);
                    end
                    app.LAFM.Movie_sym{i} =  app.LAFM.Movie_sym{i}./max( app.LAFM.Movie_sym{i}(:));
                end

                if sum(strcmp(app.RightPlotList.Items,'LAFM sym Movie')) <1
                    app.RightPlotList.Items = [app.RightPlotList.Items , {'LAFM sym Movie'}];
                end
                app.RightPlotList.Value = 'LAFM sym Movie';
                storedata(app)
                savedata(app,app.LAFM.Movie_sym{i},'LAFM Movie sym')
            else

                try
                    [app.LAFM.Movie, app.LAFM.lims, app.LAFM.time]= LAFM_Movie_renderer(app.LAFM.Locs, app.LAFM_gus.Value,  app.LAFM_exp.Value, color,P, [app.LAFMColorMin.Value, app.LAFMColorMax.Value],app.LAFM_ZScaleDropBox.Value, window, slide);
                catch
                    app.FramePartSwitch.Value = 'Particles';
                    [app.LAFM.Movie, app.LAFM.lims, app.LAFM.time] = LAFM_Movie_renderer(app.LAFM.Locs, app.LAFM_gus.Value,  app.LAFM_exp.Value, color, P, [app.LAFMColorMin.Value, app.LAFMColorMax.Value],app.LAFM_ZScaleDropBox.Value, window, slide);
                end
                for i = 1:n
                    if strcmp(app.LAFMView.Value,'Height')
                        app.LAFM.Movie{i} = app.LAFM.Movie{i}./(mean(app.LAFM.Movie{i},3)+0.8);
                    end
                    app.LAFM.Movie{i} = app.LAFM.Movie{i}./max(app.LAFM.Movie{i}(:));
                end

                if sum(strcmp(app.RightPlotList.Items,'LAFM Movie')) <1
                    app.RightPlotList.Items = [app.RightPlotList.Items , {'LAFM Movie'}];
                end
                app.RightPlotList.Value = 'LAFM Movie';
                storedata(app)
                savedata(app,app.LAFM.Movie{i},'LAFM Movie')
            end

            UpdateLAFM_MovieTools(app)
            RightPlotListValueChanged(app, event)
            finished(app)


        end

        % Callback function: LAFMFrameSlider, LAFMFrameSlider, LAFMSpinner,
        % 
        % ...and 1 other component
        function LAFMFrameSliderValueChanging(app, event)
            app.LAFMSpinner.Value = round(event.Value);
            app.LAFMFrameSlider.Value = round(event.Value);
            updatePlot2(app,'normal')
        end

        % Image clicked function: LAFMPlayImage
        function LAFMPlayImageClicked(app, event)
            app.LAFMPlayImage.Visible=0;
            app.LAFMPauseImage.Visible=1;
            app.stop=0;
            currentFrame = round(app.LAFMFrameSlider.Value);

            while true
                tic;
                if app.stop == 1
                    return
                end

                if currentFrame == app.LAFMFrameSlider.Limits(2) + 1
                    currentFrame = 1;
                end
                app.LAFMFrameSlider.Value = currentFrame;
                app.LAFMSpinner.Value = currentFrame;
                updatePlot2(app,'normal')
                playtime = toc;
                pause(1/app.fps.Value-playtime)
                drawnow
                currentFrame = currentFrame + 1;
            end

            app.PauseImage.Visible=0;
            app.PlayImage.Visible=1;
        end

        % Image clicked function: LAFMPauseImage
        function LAFMPauseImageClicked(app, event)
            app.LAFMPlayImage.Visible=1;
            app.LAFMPauseImage.Visible=0;
            app.stop = 1;
        end

        % Image clicked function: LAFMStartImage
        function LAFMStartImageClicked(app, event)
            app.LAFMFrameSlider.Value = 1;
            app.LAFMSpinner.Value = app.LAFMFrameSlider.Value;
            updatePlot2(app,'normal')
        end

        % Image clicked function: LAFMEndImage
        function LAFMEndImageClicked(app, event)
            app.LAFMFrameSlider.Value = app.LAFMFrameSlider.Limits(2);
            app.LAFMSpinner.Value = app.LAFMFrameSlider.Value;
            updatePlot2(app,'normal')
        end

        % Value changed function: InvertMask
        function InvertMaskValueChanged(app, event)
            if app.InvertMask.Value
                app.InvertMask.FontColor = [0.22,0.98,0.26];
                app.InvertMask.BackgroundColor = [0 0 0];
            else
                app.InvertMask.FontColor = [1 0 0];
                app.InvertMask.BackgroundColor = [1 1 1];
            end
            app.filter_already =0;
            app.PreviewonoffButton.Value =1;
            previewOnOff(app)
            run_filter(app)
        end

        % Menu selected function: QuitMenu
        function QuitMenuSelected(app, event)
            delete(app.NanoLocz)

        end

        % Menu selected function: ResizerotateMenu
        function ResizerotateMenuSelected(app, event)
            app.PopupApp.ImageTools = ImageTools();
        end

        % Value changed function: Min_CCR_Edit
        function Min_CCR_EditValueChanged(app, event)
            updateHist(app,'normal')
        end

        % Value changed function: AspectRatioSwitch
        function AspectRatioSwitchValueChanged(app, event)

            if strcmp(app.AspectRatioSwitch.Value, '1:1')
                axis(app.UIAxes,'normal');
                pbaspect(app.UIAxes,[1 1 1])
            else
                axis(app.UIAxes,'tight','equal');
            end
            updatePlot(app,'normal')
        end

        % Value changed function: LAFMColorMax, LAFMColorMin
        function LAFMColorMaxValueChanged(app, event)
            app.LAFM_ZScaleDropBox.Value = 'Manual';
            if app.LAFMColorMax.Value > app.LAFMColorMin.Value
                RenderLAFMButtonPushed(app, event)
            else
                app.LAFMColorMax.Value = app.LAFMColorMin.Value+0.01;
            end
        end

        % Image clicked function: BinRangeImage_2
        function BinRangeImage_2Clicked(app, event)
            app.Part.Image= app.Part.Image(:,:,app.Part.IncludeImages);
            app.Part.Locs = app.Part.Locs(app.Part.IncludeImages,:);
            updateHist(app,'normal')
            app.Part.IncludeImages = app.Part.Locs(:,4) >0;
        end

        % Button pushed function: LoaddemodataButton
        function LoaddemodataButtonPushed(app, event)
            reset_defaults(app)
            LoadstartupImg(app)

            updateHist(app,'normal')
            app.Spinner.Limits = [1 app.ImageInfo.n];
            app.FrameSlider.Limits = [1 app.ImageInfo.n];
            updatePlot(app,'normal')

            updateVideoTools(app)

            field1 = 'ImageName';  value1 = 'Demo';
            field2 = 'Channel';    value2 = 'Demo';
            field3 = 'Imagedata';  value3 = 0;
            field4 = 'ImageInfo';  value4 = app.ImageInfo;
            field5 = 'ImageLocs';  value5 = [];
            field6 = 'ref';        value6 = [];
            field7 = 'LAFMLocs';   value7 = [];
            field8 = 'Mask';   value8 = [];

            app.stored = struct(field1,value1,field2,value2,field3, value3, field4, value4, field5, value5, field6, value6, field7, value7,field8,value8);
            
        end

        % Value changed function: AutoSaveButton
        function AutoSaveButtonValueChanged(app, event)
            app.Lamp.Enable = app.AutoSaveButton.Value;
            app.Settings.ExportSettings.AutoSave = app.AutoSaveButton.Value;
            Settings.ExportSettings.AutoSave = app.AutoSaveButton.Value;
            Settings.Align = app.Settings.Align;
            Settings.label = app.Settings.label;
            Settings.MaskColor = app.Settings.MaskColor;
            Settings.MaskAlpha = app.Settings.MaskAlpha;
            Settings.Folder = app.Settings.Folder;
            Settings.ColourmapDropDown = app.Settings.ColourmapDropDown;
            Settings.ExportSettings = app.Settings.ExportSettings;
            appDir = fileparts(mfilename('fullpath'));
            PrefFile = fullfile(appDir, 'pref.mat');

            save(PrefFile, 'Settings');
        end

        % Button pushed function: OutputFolderButton
        function OutputFolderButtonPushed(app, event)
            app.Settings.Folder.Output = uigetdir(app.Settings.Folder.Open);
            try
                app.OutputFolder.Value = app.Settings.Folder.Output;
            catch
            end
        end

        % Value changed function: OutputFolder
        function OutputFolderValueChanged(app, event)
            app.Settings.Folder.Output = uigetdir(app.Settings.Folder.Output);
            try
                app.OutputFolder.Value = app.Settings.Folder.Output;
            catch
            end
        end

        % Value changed function: OpenFolder
        function OpenFolderValueChanged(app, event)
            try
                app.Settings.Folder.Open = app.OpenFolder.Value;
                updateFolder(app)
                app.FilesDropDown.Value = 'All files';
            catch
            end

        end

        % Button pushed function: RecalculatecorrelationButton
        function RecalculatecorrelationButtonPushed(app, event)
            img= app.Part.Image;
            n = numel(img(1,1,:));
            rf = app.ref.image;
            for i = 1:n
                cor(i) = corr2(img(:,:,i),rf);
            end
            app.Part.Locs(:,4) = cor;
            updateHist(app,'normal')

        end

        % Menu selected function: SettingsMenu_2
        function SettingsMenu_2Selected(app, event)
            app.PopupApp.Preferences = Preferences();
        end

        % Button pushed function: PointCloudAlignButton
        function PointCloudAlignButtonPushed(app, event)
            [app.LAFM.Locs,~] = align_PtCloud(app.LAFM.Locs, app.LAFM.Image, app.LAFM_exp.Value);
            RenderLAFMButtonPushed(app)
        end

        % Menu selected function: ImportLocalizationsMenu, 
        % ...and 1 other component
        function LocalizationsMenuSelected(app, event)

            selection = questdlg('Import to current file or as a new file?', 'Import Localizations','Current File', 'New File', 'Cancel', 'New File');

            switch selection
                case 'New File'
                    reset_defaults(app)
                    app.ImageName = inputdlg('File name:', 'Import Localizations', [1 50]);
                    if isempty(app.ImageName)
                        return
                    end
                    app.ImageTarget = zeros(25,25);
                    sz = size(app.ImageTarget);
                    app.Spinner.Value = 1;
                    app.FrameSlider.Value = 1;
                    app.ImageInfo.PixelPerNm = 1;
                    app.ImageInfo.ScanSize = sz(2)./app.ImageInfo.PixelPerNm;
                    app.ImageInfo.xPixels = sz(2);
                    app.ImageInfo.yPixels = sz(1);
                    app.ImageInfo.Channel = 'Localizations';
                    app.ImageInfo.Channels = {app.ImageInfo.Channel};
                    app.ImageInfo.time = 0;
                    app.ImageInfo.n = 1;
                    updateTable(app)
                    updateVideoTools(app)
                    updatePlot(app,'normal')
                    app.FilesListBox.Items = [app.stored.ImageName, app.ImageName];
                    app.FilesListBox.Value = app.ImageName;
                    storedata(app)

                    app.FilesDropDown.Value = 'Processed';
                case 'Cancel'
                    return
            end

            [file, path] = uigetfile('*');
            if any(file)
                LocsData = fullfile(path, file);
                namesplit = split(file,'.');
                filetype = namesplit{end};
                switch filetype
                    case {'txt', 'xls', 'csv'}
                        T = readtable(LocsData,'NumHeaderLines',0);
                        app.LAFM.Locs = table2array(T);
                    case 'mat'
                        load(LocsData);
                        if exist('Data', 'var') && isfield(Data, 'LAFMLocs') && isfield(Data.LAFMLocs, 'Locs')
                            app.LAFM.Locs = Data.LAFMLocs.Locs;
                        elseif exist('LAFM', 'var') && isfield(LAFM, 'Locs')
                            app.LAFM.Locs = LAFM.Locs;
                        elseif exist('Locs', 'var')
                            app.LAFM.Locs = Locs;
                        else
                            msgbox(["No suitable Locs data found. Please ensure the localizations are saved in one of the following ways:"; ...
                                "1. Directly as a variable named Locs"; ...}
                                "2. As a field named Locs within a structure Data.LAFMLocs, i.e., Data.LAFMLocs.Locs."; ...
                                "3. As a field named Locs within a structure LAFM, i.e., LAFM.Locs."])
                            return
                        end
                end

                if sum(strcmp(app.RightPlotList.Items,'Localizations')) <1
                    app.RightPlotList.Items = [app.RightPlotList.Items , {'Localizations'}];
                end
                app.RightPlotList.Value = 'Localizations';
                if sum(strcmp(app.RefListBox.Items,'Simulated Reference')) <1
                    app.RefListBox.Items = [app.RefListBox.Items , {'Simulated Reference'}];
                end

                updateLAFMHist(app)
                filter_LAFMlocs(app)
                RightPlotListValueChanged(app, event)
                updateHist(app,'normal')
                updateRightPlot(app);

            end

        end

        % Callback function: FRCResolutionMenu, FRCresolutionButton
        function FRCResolutionButtonPushed(app, event)

                loading(app)
                cla(app.loadbar)
                progressText = text(app.loadbar, 0.5, 0.5, '0%', ...
                    'HorizontalAlignment', 'center', ...
                    'VerticalAlignment', 'middle', ...
                    'Color', 'k');

                progressText.String = sprintf('Checking Resolution');
                writeLog(app, progressText.String);
                drawnow;
                pixpernm = median(app.ImageInfo.PixelPerNm);
                % switch app.FRCMethod.Value
                %     case 'Half Images'
                av_repeats = 10;
                [app.FRC.q, app.FRC.frc_mean, app.FRC.av_resolution, app.FRC.sd_resolution] = measureFRC(app.LAFM.Locs,pixpernm,av_repeats,app.LAFM_exp.Value);
                figure(33);
                plot(app.FRC.q*pixpernm*app.LAFM_exp.Value,  app.FRC.frc_mean, 'LineWidth',1.5, 'DisplayName',['Resolution = ',num2str(round(app.FRC.av_resolution,2)),' nm'])
                xlabel('Spatial frequency [1/nm]', 'FontSize', 16)
                ylabel('FRC', 'FontSize', 16)
                hold on
                legend show
                thresholdcurve = 1/7*ones(size(app.FRC.frc_mean));
                plot(app.FRC.q*pixpernm*app.LAFM_exp.Value, thresholdcurve,'k--')
                progressText.String = sprintf(['Resolution = ',num2str(round(app.FRC.av_resolution,2)),' nm']);
                finished(app)
                 writeLog(app, progressText.String,'image');
            

        end

        % Button pushed function: AutoButton_2
        function AutoButton_2Pushed(app, event)
            if app.ImageInfo.PixelPerNm(1) ==0
                app.ImageInfo.PixelPerNm(1) =1;
            else
                pixpernm = median(app.ImageInfo.PixelPerNm);
            end

            app.LoczLowpassDropDown.Value = 'Gaussian';
            app.LoczLowpass.Value = pixpernm*1.25/10;
            updatePlot(app,'normal')
        end

        % Callback function
        function RefinePushed(app, event)

            % n = numel(app.ImageTarget(1,1,:));
            % for  i = 1:n
            %     I = app.ImageTarget(:,:,i);
            %     BW = activecontour(I,app.Mask.Mask(:,:,i)==1);
            %     app.Mask.ROI = BW;
            %     app.Mask.Mask(:,:,i) = BW;
            % end
            % app.Mask.Mask(app.Mask.Mask==0) = NaN;
            % app.filter_already =0;
            % app.PreviewonoffButton.Value =1;
            % previewOnOff(app)
            % run_filter(app)

        end

        % Value changed function: DrawTool
        function DrawToolValueChanged(app, event)
            switch app.DrawTool.Value
                case 'Profile'
                    app.Profile_tool_Button.Value = 1;
                    TurnOffToolButtons(app)
                case 'ROI'
                    app.ROI_Button.Value = 1;
                    TurnOffToolButtons(app)
                case 'Average'
                   app.Average_tool_Button.Value = 1;
                    TurnOffToolButtons(app)
                    app.LineThickness.Value =1;
                case '+ Mask'
                     app.AddMask_tool_Button.Value = 1;
                    TurnOffToolButtons(app)
                    app.LineThickness.Value =1;
                    app.ViewMask.Value = 1;
                    ViewMaskChanged(app)
                    updatePlot(app,'normal')
                case '- Mask'
                    app.RemoveMask_tool_Button.Value = 1;
                    TurnOffToolButtons(app)
                    app.LineThickness.Value =1;
                    app.ViewMask.Value = 1;
                    ViewMaskChanged(app)
                    updatePlot(app,'normal')
                otherwise
            end
            
        end

        % Value changed function: Draw
        function DrawValueChanged(app, event)

            switch app.Draw.Value
                case 'Off'
                    app.DrawTool.Items = {'Profile','ROI','Average','+ Mask','- Mask'};
                    TurnOffDrawButtons(app,'all')
                case 'Line'
                    app.DrawTool.Items = {'Profile'};
                    app.Line_roi_Button.Value = 1;
                    TurnOffDrawButtons(app,'app.Line_roi_Button')
                case 'Square'
                    app.DrawTool.Items = {'Profile','ROI','Average','+ Mask','- Mask'};
                    app.Sq_roi_Button.Value = 1;
                    TurnOffDrawButtons(app,'app.Sq_roi_Button')
                case 'Circle'
                    app.DrawTool.Items = {'Profile','ROI','Average','+ Mask','- Mask'};
                    app.Circ_roi_Button.Value = 1;
                    TurnOffDrawButtons(app,'app.Circ_roi_Button')
                case 'Point'
                    app.DrawTool.Items = {'Profile'};
                    app.Point_roi_Button.Value = 1;
                    TurnOffDrawButtons(app,'app.Point_roi_Button')
                case 'Freehand'
                    app.DrawTool.Items = {'+ Mask','- Mask','Profile'};
                    app.Freehand_roi_Button.Value = 1;
                    TurnOffDrawButtons(app,'app.Freehand_roi_Button')
            end

            if app.VideoPanel.Visible == 0
                app.DrawTool.Items = app.DrawTool.Items(~strcmp(app.DrawTool.Items, 'Average'));
            end
            HideToolButtons(app)
            TurnOffToolButtons(app)

        end

        % Value changed function: LineThickness
        function LineThicknessValueChanged(app, event)
           
            switch app.Draw.Value
                case 'Freehand'
                     app.LineThickness.Value = 1;
            end
            app.RightPlotList.Value = 'Line Profile';
            updatePlot2_type(app, 'plot');
            app.OverlayDropDown.Value = 'Line Profile';
            updatePlot2(app, 'live');
            updateResultsTable(app, 'Line Profile');
        end

        % Value changed function: Freehand_roi_Button
        function Freehand_roi_ButtonValueChanged(app, event)
            TurnOffDrawButtons(app,'app.Freehand_roi_Button')
            app.Draw.Value = 'Freehand';
            DrawValueChanged(app)
        end

        % Value changed function: Profile_tool_Button
        function Profile_tool_ButtonValueChanged(app, event)
            app.DrawTool.Value = 'Profile';
            TurnOffToolButtons(app)
            DrawToolValueChanged(app)

        end

        % Value changed function: Average_tool_Button
        function Average_tool_ButtonValueChanged(app, event)
app.DrawTool.Value = 'Average';
            TurnOffToolButtons(app)
            
            DrawToolValueChanged(app)
        end

        % Value changed function: ROI_Button
        function ROI_ButtonValueChanged(app, event)
            app.DrawTool.Value = 'ROI';
            TurnOffToolButtons(app)

            DrawToolValueChanged(app)
        end

        % Value changed function: AddMask_tool_Button
        function AddMask_tool_ButtonValueChanged(app, event)
            app.DrawTool.Value = '+ Mask';
            TurnOffToolButtons(app)

            DrawToolValueChanged(app)
        end

        % Value changed function: RemoveMask_tool_Button
        function RemoveMask_tool_ButtonValueChanged(app, event)
            app.DrawTool.Value = '- Mask';
            TurnOffToolButtons(app)

            DrawToolValueChanged(app)
        end

        % Menu selected function: AffineAlignMenu
        function AffineAlignButtonPushed(app, event)
            app.CancelButton.Visible = 1;
            loading(app)
            img = app.Part.Image(:,:,app.Part.IncludeImages);
            cla(app.loadbar);
            updateInterval = 5;
            n = numel(img(1,1,:));

            stepsPerUpdate = ceil(n * (updateInterval / 100));
            rectangle(app.loadbar, 'Position', [0, 0, 1, 1], 'FaceColor', 'none', 'EdgeColor', 'black');
            progressBar = rectangle(app.loadbar, 'Position', [0, 0, 0, 1], 'FaceColor', [0.30,0.98,0.40]);
            progressText = text(app.loadbar, 0.5, 0.5, '0%', ...
                'HorizontalAlignment', 'center', ...
                'VerticalAlignment', 'middle', ...
                'Color', 'k');


            %iterative loop starts
            [optimizer,metric] = imregconfig("multimodal");
            optimizer.InitialRadius = 0.009;
            optimizer.Epsilon = 1.5e-4;
            optimizer.GrowthFactor = 1.01;
            optimizer.MaximumIterations = 300;
            for i = 1:numel(img(1,1,:))
                if app.stop == 1
                    return
                end

                img(:,:,i) = imregister(img(:,:,i),app.ref.image,"affine",optimizer,metric);

                if mod(i, stepsPerUpdate) == 0 || i == n
                    progressBar.Position(3) = i / n;
                    progressText.String = sprintf([num2str(i),' frames affine aligned']);
                    drawnow;
                end
            end
            writeLog(app, progressText.String,'image');
            app.Part.Image(:,:,app.Part.IncludeImages) = img;
            %iterative loop ends
            updatePlot(app,'normal')
            finished(app)
            app.CancelButton.Visible = 0;
        end

        % Menu selected function: TransferParticlesMenu
        function TransferParticlesMenuSelected(app, event)

            if isfield(app.Part, 'Locs')
                % Create a UI figure
                fig = uifigure('Name', 'Select Image Channel', 'Position', [500, 400, 300, 150]);

                % Create a label
                lbl = uilabel(fig, 'Text', 'Select an Image Channel:', ...
                    'Position', [20, 90, 200, 30], 'FontSize', 12);

                % Create a dropdown menu with items from app.ChannelDropDown.Items
                dropdown = uidropdown(fig, ...
                    'Items', app.ChannelDropDown.Items, ...
                    'Position', [20, 50, 250, 30]);

                % Create an OK button
                btn = uibutton(fig, 'Text', 'OK', ...
                    'Position', [100, 10, 100, 30], ...
                    'ButtonPushedFcn', @(btn, event) onSelectionMade(dropdown, fig, app));

            else
                errordlg( 'No particle data found. Run detection first.', 'Warning');
                writeLog(app, '---ERROR--- No particle data found. Run detection first')
            end

            function onSelectionMade(dropdown, fig, app)
                selectedChannel = dropdown.Value;
                app.ChannelDropDown.Value = selectedChannel;
                tempPart = app.Part;
                tempRef = app.ref;
                tempswitch = app.FramePartSwitch.Value;
                FilesListBoxValueChanged(app)
                app.Part = tempPart;
                app.ref = tempRef;
                app.Part.Image  = ConstructParticleStack(app.ImageTarget, app.Part,0, app);
                app.ref.av  = mean(app.Part.Image(:,:,app.Part.IncludeImages),3);

                if sum(strcmp(app.RefListBox.Items,'Averaged Reference')) <1
                    app.RefListBox.Items = [app.RefListBox.Items , {'Averaged Reference'}];
                end
                app.RefListBox.Value = 'Averaged Reference';

                if sum(strcmp(app.RightPlotList.Items,'Ref Image')) <1
                    app.RightPlotList.Items = [app.RightPlotList.Items , {'Ref Image'}];
                end
                app.RightPlotList.Value = 'Ref Image';
                updateRightPlot(app);
                updatePlot2(app,'normal')
                app.FramePartSwitch.Value = tempswitch;

                switch  app.FramePartSwitch.Value
                    case 'Frames'
                        app.OverlayDropDown.Value = 'Detections';
                    case 'Particles'

                end
                updatePlot(app,'normal')
                close(fig);
            end

        end

        % Value changed function: HistogramDropDown
        function HistogramDropDownValueChanged(app, event)
      updateHist(app,'normal')
        end

        % Value changed function: ViewMask
        function ViewMaskValueChanged(app, event)
            if app.ViewMask.Value ==1
                app.OverlayDropDown.Value = 'Mask';
            else
                app.OverlayDropDown.Value = 'None';
            end
            ViewMaskChanged(app)
          updatePlot(app,'normal')
        end

        % Button pushed function: ResetButton_2
        function ResetButton_2Pushed(app, event)
            app.Mask.low_thresh = -Inf;
            app.Mask.high_thresh = Inf;
            updateHist(app,'normal')
        end

        % Menu selected function: DSurfaceLightingMenu
        function DSurfaceLightingMenuSelected(app, event)

            app.PlotTypeDropDown.Value = '3D surface';
            app.UIAxes.Toolbar.Visible = 'on';
            rotate3d(app.UIAxes, 'on');
            updatePlot(app,'normal');
            app.PopupApp.Mask = Lighting();
        end

        % Button pushed function: Undo_FineAlignButton
        function Undo_FineAlignButtonPushed(app, event)
            app.Part.Locs = app.Part.Locs_temp;
            app.Part.Image =  ConstructParticleStack(app.ImageTarget, app.Part,0, app);
            updatePlot(app,'normal')

            finished(app)
            storedata(app)
            savedata(app,app.Part.Locs,'Part Locs')

        end

        % Button pushed function: PopHist
        function PopHistButtonPushed(app, event)
               updateHist(app,'popout')
        end

        % Button pushed function: AutoButton_3
        function AutoButton_3Pushed(app, event)
         
            loading(app)
            cla(app.loadbar)
            progressText = text(app.loadbar, 0.5, 0.5, '0%', ...
                'HorizontalAlignment', 'center', ...
                'VerticalAlignment', 'middle', ...
                'Color', 'k');

            progressText.String = sprintf('Checking Resolution');
            writeLog(app, progressText.String);
            drawnow;
            pixpernm = median(app.ImageInfo.PixelPerNm);
            % switch app.FRCMethod.Value
            %     case 'Half Images'
            av_repeats = 5;
            [app.FRC.q, app.FRC.frc_mean, app.FRC.av_resolution, app.FRC.sd_resolution] = measureFRC(app.LAFM.Locs,pixpernm,av_repeats,app.LAFM_exp.Value);
            [app.LAFM_gus.Value, app.LAFM_exp.Value ] = Res_to_render(pixpernm, app.FRC.av_resolution);
            progressText.String = sprintf(['Resolution = ',num2str(round(app.FRC.av_resolution,2)),' nm']);
            writeLog(app, progressText.String,'image');
            msg = sprintf("Suggested LAFM render values - Render Point size: %g  Expand size: x%g", ...
                app.LAFM_gus.Value, app.LAFM_exp.Value);
            writeLog(app, msg,'image');

            finished(app)

        end

        % Image clicked function: BinRangeImage_3
        function BinRangeImage_3Clicked(app, event)
           app.LAFM.Locs = Rem_outliers(app.LAFM.Locs, app.DeleteoutliersEditField.Value);
           filter_LAFMlocs(app)
           updatePlot2(app,'normal')
        end

        % Menu selected function: LabelsettingsMenu
        function LabelsettingsMenuSelected(app, event)
            app.PopupApp.Preferences = Preferences();
            drawnow;
            app.PopupApp.Preferences.TabGroup.SelectedTab = app.PopupApp.Preferences.ImageLabelsTab;

        end

        % Value changed function: Lightmode
        function LightmodeValueChanged(app, event)
            if app.Lightmode.Value == 0
                app.CenterPanel.ForegroundColor = [1,1,1];
                app.CenterPanel.BackgroundColor = [1,1,1];
                app.Settings.ExportSettings.color = 'white';
                app.ScalebarCheckBox.FontColor = [0,0,0];
                app.ColorBarCheckBox.FontColor = [0,0,0];
                app.TimescaleCheckBox.FontColor = [0,0,0];
            else
                app.CenterPanel.ForegroundColor = [0.15,0.15,0.15];
                app.CenterPanel.BackgroundColor =[0.15,0.15,0.15];
                app.Settings.ExportSettings.color = 'black';
                app.ScalebarCheckBox.FontColor = [0.30,0.98,0.40];
                app.ColorBarCheckBox.FontColor = [0.30,0.98,0.40];
                app.TimescaleCheckBox.FontColor = [0.30,0.98,0.40];

            end
            ColorBarCheckBoxValueChanged(app)
            
        end

        % Value changed function: ThresholdMethod
        function ThresholdMethodValueChanged(app, event)

            switch app.ThresholdMethod.Value
                case 'auto edges'
                    app.ImgFilterStrength_4.Visible=1;
                    app.ImgFilterStrength_5.Visible=1;
                    app.ImgFilterLabel_4.Visible = 1;
                    app.ImgFilterLabel_5.Visible = 1;

                    app.ImgFilterLabel_4.Value = 'Threshold';
                    app.ImgFilterStrength_4.Limits =  [0,Inf];
                    app.ImgFilterStrength_4.Value = 1.5;
                    app.ImgFilterStrength_4.Step =0.1;
                    app.Mask.auto_thresh = app.ImgFilterStrength_4.Value;
 
            
                    app.ImgFilterLabel_5.Value = 'Thickness';
                    app.ImgFilterStrength_5.Limits =  [1,50];
                    app.ImgFilterStrength_5.Value = 5;
                    app.ImgFilterStrength_5.Step =1;
                    app.Mask.auto_thick = app.ImgFilterStrength_5.Value;
                case 'parachute'
                    app.ImgFilterStrength_4.Visible=1;
                    app.ImgFilterStrength_5.Visible=1;
                    app.ImgFilterLabel_4.Visible = 1;
                    app.ImgFilterLabel_5.Visible = 1;

                    app.ImgFilterLabel_4.Value = 'Direction';
                    app.ImgFilterStrength_4.Limits =  [-1,1];
                    app.ImgFilterStrength_4.Value = 1;
                    app.ImgFilterStrength_4.Step =1;
                    app.Mask.auto_thresh = app.ImgFilterStrength_4.Value;
            
                    app.ImgFilterLabel_5.Value = 'Threshold';
                    app.ImgFilterStrength_5.Limits =  [0,Inf];
                    app.ImgFilterStrength_5.Value = 1;
                    app.ImgFilterStrength_5.Step =0.1;
                    app.Mask.auto_thick = app.ImgFilterStrength_5.Value;


                otherwise

                    app.ImgFilterStrength_4.Visible=0;
                    app.ImgFilterStrength_5.Visible=0;
                    app.ImgFilterLabel_4.Visible = 0;
                    app.ImgFilterLabel_5.Visible = 0;
            end

            switch app.ImgFilterType.Value
                case 'Sliding Mean Frames'
                    if app.ImgFilterStrength.Value >1
                        app.ImagePreview = movmean(app.ImageTarget ,app.ImgFilterStrength.Value,3);
                        app.PreviewonoffButton.Value =1;
                        previewOnOff(app)
                        updatePlot(app,'normal')
                    end
                otherwise
                    app.filter_already =0;
                    app.PreviewonoffButton.Value =1;
                    previewOnOff(app)
                    run_filter(app)
            end
            
        end

        % Value changed function: ImgFilterStrength_4, ImgFilterStrength_5
        function ImgFilterStrength_4ValueChanged(app, event)
            app.Mask.auto_thresh = app.ImgFilterStrength_4.Value;
            app.Mask.auto_thick = app.ImgFilterStrength_5.Value;
            app.filter_already =0;
            app.PreviewonoffButton.Value =1;
            previewOnOff(app)
            run_filter(app)
        end

        % Menu selected function: ConvertParticlestoframesMenu
        function ConvertParticlestoframesMenuSelected(app, event)

            if isempty(app.Part) || isempty(app.Part.Image) || isempty(app.Part.IncludeImages)
                writeLog(app, "Error: No particle data available. Cannot switch to Frames.");
                return; % stop further execution
            end
            app.ImageTarget = app.Part.Image(:,:,app.Part.IncludeImages);
            app.ImageInfo.n = numel(app.Part.Image(1,1,app.Part.IncludeImages));

            time = app.ImageInfo.time(app.Part.Locs(:,5));
            app.ImageInfo.time = time;

            p = app.ImageInfo.PixelPerNm(app.Part.Locs(:,5));
            app.ImageInfo.PixelPerNm = p;

            app.Part = [];
            app.FramePartSwitch.Value = 'Frames';
            app.FrameSlider.Value = 1;

            updateVideoTools(app)
            app.Spinner.Value = round(app.FrameSlider.Value);
            updatePlot(app,'normal')
            updatePlot2(app,'normal')
            updateHist(app,'normal')
            storedata(app)
            savedata(app, app.ImageTarget,'Image');

            writeLog(app, "Switched to Frames mode from Particles.",'image');

        end

        % Close request function: NanoLocz
        function NanoLoczCloseRequest(app, event)
            try
                delete(app.PopupApp.Preferences)
            catch
            end

            try
                delete(app.LogFig)
            catch
            end

            try
                delete(app.PopupApp.Mask)
            catch
            end

            try
                delete(app.PopupApp.Saving);
            catch
            end

            try
                delete(app.PopupApp.ImageTools);
            catch
            end

            delete(app);
            
        end

        % Value changed function: ChannelDropDown_2
        function ChannelDropDown_2ValueChanged(app, event)
            try
            if strcmp(app.ChannelDropDown_2.Value, app.ChannelDropDown.Value)
            else
                fileName = [app.Settings.Folder.Open '/' app.FilesListBox.Value];
                writeLog(app, "Background loading File... " + app.FilesListBox.Value +" Channel: "+ app.ChannelDropDown_2.Value);
                if app.View1stCheckBox.Value
                    [im, ~] = ReadAFMFile(fileName, app.ChannelDropDown_2.Value,app.View1st.Value, app);
                else
                    [im, ~] = ReadAFMFile(fileName, app.ChannelDropDown_2.Value,'all', app);
                end
 
                app.ImageExtraChannel = single(im);
                  writeLog(app, "Masking on channel: "+ app.ChannelDropDown.Value,'image');
            end


            app.filter_already =0;
            app.PreviewonoffButton.Value =1;
            previewOnOff(app)
            run_filter(app)

            catch
                writeLog(app, "Failed to open additional channel");
            end
        end

        % Menu selected function: OpenLogwindowMenu
        function LogMenuSelected(app, event)
            createLogWindow(app)
        end

        % Button pushed function: UndoFilter
        function UndoFilterPushed(app, event)
            app.ImageTarget = app.ImageUndo;
            writeLog(app, "Undo")
             updatePlot(app,'normal')
            updateHist(app,'normal')
            storedata(app)
        end

        % Button pushed function: LineFFTButton
        function LineFFTButtonPushed(app, event)
            result = fft_line_analysis(app.ROI.Line(1).Profile(:,1), app.ROI.Line(1).Profile(:,2), 1);
            writeLog(app, "Dominant spatial periods (nm):",'image');

            for i = 1:min(5, numel(result.periods))
                msg = sprintf("  T = %.2f ± %.2f nm", ...
                    result.periods(i), result.periodError(i));
                writeLog(app, msg,'image');
            end


        end

        % Menu selected function: FullLogMenu
        function ExportLogastxtMenuSelected(app, event)

            [file, path] = uiputfile('NanoLocz_Log.txt', 'Save Log As');
            if isequal(file,0)
                return; % user cancelled
            end

            fullName = fullfile(path, file);

            % Convert log buffer to newline-separated text
            logText = strjoin(app.LogBuffer, newline);

            % Write file
            fid = fopen(fullName, 'w');
            if fid == -1
                uialert(app.UIFigure, 'Unable to create log file.', 'Error');
                return;
            end

            fprintf(fid, '%s\n', logText);
            fclose(fid);

            % Confirmation (optional)
            writeLog(app, "Log exported to: " + fullName);
        end

        % Menu selected function: ViewImagehistoryMenu
        function ViewImagehistoryMenuSelected(app, event)
            
    % --- Check log exists ---
    if ~isstruct(app.ImageInfo) || ...
       ~isfield(app.ImageInfo,'Log') || ...
       isempty(app.ImageInfo.Log)

        uialert(app.UIFigure, ...
            'No image-specific log entries found.', ...
            'Image Log');
        return
    end

    % --- Create or reuse figure ---
    if isempty(app.ImageLogFig) || ~isvalid(app.ImageLogFig)

        app.ImageLogFig = uifigure( ...
            'Name','Image Log History', ...
            'Position',[100 100 600 400]);

        app.ImageLogListBox = uilistbox( ...
            app.ImageLogFig, ...
            'Position',[10 10 580 380], ...
            'FontName','Courier New', ...
            'Items', strings(0,1));

    end

    % --- Update contents (ListBox needs ROW) ---
    app.ImageLogListBox.Items = app.ImageInfo.Log(:).';

    drawnow
        end

        % Menu selected function: CurrentImageLogMenu
        function CurrentImageLogMenuSelected(app, event)

    % --- Check image log exists ---
    if ~isstruct(app.ImageInfo) || ...
       ~isfield(app.ImageInfo,'Log') || ...
       isempty(app.ImageInfo.Log)
        return
    end

    % --- Default filename from image name ---
    if ~isempty(app.FilesListBox.Value)
        defaultName = "NanoLocz_" + string(app.FilesListBox.Value) + "_Log.txt";
    else
        defaultName = "NanoLocz_Image_Log.txt";
    end

    % --- File dialog ---
    [file, path] = uiputfile(defaultName, 'Save Image Log As');
    if isequal(file,0)
        return   % user cancelled
    end

    fullName = fullfile(path, file);

    % --- Convert vertical log to text ---
    logText = strjoin(app.ImageInfo.Log(:), newline);

    % --- Write file ---
    fid = fopen(fullName, 'w');
    if fid == -1
        uialert(app.UIFigure, 'Unable to create log file.', 'Error');
        return
    end

    fprintf(fid, '%s\n', logText);
    fclose(fid);

    % --- Confirm + provenance ---
    writeLog(app, "Image log exported to: " + fullName);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.NanoLocz = uifigure('Visible', 'off');
            app.NanoLocz.Name = 'NanoLocz';
            app.NanoLocz.Icon = fullfile(pathToMLAPP, 'icons', 'zoomin.png');
            app.NanoLocz.CloseRequestFcn = createCallbackFcn(app, @NanoLoczCloseRequest, true);
            app.NanoLocz.WindowButtonDownFcn = createCallbackFcn(app, @NanoLoczWindowButtonDown, true);
            app.NanoLocz.KeyPressFcn = createCallbackFcn(app, @NanoLoczKeyPress, true);
            app.FileMenu = uimenu(app.NanoLocz);
            app.FileMenu.Text = 'File';
            app.OpenMenu = uimenu(app.FileMenu);
            app.OpenMenu.Text = 'Open';
            app.FolderMenu = uimenu(app.OpenMenu);
            app.FolderMenu.MenuSelectedFcn = createCallbackFcn(app, @OpenFolderButtonClicked, true);
            app.FolderMenu.Text = 'Folder';
            app.OpenSessionMenu = uimenu(app.OpenMenu);
            app.OpenSessionMenu.MenuSelectedFcn = createCallbackFcn(app, @OpenSessionMenuSelected, true);
            app.OpenSessionMenu.Text = 'Session';
            app.ImportMenu = uimenu(app.FileMenu);
            app.ImportMenu.Text = 'Import';
            app.LocalizationsMenu = uimenu(app.ImportMenu);
            app.LocalizationsMenu.MenuSelectedFcn = createCallbackFcn(app, @LocalizationsMenuSelected, true);
            app.LocalizationsMenu.Text = 'Localizations';
            app.ParticleLocationsMenu = uimenu(app.ImportMenu);
            app.ParticleLocationsMenu.Text = 'Particle Locations';
            app.SaveMenu = uimenu(app.FileMenu);
            app.SaveMenu.MenuSelectedFcn = createCallbackFcn(app, @SaveSessionImageClicked, true);
            app.SaveMenu.Text = 'Save';
            app.QuitMenu = uimenu(app.FileMenu);
            app.QuitMenu.MenuSelectedFcn = createCallbackFcn(app, @QuitMenuSelected, true);
            app.QuitMenu.Text = 'Quit';
            app.SettingsMenu = uimenu(app.NanoLocz);
            app.SettingsMenu.Text = 'Settings';
            app.SettingsMenu_2 = uimenu(app.SettingsMenu);
            app.SettingsMenu_2.MenuSelectedFcn = createCallbackFcn(app, @SettingsMenu_2Selected, true);
            app.SettingsMenu_2.Text = 'Settings';
            app.LabelsettingsMenu = uimenu(app.SettingsMenu);
            app.LabelsettingsMenu.MenuSelectedFcn = createCallbackFcn(app, @LabelsettingsMenuSelected, true);
            app.LabelsettingsMenu.Text = 'Label settings';
            app.ImageMenu = uimenu(app.NanoLocz);
            app.ImageMenu.Text = 'Image';
            app.ResizerotateMenu = uimenu(app.ImageMenu);
            app.ResizerotateMenu.MenuSelectedFcn = createCallbackFcn(app, @ResizerotateMenuSelected, true);
            app.ResizerotateMenu.Text = 'Resize/rotate';
            app.DSurfaceLightingMenu = uimenu(app.ImageMenu);
            app.DSurfaceLightingMenu.MenuSelectedFcn = createCallbackFcn(app, @DSurfaceLightingMenuSelected, true);
            app.DSurfaceLightingMenu.Text = '3D Surface Lighting';
            app.ToolsMenu = uimenu(app.NanoLocz);
            app.ToolsMenu.Text = 'Tools';
            app.SimulationAFMMenu = uimenu(app.ToolsMenu);
            app.SimulationAFMMenu.MenuSelectedFcn = createCallbackFcn(app, @OpenMat_SimAFMMenuSelected, true);
            app.SimulationAFMMenu.Text = 'Simulation AFM';
            app.MaskAnalysisMenu = uimenu(app.ToolsMenu);
            app.MaskAnalysisMenu.MenuSelectedFcn = createCallbackFcn(app, @MaskAreasMenuSelected, true);
            app.MaskAnalysisMenu.Text = 'Mask Analysis';
            app.PlotterMenu_2 = uimenu(app.ToolsMenu);
            app.PlotterMenu_2.MenuSelectedFcn = createCallbackFcn(app, @PlotterValueChanged, true);
            app.PlotterMenu_2.Text = 'Plotter';
            app.TransferParticlesMenu = uimenu(app.ToolsMenu);
            app.TransferParticlesMenu.MenuSelectedFcn = createCallbackFcn(app, @TransferParticlesMenuSelected, true);
            app.TransferParticlesMenu.Text = 'Transfer Particles';
            app.LAFMMenu = uimenu(app.ToolsMenu);
            app.LAFMMenu.Text = 'LAFM';
            app.FRCResolutionMenu = uimenu(app.LAFMMenu);
            app.FRCResolutionMenu.MenuSelectedFcn = createCallbackFcn(app, @FRCResolutionButtonPushed, true);
            app.FRCResolutionMenu.Text = 'FRC Resolution';
            app.ImportLocalizationsMenu = uimenu(app.LAFMMenu);
            app.ImportLocalizationsMenu.MenuSelectedFcn = createCallbackFcn(app, @LocalizationsMenuSelected, true);
            app.ImportLocalizationsMenu.Text = 'Import Localizations';
            app.AffineAlignMenu = uimenu(app.LAFMMenu);
            app.AffineAlignMenu.MenuSelectedFcn = createCallbackFcn(app, @AffineAlignButtonPushed, true);
            app.AffineAlignMenu.Text = 'Affine Align';
            app.ConvertParticlestoframesMenu = uimenu(app.ToolsMenu);
            app.ConvertParticlestoframesMenu.MenuSelectedFcn = createCallbackFcn(app, @ConvertParticlestoframesMenuSelected, true);
            app.ConvertParticlestoframesMenu.Text = 'Convert Particles to frames';
            app.LogMenu = uimenu(app.NanoLocz);
            app.LogMenu.Text = 'Log';
            app.OpenLogwindowMenu = uimenu(app.LogMenu);
            app.OpenLogwindowMenu.MenuSelectedFcn = createCallbackFcn(app, @LogMenuSelected, true);
            app.OpenLogwindowMenu.Text = 'Open Log window';
            app.ViewImagehistoryMenu = uimenu(app.LogMenu);
            app.ViewImagehistoryMenu.MenuSelectedFcn = createCallbackFcn(app, @ViewImagehistoryMenuSelected, true);
            app.ViewImagehistoryMenu.Text = 'View Image history';
            app.ExportLogastxtMenu = uimenu(app.LogMenu);
            app.ExportLogastxtMenu.Text = 'Export Log as .txt';
            app.FullLogMenu = uimenu(app.ExportLogastxtMenu);
            app.FullLogMenu.MenuSelectedFcn = createCallbackFcn(app, @ExportLogastxtMenuSelected, true);
            app.FullLogMenu.Text = 'Full Log';
            app.CurrentImageLogMenu = uimenu(app.ExportLogastxtMenu);
            app.CurrentImageLogMenu.MenuSelectedFcn = createCallbackFcn(app, @CurrentImageLogMenuSelected, true);
            app.CurrentImageLogMenu.Text = 'Current Image Log';
            app.LeftPanel = uipanel(app.NanoLocz);
            app.loadbar = uiaxes(app.LeftPanel);
            title(app.loadbar, 'Title')
            xlabel(app.loadbar, 'X')
            ylabel(app.loadbar, 'Y')
            zlabel(app.loadbar, 'Z')
            app.Hist_Axes = uiaxes(app.LeftPanel);
            zlabel(app.Hist_Axes, 'Z')
            app.Hist_Axes.YTick = [];
            app.Hist_Axes.YTickLabel = {};
            app.AutoSaveButton = uibutton(app.LeftPanel, 'state');
            app.AutoSaveButton.ValueChangedFcn = createCallbackFcn(app, @AutoSaveButtonValueChanged, true);
            app.AutoSaveButton.Text = 'AutoSave ';
            app.FilesListBox = uilistbox(app.LeftPanel);
            app.FilesListBox.Items = {};
            app.FilesListBox.ValueChangedFcn = createCallbackFcn(app, @FilesListBoxValueChanged, true);
            app.FilesListBox.DoubleClickedFcn = createCallbackFcn(app, @FilesListBoxDoubleClicked, true);
            app.FilesListBox.Value = {};
            app.UITable = uitable(app.LeftPanel);
            app.UITable.ColumnName = {'Parameter'; 'Value'};
            app.UITable.RowName = {};
            app.UITable.ColumnEditable = [false true];
            app.UITable.CellEditCallback = createCallbackFcn(app, @UITableCellEdit, true);
            app.TabGroup = uitabgroup(app.LeftPanel);
            app.HomeTab = uitab(app.TabGroup);
            app.HomeTab.Title = 'Home';
            app.LoaddemodataButton = uibutton(app.HomeTab, 'push');
            app.LoaddemodataButton.ButtonPushedFcn = createCallbackFcn(app, @LoaddemodataButtonPushed, true);
            app.LoaddemodataButton.Text = 'Load demo data';
            app.PreferencesButton = uibutton(app.HomeTab, 'push');
            app.PreferencesButton.ButtonPushedFcn = createCallbackFcn(app, @PreferencesButtonPressed, true);
            app.PreferencesButton.Text = 'Preferences ';
            app.Hyperlink = uihyperlink(app.HomeTab);
            app.Hyperlink.URL = 'https://george-r-heath.github.io/NanoLocz/docs/';
            app.Hyperlink.Text = 'User Guide';
            app.VideoAlignSettings_2 = uiimage(app.HomeTab);
            app.VideoAlignSettings_2.ImageClickedFcn = createCallbackFcn(app, @PreferencesButtonPressed, true);
            app.VideoAlignSettings_2.ImageSource = fullfile(pathToMLAPP, 'icons', 'settings.png');
            app.OutputFolder = uieditfield(app.HomeTab, 'text');
            app.OutputFolder.ValueChangedFcn = createCallbackFcn(app, @OutputFolderValueChanged, true);
            app.OutputFolderButton = uibutton(app.HomeTab, 'push');
            app.OutputFolderButton.ButtonPushedFcn = createCallbackFcn(app, @OutputFolderButtonPushed, true);
            app.OutputFolderButton.Text = 'Output Folder:';
            app.OpenFolder = uieditfield(app.HomeTab, 'text');
            app.OpenFolder.ValueChangedFcn = createCallbackFcn(app, @OpenFolderValueChanged, true);
            app.CurrentDirButton = uibutton(app.HomeTab, 'push');
            app.CurrentDirButton.ButtonPushedFcn = createCallbackFcn(app, @OpenFolderButtonClicked, true);
            app.CurrentDirButton.Text = 'Current Dir:';
            app.OpenFolderImage_2 = uiimage(app.HomeTab);
            app.OpenFolderImage_2.ImageSource = fullfile(pathToMLAPP, 'icons', 'open.png');
            app.LevelTab = uitab(app.TabGroup);
            app.LevelTab.Title = 'Level';
            app.LevelTab.ButtonDownFcn = createCallbackFcn(app, @LevelTabButtonDown, true);
            app.LevelingPanel = uipanel(app.LevelTab);
            app.LevelingPanel.Title = 'Leveling';
            app.PlaneLabel = uilabel(app.LevelingPanel);
            app.PlaneLabel.Text = 'Plane';
            app.LineLabel = uilabel(app.LevelingPanel);
            app.LineLabel.Text = 'Line';
            app.XLineSpinner = uispinner(app.LevelingPanel);
            app.XLineSpinner.Limits = [0 Inf];
            app.XLineSpinner.ValueChangedFcn = createCallbackFcn(app, @Refresh_Filter, true);
            app.YLineSpinner = uispinner(app.LevelingPanel);
            app.YLineSpinner.Limits = [0 Inf];
            app.YLineSpinner.ValueChangedFcn = createCallbackFcn(app, @Refresh_Filter, true);
            app.XSpinnerLabel = uilabel(app.LevelingPanel);
            app.XSpinnerLabel.Text = 'X';
            app.XPlaneSpinner = uispinner(app.LevelingPanel);
            app.XPlaneSpinner.Limits = [0 Inf];
            app.XPlaneSpinner.ValueChangedFcn = createCallbackFcn(app, @Refresh_Filter, true);
            app.YSpinnerLabel = uilabel(app.LevelingPanel);
            app.YSpinnerLabel.Text = 'Y';
            app.YPlaneSpinner = uispinner(app.LevelingPanel);
            app.YPlaneSpinner.Limits = [0 Inf];
            app.YPlaneSpinner.ValueChangedFcn = createCallbackFcn(app, @Refresh_Filter, true);
            app.MedianCheckBox = uicheckbox(app.LevelingPanel);
            app.MedianCheckBox.ValueChangedFcn = createCallbackFcn(app, @Refresh_Filter, true);
            app.MedianCheckBox.Text = '';
            app.MeanPlaneCheckBox = uicheckbox(app.LevelingPanel);
            app.MeanPlaneCheckBox.ValueChangedFcn = createCallbackFcn(app, @Refresh_Filter, true);
            app.MeanPlaneCheckBox.Text = '';
            app.MeanLabel_2 = uilabel(app.LevelingPanel);
            app.MeanLabel_2.Text = '-Mean';
            app.YSpinnerLabel_4 = uilabel(app.LevelingPanel);
            app.YSpinnerLabel_4.Text = 'Auto:';
            app.AutoflattenDropDown = uidropdown(app.LevelingPanel);
            app.AutoflattenDropDown.Items = {'Off', 'plane-line', 'multi-plane-edges', 'multi-plane-otsu', 'iterative 1nm high', 'iterative -1nm low', 'iterative high low', 'high-low x2 (fit)', 'iterative fit holes', 'iterative fit peaks'};
            app.AutoflattenDropDown.ValueChangedFcn = createCallbackFcn(app, @Refresh_Filter, true);
            app.AutoflattenDropDown.Value = 'Off';
            app.sMedianCheckBox = uicheckbox(app.LevelingPanel);
            app.sMedianCheckBox.ValueChangedFcn = createCallbackFcn(app, @Refresh_Filter, true);
            app.sMedianCheckBox.Text = '';
            app.sMedLabel = uilabel(app.LevelingPanel);
            app.sMedLabel.Text = '-sMed';
            app.MultiPlane = uicheckbox(app.LevelingPanel);
            app.MultiPlane.ValueChangedFcn = createCallbackFcn(app, @Refresh_Filter, true);
            app.MultiPlane.Tooltip = {'wighted fit to multiple planes'};
            app.MultiPlane.Text = '';
            app.MultiplanefitLabel = uilabel(app.LevelingPanel);
            app.MultiplanefitLabel.Text = 'Multi plane fit';
            app.MedianCheckBox_y = uicheckbox(app.LevelingPanel);
            app.MedianCheckBox_y.ValueChangedFcn = createCallbackFcn(app, @Refresh_Filter, true);
            app.MedianCheckBox_y.Text = '';
            app.MedLabel = uilabel(app.LevelingPanel);
            app.MedLabel.Text = 'Med';
            app.ZeroallButton = uibutton(app.LevelTab, 'push');
            app.ZeroallButton.ButtonPushedFcn = createCallbackFcn(app, @ZeroallButtonPushed, true);
            app.ZeroallButton.Text = 'Zero all';
            app.RestoreButton = uibutton(app.LevelTab, 'push');
            app.RestoreButton.ButtonPushedFcn = createCallbackFcn(app, @Reload, true);
            app.RestoreButton.Text = 'Restore';
            app.FixLineShift = uicheckbox(app.LevelTab);
            app.FixLineShift.ValueChangedFcn = createCallbackFcn(app, @Refresh_Filter, true);
            app.FixLineShift.Visible = 'off';
            app.FixLineShift.Text = 'Line shift';
            app.FixShiftMethod = uidropdown(app.LevelTab);
            app.FixShiftMethod.Items = {'Line peaks', 'Line correlation', 'Manual Shift'};
            app.FixShiftMethod.ValueChangedFcn = createCallbackFcn(app, @Refresh_Filter, true);
            app.FixShiftMethod.Visible = 'off';
            app.FixShiftMethod.Value = 'Line peaks';
            app.FixShiftValue = uieditfield(app.LevelTab, 'numeric');
            app.FixShiftValue.ValueChangedFcn = createCallbackFcn(app, @Refresh_Filter, true);
            app.FixShiftValue.Visible = 'off';
            app.MaskingPanel = uipanel(app.LevelTab);
            app.MaskingPanel.Title = 'Masking';
            app.ThresholdMethod = uidropdown(app.MaskingPanel);
            app.ThresholdMethod.Items = {'histogram', 'auto edges', 'hist edges', 'otsu edges', 'otsu skel', 'hist skel', 'otsu', '2 level otsu', 'step detection', 'adaptive', 'parachute'};
            app.ThresholdMethod.ValueChangedFcn = createCallbackFcn(app, @ThresholdMethodValueChanged, true);
            app.ThresholdMethod.Value = 'histogram';
            app.InvertMask = uibutton(app.MaskingPanel, 'state');
            app.InvertMask.ValueChangedFcn = createCallbackFcn(app, @InvertMaskValueChanged, true);
            app.InvertMask.Text = 'Invert';
            app.AnalyzeMaskButton = uibutton(app.MaskingPanel, 'push');
            app.AnalyzeMaskButton.ButtonPushedFcn = createCallbackFcn(app, @MaskAreasMenuSelected, true);
            app.AnalyzeMaskButton.Text = 'Analyze Mask';
            app.ViewMask = uibutton(app.MaskingPanel, 'state');
            app.ViewMask.ValueChangedFcn = createCallbackFcn(app, @ViewMaskValueChanged, true);
            app.ViewMask.Text = 'View Mask';
            app.ViewMask.Value = true;
            app.ImgFilterStrength_4 = uispinner(app.MaskingPanel);
            app.ImgFilterStrength_4.Step = 0.1;
            app.ImgFilterStrength_4.Limits = [0 Inf];
            app.ImgFilterStrength_4.ValueChangedFcn = createCallbackFcn(app, @ImgFilterStrength_4ValueChanged, true);
            app.ImgFilterStrength_4.Visible = 'off';
            app.ImgFilterStrength_5 = uispinner(app.MaskingPanel);
            app.ImgFilterStrength_5.Step = 0.1;
            app.ImgFilterStrength_5.Limits = [0 Inf];
            app.ImgFilterStrength_5.ValueChangedFcn = createCallbackFcn(app, @ImgFilterStrength_4ValueChanged, true);
            app.ImgFilterStrength_5.Visible = 'off';
            app.ImgFilterLabel_4 = uieditfield(app.MaskingPanel, 'text');
            app.ImgFilterLabel_4.Visible = 'off';
            app.ImgFilterLabel_5 = uieditfield(app.MaskingPanel, 'text');
            app.ImgFilterLabel_5.Visible = 'off';
            app.ChannelDropDown_2 = uidropdown(app.MaskingPanel);
            app.ChannelDropDown_2.Items = {'Height'};
            app.ChannelDropDown_2.ValueChangedFcn = createCallbackFcn(app, @ChannelDropDown_2ValueChanged, true);
            app.ChannelDropDown_2.Value = 'Height';
            app.FilteringPanel = uipanel(app.LevelTab);
            app.FilteringPanel.Title = 'Filtering';
            app.ImgFilterStrength = uispinner(app.FilteringPanel);
            app.ImgFilterStrength.Step = 0.1;
            app.ImgFilterStrength.ValueChangingFcn = createCallbackFcn(app, @Refresh_Filter, true);
            app.ImgFilterStrength.Limits = [0 Inf];
            app.ImgFilterStrength.ValueChangedFcn = createCallbackFcn(app, @Refresh_Filter, true);
            app.FilterLabel = uilabel(app.FilteringPanel);
            app.FilterLabel.Text = 'Filter:      ';
            app.ImgFilterStrength_2 = uispinner(app.FilteringPanel);
            app.ImgFilterStrength_2.Step = 0.1;
            app.ImgFilterStrength_2.ValueChangingFcn = createCallbackFcn(app, @Refresh_Filter, true);
            app.ImgFilterStrength_2.Limits = [0 Inf];
            app.ImgFilterStrength_2.ValueChangedFcn = createCallbackFcn(app, @Refresh_Filter, true);
            app.ImgFilterLabel = uieditfield(app.FilteringPanel, 'text');
            app.ImgFilterLabel_2 = uieditfield(app.FilteringPanel, 'text');
            app.SubtractmodeCheckBox = uicheckbox(app.FilteringPanel);
            app.SubtractmodeCheckBox.ValueChangedFcn = createCallbackFcn(app, @Refresh_Filter, true);
            app.SubtractmodeCheckBox.Text = 'Subtract mode';
            app.ImgFilterType = uidropdown(app.FilteringPanel);
            app.ImgFilterType.Items = {'Off', 'Gaussian', 'Median', 'Mean', 'Non-local mean', 'High-pass', 'Top Hat', 'Sliding Mean Frames', 'Sphere Deconvolution', 'Mean All', 'Median all', 'Fill Mask', 'Scar Fill'};
            app.ImgFilterType.ValueChangedFcn = createCallbackFcn(app, @ImgFilterTypeValueChanged, true);
            app.ImgFilterType.Value = 'Off';
            app.ImgFilterStrength_3 = uispinner(app.FilteringPanel);
            app.ImgFilterStrength_3.Step = 0.1;
            app.ImgFilterStrength_3.ValueChangingFcn = createCallbackFcn(app, @Refresh_Filter, true);
            app.ImgFilterStrength_3.Limits = [0 Inf];
            app.ImgFilterStrength_3.ValueChangedFcn = createCallbackFcn(app, @Refresh_Filter, true);
            app.ImgFilterLabel_3 = uieditfield(app.FilteringPanel, 'text');
            app.UndoFilter = uibutton(app.LevelTab, 'push');
            app.UndoFilter.ButtonPushedFcn = createCallbackFcn(app, @UndoFilterPushed, true);
            app.UndoFilter.Text = 'Undo';
            app.Apply = uibutton(app.LevelTab, 'push');
            app.Apply.ButtonPushedFcn = createCallbackFcn(app, @AcceptButtonImageClicked, true);
            app.Apply.Text = 'Apply';
            app.DetectTab = uitab(app.TabGroup);
            app.DetectTab.Title = 'Detect';
            app.DetectTab.ButtonDownFcn = createCallbackFcn(app, @DetectTabButtonDown, true);
            app.FilterCCREditField_Detect = uieditfield(app.DetectTab, 'numeric');
            app.FilterCCREditField_Detect.Limits = [0 Inf];
            app.FilterCCREditField_Detect.Value = 1;
            app.Overlay = uipanel(app.DetectTab);
            app.MarkersCheckBox = uicheckbox(app.Overlay);
            app.MarkersCheckBox.ValueChangedFcn = createCallbackFcn(app, @MarkersCheckBoxValueChanged, true);
            app.MarkersCheckBox.Text = 'Markers';
            app.BoxesCheckBox = uicheckbox(app.Overlay);
            app.BoxesCheckBox.ValueChangedFcn = createCallbackFcn(app, @MarkersCheckBoxValueChanged, true);
            app.BoxesCheckBox.Text = 'Boxes';
            app.BoxesCheckBox.Value = true;
            app.TrackLengthEditFieldLabel = uilabel(app.Overlay);
            app.TrackLengthEditFieldLabel.Text = 'Track Length';
            app.TrackLengthEditField = uieditfield(app.Overlay, 'numeric');
            app.TrackLengthEditField.ValueChangedFcn = createCallbackFcn(app, @MarkersCheckBoxValueChanged, true);
            app.OverlayValues = uidropdown(app.Overlay);
            app.OverlayValues.Items = {'None', 'Height', 'CCR', 'Angle', 'Track Number', 'FWHM'};
            app.OverlayValues.ValueChangedFcn = createCallbackFcn(app, @MarkersCheckBoxValueChanged, true);
            app.OverlayValues.Value = 'None';
            app.SettingsLabel_Detect = uilabel(app.Overlay);
            app.SettingsLabel_Detect.Text = 'Overlay Values:';
            app.TrackDetectionsPanel = uipanel(app.DetectTab);
            app.RunTrackingButton = uibutton(app.TrackDetectionsPanel, 'push');
            app.RunTrackingButton.ButtonPushedFcn = createCallbackFcn(app, @RunTrackingButtonPushed, true);
            app.RunTrackingButton.Text = 'Run Tracking';
            app.MaxmissingEditFieldLabel = uilabel(app.TrackDetectionsPanel);
            app.MaxmissingEditFieldLabel.Text = 'Max missing ';
            app.MaxmissingEditField = uieditfield(app.TrackDetectionsPanel, 'numeric');
            app.MaxmissingEditField.Value = 3;
            app.MaxstepEditFieldLabel = uilabel(app.TrackDetectionsPanel);
            app.MaxstepEditFieldLabel.Text = 'Max step';
            app.MaxstepEditField = uieditfield(app.TrackDetectionsPanel, 'numeric');
            app.MaxstepEditField.Value = 10;
            app.FilterImageLabel = uilabel(app.DetectTab);
            app.FilterImageLabel.Text = 'Filter image';
            app.FilterImgEditField_Detect = uieditfield(app.DetectTab, 'numeric');
            app.FilterImgEditField_Detect.Limits = [0 Inf];
            app.FilterImgEditField_Detect.ValueChangedFcn = createCallbackFcn(app, @TrackLengthEditFieldValueChanged, true);
            app.FilterImgEditField_Detect.Value = 1;
            app.FindParticlesButton = uibutton(app.DetectTab, 'push');
            app.FindParticlesButton.ButtonPushedFcn = createCallbackFcn(app, @FindParticlesButtonPushed, true);
            app.FindParticlesButton.Text = 'Find Particles';
            app.SelectMethodLabel_2 = uilabel(app.DetectTab);
            app.SelectMethodLabel_2.Text = 'Select Method:';
            app.PeaksRegionsSwitch = uiswitch(app.DetectTab, 'slider');
            app.PeaksRegionsSwitch.Items = {'Peaks', 'ROI'};
            app.PeaksRegionsSwitch.ValueChangedFcn = createCallbackFcn(app, @PeaksRegionsSwitchValueChanged, true);
            app.PeaksRegionsSwitch.Value = 'ROI';
            app.BoxSizeEditFieldLabel = uilabel(app.DetectTab);
            app.BoxSizeEditFieldLabel.Visible = 'off';
            app.BoxSizeEditFieldLabel.Text = 'Box Size';
            app.BoxSizeEditField = uieditfield(app.DetectTab, 'numeric');
            app.BoxSizeEditField.Limits = [2 1000];
            app.BoxSizeEditField.Visible = 'off';
            app.BoxSizeEditField.Value = 30;
            app.FilterxcorimageLabel = uilabel(app.DetectTab);
            app.FilterxcorimageLabel.Text = 'Filter xcor image';
            app.ExcludeEdgeCheckBox = uicheckbox(app.DetectTab);
            app.ExcludeEdgeCheckBox.Text = '';
            app.ExcludeEdgeCheckBox.Value = true;
            app.ExcludeedgesLabel = uilabel(app.DetectTab);
            app.ExcludeedgesLabel.Text = 'Exclude edges';
            app.PreviewPeaks = uicheckbox(app.DetectTab);
            app.PreviewPeaks.ValueChangedFcn = createCallbackFcn(app, @TrackLengthEditFieldValueChanged, true);
            app.PreviewPeaks.Visible = 'off';
            app.PreviewPeaks.Text = '';
            app.PreviewpeaksLabel = uilabel(app.DetectTab);
            app.PreviewpeaksLabel.Visible = 'off';
            app.PreviewpeaksLabel.Text = 'Preview peaks';
            app.Peaks_minHeight = uispinner(app.DetectTab);
            app.Peaks_minHeight.Step = 0.1;
            app.Peaks_minHeight.ValueChangedFcn = createCallbackFcn(app, @Peaks_minHeightValueChanged, true);
            app.Peaks_minHeight.Value = -10;
            app.Peaks_minSep = uispinner(app.DetectTab);
            app.Peaks_minSep.Limits = [1 50];
            app.Peaks_minSep.ValueChangedFcn = createCallbackFcn(app, @TrackLengthEditFieldValueChanged, true);
            app.Peaks_minSep.Visible = 'off';
            app.Peaks_minSep.Value = 2;
            app.MinheightLabel = uilabel(app.DetectTab);
            app.MinheightLabel.Text = 'Min height';
            app.MinseparationLabel = uilabel(app.DetectTab);
            app.MinseparationLabel.Visible = 'off';
            app.MinseparationLabel.Text = 'Min separation';
            app.Sq_roi_Button_2 = uibutton(app.DetectTab, 'state');
            app.Sq_roi_Button_2.ValueChangedFcn = createCallbackFcn(app, @Sq_roi_Button_2ValueChanged, true);
            app.Sq_roi_Button_2.Icon = fullfile(pathToMLAPP, 'icons', 'roi_sq.png');
            app.Sq_roi_Button_2.Text = '';
            app.AutoProfileButton = uibutton(app.DetectTab, 'state');
            app.AutoProfileButton.ValueChangedFcn = createCallbackFcn(app, @AutoProfileButtonValueChanged, true);
            app.AutoProfileButton.Visible = 'off';
            app.AutoProfileButton.Text = {'Auto'; 'Profile'};
            app.LineHor = uibutton(app.DetectTab, 'state');
            app.LineHor.Visible = 'off';
            app.LineHor.Text = '---';
            app.LineHor.Value = true;
            app.LineVert = uibutton(app.DetectTab, 'state');
            app.LineVert.Visible = 'off';
            app.LineVert.Text = '|';
            app.LineVert.Value = true;
            app.LineDiag1 = uibutton(app.DetectTab, 'state');
            app.LineDiag1.Visible = 'off';
            app.LineDiag1.Text = '/';
            app.LineDiag1.Value = true;
            app.LineDiag2 = uibutton(app.DetectTab, 'state');
            app.LineDiag2.Visible = 'off';
            app.LineDiag2.Text = '\';
            app.LineDiag2.Value = true;
            app.OverlaylinesCheckBox = uicheckbox(app.DetectTab);
            app.OverlaylinesCheckBox.ValueChangedFcn = createCallbackFcn(app, @OverlaylinesCheckBoxValueChanged, true);
            app.OverlaylinesCheckBox.Visible = 'off';
            app.OverlaylinesCheckBox.Text = 'Overlay lines';
            app.FWHMreferenceDropDownLabel = uilabel(app.DetectTab);
            app.FWHMreferenceDropDownLabel.Visible = 'off';
            app.FWHMreferenceDropDownLabel.Text = 'FWHM reference';
            app.FWHMreferenceDropDown = uidropdown(app.DetectTab);
            app.FWHMreferenceDropDown.Items = {'z = 0', 'local height'};
            app.FWHMreferenceDropDown.Visible = 'off';
            app.FWHMreferenceDropDown.Value = 'z = 0';
            app.MaxradiusEditField = uieditfield(app.DetectTab, 'numeric');
            app.MaxradiusEditField.Limits = [2 Inf];
            app.MaxradiusEditField.RoundFractionalValues = 'on';
            app.MaxradiusEditField.Visible = 'off';
            app.MaxradiusEditField.Value = 10;
            app.LinelengthLabel = uilabel(app.DetectTab);
            app.LinelengthLabel.Visible = 'off';
            app.LinelengthLabel.Text = 'Line length';
            app.FastFindCheckBox = uicheckbox(app.DetectTab);
            app.FastFindCheckBox.Text = {'Fast'; 'Find'};
            app.FastFindCheckBox.Value = true;
            app.BinRangeImage_2 = uiimage(app.DetectTab);
            app.BinRangeImage_2.ImageClickedFcn = createCallbackFcn(app, @BinRangeImage_2Clicked, true);
            app.BinRangeImage_2.Tooltip = {'delete filtered detections'};
            app.BinRangeImage_2.ImageSource = fullfile(pathToMLAPP, 'icons', 'bin.png');
            app.MaxstepEditFieldLabel_3 = uilabel(app.DetectTab);
            app.MaxstepEditFieldLabel_3.Text = 'Delete detections:';
            app.MinLabel_2 = uilabel(app.DetectTab);
            app.MinLabel_2.Text = 'Min';
            app.MaxLabel_2 = uilabel(app.DetectTab);
            app.MaxLabel_2.Text = 'Max';
            app.Max_CCR_Edit = uieditfield(app.DetectTab, 'numeric');
            app.Max_CCR_Edit.Limits = [-1 1];
            app.Max_CCR_Edit.Value = 1;
            app.Min_CCR_Edit = uieditfield(app.DetectTab, 'numeric');
            app.Min_CCR_Edit.Limits = [-1 1];
            app.Min_CCR_Edit.ValueChangedFcn = createCallbackFcn(app, @Min_CCR_EditValueChanged, true);
            app.Min_CCR_Edit.Value = 0.6;
            app.MaxstepEditFieldLabel_2 = uilabel(app.DetectTab);
            app.MaxstepEditFieldLabel_2.Text = 'Correlation limits:';
            app.FineAlignTab = uitab(app.TabGroup);
            app.FineAlignTab.Title = 'FineAlign';
            app.FineAlignTab.ButtonDownFcn = createCallbackFcn(app, @FineAlignTabButtonDown, true);
            app.Panel_3 = uipanel(app.FineAlignTab);
            app.searchangleEditFieldLabel = uilabel(app.Panel_3);
            app.searchangleEditFieldLabel.Text = 'search angle +/- ';
            app.searchangleEditField = uieditfield(app.Panel_3, 'numeric');
            app.searchangleEditField.Limits = [0 Inf];
            app.searchangleEditField.Value = 90;
            app.RotationalLabel = uilabel(app.Panel_3);
            app.RotationalLabel.Text = 'Rotational';
            app.IterationsEditField_2Label = uilabel(app.Panel_3);
            app.IterationsEditField_2Label.Text = 'Iterations';
            app.Rot_IterationsEditField = uieditfield(app.Panel_3, 'numeric');
            app.Rot_IterationsEditField.Limits = [0 Inf];
            app.TranslationalLabel = uilabel(app.Panel_3);
            app.TranslationalLabel.Text = 'Translational';
            app.IterationsEditField_3Label = uilabel(app.Panel_3);
            app.IterationsEditField_3Label.Text = 'Iterations';
            app.Tran_IterationsEditField = uieditfield(app.Panel_3, 'numeric');
            app.Tran_IterationsEditField.Limits = [0 Inf];
            app.Tran_IterationsEditField.Value = 1;
            app.MaxdriftEditField_2Label = uilabel(app.Panel_3);
            app.MaxdriftEditField_2Label.Text = 'Max drift';
            app.MaxdriftEditField_2 = uieditfield(app.Panel_3, 'numeric');
            app.MaxdriftEditField_2.Limits = [0 Inf];
            app.MaxdriftEditField_2.Value = 5;
            app.AutoupdatereferenceimageCheckBox = uicheckbox(app.Panel_3);
            app.AutoupdatereferenceimageCheckBox.Text = 'Auto update reference image';
            app.RunFineAlignButton = uibutton(app.Panel_3, 'push');
            app.RunFineAlignButton.ButtonPushedFcn = createCallbackFcn(app, @RunFineAlignButtonPushed, true);
            app.RunFineAlignButton.Text = 'Run Align';
            app.ThresholdwithminheightCheckBox = uicheckbox(app.Panel_3);
            app.ThresholdwithminheightCheckBox.Text = 'Threshold with min height';
            app.Translat_Method = uidropdown(app.Panel_3);
            app.Translat_Method.Items = {'Cross corr', 'FFT cross'};
            app.Translat_Method.Value = 'Cross corr';
            app.Rota_Method = uidropdown(app.Panel_3);
            app.Rota_Method.Items = {'Rotation corr', 'Polar Corr'};
            app.Rota_Method.Value = 'Rotation corr';
            app.Undo_FineAlignButton = uibutton(app.Panel_3, 'push');
            app.Undo_FineAlignButton.ButtonPushedFcn = createCallbackFcn(app, @Undo_FineAlignButtonPushed, true);
            app.Undo_FineAlignButton.Text = 'Undo';
            app.RoiareaEditFieldLabel = uilabel(app.FineAlignTab);
            app.RoiareaEditFieldLabel.Text = '+/- Roi area';
            app.RoiareaEditField = uieditfield(app.FineAlignTab, 'numeric');
            app.ApplyButton = uibutton(app.FineAlignTab, 'push');
            app.ApplyButton.ButtonPushedFcn = createCallbackFcn(app, @ApplyButtonPushed, true);
            app.ApplyButton.Text = 'Apply';
            app.TrackList = uilistbox(app.FineAlignTab);
            app.TrackList.Items = {};
            app.TrackList.ValueChangedFcn = createCallbackFcn(app, @TrackListValueChanged, true);
            app.TrackList.Value = {};
            app.SelectTrackButton = uibutton(app.FineAlignTab, 'state');
            app.SelectTrackButton.ValueChangedFcn = createCallbackFcn(app, @SelectTrackButtonValueChanged, true);
            app.SelectTrackButton.Text = {'Select'; 'Track'};
            app.RecalculatecorrelationButton = uibutton(app.FineAlignTab, 'push');
            app.RecalculatecorrelationButton.ButtonPushedFcn = createCallbackFcn(app, @RecalculatecorrelationButtonPushed, true);
            app.RecalculatecorrelationButton.Text = 'Recalculate correlation';
            app.LocalizeTab = uitab(app.TabGroup);
            app.LocalizeTab.Title = 'Localize';
            app.Localize_FilterPanel = uipanel(app.LocalizeTab);
            app.HighpassDropDownLabel = uilabel(app.Localize_FilterPanel);
            app.HighpassDropDownLabel.Text = 'High-pass';
            app.LoczHighpassDropDown = uidropdown(app.Localize_FilterPanel);
            app.LoczHighpassDropDown.Items = {'Off', 'Laplacian', 'Deconv', 'Peak sharp', 'ButterHP', '-Average', '-Gaussian'};
            app.LoczHighpassDropDown.ValueChangedFcn = createCallbackFcn(app, @TrackLengthEditFieldValueChanged, true);
            app.LoczHighpassDropDown.Value = 'Off';
            app.LowpassDropDownLabel = uilabel(app.Localize_FilterPanel);
            app.LowpassDropDownLabel.Text = 'Low-pass';
            app.LoczLowpassDropDown = uidropdown(app.Localize_FilterPanel);
            app.LoczLowpassDropDown.Items = {'Off', 'Gaussian', 'Sphere', 'Wiener', 'Non-local-means', 'ButterLP'};
            app.LoczLowpassDropDown.ValueChangedFcn = createCallbackFcn(app, @TrackLengthEditFieldValueChanged, true);
            app.LoczLowpassDropDown.Value = 'Gaussian';
            app.LoczLowpass = uispinner(app.Localize_FilterPanel);
            app.LoczLowpass.Step = 0.1;
            app.LoczLowpass.Limits = [0 Inf];
            app.LoczLowpass.ValueChangedFcn = createCallbackFcn(app, @TrackLengthEditFieldValueChanged, true);
            app.LoczHighpass = uispinner(app.Localize_FilterPanel);
            app.LoczHighpass.Step = 0.1;
            app.LoczHighpass.Limits = [0 Inf];
            app.LoczHighpass.ValueChangedFcn = createCallbackFcn(app, @TrackLengthEditFieldValueChanged, true);
            app.LocFilterLabel = uilabel(app.Localize_FilterPanel);
            app.LocFilterLabel.Text = 'LocFilter';
            app.LoczShowfilterCheckBox = uicheckbox(app.Localize_FilterPanel);
            app.LoczShowfilterCheckBox.ValueChangedFcn = createCallbackFcn(app, @TrackLengthEditFieldValueChanged, true);
            app.LoczShowfilterCheckBox.Text = 'See filter';
            app.ThresholdLabel = uilabel(app.Localize_FilterPanel);
            app.ThresholdLabel.Text = 'Min separation';
            app.LoczMinSep = uispinner(app.Localize_FilterPanel);
            app.LoczMinSep.Limits = [1 50];
            app.LoczMinSep.ValueChangedFcn = createCallbackFcn(app, @TrackLengthEditFieldValueChanged, true);
            app.LoczMinSep.Value = 1;
            app.ThresholdLabel_3 = uilabel(app.Localize_FilterPanel);
            app.ThresholdLabel_3.Text = 'Threshold';
            app.ThresholdLabel_2 = uilabel(app.Localize_FilterPanel);
            app.ThresholdLabel_2.Text = 'Height';
            app.LoczHeightSpinner = uispinner(app.Localize_FilterPanel);
            app.LoczHeightSpinner.Step = 0.1;
            app.LoczHeightSpinner.ValueChangedFcn = createCallbackFcn(app, @TrackLengthEditFieldValueChanged, true);
            app.ProminenceSpinnerLabel = uilabel(app.Localize_FilterPanel);
            app.ProminenceSpinnerLabel.Text = 'Prominence ';
            app.LoczProminenceSpinner = uispinner(app.Localize_FilterPanel);
            app.LoczProminenceSpinner.Step = 0.1;
            app.LoczProminenceSpinner.ValueChangedFcn = createCallbackFcn(app, @TrackLengthEditFieldValueChanged, true);
            app.LoczImagepeaksCheckBox = uicheckbox(app.Localize_FilterPanel);
            app.LoczImagepeaksCheckBox.ValueChangedFcn = createCallbackFcn(app, @LoczImagepeaksCheckBoxValueChanged, true);
            app.LoczImagepeaksCheckBox.Text = 'Preview peaks';
            app.AutoButton_2 = uibutton(app.Localize_FilterPanel, 'push');
            app.AutoButton_2.ButtonPushedFcn = createCallbackFcn(app, @AutoButton_2Pushed, true);
            app.AutoButton_2.Visible = 'off';
            app.AutoButton_2.Text = 'Auto';
            app.BoostsubpixCheckBox = uicheckbox(app.Localize_FilterPanel);
            app.BoostsubpixCheckBox.Text = 'Boost subpix';
            app.FindallpeaksButton = uibutton(app.LocalizeTab, 'push');
            app.FindallpeaksButton.ButtonPushedFcn = createCallbackFcn(app, @FindallpeaksButtonPushed, true);
            app.FindallpeaksButton.Text = 'Find all peaks';
            app.RenderLAFMButton = uibutton(app.LocalizeTab, 'push');
            app.RenderLAFMButton.ButtonPushedFcn = createCallbackFcn(app, @RenderLAFMButtonPushed, true);
            app.RenderLAFMButton.Text = 'Render LAFM';
            app.RenderpointsLabel = uilabel(app.LocalizeTab);
            app.RenderpointsLabel.Text = 'Render point size';
            app.LAFM_gus = uieditfield(app.LocalizeTab, 'numeric');
            app.LAFM_gus.Limits = [0.1 50];
            app.LAFM_gus.Value = 1;
            app.imageexpansionLabel = uilabel(app.LocalizeTab);
            app.imageexpansionLabel.Text = 'Pixel expansion';
            app.LAFM_exp = uieditfield(app.LocalizeTab, 'numeric');
            app.LAFM_exp.Limits = [1 50];
            app.LAFM_exp.Value = 5;
            app.RenderLAFMMovieButton = uibutton(app.LocalizeTab, 'push');
            app.RenderLAFMMovieButton.ButtonPushedFcn = createCallbackFcn(app, @RenderLAFMMovieButtonPushed, true);
            app.RenderLAFMMovieButton.Text = 'Render LAFM Movie';
            app.PointCloudAlignButton = uibutton(app.LocalizeTab, 'push');
            app.PointCloudAlignButton.ButtonPushedFcn = createCallbackFcn(app, @PointCloudAlignButtonPushed, true);
            app.PointCloudAlignButton.Text = 'Point Cloud Align';
            app.LocalizationMethodLabel = uilabel(app.LocalizeTab);
            app.LocalizationMethodLabel.Text = 'Localization Method:';
            app.LocalizationMethodDropDown = uidropdown(app.LocalizeTab);
            app.LocalizationMethodDropDown.Items = {'bicubic', 'sphere', 'gaussian'};
            app.LocalizationMethodDropDown.Value = 'bicubic';
            app.ParticlespermapEditFieldLabel = uilabel(app.LocalizeTab);
            app.ParticlespermapEditFieldLabel.Text = 'Particles per map';
            app.ParticlespermapEditField = uieditfield(app.LocalizeTab, 'numeric');
            app.ParticlespermapEditField.Value = 50;
            app.SlidewindowEditFieldLabel = uilabel(app.LocalizeTab);
            app.SlidewindowEditFieldLabel.Text = 'Slide window ';
            app.SlidewindowEditField = uieditfield(app.LocalizeTab, 'numeric');
            app.SlidewindowEditField.Value = 50;
            app.FRCresolutionButton = uibutton(app.LocalizeTab, 'push');
            app.FRCresolutionButton.ButtonPushedFcn = createCallbackFcn(app, @FRCResolutionButtonPushed, true);
            app.FRCresolutionButton.Text = 'FRC resolution';
            app.AutoButton_3 = uibutton(app.LocalizeTab, 'push');
            app.AutoButton_3.ButtonPushedFcn = createCallbackFcn(app, @AutoButton_3Pushed, true);
            app.AutoButton_3.Text = 'Auto';
            app.DeleteoutliersEditFieldLabel = uilabel(app.LocalizeTab);
            app.DeleteoutliersEditFieldLabel.Text = 'Delete outliers';
            app.DeleteoutliersEditField = uieditfield(app.LocalizeTab, 'numeric');
            app.DeleteoutliersEditField.Limits = [0 Inf];
            app.DeleteoutliersEditField.Value = 4;
            app.BinRangeImage_3 = uiimage(app.LocalizeTab);
            app.BinRangeImage_3.ImageClickedFcn = createCallbackFcn(app, @BinRangeImage_3Clicked, true);
            app.BinRangeImage_3.Tooltip = {'delete filtered detections'};
            app.BinRangeImage_3.ImageSource = fullfile(pathToMLAPP, 'icons', 'bin.png');
            app.View1stCheckBox = uicheckbox(app.LeftPanel);
            app.View1stCheckBox.ValueChangedFcn = createCallbackFcn(app, @Reload, true);
            app.View1stCheckBox.Text = 'Load         frames';
            app.View1st = uieditfield(app.LeftPanel, 'numeric');
            app.View1st.Limits = [1 Inf];
            app.View1st.ValueChangedFcn = createCallbackFcn(app, @Reload, true);
            app.View1st.Value = 10;
            app.FilesDropDown = uidropdown(app.LeftPanel);
            app.FilesDropDown.Items = {'All files', 'Processed'};
            app.FilesDropDown.ValueChangedFcn = createCallbackFcn(app, @FilesDropDownValueChanged, true);
            app.FilesDropDown.Value = 'All files';
            app.CancelButton = uibutton(app.LeftPanel, 'push');
            app.CancelButton.ButtonPushedFcn = createCallbackFcn(app, @CancelButtonPushed, true);
            app.CancelButton.Visible = 'off';
            app.CancelButton.Text = 'Cancel';
            app.ChannelDropDown = uidropdown(app.LeftPanel);
            app.ChannelDropDown.Items = {'Height'};
            app.ChannelDropDown.ValueChangedFcn = createCallbackFcn(app, @FilesListBoxValueChanged, true);
            app.ChannelDropDown.Value = 'Height';
            app.ErrorButton = uibutton(app.LeftPanel, 'state');
            app.ErrorButton.ValueChangedFcn = createCallbackFcn(app, @FilesListBoxValueChanged, true);
            app.ErrorButton.Visible = 'off';
            app.ErrorButton.Text = 'Error';
            app.Stacker = uidropdown(app.LeftPanel);
            app.Stacker.Items = {'Stack off', 'Stack', 'Intercalate'};
            app.Stacker.Value = 'Stack off';
            app.FolderUp = uiimage(app.LeftPanel);
            app.FolderUp.ImageClickedFcn = createCallbackFcn(app, @FolderUpImageClicked, true);
            app.FolderUp.Tooltip = {'Move one folder above current directory'};
            app.FolderUp.ImageSource = fullfile(pathToMLAPP, 'icons', 'up.png');
            app.FolderDown = uiimage(app.LeftPanel);
            app.FolderDown.ImageClickedFcn = createCallbackFcn(app, @FolderDownImageClicked, true);
            app.FolderDown.Tooltip = {'Open folder in file list'};
            app.FolderDown.ImageSource = fullfile(pathToMLAPP, 'icons', 'down.png');
            app.OpenFolderImage = uiimage(app.LeftPanel);
            app.OpenFolderImage.ImageClickedFcn = createCallbackFcn(app, @OpenFolderButtonClicked, true);
            app.OpenFolderImage.ImageSource = fullfile(pathToMLAPP, 'icons', 'open.png');
            app.Image_2 = uiimage(app.LeftPanel);
            app.Image_2.ImageClickedFcn = createCallbackFcn(app, @SaveSessionImageClicked, true);
            app.Image_2.ImageSource = fullfile(pathToMLAPP, 'icons', 'save.png');
            app.Lamp = uilamp(app.LeftPanel);
            app.Lamp.Enable = 'off';
            app.AutoplayCheckBox = uicheckbox(app.LeftPanel);
            app.AutoplayCheckBox.Text = 'Auto play';
            app.PreviewonoffButton = uibutton(app.LeftPanel, 'state');
            app.PreviewonoffButton.ValueChangedFcn = createCallbackFcn(app, @PreviewonoffButtonValueChanged, true);
            app.PreviewonoffButton.Text = 'Preview on/off';
            app.HistogramDropDown = uidropdown(app.LeftPanel);
            app.HistogramDropDown.Items = {'Mask z (all)', 'Mask z (frame)'};
            app.HistogramDropDown.ValueChangedFcn = createCallbackFcn(app, @HistogramDropDownValueChanged, true);
            app.HistogramDropDown.Value = 'Mask z (all)';
            app.ResetButton_2 = uibutton(app.LeftPanel, 'push');
            app.ResetButton_2.ButtonPushedFcn = createCallbackFcn(app, @ResetButton_2Pushed, true);
            app.ResetButton_2.Text = 'Reset';
            app.FitButton = uibutton(app.LeftPanel, 'push');
            app.FitButton.ButtonPushedFcn = createCallbackFcn(app, @FitButtonPushed, true);
            app.FitButton.Text = 'Fit';
            app.Hist_excludeOut = uibutton(app.LeftPanel, 'state');
            app.Hist_excludeOut.ValueChangedFcn = createCallbackFcn(app, @Hist_excludeOutValueChanged, true);
            app.Hist_excludeOut.Text = 'Zoom';
            app.LogYHist = uibutton(app.LeftPanel, 'state');
            app.LogYHist.ValueChangedFcn = createCallbackFcn(app, @LogYHistValueChanged, true);
            app.LogYHist.Text = 'Log y';
            app.PopHist = uibutton(app.LeftPanel, 'push');
            app.PopHist.ButtonPushedFcn = createCallbackFcn(app, @PopHistButtonPushed, true);
            app.PopHist.Icon = fullfile(pathToMLAPP, 'icons', 'pop.png');
            app.PopHist.Text = '';
            app.CenterPanel = uipanel(app.NanoLocz);
            app.UIAxes = uiaxes(app.CenterPanel);
            app.UIAxes.XTick = [];
            app.UIAxes.XTickLabel = '';
            app.UIAxes.YTick = [];
            app.UIAxes.YTickLabel = '';
            colormap(app.UIAxes, 'hot')
            app.UIAxes_2 = uiaxes(app.CenterPanel);
            app.UIAxes_2.XTick = [];
            app.UIAxes_2.XTickLabel = '';
            app.UIAxes_2.YTick = [];
            app.UIAxes_2.YTickLabel = '';
            colormap(app.UIAxes_2, 'hot')
            app.Sq_roi_Button = uibutton(app.CenterPanel, 'state');
            app.Sq_roi_Button.ValueChangedFcn = createCallbackFcn(app, @Sq_roi_ButtonValueChanged, true);
            app.Sq_roi_Button.Tooltip = {'Draw square'};
            app.Sq_roi_Button.Icon = fullfile(pathToMLAPP, 'icons', 'roi_sq.png');
            app.Sq_roi_Button.Text = '';
            app.Line_roi_Button = uibutton(app.CenterPanel, 'state');
            app.Line_roi_Button.ValueChangedFcn = createCallbackFcn(app, @Line_roi_ButtonValueChanged, true);
            app.Line_roi_Button.Tooltip = {'Draw line'};
            app.Line_roi_Button.Icon = fullfile(pathToMLAPP, 'icons', 'line.png');
            app.Line_roi_Button.Text = '';
            app.Point_roi_Button = uibutton(app.CenterPanel, 'state');
            app.Point_roi_Button.ValueChangedFcn = createCallbackFcn(app, @Point_roi_ButtonValueChanged, true);
            app.Point_roi_Button.Tooltip = {'Data tips'};
            app.Point_roi_Button.Icon = fullfile(pathToMLAPP, 'icons', 'multi_point.png');
            app.Point_roi_Button.Text = '';
            app.CropButton = uibutton(app.CenterPanel, 'push');
            app.CropButton.ButtonPushedFcn = createCallbackFcn(app, @CropButtonPushed, true);
            app.CropButton.Icon = fullfile(pathToMLAPP, 'icons', 'crop.png');
            app.CropButton.Tooltip = {'Crop'};
            app.CropButton.Text = '';
            app.ZoomIn = uibutton(app.CenterPanel, 'state');
            app.ZoomIn.ValueChangedFcn = createCallbackFcn(app, @ZoomInValueChanged, true);
            app.ZoomIn.Tooltip = {'Zoom'};
            app.ZoomIn.Icon = fullfile(pathToMLAPP, 'icons', 'zoomin.png');
            app.ZoomIn.Text = '';
            app.kymo = uibutton(app.CenterPanel, 'push');
            app.kymo.ButtonPushedFcn = createCallbackFcn(app, @kymoButtonPushed, true);
            app.kymo.Icon = fullfile(pathToMLAPP, 'icons', 'kymo.png');
            app.kymo.Tooltip = {'Kymograph from line profile'};
            app.kymo.Text = '';
            app.MontageButton = uibutton(app.CenterPanel, 'push');
            app.MontageButton.ButtonPushedFcn = createCallbackFcn(app, @MontageButtonPushed, true);
            app.MontageButton.Icon = fullfile(pathToMLAPP, 'icons', 'montage.png');
            app.MontageButton.Tooltip = {'Image montage'};
            app.MontageButton.Text = '';
            app.ZPanel = uipanel(app.CenterPanel);
            app.ZScaleDropBox = uidropdown(app.ZPanel);
            app.ZScaleDropBox.Items = {'Max Min', 'Exc outliers', 'Histogram', 'Manual'};
            app.ZScaleDropBox.ValueChangedFcn = createCallbackFcn(app, @TrackLengthEditFieldValueChanged, true);
            app.ZScaleDropBox.Value = 'Max Min';
            app.maxSpinnerLabel = uilabel(app.ZPanel);
            app.maxSpinnerLabel.Text = 'max';
            app.ContrastMaxSpinner = uispinner(app.ZPanel);
            app.ContrastMaxSpinner.Step = 0.25;
            app.ContrastMaxSpinner.ValueChangedFcn = createCallbackFcn(app, @HoldButtonPushed, true);
            app.minSpinnerLabel = uilabel(app.ZPanel);
            app.minSpinnerLabel.Text = 'min';
            app.ContrastMinSpinner = uispinner(app.ZPanel);
            app.ContrastMinSpinner.Step = 0.25;
            app.ContrastMinSpinner.ValueChangedFcn = createCallbackFcn(app, @HoldButtonPushed, true);
            app.AutoButton = uibutton(app.ZPanel, 'push');
            app.AutoButton.ButtonPushedFcn = createCallbackFcn(app, @AutoButtonPushed, true);
            app.AutoButton.Text = 'Auto';
            app.HoldButton = uibutton(app.ZPanel, 'push');
            app.HoldButton.ButtonPushedFcn = createCallbackFcn(app, @HoldButtonPushed, true);
            app.HoldButton.Text = 'Hold';
            app.PopAx1 = uibutton(app.CenterPanel, 'push');
            app.PopAx1.ButtonPushedFcn = createCallbackFcn(app, @PopAx1ButtonPushed, true);
            app.PopAx1.Icon = fullfile(pathToMLAPP, 'icons', 'pop.png');
            app.PopAx1.Text = '';
            app.Forceto11Button = uibutton(app.CenterPanel, 'push');
            app.Forceto11Button.ButtonPushedFcn = createCallbackFcn(app, @Forceto11ButtonPushed, true);
            app.Forceto11Button.Text = 'Force to 1:1';
            app.ColorBarCheckBox = uicheckbox(app.CenterPanel);
            app.ColorBarCheckBox.ValueChangedFcn = createCallbackFcn(app, @ColorBarCheckBoxValueChanged, true);
            app.ColorBarCheckBox.Text = 'z-scale';
            app.ViewpixelsDropDown = uidropdown(app.CenterPanel);
            app.ViewpixelsDropDown.Items = {'Original', 'Interpolated'};
            app.ViewpixelsDropDown.ValueChangedFcn = createCallbackFcn(app, @TrackLengthEditFieldValueChanged, true);
            app.ViewpixelsDropDown.Value = 'Original';
            app.LeftPlotList = uidropdown(app.CenterPanel);
            app.LeftPlotList.Items = {'Target', 'Preview'};
            app.LeftPlotList.Value = 'Target';
            app.AspectRatioSwitch = uiswitch(app.CenterPanel, 'slider');
            app.AspectRatioSwitch.Items = {'Original', '1:1'};
            app.AspectRatioSwitch.ValueChangedFcn = createCallbackFcn(app, @AspectRatioSwitchValueChanged, true);
            app.AspectRatioSwitch.Value = 'Original';
            app.ScalebarCheckBox = uicheckbox(app.CenterPanel);
            app.ScalebarCheckBox.ValueChangedFcn = createCallbackFcn(app, @TrackLengthEditFieldValueChanged, true);
            app.ScalebarCheckBox.Tooltip = {''};
            app.ScalebarCheckBox.Text = 'Scale bar';
            app.OverlayDropDown = uidropdown(app.CenterPanel);
            app.OverlayDropDown.Items = {'None', 'Mask', 'Line Profile', 'Detections', 'Localizations'};
            app.OverlayDropDown.ValueChangedFcn = createCallbackFcn(app, @TrackLengthEditFieldValueChanged, true);
            app.OverlayDropDown.Value = 'Mask';
            app.PlotTypeDropDown = uidropdown(app.CenterPanel);
            app.PlotTypeDropDown.Items = {'2D surface', '3D surface', 'Coordinates'};
            app.PlotTypeDropDown.ValueChangedFcn = createCallbackFcn(app, @PlotTypeDropDownValueChanged, true);
            app.PlotTypeDropDown.Value = '2D surface';
            app.ColourmapDropDown = uidropdown(app.CenterPanel);
            app.ColourmapDropDown.Items = {'AFM brown', 'AFM dark gold', 'AFM gold', 'fire', 'magma', 'inferno', 'plasma', 'viridis', 'Rainbow', 'gray', 'hot', 'cool', 'Orange hot', 'jet', 'turbo', 'parula', 'hsv', 'winter', 'spring', 'summer', 'autumn'};
            app.ColourmapDropDown.ValueChangedFcn = createCallbackFcn(app, @TrackLengthEditFieldValueChanged, true);
            app.ColourmapDropDown.Value = 'AFM brown';
            app.VideoPanel = uipanel(app.CenterPanel);
            app.FrameSlider = uislider(app.VideoPanel);
            app.FrameSlider.Limits = [1 10];
            app.FrameSlider.MajorTicks = [1 10];
            app.FrameSlider.MajorTickLabels = {'1', '10'};
            app.FrameSlider.ValueChangingFcn = createCallbackFcn(app, @FrameSliderValueChanging, true);
            app.FrameSlider.Value = 1;
            app.Spinner = uispinner(app.VideoPanel);
            app.Spinner.Limits = [1 Inf];
            app.Spinner.ValueChangedFcn = createCallbackFcn(app, @SpinnerValueChanged, true);
            app.Spinner.Value = 1;
            app.fpsEditFieldLabel = uilabel(app.VideoPanel);
            app.fpsEditFieldLabel.Text = 'fps';
            app.fps = uieditfield(app.VideoPanel, 'numeric');
            app.fps.Limits = [0.1 50];
            app.fps.Value = 10;
            app.PlayImage = uiimage(app.VideoPanel);
            app.PlayImage.ImageClickedFcn = createCallbackFcn(app, @PlayImageClicked, true);
            app.PlayImage.ImageSource = fullfile(pathToMLAPP, 'icons', 'play.png');
            app.PauseImage = uiimage(app.VideoPanel);
            app.PauseImage.ImageClickedFcn = createCallbackFcn(app, @PauseImageClicked, true);
            app.PauseImage.Visible = 'off';
            app.PauseImage.ImageSource = fullfile(pathToMLAPP, 'icons', 'pause.png');
            app.EndImage = uiimage(app.VideoPanel);
            app.EndImage.ImageClickedFcn = createCallbackFcn(app, @EndImageClicked, true);
            app.EndImage.ImageSource = fullfile(pathToMLAPP, 'icons', 'end.png');
            app.StartImage = uiimage(app.VideoPanel);
            app.StartImage.ImageClickedFcn = createCallbackFcn(app, @StartImageClicked, true);
            app.StartImage.ImageSource = fullfile(pathToMLAPP, 'icons', 'start.png');
            app.BinImage = uiimage(app.VideoPanel);
            app.BinImage.ImageClickedFcn = createCallbackFcn(app, @BinImageClicked, true);
            app.BinImage.ImageSource = fullfile(pathToMLAPP, 'icons', 'bin.png');
            app.BinRangeImage = uiimage(app.VideoPanel);
            app.BinRangeImage.ImageClickedFcn = createCallbackFcn(app, @BinRangeImageClicked, true);
            app.BinRangeImage.ImageSource = fullfile(pathToMLAPP, 'icons', 'bin.png');
            app.RemoveframesEditField = uieditfield(app.VideoPanel, 'text');
            app.DeleterangeLabel = uilabel(app.VideoPanel);
            app.DeleterangeLabel.Text = 'Delete range:';
            app.VideoAlignButton = uibutton(app.VideoPanel, 'push');
            app.VideoAlignButton.ButtonPushedFcn = createCallbackFcn(app, @RunAlignButtonPushed, true);
            app.VideoAlignButton.Text = 'Video Align';
            app.RightPlotList = uidropdown(app.CenterPanel);
            app.RightPlotList.Items = {'Off'};
            app.RightPlotList.ValueChangedFcn = createCallbackFcn(app, @RightPlotListValueChanged, true);
            app.RightPlotList.Value = 'Off';
            app.PopAx2 = uibutton(app.CenterPanel, 'push');
            app.PopAx2.ButtonPushedFcn = createCallbackFcn(app, @PopAx2Pushed, true);
            app.PopAx2.Icon = fullfile(pathToMLAPP, 'icons', 'pop.png');
            app.PopAx2.Text = '';
            app.LineProfilePanel = uipanel(app.CenterPanel);
            app.LineProfilePanel.Visible = 'off';
            app.AlignButton = uibutton(app.LineProfilePanel, 'push');
            app.AlignButton.ButtonPushedFcn = createCallbackFcn(app, @AlignButtonPushed, true);
            app.AlignButton.Text = 'Align';
            app.Live = uibutton(app.LineProfilePanel, 'state');
            app.Live.Text = 'Live';
            app.Live.Value = true;
            app.AlignLineType = uidropdown(app.LineProfilePanel);
            app.AlignLineType.Items = {'Correlation', 'Max'};
            app.AlignLineType.Value = 'Max';
            app.FWHMCheckBox = uicheckbox(app.LineProfilePanel);
            app.FWHMCheckBox.Text = 'FWHM';
            app.LineThicknessSpinnerLabel = uilabel(app.LineProfilePanel);
            app.LineThicknessSpinnerLabel.Text = 'Line Thickness';
            app.LineThickness = uispinner(app.LineProfilePanel);
            app.LineThickness.Limits = [1 100];
            app.LineThickness.ValueChangedFcn = createCallbackFcn(app, @LineThicknessValueChanged, true);
            app.LineThickness.Value = 1;
            app.LineFFTButton = uibutton(app.LineProfilePanel, 'push');
            app.LineFFTButton.ButtonPushedFcn = createCallbackFcn(app, @LineFFTButtonPushed, true);
            app.LineFFTButton.Text = {'Line'; 'FFT'};
            app.TabGroup2 = uitabgroup(app.CenterPanel);
            app.LineTab = uitab(app.TabGroup2);
            app.LineTab.Title = 'Line';
            app.UITable2 = uitable(app.LineTab);
            app.UITable2.ColumnName = '';
            app.UITable2.RowName = {};
            app.ReferenceTab = uitab(app.TabGroup2);
            app.ReferenceTab.Title = 'Reference';
            app.RefCropButton = uibutton(app.ReferenceTab, 'push');
            app.RefCropButton.ButtonPushedFcn = createCallbackFcn(app, @RefCropButtonPushed, true);
            app.RefCropButton.Icon = fullfile(pathToMLAPP, 'icons', 'crop.png');
            app.RefCropButton.Tooltip = {'Crop Reference'};
            app.RefCropButton.Text = '';
            app.ResettrimButton = uibutton(app.ReferenceTab, 'push');
            app.ResettrimButton.ButtonPushedFcn = createCallbackFcn(app, @ResettrimButtonPushed, true);
            app.ResettrimButton.Text = {'Reset'; 'trim'};
            app.RefListBox = uilistbox(app.ReferenceTab);
            app.RefListBox.Items = {};
            app.RefListBox.Multiselect = 'on';
            app.RefListBox.ValueChangedFcn = createCallbackFcn(app, @RefListBoxValueChanged, true);
            app.RefListBox.Value = {};
            app.ImportSimulationButton = uibutton(app.ReferenceTab, 'push');
            app.ImportSimulationButton.ButtonPushedFcn = createCallbackFcn(app, @OpenMat_SimAFMMenuSelected, true);
            app.ImportSimulationButton.Text = 'Import Simulation';
            app.AverageReferenceButton = uibutton(app.ReferenceTab, 'push');
            app.AverageReferenceButton.ButtonPushedFcn = createCallbackFcn(app, @AverageReferenceButtonPushed, true);
            app.AverageReferenceButton.Text = 'Average Reference';
            app.RotationalAvgButton = uibutton(app.ReferenceTab, 'push');
            app.RotationalAvgButton.ButtonPushedFcn = createCallbackFcn(app, @RotationalAvgButtonPushed, true);
            app.RotationalAvgButton.Text = 'Rotational Avg';
            app.symmetry = uispinner(app.ReferenceTab);
            app.symmetry.Limits = [1 2000];
            app.symmetry.ValueChangedFcn = createCallbackFcn(app, @symmetryValueChanged, true);
            app.symmetry.Value = 1;
            app.CenterReferenceButton = uibutton(app.ReferenceTab, 'push');
            app.CenterReferenceButton.ButtonPushedFcn = createCallbackFcn(app, @CenterReferenceButtonPushed, true);
            app.CenterReferenceButton.Text = 'Center Reference';
            app.SymmetryLabel = uilabel(app.ReferenceTab);
            app.SymmetryLabel.Text = 'Symmetry:';
            app.RotationalFreedom = uibutton(app.ReferenceTab, 'state');
            app.RotationalFreedom.ValueChangedFcn = createCallbackFcn(app, @RotationalFreedomValueChanged, true);
            app.RotationalFreedom.Text = 'Allow Rotation';
            app.anglesLabel = uilabel(app.ReferenceTab);
            app.anglesLabel.Visible = 'off';
            app.anglesLabel.Text = 'angles: ';
            app.anglesEditField = uieditfield(app.ReferenceTab, 'text');
            app.anglesEditField.Visible = 'off';
            app.anglesEditField.Value = '-40:10:40';
            app.FindParticlesButton_2 = uibutton(app.ReferenceTab, 'push');
            app.FindParticlesButton_2.ButtonPushedFcn = createCallbackFcn(app, @FindParticlesButtonPushed, true);
            app.FindParticlesButton_2.Text = 'Find Particles';
            app.LAFMTab = uitab(app.TabGroup2);
            app.LAFMTab.Title = 'LAFM';
            app.AutoCenterLAFM = uicheckbox(app.LAFMTab);
            app.AutoCenterLAFM.ValueChangedFcn = createCallbackFcn(app, @RenderLAFMButtonPushed, true);
            app.AutoCenterLAFM.Text = 'Auto Center';
            app.AutoCenterLAFM.Value = true;
            app.ViewSymmetrizedButton = uibutton(app.LAFMTab, 'state');
            app.ViewSymmetrizedButton.ValueChangedFcn = createCallbackFcn(app, @RenderLAFMButtonPushed, true);
            app.ViewSymmetrizedButton.Text = 'View Symmetrized';
            app.LAFMScalebarCheckBox = uicheckbox(app.LAFMTab);
            app.LAFMScalebarCheckBox.ValueChangedFcn = createCallbackFcn(app, @LAFMScalebarCheckBoxValueChanged, true);
            app.LAFMScalebarCheckBox.Tooltip = {'Right click for more settings'};
            app.LAFMScalebarCheckBox.Text = 'Scale bar';
            app.LAFM_LUT = uidropdown(app.LAFMTab);
            app.LAFM_LUT.Items = {'LAFM color', 'magma', 'plasma', 'inferno', 'viridis', 'gray', 'Rainbow', 'hot', 'jet', 'AFM brown', 'AFM dark gold', 'AFM gold', 'fire'};
            app.LAFM_LUT.ValueChangedFcn = createCallbackFcn(app, @RenderLAFMButtonPushed, true);
            app.LAFM_LUT.Value = 'LAFM color';
            app.ColorLabel = uilabel(app.LAFMTab);
            app.ColorLabel.Text = 'Color';
            app.LAFMColorBarCheckBox = uicheckbox(app.LAFMTab);
            app.LAFMColorBarCheckBox.ValueChangedFcn = createCallbackFcn(app, @LAFMColorBarCheckBoxValueChanged, true);
            app.LAFMColorBarCheckBox.Text = 'z-scale (nm)';
            app.LAFMSpinner = uispinner(app.LAFMTab);
            app.LAFMSpinner.ValueChangingFcn = createCallbackFcn(app, @LAFMFrameSliderValueChanging, true);
            app.LAFMSpinner.Limits = [1 Inf];
            app.LAFMSpinner.ValueChangedFcn = createCallbackFcn(app, @LAFMFrameSliderValueChanging, true);
            app.LAFMSpinner.Visible = 'off';
            app.LAFMSpinner.Value = 1;
            app.LAFM_ZScaleDropBox = uidropdown(app.LAFMTab);
            app.LAFM_ZScaleDropBox.Items = {'Max Min', 'Exc outliers', 'Manual'};
            app.LAFM_ZScaleDropBox.ValueChangedFcn = createCallbackFcn(app, @RenderLAFMButtonPushed, true);
            app.LAFM_ZScaleDropBox.Value = 'Max Min';
            app.LAFM_sym = uispinner(app.LAFMTab);
            app.LAFM_sym.Limits = [1 50];
            app.LAFM_sym.ValueChangedFcn = createCallbackFcn(app, @RenderLAFMButtonPushed, true);
            app.LAFM_sym.Value = 1;
            app.LAFMMaxSlider = uislider(app.LAFMTab);
            app.LAFMMaxSlider.Limits = [0 1];
            app.LAFMMaxSlider.MajorTicks = [];
            app.LAFMMaxSlider.ValueChangingFcn = createCallbackFcn(app, @LAFMMaxSliderValueChanging, true);
            app.LAFMMaxSlider.MinorTicks = [0 0.05 0.1 0.15 0.2 0.25 0.3 0.35 0.4 0.45 0.5 0.55 0.6 0.65 0.7 0.75 0.8 0.85 0.9 0.95 1];
            app.LAFMMaxSlider.Value = 1;
            app.MaxLabel_5 = uilabel(app.LAFMTab);
            app.MaxLabel_5.Text = 'Max';
            app.MinLabel_5 = uilabel(app.LAFMTab);
            app.MinLabel_5.Text = 'Min';
            app.LAFMMinSlider = uislider(app.LAFMTab);
            app.LAFMMinSlider.Limits = [0 1];
            app.LAFMMinSlider.MajorTicks = [];
            app.LAFMMinSlider.ValueChangingFcn = createCallbackFcn(app, @LAFMMinSliderValueChanging, true);
            app.LAFMMinSlider.MinorTicks = [0 0.05 0.1 0.15 0.2 0.25 0.3 0.35 0.4 0.45 0.5 0.55 0.6 0.65 0.7 0.75 0.8 0.85 0.9 0.95 1];
            app.ColormaxLabel = uilabel(app.LAFMTab);
            app.ColormaxLabel.Text = 'Color max (nm)';
            app.LAFMColorMax = uispinner(app.LAFMTab);
            app.LAFMColorMax.Step = 0.25;
            app.LAFMColorMax.ValueChangedFcn = createCallbackFcn(app, @LAFMColorMaxValueChanged, true);
            app.ColorminLabel = uilabel(app.LAFMTab);
            app.ColorminLabel.Text = 'Color min (nm)';
            app.LAFMColorMin = uispinner(app.LAFMTab);
            app.LAFMColorMin.Step = 0.25;
            app.LAFMColorMin.ValueChangedFcn = createCallbackFcn(app, @LAFMColorMaxValueChanged, true);
            app.TypeLabel = uilabel(app.LAFMTab);
            app.TypeLabel.Text = 'Type';
            app.LAFMView = uidropdown(app.LAFMTab);
            app.LAFMView.Items = {'LAFM map', 'Probability', 'Height'};
            app.LAFMView.ValueChangedFcn = createCallbackFcn(app, @RenderLAFMButtonPushed, true);
            app.LAFMView.Value = 'LAFM map';
            app.LAFMFrameSlider = uislider(app.LAFMTab);
            app.LAFMFrameSlider.Limits = [1 10];
            app.LAFMFrameSlider.MajorTicks = [];
            app.LAFMFrameSlider.MajorTickLabels = {''};
            app.LAFMFrameSlider.ValueChangedFcn = createCallbackFcn(app, @LAFMFrameSliderValueChanging, true);
            app.LAFMFrameSlider.ValueChangingFcn = createCallbackFcn(app, @LAFMFrameSliderValueChanging, true);
            app.LAFMFrameSlider.MinorTicks = [1 1.3 1.6 1.9 2.2 2.5 2.8 3.1 3.4 3.7 4 4.3 4.6 4.9 5.2 5.5 5.8 6.1 6.4 6.7 7 7.3 7.6 7.9 8.2 8.5 8.8 9.1 9.4 9.7 10];
            app.LAFMFrameSlider.Visible = 'off';
            app.LAFMFrameSlider.Value = 1;
            app.LAFMPauseImage = uiimage(app.LAFMTab);
            app.LAFMPauseImage.ImageClickedFcn = createCallbackFcn(app, @LAFMPauseImageClicked, true);
            app.LAFMPauseImage.Visible = 'off';
            app.LAFMPauseImage.ImageSource = fullfile(pathToMLAPP, 'icons', 'pause.png');
            app.LAFMPlayImage = uiimage(app.LAFMTab);
            app.LAFMPlayImage.ImageClickedFcn = createCallbackFcn(app, @LAFMPlayImageClicked, true);
            app.LAFMPlayImage.Visible = 'off';
            app.LAFMPlayImage.ImageSource = fullfile(pathToMLAPP, 'icons', 'play.png');
            app.LAFMEndImage = uiimage(app.LAFMTab);
            app.LAFMEndImage.ImageClickedFcn = createCallbackFcn(app, @LAFMEndImageClicked, true);
            app.LAFMEndImage.Visible = 'off';
            app.LAFMEndImage.ImageSource = fullfile(pathToMLAPP, 'icons', 'end.png');
            app.LAFMStartImage = uiimage(app.LAFMTab);
            app.LAFMStartImage.ImageClickedFcn = createCallbackFcn(app, @LAFMStartImageClicked, true);
            app.LAFMStartImage.Visible = 'off';
            app.LAFMStartImage.ImageSource = fullfile(pathToMLAPP, 'icons', 'start.png');
            app.LAFMTimescaleCheckBox = uicheckbox(app.LAFMTab);
            app.LAFMTimescaleCheckBox.ValueChangedFcn = createCallbackFcn(app, @LAFMScalebarCheckBoxValueChanged, true);
            app.LAFMTimescaleCheckBox.Visible = 'off';
            app.LAFMTimescaleCheckBox.Tooltip = {'Right click for more settings'};
            app.LAFMTimescaleCheckBox.Text = 'Timescale';
            app.ExportFileFormat = uidropdown(app.CenterPanel);
            app.ExportFileFormat.Items = {'.tiff', '.gif', '.avi', '.eps', '.png', '.jpeg', '.pdf', '.txt', '.csv', '.xlsx', 'Matlab workspace'};
            app.ExportFileFormat.Value = '.tiff';
            app.ExportSelect = uidropdown(app.CenterPanel);
            app.ExportSelect.Items = {'Plot 1', 'Plot 2', 'data'};
            app.ExportSelect.Value = 'Plot 1';
            app.ExportButton = uibutton(app.CenterPanel, 'push');
            app.ExportButton.ButtonPushedFcn = createCallbackFcn(app, @ExportButtonPushed, true);
            app.ExportButton.Tooltip = {''};
            app.ExportButton.Text = 'Export:';
            app.ResetButton = uibutton(app.CenterPanel, 'push');
            app.ResetButton.ButtonPushedFcn = createCallbackFcn(app, @ResetButtonPushed, true);
            app.ResetButton.Text = 'Reset';
            app.TimescaleCheckBox = uicheckbox(app.CenterPanel);
            app.TimescaleCheckBox.ValueChangedFcn = createCallbackFcn(app, @TrackLengthEditFieldValueChanged, true);
            app.TimescaleCheckBox.Tooltip = {''};
            app.TimescaleCheckBox.Text = 'Timescale';
            app.PreferencesIcon = uibutton(app.CenterPanel, 'push');
            app.PreferencesIcon.ButtonPushedFcn = createCallbackFcn(app, @PreferencesButtonPressed, true);
            app.PreferencesIcon.Icon = fullfile(pathToMLAPP, 'icons', 'settings.png');
            app.PreferencesIcon.Tooltip = {'Preferences'};
            app.PreferencesIcon.Text = '';
            app.Draw = uidropdown(app.CenterPanel);
            app.Draw.Items = {'Off', 'Line', 'Square', 'Circle', 'Point', 'Freehand'};
            app.Draw.ValueChangedFcn = createCallbackFcn(app, @DrawValueChanged, true);
            app.Draw.Value = 'Off';
            app.DrawLabel = uilabel(app.CenterPanel);
            app.DrawLabel.Text = 'Draw:';
            app.ToolLabel = uilabel(app.CenterPanel);
            app.ToolLabel.Text = 'Tool:';
            app.Plotter = uibutton(app.CenterPanel, 'push');
            app.Plotter.ButtonPushedFcn = createCallbackFcn(app, @PlotterValueChanged, true);
            app.Plotter.Icon = fullfile(pathToMLAPP, 'icons', 'Plotter.png');
            app.Plotter.Text = '';
            app.Freehand_roi_Button = uibutton(app.CenterPanel, 'state');
            app.Freehand_roi_Button.ValueChangedFcn = createCallbackFcn(app, @Freehand_roi_ButtonValueChanged, true);
            app.Freehand_roi_Button.Tooltip = {'Draw freehand'};
            app.Freehand_roi_Button.Icon = fullfile(pathToMLAPP, 'icons', 'freehand.png');
            app.Freehand_roi_Button.Text = '';
            app.Circ_roi_Button = uibutton(app.CenterPanel, 'state');
            app.Circ_roi_Button.ValueChangedFcn = createCallbackFcn(app, @Circ_roi_ButtonValueChanged, true);
            app.Circ_roi_Button.Tooltip = {'Draw circle'};
            app.Circ_roi_Button.Icon = fullfile(pathToMLAPP, 'icons', 'circle.png');
            app.Circ_roi_Button.Text = '';
            app.ROI_Button = uibutton(app.CenterPanel, 'state');
            app.ROI_Button.ValueChangedFcn = createCallbackFcn(app, @ROI_ButtonValueChanged, true);
            app.ROI_Button.Tooltip = {'Region of interest'};
            app.ROI_Button.Icon = fullfile(pathToMLAPP, 'icons', 'ROI.png');
            app.ROI_Button.Text = '';
            app.Average_tool_Button = uibutton(app.CenterPanel, 'state');
            app.Average_tool_Button.ValueChangedFcn = createCallbackFcn(app, @Average_tool_ButtonValueChanged, true);
            app.Average_tool_Button.Tooltip = {'Average vs frame'};
            app.Average_tool_Button.Icon = fullfile(pathToMLAPP, 'icons', 'Average.png');
            app.Average_tool_Button.Text = '';
            app.Profile_tool_Button = uibutton(app.CenterPanel, 'state');
            app.Profile_tool_Button.ValueChangedFcn = createCallbackFcn(app, @Profile_tool_ButtonValueChanged, true);
            app.Profile_tool_Button.Tooltip = {'Z Profile'};
            app.Profile_tool_Button.Icon = fullfile(pathToMLAPP, 'icons', 'Profile.png');
            app.Profile_tool_Button.Text = '';
            app.AddMask_tool_Button = uibutton(app.CenterPanel, 'state');
            app.AddMask_tool_Button.ValueChangedFcn = createCallbackFcn(app, @AddMask_tool_ButtonValueChanged, true);
            app.AddMask_tool_Button.Tooltip = {'Add to mask'};
            app.AddMask_tool_Button.Icon = fullfile(pathToMLAPP, 'icons', 'AddMask.png');
            app.AddMask_tool_Button.Text = '';
            app.RemoveMask_tool_Button = uibutton(app.CenterPanel, 'state');
            app.RemoveMask_tool_Button.ValueChangedFcn = createCallbackFcn(app, @RemoveMask_tool_ButtonValueChanged, true);
            app.RemoveMask_tool_Button.Tooltip = {'Remove from mask'};
            app.RemoveMask_tool_Button.Icon = fullfile(pathToMLAPP, 'icons', 'RemoveMask.png');
            app.RemoveMask_tool_Button.Text = '';
            app.DrawTool = uidropdown(app.CenterPanel);
            app.DrawTool.Items = {'Off', 'Profile', 'ROI', 'Average', '+ Mask', '- Mask'};
            app.DrawTool.ValueChangedFcn = createCallbackFcn(app, @DrawToolValueChanged, true);
            app.DrawTool.Value = 'Off';
            app.ProcessingImage = uiimage(app.CenterPanel);
            app.ProcessingImage.Visible = 'off';
            app.ProcessingImage.ImageSource = fullfile(pathToMLAPP, 'icons', 'processing.png');
            app.AcceptButtonImage = uiimage(app.CenterPanel);
            app.AcceptButtonImage.ImageClickedFcn = createCallbackFcn(app, @AcceptButtonImageClicked, true);
            app.AcceptButtonImage.Visible = 'off';
            app.AcceptButtonImage.ImageSource = fullfile(pathToMLAPP, 'icons', 'accept.png');
            app.FramePartSwitch = uiswitch(app.CenterPanel, 'slider');
            app.FramePartSwitch.Items = {'Frames', 'Particles'};
            app.FramePartSwitch.ValueChangedFcn = createCallbackFcn(app, @FramePartSwitchValueChanged, true);
            app.FramePartSwitch.Value = 'Frames';
            app.Lightmode = uibutton(app.CenterPanel, 'state');
            app.Lightmode.ValueChangedFcn = createCallbackFcn(app, @LightmodeValueChanged, true);
            app.Lightmode.Tooltip = {'Light mode'};
            app.Lightmode.Icon = fullfile(pathToMLAPP, 'icons', 'lightmode.png');
            app.Lightmode.Text = '';
            app.Lightmode.Value = true;
            % Show the figure after all components are created
            app.NanoLocz.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = NanoLocz_v1_41

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.NanoLocz)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.NanoLocz)
        end
    end
end