classdef Preferences < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        NanoLoczPreferencesUIFigure   matlab.ui.Figure
        TabGroup                      matlab.ui.container.TabGroup
        GeneralTab                    matlab.ui.container.Tab
        ResetalldefaultsButton        matlab.ui.control.Button
        StartupFolder                 matlab.ui.control.EditField
        StartupFolderButton           matlab.ui.control.Button
        CdButton_Start                matlab.ui.control.Button
        CdButton_Output               matlab.ui.control.Button
        OutputFolderButton            matlab.ui.control.Button
        OutputFolder                  matlab.ui.control.EditField
        PlotImageExportSettingsPanel  matlab.ui.container.Panel
        ExportColor_Bg                matlab.ui.control.DropDown
        BackgroundcolorDropDownLabel  matlab.ui.control.Label
        ExportRes_DPI                 matlab.ui.control.NumericEditField
        PixelresolutiondpiEditFieldLabel  matlab.ui.control.Label
        DataExportSettingsPanel       matlab.ui.container.Panel
        SubFolders_2                  matlab.ui.control.CheckBox
        SubFolders                    matlab.ui.control.CheckBox
        AutoSaveonoffButton           matlab.ui.control.StateButton
        CombiFormat                   matlab.ui.control.DropDown
        CombinedfileformatLabel       matlab.ui.control.Label
        ImageFormat                   matlab.ui.control.DropDown
        TableFormat                   matlab.ui.control.DropDown
        ImageVideoformatLabel         matlab.ui.control.Label
        AnalysistableformatLabel      matlab.ui.control.Label
        DefaultColourMapLabel         matlab.ui.control.Label
        ColourmapDropDown             matlab.ui.control.DropDown
        ImageLabelsTab                matlab.ui.container.Tab
        FormatDropDown                matlab.ui.control.DropDown
        FormatDropDownLabel           matlab.ui.control.Label
        TimeStampPanel                matlab.ui.container.Panel
        DecimalsSpinner               matlab.ui.control.Spinner
        DecimalsSpinnerLabel          matlab.ui.control.Label
        ShowUnits                     matlab.ui.control.CheckBox
        FontsizeEditField_TS          matlab.ui.control.EditField
        FontsizeEditFieldLabel        matlab.ui.control.Label
        OffImageCheckbox_2            matlab.ui.control.CheckBox
        ZerostartpointButton          matlab.ui.control.Button
        PositionListBox_2             matlab.ui.control.ListBox
        PositionListBox_2Label        matlab.ui.control.Label
        ColorDropDown_2               matlab.ui.control.DropDown
        ColorDropDown_2Label          matlab.ui.control.Label
        ScaleBarPanel                 matlab.ui.container.Panel
        FontsizeEditField_SB          matlab.ui.control.EditField
        FontsizeEditField_2Label      matlab.ui.control.Label
        Bar_only                      matlab.ui.control.CheckBox
        OffImageCheckbox              matlab.ui.control.CheckBox
        PositionListBox               matlab.ui.control.ListBox
        PositionListBoxLabel          matlab.ui.control.Label
        ColorDropDown                 matlab.ui.control.DropDown
        ColorDropDownLabel            matlab.ui.control.Label
        VideoAlignTab                 matlab.ui.container.Tab
        VideoAlignSettingsPanel       matlab.ui.container.Panel
        SmoothDrift                   matlab.ui.control.NumericEditField
        SmoothdriftLabel              matlab.ui.control.Label
        CroppingSwitch                matlab.ui.control.Switch
        CroppingSwitchLabel           matlab.ui.control.Label
        Maxdrift                      matlab.ui.control.NumericEditField
        MaxdriftEditFieldLabel        matlab.ui.control.Label
        FilterImg                     matlab.ui.control.NumericEditField
        FilterImgEditFieldLabel       matlab.ui.control.Label
        FilterCCR                     matlab.ui.control.NumericEditField
        FilterCCREditFieldLabel       matlab.ui.control.Label
        SubPixelLabel                 matlab.ui.control.Label
        SubPixCheckBox                matlab.ui.control.CheckBox
    end

    
    properties (Access = private)
     mainApp
     
    end
    

    methods (Access = public)
        
        function updatePref(app)


            app.mainApp.Settings.Folder.Starting = app.StartupFolder.Value;
            app.mainApp.Settings.Folder.Output = app.OutputFolder.Value;
            app.mainApp.Settings.ColourmapDropDown = app.ColourmapDropDown.Value;

            %Image Export settings

            app.mainApp.Settings.ExportSettings.CombiFormat = app.CombiFormat.Value;
            app.mainApp.Settings.ExportSettings.ImageFormat = app.ImageFormat.Value;
            app.mainApp.Settings.ExportSettings.TableFormat = app.TableFormat.Value;
            app.mainApp.Settings.ExportSettings.AutoSave = app.AutoSaveonoffButton.Value;
            app.mainApp.Settings.ExportSettings.SubFolders = app.SubFolders.Value;
            app.mainApp.Settings.ExportSettings.SubFolders_2 = app.SubFolders_2.Value;

            app.mainApp.Settings.ExportSettings.color = app.ExportColor_Bg.Value;
            app.mainApp.Settings.ExportSettings.resolution = app.ExportRes_DPI.Value;

            %Label Settings
            app.mainApp.Settings.label.ScaleBar.color = app.ColorDropDown.Value;
            app.mainApp.Settings.label.TimeStamp.color = app.ColorDropDown_2.Value;
            app.mainApp.Settings.label.ScaleBar.position = app.PositionListBox.Value;
            app.mainApp.Settings.label.ScaleBar.offimage = app.OffImageCheckbox.Value;
            app.mainApp.Settings.label.ScaleBar.baronly = app.Bar_only.Value;
            app.mainApp.Settings.label.ScaleBar.font = app.FontsizeEditField_SB.Value;
            app.mainApp.Settings.label.TimeStamp.offimage_2 = app.OffImageCheckbox_2.Value;
            app.mainApp.Settings.label.TimeStamp.position = app.PositionListBox_2.Value;
            app.mainApp.Settings.label.TimeStamp.format = app.FormatDropDown.Value;
            app.mainApp.Settings.label.TimeStamp.font = app.FontsizeEditField_TS.Value;
            app.mainApp.Settings.label.TimeStamp.decimalPlaces = app.DecimalsSpinner.Value;
            app.mainApp.Settings.label.TimeStamp.showUnits = app.ShowUnits.Value;

            % Video Align Settings
            app.mainApp.Settings.Align.FilterCCR = app.FilterCCR.Value;
            app.mainApp.Settings.Align.FilterImg = app.FilterImg.Value;
            app.mainApp.Settings.Align.Maxdrift = app.Maxdrift.Value;
            app.mainApp.Settings.Align.CroppingSwitch = app.CroppingSwitch.Value;
            app.mainApp.Settings.Align.SubPix = app.SubPixCheckBox.Value;
            app.mainApp.Settings.Align.SmoothDrift = app.SmoothDrift.Value;


            appDir = fileparts(mfilename('fullpath'));
            PrefFile = fullfile(appDir, 'pref.mat');
            Settings.Align = app.mainApp.Settings.Align;
            Settings.label = app.mainApp.Settings.label;
            Settings.MaskColor = app.mainApp.Settings.MaskColor;
            Settings.MaskAlpha = app.mainApp.Settings.MaskAlpha;
            Settings.Folder = app.mainApp.Settings.Folder;
            Settings.ColourmapDropDown = app.mainApp.Settings.ColourmapDropDown;
            Settings.ExportSettings = app.mainApp.Settings.ExportSettings;
            save(PrefFile, 'Settings');
        end

    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            movegui(app.NanoLoczPreferencesUIFigure,'center');
            allfigs = findall(0,'Type', 'figure');
            % isolate the NanoLocz handle
            app2Handle = findall(allfigs, 'Name', 'NanoLocz');
            app.mainApp = app2Handle(1).RunningAppInstance;

            app.StartupFolder.Value =  app.mainApp.Settings.Folder.Starting;
            app.OutputFolder.Value =  app.mainApp.Settings.Folder.Output;

            app.ColourmapDropDown.Value = app.mainApp.Settings.ColourmapDropDown;
            

            %Image Export Settings
            app.CombiFormat.Value = app.mainApp.Settings.ExportSettings.CombiFormat;
            app.ImageFormat.Value = app.mainApp.Settings.ExportSettings.ImageFormat;
            app.TableFormat.Value = app.mainApp.Settings.ExportSettings.TableFormat;
            app.AutoSaveonoffButton.Value = app.mainApp.Settings.ExportSettings.AutoSave;
            app.SubFolders.Value = app.mainApp.Settings.ExportSettings.SubFolders;
            app.SubFolders_2.Value = app.mainApp.Settings.ExportSettings.SubFolders_2;
            app.ExportRes_DPI.Value =  app.mainApp.Settings.ExportSettings.resolution;
            app.ExportColor_Bg.Value = app.mainApp.Settings.ExportSettings.color;

            %Image Label Settings
            app.ColorDropDown.Value = app.mainApp.Settings.label.ScaleBar.color;
            app.ColorDropDown_2.Value = app.mainApp.Settings.label.TimeStamp.color;
            app.PositionListBox.Value = app.mainApp.Settings.label.ScaleBar.position;
            app.OffImageCheckbox.Value = app.mainApp.Settings.label.ScaleBar.offimage;
            app.Bar_only.Value = app.mainApp.Settings.label.ScaleBar.baronly;
            app.OffImageCheckbox_2.Value = app.mainApp.Settings.label.TimeStamp.offimage_2;
            app.PositionListBox_2.Value = app.mainApp.Settings.label.TimeStamp.position;

            app.FontsizeEditField_SB.Value = app.mainApp.Settings.label.ScaleBar.font;
            app.FormatDropDown.Value = app.mainApp.Settings.label.TimeStamp.format;
            app.FontsizeEditField_TS.Value = app.mainApp.Settings.label.TimeStamp.font;

            app.DecimalsSpinner.Value = app.mainApp.Settings.label.TimeStamp.decimalPlaces;
            app.ShowUnits.Value = app.mainApp.Settings.label.TimeStamp.showUnits;

            %Video ALign Settings
            app.FilterCCR.Value = app.mainApp.Settings.Align.FilterCCR;
            app.FilterImg.Value = app.mainApp.Settings.Align.FilterImg;
            app.Maxdrift.Value =  app.mainApp.Settings.Align.Maxdrift;
            app.CroppingSwitch.Value = app.mainApp.Settings.Align.CroppingSwitch;
            app.SubPixCheckBox.Value = app.mainApp.Settings.Align.SubPix;
            app.SmoothDrift.Value = app.mainApp.Settings.Align.SmoothDrift;

           
        end

        % Value changed function: Bar_only, ColorDropDown, 
        % ...and 11 other components
        function UpdatePref_Plot(app, event)
            updatePref(app)
            try
                app.mainApp.updatePlot('normal')
            catch
            end
        end

        % Button pushed function: ZerostartpointButton
        function ZerostartpointButtonPushed(app, event)
            app.mainApp.ImageInfo.time = app.mainApp.ImageInfo.time - app.mainApp.ImageInfo.time(1);
            app.mainApp.updatePlot('normal')
        end

        % Value changed function: CombiFormat, CroppingSwitch, 
        % ...and 11 other components
        function UpdatePref(app, event)
            updatePref(app)
        end

        % Button pushed function: CdButton_Start
        function CdButton_StartPushed(app, event)
            app.StartupFolder.Value = app.mainApp.Settings.Folder.Open;
            updatePref(app)
        end

        % Button pushed function: CdButton_Output
        function CdButton_OutputPushed(app, event)
            app.OutputFolder.Value =  app.mainApp.Settings.Folder.Open;
            app.mainApp.OutputFolder.Value = app.OutputFolder.Value;
            updatePref(app)
        end

        % Value changed function: OutputFolder
        function OutputFolderValueChanged(app, event)
           
           app.mainApp.OutputFolder.Value = app.OutputFolder.Value;
            updatePref(app) 
        end

        % Value changed function: StartupFolder
        function StartupFolderValueChanged(app, event)
              updatePref(app)
        end

        % Button pushed function: StartupFolderButton
        function StartupFolderButtonPushed(app, event)
           try
            app.StartupFolder.Value = uigetdir(app.StartupFolder.Value);
            updatePref(app)
           catch 
           end
        end

        % Button pushed function: OutputFolderButton
        function OutputFolderButtonPushed(app, event)
          try
            app.OutputFolder.Value = uigetdir(app.OutputFolder.Value);
            updatePref(app)
              app.mainApp.OutputFolder.Value = app.OutputFolder.Value;
          catch
          end
        end

        % Value changed function: AutoSaveonoffButton
        function AutoSaveonoffButtonValueChanged(app, event)

            updatePref(app)
            app.mainApp.Lamp.Enable = app.AutoSaveonoffButton.Value;
            app.mainApp.AutoSaveButton.Value = app.AutoSaveonoffButton.Value;
        end

        % Button pushed function: ResetalldefaultsButton
        function ResetalldefaultsButtonPushed(app, event)
          s = load('Reset_pref.mat');
          app.mainApp.Settings = s.Settings;
            
            app.StartupFolder.Value =  app.mainApp.Settings.Folder.Starting;
            app.OutputFolder.Value =  app.mainApp.Settings.Folder.Output;

            app.ColourmapDropDown.Value = app.mainApp.Settings.ColourmapDropDown;
            

            %Image Export Settings
            app.CombiFormat.Value = app.mainApp.Settings.ExportSettings.CombiFormat;
            app.ImageFormat.Value = app.mainApp.Settings.ExportSettings.ImageFormat;
            app.TableFormat.Value = app.mainApp.Settings.ExportSettings.TableFormat;
            app.AutoSaveonoffButton.Value = app.mainApp.Settings.ExportSettings.AutoSave;
            app.SubFolders.Value = app.mainApp.Settings.ExportSettings.SubFolders;
            app.SubFolders_2.Value = app.mainApp.Settings.ExportSettings.SubFolders_2;
            app.ExportRes_DPI.Value =  app.mainApp.Settings.ExportSettings.resolution;
            app.ExportColor_Bg.Value = app.mainApp.Settings.ExportSettings.color;

            %Image Label Settings
            app.ColorDropDown.Value = app.mainApp.Settings.label.ScaleBar.color;
            app.ColorDropDown_2.Value = app.mainApp.Settings.label.TimeStamp.color;
            app.PositionListBox.Value = app.mainApp.Settings.label.ScaleBar.position;
            app.OffImageCheckbox.Value = app.mainApp.Settings.label.ScaleBar.offimage;
            app.mainApp.Settings.label.ScaleBar.baronly = app.Bar_only.Value;
            app.OffImageCheckbox_2.Value = app.mainApp.Settings.label.TimeStamp.offimage_2;
            app.PositionListBox_2.Value = app.mainApp.Settings.label.TimeStamp.position;
            app.FontsizeEditField_SB.Value = app.mainApp.Settings.label.ScaleBar.font;
            app.FormatDropDown.Value = app.mainApp.Settings.label.TimeStamp.format;
            app.FontsizeEditField_TS.Value = app.mainApp.Settings.label.TimeStamp.font;
            app.DecimalsSpinner.Value = app.mainApp.Settings.label.TimeStamp.decimalPlaces;
            app.ShowUnits.Value = app.mainApp.Settings.label.TimeStamp.showUnits;

            %Video ALign Settings
            app.FilterCCR.Value = app.mainApp.Settings.Align.FilterCCR;
            app.FilterImg.Value = app.mainApp.Settings.Align.FilterImg;
            app.Maxdrift.Value =  app.mainApp.Settings.Align.Maxdrift;
            app.CroppingSwitch.Value = app.mainApp.Settings.Align.CroppingSwitch;
            app.SubPixCheckBox.Value = app.mainApp.Settings.Align.SubPix;
            app.SmoothDrift.Value = app.mainApp.Settings.Align.SmoothDrift;

             updatePref(app)
          
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.NanoLoczPreferencesUIFigure = uifigure('Visible', 'off');
            app.NanoLoczPreferencesUIFigure.Name = 'NanoLocz Preferences';
            app.TabGroup = uitabgroup(app.NanoLoczPreferencesUIFigure);
            app.GeneralTab = uitab(app.TabGroup);
            app.GeneralTab.Title = 'General';
            app.ColourmapDropDown = uidropdown(app.GeneralTab);
            app.ColourmapDropDown.Items = {'AFM brown', 'AFM dark gold', 'AFM gold', 'fire', 'Rainbow', 'gray', 'hot', 'cool', 'Orange hot', 'jet', 'turbo', 'parula', 'hsv', 'winter', 'spring', 'summer', 'autumn'};
            app.ColourmapDropDown.ValueChangedFcn = createCallbackFcn(app, @UpdatePref_Plot, true);
            app.ColourmapDropDown.Value = 'AFM brown';
            app.DefaultColourMapLabel = uilabel(app.GeneralTab);
            app.DefaultColourMapLabel.Text = 'Default Colour Map:';
            app.DataExportSettingsPanel = uipanel(app.GeneralTab);
            app.DataExportSettingsPanel.Title = 'Data Export Settings';
            app.AnalysistableformatLabel = uilabel(app.DataExportSettingsPanel);
            app.AnalysistableformatLabel.Text = 'Analysis table format:';
            app.ImageVideoformatLabel = uilabel(app.DataExportSettingsPanel);
            app.ImageVideoformatLabel.Text = 'Image/Video format:';
            app.TableFormat = uidropdown(app.DataExportSettingsPanel);
            app.TableFormat.Items = {'off', '.txt', '.csv', '.xlsx'};
            app.TableFormat.ValueChangedFcn = createCallbackFcn(app, @UpdatePref, true);
            app.TableFormat.Value = 'off';
            app.ImageFormat = uidropdown(app.DataExportSettingsPanel);
            app.ImageFormat.Items = {'off', '.tiff', '.txt', '.csv', '.xlsx'};
            app.ImageFormat.ValueChangedFcn = createCallbackFcn(app, @UpdatePref, true);
            app.ImageFormat.Value = 'off';
            app.CombinedfileformatLabel = uilabel(app.DataExportSettingsPanel);
            app.CombinedfileformatLabel.Text = 'Combined file format:';
            app.CombiFormat = uidropdown(app.DataExportSettingsPanel);
            app.CombiFormat.Items = {'off', '.h5', '.mat'};
            app.CombiFormat.ValueChangedFcn = createCallbackFcn(app, @UpdatePref, true);
            app.CombiFormat.Value = '.h5';
            app.AutoSaveonoffButton = uibutton(app.DataExportSettingsPanel, 'state');
            app.AutoSaveonoffButton.ValueChangedFcn = createCallbackFcn(app, @AutoSaveonoffButtonValueChanged, true);
            app.AutoSaveonoffButton.Text = 'AutoSave on/off';
            app.SubFolders = uicheckbox(app.DataExportSettingsPanel);
            app.SubFolders.ValueChangedFcn = createCallbackFcn(app, @UpdatePref, true);
            app.SubFolders.Text = {'Create output'; 'folder/folder'};
            app.SubFolders_2 = uicheckbox(app.DataExportSettingsPanel);
            app.SubFolders_2.ValueChangedFcn = createCallbackFcn(app, @UpdatePref, true);
            app.SubFolders_2.Text = {'Create output'; 'folder/image '};
            app.PlotImageExportSettingsPanel = uipanel(app.GeneralTab);
            app.PlotImageExportSettingsPanel.Title = 'Plot/Image Export Settings';
            app.PixelresolutiondpiEditFieldLabel = uilabel(app.PlotImageExportSettingsPanel);
            app.PixelresolutiondpiEditFieldLabel.Text = 'Pixel resolution (dpi)';
            app.ExportRes_DPI = uieditfield(app.PlotImageExportSettingsPanel, 'numeric');
            app.ExportRes_DPI.Limits = [0.1 Inf];
            app.ExportRes_DPI.ValueChangedFcn = createCallbackFcn(app, @UpdatePref, true);
            app.ExportRes_DPI.Value = 300;
            app.BackgroundcolorDropDownLabel = uilabel(app.PlotImageExportSettingsPanel);
            app.BackgroundcolorDropDownLabel.Text = 'Background color';
            app.ExportColor_Bg = uidropdown(app.PlotImageExportSettingsPanel);
            app.ExportColor_Bg.Items = {'black', 'white'};
            app.ExportColor_Bg.ValueChangedFcn = createCallbackFcn(app, @UpdatePref, true);
            app.ExportColor_Bg.Value = 'black';
            app.OutputFolder = uieditfield(app.GeneralTab, 'text');
            app.OutputFolder.ValueChangedFcn = createCallbackFcn(app, @OutputFolderValueChanged, true);
            app.OutputFolderButton = uibutton(app.GeneralTab, 'push');
            app.OutputFolderButton.ButtonPushedFcn = createCallbackFcn(app, @OutputFolderButtonPushed, true);
            app.OutputFolderButton.Text = 'Output Folder:';
            app.CdButton_Output = uibutton(app.GeneralTab, 'push');
            app.CdButton_Output.ButtonPushedFcn = createCallbackFcn(app, @CdButton_OutputPushed, true);
            app.CdButton_Output.Text = 'Cd';
            app.CdButton_Start = uibutton(app.GeneralTab, 'push');
            app.CdButton_Start.ButtonPushedFcn = createCallbackFcn(app, @CdButton_StartPushed, true);
            app.CdButton_Start.Text = 'Cd';
            app.StartupFolderButton = uibutton(app.GeneralTab, 'push');
            app.StartupFolderButton.ButtonPushedFcn = createCallbackFcn(app, @StartupFolderButtonPushed, true);
            app.StartupFolderButton.Text = 'Startup Folder:';
            app.StartupFolder = uieditfield(app.GeneralTab, 'text');
            app.StartupFolder.ValueChangedFcn = createCallbackFcn(app, @StartupFolderValueChanged, true);
            app.ResetalldefaultsButton = uibutton(app.GeneralTab, 'push');
            app.ResetalldefaultsButton.ButtonPushedFcn = createCallbackFcn(app, @ResetalldefaultsButtonPushed, true);
            app.ResetalldefaultsButton.Text = 'Reset all defaults';
            app.ImageLabelsTab = uitab(app.TabGroup);
            app.ImageLabelsTab.Title = 'Image Labels';
            app.ScaleBarPanel = uipanel(app.ImageLabelsTab);
            app.ScaleBarPanel.Title = 'Scale Bar';
            app.ColorDropDownLabel = uilabel(app.ScaleBarPanel);
            app.ColorDropDownLabel.Text = 'Color';
            app.ColorDropDown = uidropdown(app.ScaleBarPanel);
            app.ColorDropDown.Items = {'white', 'black', 'cyan', 'magenta', 'red', 'green', 'blue'};
            app.ColorDropDown.ValueChangedFcn = createCallbackFcn(app, @UpdatePref_Plot, true);
            app.ColorDropDown.Value = 'white';
            app.PositionListBoxLabel = uilabel(app.ScaleBarPanel);
            app.PositionListBoxLabel.Text = 'Position';
            app.PositionListBox = uilistbox(app.ScaleBarPanel);
            app.PositionListBox.Items = {'Top Left', 'Top Right', 'Bottom Left', 'Bottom Right'};
            app.PositionListBox.ValueChangedFcn = createCallbackFcn(app, @UpdatePref_Plot, true);
            app.PositionListBox.Value = 'Top Left';
            app.OffImageCheckbox = uicheckbox(app.ScaleBarPanel);
            app.OffImageCheckbox.ValueChangedFcn = createCallbackFcn(app, @UpdatePref_Plot, true);
            app.OffImageCheckbox.Text = 'Label off image';
            app.Bar_only = uicheckbox(app.ScaleBarPanel);
            app.Bar_only.ValueChangedFcn = createCallbackFcn(app, @UpdatePref_Plot, true);
            app.Bar_only.Text = 'Bar only';
            app.FontsizeEditField_2Label = uilabel(app.ScaleBarPanel);
            app.FontsizeEditField_2Label.Text = 'Font size';
            app.FontsizeEditField_SB = uieditfield(app.ScaleBarPanel, 'text');
            app.FontsizeEditField_SB.CharacterLimits = [1 Inf];
            app.FontsizeEditField_SB.ValueChangedFcn = createCallbackFcn(app, @UpdatePref_Plot, true);
            app.FontsizeEditField_SB.Value = '15';
            app.TimeStampPanel = uipanel(app.ImageLabelsTab);
            app.TimeStampPanel.Title = 'Time Stamp';
            app.ColorDropDown_2Label = uilabel(app.TimeStampPanel);
            app.ColorDropDown_2Label.Text = 'Color';
            app.ColorDropDown_2 = uidropdown(app.TimeStampPanel);
            app.ColorDropDown_2.Items = {'white', 'black', 'cyan', 'magenta', 'red', 'green', 'blue'};
            app.ColorDropDown_2.ValueChangedFcn = createCallbackFcn(app, @UpdatePref_Plot, true);
            app.ColorDropDown_2.Value = 'white';
            app.PositionListBox_2Label = uilabel(app.TimeStampPanel);
            app.PositionListBox_2Label.Text = 'Position';
            app.PositionListBox_2 = uilistbox(app.TimeStampPanel);
            app.PositionListBox_2.Items = {'Top Left', 'Top Right', 'Bottom Left', 'Bottom Right'};
            app.PositionListBox_2.ValueChangedFcn = createCallbackFcn(app, @UpdatePref_Plot, true);
            app.PositionListBox_2.Value = 'Top Left';
            app.ZerostartpointButton = uibutton(app.TimeStampPanel, 'push');
            app.ZerostartpointButton.ButtonPushedFcn = createCallbackFcn(app, @ZerostartpointButtonPushed, true);
            app.ZerostartpointButton.Text = 'Zero start point';
            app.OffImageCheckbox_2 = uicheckbox(app.TimeStampPanel);
            app.OffImageCheckbox_2.ValueChangedFcn = createCallbackFcn(app, @UpdatePref_Plot, true);
            app.OffImageCheckbox_2.Text = 'Label off image';
            app.FontsizeEditFieldLabel = uilabel(app.TimeStampPanel);
            app.FontsizeEditFieldLabel.Text = 'Font size';
            app.FontsizeEditField_TS = uieditfield(app.TimeStampPanel, 'text');
            app.FontsizeEditField_TS.CharacterLimits = [1 Inf];
            app.FontsizeEditField_TS.ValueChangedFcn = createCallbackFcn(app, @UpdatePref_Plot, true);
            app.FontsizeEditField_TS.Value = '17';
            app.ShowUnits = uicheckbox(app.TimeStampPanel);
            app.ShowUnits.ValueChangedFcn = createCallbackFcn(app, @UpdatePref_Plot, true);
            app.ShowUnits.Text = 'Show units';
            app.DecimalsSpinnerLabel = uilabel(app.TimeStampPanel);
            app.DecimalsSpinnerLabel.Text = 'Decimals';
            app.DecimalsSpinner = uispinner(app.TimeStampPanel);
            app.DecimalsSpinner.Limits = [0 Inf];
            app.DecimalsSpinner.ValueChangedFcn = createCallbackFcn(app, @UpdatePref_Plot, true);
            app.FormatDropDownLabel = uilabel(app.ImageLabelsTab);
            app.FormatDropDownLabel.Text = 'Format';
            app.FormatDropDown = uidropdown(app.ImageLabelsTab);
            app.FormatDropDown.Items = {'milliseconds', 'seconds', 'minutes:seconds', 'hh:mm:ss'};
            app.FormatDropDown.ValueChangedFcn = createCallbackFcn(app, @UpdatePref_Plot, true);
            app.FormatDropDown.Value = 'seconds';
            app.VideoAlignTab = uitab(app.TabGroup);
            app.VideoAlignTab.Title = 'Video Align';
            app.VideoAlignSettingsPanel = uipanel(app.VideoAlignTab);
            app.VideoAlignSettingsPanel.Title = 'Video Align Settings';
            app.SubPixCheckBox = uicheckbox(app.VideoAlignSettingsPanel);
            app.SubPixCheckBox.ValueChangedFcn = createCallbackFcn(app, @UpdatePref, true);
            app.SubPixCheckBox.Text = '';
            app.SubPixelLabel = uilabel(app.VideoAlignSettingsPanel);
            app.SubPixelLabel.Text = 'Sub-Pixel?';
            app.FilterCCREditFieldLabel = uilabel(app.VideoAlignSettingsPanel);
            app.FilterCCREditFieldLabel.Text = 'Filter CCR';
            app.FilterCCR = uieditfield(app.VideoAlignSettingsPanel, 'numeric');
            app.FilterCCR.Limits = [0 Inf];
            app.FilterCCR.ValueChangedFcn = createCallbackFcn(app, @UpdatePref, true);
            app.FilterCCR.Value = 1;
            app.FilterImgEditFieldLabel = uilabel(app.VideoAlignSettingsPanel);
            app.FilterImgEditFieldLabel.Text = 'Filter Img';
            app.FilterImg = uieditfield(app.VideoAlignSettingsPanel, 'numeric');
            app.FilterImg.Limits = [0 Inf];
            app.FilterImg.ValueChangedFcn = createCallbackFcn(app, @UpdatePref, true);
            app.FilterImg.Value = 1;
            app.MaxdriftEditFieldLabel = uilabel(app.VideoAlignSettingsPanel);
            app.MaxdriftEditFieldLabel.Text = 'Max drift';
            app.Maxdrift = uieditfield(app.VideoAlignSettingsPanel, 'numeric');
            app.Maxdrift.Limits = [0 Inf];
            app.Maxdrift.ValueChangedFcn = createCallbackFcn(app, @UpdatePref, true);
            app.CroppingSwitchLabel = uilabel(app.VideoAlignSettingsPanel);
            app.CroppingSwitchLabel.Text = 'Cropping';
            app.CroppingSwitch = uiswitch(app.VideoAlignSettingsPanel, 'slider');
            app.CroppingSwitch.Items = {'Crop', 'Expand'};
            app.CroppingSwitch.ValueChangedFcn = createCallbackFcn(app, @UpdatePref, true);
            app.CroppingSwitch.Value = 'Expand';
            app.SmoothdriftLabel = uilabel(app.VideoAlignSettingsPanel);
            app.SmoothdriftLabel.Text = 'Smooth drift';
            app.SmoothDrift = uieditfield(app.VideoAlignSettingsPanel, 'numeric');
            app.SmoothDrift.Limits = [0 Inf];
            app.SmoothDrift.RoundFractionalValues = 'on';
            app.SmoothDrift.ValueChangedFcn = createCallbackFcn(app, @UpdatePref, true);
            app.SmoothDrift.Value = 4;
            % Show the figure after all components are created
            app.NanoLoczPreferencesUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Preferences

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.NanoLoczPreferencesUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.NanoLoczPreferencesUIFigure)
        end
    end
end