classdef Saving < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        SavingOptionsUIFigure         matlab.ui.Figure
        ChannelDropDown               matlab.ui.control.DropDown
        OuputLocationPanel            matlab.ui.container.Panel
        SubFolders_2                  matlab.ui.control.CheckBox
        SubFolders                    matlab.ui.control.CheckBox
        OutputFolderButton            matlab.ui.control.Button
        CdButton_Output               matlab.ui.control.Button
        OutputFolder                  matlab.ui.control.EditField
        SaveButton                    matlab.ui.control.Button
        SelectFormatPanel             matlab.ui.container.Panel
        ImageFormat                   matlab.ui.control.DropDown
        TableFormat                   matlab.ui.control.DropDown
        ImageVideoformatLabel         matlab.ui.control.Label
        AnalysistablesformatLabel     matlab.ui.control.Label
        CombiFormat                   matlab.ui.control.DropDown
        CombinedfileformatLabel       matlab.ui.control.Label
        SelectFilesPanel              matlab.ui.container.Panel
        LevelunprocesseddataCheckBox  matlab.ui.control.CheckBox
        SaveDropDown                  matlab.ui.control.DropDown
        SaveDropDownLabel             matlab.ui.control.Label
        Tree                          matlab.ui.container.CheckBoxTree
        Node                          matlab.ui.container.TreeNode
    end

    
    properties (Access = private)
     mainApp
     
    end
    

    methods (Access = public)
        
        function updatePref(app)
            app.mainApp.Settings.Folder.Output = app.OutputFolder.Value;

            app.mainApp.Settings.ExportSettings.CombiFormat = app.CombiFormat.Value;
            app.mainApp.Settings.ExportSettings.ImageFormat = app.ImageFormat.Value;
            app.mainApp.Settings.ExportSettings.TableFormat = app.TableFormat.Value;
            app.mainApp.Settings.ExportSettings.SubFolders = app.SubFolders.Value;
            app.mainApp.Settings.ExportSettings.SubFolders_2 = app.SubFolders_2.Value;
            app.mainApp.Settings.ExportSettings.SaveType = app.SaveDropDown.Value;

            appDir = fileparts(mfilename('fullpath'));
            PrefFile = fullfile(appDir, 'pref.mat');
            Settings.Align = app.mainApp.Settings.Align;
            Settings.label = app.mainApp.Settings.label;
            Settings.MaskColor = app.mainApp.Settings.MaskColor;
            Settings.MaskAlpha = app.mainApp.Settings.MaskAlpha;
            Settings.Folder = app.mainApp.Settings.Folder;
            Settings.ColourmapDropDown = app.mainApp.Settings.ColourmapDropDown;
            Settings.ExportSettings = app.mainApp.Settings.ExportSettings;
            save(PrefFile, 'Settings');
        end


        function update_FileList(app)
            delete(app.Node.Children);
            switch app.SaveDropDown.Value
                case 'Current File'
                    for i = 1:numel(app.mainApp.FilesListBox.Items)
                        app.Node.Children(i) = uitreenode(app.Node,'Text',app.mainApp.FilesListBox.Items{i});
                        ch = contains(app.mainApp.FilesListBox.Value,  app.Node.Children(i).Text);
                        if ch
                            app.Tree.CheckedNodes = [ app.Node.Children(i)];
                        end
                    end
                case 'All Files'
                    for i = 1:numel(app.mainApp.FilesListBox.Items)
                        app.Node.Children(i) = uitreenode(app.Node,'Text',app.mainApp.FilesListBox.Items{i});
                    end
                    app.Tree.CheckedNodes = [ app.Node];
                case 'All Processed'

                    for i = 2:numel({app.mainApp.stored.ImageName})
                        app.Node.Children(i-1) = uitreenode(app.Node,'Text',app.mainApp.stored(i).ImageName);
                        app.Tree.CheckedNodes = [ app.Node.Children(i-1)];
                    end
                    app.Tree.CheckedNodes = [ app.Node];
                case 'Session'
            end
        end

    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            movegui(app.SavingOptionsUIFigure,'center');
            allfigs = findall(0,'Type', 'figure');
            % isolate the NanoLocz handle
            app2Handle = findall(allfigs, 'Name', 'NanoLocz');
            app.mainApp = app2Handle(1).RunningAppInstance;

            app.OutputFolder.Value =  app.mainApp.Settings.Folder.Output;
            app.CombiFormat.Value  = app.mainApp.Settings.ExportSettings.CombiFormat;
            app.ImageFormat.Value  = app.mainApp.Settings.ExportSettings.ImageFormat;
            app.TableFormat.Value  = app.mainApp.Settings.ExportSettings.TableFormat;
            app.SaveDropDown.Value = app.mainApp.Settings.ExportSettings.SaveType;
            app.SubFolders.Value   = app.mainApp.Settings.ExportSettings.SubFolders;
            app.SubFolders_2.Value = app.mainApp.Settings.ExportSettings.SubFolders_2;
try
            app.ChannelDropDown.Items = app.mainApp.ImageInfo.Channels;
            app.ChannelDropDown.Value = app.mainApp.ChannelDropDown.Value;
catch
end
           update_FileList(app)
            expand(app.Node)

        end

        % Value changed function: CombiFormat, ImageFormat, SubFolders, 
        % ...and 2 other components
        function UpdatePref(app, event)
            
            updatePref(app)
        end

        % Button pushed function: CdButton_Output
        function CdButton_OutputPushed(app, event)
            app.OutputFolder.Value = app.mainApp.Settings.Folder.Open;
            updatePref(app)
        end

        % Button pushed function: OutputFolderButton
        function OutputFolderButtonPushed(app, event)
          try
              app.OutputFolder.Value = uigetdir(app.OutputFolder.Value);
              app.mainApp.OutputFolder.Value = app.OutputFolder.Value;
              updatePref(app)
          catch
          end
        end

        % Value changed function: SaveDropDown
        function SaveDropDownValueChanged(app, event)
            update_FileList(app)
            updatePref(app)
        end

        % Button pushed function: SaveButton
        function SaveButtonPushed(app, event)

            switch app.SaveDropDown.Value
                case {'Current File','All Files','All Processed'}
                    SelectedNodes = app.Tree.CheckedNodes;
                    n=  numel(SelectedNodes);
                    app.mainApp.Spinner.Value = 1;
                    app.mainApp.FrameSlider.Value = 1;

                    file_name = SelectedNodes(1).Text;
                    if  ismember(file_name, 'Images')
                        n = n-1;
                        f = 2:n;
                    else
                        f = 1:n;
                    end

                    if n>0
                        app.mainApp.Settings.ExportSettings.AutoSave = true;
                        for i = f %loop through selected images     
                            file_name = SelectedNodes(i).Text;
                            try
                                app.mainApp.ChannelDropDown.Value = app.ChannelDropDown.Value;
                            catch
                                app.mainApp.ChannelDropDown.Items = app.ChannelDropDown.Items;
                                app.mainApp.ChannelDropDown.Value = app.ChannelDropDown.Value;
                            end
                            cla(app.mainApp.loadbar)
                            rectangle(app.mainApp.loadbar, 'Position', [0, 0, 1, 1], 'FaceColor', 'none', 'EdgeColor', 'black');
                            progressBar = rectangle(app.mainApp.loadbar, 'Position', [0, 0, 0, 1], 'FaceColor', 'green');
                            progressText = text(app.mainApp.loadbar, 0.5, 0.5, '0%', 'HorizontalAlignment', 'center', 'VerticalAlignment', 'middle');
                            progressBar.Position(3) = (i) / (n);
                            progressText.String = sprintf('Saving File %d of %d', i, (n));
                            drawnow;

                            app.mainApp.FilesListBox.Value = file_name;
                            ch = contains({app.mainApp.stored.ImageName},file_name);
                            if sum(ch)==1
                                if numel(app.mainApp.stored(ch).ImageInfo.Channels)>1
                                    app.mainApp.ChannelDropDown.Visible=1;
                                    try
                                        ch2 = contains(app.mainApp.stored(ch).Channel,app.mainApp.ChannelDropDown.Value);
                                    catch
                                        ch2 = contains({app.mainApp.stored(ch).Channel},app.mainApp.ChannelDropDown.Value);
                                    end
                                else
                                    ch2 = 1;
                                end
                            else
                                ch2=0;
                            end

                            if sum(ch)+ sum(ch2) ==2
                                app.mainApp.ImageName = app.mainApp.stored(ch).ImageName;
                                app.mainApp.ImageInfo = app.mainApp.stored(ch).ImageInfo;
                                im = app.mainApp.stored(ch).Imagedata;
                                app.mainApp.ImageTarget = single(im);

                                try
                                    if isfield(app.mainApp.stored(ch).ref,'position')
                                        app.mainApp.ref = app.mainApp.stored(ch).ref;
                                        if strcmp(app.ImageFormat.Value, 'off')
                                        else
                                            app.mainApp.savedata(app.mainApp.ref.av,'Ref avg');
                                        end
                                    end
                                catch
                                end


                                try
                                    if isfield(app.mainApp.stored(ch).ImageLocs,'Locs')
                                        app.mainApp.Part = app.mainApp.stored(ch).ImageLocs;
                                        %  app.mainApp.filter_Partlocs()
                                        if strcmp(app.TableFormat.Value, 'off')
                                        else
                                            app.mainApp.savedata(app.mainApp.Part.Locs,'Part Locs')
                                        end
                                    end
                                catch
                                end

                                try
                                    if isfield(app.mainApp.stored(ch).LAFMLocs,'Locs')
                                        app.mainApp.LAFM = app.mainApp.stored(ch).LAFMLocs;
                                        if strcmp(app.TableFormat.Value, 'off')
                                        else
                                            app.mainApp.savedata(app.mainApp.LAFM.Locs,'LAFM Locs')
                                        end
                                        if strcmp(app.ImageFormat.Value, 'off')
                                        else
                                            app.mainApp.savedata(app.mainApp.LAFM.Image,'LAFM')
                                            app.mainApp.savedata(app.mainApp.LAFM.Image_sym,'LAFM sym')
                                        end
                                    end
                                catch
                                end

                                try
                                    if isfield(app.mainApp.stored(ch).Mask,'Analysis')
                                        app.mainApp.Mask = app.mainApp.stored(ch).Mask;
                                        if strcmp(app.TableFormat.Value, 'off')
                                        else
                                            savedata(app.mainApp.Mask.Analysis,'Area')
                                            savedata(app.mainApp.Mask.Frame_Analysis,'Area Frame')
                                        end
                                    end
                                catch
                                end
                                app.mainApp.savedata(app.mainApp.ImageTarget,'Image')

                            else
                                im = app.mainApp.Open_files();
                                app.mainApp.ImageTarget = single(im);

                                if app.LevelunprocesseddataCheckBox.Value

                                    min_all = double(min(app.mainApp.ImageTarget,[],'all'));
                                    max_all = double(max(app.mainApp.ImageTarget,[],'all'));
                                    app.mainApp.SliderMax.Limits = [min_all, max_all];
                                    app.mainApp.SliderMin.Limits = [min_all, max_all];
                                    app.mainApp.SliderMax.Value = max_all;
                                    app.mainApp.SliderMin.Value = min_all;

                                    app.mainApp.filter_already = 0;
                                    app.mainApp.filter_full=1;
                                    app.mainApp.ImagePreview = app.mainApp.filter();
                                    app.mainApp.ImageTarget = app.mainApp.ImagePreview;
                                end
                                app.mainApp.storedata()
                                app.mainApp.savedata(app.mainApp.ImageTarget ,'Image')
                            end


                        end
                        app.mainApp.Settings.ExportSettings.AutoSave = false;
                    else
                        msgbox('No files selected', 'Warning', 'warn');
                    end
                    progressText.String = sprintf('Saving Complete');
                case 'Session'
                    Data = app.mainApp.stored;
                    [~, namesplit, ~] = fileparts(app.mainApp.Settings.Folder.Open);
                    namesuggest = join(namesplit,"_") + "_session."+"mat";
                    WorkingFolder = app.mainApp.Settings.Folder.Open;
                    [filename, pathname] = uiputfile(namesuggest, 'Save App Data');
                    if isequal(filename, 0) || isequal(pathname, 0)
                    else
                        filepath = fullfile(pathname, filename);
                        save(filepath, 'Data','WorkingFolder');
                    end
            end
        end

        % Value changed function: OutputFolder
        function OutputFolderValueChanged(app, event)
    
           app.mainApp.OutputFolder.Value = app.OutputFolder.Value;
              updatePref(app) 
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.SavingOptionsUIFigure = uifigure('Visible', 'off');
            app.SavingOptionsUIFigure.Name = 'Saving Options';
            app.SelectFilesPanel = uipanel(app.SavingOptionsUIFigure);
            app.SelectFilesPanel.Title = 'Select Files';
            app.Tree = uitree(app.SelectFilesPanel, 'checkbox');
            app.Node = uitreenode(app.Tree);
            app.Node.Icon = 'open.png';
            app.Node.Text = 'Images';
            app.SaveDropDownLabel = uilabel(app.SelectFilesPanel);
            app.SaveDropDownLabel.Text = 'Save:';
            app.SaveDropDown = uidropdown(app.SelectFilesPanel);
            app.SaveDropDown.Items = {'Current File', 'All Files', 'All Processed', 'Session'};
            app.SaveDropDown.ValueChangedFcn = createCallbackFcn(app, @SaveDropDownValueChanged, true);
            app.SaveDropDown.Value = 'Current File';
            app.LevelunprocesseddataCheckBox = uicheckbox(app.SelectFilesPanel);
            app.LevelunprocesseddataCheckBox.Text = 'Level unprocessed data';
            app.LevelunprocesseddataCheckBox.Value = true;
            app.SelectFormatPanel = uipanel(app.SavingOptionsUIFigure);
            app.SelectFormatPanel.Title = 'Select Format';
            app.CombinedfileformatLabel = uilabel(app.SelectFormatPanel);
            app.CombinedfileformatLabel.Text = 'Combined file format:';
            app.CombiFormat = uidropdown(app.SelectFormatPanel);
            app.CombiFormat.Items = {'off', '.h5', '.mat'};
            app.CombiFormat.ValueChangedFcn = createCallbackFcn(app, @UpdatePref, true);
            app.CombiFormat.Value = 'off';
            app.AnalysistablesformatLabel = uilabel(app.SelectFormatPanel);
            app.AnalysistablesformatLabel.Text = 'Analysis tables format:';
            app.ImageVideoformatLabel = uilabel(app.SelectFormatPanel);
            app.ImageVideoformatLabel.Text = 'Image/Video format:';
            app.TableFormat = uidropdown(app.SelectFormatPanel);
            app.TableFormat.Items = {'off', '.txt', '.csv', '.xlsx'};
            app.TableFormat.ValueChangedFcn = createCallbackFcn(app, @UpdatePref, true);
            app.TableFormat.Value = 'off';
            app.ImageFormat = uidropdown(app.SelectFormatPanel);
            app.ImageFormat.Items = {'off', '.tiff', '.txt', '.csv', '.xlsx'};
            app.ImageFormat.ValueChangedFcn = createCallbackFcn(app, @UpdatePref, true);
            app.ImageFormat.Value = 'off';
            app.SaveButton = uibutton(app.SavingOptionsUIFigure, 'push');
            app.SaveButton.ButtonPushedFcn = createCallbackFcn(app, @SaveButtonPushed, true);
            app.SaveButton.Text = 'Save';
            app.OuputLocationPanel = uipanel(app.SavingOptionsUIFigure);
            app.OuputLocationPanel.Title = 'Ouput Location';
            app.OutputFolder = uieditfield(app.OuputLocationPanel, 'text');
            app.OutputFolder.ValueChangedFcn = createCallbackFcn(app, @OutputFolderValueChanged, true);
            app.CdButton_Output = uibutton(app.OuputLocationPanel, 'push');
            app.CdButton_Output.ButtonPushedFcn = createCallbackFcn(app, @CdButton_OutputPushed, true);
            app.CdButton_Output.Text = 'Cd';
            app.OutputFolderButton = uibutton(app.OuputLocationPanel, 'push');
            app.OutputFolderButton.ButtonPushedFcn = createCallbackFcn(app, @OutputFolderButtonPushed, true);
            app.OutputFolderButton.Text = 'Output Folder:';
            app.SubFolders = uicheckbox(app.OuputLocationPanel);
            app.SubFolders.ValueChangedFcn = createCallbackFcn(app, @UpdatePref, true);
            app.SubFolders.Text = 'Create a new subfolder for each parent folder';
            app.SubFolders_2 = uicheckbox(app.OuputLocationPanel);
            app.SubFolders_2.ValueChangedFcn = createCallbackFcn(app, @UpdatePref, true);
            app.SubFolders_2.Text = 'Create multiple subfolder(s) for each Image/Movie ';
            app.ChannelDropDown = uidropdown(app.SavingOptionsUIFigure);
            app.ChannelDropDown.Items = {'Height'};
            app.ChannelDropDown.Value = 'Height';
            % Show the figure after all components are created
            app.SavingOptionsUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Saving

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.SavingOptionsUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.SavingOptionsUIFigure)
        end
    end
end